classdef dockMisc_AddKFactor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        Document                matlab.ui.container.GridLayout
        jsBackDoor              matlab.ui.control.HTML
        edit_unitRefresh        matlab.ui.control.Image
        btnOK                   matlab.ui.control.Button
        gpsLocationPanel        matlab.ui.container.Panel
        gpsLocationGrid         matlab.ui.container.GridLayout
        misc_Edit_kFactorAdd    matlab.ui.control.Image
        misc_Edit_kFactorTree   matlab.ui.container.Tree
        misc_Edit_kFactorLabel  matlab.ui.control.Label
        misc_Edit_kFactor       matlab.ui.control.DropDown
        misc_Edit_unitRawLabel  matlab.ui.control.Label
        misc_Edit_unitRaw       matlab.ui.control.EditField
        gpsLocationPanelLabel   matlab.ui.control.Label
        btnClose                matlab.ui.control.Image
        ContextMenu             matlab.ui.container.ContextMenu
        ContextMenu_del         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Container
        isDocked = true

        CallingApp
        specData
        referenceData
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function initialValues(app, idxThread)
            app.referenceData = struct('idxThread', idxThread);
            Layout(app)
        end

        %-----------------------------------------------------------------%
        function Layout(app)
            if ~isempty(app.misc_Edit_kFactorTree.Children)
                delete(app.misc_Edit_kFactorTree.Children)
            end

            idxThread   = app.referenceData.idxThread;
            Calibration = app.specData(idxThread).UserData.measCalibration;

            if ~isempty(Calibration)
                app.misc_Edit_unitRaw.Value = Calibration.oldUnitLevel{1};

                for ii = 1:height(Calibration)
                    uitreenode(app.misc_Edit_kFactorTree, 'Text', sprintf('%s → %s: "%s"', Calibration.oldUnitLevel{ii}, ...
                                                                                           Calibration.newUnitLevel{ii}, ...
                                                                                           Calibration.Name{ii}),        ...
                                                          'NodeData', ii, 'ContextMenu', app.ContextMenu);
                end

            else
                app.misc_Edit_unitRaw.Value = app.specData(idxThread).MetaData.LevelUnit;
            end
            app.misc_Edit_kFactorAdd.Enable = 1;

            if ~isempty(app.misc_Edit_kFactorTree.Children)
                app.edit_unitRefresh.Enable = 1;
            else
                app.edit_unitRefresh.Enable = 0;
            end
        end

        %-----------------------------------------------------------------%
        function misc_unitLevelCorrection(app, OperationType, idx1, idx2, Name)
            try
                fcn.measCalibration(app, OperationType, idx1, idx2, Name)
            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', ME.message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idxThreads)
            
            app.CallingApp = mainapp;
            app.specData   = mainapp.specData;

            initialValues(app, idxThreads)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Callback function: ContextMenu_del, edit_unitRefresh, 
        % ...and 1 other component
        function gpsValueChanged(app, event)
            
            focus(app.jsBackDoor)

            idxThread = app.referenceData.idxThread;

            switch event.Source
                %---------------------------------------------------------%
                case app.edit_unitRefresh
                    idx2 = 1:height(app.specData(idxThread).UserData.measCalibration);
                    Name = app.specData(idxThread).UserData.measCalibration.Name;
                    misc_unitLevelCorrection(app, 'Remove', idxThread, idx2, Name)

                %---------------------------------------------------------%
                case app.misc_Edit_kFactorAdd
                    Name = app.misc_Edit_kFactor.Value;
                    misc_unitLevelCorrection(app, 'Add', idxThread, [], Name)

                %---------------------------------------------------------%
                case app.ContextMenu_del
                    idx2 = app.misc_Edit_kFactorTree.SelectedNodes.NodeData;
                    Name = app.specData(idxThread).UserData.measCalibration.Name(idx2);
                    misc_unitLevelCorrection(app, 'Remove', idxThread, idx2, Name)
            end
            Layout(app)

            if checkEdition(app)
                app.btnOK.Enable = 1;
            else
                app.btnOK.Enable = 0;
            end
            
        end

        % Callback function: btnClose, btnOK
        function ButtonPushed(app, event)
            
            pushedButtonTag = event.Source.Tag;

            if pushedButtonTag == "OK"
                if checkEdition(app)
                    % idxThreads = app.initialGPS.idxThread;
                    % 
                    % for ii = idxThreads                        
                    %     if app.initialGPS.Status ~= -1
                    %         app.specData(ii).GPS.Status = -1;
                    %     end
                    % 
                    %     app.specData(ii).GPS.Latitude   = app.gpsLatitude.Value;
                    %     app.specData(ii).GPS.Longitude  = app.gpsLongitude.Value;
                    %     app.specData(ii).GPS.Location   = app.gpsCity.Value;
                    %     app.specData(ii).GPS.Edited     = true;
                    % end
                end
            end

            appBackDoor(app.CallingApp, app, 'ButtonPushed', pushedButtonTag)
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.btnClose = uiimage(app.GridLayout);
            app.btnClose.ImageClickedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnClose.Tag = 'Close';
            app.btnClose.ImageSource = 'Delete_12SVG.svg';
            app.Document = uigridlayout(app.GridLayout);
            app.gpsLocationPanelLabel = uilabel(app.Document);
            app.gpsLocationPanelLabel.Text = 'CURVA DE CORREÇÃO';
            app.gpsLocationPanel = uipanel(app.Document);
            app.gpsLocationGrid = uigridlayout(app.gpsLocationPanel);
            app.misc_Edit_unitRaw = uieditfield(app.gpsLocationGrid, 'text');
            app.misc_Edit_unitRaw.Editable = 'off';
            app.misc_Edit_unitRawLabel = uilabel(app.gpsLocationGrid);
            app.misc_Edit_unitRawLabel.Interpreter = 'html';
            app.misc_Edit_unitRawLabel.Text = {'Unidade original:'; '<p style="line-height:10px; font-size:9px; color:gray;">(coleta)</p>'};
            app.misc_Edit_kFactor = uidropdown(app.gpsLocationGrid);
            app.misc_Edit_kFactor.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (Argus)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (690 MHz - 6 GHz)'};
            app.misc_Edit_kFactor.Value = '';
            app.misc_Edit_kFactorLabel = uilabel(app.gpsLocationGrid);
            app.misc_Edit_kFactorLabel.Interpreter = 'html';
            app.misc_Edit_kFactorLabel.Text = {'Curva de correção:'; '<p style="line-height:10px; font-size:9px; color:gray;">(calibração, fator-k)</p>'};
            app.misc_Edit_kFactorTree = uitree(app.gpsLocationGrid);
            app.misc_Edit_kFactorAdd = uiimage(app.gpsLocationGrid);
            app.misc_Edit_kFactorAdd.ImageClickedFcn = createCallbackFcn(app, @gpsValueChanged, true);
            app.misc_Edit_kFactorAdd.ImageSource = 'addSymbol_32.png';
            app.btnOK = uibutton(app.Document, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnOK.Tag = 'OK';
            app.btnOK.Enable = 'off';
            app.btnOK.Text = 'OK';
            app.edit_unitRefresh = uiimage(app.Document);
            app.edit_unitRefresh.ImageClickedFcn = createCallbackFcn(app, @gpsValueChanged, true);
            app.edit_unitRefresh.ImageSource = 'Refresh_18.png';
            app.jsBackDoor = uihtml(app.Document);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu_del = uimenu(app.ContextMenu);
            app.ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @gpsValueChanged, true);
            app.ContextMenu_del.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockMisc_AddKFactor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end