classdef dockMisc_EditLocation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Document               matlab.ui.container.GridLayout
        btnOK                  matlab.ui.control.Button
        gpsRefresh             matlab.ui.control.Image
        gpsLocationPanel       matlab.ui.container.Panel
        gpsLocationGrid        matlab.ui.container.GridLayout
        gpsCity                matlab.ui.control.EditField
        gpsCityLabel           matlab.ui.control.Label
        gpsSearchCity          matlab.ui.control.Image
        gpsLongitude           matlab.ui.control.NumericEditField
        gpsLongitudeLabel      matlab.ui.control.Label
        gpsLatitude            matlab.ui.control.NumericEditField
        gpsLatitudeLabel       matlab.ui.control.Label
        gpsLocationPanelLabel  matlab.ui.control.Label
        btnClose               matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Container
        isDocked = true

        CallingApp
        specData
        referenceData
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function initialValues(app, idxThreads)
            idxThread = idxThreads(1);

            app.referenceData = struct('idxThread', idxThreads,                                      ...
                                       'Status',    app.specData(idxThread).GPS.Status,              ...
                                       'Latitude',  round(app.specData(idxThread).GPS.Latitude,  6), ...
                                       'Longitude', round(app.specData(idxThread).GPS.Longitude, 6), ...
                                       'Location',  app.specData(idxThread).GPS.Location);

            app.gpsLatitude.Value  = app.referenceData.Latitude;
            app.gpsLongitude.Value = app.referenceData.Longitude;
            app.gpsCity.Value      = app.referenceData.Location;
        end

        %-----------------------------------------------------------------%
        function editionFlag = checkEdition(app)
            if abs(app.gpsLatitude.Value  - app.referenceData.Latitude)  > class.Constants.floatDiffTolerance || ...
               abs(app.gpsLongitude.Value - app.referenceData.Longitude) > class.Constants.floatDiffTolerance || ...
               ~strcmp(app.gpsCity.Value, app.referenceData.Location)

                editionFlag = true;
            else
                editionFlag = false;
            end
        end

        %-----------------------------------------------------------------%
        function CallingMainApp(app, updateFlag, returnFlag)
            appBackDoor(app.CallingApp, app, 'MISCELLANEOUS', updateFlag, returnFlag)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idxThreads)
            
            app.CallingApp = mainapp;
            app.specData   = mainapp.specData;

            initialValues(app, idxThreads)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Callback function: gpsCity, gpsLatitude, gpsLongitude,
        % gpsRefresh, 
        % ...and 1 other component
        function gpsValueChanged(app, event)
            
            switch event.Source
                %---------------------------------------------------------%
                case app.gpsLatitude
                    focus(app.gpsLongitude)

                %---------------------------------------------------------%
                case app.gpsLongitude
                    focus(app.gpsSearchCity)

                %---------------------------------------------------------%
                case app.gpsSearchCity
                    gps = struct('Latitude',  app.gpsLatitude.Value, ...
                                 'Longitude', app.gpsLongitude.Value);
                    
                    app.CallingApp.progressDialog.Visible = 'visible';
                    [cityName, distValue_km, locInfo] = fcn.gpsFindCity(gps);
                    app.CallingApp.progressDialog.Visible = 'hidden';

                    if ~strcmp(cityName, app.gpsCity.Value)        
                        msgQuestion   = sprintf(['%s retornou o município "%s" como a localidade mais próxima das '  ...
                                                 'coordenadas geográficas (%.6fº, %.6fº), cuja sede está a %.1f km ' ...
                                                 'de distância.<br><br>Deseja atualizar essa informação?!'], locInfo.infoSource, cityName, gps.Latitude, gps.Longitude, distValue_km);
                        userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
    
                        if userSelection == "Sim"
                            app.gpsCity.Value = cityName;
                        end

                    else
                        msgWarning    = sprintf('%s retornou o município "%s", valor já inserido no campo "Município/UF"', locInfo.infoSource, cityName);
                        appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                    end

                %---------------------------------------------------------%
                case app.gpsCity
                    app.gpsCity.Value = strtrim(app.gpsCity.Value);
                    [cityName, Latitude, Longitude] = fcn.gpsFindCoordinates(app.gpsCity.Value);

                    if ~isempty(cityName)
                        msgQuestion   = 'Deseja atualizar, além da localidade, as informações de Latitude e Longitude?';
                        userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);

                        if userSelection == "Sim"
                            app.gpsCity.Value      = cityName;
                            app.gpsLatitude.Value  = Latitude;
                            app.gpsLongitude.Value = Longitude;
                        end
                        
                    else
                        app.gpsCity.Value = event.PreviousValue;
                        
                        msgWarning    = sprintf(['Não encontrada em base do IBGE o município <b>"%s"</b>. Favor corrigir ' ...
                                                 'eventual erro na grafia, inserindo os acentos, no formato Município/UF.'], app.gpsCity.Value);
                        appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                    end

                %---------------------------------------------------------%
                case app.gpsRefresh
                    idxThreads = app.referenceData.idxThread;
                    if app.referenceData.Status == -1
                        fcn.gpsProperty(app.specData, idxThreads)
                    end
                    initialValues(app, idxThreads)
                    CallingMainApp(app, true, true)
            end

            if checkEdition(app)
                app.btnOK.Enable = 1;
            else
                app.btnOK.Enable = 0;
            end
            
        end

        % Callback function: btnClose, btnOK
        function ButtonPushed(app, event)
            
            pushedButtonTag = event.Source.Tag;
            switch pushedButtonTag
                case 'OK'
                    if checkEdition(app)
                        idxThreads = app.referenceData.idxThread;
    
                        for ii = idxThreads                        
                            if app.referenceData.Status ~= -1
                                app.specData(ii).GPS.Status = -1;
                            end
        
                            app.specData(ii).GPS.Latitude   = app.gpsLatitude.Value;
                            app.specData(ii).GPS.Longitude  = app.gpsLongitude.Value;
                            app.specData(ii).GPS.Location   = app.gpsCity.Value;
                            app.specData(ii).GPS.Edited     = true;
                        end
                    end

                    updateFlag = true;

                case 'Close'
                    updateFlag = false;
            end

            CallingMainApp(app, updateFlag, false)
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.btnClose = uiimage(app.GridLayout);
            app.btnClose.ImageClickedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnClose.Tag = 'Close';
            app.btnClose.ImageSource = 'Delete_12SVG.svg';
            app.Document = uigridlayout(app.GridLayout);
            app.gpsLocationPanelLabel = uilabel(app.Document);
            app.gpsLocationPanelLabel.Text = 'LOCAL DA MONITORAÇÃO';
            app.gpsLocationPanel = uipanel(app.Document);
            app.gpsLocationGrid = uigridlayout(app.gpsLocationPanel);
            app.gpsLatitudeLabel = uilabel(app.gpsLocationGrid);
            app.gpsLatitudeLabel.Text = 'Latitude:';
            app.gpsLatitude = uieditfield(app.gpsLocationGrid, 'numeric');
            app.gpsLatitude.Limits = [-90 90];
            app.gpsLatitude.ValueDisplayFormat = '%.6f';
            app.gpsLatitude.ValueChangedFcn = createCallbackFcn(app, @gpsValueChanged, true);
            app.gpsLatitude.Value = -1;
            app.gpsLongitudeLabel = uilabel(app.gpsLocationGrid);
            app.gpsLongitudeLabel.Text = 'Longitude:';
            app.gpsLongitude = uieditfield(app.gpsLocationGrid, 'numeric');
            app.gpsLongitude.Limits = [-180 180];
            app.gpsLongitude.ValueDisplayFormat = '%.6f';
            app.gpsLongitude.ValueChangedFcn = createCallbackFcn(app, @gpsValueChanged, true);
            app.gpsLongitude.Value = -1;
            app.gpsSearchCity = uiimage(app.gpsLocationGrid);
            app.gpsSearchCity.ImageClickedFcn = createCallbackFcn(app, @gpsValueChanged, true);
            app.gpsSearchCity.Tooltip = {'Consulta município'};
            app.gpsSearchCity.ImageSource = 'Globo_32.png';
            app.gpsCityLabel = uilabel(app.gpsLocationGrid);
            app.gpsCityLabel.Text = 'Município/UF:';
            app.gpsCity = uieditfield(app.gpsLocationGrid, 'text');
            app.gpsCity.ValueChangedFcn = createCallbackFcn(app, @gpsValueChanged, true);
            app.gpsRefresh = uiimage(app.Document);
            app.gpsRefresh.ImageClickedFcn = createCallbackFcn(app, @gpsValueChanged, true);
            app.gpsRefresh.Tooltip = {'Retorna às configurações iniciais'};
            app.gpsRefresh.ImageSource = 'Refresh_18.png';
            app.btnOK = uibutton(app.Document, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnOK.Tag = 'OK';
            app.btnOK.Enable = 'off';
            app.btnOK.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockMisc_EditLocation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end