classdef winCredential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Grid1          matlab.ui.container.GridLayout
        Button         matlab.ui.control.Button
        CheckBox       matlab.ui.control.CheckBox
        Issue          matlab.ui.control.NumericEditField
        IssueLabel     matlab.ui.control.Label
        Panel          matlab.ui.container.Panel
        Grid2          matlab.ui.container.GridLayout
        Password       matlab.ui.control.HTML
        PasswordLabel  matlab.ui.control.Label
        Login          matlab.ui.control.EditField
        LoginLabel     matlab.ui.control.Label
        Label          matlab.ui.control.Label
        Title          matlab.ui.control.Label
        Image          matlab.ui.control.Image
    end

    
    properties (Access = private)
        CallingApp 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            fcn.winPosition(app)

            app.Password.HTMLSource = sprintf('<!DOCTYPE html>\n<html>\n<head>\n\t<script type="text/javascript">\n\t\tfunction setup(htmlComponent) {\n\t\t\tvar dom = document.getElementById("pwrd");\n\t\t\twindow.updateData = function (newData) {\n\t\t\t\thtmlComponent.Data = dom.value;\n\t\t\t}\n\t\t}\n\t</script>\n</head>\n<body>\n\t<input type="password" id="pwrd" onchange="updateData()" style="padding-left: 3px; width: 384px; height: 16px"></input>\n</body>\n</html>');
            app.Issue.Value = app.CallingApp.report_Issue.Value;

            focus(app.Login)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if isempty(app.Login.Value) || isempty(app.Password.Data)
                msg = 'Credencial do fiscal inválida.';
            elseif  (app.Issue.Value <= 0) || isinf(app.Issue.Value)
                msg = 'Número de inspeção inválido.';
            end

            if exist('msg', 'var')
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', msg);
                return
            end

            try
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Aguardando resposta do Fiscaliza...</font>');
                
                pyModule    = py.importlib.import_module('fiscaliza.info');
                py.importlib.reload(pyModule);
                
                issueInfo   = pyModule.detalhar_issue(pyargs('issue', num2str(app.Issue.Value), ...
                                                             'login', app.Login.Value,       ...
                                                             'senha', app.Password.Data,     ...
                                                             'teste', logical(app.CheckBox.Value)));                    
                issueInfo   = fcn.py2matRedmine(issueInfo, 1);
                
                app.CallingApp.auxiliarWin3 = auxApp.winFiscaliza(app.CallingApp, pyModule, app.Login.Value, app.Password.Data, logical(app.CheckBox.Value), issueInfo);
                drawnow nocallbacks
                
                delete(d)
                closeFcn(app)

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', ME.message);
            end
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            
            if app.CheckBox.Value
                app.Title.Text = {'<b style="color: #a2142f; font-size: 24;">Fiscaliza</b><font style="font-size: 10;">HOMOLOGAÇÃO</font>'; '<font>Sistema de gestão da fiscalização da Agência</font>'; ' '};
            else
                app.Title.Text = {'<b style="color: #a2142f; font-size: 24;">Fiscaliza</b>'; '<font>Sistema de gestão da fiscalização da Agência</font>'; ' '};
            end

        end

        % Callback function: Login, Password
        function DeleteEmptyChar(app, event)
            
            switch event.Source
                case app.Login;    app.Login.Value   = strtrim(app.Login.Value);
                case app.Password; app.Password.Data = strtrim(app.Password.Data);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'LT_redmine.png';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #a2142f; font-size: 24;">Fiscaliza</b>'; '<font>Sistema de gestão da fiscalização da Agência</font>'; ' '};
            app.Label = uilabel(app.Grid1);
            app.Label.Text = 'Credenciais:';
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.LoginLabel = uilabel(app.Grid2);
            app.LoginLabel.Text = 'Usuário:';
            app.Login = uieditfield(app.Grid2, 'text');
            app.Login.ValueChangedFcn = createCallbackFcn(app, @DeleteEmptyChar, true);
            app.PasswordLabel = uilabel(app.Grid2);
            app.PasswordLabel.Text = 'Senha:';
            app.Password = uihtml(app.Grid2);
            app.Password.DataChangedFcn = createCallbackFcn(app, @DeleteEmptyChar, true);
            app.IssueLabel = uilabel(app.Grid1);
            app.IssueLabel.Text = 'Inspeção:';
            app.Issue = uieditfield(app.Grid1, 'numeric');
            app.Issue.Limits = [-1 Inf];
            app.Issue.RoundFractionalValues = 'on';
            app.Issue.ValueDisplayFormat = '%d';
            app.Issue.Value = -1;
            app.CheckBox = uicheckbox(app.Grid1);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'HOMOLOGAÇÃO';
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winCredential(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end