classdef winDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Grid1                           matlab.ui.container.GridLayout
        Button2                         matlab.ui.control.Button
        play_FindPeaks_ParametersPanel  matlab.ui.container.Panel
        play_FindPeaks_ParametersGrid   matlab.ui.container.GridLayout
        play_FindPeaks_MaxHoldPanel     matlab.ui.container.Panel
        play_FindPeaks_MaxHoldGrid      matlab.ui.container.GridLayout
        play_FindPeaks_maxOCC           matlab.ui.control.Spinner
        play_FindPeaks_maxOCCLabel      matlab.ui.control.Label
        play_FindPeaks_meanOCC          matlab.ui.control.Spinner
        play_FindPeaks_meanOCCLabel     matlab.ui.control.Label
        play_FindPeaks_Prominence2      matlab.ui.control.Spinner
        play_FindPeaks_Prominence2Label  matlab.ui.control.Label
        play_FindPeaks_MeanPanel        matlab.ui.container.Panel
        play_FindPeaks_MeanGrid         matlab.ui.container.GridLayout
        play_FindPeaks_Prominence1      matlab.ui.control.Spinner
        play_FindPeaks_Prominence1Label  matlab.ui.control.Label
        play_FindPeaks_BW               matlab.ui.control.Spinner
        play_FindPeaks_BWLabel          matlab.ui.control.Label
        play_FindPeaks_distance         matlab.ui.control.Spinner
        play_FindPeaks_distanceLabel    matlab.ui.control.Label
        play_FindPeaks_Class            matlab.ui.control.DropDown
        play_FindPeaks_ClassLabel       matlab.ui.control.Label
        play_FindPeaks_prominence       matlab.ui.control.Spinner
        play_FindPeaks_prominenceLabel  matlab.ui.control.Label
        play_FindPeaks_THR              matlab.ui.control.Spinner
        play_FindPeaks_THRLabel         matlab.ui.control.Label
        play_FindPeaks_Numbers          matlab.ui.control.Spinner
        play_FindPeaks_NumbersLabel     matlab.ui.control.Label
        play_FindPeaks_Trace            matlab.ui.control.DropDown
        play_FindPeaks_TraceLabel       matlab.ui.control.Label
        play_FindPeaks_Algorithm        matlab.ui.control.DropDown
        play_FindPeaks_AlgorithmLabel   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Title                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        rootFolder        
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function Fcn_InitialValues(app)

            idx = app.CallingApp.play_PlotPanel.UserData.NodeData;
            
            app.play_FindPeaks_Class.Items = [app.CallingApp.channelObj.FindPeaks.Name; {'Custom'}];            
            findPeaks = FindPeaksOfPrimaryBand(app.CallingApp.channelObj, app.CallingApp.specData(idx));
            if ~isempty(findPeaks)
                app.play_FindPeaks_Class.Value  = findPeaks.Name{1};
            end
            ClassValueChanged(app)
            
            app.play_FindPeaks_Algorithm.Value  = app.CallingApp.specData(idx).UserData.reportDetection.Algorithm;
            AlgorithmValueChanged(app)

            switch app.play_FindPeaks_Algorithm.Value
                case 'FindPeaks'
                    app.play_FindPeaks_Trace.Value        = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.Fcn;
                    app.play_FindPeaks_Numbers.Value      = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.NPeaks;
                    app.play_FindPeaks_THR.Value          = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.THR;
                    app.play_FindPeaks_prominence.Value   = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.Prominence;
                    app.play_FindPeaks_distance.Value     = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.Distance;
                    app.play_FindPeaks_BW.Value           = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.BW;

                case 'FindPeaks+OCC'
                    if ~isequal(app.play_FindPeaks_distance.Value,         app.CallingApp.specData(idx).UserData.reportDetection.Parameters.Distance)    || ...
                            ~isequal(app.play_FindPeaks_BW.Value,          app.CallingApp.specData(idx).UserData.reportDetection.Parameters.BW)          || ...
                            ~isequal(app.play_FindPeaks_Prominence1.Value, app.CallingApp.specData(idx).UserData.reportDetection.Parameters.Prominence1) || ...
                            ~isequal(app.play_FindPeaks_Prominence2.Value, app.CallingApp.specData(idx).UserData.reportDetection.Parameters.Prominence2) || ...
                            ~isequal(app.play_FindPeaks_meanOCC.Value,     app.CallingApp.specData(idx).UserData.reportDetection.Parameters.meanOCC)     || ...
                            ~isequal(app.play_FindPeaks_maxOCC.Value,      app.CallingApp.specData(idx).UserData.reportDetection.Parameters.maxOCC)

                        app.play_FindPeaks_Class.Value        = 'Custom';
                        app.play_FindPeaks_distance.Value     = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.Distance;
                        app.play_FindPeaks_BW.Value           = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.BW;
                        app.play_FindPeaks_Prominence1.Value  = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.Prominence1;
                        app.play_FindPeaks_Prominence2.Value  = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.Prominence2;
                        app.play_FindPeaks_meanOCC.Value      = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.meanOCC;
                        app.play_FindPeaks_maxOCC.Value       = app.CallingApp.specData(idx).UserData.reportDetection.Parameters.maxOCC;
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.rootFolder = app.CallingApp.rootFolder;
            appUtil.winPosition(app.UIFigure)

            Fcn_InitialValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Value changed function: play_FindPeaks_Algorithm
        function AlgorithmValueChanged(app, event)
            
            switch app.play_FindPeaks_Algorithm.Value
                case 'FindPeaks'
                    app.play_FindPeaks_ParametersGrid.RowHeight([3 4 7]) = {25,22,0};
                    
                    app.play_FindPeaks_prominenceLabel.Visible = 1;
                    app.play_FindPeaks_prominence.Visible      = 1;

                    app.play_FindPeaks_ClassLabel.Visible = 0;
                    app.play_FindPeaks_Class.Visible      = 0;

                case 'FindPeaks+OCC'
                    app.play_FindPeaks_ParametersGrid.RowHeight([3 4 7]) = {0,0,140};

                    app.play_FindPeaks_prominenceLabel.Visible = 0;
                    app.play_FindPeaks_prominence.Visible      = 0;

                    app.play_FindPeaks_ClassLabel.Visible = 1;
                    app.play_FindPeaks_Class.Visible      = 1;
            end

        end

        % Value changed function: play_FindPeaks_Class
        function ClassValueChanged(app, event)
            
            if ~strcmp(app.play_FindPeaks_Class.Value, 'Custom')
                idx = find(strcmp(app.CallingApp.channelObj.FindPeaks.Name, app.play_FindPeaks_Class.Value), 1);
    
                app.play_FindPeaks_distance.Value    = 1000 * app.CallingApp.channelObj.FindPeaks.Distance(idx);
                app.play_FindPeaks_BW.Value          = 1000 * app.CallingApp.channelObj.FindPeaks.BW(idx);
                app.play_FindPeaks_Prominence1.Value = app.CallingApp.channelObj.FindPeaks.Prominence1(idx);
                app.play_FindPeaks_Prominence2.Value = app.CallingApp.channelObj.FindPeaks.Prominence2(idx);
                app.play_FindPeaks_meanOCC.Value     = app.CallingApp.channelObj.FindPeaks.meanOCC(idx);
                app.play_FindPeaks_maxOCC.Value      = app.CallingApp.channelObj.FindPeaks.maxOCC(idx);
            end

        end

        % Value changed function: play_FindPeaks_maxOCC, 
        % ...and 1 other component
        function occValueChanged(app, event)
            
            switch event.Source
                case app.play_FindPeaks_meanOCC
                    if app.play_FindPeaks_meanOCC.Value > app.play_FindPeaks_maxOCC.Value
                        app.play_FindPeaks_maxOCC.Value = app.play_FindPeaks_meanOCC.Value;
                    end

                case app.play_FindPeaks_maxOCC
                    if app.play_FindPeaks_maxOCC.Value < app.play_FindPeaks_meanOCC.Value
                        app.play_FindPeaks_meanOCC.Value = app.play_FindPeaks_maxOCC.Value;
                    end
            end

        end

        % Button pushed function: Button2
        function ButtonPushed(app, event)
            
            idx = app.CallingApp.play_PlotPanel.UserData.NodeData;
            app.CallingApp.specData(idx).UserData.reportDetection.Algorithm = app.play_FindPeaks_Algorithm.Value;

            switch app.play_FindPeaks_Algorithm.Value
                case 'FindPeaks'
                    app.CallingApp.specData(idx).UserData.reportDetection.Parameters = struct('Fcn',        app.play_FindPeaks_Trace.Value,      ...
                                                                                              'NPeaks',     app.play_FindPeaks_Numbers.Value,    ...
                                                                                              'THR',        app.play_FindPeaks_THR.Value,        ...
                                                                                              'Prominence', app.play_FindPeaks_prominence.Value, ...
                                                                                              'Distance',   app.play_FindPeaks_distance.Value,   ...
                                                                                              'BW',         app.play_FindPeaks_BW.Value);

                case 'FindPeaks+OCC'
                    app.CallingApp.specData(idx).UserData.reportDetection.Parameters = struct('Distance',    app.play_FindPeaks_distance.Value,    ...
                                                                                              'BW',          app.play_FindPeaks_BW.Value,          ...
                                                                                              'Prominence1', app.play_FindPeaks_Prominence1.Value, ...
                                                                                              'Prominence2', app.play_FindPeaks_Prominence2.Value, ...
                                                                                              'meanOCC',     app.play_FindPeaks_meanOCC.Value,     ...
                                                                                              'maxOCC',      app.play_FindPeaks_maxOCC.Value);
            end

            app.CallingApp.auxiliarWinFunction(app, idx)
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Detecção de emissões</b>'; '<font>Escolha do algoritmo de detecção de emissões</font>'; ' '};
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'Detection_128.png';
            app.play_FindPeaks_ParametersPanel = uipanel(app.Grid1);
            app.play_FindPeaks_ParametersGrid = uigridlayout(app.play_FindPeaks_ParametersPanel);
            app.play_FindPeaks_AlgorithmLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_AlgorithmLabel.Text = 'Algoritmo:';
            app.play_FindPeaks_Algorithm = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Algorithm.Items = {'FindPeaks', 'FindPeaks+OCC'};
            app.play_FindPeaks_Algorithm.ValueChangedFcn = createCallbackFcn(app, @AlgorithmValueChanged, true);
            app.play_FindPeaks_Algorithm.Value = 'FindPeaks';
            app.play_FindPeaks_TraceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_TraceLabel.Text = {'Tipo de '; 'traço:'};
            app.play_FindPeaks_Trace = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Trace.Items = {'MinHold', 'Média', 'MaxHold'};
            app.play_FindPeaks_Trace.Value = 'Média';
            app.play_FindPeaks_NumbersLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_NumbersLabel.Text = {'Número de '; 'picos:'};
            app.play_FindPeaks_Numbers = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Numbers.Limits = [1 100];
            app.play_FindPeaks_Numbers.RoundFractionalValues = 'on';
            app.play_FindPeaks_Numbers.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Numbers.Value = 10;
            app.play_FindPeaks_THRLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_THRLabel.Text = {'Threshold'; '(dB):'};
            app.play_FindPeaks_THR = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_THR.Step = 10;
            app.play_FindPeaks_THR.RoundFractionalValues = 'on';
            app.play_FindPeaks_THR.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_THR.Value = -Inf;
            app.play_FindPeaks_prominenceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_prominenceLabel.Visible = 'off';
            app.play_FindPeaks_prominenceLabel.Text = {'Proeminência '; '(dB):'};
            app.play_FindPeaks_prominence = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_prominence.Step = 10;
            app.play_FindPeaks_prominence.Limits = [1 Inf];
            app.play_FindPeaks_prominence.RoundFractionalValues = 'on';
            app.play_FindPeaks_prominence.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_prominence.Visible = 'off';
            app.play_FindPeaks_prominence.Value = 30;
            app.play_FindPeaks_ClassLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_ClassLabel.Text = {'Classe de '; 'emissão:'};
            app.play_FindPeaks_Class = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Class.Items = {'Genérico', 'Radiocomunicação', 'Radiodifusão (FM)', 'Radiodifusão (TV)'};
            app.play_FindPeaks_Class.ValueChangedFcn = createCallbackFcn(app, @ClassValueChanged, true);
            app.play_FindPeaks_Class.Value = 'Genérico';
            app.play_FindPeaks_distanceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distanceLabel.Text = {'Distância entre'; 'picos (kHz):'};
            app.play_FindPeaks_distance = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distance.Step = 25;
            app.play_FindPeaks_distance.Limits = [0 Inf];
            app.play_FindPeaks_distance.RoundFractionalValues = 'on';
            app.play_FindPeaks_distance.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_distance.Value = 25;
            app.play_FindPeaks_BWLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_BWLabel.Text = {'Largura ocupada '; '(kHz):'};
            app.play_FindPeaks_BW = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_BW.Step = 10;
            app.play_FindPeaks_BW.Limits = [0 Inf];
            app.play_FindPeaks_BW.RoundFractionalValues = 'on';
            app.play_FindPeaks_BW.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_BW.Value = 10;
            app.play_FindPeaks_MeanPanel = uipanel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_MeanPanel.Title = 'MÉDIA';
            app.play_FindPeaks_MeanGrid = uigridlayout(app.play_FindPeaks_MeanPanel);
            app.play_FindPeaks_Prominence1Label = uilabel(app.play_FindPeaks_MeanGrid);
            app.play_FindPeaks_Prominence1Label.Text = {'Proeminência'; '(dB):'};
            app.play_FindPeaks_Prominence1 = uispinner(app.play_FindPeaks_MeanGrid);
            app.play_FindPeaks_Prominence1.Step = 10;
            app.play_FindPeaks_Prominence1.Limits = [1 Inf];
            app.play_FindPeaks_Prominence1.RoundFractionalValues = 'on';
            app.play_FindPeaks_Prominence1.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Prominence1.Value = 10;
            app.play_FindPeaks_MaxHoldPanel = uipanel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_MaxHoldPanel.Title = 'MAXHOLD';
            app.play_FindPeaks_MaxHoldGrid = uigridlayout(app.play_FindPeaks_MaxHoldPanel);
            app.play_FindPeaks_Prominence2Label = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_Prominence2Label.Text = {'Proeminência'; '(dB):'};
            app.play_FindPeaks_Prominence2 = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_Prominence2.Step = 10;
            app.play_FindPeaks_Prominence2.Limits = [1 Inf];
            app.play_FindPeaks_Prominence2.RoundFractionalValues = 'on';
            app.play_FindPeaks_Prominence2.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Prominence2.Value = 30;
            app.play_FindPeaks_meanOCCLabel = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_meanOCCLabel.Text = {'Ocupação'; 'média (%):'};
            app.play_FindPeaks_meanOCC = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_meanOCC.Step = 10;
            app.play_FindPeaks_meanOCC.Limits = [0 100];
            app.play_FindPeaks_meanOCC.RoundFractionalValues = 'on';
            app.play_FindPeaks_meanOCC.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_meanOCC.ValueChangedFcn = createCallbackFcn(app, @occValueChanged, true);
            app.play_FindPeaks_meanOCC.Value = 1;
            app.play_FindPeaks_maxOCCLabel = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_maxOCCLabel.Text = {'Ocupação'; 'máxima (%):'};
            app.play_FindPeaks_maxOCC = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_maxOCC.Step = 10;
            app.play_FindPeaks_maxOCC.Limits = [0 100];
            app.play_FindPeaks_maxOCC.RoundFractionalValues = 'on';
            app.play_FindPeaks_maxOCC.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_maxOCC.ValueChangedFcn = createCallbackFcn(app, @occValueChanged, true);
            app.play_FindPeaks_maxOCC.Value = 10;
            app.Button2 = uibutton(app.Grid1, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDetection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end