classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Grid                            matlab.ui.container.GridLayout
        toolGrid                        matlab.ui.container.GridLayout
        filter_DataBinningExport        matlab.ui.control.Image
        filter_Summary                  matlab.ui.control.Image
        jsBackDoor                      matlab.ui.control.HTML
        tool_TimestampLabel             matlab.ui.control.Label
        tool_TimestampSlider            matlab.ui.control.Slider
        tool_LoopControl                matlab.ui.control.Image
        tool_Play                       matlab.ui.control.Image
        tool_ControlPanelVisibility     matlab.ui.control.Image
        plotFootnote                    matlab.ui.control.Label
        plotSource                      matlab.ui.container.ButtonGroup
        plotSource_specBinTable         matlab.ui.control.RadioButton
        plotSource_specFilteredTable    matlab.ui.control.RadioButton
        axesToolbarGrid                 matlab.ui.container.GridLayout
        axesTool_PlotSize               matlab.ui.control.Slider
        axesTool_DensityPlot            matlab.ui.control.Image
        axesTool_DistortionPlot         matlab.ui.control.Image
        axesTool_DataTip                matlab.ui.control.Image
        axesTool_RegionZoom             matlab.ui.control.Image
        axesTool_RestoreView            matlab.ui.control.Image
        plotPanel                       matlab.ui.container.Panel
        ControlTabGrid                  matlab.ui.container.GridLayout
        menu_MainGrid                   matlab.ui.container.GridLayout
        menu_Button4Grid                matlab.ui.container.GridLayout
        menu_Button4Icon                matlab.ui.control.Image
        menu_Button4Label               matlab.ui.control.Label
        menu_Button3Grid                matlab.ui.container.GridLayout
        menu_Button3Icon                matlab.ui.control.Image
        menu_Button3Label               matlab.ui.control.Label
        menu_Button2Grid                matlab.ui.container.GridLayout
        menu_Button2Icon                matlab.ui.control.Image
        menu_Button2Label               matlab.ui.control.Label
        menu_Button1Grid                matlab.ui.container.GridLayout
        menu_Button1Icon                matlab.ui.control.Image
        menu_Button1Label               matlab.ui.control.Label
        menuUnderline                   matlab.ui.control.Image
        ControlTabGroup                 matlab.ui.container.TabGroup
        Tab1_Emission                   matlab.ui.container.Tab
        Tab1_Grid                       matlab.ui.container.GridLayout
        general_ReportFlag              matlab.ui.control.CheckBox
        general_chPanel                 matlab.ui.container.Panel
        general_chGrid                  matlab.ui.container.GridLayout
        general_chBW                    matlab.ui.control.NumericEditField
        general_chBWLabel               matlab.ui.control.Label
        general_chFrequency             matlab.ui.control.NumericEditField
        general_chFrequencyLabel        matlab.ui.control.Label
        general_chEdit                  matlab.ui.control.Image
        general_chLabel                 matlab.ui.control.Label
        general_emissionInfoPanel       matlab.ui.container.Panel
        general_emissionInfoGrid        matlab.ui.container.GridLayout
        general_emissionInfo            matlab.ui.control.HTML
        general_emissionList            matlab.ui.control.DropDown
        general_emissionListRefresh     matlab.ui.control.Image
        general_emissionListLabel       matlab.ui.control.Label
        Tab2_Filter                     matlab.ui.container.Tab
        Tab2_Grid                       matlab.ui.container.GridLayout
        DATABINNINGpstylecolorgraytextalignjustifyLabel  matlab.ui.control.Label
        filter_DataBinningPanel         matlab.ui.container.Panel
        filter_DataBinningGrid          matlab.ui.container.GridLayout
        filter_DataBinningFcn           matlab.ui.control.DropDown
        filter_DataBinningFcnLabel      matlab.ui.control.Label
        filter_DataBinningLength        matlab.ui.control.Spinner
        filter_DataBinningLengthLabel   matlab.ui.control.Label
        filter_Tree                     matlab.ui.container.CheckBoxTree
        filter_addImage                 matlab.ui.control.Image
        filter_RadioGroup               matlab.ui.container.ButtonGroup
        filter_GeographicType           matlab.ui.control.DropDown
        filter_GeographicTypeLabel      matlab.ui.control.Label
        filter_Geographic               matlab.ui.control.RadioButton
        filter_Timestamp                matlab.ui.control.RadioButton
        filter_THR                      matlab.ui.control.RadioButton
        filter_TreeLabel                matlab.ui.control.Label
        Tab3_Points                     matlab.ui.container.Tab
        Tab3_Grid                       matlab.ui.container.GridLayout
        points_Tree                     matlab.ui.container.CheckBoxTree
        points_addImage                 matlab.ui.control.Image
        points_addValuePanel            matlab.ui.container.Panel
        points_addValueGrid             matlab.ui.container.GridLayout
        points_addSubtype2Distance      matlab.ui.control.Spinner
        points_addSubtype2DistanceLabel  matlab.ui.control.Label
        points_addSubtype2NPeaks        matlab.ui.control.Spinner
        points_addSubtype2NPeaksLabel   matlab.ui.control.Label
        points_addSubtype2DropDown      matlab.ui.control.DropDown
        points_addSubtype2Label         matlab.ui.control.Label
        points_addSubtype1Distance      matlab.ui.control.NumericEditField
        points_addSubtype1DistanceLabel  matlab.ui.control.Label
        points_addSubtype1TextArea      matlab.ui.control.TextArea
        points_addSubtype1DropDown      matlab.ui.control.DropDown
        points_addSubtype1Label         matlab.ui.control.Label
        points_addTypePanel             matlab.ui.container.ButtonGroup
        points_addFindPeaks             matlab.ui.control.RadioButton
        points_addRFDataHub             matlab.ui.control.RadioButton
        points_TreeLabel                matlab.ui.control.Label
        Tab4_Config                     matlab.ui.container.Tab
        Tab4_Grid                       matlab.ui.container.GridLayout
        config_xyAxesPanel              matlab.ui.container.Panel
        config_xyAxesGrid               matlab.ui.container.GridLayout
        config_BandGuardPanel           matlab.ui.container.Panel
        config_BandGuardGrid            matlab.ui.container.GridLayout
        config_BandGuardBWRelatedValue  matlab.ui.control.Spinner
        config_BandGuardFixedValue      matlab.ui.control.NumericEditField
        config_BandGuardValueLabel      matlab.ui.control.Label
        config_BandGuardType            matlab.ui.control.DropDown
        config_BandGuardTypeLabel       matlab.ui.control.Label
        config_BandGuardLabel           matlab.ui.control.Label
        config_chPowerFaceAlpha         matlab.ui.control.Spinner
        config_chPowerEdgeAlpha         matlab.ui.control.Spinner
        config_chPowerColor             matlab.ui.control.ColorPicker
        config_chPowerVisibility        matlab.ui.control.DropDown
        config_chPowerLabel             matlab.ui.control.Label
        config_chROIFaceAlpha           matlab.ui.control.Spinner
        config_chROIEdgeAlpha           matlab.ui.control.Spinner
        config_chROIColor               matlab.ui.control.ColorPicker
        config_chROIVisibility          matlab.ui.control.DropDown
        config_chROILabel               matlab.ui.control.Label
        config_PersistanceVisibility    matlab.ui.control.DropDown
        config_PersistanceLabel         matlab.ui.control.Label
        config_xyAxesLabel              matlab.ui.control.Label
        config_geoAxesPanel             matlab.ui.container.Panel
        config_geoAxesGrid              matlab.ui.container.GridLayout
        config_points_Size              matlab.ui.control.Slider
        config_points_Color             matlab.ui.control.ColorPicker
        config_points_LineStyle         matlab.ui.control.DropDown
        config_points_Label             matlab.ui.control.Label
        config_Car_Size                 matlab.ui.control.Slider
        config_Car_Color                matlab.ui.control.ColorPicker
        config_Car_LineStyle            matlab.ui.control.DropDown
        config_Car_Label                matlab.ui.control.Label
        config_route_Size               matlab.ui.control.Slider
        config_route_InColor            matlab.ui.control.ColorPicker
        config_route_OutColor           matlab.ui.control.ColorPicker
        config_route_LineStyle          matlab.ui.control.DropDown
        config_route_Label              matlab.ui.control.Label
        config_geoAxesSubPanel          matlab.ui.container.Panel
        config_geoAxesSubGrid           matlab.ui.container.GridLayout
        config_Colormap                 matlab.ui.control.DropDown
        config_ColormapLabel            matlab.ui.control.Label
        config_Basemap                  matlab.ui.control.DropDown
        config_BasemapLabel             matlab.ui.control.Label
        config_geoAxesSublabel          matlab.ui.control.Label
        config_Refresh                  matlab.ui.control.Image
        config_geoAxesLabel             matlab.ui.control.Label
        filter_ContextMenu              matlab.ui.container.ContextMenu
        filter_delButton                matlab.ui.container.Menu
        filter_delAllButton             matlab.ui.container.Menu
        points_ContextMenu              matlab.ui.container.ContextMenu
        points_delButton                matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        General
        rootFolder

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag = {true, ...
                          true, ...
                          true, ...
                          true};

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        % Propriedades do app, além de handle para RFDataHub (variável
        % global)
        specData
        projectData
        channelObj
        rfDataHub

        %-----------------------------------------------------------------%
        % ESPECIFICIDADES AUXAPP.WINDRIVETEST
        %-----------------------------------------------------------------%
        % (a) OBJETO QUE SIMPLIFICA OPERAÇÕES RELACIONADAS AO FLUXO ESPECTRAL 
        %     SELECIONADO
        tempBandObj

        % (b) POTÊNCIA DE CANAL, FILTRAGEM, DATA-BINNING
        specRawTable
        specFilteredTable
        specBinTable

        filterTable = table({}, {}, struct('handle', {}, 'specification', {}),                  'VariableNames', {'type', 'subtype', 'roi'})
        pointsTable = table({}, struct('source', {}, 'idx', {}, 'Coordinates', {}), true(0, 1), 'VariableNames', {'type', 'value', 'visible'})

        % (c) PLOT+PLAYBACK
        UIAxes1
        UIAxes2
        UIAxes3
        UIAxes4
        restoreView = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})
        
        plotFlag = 0
        idxTime  = 1
        
        hCar
        hClearWrite
        hPersistanceObj
        hTimeline
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function startup_timerCreation(app, idxThread, idxEmission)
            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)startup_timerFcn(app, idxThread, idxEmission));
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        function startup_timerFcn(app, idxThread, idxEmission)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)

                % Define tamanho mínimo do app (não aplicável à versão webapp).
                if ~strcmp(app.CallingApp.executionMode, 'webApp')
                    appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
                end
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app, 0)
                jsBackDoor_Customizations(app, 1)

                app.progressDialog.Visible = 'visible';

                % Criação do eixo e leitura dos dados...    
                startup_AppProperties(app)
                startup_GUIComponents(app, idxThread, idxEmission)
                startup_AxesCreation(app)

                % Plot inicial:
                prePlot_startupFcn(app, idxThread, idxEmission)
                plot_Draw(app, idxThread, idxEmission)

                app.progressDialog.Visible = 'hidden';
            end
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end

        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            % O menu gráfico controla, programaticamente, qual das abas de
            % app.TabGroup estará visível. 

            % Lembrando que o MATLAB renderiza em tela apenas as abas visíveis.
            % Por isso as customizações de abas e suas subabas somente é possível 
            % após a renderização da aba.

            switch tabIndex
                case 0 % STARTUP
                    app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                           'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                               '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                               '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));
        
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                           'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));
                    
                    ccTools.compCustomizationV2(app.jsBackDoor, app.ControlTabGroup, 'transparentHeader', 'transparent')                    
                    ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'borderBottomLeftRadius', '5px', 'borderBottomRightRadius', '5px')

                otherwise
                    if any(app.jsBackDoorFlag{tabIndex})
                        app.jsBackDoorFlag{tabIndex} = false;

                        switch tabIndex
                            case 1 % ASPECTOS GERAIS
                                % ...            
                            case 2 % FILTRO
                                % ...
                            case 3 % PONTOS DE INTERESSE
                                % ...
                            case 4 % LAYOUT
                                % ...
                        end
                    end
            end
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            app.tempBandObj = class.Band('appAnalise:DRIVETEST', app);
            app.General.Plot.Waterfall.Fcn = 'image';
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app, idxThread, idxEmission)
            % Inicialização de botões do toolbar do eixo, e de edição das 
            % características do canal:
            app.axesTool_DataTip.UserData = false;
            app.general_chEdit.UserData   = false;

            % Lista as emissões:
            checkListOfEmissions(app, idxThread, idxEmission)
            
            % Painel de pontos de interesse:
            app.Tab3_Grid.RowHeight{3} = 118;
            points_addTypePanelSelectionChanged(app)

            % Configurações:
            app.config_chROIColor.Value     = app.General.Plot.ChannelROI.Color;
            app.config_chROIEdgeAlpha.Value = app.General.Plot.ChannelROI.EdgeAlpha;
            app.config_chROIFaceAlpha.Value = app.General.Plot.ChannelROI.FaceAlpha;
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            hParent     = tiledlayout(app.plotPanel, 16, 16, "Padding", "none", "TileSpacing", "none");

            % Eixo geográfico: MAPA
            app.UIAxes1 = plot.axes.Creation(hParent, 'Geographic', {'Basemap', app.config_Basemap.Value, ...
                                                                     'UserData', struct('CLimMode', 'auto', 'Colormap', '', 'PlotMode', 'distortion')});
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [16, 12];

            set(app.UIAxes1.LatitudeAxis,  'TickLabels', {}, 'Color', 'none')
            set(app.UIAxes1.LongitudeAxis, 'TickLabels', {}, 'Color', 'none')
            geolimits(app.UIAxes1, 'auto')
            plot.axes.Colormap(app.UIAxes1, app.config_Colormap.Value)

            % Eixo cartesiano: ESPECTRO
            app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'XColor', 'white', 'XGrid', 1, 'XMinorGrid', 0, 'XTick', {}, 'XTickLabel', {}, ...
                                                                    'YColor', 'white', 'YGrid', 0, 'YMinorGrid', 0, 'YTick', {},                   ...
                                                                    'Layer', 'top', 'GridLineStyle', '-.', 'TickDir', 'none',                      ...
                                                                    'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes2.Layout.Tile = 13;
            app.UIAxes2.Layout.TileSpan = [4, 4];

            % Eixo cartesiano: WATERFALL
            app.UIAxes3 = plot.axes.Creation(hParent, 'Cartesian', {'XColor', 'white', 'XGrid', 1, 'XMinorGrid', 0, 'XTick', {}, 'XTickLabel', {}, ...
                                                                    'YColor', 'white', 'YGrid', 1, 'YMinorGrid', 0, 'YTick', {}, 'YTickLabel', {}, ...
                                                                    'Layer', 'top', 'GridLineStyle', '-.', 'TickDir', 'in',                        ...
                                                                    'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes3.Layout.Tile = 77;
            app.UIAxes3.Layout.TileSpan = [12, 4];
            app.UIAxes3.XAxis.TickLabelRotation = 0;

            % Eixo cartesiano: POTÊNCIA DO CANAL
            app.UIAxes4 = plot.axes.Creation(hParent, 'Cartesian', {'XColor', 'white', 'XGrid', 1, 'XMinorGrid', 0,              ...
                                                                    'YColor', 'white', 'YGrid', 0, 'YMinorGrid', 0, 'YTick', {}, ...
                                                                    'Layer', 'top', 'GridLineStyle', '-.', 'TickDir', 'both', 'Color', 'none'});
            app.UIAxes4.Layout.Tile = 80;
            app.UIAxes4.Layout.TileSpan = [12, 1];
            app.UIAxes4.View = [270, 90];
            app.UIAxes4.YAxis.Direction = "reverse";

            % Colorbar
            colorBar = colorbar(app.UIAxes1, "Location", "layout", "TickDirection", "none", "HitTest", "off", "PickableParts", "none", "FontSize", 7, "Color", "white", 'AxisLocation', 'in', 'Box', 'off');
            colorBar.Layout.Tile = 220;
            colorBar.Layout.TileSpan = [2,1];

            % Interações:
            linkaxes([app.UIAxes2, app.UIAxes3], 'x')
            plot.axes.Interactivity.DefaultCreation(app.UIAxes1, [dataTipInteraction, zoomInteraction, panInteraction])
            plot.axes.Interactivity.DefaultCreation(app.UIAxes2, dataTipInteraction)
        end

        %-----------------------------------------------------------------%
        % ÍNDICES DO FLUXO ESPECTRAL E DA EMISSÃO
        %-----------------------------------------------------------------%
        function [idxThread, idxEmission] = specDataIndex(app, operationType)
            arguments
                app 
                operationType char {mustBeMember(operationType, {'EmissionSelectedChanged', 'EmissionShowed'})} = 'EmissionShowed'
            end

            % É salva na propriedade "UserData" de app.general_emissionInfo a
            % informação relacionada ao fluxo espectral e a emissão sob análise.

            % emissionID = struct('Thread',   struct('Index',     idx,                                                     ...
            %                                        'UUID',      {specData(idx).RelatedFiles.uuid}),                      ...
            %                     'Emission', struct('Index',     idxEmission,                                             ...
            %                                        'Frequency', specData(idx).UserData.Emissions.Frequency(idxEmission), ...
            %                                        'BW',        specData(idx).UserData.Emissions.BW(idxEmission),        ...
            %                                        'Tag',       emissionTag));

            try
                emissionID  = app.general_emissionInfo.UserData;

                % Inicialmente, busca-se o fluxo espectral (referenciado 
                % unicamente pela lista UUID dos arquivos brutos).
                threadUUID  = emissionID.Thread.UUID;
                listOfAllThreadsUUID = arrayfun(@(x) x.RelatedFiles.uuid, app.specData, 'UniformOutput', false);
                
                idxThread   = find(cellfun(@(x) isequal(x, threadUUID), listOfAllThreadsUUID), 1);
                idxEmission = [];
    
                % Caso seja identificado o fluxo espectral, busca na lista
                % de emissões à emissão sob análise.
                if ~isempty(idxThread)
                    switch operationType
                        case 'EmissionSelectedChanged'
                        newEmissionTag = app.general_emissionList.Value;
                        emissionTable  = app.specData(idxThread).UserData.Emissions;

                        for ii = 1:height(emissionTable)
                            if strcmp(newEmissionTag, sprintf('%.3f MHz ⌂ %.1f kHz', emissionTable.Frequency(ii), emissionTable.BW(ii)))
                                idxEmission = ii;
                                break
                            end
                        end

                        case 'EmissionShowed'
                            emissionFrequency = emissionID.Emission.Frequency;
                            emissionBW        = emissionID.Emission.BW;
            
                            idxEmission = find(abs(app.specData(idxThread).UserData.Emissions.Frequency - emissionFrequency) <= class.Constants.floatDiffTolerance & ...
                                               abs(app.specData(idxThread).UserData.Emissions.BW        - emissionBW)        <= class.Constants.floatDiffTolerance, 1);
                    end
                end
    
                if isempty(idxThread)
                    error('DriveTest:specDataIndex:ThreadNotFound',   'O fluxo espectral sob análise foi editado ou excluído.')
                elseif isempty(idxEmission)
                    error('DriveTest:specDataIndex:EmissionNotFound', 'A emissão sob análise foi editada ou excluída.')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', getReport(ME));
                pause(3)
                closeFcn(app)
            end
        end

        %-----------------------------------------------------------------%
        function checkListOfEmissions(app, idxThread, idxEmission)
            if isempty(app.specData(idxThread).UserData.Emissions)
                closeFcn(app)
                return
            end

            emissionTable = app.specData(idxThread).UserData.Emissions;
            app.general_emissionList.Items = {};
            for ii = 1:height(emissionTable)
                app.general_emissionList.Items{end+1} = sprintf('%.3f MHz ⌂ %.1f kHz', emissionTable.Frequency(ii), emissionTable.BW(ii));
            end
            app.general_emissionList.Value = app.general_emissionList.Items{idxEmission};
        end

        %-----------------------------------------------------------------%
        function checkChannelAssigned(app, idxThread, idxEmission)
            chAssigned = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).ChannelAssigned;
            if isempty(chAssigned)
                chAssigned = auxApp.drivetest.getChannel(app.specData, idxThread, idxEmission, app.channelObj);
            end

            app.general_chFrequency.Value = chAssigned.Frequency;
            app.general_chBW.Value        = chAssigned.ChannelBW;

            checkFixedScreenSpanLimits(app)
        end

        %-----------------------------------------------------------------%
        function dataSource = checkDataSource(app)
            switch app.plotSource.SelectedObject
                case app.plotSource_specFilteredTable
                    if isempty(app.filterTable)
                        dataSource = 'Raw';
                    else
                        dataSource = 'Filtered';
                    end
                case app.plotSource_specBinTable
                    dataSource = 'Data-Binning';
            end
        end

        %-----------------------------------------------------------------%
        function checkFixedScreenSpanLimits(app)
            if strcmp(app.config_BandGuardType.Value, 'Fixed')
                chBW        = app.general_chBW.Value; % kHz
                chBWLimits = chBW * app.config_BandGuardBWRelatedValue.Limits;
    
                if app.config_BandGuardFixedValue.Value < chBWLimits(1)
                    app.config_BandGuardFixedValue.Value = chBWLimits(1);
                elseif app.config_BandGuardFixedValue.Value > chBWLimits(2)
                    app.config_BandGuardFixedValue.Value = chBWLimits(2);
                end
            end
        end

        %-----------------------------------------------------------------%
        function screenSpanValue = getFrequencyScreenSpanInMHz(app, requiredData)
            arguments
                app
                requiredData char {mustBeMember(requiredData, {'Screen', 'ScreenLimits', 'ScreenMaxLimits'})}
            end

            chFrequency = app.general_chFrequency.Value; % MHz
            chBW        = app.general_chBW.Value / 1000; % kHz >> MHz
            maxFactor   = app.config_BandGuardBWRelatedValue.Limits(2);

            switch app.config_BandGuardType.Value
                case 'Fixed'
                    screenSpan = app.config_BandGuardFixedValue.Value / 1000;     % kHz >> MHz
                case 'BWRelated'
                    screenSpan = app.config_BandGuardBWRelatedValue.Value * chBW; % MHz
            end

            screenSpanLimits    = [chFrequency - screenSpan/2,     chFrequency + screenSpan/2];
            screenSpanMaxLimits = [chFrequency - maxFactor*chBW/2, chFrequency + maxFactor*chBW/2];

            switch requiredData
                case 'Screen'
                    screenSpanValue = screenSpan;
                case 'ScreenLimits'
                    screenSpanValue = screenSpanLimits;
                case 'ScreenMaxLimits'
                    screenSpanValue = screenSpanMaxLimits;
            end
        end

        %-----------------------------------------------------------------%
        function updateCustomProperty(app, idxThread, idxEmission, updateType)
            arguments
                app
                idxThread
                idxEmission
                updateType char {mustBeMember(updateType, {'ChannelAssigned', 'DriveTest'})} = 'DriveTest'
            end

            switch updateType
                case 'ChannelAssigned'
                    chAssigned = struct('Frequency', app.general_chFrequency.Value, ...
                                        'ChannelBW', app.general_chBW.Value);
                    app.specData(idxThread).UserData.Emissions.UserData(idxEmission).ChannelAssigned = chAssigned;
                
                case 'DriveTest'
                    if app.general_ReportFlag.Value
                        app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest = createPlotParameters(app);
                    else
                        app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest = [];
                    end
            end
        end

        %-----------------------------------------------------------------%
        function processingSpecRawTable(app, idxThread, idxEmission)
            [app.specRawTable,      ...
             app.specFilteredTable, ...
             app.specBinTable,      ...
             app.filterTable,       ...
             app.filter_Summary.UserData] = RF.DataBinning.execute(app.specRawTable,                   ...
                                                                   app.filter_DataBinningLength.Value, ...
                                                                   app.filter_DataBinningFcn.Value,    ...
                                                                   app.filterTable);
            updateCustomProperty(app, idxThread, idxEmission)
        end

        %-----------------------------------------------------------------%
        function Parameters = createPlotParameters(app)
            for ii = 1:height(app.filterTable)
                app.filterTable.roi(ii).specification = filter_roiSpecification(app, app.filterTable.subtype{ii}, app.filterTable.roi(ii).handle);
            end

            Parameters = struct('Source',                checkDataSource(app),               ...
                                'specRawTable',          app.specRawTable,                   ...
                                'specFilteredTable',     app.specFilteredTable,              ...
                                'specBinTable',          app.specBinTable,                   ...
                                'filterTable',           app.filterTable,                    ...
                                'pointsTable',           app.pointsTable,                    ...
                                'plotType',              app.UIAxes1.UserData.PlotMode,      ...
                                'plotSize',              app.axesTool_PlotSize.Value,        ...
                                'binning_Value',         app.filter_DataBinningLength.Value, ...
                                'binning_Fcn',           app.filter_DataBinningFcn.Value,    ...
                                'route_LineStyle',       app.config_route_LineStyle.Value,   ...
                                'route_OutColor',        app.config_route_OutColor.Value,    ...
                                'route_InColor',         app.config_route_InColor.Value,     ...
                                'route_MarkerSize',      app.config_route_Size.Value,        ...
                                'Colormap',              app.config_Colormap.Value,          ...
                                'points_Marker',         app.config_points_LineStyle.Value,  ...
                                'points_Color',          app.config_points_Color.Value,      ...
                                'points_Size',           app.config_points_Size.Value,       ...                                
                                'Basemap',               app.UIAxes1.Basemap);

        end

        %-----------------------------------------------------------------%
        % PLOT
        %-----------------------------------------------------------------%
        function prePlot_startupFcn(app, idxThread, idxEmission)
            % Inicialmente, a informação acerca do fluxo espectral e da emissão 
            % sob análise são salvas na propriedade "UserData" de app.general_emissionInfo.
            [htmlContent, emissionID] = auxApp.drivetest.htmlCode_EmissionInfo(app.specData, idxThread, idxEmission);
            set(app.general_emissionInfo, 'HTMLSource', htmlContent, 'UserData', emissionID)

            % Atualiza canal, bloqueando edição de informação do canal.
            checkChannelAssigned(app, idxThread, idxEmission)
            if app.general_chEdit.UserData
                general_chEditImageClicked(app)
            end
            
            channelTag = sprintf('%.3f MHz ⌂ %.1f kHz', app.general_chFrequency.Value, app.general_chBW.Value);
            app.plotFootnote.Text{2} = channelTag;

            % Atualiza valores customizados do plot, caso habilitado.
            prePlot_customProperties(app, idxThread, idxEmission)

            % O objeto app.tempBandObj armazena propriedades de app.specData(idxThreads) 
            % que simplifica o processo do plot, em especial na passagem de 
            % argumentos para as funções plot.draw2D e plot.draw3D.
            GuardBand  = struct('Mode', 'manual', 'Parameters', struct('Type', 'Fixed', 'Value', getFrequencyScreenSpanInMHz(app, 'Screen')));
            axesLimits = update(app.tempBandObj, idxThread, idxEmission, GuardBand);
            prePlot_restartProperties(app, axesLimits)
            
            % Afere-se, então, a potência do canal para cada uma das varreduras, 
            % criando a tabela app.specTable com as colunas "Timestamp", "Latitude", 
            % "Longitude" e "emissionPower".
            chAssigned  = struct('Frequency', app.general_chFrequency.Value, ...
                                 'ChannelBW', app.general_chBW.Value);
            app.specRawTable = RF.DataBinning.RawTableCreation(app.specData, idxThread, chAssigned);

            try
                processingSpecRawTable(app, idxThread, idxEmission)
            catch ME
                % O erro aqui é controlado, e esperado apenas se após a aplicação
                % do filtro aos dados, restar no máximo uma amostra. Neste caso,
                % exclui-se o filtro, redesenhando-se a árvore.
                filter_TreeBuilding(app)
            end       
        end

        %-----------------------------------------------------------------%
        function prePlot_restartProperties(app, axesLimits)
            cla([app.UIAxes1, app.UIAxes4])

            app.UIAxes1.UserData.CLimMode = 'auto';
            app.UIAxes3.UserData.CLimMode = 'auto';
            app.General.Plot.Waterfall.LevelLimits = [0, 0];

            app.restoreView(1) = struct('ID', 'app.UIAxes1', 'xLim', [],              'yLim', [],                   'cLim', 'auto');          % Eixo geográfico
            app.restoreView(2) = struct('ID', 'app.UIAxes2', 'xLim', axesLimits.xLim, 'yLim', axesLimits.yLevelLim, 'cLim', 'auto');          % Eixo cartesiano (Espectro)
            app.restoreView(3) = struct('ID', 'app.UIAxes3', 'xLim', axesLimits.xLim, 'yLim', [],                   'cLim', axesLimits.cLim); % Eixo cartesiano (Waterfall)
            app.restoreView(4) = struct('ID', 'app.UIAxes4', 'xLim', [],              'yLim', [],                   'cLim', 'auto');          % Eixo cartesiano (Potência do canal)
        
            app.hCar = [];
            app.hTimeline = [];
        end

        %-----------------------------------------------------------------%
        function prePlot_customProperties(app, idxThread, idxEmission)

            % AINDA PENDENTE DE REVISÃO A PARTE RELACIONADA AOS FILTROS E
            % PONTOS.

            if ~isempty(app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest)
                app.general_ReportFlag.Value = 1;

                % Se tiver algum ROI em tela, exclui-se...
                if ~isempty(app.filterTable)
                    arrayfun(@(x) delete(x), [app.filterTable.roi.handle]);
                end

                app.filterTable                    = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.filterTable;
                app.pointsTable                    = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.pointsTable;
                app.filter_DataBinningLength.Value = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.binning_Value;
                app.filter_DataBinningFcn.Value    = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.binning_Fcn;
                
                app.config_route_LineStyle.Value   = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.route_LineStyle;
                app.config_route_OutColor.Value    = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.route_OutColor;
                app.config_route_InColor.Value     = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.route_InColor;
                app.config_route_Size.Value        = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.route_MarkerSize;
                
                app.config_points_LineStyle.Value  = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.points_Marker;
                app.config_points_Color.Value      = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.points_Color;
                app.config_points_Size.Value       = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.points_Size;
                
                app.axesTool_PlotSize.Value        = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.plotSize;
                app.config_Colormap.Value          = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.Colormap;
                plot.axes.Colormap(app.UIAxes1, app.config_Colormap.Value)
                app.UIAxes1.Basemap                = app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.Basemap;                

                switch app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.Source
                    case {'Raw', 'Filtered'}
                        app.plotSource_specFilteredTable.Value = 1;
                    case 'Data-Binning'
                        app.plotSource_specBinTable.Value      = 1;
                end

                switch app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.plotType
                    case 'distortion'
                        app.axesTool_DistortionPlot.ImageSource = 'DriveTestDistortion_32.png';
                    case 'density'
                        app.axesTool_DistortionPlot.ImageSource = 'DriveTestDensity_32.png';
                end

                points_TreeBuilding(app)

                % Cria ROIs relacionados aos filtros, caso existentes.
                if ~isempty(app.filterTable)
                    filter_ROISelectionChanged(app)

                    for ii = 1:height(app.filterTable)
                        switch app.filterTable.subtype{ii}
                            case 'Threshold'
                                hAxes = app.UIAxes4;
                            otherwise
                                hAxes = app.UIAxes1;
                        end
                        hROI = plot.ROI(app.filterTable.subtype{ii}, hAxes, {'Tag', 'FilterROI'});

                        fieldsList = fields(app.filterTable.roi(ii).specification);
                        for jj = 1:numel(fieldsList)
                            hROI.(fieldsList{jj}) = app.filterTable.roi(ii).specification.(fieldsList{jj});
                        end
                        plot_AddListener2ROI(app, hROI)
                        app.filterTable.roi(ii).handle = hROI;
                    end
                end

            else
                app.general_ReportFlag.Value = 0;

                idxFindPeaks = find(strcmp(app.pointsTable.type, 'FindPeaks'));
                if ~isempty(idxFindPeaks)
                    app.pointsTable(idxFindPeaks,:) = [];
                    points_TreeBuilding(app)
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_Draw(app, idxThread, idxEmission)
            % No processo de inicialização do PLOT, que ocorre toda vez que é
            % alterado o fluxo espectral selecionado, na árvore principal do
            % appAnalise, os eixos são limpos e os handles dos principais
            % componentes do plot são apagados. app.hClearWrite vazio significa 
            % que deverá ser desenhado um novo PLOT. Por outro lado, caso não 
            % seja vazio, então o PLOT deverá ser atualizado.
            if isempty(app.UIAxes1.Children)
                plot_updateGeographicAxes(app)
            end
        
            if isempty(app.hClearWrite)
                % % prePLOT
                chFrequency = app.general_chFrequency.Value;
                chBW        = app.general_chBW.Value;                
                set(app.UIAxes2, 'XLim', app.restoreView(2).xLim, 'YLim', app.restoreView(2).yLim, 'XTick', chFrequency)
                set(app.UIAxes3, 'XTick', chFrequency, 'CLim', app.restoreView(3).cLim)

                % % PLOT                
                % (b) ClearWrite/Persistance
                app.hClearWrite = plot.draw2D.OrdinaryLine(app.UIAxes2, app.tempBandObj, idxThread, 'ClearWrite');
                plot.datatip.Template(app.hClearWrite, "Frequency+Level", app.tempBandObj.LevelUnit)
                app.hPersistanceObj = plot.draw3D.Persistance('Creation', app.hPersistanceObj, app.UIAxes2, app.tempBandObj, idxThread);

                % (c) Waterfall, Timeline and car position
                plot.draw3D.Waterfall('Creation', app.UIAxes3, app.tempBandObj, idxThread);
                app.hTimeline = line(app.UIAxes3, getFrequencyScreenSpanInMHz(app, 'ScreenMaxLimits'), [app.idxTime, app.idxTime], 'Color', 'red', 'Tag', 'Timeline');

                % (d) ChannelPower
                plot_ChannelPower(app)

                % (e) ChannelROI
                srcROITable = table(chFrequency, chBW, 'VariableNames', {'Frequency', 'BW'});
                plot.draw2D.rectangularROI(app.UIAxes2, app.tempBandObj, srcROITable, 1, 'ChannelROI', {'InteractionsAllowed', 'none'})
                plot.draw2D.rectangularROI(app.UIAxes3, app.tempBandObj, srcROITable, 1, 'ChannelROI', {'InteractionsAllowed', 'none'})

                % % postPLOT
                app.UIAxes3.YTick = app.UIAxes4.XTick;

                app.restoreView(1).xLim = app.UIAxes1.LatitudeLimits;
                app.restoreView(1).yLim = app.UIAxes1.LongitudeLimits;
                app.restoreView(3).yLim = app.UIAxes4.YLim;
                app.restoreView(4).xLim = app.UIAxes4.XLim;
                app.restoreView(4).yLim = app.UIAxes4.YLim;

                plot.axes.StackingOrder.execute(app.UIAxes1, app.tempBandObj.Context)
                plot.axes.StackingOrder.execute(app.UIAxes2, app.tempBandObj.Context)
                plot.axes.StackingOrder.execute(app.UIAxes3, app.tempBandObj.Context)
        
            else
                if app.plotFlag
                    % % PLOT UPDATE - "EmissionShowed"
                    % (a) ClearWrite
                    plot.draw2D.OrdinaryLineUpdate(app.hClearWrite, app.tempBandObj, idxThread, 'ClearWrite')
                    
                    % (b) Timeline and car position
                    app.hTimeline.YData = [app.idxTime, app.idxTime];
                    set(app.hCar, 'LatitudeData', app.specRawTable.Latitude(app.idxTime), 'LongitudeData', app.specRawTable.Longitude(app.idxTime))

                else
                    % % PLOT UPDATE - "EmissionSelectionChanged"
                    % (a) Route+ChannelPower
                    plot.old_axesDraw.geographicAxes_type1(app.UIAxes1, createPlotParameters(app), 'auxApp.winDriveTest')
                    plot_Car(app)

                    % (b) Timeline and car position
                    app.hTimeline = line(app.UIAxes3, getFrequencyScreenSpanInMHz(app, 'ScreenMaxLimits'), [app.idxTime, app.idxTime], 'Color', 'red', 'Tag', 'Timeline');

                    % (c) ChannelPower
                    plot_ChannelPower(app)

                    % (d) ChannelROI
                    chFrequency  = app.general_chFrequency.Value;
                    chBW         = app.general_chBW.Value;
                    
                    hChannelROI1 = findobj(app.UIAxes2.Children, 'Tag', 'ChannelROI');
                    hChannelROI2 = findobj(app.UIAxes3.Children, 'Tag', 'ChannelROI');
                    hChannelROI1.Position([1,3]) = [chFrequency-chBW/2000, chBW/1000];
                    hChannelROI2.Position([1,3]) = [chFrequency-chBW/2000, chBW/1000];

                    % % postPLOT
                    set(app.UIAxes2, 'XLim', app.restoreView(2).xLim, 'XTick', chFrequency)                    
                    set(app.UIAxes3, 'XTick', chFrequency, 'YTick', app.UIAxes4.XTick, 'CLim', app.restoreView(3).cLim)

                    app.restoreView(1).xLim = app.UIAxes1.LatitudeLimits;
                    app.restoreView(1).yLim = app.UIAxes1.LongitudeLimits;
                    app.restoreView(3).yLim = app.UIAxes4.YLim;
                    app.restoreView(4).xLim = app.UIAxes4.XLim;
                    app.restoreView(4).yLim = app.UIAxes4.YLim;
                end
            end
            drawnow
        end

        %-----------------------------------------------------------------%
        function plot_Car(app)
            delete(app.hCar)

            app.hCar = geoscatter(app.UIAxes1, app.specRawTable.Latitude(app.idxTime), app.specRawTable.Longitude(app.idxTime), 'filled',              ...
                                  'Marker', app.config_Car_LineStyle.Value, 'MarkerFaceColor', app.config_Car_Color.Value, 'MarkerEdgeColor', 'black', ...
                                  'SizeData', 10*app.config_Car_Size.Value, 'PickableParts', 'none', 'Tag', 'Car');
        end

        %-----------------------------------------------------------------%
        function plot_ChannelPower(app)
            delete(findobj(app.UIAxes4.Children, '-not', 'Tag', 'FilterROI'))

            [minY, maxY] = bounds(app.specRawTable.ChannelPower);
            set(app.UIAxes4, 'XLim', [1, height(app.specRawTable)+.001], 'YLim', [minY, maxY+.001])

            chPowerColor = app.UIAxes3.Colormap(end,:);
            app.config_chPowerColor.Value = chPowerColor;

            chPowerLine  = area(app.UIAxes4, app.specRawTable.ChannelPower, minY, 'EdgeAlpha', app.config_chPowerEdgeAlpha.Value, ...
                                                                                  'FaceAlpha', app.config_chPowerFaceAlpha.Value, ...
                                                                                  'FaceColor', chPowerColor,                      ...
                                                                                  'EdgeColor', chPowerColor,                      ...
                                                                                  'Tag', 'ChannelPower');
            plot.datatip.Template(chPowerLine, 'SweepID+ChannelPower')
        end

        %-----------------------------------------------------------------%
        % PLAYBACK
        %-----------------------------------------------------------------%
        function plot_mainLoop(app, idxThread, idxEmission, nSweeps)
            app.tool_Play.ImageSource = 'stop_32.png';

            while app.idxTime <= nSweeps
                % A variável app.plotFlag pode assumir os valores -1 | 0 | 1.
                % - -1: alteração de fluxo espectral
                % -  0: finalização do plot
                % -  1: atualização do plot
                switch app.plotFlag
                    case -1; [idxThread, idxEmission, nSweeps] = plot_updateIndex(app);
                    case  0; break
                end
                sweepTic = tic;
                
                plot_Draw(app, idxThread, idxEmission)
                app.tool_TimestampLabel.Text   = sprintf('%d de %d\n%s', app.idxTime, nSweeps, app.specData(idxThread).Data{1}(app.idxTime));
                app.tool_TimestampSlider.Value = 100 * app.idxTime/nSweeps;
                
                pause(max(app.CallingApp.play_MinPlotTime.Value/1000-toc(sweepTic), .001))
                
                % Reload Flag
                if app.idxTime == nSweeps
                    if app.tool_LoopControl.Tag == "loop"
                        app.idxTime = 1;
                    else
                        break
                    end
                else
                    app.idxTime = app.idxTime+1;
                end                
            end
            
            app.tool_Play.ImageSource = 'play_32.png';
        end

        %-----------------------------------------------------------------%
        function [idxThread, idxEmission, nSweeps] = plot_updateIndex(app)
            [idxThread, idxEmission] = specDataIndex(app, 'EmissionSelectedChanged');
            nSweeps = numel(app.specData(idxThread).Data{1});
            
            prePlot_startupFcn(app, idxThread, idxEmission)
            plot_Draw(app, idxThread, idxEmission)

            app.plotFlag = 1;
        end

        %-----------------------------------------------------------------%
        function plot_updateGeographicAxes(app)
            plot.old_axesDraw.geographicAxes_type1(app.UIAxes1, createPlotParameters(app), 'auxApp.winDriveTest')
            plot_Car(app)
        end

        %-----------------------------------------------------------------%
        % ASPECTOS AINDA NÃO REVISADOS
        % # FILTER
        % # POINTS
        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            if ~isempty(app.filterTable)
                checkedNodes = [];
                for ii = 1:height(app.filterTable)
                    nodeText = sprintf('%s:%s', app.filterTable.type{ii}, app.filterTable.subtype{ii});
                    treeNode = uitreenode(app.filter_Tree, 'Text', nodeText, 'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                end
                app.filter_Tree.CheckedNodes = checkedNodes;

                set(app.filter_Tree, 'SelectedNodes', app.filter_Tree.Children(end), 'Enable', 1)
                filter_TreeSelectionChanged(app)

                set(app.filter_ContextMenu.Children, Enable=1)
            else
                app.filter_Tree.Enable = 1;
                set(app.filter_ContextMenu.Children, Enable=0)
            end
        end

        %-----------------------------------------------------------------%
        function filter_updateInfo(app, idxThread, idxEmission)
            % d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            % try
            if nargin == 1
                [idxThread, idxEmission] = specDataIndex(app);
            end

                [app.specRawTable,   ...
                 app.specFilteredTable,   ...
                 app.specBinTable,    ...
                 app.filterTable] = RF.DataBinning.execute(app.specRawTable, app.filter_DataBinningLength.Value, app.filter_DataBinningFcn.Value, app.filterTable);

                % plot_MainControl(app, idxThread)
                updateCustomProperty(app, idxThread, idxEmission)             

            % catch ME
            %     appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            % end

            % delete(d)
        end

        %-----------------------------------------------------------------%
        function specification = filter_roiSpecification(app, subtype, hROI)
            switch subtype
                case 'Threshold'; specification = struct('Position',  hROI.Position);
                case 'Circle';    specification = struct('Center',    hROI.Center,   'Radius',        hROI.Radius);
                case 'Rectangle'; specification = struct('Position',  hROI.Position, 'RotationAngle', hROI.RotationAngle);
                case 'Polygon';   specification = struct('Position',  hROI.Position);
            end
        end

        %-----------------------------------------------------------------%
        function points_TreeBuilding(app)
            if ~isempty(app.points_Tree.Children)
                delete(app.points_Tree.Children)
            end

            checkedTreeNodes = [];
            for ii = 1:height(app.pointsTable)
                treeNode = uitreenode(app.points_Tree, 'Text', sprintf('%s: %s', app.pointsTable.type{ii}, strjoin(string(app.pointsTable.value(ii).idx), ',')), ...
                                                       'NodeData', ii, 'ContextMenu', app.points_ContextMenu);
                if app.pointsTable.visible(ii)
                    checkedTreeNodes = [checkedTreeNodes, treeNode];
                end
            end
            app.points_Tree.CheckedNodes = checkedTreeNodes;

            if app.general_ReportFlag.Value
                [idxThread, idxEmission] = specDataIndex(app);
                app.specData(idxThread).UserData.Emissions.UserData(idxEmission).DriveTest.pointsTable = app.pointsTable;
            end
        end

        %-----------------------------------------------------------------%
        function points_TableCheck(app, newRow)
            status = true;
            for ii = 1:height(app.pointsTable)
                if isequal(newRow(1), app.pointsTable{ii,1}) && ...
                   isequal(newRow{2}, app.pointsTable{ii,2})
                    status = false;
                    break
                end
            end

            if status
                app.pointsTable(end+1,:) = newRow;
            else
                error('Registro já incluído!')
            end
        end

        %-----------------------------------------------------------------%
        function [idx, Coordinates] = points_FindPeaks(app, Source, NPeaks, MinDistance)
            idx = [];
            Coordinates = [];

            % switch Source
            %     case 'Dados brutos';                     sourceData = get(findobj(app.UIAxes4.Children, 'Tag', 'rawTable'),     'YData');
            %     case 'Dados processados (Data Binning)'; sourceData = get(findobj(app.UIAxes4.Children, 'Tag', 'binTable'),     'YData');
            %     case 'Curva suave';                      sourceData = get(findobj(app.UIAxes4.Children, 'Tag', 'SmoothedData'), 'YData');
            % end

            [~, pksIndex] = findpeaks(sourceData, 'SortStr', 'descend');

            if ~isempty(pksIndex)
                idx = pksIndex(1);

                for ii = 2:numel(pksIndex)
                    if numel(idx) >= NPeaks
                        break
                    end

                    switch Source
                        case {'Dados brutos', 'Curva suave'}
                            refCoordinates = app.specFilteredTable{idx, 1:2};
                            Coordinates    = app.specFilteredTable{pksIndex(ii), 1:2};    
                        
                        case 'Dados processados (Data Binning)'
                            refCoordinates = app.specBinTable{app.specFilteredTable.FK2(arrayfun(@(x) find(app.specFilteredTable.FK1 == x, 1), idx)), 1:2};
                            Coordinates    = app.specBinTable{app.specFilteredTable.FK2(arrayfun(@(x) find(app.specFilteredTable.FK1 == x, 1), pksIndex(ii))), 1:2};
                    end
    
                    tempDistance = deg2km(distance(refCoordinates, Coordinates(end,:))); % em km
                    if all(tempDistance >= MinDistance)
                        idx(end+1) = pksIndex(ii);
                    end
                end

                switch Source
                    case {'Dados brutos', 'Curva suave'};    Coordinates = app.specFilteredTable{idx, 1:2};    
                    case 'Dados processados (Data Binning)'; Coordinates = app.specBinTable{app.specFilteredTable.FK2(arrayfun(@(x) find(app.specFilteredTable.FK1 == x, 1), idx)), 1:2};
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_AddListener2ROI(app, hROI)
            addlistener(hROI, 'MovingROI',            @(~, evt)plot.axes.Interactivity.CustomROIInteractionFcn(evt, [app.UIAxes1, app.UIAxes4], []))
            addlistener(hROI, 'ROIMoved',             @(~, evt)plot.axes.Interactivity.CustomROIInteractionFcn(evt, [app.UIAxes1, app.UIAxes4], @app.filter_updateInfo))
            addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plot.axes.Interactivity.DeleteROIListeners(src));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idxThread, idxEmission)
            
            global RFDataHub

            app.CallingApp  = mainapp;
            app.General     = mainapp.General;
            app.rootFolder  = mainapp.rootFolder;
            app.rfDataHub   = RFDataHub;
            app.specData    = mainapp.specData;
            app.projectData = mainapp.projectData;
            app.channelObj  = mainapp.channelObj;

            appUtil.winPosition(app.UIFigure)
            jsBackDoor_Initialization(app)
            startup_timerCreation(app, idxThread, idxEmission)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWin2 = [];
            delete(app.timerObj)
            delete(app)
            
        end

        % Image clicked function: general_chEdit
        function general_chEditImageClicked(app, event)
            
            app.general_chEdit.UserData = ~app.general_chEdit.UserData;

            if app.general_chEdit.UserData
                app.general_chEdit.ImageSource   = 'Edit_32Filled.png';
                app.general_chFrequency.Editable = 1;
                app.general_chBW.Editable        = 1;
                focus(app.general_chBW)

            else
                app.general_chEdit.ImageSource   = 'Edit_32.png';
                app.general_chFrequency.Editable = 0;
                app.general_chBW.Editable        = 0;

                focus(app.jsBackDoor)
            end

        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 2 other components
        function general_LeftPanelSelectionChanged(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.menu_MainGrid.ColumnWidth   = columnWidth;
            app.menuUnderline.Layout.Column = idx;
            app.ControlTabGroup.SelectedTab = app.ControlTabGroup.Children(idx);

            jsBackDoor_Customizations(app, idx)
            focus(app.jsBackDoor)

        end

        % Image clicked function: tool_ControlPanelVisibility
        function tool_LeftPanelVisibilityImageClicked(app, event)
            
            focus(app.jsBackDoor)

            switch event.Source
                %---------------------------------------------------------%
                case app.tool_ControlPanelVisibility
                    if app.Grid.ColumnWidth{2}
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowRight_32.png';
                        app.Grid.ColumnWidth(2:3) = {0,0};
                    else
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                        app.Grid.ColumnWidth(2:3) = {325,10};
                    end

                %---------------------------------------------------------%
                % case app.tool_RefreshScreenSize
                %     app.UIFigure.Position(3:4) = [1244, 660];
                %     appUtil.winPosition(app.UIFigure)
            end

        end

        % Image clicked function: tool_LoopControl, tool_Play
        function tool_PlaybackControlImageClicked(app, event)
            
            switch event.Source
                case app.tool_Play
                    idxThread   = app.general_emissionInfo.UserData.Thread.Index;
                    idxEmission = app.general_emissionInfo.UserData.Emission.Index;
        
                    if idxThread && ~app.plotFlag
                        if app.CallingApp.plotFlag
                            app.CallingApp.plotFlag = 0;
                            app.CallingApp.tool_Play.ImageSource = 'play_32.png';
                            drawnow
                        end

                        app.plotFlag = 1;
                        plot_mainLoop(app, idxThread, idxEmission, numel(app.specData(idxThread).Data{1}))        
                    else
                        app.plotFlag = 0;
                    end

                %---------------------------------------------------------%
                case app.tool_LoopControl
                    switch app.tool_LoopControl.Tag
                        case 'loop';   set(app.tool_LoopControl, Tag='direct', ImageSource='playbackStraight_32Blue.png')
                        case 'direct'; set(app.tool_LoopControl, Tag='loop',   ImageSource='playbackLoop_32Blue.png')
                    end
            end

        end

        % Value changing function: tool_TimestampSlider
        function tool_TimelineSliderValueChanging(app, event)
            
            nSweeps = app.tempBandObj.nSweeps;
            
            app.idxTime = round(event.Value/100 * nSweeps);
            if app.idxTime < 1
                app.idxTime = 1;
            elseif app.idxTime > nSweeps
                app.idxTime = nSweeps;
            end

            if ~app.plotFlag
                idxThread = app.general_emissionInfo.UserData.Thread.Index;

                plot.draw2D.OrdinaryLineUpdate(app.hClearWrite, app.tempBandObj, idxThread, 'ClearWrite')
                app.hTimeline.YData = [app.idxTime, app.idxTime];
                set(app.hCar, 'LatitudeData', app.specRawTable.Latitude(app.idxTime), 'LongitudeData', app.specRawTable.Longitude(app.idxTime))

                app.tool_TimestampLabel.Text = sprintf('%d de %d\n%s', app.idxTime, nSweeps, app.specData(idxThread).Data{1}(app.idxTime));
            end

        end

        % Image clicked function: filter_DataBinningExport
        function tool_ExportFileButtonPushed(app, event)
            
            msgQuestion   = 'Deseja exportar os resultados da análise em arquivos XLSX e KML?';
            userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
            if userSelection == "Não"
                return
            end

            app.progressDialog.Visible = 'visible';

            Basename   = class.Constants.DefaultFileName(app.CallingApp.config_Folder_userPath.Value, 'Drive-test', app.CallingApp.report_Issue.Value);
            dataSource = checkDataSource(app);
            hPlot      = findobj(app.UIAxes1.Children, 'Tag', 'Distortion');
            channelTag = sprintf('%.3f MHz ⌂ %.1f kHz', app.general_chFrequency.Value, app.general_chBW.Value);

            try
                msgWarning = auxApp.drivetest.exportFiles(app.specRawTable, app.specFilteredTable, app.specBinTable, Basename, dataSource, hPlot, channelTag);
                appUtil.modalWindow(app.UIFigure, 'info', msgWarning);
            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: filter_Summary
        function tool_SummaryImageClicked(app, event)
            
            appUtil.modalWindow(app.UIFigure, 'info', app.filter_Summary.UserData);

        end

        % Image clicked function: axesTool_DataTip, axesTool_RegionZoom, 
        % ...and 1 other component
        function axesTool_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.axesTool_RestoreView
                    geolimits(app.UIAxes1, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.axesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes1, app.axesTool_RegionZoom)

                case app.axesTool_DataTip
                    app.axesTool_DataTip.UserData = ~app.axesTool_DataTip.UserData;
                    if app.axesTool_DataTip.UserData
                        app.axesTool_DataTip.ImageSource = 'DataTip_22Filled.png';
                    else
                        app.axesTool_DataTip.ImageSource = 'DataTip_22.png';
                    end

                    plot.axes.Interactivity.DataCursorMode(app.UIFigure, app.axesTool_DataTip.UserData)
            end

        end

        % Image clicked function: axesTool_DensityPlot, 
        % ...and 1 other component
        function axesTool_PlotTypeValueChanged(app, event)

            hDistortion = findobj(app.UIAxes1.Children, 'Tag', 'Distortion');
            hDensity    = findobj(app.UIAxes1.Children, 'Tag', 'Density');

            switch event.Source
                %---------------------------------------------------------%
                case app.axesTool_DistortionPlot
                    if hDistortion.Visible
                        app.UIAxes1.UserData.PlotMode = 'none';
                        hDistortion.Visible = 0;
                    else
                        app.UIAxes1.UserData.PlotMode = 'distortion';
                        hDensity.Visible = 0;
                        set(hDistortion, 'Visible', 1, 'SizeData', 20*app.axesTool_PlotSize.Value)                        
                    end

                %---------------------------------------------------------%
                case app.axesTool_DensityPlot
                    if hDensity.Visible
                        app.UIAxes1.UserData.PlotMode = 'none';
                        hDensity.Visible = 0;
                    else
                        app.UIAxes1.UserData.PlotMode = 'density';
                        hDistortion.Visible = 0;
                        set(hDensity, 'Visible', 1, 'Radius', 100*app.axesTool_PlotSize.Value)
                    end
            end

            % A atualização do colorbar só é renderizada em tela quando o
            % usuário faz alguma interação com o eixo... duas possibilidades 
            % para forçar a atualização:

            % (a) Trocar "LimitsMode" para "manual" e depois para "auto". A
            %     operação é concluída em cerca de 0.461 ms.
            % (b) Chamar o método "reset", reconfigurando o eixo. A operação
            %     é concluída em cerca de 16.601 ms.

            colorBar = findobj(app.UIAxes1.Parent.Children, 'Type', 'colorbar');
            colorBar.LimitsMode = 'manual';
            colorBar.LimitsMode = 'auto';
            
            [idxThread, idxEmission] = specDataIndex(app);
            updateCustomProperty(app, idxThread, idxEmission)

        end

        % Value changed function: axesTool_PlotSize
        function axesTool_PlotSizeValueChanged(app, event)

            % Como exposto em axesTool_PlotSizeValueChanging(app, event),
            % o plot de distorção será atualizado em "tempo real", no 
            % "ValueChangingFcn", mas o plot de densidade apenas no callback 
            % "ValueChangedFcn".

            hDensity = findobj(app.UIAxes1.Children, 'Tag', 'Density');

            if hDensity.Visible
                set(findobj(app.UIAxes1.Children, 'Tag', 'Density'), 'Radius',  100*event.Value)
                drawnow
            end

            [idxThread, idxEmission] = specDataIndex(app);
            updateCustomProperty(app, idxThread, idxEmission)
            
        end

        % Value changing function: axesTool_PlotSize
        function axesTool_PlotSizeValueChanging(app, event)
            
            % Ao interagir com o slider, não soltando o mouse, o MATLAB dispara 
            % apenas o evento "ValueChangingFcn". Ao soltar o botão do mouse, o 
            % MATLAB dispara, nesta ordem, os eventos "ValueChangingFcn" e 
            % "ValueChangedFcn".

            % Nesse contexto, considerando que o plot de densidade demora
            % para atualizar, o plot de distorção será atualizado em "tempo
            % real", no "ValueChangingFcn", mas o plot de densidade apenas 
            % no callback "ValueChangedFcn".

            hDistortion = findobj(app.UIAxes1.Children, 'Tag', 'Distortion');

            if hDistortion.Visible
                set(findobj(app.UIAxes1.Children, 'Tag', 'Distortion'), 'SizeData', 20*event.Value)
            end
            
        end

        % Callback function: config_Car_Color, config_points_Color, 
        % ...and 2 other components
        function config_geoAxesColorParameterChanged(app, event)
            
            selectedColor = event.Source.Value;
            % event.Source.BackgroundColor = selectedColor;

            switch event.Source
                case app.config_route_OutColor
                    set(findobj(app.UIAxes1.Children, 'Tag', 'OutRoute'), 'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                case app.config_route_InColor
                    set(findobj(app.UIAxes1.Children, 'Tag', 'InRoute'),  'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                case app.config_Car_Color
                    set(app.hCar, 'MarkerFaceColor', selectedColor)

                case app.config_points_Color
                    hPoints = findobj(app.UIAxes1.Children, 'Tag', 'Points');
                    for ii = 1:numel(hPoints)
                        if strcmp(hPoints(ii).MarkerFaceColor, 'none')
                            set(hPoints(ii), 'Color', selectedColor, 'MarkerEdgeColor', selectedColor)
                        else
                            set(hPoints(ii), 'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
                        end
                    end
            end

            [idxThread, idxEmission] = specDataIndex(app);
            updateCustomProperty(app, idxThread, idxEmission)
            
        end

        % Callback function: config_Basemap, config_Car_LineStyle, 
        % ...and 6 other components
        function config_geoAxesOthersParametersChanged(app, event)
            
            switch event.Source
                case app.config_Basemap
                    hColorbar = findobj(app.UIAxes1.Parent.Children, 'Type', 'colorbar');
                    switch app.config_Basemap.Value
                        case 'none'
                            set(app.UIAxes1, 'Basemap', app.config_Basemap.Value, 'Box', 'on', 'AxisColor', [.8,.8,.8])
                            hColorbar.Color = [.8,.8,.8];
                            app.Grid.ColumnWidth{4} = 0;
                            ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'backgroundColor', 'transparent')
                        otherwise
                            set(app.UIAxes1, 'Basemap', app.config_Basemap.Value, 'Box', 'off', 'AxisColor', 'white')
                            hColorbar.Color = 'white';
                            app.Grid.ColumnWidth{4} = 5;
                            ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'backgroundColor', '#ffffff')
                    end

                case app.config_Colormap
                    plot.axes.Colormap(app.UIAxes1, event.Value)

                case app.config_route_LineStyle
                    switch event.Value
                        case 'none'; markerSize = 1;
                        otherwise;   markerSize = 8*app.config_route_Size.Value;
                    end
                    set(findobj(app.UIAxes1.Children, 'Tag', 'OutRoute'),                          'MarkerSize', markerSize)
                    set(findobj(app.UIAxes1.Children, 'Tag', 'InRoute'), 'LineStyle', event.Value, 'MarkerSize', markerSize)

                case app.config_route_Size
                    if ~strcmp(app.config_route_LineStyle.Value, 'none')
                        set(findobj(app.UIAxes1.Children, 'Tag', 'OutRoute'), 'MarkerSize', 8*event.Value)
                        set(findobj(app.UIAxes1.Children, 'Tag', 'InRoute'),  'MarkerSize', 8*event.Value)
                    end

                case app.config_Car_LineStyle
                    set(app.hCar, 'Marker', event.Value)
                
                case app.config_Car_Size
                    set(app.hCar, 'SizeData', 10*event.Value)

                case app.config_points_LineStyle
                    set(findobj(app.UIAxes1.Children, 'Tag', 'Points'), 'Marker', event.Value)
                
                case app.config_points_Size
                    set(findobj(app.UIAxes1.Children, 'Tag', 'Points'), 'MarkerSize', event.Value)
            end

            [idxThread, idxEmission] = specDataIndex(app);
            updateCustomProperty(app, idxThread, idxEmission)

        end

        % Value changed function: config_PersistanceVisibility, 
        % ...and 8 other components
        function config_xyAxesParameterChanged(app, event)

            switch event.Source
                case app.config_PersistanceVisibility
                    hPersistance = findobj(app.UIAxes2.Children, 'Tag', 'Persistance');
                    hPersistance.Visible = app.config_PersistanceVisibility.Value;

                case app.config_chROIVisibility
                    hChannelROI = findobj([app.UIAxes2.Children; app.UIAxes3.Children], 'Tag', 'ChannelROI');
                    set(hChannelROI, 'Visible', app.config_chROIVisibility.Value)

                case {app.config_chROIColor, app.config_chROIEdgeAlpha, app.config_chROIFaceAlpha}                    
                    hChannelROI = findobj([app.UIAxes2.Children; app.UIAxes3.Children], 'Tag', 'ChannelROI');
                    set(hChannelROI, 'Color',     app.config_chROIColor.Value,     ...
                                     'EdgeAlpha', app.config_chROIEdgeAlpha.Value, ...
                                     'FaceAlpha', app.config_chROIFaceAlpha.Value)

                    app.General.Plot.ChannelROI.Color     = app.config_chROIColor.Value;
                    app.General.Plot.ChannelROI.EdgeAlpha = app.config_chROIEdgeAlpha.Value;
                    app.General.Plot.ChannelROI.FaceAlpha = app.config_chROIFaceAlpha.Value;

                case app.config_chPowerVisibility
                    set(findobj(app.UIAxes4), 'Visible', app.config_chPowerVisibility.Value)

                case {app.config_chPowerColor, app.config_chPowerEdgeAlpha, app.config_chPowerFaceAlpha}
                    hChannelROI = findobj(app.UIAxes4.Children, 'Tag', 'ChannelPower');
                    set(hChannelROI, 'EdgeColor', app.config_chPowerColor.Value,     ...
                                     'FaceColor', app.config_chPowerColor.Value,     ...
                                     'EdgeAlpha', app.config_chPowerEdgeAlpha.Value, ...
                                     'FaceAlpha', app.config_chPowerFaceAlpha.Value)
            end

            [idxThread, idxEmission] = specDataIndex(app);
            updateCustomProperty(app, idxThread, idxEmission)
            
        end

        % Image clicked function: config_Refresh
        function config_RefreshImageClicked(app, event)
            
            % Trava função do Waterfall em "image" para que seja possível
            % sincronizar os eixos app.UIAxes3.YAxes e app.UIAxes4.XAxis.
            app.General = app.CallingApp.General;
            app.General.Plot.Waterfall.Fcn = 'image';

            % Guia "Configurações gerais":
            app.config_Colormap.Value              = 'turbo';            
            app.config_route_LineStyle.Value       = 'none';
            app.config_route_OutColor.Value        = [.5,.5,.5];
            app.config_route_InColor.Value         = [.87,.54,.54];
            app.config_route_Size.Value            = 1;            
            app.config_Car_LineStyle.Value         = 'none';
            app.config_Car_Color.Value             = [1,0,0];
            app.config_Car_Size.Value              = 10;            
            app.config_points_LineStyle.Value      = 'none';
            app.config_points_Color.Value          = [0,0,0];
            app.config_points_Size.Value           = 9;            
            app.config_PersistanceVisibility.Value = 'on';
            app.config_chROIVisibility.Value       = 'on';
            app.config_chROIColor.Value            = app.General.Plot.ChannelROI.Color;
            app.config_chROIEdgeAlpha.Value        = 0;
            app.config_chROIFaceAlpha.Value        = .4;
            app.config_chPowerVisibility.Value     = 'on';
            app.config_chPowerColor.Value          = app.UIAxes3.Colormap(end,:);
            app.config_chPowerEdgeAlpha.Value      = .2;
            app.config_chPowerFaceAlpha.Value      = .4;

            % Plot...
            [idxThread, idxEmission] = specDataIndex(app, 'EmissionShowed');
            
            prePlot_startupFcn(app, idxThread, idxEmission)
            plot_Draw(app, idxThread, idxEmission)

        end

        % Value changed function: config_BandGuardBWRelatedValue, 
        % ...and 2 other components
        function config_BandGuardValueChanged(app, event)

            chBW = app.general_chBW.Value;

            switch event.Source
                case app.config_BandGuardType
                    switch app.config_BandGuardType.Value
                        case 'Fixed'
                            app.config_BandGuardValueLabel.Text = 'Span (kHz):';                            
                            set(app.config_BandGuardFixedValue, 'Visible', 1, 'Value', app.config_BandGuardBWRelatedValue.Value * chBW)
                            app.config_BandGuardBWRelatedValue.Visible = 0;                            

                        case 'BWRelated'
                            app.config_BandGuardValueLabel.Text    = 'Multiplicador';
                            app.config_BandGuardFixedValue.Visible = 0;
                            set(app.config_BandGuardBWRelatedValue, 'Visible', 1, 'Value', app.config_BandGuardFixedValue.Value / chBW)
                    end
                    config_BandGuardValueChanged(app, struct('Source', app.config_BandGuardBWRelatedValue))

                case {app.config_BandGuardFixedValue, app.config_BandGuardBWRelatedValue}
                    checkFixedScreenSpanLimits(app)

                    xLim = getFrequencyScreenSpanInMHz(app, 'ScreenLimits');
                    app.UIAxes2.XLim = xLim;
                    app.restoreView(2).xLim = xLim;
                    app.restoreView(3).xLim = xLim;
            end

        end

        % Image clicked function: general_emissionListRefresh
        function emission_RefreshListImageClicked(app, event)
            
            preSelection = app.general_emissionList.Value;

            [idxThread, idxEmission] = specDataIndex(app);
            checkListOfEmissions(app, idxThread, idxEmission)

            if ismember(preSelection, app.general_emissionList.Items)
                app.general_emissionList.Value = preSelection;
            else
                emission_SelectionChanged(app)
            end

        end

        % Value changed function: general_chBW, general_chFrequency, 
        % ...and 1 other component
        function emission_SelectionChanged(app, event)

            % Essa função é o TRIGGER principal para atualização do plot
            % deste app.

            % (a) É executado toda vez que for alterada a seleção de emissão,
            %     inclusive quando decorrente da atualização da lista de
            %     emissões.
            % (b) É executado toda vez que for alterado algum dos parâmetros
            %     relacionados ao canal - "Frequency" e "ChannelBW". Neste
            %     caso, registra-se essa informação em app.specData.

            if exist('event', 'var') && ismember(event.Source, [app.general_chFrequency, app.general_chBW])
                [idxThread, idxEmission] = specDataIndex(app, 'EmissionShowed');
                updateCustomProperty(app, idxThread, idxEmission, 'ChannelAssigned')
            end

            if app.plotFlag
                app.plotFlag = -1;
            else
                [idxThread, idxEmission] = specDataIndex(app, 'EmissionSelectedChanged');

                prePlot_startupFcn(app, idxThread, idxEmission)
                plot_Draw(app, idxThread, idxEmission)
            end
            
        end

        % Value changed function: general_ReportFlag
        function emission_ReportFlagCheckBoxClicked(app, event)

            [idxThread, idxEmission] = specDataIndex(app);
            updateCustomProperty(app, idxThread, idxEmission)

        end

        % Selection changed function: filter_Tree
        function filter_TreeSelectionChanged(app, event)
            
            if ~isempty(app.filter_Tree.SelectedNodes)
                idx = app.filter_Tree.SelectedNodes.NodeData;        
    
                for ii = 1:height(app.filterTable)
                    if ii ~= idx
                        set(app.filterTable.roi(ii).handle, 'InteractionsAllowed', 'none')
    
                    else
                        if app.filterTable.type == "Level"
                            set(app.filterTable.roi(ii).handle, 'InteractionsAllowed',  'translate')
                        else
                            set(app.filterTable.roi(ii).handle, 'InteractionsAllowed',  'all')
                        end                    
                    end
                end
                uistack(app.filterTable.roi(idx).handle, "top")
            end
            
        end

        % Image clicked function: filter_addImage
        function filter_addImageCallback(app, event)
            
            switch app.filter_RadioGroup.SelectedObject
                case app.filter_THR
                    Type = 'Level';
                    SubType = 'Threshold';                        
                    THR  = min(app.specFilteredTable.ChannelPower);
                    
                    set(app.UIAxes4, LineWidth=4, XColor=[1,0,0], YColor=[1,0,0])
                    hROI = images.roi.Line(app.UIAxes4, 'Position', [height(app.specFilteredTable) THR; 1 THR],  ...
                                                        'Color', 'red', 'MarkerSize', 4, 'LineWidth', 1, ...
                                                        'Deletable', 0, 'InteractionsAllowed', 'translate', 'Tag', 'FilterROI');
                    set(app.UIAxes4, LineWidth=1, XColor=[0,0,0], YColor=[0,0,0])

                case app.filter_Timestamp
                    Type = 'Timestamp';
                    SubType = 'Rectangle';
                    hROI = drawrectangle(app.UIAxes3, LineWidth=1, Deletable=0, FaceSelectable=0, Tag='FilterROI');

                case app.filter_Geographic
                    switch app.filter_GeographicType.Value
                        case 'Arquivo externo KML'
                          [fileName, filePath] = uigetfile('*.kml', '', app.General.fileFolder.lastVisited);
                            figure(app.UIFigure)
                
                            if isequal(fileName, 0)
                                return
                            end

                            try
                                KML  = readgeotable('Municipios_ES.kml', 'CoordinateSystemType', 'geographic');
                                hROI = drawpolygon(app.UIAxes1, LineWidth=1, Deletable=0, FaceSelectable=0, Tag="ROI");
                            catch ME
                                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
                                return
                            end

                        otherwise
                            switch app.filter_GeographicType.Value
                                case 'ROI:Círculo'
                                    Type    = 'Geographic ROI';
                                    SubType = 'Circle';
                                    roiFcn  = 'drawcircle';
                                    roiNameArgument = '';
        
                                case 'ROI:Retângulo'
                                    Type    = 'Geographic ROI';
                                    SubType = 'Rectangle';
                                    roiFcn  = 'drawrectangle';
                                    roiNameArgument = 'Rotatable=true, ';
        
                                case 'ROI:Polígono'
                                    Type    = 'Geographic ROI';
                                    SubType = 'Polygon';
                                    roiFcn  = 'drawpolygon';
                                    roiNameArgument = '';                            
                            end
                            eval(sprintf('hROI = %s(app.UIAxes1, LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI");', roiFcn, roiNameArgument))
                    end
                    
            end

            set(app.filter_Tree, 'SelectedNodes', [], 'Enable', 0)
            set(app.filter_ContextMenu.Children, 'Enable', 0)

            if isempty(hROI.Position)
                delete(hROI)
                
                app.filter_Tree.Enable = 1;
                if ~isempty(app.filter_Tree.Children)
                    app.filter_Tree.SelectedNodes = app.filter_Tree.Children(end);
                    filter_TreeSelectionChanged(app)
                end

                return
            end
            plot_AddListener2ROI(app, hROI)

            app.filterTable(end+1,:) = {Type, SubType, struct('handle', hROI, 'specification', filter_roiSpecification(app, SubType, hROI))};
            filter_TreeBuilding(app)
            filter_updateInfo(app)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_Tree.SelectedNodes)
                        return
                    end
                    idx = arrayfun(@(x) x.NodeData, app.filter_Tree.SelectedNodes);

                case app.filter_delAllButton
                    idx = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx)
                arrayfun(@(x) delete(x), [app.filterTable.roi(idx).handle]); 
                app.filterTable(idx,:) = [];

                filter_TreeBuilding(app)
                filter_updateInfo(app)
            end

        end

        % Selection changed function: points_addTypePanel
        function points_addTypePanelSelectionChanged(app, event)
            
            selectedButton = app.points_addTypePanel.SelectedObject;
            switch selectedButton
                case app.points_addRFDataHub;  app.points_addValueGrid.RowHeight = {17,22,22,22,0,0,0,0};
                case app.points_addFindPeaks;  app.points_addValueGrid.RowHeight = {0,0,0,0,17,22,22,22};
            end
            
        end

        % Image clicked function: points_addImage
        function points_addImageImageClicked(app, event)
            
            selectedButton = app.points_addTypePanel.SelectedObject;

            % try
                switch selectedButton
                    %-----------------------------------------------------%
                    case app.points_addRFDataHub
                        global RFDataHub
            
                        if isempty(char(app.points_addSubtype1TextArea.Value))
                            return
                        end
                        entryText = char(strjoin(app.points_addSubtype1TextArea.Value, ','));
            
                        switch app.points_addSubtype1DropDown.Value
                            case 'Índices de registros do RFDataHub'
                                idx = regexp(entryText, '#(\d+)', 'tokens');
                                idx = str2double([idx{:}]);
            
                            case 'Lista de frequências'
                                freqList = regexp(entryText, '(\d+.\d+)', 'tokens');
                                freqList = cellfun(@(x) str2double(x), [freqList{:}]);
            
                                filterTempTable = table('Size',          [0, 9],                                                                      ...
                                                        'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                                                        'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'});
            
                                for ii = 1:numel(freqList)
                                    if ii == 1; Order = 'Node';  RelatedID = -1;
                                    else;       Order = 'Child'; RelatedID = 1;
                                    end
                                    filterTempTable(ii,:) = {Order, ii, RelatedID, 'Frequência', '=', 1, {freqList(ii)}, true, ''};
                                end            
                                idx = find(fcn.TableFiltering(RFDataHub, filterTempTable));
                        end
        
                        if ~isempty(idx)
                            NN = numel(idx);
                            distanceArray = deg2km(distance(RFDataHub.Latitude(idx),   RFDataHub.Longitude(idx),  ...
                                                            app.specData(idxThread).GPS.Latitude, app.specData(idxThread).GPS.Longitude));
                            
                            idx(distanceArray > app.points_addSubtype1Distance.Value) = [];
                            MM = numel(idx);
                            if ~MM
                                error('Não identificada estação de telecomunicações que atenda às especificações...')
                            end
                            
                            newRow = {'RFDataHub', struct('source', app.points_addSubtype1DropDown.Value, 'idx', idx, 'Coordinates', RFDataHub{idx,6:7}), true};
                            points_TableCheck(app, newRow)
        
                            msg = sprintf('Identificada(s) %d estação(ões) de telecomunicações que atende(m) às especificações.', NN);
                            if MM ~= NN
                                msg = sprintf('%s Contudo, apenas %d atende(m) à distância máxima especificada.', msg, MM);
                            end
                        else
                            error('Não identificada estação de telecomunicações que atenda às especificações...')
                        end
                        appUtil.modalWindow(app.UIFigure, 'warning', msg);
    
                    %---------------------------------------------------------%
                    case app.points_addFindPeaks
                        [idx, Coordinates] = points_FindPeaks(app, app.points_addSubtype2DropDown.Value, app.points_addSubtype2NPeaks.Value, app.points_addSubtype2Distance.Value/1000);
                        newRow = {'FindPeaks', struct('source', app.points_addSubtype2DropDown.Value, 'idx', idx, 'Coordinates', Coordinates), true};
                        points_TableCheck(app, newRow)

                        msg = sprintf('Identificado(s) %d ponto(s) que atende(m) às especificações.', numel(idx));
                        appUtil.modalWindow(app.UIFigure, 'warning', msg);
                end

            % catch ME
            %     appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            %     return
            % end

            points_TreeBuilding(app)
            plot.old_axesDraw.DriveTestPointsPlot(app.UIAxes1, createPlotParameters(app))
            plotFcn.axes.StackingOrder.execute(app.UIAxes1, 'appAnalise:DRIVETEST')

        end

        % Value changed function: points_addSubtype1TextArea
        function points_addSubtype1TextAreaValueChanged(app, event)

            app.points_addSubtype1TextArea.Value = strtrim(app.points_addSubtype1TextArea.Value);
            
        end

        % Callback function: points_Tree
        function points_TreeCheckedNodesChanged(app, event)
            
            visibleNodeData   = arrayfun(@(x) x.NodeData, app.points_Tree.CheckedNodes);
            invisibleNodeData = setdiff(1:height(app.pointsTable), visibleNodeData);

            app.pointsTable.visible(visibleNodeData)   = true;
            app.pointsTable.visible(invisibleNodeData) = false;

            plot.old_axesDraw.DriveTestPointsPlot(app.UIAxes1, createPlotParameters(app))
            plotFcn.axes.StackingOrder.execute(app.UIAxes1, 'appAnalise:DRIVETEST')
            
        end

        % Menu selected function: points_delButton
        function points_delButtonMenuSelected(app, event)
            
            if isempty(app.pointsTable) || isempty(app.points_Tree.SelectedNodes)
                return
            end

            idx = arrayfun(@(x) x.NodeData, app.points_Tree.SelectedNodes);
    
            if ~isempty(idx)
                app.pointsTable(idx,:) = [];

                points_TreeBuilding(app)
                plot.old_axesDraw.DriveTestPointsPlot(app.UIAxes1, createPlotParameters(app))
                plot.axes.StackingOrder.execute(app.UIAxes1, 'appAnalise:DRIVETEST')
            end

        end

        % Callback function: not associated with a component
        function filter_DataBinningValueChanged(app, event)


        end

        % Callback function: filter_DataBinningFcn, 
        % ...and 2 other components
        function plot_RefreshCallback(app, event)
            
            updateDataFlag = 1;

            switch event.Source
                case app.plotSource
                    if app.plotSource_specBinTable.Value
                        app.axesTool_DensityPlot.Enable = 1;
                    else
                        app.axesTool_DensityPlot.Enable = 0;
                    end

                otherwise % {app.filter_DataBinningLength, app.filter_DataBinningFcn}
                    if ~app.plotSource_specBinTable.Value
                        updateDataFlag = 0;
                    end
            end

            if updateDataFlag
                [idxThread, idxEmission] = specDataIndex(app);
                processingSpecRawTable(app, idxThread, idxEmission)
                plot_updateGeographicAxes(app)
                updateCustomProperty(app, idxThread, idxEmission)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.ControlTabGrid = uigridlayout(app.Grid);
            app.ControlTabGroup = uitabgroup(app.ControlTabGrid);
            app.Tab1_Emission = uitab(app.ControlTabGroup);
            app.Tab1_Grid = uigridlayout(app.Tab1_Emission);
            app.general_emissionListLabel = uilabel(app.Tab1_Grid);
            app.general_emissionListLabel.Text = 'EMISSÃO';
            app.general_emissionListRefresh = uiimage(app.Tab1_Grid);
            app.general_emissionListRefresh.ImageClickedFcn = createCallbackFcn(app, @emission_RefreshListImageClicked, true);
            app.general_emissionListRefresh.Tooltip = {'Atualiza lista de emissões'};
            app.general_emissionListRefresh.ImageSource = 'Refresh_18.png';
            app.general_emissionList = uidropdown(app.Tab1_Grid);
            app.general_emissionList.Items = {''};
            app.general_emissionList.ValueChangedFcn = createCallbackFcn(app, @emission_SelectionChanged, true);
            app.general_emissionList.Value = '';
            app.general_emissionInfoPanel = uipanel(app.Tab1_Grid);
            app.general_emissionInfoGrid = uigridlayout(app.general_emissionInfoPanel);
            app.general_emissionInfo = uihtml(app.general_emissionInfoGrid);
            app.general_emissionInfo.HTMLSource = ' ';
            app.general_chLabel = uilabel(app.Tab1_Grid);
            app.general_chLabel.Text = 'CANAL SOB ANÁLISE';
            app.general_chEdit = uiimage(app.Tab1_Grid);
            app.general_chEdit.ImageClickedFcn = createCallbackFcn(app, @general_chEditImageClicked, true);
            app.general_chEdit.ImageSource = 'Edit_32.png';
            app.general_chPanel = uipanel(app.Tab1_Grid);
            app.general_chGrid = uigridlayout(app.general_chPanel);
            app.general_chFrequencyLabel = uilabel(app.general_chGrid);
            app.general_chFrequencyLabel.Text = 'Frequência central (MHz):';
            app.general_chFrequency = uieditfield(app.general_chGrid, 'numeric');
            app.general_chFrequency.ValueDisplayFormat = '%.3f';
            app.general_chFrequency.ValueChangedFcn = createCallbackFcn(app, @emission_SelectionChanged, true);
            app.general_chFrequency.Editable = 'off';
            app.general_chBWLabel = uilabel(app.general_chGrid);
            app.general_chBWLabel.Text = 'Largura (kHz):';
            app.general_chBW = uieditfield(app.general_chGrid, 'numeric');
            app.general_chBW.ValueDisplayFormat = '%.1f';
            app.general_chBW.ValueChangedFcn = createCallbackFcn(app, @emission_SelectionChanged, true);
            app.general_chBW.Editable = 'off';
            app.general_ReportFlag = uicheckbox(app.Tab1_Grid);
            app.general_ReportFlag.ValueChangedFcn = createCallbackFcn(app, @emission_ReportFlagCheckBoxClicked, true);
            app.general_ReportFlag.Text = 'Customizar plot, habilitando-o para inclusão em relatório.';
            app.Tab2_Filter = uitab(app.ControlTabGroup);
            app.Tab2_Grid = uigridlayout(app.Tab2_Filter);
            app.filter_TreeLabel = uilabel(app.Tab2_Grid);
            app.filter_TreeLabel.Text = 'ORDINÁRIA';
            app.filter_RadioGroup = uibuttongroup(app.Tab2_Grid);
            app.filter_THR = uiradiobutton(app.filter_RadioGroup);
            app.filter_THR.Text = {'<b>NÍVEL</b>'; '<p style="color: gray; text-align: justify;">Elimina medições cuja potência da emissão foi inferior a um <i>threshold</i>.</font>'};
            app.filter_THR.Interpreter = 'html';
            app.filter_THR.Value = true;
            app.filter_Timestamp = uiradiobutton(app.filter_RadioGroup);
            app.filter_Timestamp.Text = {'<b>TEMPORAL</b>'; '<p style="color: gray; text-align: justify;">Elimina medições realizadas fora de intervalo de tempo.</font>'};
            app.filter_Timestamp.Interpreter = 'html';
            app.filter_Geographic = uiradiobutton(app.filter_RadioGroup);
            app.filter_Geographic.Text = {'<b>GEOGRÁFICA</b>'; '<p style="color: gray; text-align: justify;">Elimina medições realizadas fora da região de interesse (ROI).</p>'};
            app.filter_Geographic.Interpreter = 'html';
            app.filter_GeographicTypeLabel = uilabel(app.filter_RadioGroup);
            app.filter_GeographicTypeLabel.Text = 'Fonte:';
            app.filter_GeographicType = uidropdown(app.filter_RadioGroup);
            app.filter_GeographicType.Items = {'ROI:Círculo', 'ROI:Retângulo', 'ROI:Polígono', 'Arquivo externo KML'};
            app.filter_GeographicType.Value = 'Arquivo externo KML';
            app.filter_addImage = uiimage(app.Tab2_Grid);
            app.filter_addImage.ImageClickedFcn = createCallbackFcn(app, @filter_addImageCallback, true);
            app.filter_addImage.ImageSource = 'addSymbol_32.png';
            app.filter_Tree = uitree(app.Tab2_Grid, 'checkbox');
            app.filter_Tree.SelectionChangedFcn = createCallbackFcn(app, @filter_TreeSelectionChanged, true);
            app.filter_DataBinningPanel = uipanel(app.Tab2_Grid);
            app.filter_DataBinningGrid = uigridlayout(app.filter_DataBinningPanel);
            app.filter_DataBinningLengthLabel = uilabel(app.filter_DataBinningGrid);
            app.filter_DataBinningLengthLabel.Interpreter = 'html';
            app.filter_DataBinningLengthLabel.Text = {'Comprimento da lateral'; 'da quadrícula (metros):'};
            app.filter_DataBinningLength = uispinner(app.filter_DataBinningGrid);
            app.filter_DataBinningLength.Step = 50;
            app.filter_DataBinningLength.Limits = [50 1500];
            app.filter_DataBinningLength.RoundFractionalValues = 'on';
            app.filter_DataBinningLength.ValueDisplayFormat = '%.0f';
            app.filter_DataBinningLength.ValueChangedFcn = createCallbackFcn(app, @plot_RefreshCallback, true);
            app.filter_DataBinningLength.Value = 100;
            app.filter_DataBinningFcnLabel = uilabel(app.filter_DataBinningGrid);
            app.filter_DataBinningFcnLabel.Text = {'Função'; 'estatística:'};
            app.filter_DataBinningFcn = uidropdown(app.filter_DataBinningGrid);
            app.filter_DataBinningFcn.Items = {'min', 'mean', 'median', 'rms', 'max'};
            app.filter_DataBinningFcn.ValueChangedFcn = createCallbackFcn(app, @plot_RefreshCallback, true);
            app.filter_DataBinningFcn.Value = 'min';
            app.DATABINNINGpstylecolorgraytextalignjustifyLabel = uilabel(app.Tab2_Grid);
            app.DATABINNINGpstylecolorgraytextalignjustifyLabel.Interpreter = 'html';
            app.DATABINNINGpstylecolorgraytextalignjustifyLabel.Text = {'DATA-BINNING'; '<p style="font-size: 10px; color: gray; text-align: justify;">Agrupa medições em quadrículas, sumarizando-as por meio de função estatística.</p>'};
            app.Tab3_Points = uitab(app.ControlTabGroup);
            app.Tab3_Grid = uigridlayout(app.Tab3_Points);
            app.points_TreeLabel = uilabel(app.Tab3_Grid);
            app.points_TreeLabel.Text = 'TIPO';
            app.points_addTypePanel = uibuttongroup(app.Tab3_Grid);
            app.points_addTypePanel.SelectionChangedFcn = createCallbackFcn(app, @points_addTypePanelSelectionChanged, true);
            app.points_addRFDataHub = uiradiobutton(app.points_addTypePanel);
            app.points_addRFDataHub.Text = {'<b>ESTAÇÕES DE TELECOMUNICAÇÕES</b>'; '<p style="color: gray; text-align: justify;">Adiciona estações incluídas no RFDataHub.</font>'};
            app.points_addRFDataHub.Interpreter = 'html';
            app.points_addRFDataHub.Value = true;
            app.points_addFindPeaks = uiradiobutton(app.points_addTypePanel);
            app.points_addFindPeaks.Text = {'<b>POTÊNCIA DO CANAL</b>'; '<p style="color: gray; text-align: justify;">Adiciona locais em que o sensor captou o canal sob análise com seus maiores níveis de potência.</font>'};
            app.points_addFindPeaks.Interpreter = 'html';
            app.points_addValuePanel = uipanel(app.Tab3_Grid);
            app.points_addValueGrid = uigridlayout(app.points_addValuePanel);
            app.points_addSubtype1Label = uilabel(app.points_addValueGrid);
            app.points_addSubtype1Label.Text = 'Tipo de registro:';
            app.points_addSubtype1DropDown = uidropdown(app.points_addValueGrid);
            app.points_addSubtype1DropDown.Items = {'Índices de registros do RFDataHub', 'Lista de frequências'};
            app.points_addSubtype1DropDown.Value = 'Índices de registros do RFDataHub';
            app.points_addSubtype1TextArea = uitextarea(app.points_addValueGrid);
            app.points_addSubtype1TextArea.ValueChangedFcn = createCallbackFcn(app, @points_addSubtype1TextAreaValueChanged, true);
            app.points_addSubtype1DistanceLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype1DistanceLabel.Text = 'Distância máxima entre estação e local da monitoração (km):';
            app.points_addSubtype1Distance = uieditfield(app.points_addValueGrid, 'numeric');
            app.points_addSubtype1Distance.Limits = [1 Inf];
            app.points_addSubtype1Distance.RoundFractionalValues = 'on';
            app.points_addSubtype1Distance.ValueDisplayFormat = '%d';
            app.points_addSubtype1Distance.Value = 200;
            app.points_addSubtype2Label = uilabel(app.points_addValueGrid);
            app.points_addSubtype2Label.Text = 'Fonte da informação:';
            app.points_addSubtype2DropDown = uidropdown(app.points_addValueGrid);
            app.points_addSubtype2DropDown.Items = {'Dados brutos', 'Dados processados (Data Binning)'};
            app.points_addSubtype2DropDown.Value = 'Dados brutos';
            app.points_addSubtype2NPeaksLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype2NPeaksLabel.Text = 'Número de picos:';
            app.points_addSubtype2NPeaks = uispinner(app.points_addValueGrid);
            app.points_addSubtype2NPeaks.Limits = [1 100];
            app.points_addSubtype2NPeaks.RoundFractionalValues = 'on';
            app.points_addSubtype2NPeaks.ValueDisplayFormat = '%.0f';
            app.points_addSubtype2NPeaks.Value = 1;
            app.points_addSubtype2DistanceLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype2DistanceLabel.Text = 'Distância mínima entre picos (metros):';
            app.points_addSubtype2Distance = uispinner(app.points_addValueGrid);
            app.points_addSubtype2Distance.Step = 100;
            app.points_addSubtype2Distance.Limits = [0 10000];
            app.points_addSubtype2Distance.RoundFractionalValues = 'on';
            app.points_addSubtype2Distance.ValueDisplayFormat = '%.0f';
            app.points_addSubtype2Distance.Value = 1000;
            app.points_addImage = uiimage(app.Tab3_Grid);
            app.points_addImage.ImageClickedFcn = createCallbackFcn(app, @points_addImageImageClicked, true);
            app.points_addImage.ImageSource = 'addSymbol_32.png';
            app.points_Tree = uitree(app.Tab3_Grid, 'checkbox');
            % Assign Checked Nodes
            app.points_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @points_TreeCheckedNodesChanged, true);
            app.Tab4_Config = uitab(app.ControlTabGroup);
            app.Tab4_Grid = uigridlayout(app.Tab4_Config);
            app.config_geoAxesLabel = uilabel(app.Tab4_Grid);
            app.config_geoAxesLabel.Text = 'EIXO GEOGRÁFICO';
            app.config_Refresh = uiimage(app.Tab4_Grid);
            app.config_Refresh.ImageClickedFcn = createCallbackFcn(app, @config_RefreshImageClicked, true);
            app.config_Refresh.Tooltip = {'Volta à configuração inicial'};
            app.config_Refresh.ImageSource = 'Refresh_18.png';
            app.config_geoAxesPanel = uipanel(app.Tab4_Grid);
            app.config_geoAxesGrid = uigridlayout(app.config_geoAxesPanel);
            app.config_geoAxesSublabel = uilabel(app.config_geoAxesGrid);
            app.config_geoAxesSublabel.Text = 'Mapa:';
            app.config_geoAxesSubPanel = uipanel(app.config_geoAxesGrid);
            app.config_geoAxesSubGrid = uigridlayout(app.config_geoAxesSubPanel);
            app.config_BasemapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_BasemapLabel.Text = 'Basemap:';
            app.config_Basemap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Basemap.Items = {'none', 'darkwater', 'streets-light', 'streets-dark', 'satellite', 'topographic', 'grayterrain'};
            app.config_Basemap.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Basemap.Value = 'satellite';
            app.config_ColormapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_ColormapLabel.Text = 'Mapa de cor:';
            app.config_Colormap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.config_Colormap.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Colormap.Value = 'turbo';
            app.config_route_Label = uilabel(app.config_geoAxesGrid);
            app.config_route_Label.Text = 'Rota:';
            app.config_route_LineStyle = uidropdown(app.config_geoAxesGrid);
            app.config_route_LineStyle.Items = {'none', ':', '-'};
            app.config_route_LineStyle.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_route_LineStyle.Value = 'none';
            app.config_route_OutColor = uicolorpicker(app.config_geoAxesGrid);
            app.config_route_OutColor.Value = [0.502 0.502 0.502];
            app.config_route_OutColor.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_route_InColor = uicolorpicker(app.config_geoAxesGrid);
            app.config_route_InColor.Value = [0.8706 0.5412 0.5412];
            app.config_route_InColor.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_route_Size = uislider(app.config_geoAxesGrid);
            app.config_route_Size.Limits = [1 9];
            app.config_route_Size.MajorTicks = [];
            app.config_route_Size.ValueChangingFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_route_Size.MinorTicks = [];
            app.config_route_Size.Tooltip = {'Tamanho do marcador'};
            app.config_route_Size.Value = 1;
            app.config_Car_Label = uilabel(app.config_geoAxesGrid);
            app.config_Car_Label.Text = 'Veículo:';
            app.config_Car_LineStyle = uidropdown(app.config_geoAxesGrid);
            app.config_Car_LineStyle.Items = {'none', 'o', 'square', '^'};
            app.config_Car_LineStyle.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Car_LineStyle.Value = 'none';
            app.config_Car_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_Car_Color.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_Car_Size = uislider(app.config_geoAxesGrid);
            app.config_Car_Size.Limits = [1 19];
            app.config_Car_Size.MajorTicks = [];
            app.config_Car_Size.ValueChangingFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Car_Size.MinorTicks = [];
            app.config_Car_Size.Tooltip = {'Tamanho do marcador'};
            app.config_Car_Size.Value = 10;
            app.config_points_Label = uilabel(app.config_geoAxesGrid);
            app.config_points_Label.Text = {'Pontos de'; 'interesse:'};
            app.config_points_LineStyle = uidropdown(app.config_geoAxesGrid);
            app.config_points_LineStyle.Items = {'none', 'o', 'square', '^'};
            app.config_points_LineStyle.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_points_LineStyle.Value = 'none';
            app.config_points_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_points_Color.Value = [0 0 0];
            app.config_points_Color.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_points_Size = uislider(app.config_geoAxesGrid);
            app.config_points_Size.Limits = [6 12];
            app.config_points_Size.MajorTicks = [];
            app.config_points_Size.ValueChangingFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_points_Size.MinorTicks = [];
            app.config_points_Size.Tooltip = {'Tamanho do marcador'};
            app.config_points_Size.Value = 9;
            app.config_xyAxesLabel = uilabel(app.Tab4_Grid);
            app.config_xyAxesLabel.Interpreter = 'html';
            app.config_xyAxesLabel.Text = {'EIXOS CARTESIANOS'; '<p style="color: gray; font-size: 9px; text-align: justify;">Outros parâmetros de configuração do plot e do playback são herdados diretamente do modo PLAYBACK do appAnalise.</p>'};
            app.config_xyAxesPanel = uipanel(app.Tab4_Grid);
            app.config_xyAxesGrid = uigridlayout(app.config_xyAxesPanel);
            app.config_PersistanceLabel = uilabel(app.config_xyAxesGrid);
            app.config_PersistanceLabel.Text = 'Persistência:';
            app.config_PersistanceVisibility = uidropdown(app.config_xyAxesGrid);
            app.config_PersistanceVisibility.Items = {'on', 'off'};
            app.config_PersistanceVisibility.ValueChangedFcn = createCallbackFcn(app, @config_xyAxesParameterChanged, true);
            app.config_PersistanceVisibility.Tooltip = {''};
            app.config_PersistanceVisibility.Value = 'on';
            app.config_chROILabel = uilabel(app.config_xyAxesGrid);
            app.config_chROILabel.Text = 'Canal:';
            app.config_chROIVisibility = uidropdown(app.config_xyAxesGrid);
            app.config_chROIVisibility.Items = {'on', 'off'};
            app.config_chROIVisibility.ValueChangedFcn = createCallbackFcn(app, @config_xyAxesParameterChanged, true);
            app.config_chROIVisibility.Tooltip = {''};
            app.config_chROIVisibility.Value = 'on';
            app.config_chROIColor = uicolorpicker(app.config_xyAxesGrid);
            app.config_chROIColor.Value = [0.7216 0.2706 1];
            app.config_chROIColor.ValueChangedFcn = createCallbackFcn(app, @config_xyAxesParameterChanged, true);
            app.config_chROIColor.Tooltip = {''};
            app.config_chROIEdgeAlpha = uispinner(app.config_xyAxesGrid);
            app.config_chROIEdgeAlpha.Step = 0.1;
            app.config_chROIEdgeAlpha.Limits = [0 1];
            app.config_chROIEdgeAlpha.ValueDisplayFormat = '%.1f';
            app.config_chROIEdgeAlpha.ValueChangedFcn = createCallbackFcn(app, @config_xyAxesParameterChanged, true);
            app.config_chROIEdgeAlpha.Tooltip = {'Transparência da margem'};
            app.config_chROIFaceAlpha = uispinner(app.config_xyAxesGrid);
            app.config_chROIFaceAlpha.Step = 0.1;
            app.config_chROIFaceAlpha.Limits = [0 1];
            app.config_chROIFaceAlpha.ValueDisplayFormat = '%.1f';
            app.config_chROIFaceAlpha.ValueChangedFcn = createCallbackFcn(app, @config_xyAxesParameterChanged, true);
            app.config_chROIFaceAlpha.Tooltip = {'Transparência da face'};
            app.config_chROIFaceAlpha.Value = 0.4;
            app.config_chPowerLabel = uilabel(app.config_xyAxesGrid);
            app.config_chPowerLabel.Text = {'Potência do'; 'canal:'};
            app.config_chPowerVisibility = uidropdown(app.config_xyAxesGrid);
            app.config_chPowerVisibility.Items = {'on', 'off'};
            app.config_chPowerVisibility.ValueChangedFcn = createCallbackFcn(app, @config_xyAxesParameterChanged, true);
            app.config_chPowerVisibility.Tooltip = {''};
            app.config_chPowerVisibility.Value = 'on';
            app.config_chPowerColor = uicolorpicker(app.config_xyAxesGrid);
            app.config_chPowerColor.Value = [0.5686 1 0];
            app.config_chPowerColor.ValueChangedFcn = createCallbackFcn(app, @config_xyAxesParameterChanged, true);
            app.config_chPowerColor.Tooltip = {''};
            app.config_chPowerEdgeAlpha = uispinner(app.config_xyAxesGrid);
            app.config_chPowerEdgeAlpha.Step = 0.1;
            app.config_chPowerEdgeAlpha.Limits = [0 1];
            app.config_chPowerEdgeAlpha.ValueDisplayFormat = '%.1f';
            app.config_chPowerEdgeAlpha.ValueChangedFcn = createCallbackFcn(app, @config_xyAxesParameterChanged, true);
            app.config_chPowerEdgeAlpha.Tooltip = {'Transparência da margem'};
            app.config_chPowerEdgeAlpha.Value = 0.2;
            app.config_chPowerFaceAlpha = uispinner(app.config_xyAxesGrid);
            app.config_chPowerFaceAlpha.Step = 0.1;
            app.config_chPowerFaceAlpha.Limits = [0 1];
            app.config_chPowerFaceAlpha.ValueDisplayFormat = '%.1f';
            app.config_chPowerFaceAlpha.ValueChangedFcn = createCallbackFcn(app, @config_xyAxesParameterChanged, true);
            app.config_chPowerFaceAlpha.Tooltip = {'Transparência da face'};
            app.config_chPowerFaceAlpha.Value = 0.4;
            app.config_BandGuardLabel = uilabel(app.config_xyAxesGrid);
            app.config_BandGuardLabel.Text = 'Limites do eixo x:';
            app.config_BandGuardPanel = uipanel(app.config_xyAxesGrid);
            app.config_BandGuardGrid = uigridlayout(app.config_BandGuardPanel);
            app.config_BandGuardTypeLabel = uilabel(app.config_BandGuardGrid);
            app.config_BandGuardTypeLabel.Text = 'Tipo:';
            app.config_BandGuardType = uidropdown(app.config_BandGuardGrid);
            app.config_BandGuardType.Items = {'Fixed', 'BWRelated'};
            app.config_BandGuardType.ValueChangedFcn = createCallbackFcn(app, @config_BandGuardValueChanged, true);
            app.config_BandGuardType.Value = 'BWRelated';
            app.config_BandGuardValueLabel = uilabel(app.config_BandGuardGrid);
            app.config_BandGuardValueLabel.Text = 'Multiplicador:';
            app.config_BandGuardFixedValue = uieditfield(app.config_BandGuardGrid, 'numeric');
            app.config_BandGuardFixedValue.Limits = [0 Inf];
            app.config_BandGuardFixedValue.ValueDisplayFormat = '%.1f';
            app.config_BandGuardFixedValue.ValueChangedFcn = createCallbackFcn(app, @config_BandGuardValueChanged, true);
            app.config_BandGuardFixedValue.Visible = 'off';
            app.config_BandGuardFixedValue.Value = 1000;
            app.config_BandGuardBWRelatedValue = uispinner(app.config_BandGuardGrid);
            app.config_BandGuardBWRelatedValue.Limits = [2 10];
            app.config_BandGuardBWRelatedValue.RoundFractionalValues = 'on';
            app.config_BandGuardBWRelatedValue.ValueDisplayFormat = '%.0f';
            app.config_BandGuardBWRelatedValue.ValueChangedFcn = createCallbackFcn(app, @config_BandGuardValueChanged, true);
            app.config_BandGuardBWRelatedValue.Value = 6;
            app.menu_MainGrid = uigridlayout(app.ControlTabGrid);
            app.menuUnderline = uiimage(app.menu_MainGrid);
            app.menuUnderline.ImageSource = 'LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'DRIVE-TEST';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelSelectionChanged, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'Channel_18.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'FILTRAGEM';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelSelectionChanged, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'Filter_18.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'PONTOS DE INTERESSE';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelSelectionChanged, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'Pin_18.png';
            app.menu_Button4Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button4Label = uilabel(app.menu_Button4Grid);
            app.menu_Button4Label.Text = 'CONFIGURAÇÕES GERAIS';
            app.menu_Button4Icon = uiimage(app.menu_Button4Grid);
            app.menu_Button4Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelSelectionChanged, true);
            app.menu_Button4Icon.Tag = '4';
            app.menu_Button4Icon.ImageSource = 'Settings_18.png';
            app.plotPanel = uipanel(app.Grid);
            app.axesToolbarGrid = uigridlayout(app.Grid);
            app.axesTool_RestoreView = uiimage(app.axesToolbarGrid);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RestoreView.Tooltip = {'RestoreView'};
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_RegionZoom = uiimage(app.axesToolbarGrid);
            app.axesTool_RegionZoom.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RegionZoom.Tooltip = {'RegionZoom'};
            app.axesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.axesTool_DataTip = uiimage(app.axesToolbarGrid);
            app.axesTool_DataTip.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_DataTip.Tooltip = {'DataTip'};
            app.axesTool_DataTip.ImageSource = 'DataTip_22.png';
            app.axesTool_DistortionPlot = uiimage(app.axesToolbarGrid);
            app.axesTool_DistortionPlot.ImageClickedFcn = createCallbackFcn(app, @axesTool_PlotTypeValueChanged, true);
            app.axesTool_DistortionPlot.Tooltip = {'Distortion'};
            app.axesTool_DistortionPlot.ImageSource = 'DriveTestDistortion_32.png';
            app.axesTool_DensityPlot = uiimage(app.axesToolbarGrid);
            app.axesTool_DensityPlot.ImageClickedFcn = createCallbackFcn(app, @axesTool_PlotTypeValueChanged, true);
            app.axesTool_DensityPlot.Enable = 'off';
            app.axesTool_DensityPlot.Tooltip = {'Heatmap'};
            app.axesTool_DensityPlot.ImageSource = 'DriveTestDensity_32.png';
            app.axesTool_PlotSize = uislider(app.axesToolbarGrid);
            app.axesTool_PlotSize.Limits = [1 19];
            app.axesTool_PlotSize.MajorTicks = [1 10 19];
            app.axesTool_PlotSize.MajorTickLabels = {''};
            app.axesTool_PlotSize.ValueChangedFcn = createCallbackFcn(app, @axesTool_PlotSizeValueChanged, true);
            app.axesTool_PlotSize.ValueChangingFcn = createCallbackFcn(app, @axesTool_PlotSizeValueChanging, true);
            app.axesTool_PlotSize.Value = 1;
            app.plotSource = uibuttongroup(app.Grid);
            app.plotSource.SelectionChangedFcn = createCallbackFcn(app, @plot_RefreshCallback, true);
            app.plotSource_specFilteredTable = uiradiobutton(app.plotSource);
            app.plotSource_specFilteredTable.Text = 'Dados brutos';
            app.plotSource_specFilteredTable.Interpreter = 'html';
            app.plotSource_specFilteredTable.Value = true;
            app.plotSource_specBinTable = uiradiobutton(app.plotSource);
            app.plotSource_specBinTable.Text = 'Dados processados (Data-Binning)';
            app.plotSource_specBinTable.Interpreter = 'html';
            app.plotFootnote = uilabel(app.Grid);
            app.plotFootnote.Text = {'Canal'; '0.000 MHz ⌂ 0.0 kHz'};
            app.toolGrid = uigridlayout(app.Grid);
            app.tool_ControlPanelVisibility = uiimage(app.toolGrid);
            app.tool_ControlPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_LeftPanelVisibilityImageClicked, true);
            app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.tool_Play = uiimage(app.toolGrid);
            app.tool_Play.ImageClickedFcn = createCallbackFcn(app, @tool_PlaybackControlImageClicked, true);
            app.tool_Play.Tooltip = {'Playback'};
            app.tool_Play.ImageSource = 'play_32.png';
            app.tool_LoopControl = uiimage(app.toolGrid);
            app.tool_LoopControl.ImageClickedFcn = createCallbackFcn(app, @tool_PlaybackControlImageClicked, true);
            app.tool_LoopControl.Tag = 'loop';
            app.tool_LoopControl.Tooltip = {'Loop do playback'};
            app.tool_LoopControl.ImageSource = 'playbackLoop_32Blue.png';
            app.tool_TimestampSlider = uislider(app.toolGrid);
            app.tool_TimestampSlider.MajorTicks = [0 50 100];
            app.tool_TimestampSlider.MajorTickLabels = {''};
            app.tool_TimestampSlider.ValueChangingFcn = createCallbackFcn(app, @tool_TimelineSliderValueChanging, true);
            app.tool_TimestampSlider.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.tool_TimestampLabel = uilabel(app.toolGrid);
            app.tool_TimestampLabel.Text = {'22 de 328 '; '22/02/2022 08:00:00 '};
            app.jsBackDoor = uihtml(app.toolGrid);
            app.filter_Summary = uiimage(app.toolGrid);
            app.filter_Summary.ImageClickedFcn = createCallbackFcn(app, @tool_SummaryImageClicked, true);
            app.filter_Summary.Tooltip = {'Informações acerca do processo de análise dos dados'};
            app.filter_Summary.ImageSource = 'Info_32.png';
            app.filter_DataBinningExport = uiimage(app.toolGrid);
            app.filter_DataBinningExport.ImageClickedFcn = createCallbackFcn(app, @tool_ExportFileButtonPushed, true);
            app.filter_DataBinningExport.Tooltip = {'Exporta análise em arquivos XLSX e KML'};
            app.filter_DataBinningExport.ImageSource = 'Export_16.png';
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.points_ContextMenu = uicontextmenu(app.UIFigure);
            app.points_delButton = uimenu(app.points_ContextMenu);
            app.points_delButton.MenuSelectedFcn = createCallbackFcn(app, @points_delButtonMenuSelected, true);
            app.points_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end