classdef winGraphicAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Grid                            matlab.ui.container.GridLayout
        toolGrid                        matlab.ui.container.GridLayout
        tool_resetButton                matlab.ui.control.Image
        tool_SelectedEmision            matlab.ui.control.Label
        tool_Serarator                  matlab.ui.control.Image
        tool_playButton                 matlab.ui.control.Button
        Document                        matlab.ui.container.GridLayout
        plot_CartesianPanel             matlab.ui.container.Panel
        plot_GeographicPanel            matlab.ui.container.Panel
        LeftPanelVisibility             matlab.ui.control.Image
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelGrid                   matlab.ui.container.GridLayout
        GridLayout                      matlab.ui.container.GridLayout
        general_SpecificationPanel      matlab.ui.container.Panel
        general_SpecificationGrid       matlab.ui.container.GridLayout
        Panel_3                         matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        MaxHold_Panel_3                 matlab.ui.container.Panel
        MaxHold_Grid_3                  matlab.ui.container.GridLayout
        occMaxHold_LineWidth            matlab.ui.control.Slider
        occMaxHold_FaceColor            matlab.ui.control.Button
        occMaxHold_EdgeColor            matlab.ui.control.Button
        occMaxHold_LineStyle            matlab.ui.control.DropDown
        occMaxHold_Fcn                  matlab.ui.control.DropDown
        MaxHold_Label_2                 matlab.ui.control.Label
        Average_Panel_2                 matlab.ui.container.Panel
        Average_Grid_2                  matlab.ui.container.GridLayout
        occAverage_LineWidth            matlab.ui.control.Slider
        occAverage_FaceColor            matlab.ui.control.Button
        occAverage_EdgeColor            matlab.ui.control.Button
        occAverage_LineStyle            matlab.ui.control.DropDown
        occAverage_Fcn                  matlab.ui.control.DropDown
        Average_Label_2                 matlab.ui.control.Label
        MinHold_Panel_2                 matlab.ui.container.Panel
        MinHold_Grid_2                  matlab.ui.container.GridLayout
        occMinHold_LineWidth            matlab.ui.control.Slider
        occMinHold_FaceColor            matlab.ui.control.Button
        occMinHold_EdgeColor            matlab.ui.control.Button
        occMinHold_LineStyle            matlab.ui.control.DropDown
        occMinHold_Fcn                  matlab.ui.control.DropDown
        MinHold_Label_3                 matlab.ui.control.Label
        Waterfall_Label_2               matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ROI_yPosition                   matlab.ui.control.Spinner
        ROI_yPositionLabel              matlab.ui.control.Label
        ROI_Panel                       matlab.ui.container.Panel
        ROI_Grid                        matlab.ui.container.GridLayout
        ROI_TextFontSize                matlab.ui.control.Slider
        ROI_Color                       matlab.ui.control.Button
        ROI_TextColor                   matlab.ui.control.Button
        ROI_FaceAlpha                   matlab.ui.control.Spinner
        plotInfo_xGuardBandFactorLabel_2  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        EmissionButton                  matlab.ui.control.RadioButton
        BandButton                      matlab.ui.control.RadioButton
        plotInfo_Panel_2                matlab.ui.container.Panel
        plotInfo_Grid_2                 matlab.ui.container.GridLayout
        plotInfo_xGuardBandFactor       matlab.ui.control.Spinner
        plotInfo_xGuardBandFactorLabel  matlab.ui.control.Label
        xTickLabelCheckBox              matlab.ui.control.CheckBox
        yTickLabelCheckBox              matlab.ui.control.CheckBox
        yLabelCheckBox                  matlab.ui.control.CheckBox
        xLabelCheckBox                  matlab.ui.control.CheckBox
        plotInfo_Label_2                matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        MaxHold_Panel                   matlab.ui.container.Panel
        MaxHold_Grid                    matlab.ui.container.GridLayout
        MaxHold_LineWidth               matlab.ui.control.Slider
        MaxHold_FaceColor               matlab.ui.control.Button
        MaxHold_EdgeColor               matlab.ui.control.Button
        MaxHold_LineStyle               matlab.ui.control.DropDown
        MaxHold_Fcn                     matlab.ui.control.DropDown
        MaxHold_Label                   matlab.ui.control.Label
        Average_Panel                   matlab.ui.container.Panel
        Average_Grid                    matlab.ui.container.GridLayout
        Average_LineWidth               matlab.ui.control.Slider
        Average_FaceColor               matlab.ui.control.Button
        Average_EdgeColor               matlab.ui.control.Button
        Average_LineStyle               matlab.ui.control.DropDown
        Average_Fcn                     matlab.ui.control.DropDown
        Average_Label                   matlab.ui.control.Label
        MinHold_Panel                   matlab.ui.container.Panel
        MinHold_Grid                    matlab.ui.container.GridLayout
        MinHold_LineWidth               matlab.ui.control.Slider
        MinHold_FaceColor               matlab.ui.control.Button
        MinHold_EdgeColor               matlab.ui.control.Button
        MinHold_LineStyle               matlab.ui.control.DropDown
        MinHold_Fcn                     matlab.ui.control.DropDown
        MinHold_Label                   matlab.ui.control.Label
        MinHold_Label_2                 matlab.ui.control.Label
        Waterfall_Panel                 matlab.ui.container.Panel
        Waterfall_Grid                  matlab.ui.container.GridLayout
        Waterfall_Colormap              matlab.ui.control.DropDown
        Waterfall_ColormapLabel         matlab.ui.control.Label
        Waterfall_MeshStyle             matlab.ui.control.DropDown
        Waterfall_MeshStyleLabel        matlab.ui.control.Label
        Waterfall_View                  matlab.ui.control.DropDown
        Waterfall_ViewLabel             matlab.ui.control.Label
        Waterfall_Fcn                   matlab.ui.control.DropDown
        Waterfall_FcnLabel              matlab.ui.control.Label
        Waterfall_Label                 matlab.ui.control.Label
        Persistance_Panel               matlab.ui.container.Panel
        Persistance_Grid                matlab.ui.container.GridLayout
        Persistance_Colormap            matlab.ui.control.DropDown
        Persistance_ColormapLabel       matlab.ui.control.Label
        Persistance_Interpolation       matlab.ui.control.DropDown
        Persistance_InterpolationLabel  matlab.ui.control.Label
        Persistance_Label               matlab.ui.control.Label
        ROI_Label                       matlab.ui.control.Label
        plotInfo_Label                  matlab.ui.control.Label
        general_ButtonGroup             matlab.ui.container.ButtonGroup
        general_RadioButton13           matlab.ui.control.RadioButton
        general_RadioButton12           matlab.ui.control.RadioButton
        general_RadioButton11           matlab.ui.control.RadioButton
        general_RadioButton10           matlab.ui.control.RadioButton
        general_RadioButton9            matlab.ui.control.RadioButton
        general_RadioButton8            matlab.ui.control.RadioButton
        general_RadioButton7            matlab.ui.control.RadioButton
        general_RadioButton6            matlab.ui.control.RadioButton
        general_RadioButton5            matlab.ui.control.RadioButton
        general_RadioButton4            matlab.ui.control.RadioButton
        general_RadioButton3            matlab.ui.control.RadioButton
        general_RadioButton2            matlab.ui.control.RadioButton
        general_RadioButton1            matlab.ui.control.RadioButton
        general_Sublabel2               matlab.ui.control.Label
        general_Sublabel1               matlab.ui.control.Label
        general_Label2                  matlab.ui.control.Label
        Tab1_infoGrid                   matlab.ui.container.GridLayout
        play_TreeLabel_2                matlab.ui.control.Label
        play_FindPeaks_Tree             matlab.ui.container.Tree
        play_TreeLabel                  matlab.ui.control.Label
        play_Tree                       matlab.ui.container.Tree
        general_RefreshImage            matlab.ui.control.Image
        menu_MainGrid                   matlab.ui.container.GridLayout
        menu_Button3Grid                matlab.ui.container.GridLayout
        menu_Button3Icon                matlab.ui.control.Image
        menu_Button3Label               matlab.ui.control.Label
        menu_Button2Grid                matlab.ui.container.GridLayout
        menu_Button2Icon                matlab.ui.control.Image
        menu_Button2Label               matlab.ui.control.Label
        menu_Button1Grid                matlab.ui.container.GridLayout
        menu_Button1Icon                matlab.ui.control.Image
        menu_Button1Label               matlab.ui.control.Label
        menu_Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        specData

        UIAxes1
        UIAxes2

        restoreView = {[0,1], [0,1], [0,1]}
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            app.UIAxes1 = plotFcn.axesDraw.AxesCreation(app, 'Geographic', tiledlayout(app.plot_GeographicPanel, 1, 1, "Padding", "tight"));
            app.UIAxes2 = plotFcn.axesDraw.AxesCreation(app, 'Cartesian',  tiledlayout(app.plot_CartesianPanel,  1, 1, "Padding", "tight"));
            drawnow
        end


        %-----------------------------------------------------------------%
        function startup_FillFields(app)
            app.xLabelCheckBox.Value              = app.CallingApp.General.Parameters.Axes.xLabel;
            app.yLabelCheckBox.Value              = app.CallingApp.General.Parameters.Axes.yLabel;
            app.xTickLabelCheckBox.Value          = app.CallingApp.General.Parameters.Axes.xTickLabel;
            app.yTickLabelCheckBox.Value          = app.CallingApp.General.Parameters.Axes.yTickLabel;
            app.plotInfo_xGuardBandFactor.Value   = app.CallingApp.General.Parameters.Axes.xGuardBandFactor * 100;
            
            app.MinHold_Fcn.Value                 = app.CallingApp.General.Parameters.MinHold.Fcn;
            app.MinHold_LineStyle.Value           = app.CallingApp.General.Parameters.MinHold.LineStyle;
            app.MinHold_LineWidth.Value           = app.CallingApp.General.Parameters.MinHold.LineWidth;
            app.MinHold_EdgeColor.BackgroundColor = app.CallingApp.General.Parameters.MinHold.EdgeColor;
            app.MinHold_FaceColor.BackgroundColor = app.CallingApp.General.Parameters.MinHold.FaceColor;
            
            app.Average_Fcn.Value                 = app.CallingApp.General.Parameters.Average.Fcn;
            app.Average_LineStyle.Value           = app.CallingApp.General.Parameters.Average.LineStyle;
            app.Average_LineWidth.Value           = app.CallingApp.General.Parameters.Average.LineWidth;
            app.Average_EdgeColor.BackgroundColor = app.CallingApp.General.Parameters.Average.EdgeColor;
            app.Average_FaceColor.BackgroundColor = app.CallingApp.General.Parameters.Average.FaceColor;
            
            app.MaxHold_Fcn.Value                 = app.CallingApp.General.Parameters.MaxHold.Fcn;
            app.MaxHold_LineStyle.Value           = app.CallingApp.General.Parameters.MaxHold.LineStyle;
            app.MaxHold_LineWidth.Value           = app.CallingApp.General.Parameters.MaxHold.LineWidth;
            app.MaxHold_EdgeColor.BackgroundColor = app.CallingApp.General.Parameters.MaxHold.EdgeColor;
            app.MaxHold_FaceColor.BackgroundColor = app.CallingApp.General.Parameters.MaxHold.FaceColor;
            
            app.ROI_Color.BackgroundColor         = app.CallingApp.General.Parameters.ROI.Color;
            app.ROI_FaceAlpha.Value               = app.CallingApp.General.Parameters.ROI.FaceAlpha;
            app.ROI_yPosition.Value             = app.CallingApp.General.Parameters.ROI.yPosition;
            app.ROI_TextColor.BackgroundColor     = app.CallingApp.General.Parameters.ROI.TextColor;
            app.ROI_TextFontSize.Value            = app.CallingApp.General.Parameters.ROI.TextFontSize;
            
            app.Persistance_Interpolation.Value   = app.CallingApp.General.Parameters.Persistance.Interpolation;
            app.Persistance_Colormap.Value        = app.CallingApp.General.Parameters.Persistance.Colormap;
            
            app.Waterfall_Fcn.Value               = app.CallingApp.General.Parameters.Waterfall.Fcn;
            app.Waterfall_View.Value              = app.CallingApp.General.Parameters.Waterfall.View;
            app.Waterfall_MeshStyle.Value         = app.CallingApp.General.Parameters.Waterfall.MeshStyle;
            app.Waterfall_Colormap.Value          = app.CallingApp.General.Parameters.Waterfall.Colormap;

            app.occMinHold_Fcn.Value                 = app.CallingApp.General.Parameters.occMinHold.Fcn;
            app.occMinHold_LineStyle.Value           = app.CallingApp.General.Parameters.occMinHold.LineStyle;
            app.occMinHold_LineWidth.Value           = app.CallingApp.General.Parameters.occMinHold.LineWidth;
            app.occMinHold_EdgeColor.BackgroundColor = app.CallingApp.General.Parameters.occMinHold.EdgeColor;
            app.occMinHold_FaceColor.BackgroundColor = app.CallingApp.General.Parameters.occMinHold.FaceColor;
            
            app.occAverage_Fcn.Value                 = app.CallingApp.General.Parameters.occAverage.Fcn;
            app.occAverage_LineStyle.Value           = app.CallingApp.General.Parameters.occAverage.LineStyle;
            app.occAverage_LineWidth.Value           = app.CallingApp.General.Parameters.occAverage.LineWidth;
            app.occAverage_EdgeColor.BackgroundColor = app.CallingApp.General.Parameters.occAverage.EdgeColor;
            app.occAverage_FaceColor.BackgroundColor = app.CallingApp.General.Parameters.occAverage.FaceColor;
            
            app.occMaxHold_Fcn.Value                 = app.CallingApp.General.Parameters.occMaxHold.Fcn;
            app.occMaxHold_LineStyle.Value           = app.CallingApp.General.Parameters.occMaxHold.LineStyle;
            app.occMaxHold_LineWidth.Value           = app.CallingApp.General.Parameters.occMaxHold.LineWidth;
            app.occMaxHold_EdgeColor.BackgroundColor = app.CallingApp.General.Parameters.occMaxHold.EdgeColor;
            app.occMaxHold_FaceColor.BackgroundColor = app.CallingApp.General.Parameters.occMaxHold.FaceColor;
        end


        %-----------------------------------------------------------------%
        function play_TreeBuilding(app)                                     % Cópiado em 15/12/2023 do winAppAnalise.mlapp

            if ~isempty(app.play_Tree.Children)
                delete(app.play_Tree.Children)
            end
            
            receiverRawList = {app.specData.Receiver};
            [receiverList, ~, receiverIndex] = unique(receiverRawList);            
            for ii = 1:numel(receiverList)
                idx1 = find(receiverIndex == ii)';

                receiverNode = uitreenode(app.play_Tree, 'Text',     fcn.treeReceiverName(receiverList{ii}), ...
                                                         'NodeData', idx1,                                   ...
                                                         'Icon',     fcn.treeNodeIcon('Receiver', receiverList{ii}));
                                
                for jj = idx1
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.specDataTypes)
                        nodeTextNote = '';                        
                    else
                        nodeTextNote = ' (Ocupação)';
                    end

                    dataNode = uitreenode(receiverNode, 'Text', sprintf('ID %d: %.3f - %.3f MHz%s', app.specData(jj).RelatedFiles.ID(1),        ...
                                                                                                    app.specData(jj).MetaData.FreqStart / 1e+6, ...
                                                                                                    app.specData(jj).MetaData.FreqStop  / 1e+6, ...
                                                                                                    nodeTextNote),                              ...
                                                        'NodeData', jj);

                    % TEMPO DE OBSERVAÇÃO
                    uitreenode(dataNode, 'Text', sprintf('%s - %s', datestr(app.specData(jj).Data{1}(1),   'dd/mm/yyyy HH:MM:SS'),  ...
                                                                    datestr(app.specData(jj).Data{1}(end), 'dd/mm/yyyy HH:MM:SS')), ...
                                           'NodeData', jj);
                        
                    % GPS
                    if ~app.specData(jj).GPS.Status
                        uitreenode(dataNode, 'Text',     'GPS', ...
                                             'NodeData', jj,    ...
                                             'Icon',     'LT_redWarn.png');
                    else
                        gpsNode = uitreenode(dataNode, 'Text', sprintf('%.6f, %.6f (%s)', app.specData(jj).GPS.Latitude,  ...
                                                                                          app.specData(jj).GPS.Longitude, ...
                                                                                          app.specData(jj).GPS.Location), ...
                                                       'NodeData', jj);
                        if app.specData(jj).GPS.Status == -1
                            gpsNode.Icon = 'LT_manual.png';
                        end
                    end

                    % OCC
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.specDataTypes)
                        if ~isempty(app.specData(jj).UserData.occMethod.RelatedIndex)
                            occNode = uitreenode(dataNode, 'Text',     'Fluxo(s) de ocupação', ...
                                                           'NodeData', jj);
                            
                            idx2 = app.specData(jj).UserData.occMethod.RelatedIndex;
                            for kk = idx2
                                occChildThreadNode = uitreenode(occNode, 'Text', sprintf('Threshold: %d %s (ID %d)', app.specData(kk).MetaData.Threshold,  ...
                                                                                                                     app.specData(kk).MetaData.LevelUnit,  ...
                                                                                                                     app.specData(kk).RelatedFiles.ID(1)), ...
                                                                         'NodeData', jj);

                                if app.specData(jj).UserData.occMethod.SelectedIndex == kk
                                    occChildThreadNode.Icon = 'LT_selected.png';
                                end                     
                            end

                            occChildPlaybackNode = uitreenode(occNode, 'Text', 'Ocupação a ser aferida', 'NodeData', jj);
                            if isempty(app.specData(jj).UserData.occMethod.SelectedIndex)
                                occChildPlaybackNode.Icon = 'LT_selected.png';
                            end
                        end
                    end
                end
            end
            layoutFcn.treeNodeStyle(app)

            for ii = 1:numel(app.play_Tree.Children)
                expand(app.play_Tree.Children(ii));
            end
        end


        %-----------------------------------------------------------------%
        function play_EmissionList(app, idx, selectedEmission)              % Cópiado em 15/12/2023 do winAppAnalise.mlapp

            if ~isempty(app.play_FindPeaks_Tree.Children)
                delete(app.play_FindPeaks_Tree.Children)
            end

            if ~isempty(app.specData(idx).UserData.Emissions)
                for ii = 1:height(app.specData(idx).UserData.Emissions)
                    if app.specData(idx).UserData.Emissions.isTruncated(ii); Icon = 'play_Truncated.png';
                    else;                                                    Icon = 'play_Untruncated.png';
                    end

                    uitreenode(app.play_FindPeaks_Tree, 'Text', sprintf('%d: %.3f MHz ⌂ %.3f kHz', ii, app.specData(idx).UserData.Emissions{ii,2}, app.specData(idx).UserData.Emissions{ii,3}), ...
                                                        'NodeData', ii, 'Icon', Icon);
                end

                app.play_FindPeaks_Tree.SelectedNodes = app.play_FindPeaks_Tree.Children(selectedEmission);
            end
        end


        %-----------------------------------------------------------------%
        function [rowHeight, hAxes] = general_PlotTypeSelectionChanged(app)
            selectedButton = app.general_ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case {'Drive-test: Route',      ...
                      'Drive-test: Distortion', ...
                      'Drive-test: Heatmap',    ...
                      'RFDataHub: Stations',    ...
                      'RFDataHub: Link'}
                    rowHeight = {'1x', 0};
                    hAxes     = app.UIAxes1;

                case {'Spectrum',         ...
                      'Persistance',      ...
                      'OccupancyPerBin',  ...
                      'OccupancyPerHour', ...
                      'OccupancyPerDay',  ...
                      'SamplesPerLevel',  ...
                      'ChannelPower',     ...
                      'Waterfall', 'TESTE'}
                    rowHeight = {0, '1x'};
                    hAxes     = app.UIAxes2;
            end
        end


        %-----------------------------------------------------------------%
        function Parameters = ParametersVariable(app, emissionIndex)
            Parameters = struct('Plot',        struct('Type',             app.ButtonGroup.SelectedObject.Text,      ...
                                                      'emissionIndex',    emissionIndex),                           ...
                                'Axes',        struct('xLabel',           app.xLabelCheckBox.Value,                 ...
                                                      'yLabel',           app.yLabelCheckBox.Value,                 ...
                                                      'xTickLabel',       app.xTickLabelCheckBox.Value,             ...                                                     
                                                      'yTickLabel',       app.yTickLabelCheckBox.Value,             ...
                                                      'xGuardBandFactor', app.plotInfo_xGuardBandFactor.Value/100), ...
                                'MinHold',     struct('Fcn',              app.MinHold_Fcn.Value,                    ...
                                                      'LineStyle',        app.MinHold_LineStyle.Value,              ...
                                                      'LineWidth',        app.MinHold_LineWidth.Value,              ...
                                                      'EdgeColor',        app.MinHold_EdgeColor.BackgroundColor,    ...
                                                      'FaceColor',        app.MinHold_FaceColor.BackgroundColor),   ...
                                'Average',     struct('Fcn',              app.Average_Fcn.Value,                    ...
                                                      'LineStyle',        app.Average_LineStyle.Value,              ...
                                                      'LineWidth',        app.Average_LineWidth.Value,              ...
                                                      'EdgeColor',        app.Average_EdgeColor.BackgroundColor,    ...
                                                      'FaceColor',        app.Average_FaceColor.BackgroundColor),   ...
                                'MaxHold',     struct('Fcn',              app.MaxHold_Fcn.Value,                    ...
                                                      'LineStyle',        app.MaxHold_LineStyle.Value,              ...
                                                      'LineWidth',        app.MaxHold_LineWidth.Value,              ...
                                                      'EdgeColor',        app.MaxHold_EdgeColor.BackgroundColor,    ...
                                                      'FaceColor',        app.MaxHold_FaceColor.BackgroundColor),   ...
                                'ROI',         struct('Color',            app.ROI_Color.BackgroundColor,            ...
                                                      'FaceAlpha',        app.ROI_FaceAlpha.Value,                  ...
                                                      'yPosition',        app.ROI_yPosition.Value,                  ...
                                                      'TextColor',        app.ROI_TextColor.BackgroundColor,        ...
                                                      'TextFontSize',     app.ROI_TextFontSize.Value),              ...
                                'Persistance', struct('Interpolation',    app.Persistance_Interpolation.Value,      ...
                                                      'Colormap',         app.Persistance_Colormap.Value),          ...
                                'Waterfall',   struct('Fcn',              app.Waterfall_Fcn.Value,                  ...
                                                      'View',             app.Waterfall_View.Value,                 ...
                                                      'MeshStyle',        app.Waterfall_MeshStyle.Value,            ...
                                                      'Colormap',         app.Waterfall_Colormap.Value),            ...
                                'occMinHold',  struct('Fcn',              app.occMinHold_Fcn.Value,                    ...
                                                     'LineStyle',         app.occMinHold_LineStyle.Value,              ...
                                                     'LineWidth',         app.occMinHold_LineWidth.Value,              ...
                                                     'EdgeColor',         app.occMinHold_EdgeColor.BackgroundColor,    ...
                                                     'FaceColor',         app.occMinHold_FaceColor.BackgroundColor),   ...
                                'occAverage',  struct('Fcn',              app.occAverage_Fcn.Value,                    ...
                                                     'LineStyle',         app.occAverage_LineStyle.Value,              ...
                                                     'LineWidth',         app.occAverage_LineWidth.Value,              ...
                                                     'EdgeColor',         app.occAverage_EdgeColor.BackgroundColor,    ...
                                                     'FaceColor',         app.occAverage_FaceColor.BackgroundColor),   ...
                                'occMaxHold',  struct('Fcn',              app.occMaxHold_Fcn.Value,                    ...
                                                     'LineStyle',         app.occMaxHold_LineStyle.Value,              ...
                                                     'LineWidth',         app.occMaxHold_LineWidth.Value,              ...
                                                     'EdgeColor',         app.occMaxHold_EdgeColor.BackgroundColor,    ...
                                                     'FaceColor',         app.occMaxHold_FaceColor.BackgroundColor));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = mainapp.RootFolder;
            app.specData   = mainapp.specData;
            
            fcn.winPosition(app)
            app.UIFigure.WindowState = 'maximized';

            layout_LeftPanelSelectedTabControl(app, struct('Source', app.menu_Button1Icon))
            general_ButtonGroupSelectionChanged(app)

            startup_AxesCreation(app)
            startup_FillFields(app)

            play_TreeBuilding(app)
            app.play_Tree.SelectedNodes = app.play_Tree.Children(1).Children(1);
            play_TreeSelectionChanged(app)
            play_FindPeaks_TreeSelectionChanged(app)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWin9 = [];
            delete(app)
            
        end

        % Image clicked function: tool_resetButton
        function resetFcn(app, event)
            
            msg = ['<font style="font-size:12;">Essa funcionalidade possibilita reinicializar alguns dos parâmetros do <i>app</i>, ' ...
                   'como a INTERAÇÃO com os plots, a exclusão de edições manuais na lista dos PONTOS DE MONITORAÇÃO e FILTROS.</font>'];
            selection = uiconfirm(app.UIFigure, msg, '', 'Options', {'Interação', 'Pontos', 'Filtros', 'Cancelar'}, 'DefaultOption', 1, 'Icon', 'question', 'Interpreter', 'html');

            if strcmp(selection, 'Cancelar')
                return
            end

            switch selection
                case 'Interação'
                    disableDefaultInteractivity(app.UIAxes1)
                    disableDefaultInteractivity(app.UIAxes2)
                    disableDefaultInteractivity(app.UIAxes3)
                    disableDefaultInteractivity(app.UIAxes4)
                    pause(.1)

                    enableDefaultInteractivity(app.UIAxes1)
                    enableDefaultInteractivity(app.UIAxes2)
                    enableDefaultInteractivity(app.UIAxes3)
                    enableDefaultInteractivity(app.UIAxes4)

                case 'Pontos'
                    points_refreshImageClicked(app)

                case 'Filtros'
                    filter_delFilter(app, struct('Source', app.filter_delAllButton))
            end

        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 1 other component
        function layout_LeftPanelSelectedTabControl(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            rowHeight        = repmat({0}, 1, NN);
            rowHeight(idx)   = {'1x'};

            app.menu_MainGrid.ColumnWidth      = columnWidth;
            app.menu_Image.Layout.Column       = idx;
            app.LeftPanelGrid.RowHeight(2:end) = rowHeight;
            
        end

        % Image clicked function: LeftPanelVisibility
        function layout_LeftPanelVisibilityControl(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.LeftPanelVisibility.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

        end

        % Selection changed function: general_ButtonGroup
        function general_ButtonGroupSelectionChanged(app, event)
            
            rowHeight = general_PlotTypeSelectionChanged(app);
            app.Document.RowHeight = rowHeight;
            
        end

        % Button pushed function: tool_playButton
        function tool_playButtonPushed(app, event)
            
            plotType   = app.general_ButtonGroup.SelectedObject.Text;
            [~, hAxes] = general_PlotTypeSelectionChanged(app);

            idx1 = app.play_Tree.SelectedNodes.NodeData;
            idx2 = -1;
            if strcmp(app.ButtonGroup.SelectedObject.Text, 'Emission')
                if ~isempty(app.play_FindPeaks_Tree.SelectedNodes)
                    idx2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;
                end                
            end
            
            plotFcn.axesDraw.execute(plotType, hAxes, app.specData(idx1), ParametersVariable(app, idx2))

            if isa(hAxes, 'matlab.ui.control.UIAxes')
                app.restoreView = {hAxes.XLim, hAxes.YLim, hAxes.ZLim};
            end

        end

        % Button pushed function: Average_EdgeColor, Average_FaceColor, 
        % ...and 12 other components
        function colorCallbacks(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                event.Source.BackgroundColor = selectedColor;
            end

        end

        % Selection changed function: play_Tree
        function play_TreeSelectionChanged(app, event)
            
            if ~isempty(app.play_Tree.SelectedNodes)
                idx = app.play_Tree.SelectedNodes.NodeData;
                
                play_EmissionList(app, idx, 1)
                play_FindPeaks_TreeSelectionChanged(app)
            end

        end

        % Selection changed function: play_FindPeaks_Tree
        function play_FindPeaks_TreeSelectionChanged(app, event)
            
            if ~isempty(app.play_Tree.SelectedNodes)
                idx = app.play_Tree.SelectedNodes.NodeData;
                msg = sprintf('%s\nID%d: %.3f - %.3f MHz', app.specData(idx).Receiver,                  ...
                                                           app.specData(idx).RelatedFiles.ID(1),        ...
                                                           app.specData(idx).MetaData.FreqStart / 1e+6, ...
                                                           app.specData(idx).MetaData.FreqStop  / 1e+6);
                
                if ~isempty(app.play_FindPeaks_Tree.SelectedNodes)
                    msg  = sprintf('%s @ %s', msg, app.play_FindPeaks_Tree.SelectedNodes.Text);
                end

                app.tool_SelectedEmision.Text = msg;
            else
                app.tool_SelectedEmision.Text = '';
            end
            
        end

        % Image clicked function: general_RefreshImage
        function general_RefreshImageClicked(app, event)
            
            play_TreeBuilding(app)
            play_TreeSelectionChanged(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Análise gráfica';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.Grid);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.menu_MainGrid = uigridlayout(app.LeftPanelGrid);
            app.menu_Image = uiimage(app.menu_MainGrid);
            app.menu_Image.ImageSource = 'LT_LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'Aspectos gerais';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'LT_Dots6.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'Customização';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'LT_Filter2.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = '(...)';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'LT_connect2.png';
            app.Tab1_infoGrid = uigridlayout(app.LeftPanelGrid);
            app.general_RefreshImage = uiimage(app.Tab1_infoGrid);
            app.general_RefreshImage.ImageClickedFcn = createCallbackFcn(app, @general_RefreshImageClicked, true);
            app.general_RefreshImage.ImageSource = 'LT_reload.png';
            app.play_Tree = uitree(app.Tab1_infoGrid);
            app.play_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_TreeSelectionChanged, true);
            app.play_TreeLabel = uilabel(app.Tab1_infoGrid);
            app.play_TreeLabel.Text = 'Fluxos espectrais';
            app.play_FindPeaks_Tree = uitree(app.Tab1_infoGrid);
            app.play_FindPeaks_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_TreeSelectionChanged, true);
            app.play_TreeLabel_2 = uilabel(app.Tab1_infoGrid);
            app.play_TreeLabel_2.Text = 'Emissões';
            app.GridLayout = uigridlayout(app.LeftPanelGrid);
            app.general_Label2 = uilabel(app.GridLayout);
            app.general_Label2.Text = 'Plot';
            app.general_ButtonGroup = uibuttongroup(app.GridLayout);
            app.general_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @general_ButtonGroupSelectionChanged, true);
            app.general_Sublabel1 = uilabel(app.general_ButtonGroup);
            app.general_Sublabel1.Text = 'Eixo cartesiano:';
            app.general_Sublabel2 = uilabel(app.general_ButtonGroup);
            app.general_Sublabel2.Text = 'Eixo geográfico:';
            app.general_RadioButton1 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton1.Text = 'Spectrum';
            app.general_RadioButton1.Value = true;
            app.general_RadioButton2 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton2.Text = 'Persistance';
            app.general_RadioButton3 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton3.Text = 'OccupancyPerBin';
            app.general_RadioButton4 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton4.Enable = 'off';
            app.general_RadioButton4.Text = 'OccupancyPerHour';
            app.general_RadioButton5 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton5.Enable = 'off';
            app.general_RadioButton5.Text = 'OccupancyPerDay';
            app.general_RadioButton6 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton6.Enable = 'off';
            app.general_RadioButton6.Text = 'SamplesPerLevel';
            app.general_RadioButton7 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton7.Enable = 'off';
            app.general_RadioButton7.Text = 'ChannelPower';
            app.general_RadioButton8 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton8.Text = 'Waterfall';
            app.general_RadioButton9 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton9.Enable = 'off';
            app.general_RadioButton9.Text = 'Drive-test: Route';
            app.general_RadioButton10 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton10.Enable = 'off';
            app.general_RadioButton10.Text = 'Drive-test: Distortion';
            app.general_RadioButton11 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton11.Enable = 'off';
            app.general_RadioButton11.Text = 'Drive-test: Heatmap';
            app.general_RadioButton12 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton12.Enable = 'off';
            app.general_RadioButton12.Text = 'RFDataHub: Stations';
            app.general_RadioButton13 = uiradiobutton(app.general_ButtonGroup);
            app.general_RadioButton13.Enable = 'off';
            app.general_RadioButton13.Text = 'RFDataHub: Link';
            app.general_SpecificationPanel = uipanel(app.GridLayout);
            app.general_SpecificationGrid = uigridlayout(app.general_SpecificationPanel);
            app.plotInfo_Label = uilabel(app.general_SpecificationGrid);
            app.plotInfo_Label.Text = 'Tipo de plot';
            app.ROI_Label = uilabel(app.general_SpecificationGrid);
            app.ROI_Label.Text = 'ROI';
            app.Persistance_Label = uilabel(app.general_SpecificationGrid);
            app.Persistance_Label.Text = 'Persistência';
            app.Persistance_Panel = uipanel(app.general_SpecificationGrid);
            app.Persistance_Grid = uigridlayout(app.Persistance_Panel);
            app.Persistance_InterpolationLabel = uilabel(app.Persistance_Grid);
            app.Persistance_InterpolationLabel.Text = 'Interpolação:';
            app.Persistance_Interpolation = uidropdown(app.Persistance_Grid);
            app.Persistance_Interpolation.Items = {'nearest', 'bilinear'};
            app.Persistance_Interpolation.Value = 'nearest';
            app.Persistance_ColormapLabel = uilabel(app.Persistance_Grid);
            app.Persistance_ColormapLabel.Text = 'Mapa de cor:';
            app.Persistance_Colormap = uidropdown(app.Persistance_Grid);
            app.Persistance_Colormap.Items = {'autumn', 'bone', 'cool', 'copper', 'gray', 'hot', 'hsv', 'jet', 'parula', 'pink', 'sky', 'spring', 'summer', 'turbo', 'winter'};
            app.Persistance_Colormap.Value = 'winter';
            app.Waterfall_Label = uilabel(app.general_SpecificationGrid);
            app.Waterfall_Label.Text = 'Waterfall';
            app.Waterfall_Panel = uipanel(app.general_SpecificationGrid);
            app.Waterfall_Grid = uigridlayout(app.Waterfall_Panel);
            app.Waterfall_FcnLabel = uilabel(app.Waterfall_Grid);
            app.Waterfall_FcnLabel.Text = 'Função:';
            app.Waterfall_Fcn = uidropdown(app.Waterfall_Grid);
            app.Waterfall_Fcn.Items = {'image', 'mesh'};
            app.Waterfall_Fcn.Value = 'image';
            app.Waterfall_ViewLabel = uilabel(app.Waterfall_Grid);
            app.Waterfall_ViewLabel.Text = 'Orientação:';
            app.Waterfall_View = uidropdown(app.Waterfall_Grid);
            app.Waterfall_View.Items = {'horizontal', 'vertical'};
            app.Waterfall_View.Value = 'vertical';
            app.Waterfall_MeshStyleLabel = uilabel(app.Waterfall_Grid);
            app.Waterfall_MeshStyleLabel.Text = 'Direção:';
            app.Waterfall_MeshStyle = uidropdown(app.Waterfall_Grid);
            app.Waterfall_MeshStyle.Items = {'both', 'row'};
            app.Waterfall_MeshStyle.Value = 'both';
            app.Waterfall_ColormapLabel = uilabel(app.Waterfall_Grid);
            app.Waterfall_ColormapLabel.Text = 'Mapa de cor:';
            app.Waterfall_Colormap = uidropdown(app.Waterfall_Grid);
            app.Waterfall_Colormap.Items = {'autumn', 'bone', 'cool', 'copper', 'gray', 'hot', 'hsv', 'jet', 'parula', 'pink', 'sky', 'spring', 'summer', 'turbo', 'winter'};
            app.Waterfall_Colormap.Value = 'winter';
            app.MinHold_Label_2 = uilabel(app.general_SpecificationGrid);
            app.MinHold_Label_2.Text = 'Curvas ordinárias';
            app.Panel = uipanel(app.general_SpecificationGrid);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.MinHold_Label = uilabel(app.GridLayout2);
            app.MinHold_Label.Text = 'MinHold';
            app.MinHold_Fcn = uidropdown(app.GridLayout2);
            app.MinHold_Fcn.Items = {'area', 'line'};
            app.MinHold_Fcn.Value = 'line';
            app.MinHold_LineStyle = uidropdown(app.GridLayout2);
            app.MinHold_LineStyle.Items = {'none', ':', '-.', '-'};
            app.MinHold_LineStyle.Value = '-';
            app.MinHold_Panel = uipanel(app.GridLayout2);
            app.MinHold_Grid = uigridlayout(app.MinHold_Panel);
            app.MinHold_EdgeColor = uibutton(app.MinHold_Grid, 'push');
            app.MinHold_EdgeColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.MinHold_EdgeColor.Text = '';
            app.MinHold_FaceColor = uibutton(app.MinHold_Grid, 'push');
            app.MinHold_FaceColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.MinHold_FaceColor.Text = '';
            app.MinHold_LineWidth = uislider(app.MinHold_Grid);
            app.MinHold_LineWidth.Limits = [0.5 2];
            app.MinHold_LineWidth.MajorTicks = [];
            app.MinHold_LineWidth.MajorTickLabels = {''};
            app.MinHold_LineWidth.MinorTicks = [];
            app.MinHold_LineWidth.Value = 0.5;
            app.Average_Label = uilabel(app.GridLayout2);
            app.Average_Label.Text = 'Average';
            app.Average_Fcn = uidropdown(app.GridLayout2);
            app.Average_Fcn.Items = {'area', 'line'};
            app.Average_Fcn.Value = 'line';
            app.Average_LineStyle = uidropdown(app.GridLayout2);
            app.Average_LineStyle.Items = {'none', ':', '-.', '-'};
            app.Average_LineStyle.Value = '-';
            app.Average_Panel = uipanel(app.GridLayout2);
            app.Average_Grid = uigridlayout(app.Average_Panel);
            app.Average_EdgeColor = uibutton(app.Average_Grid, 'push');
            app.Average_EdgeColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.Average_EdgeColor.Text = '';
            app.Average_FaceColor = uibutton(app.Average_Grid, 'push');
            app.Average_FaceColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.Average_FaceColor.Text = '';
            app.Average_LineWidth = uislider(app.Average_Grid);
            app.Average_LineWidth.Limits = [0.5 2];
            app.Average_LineWidth.MajorTicks = [];
            app.Average_LineWidth.MajorTickLabels = {''};
            app.Average_LineWidth.MinorTicks = [];
            app.Average_LineWidth.Value = 0.5;
            app.MaxHold_Label = uilabel(app.GridLayout2);
            app.MaxHold_Label.Text = 'MaxHold';
            app.MaxHold_Fcn = uidropdown(app.GridLayout2);
            app.MaxHold_Fcn.Items = {'area', 'line'};
            app.MaxHold_Fcn.Value = 'line';
            app.MaxHold_LineStyle = uidropdown(app.GridLayout2);
            app.MaxHold_LineStyle.Items = {'none', ':', '-.', '-'};
            app.MaxHold_LineStyle.Value = '-';
            app.MaxHold_Panel = uipanel(app.GridLayout2);
            app.MaxHold_Grid = uigridlayout(app.MaxHold_Panel);
            app.MaxHold_EdgeColor = uibutton(app.MaxHold_Grid, 'push');
            app.MaxHold_EdgeColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.MaxHold_EdgeColor.Text = '';
            app.MaxHold_FaceColor = uibutton(app.MaxHold_Grid, 'push');
            app.MaxHold_FaceColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.MaxHold_FaceColor.Text = '';
            app.MaxHold_LineWidth = uislider(app.MaxHold_Grid);
            app.MaxHold_LineWidth.Limits = [0.5 2];
            app.MaxHold_LineWidth.MajorTicks = [];
            app.MaxHold_LineWidth.MajorTickLabels = {''};
            app.MaxHold_LineWidth.MinorTicks = [];
            app.MaxHold_LineWidth.Value = 0.5;
            app.plotInfo_Label_2 = uilabel(app.general_SpecificationGrid);
            app.plotInfo_Label_2.Text = 'Eixo';
            app.plotInfo_Panel_2 = uipanel(app.general_SpecificationGrid);
            app.plotInfo_Grid_2 = uigridlayout(app.plotInfo_Panel_2);
            app.xLabelCheckBox = uicheckbox(app.plotInfo_Grid_2);
            app.xLabelCheckBox.Text = 'xLabel';
            app.yLabelCheckBox = uicheckbox(app.plotInfo_Grid_2);
            app.yLabelCheckBox.Text = 'yLabel';
            app.yTickLabelCheckBox = uicheckbox(app.plotInfo_Grid_2);
            app.yTickLabelCheckBox.Text = 'yTickLabel';
            app.xTickLabelCheckBox = uicheckbox(app.plotInfo_Grid_2);
            app.xTickLabelCheckBox.Text = 'xTickLabel';
            app.plotInfo_xGuardBandFactorLabel = uilabel(app.plotInfo_Grid_2);
            app.plotInfo_xGuardBandFactorLabel.Text = {'Banda de '; 'guarda (%):'};
            app.plotInfo_xGuardBandFactor = uispinner(app.plotInfo_Grid_2);
            app.plotInfo_xGuardBandFactor.Step = 10;
            app.plotInfo_xGuardBandFactor.Limits = [0 1000];
            app.plotInfo_xGuardBandFactor.RoundFractionalValues = 'on';
            app.plotInfo_xGuardBandFactor.ValueDisplayFormat = '%.0f';
            app.plotInfo_xGuardBandFactor.Value = 100;
            app.ButtonGroup = uibuttongroup(app.general_SpecificationGrid);
            app.BandButton = uiradiobutton(app.ButtonGroup);
            app.BandButton.Text = 'Band';
            app.BandButton.Value = true;
            app.EmissionButton = uiradiobutton(app.ButtonGroup);
            app.EmissionButton.Text = 'Emission';
            app.Panel_2 = uipanel(app.general_SpecificationGrid);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.plotInfo_xGuardBandFactorLabel_2 = uilabel(app.GridLayout3);
            app.plotInfo_xGuardBandFactorLabel_2.Text = 'FaceAlpha:';
            app.ROI_FaceAlpha = uispinner(app.GridLayout3);
            app.ROI_FaceAlpha.Step = 0.1;
            app.ROI_FaceAlpha.Limits = [0 1];
            app.ROI_FaceAlpha.ValueDisplayFormat = '%.2f';
            app.ROI_FaceAlpha.Value = 0.2;
            app.ROI_Panel = uipanel(app.GridLayout3);
            app.ROI_Grid = uigridlayout(app.ROI_Panel);
            app.ROI_TextColor = uibutton(app.ROI_Grid, 'push');
            app.ROI_TextColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.ROI_TextColor.Text = '';
            app.ROI_Color = uibutton(app.ROI_Grid, 'push');
            app.ROI_Color.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.ROI_Color.Text = '';
            app.ROI_TextFontSize = uislider(app.ROI_Grid);
            app.ROI_TextFontSize.Limits = [8 12];
            app.ROI_TextFontSize.MajorTicks = [];
            app.ROI_TextFontSize.MajorTickLabels = {''};
            app.ROI_TextFontSize.MinorTicks = [];
            app.ROI_TextFontSize.Value = 10;
            app.ROI_yPositionLabel = uilabel(app.GridLayout3);
            app.ROI_yPositionLabel.Text = 'yPosition (dB):';
            app.ROI_yPosition = uispinner(app.GridLayout3);
            app.ROI_yPosition.Limits = [0 Inf];
            app.ROI_yPosition.RoundFractionalValues = 'on';
            app.ROI_yPosition.ValueDisplayFormat = '%d';
            app.ROI_yPosition.Value = 1;
            app.Waterfall_Label_2 = uilabel(app.general_SpecificationGrid);
            app.Waterfall_Label_2.Text = 'Ocupação';
            app.Panel_3 = uipanel(app.general_SpecificationGrid);
            app.GridLayout2_2 = uigridlayout(app.Panel_3);
            app.MinHold_Label_3 = uilabel(app.GridLayout2_2);
            app.MinHold_Label_3.Text = 'MinHold';
            app.occMinHold_Fcn = uidropdown(app.GridLayout2_2);
            app.occMinHold_Fcn.Items = {'area', 'line'};
            app.occMinHold_Fcn.Value = 'line';
            app.occMinHold_LineStyle = uidropdown(app.GridLayout2_2);
            app.occMinHold_LineStyle.Items = {'none', ':', '-.', '-'};
            app.occMinHold_LineStyle.Value = '-';
            app.MinHold_Panel_2 = uipanel(app.GridLayout2_2);
            app.MinHold_Grid_2 = uigridlayout(app.MinHold_Panel_2);
            app.occMinHold_EdgeColor = uibutton(app.MinHold_Grid_2, 'push');
            app.occMinHold_EdgeColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.occMinHold_EdgeColor.Text = '';
            app.occMinHold_FaceColor = uibutton(app.MinHold_Grid_2, 'push');
            app.occMinHold_FaceColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.occMinHold_FaceColor.Text = '';
            app.occMinHold_LineWidth = uislider(app.MinHold_Grid_2);
            app.occMinHold_LineWidth.Limits = [0.5 2];
            app.occMinHold_LineWidth.MajorTicks = [];
            app.occMinHold_LineWidth.MajorTickLabels = {''};
            app.occMinHold_LineWidth.MinorTicks = [];
            app.occMinHold_LineWidth.Value = 0.5;
            app.Average_Label_2 = uilabel(app.GridLayout2_2);
            app.Average_Label_2.Text = 'Average';
            app.occAverage_Fcn = uidropdown(app.GridLayout2_2);
            app.occAverage_Fcn.Items = {'area', 'line'};
            app.occAverage_Fcn.Value = 'line';
            app.occAverage_LineStyle = uidropdown(app.GridLayout2_2);
            app.occAverage_LineStyle.Items = {'none', ':', '-.', '-'};
            app.occAverage_LineStyle.Value = '-';
            app.Average_Panel_2 = uipanel(app.GridLayout2_2);
            app.Average_Grid_2 = uigridlayout(app.Average_Panel_2);
            app.occAverage_EdgeColor = uibutton(app.Average_Grid_2, 'push');
            app.occAverage_EdgeColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.occAverage_EdgeColor.Text = '';
            app.occAverage_FaceColor = uibutton(app.Average_Grid_2, 'push');
            app.occAverage_FaceColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.occAverage_FaceColor.Text = '';
            app.occAverage_LineWidth = uislider(app.Average_Grid_2);
            app.occAverage_LineWidth.Limits = [0.5 2];
            app.occAverage_LineWidth.MajorTicks = [];
            app.occAverage_LineWidth.MajorTickLabels = {''};
            app.occAverage_LineWidth.MinorTicks = [];
            app.occAverage_LineWidth.Value = 0.5;
            app.MaxHold_Label_2 = uilabel(app.GridLayout2_2);
            app.MaxHold_Label_2.Text = 'MaxHold';
            app.occMaxHold_Fcn = uidropdown(app.GridLayout2_2);
            app.occMaxHold_Fcn.Items = {'area', 'line'};
            app.occMaxHold_Fcn.Value = 'line';
            app.occMaxHold_LineStyle = uidropdown(app.GridLayout2_2);
            app.occMaxHold_LineStyle.Items = {'none', ':', '-.', '-'};
            app.occMaxHold_LineStyle.Value = '-';
            app.MaxHold_Panel_3 = uipanel(app.GridLayout2_2);
            app.MaxHold_Grid_3 = uigridlayout(app.MaxHold_Panel_3);
            app.occMaxHold_EdgeColor = uibutton(app.MaxHold_Grid_3, 'push');
            app.occMaxHold_EdgeColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.occMaxHold_EdgeColor.Text = '';
            app.occMaxHold_FaceColor = uibutton(app.MaxHold_Grid_3, 'push');
            app.occMaxHold_FaceColor.ButtonPushedFcn = createCallbackFcn(app, @colorCallbacks, true);
            app.occMaxHold_FaceColor.Text = '';
            app.occMaxHold_LineWidth = uislider(app.MaxHold_Grid_3);
            app.occMaxHold_LineWidth.Limits = [0.5 2];
            app.occMaxHold_LineWidth.MajorTicks = [];
            app.occMaxHold_LineWidth.MajorTickLabels = {''};
            app.occMaxHold_LineWidth.MinorTicks = [];
            app.occMaxHold_LineWidth.Value = 0.5;
            app.LeftPanelVisibility = uiimage(app.Grid);
            app.LeftPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelVisibilityControl, true);
            app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
            app.Document = uigridlayout(app.Grid);
            app.plot_GeographicPanel = uipanel(app.Document);
            app.plot_CartesianPanel = uipanel(app.Document);
            app.toolGrid = uigridlayout(app.Grid);
            app.tool_playButton = uibutton(app.toolGrid, 'push');
            app.tool_playButton.ButtonPushedFcn = createCallbackFcn(app, @tool_playButtonPushed, true);
            app.tool_playButton.Tag = 'REPORT';
            app.tool_playButton.Icon = 'Run_24.png';
            app.tool_playButton.Tooltip = {''};
            app.tool_playButton.Text = '';
            app.tool_Serarator = uiimage(app.toolGrid);
            app.tool_Serarator.Enable = 'off';
            app.tool_Serarator.ImageSource = 'LT_LineV.png';
            app.tool_SelectedEmision = uilabel(app.toolGrid);
            app.tool_SelectedEmision.Text = {'R&S FSL'; 'ID1: 30.000 - 108.000 MHz @ 103.500 MHz ⌂ 256.000 kHz'};
            app.tool_resetButton = uiimage(app.toolGrid);
            app.tool_resetButton.ImageClickedFcn = createCallbackFcn(app, @resetFcn, true);
            app.tool_resetButton.ImageSource = 'LT_Dots.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winGraphicAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end