classdef winMiscEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        tool_MiscRefresh_2      matlab.ui.control.Image
        tool_MiscCancel_2       matlab.ui.control.Image
        tool_MiscEdit_2         matlab.ui.control.Image
        tool_MiscRefresh        matlab.ui.control.Image
        tool_MiscCancel         matlab.ui.control.Image
        tool_MiscEdit           matlab.ui.control.Image
        Panel                   matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        Label                   matlab.ui.control.Label
        edit_unitPanel          matlab.ui.container.Panel
        misc_Edit_unitGrid      matlab.ui.container.GridLayout
        edit_unitRefresh        matlab.ui.control.Image
        misc_Edit_kFactorTree   matlab.ui.container.Tree
        misc_Edit_kFactorAdd    matlab.ui.control.Image
        misc_Edit_kFactor       matlab.ui.control.DropDown
        misc_Edit_kFactorLabel  matlab.ui.control.Label
        misc_Edit_unitRaw       matlab.ui.control.EditField
        misc_Edit_unitRawLabel  matlab.ui.control.Label
        edit_gpsPanel           matlab.ui.container.Panel
        edit_gpsGrid            matlab.ui.container.GridLayout
        edit_gpsCityButton      matlab.ui.control.Image
        edit_gpsCityPanel       matlab.ui.container.Panel
        edit_gpsCityGrid        matlab.ui.container.GridLayout
        edit_gpsCity            matlab.ui.control.EditField
        edit_gpsCitySource      matlab.ui.control.EditField
        edit_gpsLocationLabel2  matlab.ui.control.Label
        edit_gpsLocationPanel   matlab.ui.container.Panel
        edit_gpsLocationGrid    matlab.ui.container.GridLayout
        edit_gpsLongitude       matlab.ui.control.NumericEditField
        edit_gpsLatitude        matlab.ui.control.NumericEditField
        edit_gpsSource          matlab.ui.control.EditField
        edit_gpsLocationLabel1  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

                misc_gpsLayout(app, '')
                misc_unitLevelLayout(app)


            % Painel "MISC >> antennaKFactor"
            measCalibration = jsondecode(fileread(fullfile(app.rootFolder, 'Settings', 'measCalibration.json')));
            app.misc_Edit_kFactor.Items = {measCalibration.Name};


        %-----------------------------------------------------------------%
        % EDIT
        %-----------------------------------------------------------------%
        function SelectedNodesTextList = misc_SelectedNodesText(app)
            SelectedNodesTextList = {};
            for ii = 1:numel(app.play_Tree.SelectedNodes)
                generation = misc_findGenerationOfTreeNode(app, app.play_Tree.SelectedNodes(ii));

                switch generation
                    case 0
                        NN = numel(app.play_Tree.SelectedNodes(ii).Children);
                        SelectedNodesTextList(end+1:end+NN) = {app.play_Tree.SelectedNodes(ii).Text};
                    case 1
                        SelectedNodesTextList{end+1} = app.play_Tree.SelectedNodes(ii).Text;
                    case 2
                        SelectedNodesTextList{end+1} = app.play_Tree.SelectedNodes(ii).Parent.Text;
                    case 3
                        SelectedNodesTextList{end+1} = app.play_Tree.SelectedNodes(ii).Parent.Parent.Text;
                end

            end
            SelectedNodesTextList = unique(SelectedNodesTextList);
        end

        %-----------------------------------------------------------------%
        function idx = misc_gpsFindIndex(app)

            % Identifica os fluxos espectrais selecionados, verificando se 
            % o local de monitoração que gerou a informação é único.
            idx = unique([app.play_Tree.SelectedNodes.NodeData]);

            if ~isempty(idx)
                gpsArray = arrayfun(@(x) x.GPS, app.specData(idx));
                for ii = numel(gpsArray):-1:2
                    if isequal(gpsArray(ii), gpsArray(1))
                        gpsArray(ii) = [];
                    end
                end

                if numel(gpsArray) > 1
                    idx = [];                
                end
            end
        end

        %-----------------------------------------------------------------%
        function misc_gpsLayout(app, clickEffect)
            idx = misc_gpsFindIndex(app);
            SelectedNodesTextList = misc_SelectedNodesText(app);
            
            if ~isempty(idx)    
                % gpsPanel
                switch app.specData(idx(1)).GPS.Status
                    case -1;   app.edit_gpsSource.Value = 'manual';
                    otherwise; app.edit_gpsSource.Value = 'auto';
                end
                app.edit_gpsLatitude.Value    = app.specData(idx(1)).GPS.Latitude;
                app.edit_gpsLongitude.Value   = app.specData(idx(1)).GPS.Longitude;                
    
                % gpsCityPanel
                app.edit_gpsCitySource.Value  = app.specData(idx(1)).GPS.LocationSource;
                app.edit_gpsCity.Value        = app.specData(idx(1)).GPS.Location;

                app.tool_MiscEdit.Enable     = 1;
                app.edit_gpsCityButton.Enable = 1;

                switch clickEffect
                    case 'City'
                        hComp = [app.edit_gpsCitySource, ...
                                 app.edit_gpsCity];
                        play_TreeRebuilding(app, SelectedNodesTextList, hComp)

                    case 'City+Latitude+Longitude'
                        hComp = [app.edit_gpsSource,     ...
                                 app.edit_gpsLatitude,   ...
                                 app.edit_gpsLongitude,  ...
                                 app.edit_gpsCitySource, ...
                                 app.edit_gpsCity];
                        play_TreeRebuilding(app, SelectedNodesTextList, hComp)

                    case 'Latitude+Longitude'
                        hComp = [app.edit_gpsSource,     ...
                                 app.edit_gpsLatitude,   ...
                                 app.edit_gpsLongitude];
                        play_TreeRebuilding(app, SelectedNodesTextList, hComp)
                end                
            else
                app.edit_gpsSource.Value      = '';
                app.edit_gpsLatitude.Value    = -1;
                app.edit_gpsLongitude.Value   = -1;
                app.edit_gpsCitySource.Value  = '';
                app.edit_gpsCity.Value        = '';

                app.tool_MiscEdit.Enable     = 0;
                app.edit_gpsCityButton.Enable = 0;
            end

            if ismember('manual', {app.edit_gpsSource.Value, app.edit_gpsCitySource.Value})
                app.tool_MiscRefresh.Enable = 1;
            else
                app.tool_MiscRefresh.Enable = 0;
            end

            set(findobj(app.edit_gpsLocationGrid, '-not', {'Type', 'uilabel', '-or', 'Type', 'uibutton', '-or', 'Type', 'uigridlayout'}), Editable=0)
            set(findobj(app.edit_gpsCityGrid,     '-not', {'Type', 'uilabel', '-or', 'Type', 'uibutton', '-or', 'Type', 'uigridlayout'}), Editable=0)
        end

        %-----------------------------------------------------------------%
        function misc_gpsUpdate(app, idx, Latitude, Longitude)
            app.specData(idx).GPS.Status        = -1;
            app.specData(idx).GPS.Count         = 1;
            app.specData(idx).GPS.Latitude      = Latitude;
            app.specData(idx).GPS.Longitude     = Longitude;
            app.specData(idx).GPS.Latitude_std  = 0;
            app.specData(idx).GPS.Longitude_std = 0;
            app.specData(idx).GPS.stdRange      = [100 100 100];
        end

        %-----------------------------------------------------------------%
        function misc_unitLevelLayout(app)
            if ~isempty(app.misc_Edit_kFactorTree.Children)
                delete(app.misc_Edit_kFactorTree.Children)
            end

            idx = unique([app.play_Tree.SelectedNodes.NodeData]);

            if isscalar(idx)
                measCalibration = app.specData(idx).UserData.measCalibration;
                if ~isempty(measCalibration)
                    app.misc_Edit_unitRaw.Value = measCalibration.oldUnitLevel{1};

                    for ii = 1:height(measCalibration)
                        uitreenode(app.misc_Edit_kFactorTree, 'Text', sprintf('%s → %s: "%s"', measCalibration.oldUnitLevel{ii}, ...
                                                                                               measCalibration.newUnitLevel{ii}, ...
                                                                                               measCalibration.Name{ii}),        ...
                                                              'NodeData', ii, 'ContextMenu', app.edit_ContextMenu);
                    end
                else
                    app.misc_Edit_unitRaw.Value = app.specData(idx).MetaData.LevelUnit;
                end
                app.misc_Edit_kFactorAdd.Enable = 1;
            else
                app.misc_Edit_unitRaw.Value     = '';
                app.misc_Edit_kFactorAdd.Enable = 0;
            end

            if ~isempty(app.misc_Edit_kFactorTree.Children)
                app.edit_unitRefresh.Enable = 1;
            else
                app.edit_unitRefresh.Enable = 0;
            end
        end

        %-----------------------------------------------------------------%
        function misc_unitLevelCorrection(app, OperationType, idx1, idx2, Name)
            try
                fcn.measCalibration(app, OperationType, idx1, idx2, Name)
            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', ME.message);
            end
        end

        end

        % Image clicked function: tool_MiscEdit_2, tool_MiscRefresh_2
        function misc_gpsCallbacks(app, event)
            
            idx = misc_gpsFindIndex(app);
            if isempty(idx)
                return
            end

            switch event.Source
                %---------------------------------------------------------%
                case app.tool_MiscRefresh
                    fcn.gpsProperty(app.specData, idx)
                    misc_gpsLayout(app, 'City+Latitude+Longitude')
                
                %---------------------------------------------------------%
                case app.tool_MiscEdit
                    app.edit_gpsLatitude.Editable  = 1;
                    app.edit_gpsLongitude.Editable = 1;
                    focus(app.edit_gpsLatitude)

                %---------------------------------------------------------%
                case app.edit_gpsLatitude
                    focus(app.edit_gpsLongitude)
                    
                %---------------------------------------------------------%
                case app.edit_gpsLongitude
                
                    gps = struct('Latitude',  app.edit_gpsLatitude.Value, ...
                                 'Longitude', app.edit_gpsLongitude.Value);

                    if ~strcmp(app.edit_gpsCitySource.Value, 'manual')
                        [cityName, ~, locInfo] = fcn.gpsFindCity(gps);
                    end

                    for ii = idx
                        misc_gpsUpdate(app, ii, gps.Latitude, gps.Longitude)

                        if ~strcmp(app.edit_gpsCitySource.Value, 'manual')
                            app.specData(ii).GPS.Location       = cityName;
                            app.specData(ii).GPS.LocationSource = locInfo.infoSource;
                        end
                        
                        app.specData(ii).GPS.Edited = true;
                    end
                    misc_gpsLayout(app, 'Latitude+Longitude')

                %---------------------------------------------------------%
                case app.edit_gpsCityButton
                    app.edit_gpsCity.Editable = 1;
                    focus(app.edit_gpsCity)

                %---------------------------------------------------------%
                case app.edit_gpsCity
                    [cityName, Latitude, Longitude] = fcn.gpsFindCoordinates(event.Value);                    
                    if ~isempty(cityName)        
                        msgQuestion   = sprintf(['Coordenadas geográficas atuais:\n•&thinsp;Latitude: %.6f\n•&thinsp;Longitude: %.6f\n\n'               ...
                                                 'Coordenadas geográficas da sede de <b>%s</b>:\n•&thinsp;Latitude: %.6f\n•&thinsp;Longitude: %.6f\n\n' ...
                                                 'Deseja atualizar, além da localidade, as informações de Latitude e Longitude?'],        ...
                                                 app.edit_gpsLatitude.Value, app.edit_gpsLongitude.Value, cityName, Latitude, Longitude);
                        userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não', 'Cancelar'}, 1, 3);
                        if userSelection == "Cancelar"
                            set(app.edit_gpsCity, Editable=0, Value=event.PreviousValue)
                            return
                        end

                        for ii = idx
                            if selection == "Sim"
                                misc_gpsUpdate(app, ii, Latitude, Longitude)
                            end
                            
                            app.specData(ii).GPS.Location       = cityName;
                            app.specData(ii).GPS.LocationSource = 'manual';                            
                            app.specData(ii).GPS.Edited         = true;
                        end

                        switch selection
                            case 'Sim'; misc_gpsLayout(app, 'City+Latitude+Longitude')
                            case 'Não'; misc_gpsLayout(app, 'City')
                        end
                        
                    else
                        set(app.edit_gpsCity, Editable=0, Value=event.PreviousValue)
                        
                        msg = sprintf(['Não encontrada em base do IBGE o município <b>"%s"</b>. Favor corrigir ' ...
                                       'eventual erro na grafia, inserindo os acentos, no formato Município/UF'], event.Value);
                        appUtil.modalWindow(app.UIFigure, 'warning', msg);                        
                    end
            end

        end

        % Image clicked function: misc_Edit_kFactorAdd
        function misc_Edit_kFactorAddImageClicked(app, event)
            
            focus(app.misc_Edit_kFactorTree)

            idx1 = unique([app.play_Tree.SelectedNodes.NodeData]);
            if numel(idx1) ~= 1
                return
            end

            switch event.Source
                %---------------------------------------------------------%
                case app.edit_unitRefresh
                    idx2 = 1:height(app.specData(idx1).UserData.measCalibration);
                    Name = app.specData(idx1).UserData.measCalibration.Name;
                    misc_unitLevelCorrection(app, 'Remove', idx1, idx2, Name)

                %---------------------------------------------------------%
                case app.misc_Edit_kFactorAdd
                    Name = app.misc_Edit_kFactor.Value;
                    misc_unitLevelCorrection(app, 'Add', idx1, [], Name)

                %---------------------------------------------------------%
                case app.edit_ContextMenu_del
                    idx2 = app.misc_Edit_kFactorTree.SelectedNodes.NodeData;
                    Name = app.specData(idx1).UserData.measCalibration.Name(idx2);
                    misc_unitLevelCorrection(app, 'Remove', idx1, idx2, Name)
            end
            misc_unitLevelLayout(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.edit_gpsPanel = uipanel(app.UIFigure);
            app.edit_gpsPanel.Title = 'LOCAL DA MONITORAÇÃO';
            app.edit_gpsGrid = uigridlayout(app.edit_gpsPanel);
            app.edit_gpsLocationLabel1 = uilabel(app.edit_gpsGrid);
            app.edit_gpsLocationLabel1.Interpreter = 'html';
            app.edit_gpsLocationLabel1.Text = {'Coordenadas geográficas:'; '<p style="line-height:10px; font-size:9px; color:gray;">(Fonte | Latitude | Longitude)</p>'};
            app.edit_gpsLocationPanel = uipanel(app.edit_gpsGrid);
            app.edit_gpsLocationGrid = uigridlayout(app.edit_gpsLocationPanel);
            app.edit_gpsSource = uieditfield(app.edit_gpsLocationGrid, 'text');
            app.edit_gpsSource.Editable = 'off';
            app.edit_gpsSource.Value = 'auto';
            app.edit_gpsLatitude = uieditfield(app.edit_gpsLocationGrid, 'numeric');
            app.edit_gpsLatitude.Limits = [-90 90];
            app.edit_gpsLatitude.ValueDisplayFormat = '%.6f';
            app.edit_gpsLatitude.Editable = 'off';
            app.edit_gpsLatitude.Value = -1;
            app.edit_gpsLongitude = uieditfield(app.edit_gpsLocationGrid, 'numeric');
            app.edit_gpsLongitude.Limits = [-180 180];
            app.edit_gpsLongitude.ValueDisplayFormat = '%.6f';
            app.edit_gpsLongitude.Editable = 'off';
            app.edit_gpsLongitude.Value = -1;
            app.edit_gpsLocationLabel2 = uilabel(app.edit_gpsGrid);
            app.edit_gpsLocationLabel2.Interpreter = 'html';
            app.edit_gpsLocationLabel2.Text = {'Localidade:'; '<p style="line-height:10px; font-size:9px; color:gray;">(Fonte | Município/UF)</p>'};
            app.edit_gpsCityPanel = uipanel(app.edit_gpsGrid);
            app.edit_gpsCityGrid = uigridlayout(app.edit_gpsCityPanel);
            app.edit_gpsCitySource = uieditfield(app.edit_gpsCityGrid, 'text');
            app.edit_gpsCitySource.Editable = 'off';
            app.edit_gpsCitySource.Value = 'API';
            app.edit_gpsCity = uieditfield(app.edit_gpsCityGrid, 'text');
            app.edit_gpsCity.Editable = 'off';
            app.edit_gpsCityButton = uiimage(app.edit_gpsGrid);
            app.edit_gpsCityButton.Tooltip = {'Habilita edição do campo "Município/UF"'};
            app.edit_gpsCityButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.edit_unitPanel = uipanel(app.UIFigure);
            app.edit_unitPanel.Title = 'UNIDADE DE MEDIDA';
            app.misc_Edit_unitGrid = uigridlayout(app.edit_unitPanel);
            app.misc_Edit_unitRawLabel = uilabel(app.misc_Edit_unitGrid);
            app.misc_Edit_unitRawLabel.Interpreter = 'html';
            app.misc_Edit_unitRawLabel.Text = {'Unidade original:'; '<p style="line-height:10px; font-size:9px; color:gray;">(coleta)</p>'};
            app.misc_Edit_unitRaw = uieditfield(app.misc_Edit_unitGrid, 'text');
            app.misc_Edit_unitRaw.Editable = 'off';
            app.misc_Edit_kFactorLabel = uilabel(app.misc_Edit_unitGrid);
            app.misc_Edit_kFactorLabel.Interpreter = 'html';
            app.misc_Edit_kFactorLabel.Text = {'Curva de correção:'; '<p style="line-height:10px; font-size:9px; color:gray;">(calibração, fator-k)</p>'};
            app.misc_Edit_kFactor = uidropdown(app.misc_Edit_unitGrid);
            app.misc_Edit_kFactor.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (Argus)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (690 MHz - 6 GHz)'};
            app.misc_Edit_kFactor.Value = '';
            app.misc_Edit_kFactorAdd = uiimage(app.misc_Edit_unitGrid);
            app.misc_Edit_kFactorAdd.ImageClickedFcn = createCallbackFcn(app, @misc_Edit_kFactorAddImageClicked, true);
            app.misc_Edit_kFactorAdd.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.misc_Edit_kFactorTree = uitree(app.misc_Edit_unitGrid);
            app.edit_unitRefresh = uiimage(app.misc_Edit_unitGrid);
            app.edit_unitRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout3 = uigridlayout(app.Panel);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = 'filtro temporal. datepicker, spinner e árvore registrando os vários filtros temporais';
            app.tool_MiscEdit = uiimage(app.UIFigure);
            app.tool_MiscEdit.Tag = 'MISC';
            app.tool_MiscEdit.Visible = 'off';
            app.tool_MiscEdit.Tooltip = {'Habilita edição dos campos "Latitude" e "Longitude"'};
            app.tool_MiscEdit.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.tool_MiscCancel = uiimage(app.UIFigure);
            app.tool_MiscCancel.Tag = 'MISC';
            app.tool_MiscCancel.Visible = 'off';
            app.tool_MiscCancel.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Delete_32Red.png');
            app.tool_MiscRefresh = uiimage(app.UIFigure);
            app.tool_MiscRefresh.Tag = 'MISC';
            app.tool_MiscRefresh.Visible = 'off';
            app.tool_MiscRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.tool_MiscEdit_2 = uiimage(app.UIFigure);
            app.tool_MiscEdit_2.ImageClickedFcn = createCallbackFcn(app, @misc_gpsCallbacks, true);
            app.tool_MiscEdit_2.Tag = 'MISC';
            app.tool_MiscEdit_2.Visible = 'off';
            app.tool_MiscEdit_2.Tooltip = {'Habilita edição dos campos "Latitude" e "Longitude"'};
            app.tool_MiscEdit_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.tool_MiscCancel_2 = uiimage(app.UIFigure);
            app.tool_MiscCancel_2.Tag = 'MISC';
            app.tool_MiscCancel_2.Visible = 'off';
            app.tool_MiscCancel_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Delete_32Red.png');
            app.tool_MiscRefresh_2 = uiimage(app.UIFigure);
            app.tool_MiscRefresh_2.ImageClickedFcn = createCallbackFcn(app, @misc_gpsCallbacks, true);
            app.tool_MiscRefresh_2.Tag = 'MISC';
            app.tool_MiscRefresh_2.Visible = 'off';
            app.tool_MiscRefresh_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMiscEdit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end