classdef winRFDataHub_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid                           matlab.ui.container.GridLayout
        chReportHTML                   matlab.ui.control.HTML
        UITable                        matlab.ui.control.Table
        ControlTabGrid                 matlab.ui.container.GridLayout
        menu_MainGrid                  matlab.ui.container.GridLayout
        menu_Button3Grid               matlab.ui.container.GridLayout
        menu_Button3Icon               matlab.ui.control.Image
        menu_Button3Label              matlab.ui.control.Label
        menu_Button2Grid               matlab.ui.container.GridLayout
        menu_Button2Icon               matlab.ui.control.Image
        menu_Button2Label              matlab.ui.control.Label
        menu_Button1Grid               matlab.ui.container.GridLayout
        menu_Button1Icon               matlab.ui.control.Image
        menu_Button1Label              matlab.ui.control.Label
        menuUnderline                  matlab.ui.control.Image
        ControlTabGroup                matlab.ui.container.TabGroup
        Tab_1                          matlab.ui.container.Tab
        Tab1_Grid                      matlab.ui.container.GridLayout
        GridLayout                     matlab.ui.container.GridLayout
        REGISTROSELECIONADOLabel       matlab.ui.control.Label
        search_SelectedRowInfoPanel    matlab.ui.container.Panel
        search_SelectedRowInfoGrid     matlab.ui.container.GridLayout
        search_SelectedRowInfo         matlab.ui.control.HTML
        link_Grid                      matlab.ui.container.GridLayout
        link_LinkInfoPanel             matlab.ui.container.Panel
        link_LinkInfoGrid              matlab.ui.container.GridLayout
        link_LinkInfo                  matlab.ui.control.HTML
        link_Table                     matlab.ui.control.Table
        link_AddStation                matlab.ui.control.Image
        link_TableLabel                matlab.ui.control.Label
        general_ModeRadioGroup         matlab.ui.container.ButtonGroup
        general_ModeLink               matlab.ui.control.RadioButton
        general_ModeSearch             matlab.ui.control.RadioButton
        general_ModeLabel              matlab.ui.control.Label
        Tab_2                          matlab.ui.container.Tab
        Tab2_Grid                      matlab.ui.container.GridLayout
        Image2                         matlab.ui.control.Image
        points_TreeLabel               matlab.ui.control.Label
        points_Panel                   matlab.ui.container.Panel
        points_Grid                    matlab.ui.container.GridLayout
        points_Longitude_2             matlab.ui.control.NumericEditField
        points_LongitudeLabel_2        matlab.ui.control.Label
        points_Longitude               matlab.ui.control.NumericEditField
        points_LongitudeLabel          matlab.ui.control.Label
        points_Latitude                matlab.ui.control.NumericEditField
        points_LatitudeLabel           matlab.ui.control.Label
        points_refreshImage            matlab.ui.control.Image
        filter_SecondaryTree           matlab.ui.container.CheckBoxTree
        filter_SecondaryAddImage       matlab.ui.control.Image
        filter_SecondaryValuePanel     matlab.ui.container.ButtonGroup
        filter_SecondaryValueSubpanel  matlab.ui.container.Panel
        filter_SecondaryValueGrid      matlab.ui.container.GridLayout
        filter_SecondaryLogicalOperator  matlab.ui.control.DropDown
        filter_SecondaryLogicalOperatorLabel  matlab.ui.control.Label
        filter_SecondaryReferenciaFilter  matlab.ui.control.DropDown
        filter_SecondaryReferenciaFilterLabel  matlab.ui.control.Label
        filter_SecondaryTextList       matlab.ui.control.DropDown
        filter_SecondaryTextFree       matlab.ui.control.EditField
        filter_SecondaryNumValue2      matlab.ui.control.NumericEditField
        filter_SecondaryNumSeparator   matlab.ui.control.Label
        filter_SecondaryNumValue1      matlab.ui.control.NumericEditField
        filter_SecondaryOperation10    matlab.ui.control.ToggleButton
        filter_SecondaryOperation9     matlab.ui.control.ToggleButton
        filter_SecondaryOperation8     matlab.ui.control.ToggleButton
        filter_SecondaryOperation7     matlab.ui.control.ToggleButton
        filter_SecondaryOperation6     matlab.ui.control.ToggleButton
        filter_SecondaryOperation5     matlab.ui.control.ToggleButton
        filter_SecondaryOperation4     matlab.ui.control.ToggleButton
        filter_SecondaryOperation3     matlab.ui.control.ToggleButton
        filter_SecondaryOperation2     matlab.ui.control.ToggleButton
        filter_SecondaryOperation1     matlab.ui.control.ToggleButton
        filter_SecondaryTypePanel      matlab.ui.container.ButtonGroup
        filter_SecondaryType12         matlab.ui.control.RadioButton
        filter_SecondaryType11         matlab.ui.control.RadioButton
        filter_SecondaryType10         matlab.ui.control.RadioButton
        filter_SecondaryType9          matlab.ui.control.RadioButton
        filter_SecondaryType8          matlab.ui.control.RadioButton
        filter_SecondaryType7          matlab.ui.control.RadioButton
        filter_SecondaryType6          matlab.ui.control.RadioButton
        filter_SecondaryType5          matlab.ui.control.RadioButton
        filter_SecondaryType4          matlab.ui.control.RadioButton
        filter_SecondaryType3          matlab.ui.control.RadioButton
        filter_SecondaryType2          matlab.ui.control.RadioButton
        filter_SecondaryType1          matlab.ui.control.RadioButton
        filter_SecondaryLabel          matlab.ui.control.Label
        filter_PrimaryPanel            matlab.ui.container.Panel
        filter_PrimaryGrid             matlab.ui.container.GridLayout
        filter_PrimaryEditField        matlab.ui.control.EditField
        filter_PrimaryDropDown         matlab.ui.control.DropDown
        filter_Primary                 matlab.ui.control.DropDown
        filter_PrimaryEditImage        matlab.ui.control.Image
        filter_PrimaryLabel            matlab.ui.control.Label
        Tab_3                          matlab.ui.container.Tab
        Tab3_Grid                      matlab.ui.container.GridLayout
        misc_ElevationSourcePanel      matlab.ui.container.Panel
        misc_ElevationSourceGrid       matlab.ui.container.GridLayout
        misc_PointsPerLink             matlab.ui.control.DropDown
        misc_PointsPerLinkLabel        matlab.ui.control.Label
        misc_ElevationAPISource        matlab.ui.control.DropDown
        misc_ElevationAPISourceLabel   matlab.ui.control.Label
        ELEVAOLabel                    matlab.ui.control.Label
        config_xyAxesPanel             matlab.ui.container.Panel
        config_xyAxesGrid              matlab.ui.container.GridLayout
        config_chPowerFaceAlpha        matlab.ui.control.Spinner
        config_chPowerEdgeAlpha        matlab.ui.control.Spinner
        config_chPowerColor            matlab.ui.control.ColorPicker
        config_chPowerVisibility       matlab.ui.control.DropDown
        config_chPowerLabel            matlab.ui.control.Label
        config_chROIFaceAlpha          matlab.ui.control.Spinner
        config_chROIEdgeAlpha          matlab.ui.control.Spinner
        config_chROIColor              matlab.ui.control.ColorPicker
        config_chROIVisibility         matlab.ui.control.DropDown
        config_chROILabel              matlab.ui.control.Label
        config_PersistanceVisibility   matlab.ui.control.DropDown
        config_PersistanceLabel        matlab.ui.control.Label
        config_xyAxesLabel             matlab.ui.control.Label
        config_geoAxesPanel            matlab.ui.container.Panel
        config_geoAxesGrid             matlab.ui.container.GridLayout
        config_points_Size             matlab.ui.control.Slider
        config_points_Color            matlab.ui.control.ColorPicker
        config_points_LineStyle        matlab.ui.control.DropDown
        config_points_Label            matlab.ui.control.Label
        config_Car_Size                matlab.ui.control.Slider
        config_Car_Color               matlab.ui.control.ColorPicker
        config_Car_LineStyle           matlab.ui.control.DropDown
        config_Car_Label               matlab.ui.control.Label
        config_route_Size              matlab.ui.control.Slider
        config_route_InColor           matlab.ui.control.ColorPicker
        config_route_OutColor          matlab.ui.control.ColorPicker
        config_route_LineStyle         matlab.ui.control.DropDown
        config_route_Label             matlab.ui.control.Label
        config_geoAxesSubPanel         matlab.ui.container.Panel
        config_geoAxesSubGrid          matlab.ui.container.GridLayout
        config_Colormap                matlab.ui.control.DropDown
        config_ColormapLabel           matlab.ui.control.Label
        config_Basemap                 matlab.ui.control.DropDown
        config_BasemapLabel            matlab.ui.control.Label
        config_geoAxesSublabel         matlab.ui.control.Label
        config_Refresh                 matlab.ui.control.Image
        config_geoAxesLabel            matlab.ui.control.Label
        axesToolbarGrid                matlab.ui.container.GridLayout
        axesTool_TableVisibility       matlab.ui.control.Image
        axesTool_DataTip               matlab.ui.control.Image
        axesTool_RegionZoom            matlab.ui.control.Image
        axesTool_RestoreView           matlab.ui.control.Image
        plotPanel                      matlab.ui.container.Panel
        toolGrid                       matlab.ui.container.GridLayout
        filter_Summary                 matlab.ui.control.Image
        tool_ExportButton              matlab.ui.control.Image
        jsBackDoor                     matlab.ui.control.HTML
        tool_simulationButton          matlab.ui.control.Image
        tool_PDFButton                 matlab.ui.control.Image
        tool_LOGButton                 matlab.ui.control.Image
        tool_ControlPanelVisibility    matlab.ui.control.Image
        filter_ContextMenu             matlab.ui.container.ContextMenu
        filter_delButton               matlab.ui.container.Menu
        filter_delAllButton            matlab.ui.container.Menu
        points_ContextMenu             matlab.ui.container.ContextMenu
        points_referenceButton         matlab.ui.container.Menu
        points_editRowButton           matlab.ui.container.Menu
        points_delButton               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        General
        rootFolder

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag = {true, ...
                          true, ...
                          true, ...
                          true};

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        % Propriedades do app.
        specData

        %-----------------------------------------------------------------%
        % ESPECIFICIDADES AUXAPP.WINDRIVETEST
        %-----------------------------------------------------------------%
        restoreView = {[0,1], [0,1]}

        UIAxes1
        UIAxes2       
        

        elevationObj = RF.Elevation        

        rfDataHub
        rfDataHubSummary
        rfDataHubLogical

        pointsTable = table('Size',          [0, 6],                                                    ...
                            'VariableTypes', {'cell', 'double', 'double', 'cell', 'double', 'logical'}, ...
                            'VariableNames', {'Type', 'Latitude', 'Longitude', 'Location', 'AntennaHeight', 'Reference'})

        filterTable = table('Size',          [0, 9],                                                                      ...
                            'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                            'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'})        
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)

                % Define tamanho mínimo do app (não aplicável à versão webapp).
                if ~strcmp(app.CallingApp.executionMode, 'webApp')
                    appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
                end
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app, 0)
                jsBackDoor_Customizations(app, 1)

                app.progressDialog.Visible = 'visible';

                startup_AppProperties(app)
                startup_AxesCreation(app)
                startup_GUIComponents(app)

                % points_NodeList(app)
                % filter_RFDataHub(app)
                % filter_typePanelSelectionChanged(app)

                app.progressDialog.Visible = 'hidden';
            end
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource           = ccTools.fcn.jsBackDoorHTMLSource();
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_Listener(app, evt);
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Listener(app, event)
            % switch event.HTMLEventName
            %     case 'app.filter_SecondaryTree'
            %         filter_delFilter(app, struct('Source', app.filter_delButton))
            %     case 'app.points_Tree'
            %         points_delButtonMenuSelected(app)
            % end
            drawnow
        end

        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            % O menu gráfico controla, programaticamente, qual das abas de
            % app.ControlTabGroup estará visível. 

            % Lembrando que o MATLAB renderiza em tela apenas as abas visíveis.
            % Por isso as customizações de abas e suas subabas somente é possível 
            % após a renderização da aba.

            switch tabIndex
                case 0 % STARTUP
                    app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                           'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                               '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                               '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));
        
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                           'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));
                    
                    ccTools.compCustomizationV2(app.jsBackDoor, app.ControlTabGroup, 'transparentHeader', 'transparent')
                    ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'borderBottomLeftRadius', '5px', 'borderBottomRightRadius', '5px')

                otherwise
                    if any(app.jsBackDoorFlag{tabIndex})
                        app.jsBackDoorFlag{tabIndex} = false;

                        switch tabIndex
                            case 1 % RFDATAHUB
                                % ...            
                            case 2 % FILTRAGEM
                                % ...
                            case 3 % CONFIGURAÇÕES GERAIS
                                % ...
                        end
                    end
            end
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            % ...
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            hParent     = tiledlayout(app.plotPanel, 4, 4, "Padding", "none", "TileSpacing", "none");

            % Eixo geográfico: MAPA
            app.UIAxes1 = plot.axes.Creation(hParent, 'Geographic', {'Basemap', app.config_Basemap.Value, ...
                                                                     'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [4, 4];

            set(app.UIAxes1.LatitudeAxis,  'TickLabels', {}, 'Color', 'none')
            set(app.UIAxes1.LongitudeAxis, 'TickLabels', {}, 'Color', 'none')
            geolimits(app.UIAxes1, 'auto')
            plot.axes.Colormap(app.UIAxes1, app.config_Colormap.Value)

            % Eixo cartesiano: PERFIL DE RELEVO
            app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'XGrid', 'off', 'XMinorGrid', 'off', 'XTick', [], 'XColor', 'none', ...
                                                                    'YGrid', 'off', 'YMinorGrid', 'off', 'YTick', [], 'YColor', 'none'});
            app.UIAxes2.Layout.Tile = 15;
            app.UIAxes2.Layout.TileSpan = [1 2];
            app.UIAxes2.XAxis.TickLabelFormat = '%.1f';

            % Axes interactions:
            plot.axes.Interactivity.DefaultCreation(app.UIAxes1, [dataTipInteraction, zoomInteraction, panInteraction])
            plot.axes.Interactivity.DefaultCreation(app.UIAxes2, [dataTipInteraction, regionZoomInteraction])
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            % ...
        end

        %-----------------------------------------------------------------%
        function layout_CartesianAxesLayout(app)
            if app.tool_HistogramAxesVisibility.Value
                app.tool_simulationAxesVisibility.Value = 0;

                layout_CartesianAxesSwitch(app, 'Visible',   2, [1 2])
                layout_CartesianAxesSwitch(app, 'Visible',   3, [3 1])
                layout_CartesianAxesSwitch(app, 'Invisible', 4, -1)

            else
                app.tool_simulationAxesVisibility.Value = 1;

                layout_CartesianAxesSwitch(app, 'Invisible', 2, -1)
                layout_CartesianAxesSwitch(app, 'Invisible', 3, -1)
                layout_CartesianAxesSwitch(app, 'Visible',   4, [1 3])
            end
        end

        %-----------------------------------------------------------------%
        function layout_CartesianAxesSwitch(app, axesVisibility, axesID, axesTiledPosition)
            switch axesVisibility
                case 'Invisible'
                    eval(sprintf('set(app.UIAxes%d,          Visible=0)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Children, Visible=0)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Toolbar,  Visible=0)', axesID))

                case 'Visible'
                    eval(sprintf('set(app.UIAxes%d,          Visible=1)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Children, Visible=1)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Toolbar,  Visible=1)', axesID))
    
                    eval(sprintf('app.UIAxes%d.Layout.Tile = axesTiledPosition(1);', axesID))
                    eval(sprintf('app.UIAxes%d.Layout.TileSpan = [1 axesTiledPosition(2)];', axesID))
            end
        end

        %-----------------------------------------------------------------%
        function layout_FilterOperationPanel(app, filterType, filterDefault)
            
            layout_FilterDefaultValues(app)
            filter_SecondaryReferenciaFilterValueChanged(app)

            hComp = findobj(app.filter_SecondaryValuePanel, 'Type', 'uitogglebutton');
            hCompTagFlag = contains(arrayfun(@(x) x.Tag, hComp, 'UniformOutput', false), filterType);

            set(hComp(hCompTagFlag),  Enable=1)
            set(hComp(~hCompTagFlag), Enable=0)

            selectedButton = app.filter_SecondaryValuePanel.SelectedObject;
            if ~selectedButton.Enable
                switch filterType
                    case {'textList1', 'ROI'}
                        app.filter_SecondaryOperation3.Value = 1; % CONTÉM

                    otherwise
                        app.filter_SecondaryOperation1.Value = 1; % IGUAL
                end
            end

            switch filterType
                case 'numeric'
                    app.filter_SecondaryValueGrid.RowHeight(1:3) = {22,0,0};

                case 'textFree'
                    app.filter_SecondaryValueGrid.RowHeight(1:3) = {0,22,0};

                case {'textList1', 'textList2', 'ROI'}
                    app.filter_SecondaryValueGrid.RowHeight(1:3) = {0,0,22};
                    app.filter_SecondaryTextList.Items = filterDefault;
            end
        end


        %-----------------------------------------------------------------%
        function layout_FilterDefaultValues(app)
            app.filter_SecondaryNumValue1.Value     = -1;
            app.filter_SecondaryNumValue2.Value     = -1;
            app.filter_SecondaryTextFree.Value = '';
            app.filter_SecondaryTextList.Items = {};
            app.filter_SecondaryLogicalOperator.Items       = {'E (&&)'};
        end

        %-----------------------------------------------------------------%
        function layout_simulationLink(app, idx)
            simulationFlag = false;

            app.tool_PDFButton.Enable = 0;
            if ~isempty(idx)
                if app.UITable.Data.URL(idx) ~= "-1"
                    app.tool_PDFButton.Enable = 1;
                end

                if ~isempty(app.pointsTable)
                    simulationFlag = true;
                end
            end

            if simulationFlag
                txIndex = idx;
                rxIndex = find(app.pointsTable.Reference, 1);

                [arclen, az] = distance(app.UITable.Data.Latitude(txIndex), ...
                                        app.UITable.Data.Longitude(txIndex), ...
                                        app.pointsTable.Latitude(rxIndex),  ...
                                        app.pointsTable.Longitude(rxIndex)); 
                R = deg2km(arclen);

                app.link_LinkInfo.HTMLSource = textFormatGUI.struct2PrettyPrintList(misc_simulationLinkSummary(app, idx), 'delete');
                app.misc_LinkDistance.Value         = R;
                app.misc_LinkAzimuth.Value          = az;
                app.misc_LinkAttenuation.Value      = fspl(R*1000, physconst('LightSpeed')/(app.UITable.Data.Frequency(txIndex)*10^6));
                app.tool_simulationButton.Enable    = 1;

            else
                app.link_LinkInfo.HTMLSource = ' ';
                app.misc_LinkDistance.Value         = -1;
                app.misc_LinkAzimuth.Value          = -1;
                app.misc_LinkAttenuation.Value      = -1;
                app.tool_simulationButton.Enable    = 0;
            end
        end

        %-----------------------------------------------------------------%
        function linkSummary = misc_simulationLinkSummary(app, idx)
            [txObj, rxObj]  = misc_simulationObjects(app, idx);
            txObj.Frequency = sprintf('%.3f MHz', txObj.TransmitterFrequency/1e+6);

            linkSummary(1)  = struct('group', 'ESTAÇÃO TRANSMISSORA (TX)', 'value', txObj);
            linkSummary(2)  = struct('group', 'ESTAÇÃO RECEPTORA (RX)',    'value', rxObj);
        end

        %-----------------------------------------------------------------%
        function [txObj, rxObj] = misc_simulationObjects(app, idxStation)
            txIndex = str2double(extractAfter(app.UITable.Data.ID{idxStation}, '#'));
            rxIndex = find(app.pointsTable.Reference, 1);

            txObj = struct('TransmitterFrequency', app.rfDataHub.Frequency(txIndex) * 1e+6,                ...
                           'Latitude',             app.rfDataHub.Latitude(txIndex),                        ...
                           'Longitude',            app.rfDataHub.Longitude(txIndex),                       ...
                           'AntennaHeight',        str2double(char(app.rfDataHub.AntennaHeight(txIndex))), ...
                           'AntennaPattern',       char(app.rfDataHub.AntennaPattern(txIndex)),            ...
                           'Description',          app.UITable.Data.Description{idxStation},               ...
                           'Service',              app.rfDataHub.Service(txIndex),                         ...
                           'Station',              app.rfDataHub.Station(txIndex), ...
                           'ID',                   app.UITable.Data.ID{idxStation});

            rxObj = struct('Latitude',       app.pointsTable.Latitude(rxIndex),  ...
                           'Longitude',      app.pointsTable.Longitude(rxIndex), ...
                           'AntennaHeight',  app.pointsTable.AntennaHeight(rxIndex));
        end

        %-----------------------------------------------------------------%
        function points_NodeList(app)
            app.pointsTable(:,:) = [];

            if ~isempty(app.CallingApp)
                for ii = 1:numel(app.CallingApp.specData)
                    if app.CallingApp.specData(ii).GPS.Status
                        AntennaHeight = -1;
                        if isfield(app.CallingApp.specData(ii).MetaData.Antenna, 'Height')
                            AntennaHeight = str2double(extractBefore(app.CallingApp.specData(ii).MetaData.Antenna.Height, 'm'));
                        end

                        newPoint      = {'auto',                                    ...
                                         app.CallingApp.specData(ii).GPS.Latitude,  ...
                                         app.CallingApp.specData(ii).GPS.Longitude, ...
                                         app.CallingApp.specData(ii).GPS.Location,  ...
                                         AntennaHeight,                             ...
                                         false};
                        newPointFlag = true;

                        for jj = 1:height(app.pointsTable)
                            if isequal(newPoint(2:5), table2cell(app.pointsTable(jj,2:5)))
                                newPointFlag = false;
                                break
                            end
                        end

                        if newPointFlag
                            app.pointsTable(end+1,:) = newPoint;
                        end
                    end
                end
            end

            if isempty(app.pointsTable)
                % Caso não tenha um ponto de monitoração, insere-se a sede
                % da Anatel. Isso garante a visualização do mapa centralizada
                % em Brasília/DF.
                app.pointsTable(1,:) = {'manual', -15.805479, -47.882499, 'Brasília/DF', 30, true};
            else
                app.pointsTable.Reference(1) = true;
            end            

            points_TreeBuilding(app, 1)
        end


        %-----------------------------------------------------------------%
        function points_TreeBuilding(app, selectedID)
            if ~isempty(app.points_Tree.Children)
                delete(app.points_Tree.Children)
                drawnow nocallbacks
            end

            if ~isempty(app.pointsTable)
                for ii = 1:height(app.pointsTable)
                    antennaHeight = '';
                    if app.pointsTable.AntennaHeight(ii) > 0
                        antennaHeight = sprintf(', %dm', app.pointsTable.AntennaHeight(ii));
                    end

                    nodeTree = uitreenode(app.points_Tree, 'Text', sprintf('#%d: %s (%.6fº, %.6fº%s)', ii, app.pointsTable.Location{ii},  ...
                                                                                                           app.pointsTable.Latitude(ii),  ...
                                                                                                           app.pointsTable.Longitude(ii), ...
                                                                                                           antennaHeight),                ...
                                                           'NodeData', ii, 'ContextMenu', app.points_ContextMenu);
                    if app.pointsTable.Reference(ii)
                        nodeTree.Icon = 'Pin_18.png';
                    end
                end

                if ~ismember(selectedID, 1:height(app.pointsTable))
                    selectedID = 1;
                end
                app.points_Tree.SelectedNodes = app.points_Tree.Children(selectedID);

            else
                app.points_Grid.ColumnWidth{4} = 22;
                set(app.points_Latitude,      Value=-1, Editable=1)
                set(app.points_Longitude,     Value=-1, Editable=1)
                set(app.points_AntennaHeight, Value=-1, Editable=1)
            end

            points_TreeSelectionChanged(app)
        end


        %-----------------------------------------------------------------%
        function filter_RFDataHub(app)
            global RFDataHub
            
            app.rfDataHub = RFDataHub;
            
            % Contorna erro da função inROI, que retorna como se todos os
            % pontos estivessem internos ao ROI, quando as coordenadas
            % estão em float32. No float64 isso não acontece... aberto BUG
            % na Mathworks, que indicou estar ciente.
            app.rfDataHub.Latitude    = double(app.rfDataHub.Latitude);
            app.rfDataHub.Longitude   = double(app.rfDataHub.Longitude);

            app.rfDataHub.ID          = "#" + string((1:height(RFDataHub))');
            app.rfDataHub.Description = "[" + string(RFDataHub.Source) + "] " + string(RFDataHub.Status) + ", " + string(RFDataHub.StationClass) + ", " + string(RFDataHub.Name) + " (Fistel=" + string(RFDataHub.Fistel) + ", M=" + string(RFDataHub.MergeCount) + "), " + string(RFDataHub.Location) + "/" + string(RFDataHub.State);            

            app.rfDataHubSummary      = summary(RFDataHub);
            app.tool_nRows.Text       = sprintf('0 de %d registros\n0.0 %%', height(app.rfDataHub));

            filter_DistanceColumn(app)
            plot_Nodes(app)
        end


        %-----------------------------------------------------------------%
        function filter_DistanceColumn(app)
            if ~isempty(app.pointsTable)
                idx  = find(app.pointsTable.Reference, 1);
                app.rfDataHub.Distance = round(deg2km(distance(app.rfDataHub.Latitude,        ...
                                                               app.rfDataHub.Longitude,       ...
                                                               app.pointsTable.Latitude(idx), ...
                                                               app.pointsTable.Longitude(idx))), 1);

            else
                app.rfDataHub.Distance = -1*ones(height(app.rfDataHub), 1);
            end
        end


        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_SecondaryTree.Children)
                delete(app.filter_SecondaryTree.Children)
            end

            idx1 = find(strcmp(app.filterTable.Order, 'Node'))';
            if ~isempty(idx1)
                checkedNodes = {};
                for ii = idx1
                    parentNode = uitreenode(app.filter_SecondaryTree, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(ii),                        ...
                                                                                                            app.filterTable.Type{ii},                      ...
                                                                                                            app.filterTable.Operation{ii},                 ...
                                                                                                            filter_Value(app, app.filterTable.Value{ii})), ...
                                                             'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                    if app.filterTable.Enable(ii)
                        checkedNodes = [checkedNodes, parentNode];
                    end
    
                    idx2 = find(app.filterTable.RelatedID == ii)';                
                    for jj = idx2
                        childNode = uitreenode(parentNode, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(jj),                        ...
                                                                                                          app.filterTable.Type{jj},                      ...
                                                                                                          app.filterTable.Operation{jj},                 ...
                                                                                                          filter_Value(app, app.filterTable.Value{jj})), ...
                                                           'NodeData', jj, 'ContextMenu', app.filter_ContextMenu);
    
                        if app.filterTable.Enable(jj)
                            checkedNodes = [checkedNodes, childNode];
                        end
                    end
                end
                app.filter_SecondaryTree.CheckedNodes = checkedNodes;
                expand(app.filter_SecondaryTree, 'all')
            end

            app.filter_SecondaryReferenciaFilter.Items = [{''}, cellstr("#" + string((idx1)))];
        end


        %-----------------------------------------------------------------%
        function filterValue = filter_Value(app, filterValue)
            if isnumeric(filterValue)
                if isscalar(filterValue)
                    filterValue = string(filterValue);
                else
                    filterValue = "[" + strjoin(string(filterValue), ', ') + "]";
                end
            elseif ischar(filterValue)
                filterValue = sprintf('"%s"', upper(filterValue));
            else
                filterValue = '⌂';
            end
        end


        %-----------------------------------------------------------------%
        function filter_ROICallbacks(app, src, event)
            switch(event.EventName)
                case 'MovingROI'
                    plot.axes.Interactivity.DefaultDisable(app.UIAxes1)
                    
                case 'ROIMoved'
                    plot.axes.Interactivity.DefaultEnable(app.UIAxes1)
                    
                    filter_TableFiltering(app, true)
                    if isvalid(event.Source)
                        uistack(event.Source, 'top')
                    end
            end            
        end


        %-----------------------------------------------------------------%
        function filter_TableFiltering(app, ProgressDialogFlag)
            if ProgressDialogFlag
                d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');
            end

            app.rfDataHubLogical   = fcn.TableFiltering(app.rfDataHub, app.filterTable);

            if isempty(app.UITable.Selection)
                initialSelectedRow = app.UIAxes2.UserData.TX.ID;
            else
                initialSelectedRow = app.UITable.Data.ID{app.UITable.Selection(1)};
            end

            app.UITable.Selection  = [];
            app.UITable.Data       = app.rfDataHub(app.rfDataHubLogical,[30, 1, 6:7, 31, 4:5, 12, 13, 29, 32]);

            if ~isempty(initialSelectedRow)
                finalSelectedRow = find(strcmp(app.UITable.Data.ID, initialSelectedRow), 1);
                if ~isempty(finalSelectedRow)
                    app.UITable.Selection = [finalSelectedRow, 1];
                end                
            end

            filter_TableStyle(app, 'ProgrammaticallySelectedRow')
            filter_TableStyle(app, 'TerrainProfilePlot')

            NN = sum(app.rfDataHubLogical);
            MM = numel(app.rfDataHubLogical);
            app.tool_nRows.Text = sprintf('%d de %d registros\n%.1f %%', NN, MM, (NN/MM)*100);

            plot_General(app)
            plot_SelectedRow(app)
            plot_Nodes(app)
            if app.tool_simulationAxesVisibility.Value
                plot_simulationLink(app)
            end
            drawnow

            if ProgressDialogFlag
                delete(d)
            end
        end


        %-----------------------------------------------------------------%
        function filter_TableStyle(app, Type)
            switch Type
                case {'SelectionRowChanged', 'ProgrammaticallySelectedRow'}
                    idx1 = find(app.UITable.StyleConfigurations.Target == "cell");
                    if ~isempty(idx1)
                        removeStyle(app.UITable, idx1)
                    end

                    if strcmp(Type, 'ProgrammaticallySelectedRow')
                        if ~isempty(app.UITable.Selection)
                            idx2 = app.UITable.Selection(1);
                            addStyle(app.UITable, uistyle("BackgroundColor", '#ddf0ff'), 'cell', [idx2,1])
                        end
                    end

                case 'HorizontalAlign'
                    addStyle(app.UITable, uistyle('HorizontalAlignment', 'right'), 'column', [8,10])

                case 'TerrainProfilePlot'
                    idx1 = find(app.UITable.StyleConfigurations.Target == "row");
                    if ~isempty(idx1)
                        removeStyle(app.UITable, idx1)
                    end

                    idx2 = app.UIAxes2.UserData.TX.ID;
                    if ~isempty(idx2)
                        idx3 = find(strcmp(app.UITable.Data.ID, idx2), 1);
                        if ~isempty(idx3)
                            addStyle(app.UITable, uistyle("BackgroundColor", '#c94756', 'FontColor', 'white'), 'row', idx3)
                        end
                    end
            end
        end


        %-----------------------------------------------------------------%
        function plot_General(app)
            hObjects = app.UIAxes1.Children;
            idx = contains(arrayfun(@(x) x.Tag, app.UIAxes1.Children, 'UniformOutput', false), 'ROI');
            delete(hObjects(~idx))

            rfDataHubFlag = height(app.UITable.Data);
            if rfDataHubFlag
                p1 = geoscatter(app.UIAxes1, app.UITable.Data.Latitude, app.UITable.Data.Longitude, MarkerEdgeColor='#009578', Tag='Station');
            end

            uistack(hObjects(idx))
            
            if rfDataHubFlag
                plot.datatip.Template(p1, 'winRFDataHub.Geographic', app.UITable.Data)
            end
        end


        %-----------------------------------------------------------------%
        function plot_Nodes(app)
            if ~isempty(app.pointsTable)
                idx1 = find(app.pointsTable.Reference, 1);                
                if height(app.pointsTable) > 1
                    idx2 = setdiff(1:height(app.pointsTable), idx1);
                    p1 = geoscatter(app.UIAxes1, app.pointsTable.Latitude(idx2), app.pointsTable.Longitude(idx2), 'Marker', '^', 'MarkerEdgeColor', '#c94756', 'Tag', 'OthersNodes');
                    plot.datatip.Template(p1, 'winRFDataHub.SelectedNode')
                end
                
                p2 = geoscatter(app.UIAxes1, app.pointsTable.Latitude(idx1), app.pointsTable.Longitude(idx1), 'Marker', '^', 'MarkerEdgeColor', '#c94756', 'MarkerFaceColor', '#c94756', 'Tag', 'SelectedNode');
                plot.datatip.Template(p2, 'winRFDataHub.SelectedNode')
            end
        end


        %-----------------------------------------------------------------%
        function plot_SelectedRow(app)
            hSelected = findobj(app.UIAxes1, Tag='SelectedRow');
            
            if ~isempty(app.UITable.Selection)
                idx = app.UITable.Selection(1);
                app.tool_LOGButton.Enable = 1;

                if isempty(hSelected)
                    hSelected = geoscatter(app.UIAxes1, app.UITable.Data.Latitude(idx), app.UITable.Data.Longitude(idx), MarkerEdgeColor='#c94756', ...
                                                                                                                         MarkerFaceColor='#c94756', ...
                                                                                                                         Tag='SelectedRow');
                else
                    set(hSelected, 'LatitudeData',  app.UITable.Data.Latitude(idx), ...
                                   'LongitudeData', app.UITable.Data.Longitude(idx))
                end
                plot.datatip.Template(hSelected, 'winRFDataHub.Geographic', app.UITable.Data(idx,:))
                layout_simulationLink(app, idx)
                
            else
                delete(hSelected)
                app.tool_LOGButton.Enable = 0;

                layout_simulationLink(app, [])
            end
        end


        %-----------------------------------------------------------------%
        function plot_simulationLink(app)
            % VALIDAÇÕES
            if isempty(app.UITable.Selection) || isempty(app.pointsTable)
                return
            end
            idx = app.UITable.Selection(1);

            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');
            
            % OBJETOS TX e RX
            [txObj, rxObj] = misc_simulationObjects(app, idx);       

            % ELEVAÇÃO DO LINK TX-RX
            nPoints = str2double(app.misc_PointsPerLink.Value);
            Server  = app.misc_ElevationAPISource.Value;

            [wayPoints3D, msgWarning] = Get(app.elevationObj, txObj, rxObj, nPoints, false, Server);
            if ~isempty(msgWarning)
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
            end

            % PLOT: MAPA
            delete(findobj(app.UIAxes1, 'Tag', 'RFLinkView', '-or', 'Tag', 'RFLink'))
            geoplot(app.UIAxes1, wayPoints3D(:,1), wayPoints3D(:,2), Color='#c94756', LineStyle='-.', PickableParts='none', Tag='RFLinkView');

            % PLOT: RFLink
            plot.RFLink(app.UIAxes2, txObj, rxObj, wayPoints3D)

            app.restoreView = {app.UIAxes2.XLim, app.UIAxes2.YLim};
            filter_TableStyle(app, 'TerrainProfilePlot')

            delete(d)
        end
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function externalRequest(app, request)
            switch request.Type
                case 'TerrainProfilePlot'
                    try
                        % Aba 1: PONTO DE MONITORAÇÃO
                        % Avalia a distância do sensor registrada na tabela
                        % app.peaksTable (propriedade do app principal) e a
                        % distância dos pontos de monitoração registrados
                        % em app.pointsTable (os quais estão sujeitos à
                        % edição).
                        rxLat = request.rxLatitude;
                        rxLon = request.rxLongitude;

                        refIndex = find(app.pointsTable.Reference, 1);                        
                        minDist  = deg2km(distance(app.pointsTable.Latitude(refIndex), app.pointsTable.Longitude(refIndex), rxLat, rxLon))/1000;

                        if minDist > class.Constants.floatDiffTolerance
                            points_NodeList(app)
                            [~, minDistIndex] = min(distance(app.pointsTable.Latitude, app.pointsTable.Longitude, rxLat, rxLon));

                            refIndex = find(app.pointsTable.Reference, 1);
                            if refIndex ~= minDistIndex
                                app.pointsTable.Reference               = zeros(height(app.pointsTable), 1, 'logical');
                                app.pointsTable.Reference(minDistIndex) = true;
                                
                                points_TreeBuilding(app, minDistIndex)
                            end
                        end

                        % Aba 2: FILTRO
                        filter_delFilter(app, struct('Source', app.filter_delAllButton))
                        txStationNumber = request.txStationNumber;

                        app.filter_SecondaryType8.Value = true;
                        filter_typePanelSelectionChanged(app)
                        app.filter_SecondaryNumValue1.Value = double(txStationNumber);
                        filter_addFilter(app)

                        app.UITable.Selection = [1 1];
                        UITableSelectionChanged(app)

                        % AJUSTES FINAIS: ENLACE E LAYOUT
                        % A versão compilada está deixando de renderizar em
                        % tela algumas das personalizações abaixo... por
                        % isso inserido o drawnow.
                        drawnow nocallbacks
                        pause(.1)
                        misc_TerrainProfileRefreshImage(app)
                        
                        general_LeftPanelMenuSelectionChanged(app, struct('Source', app.menu_Button2Icon))
                        figure(app.UIFigure)
                    catch

                    end

                case 'StationsMap'

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.General    = mainapp.General;
            app.rootFolder = mainapp.rootFolder;
            app.specData   = mainapp.specData;

            appUtil.winPosition(app.UIFigure)
            jsBackDoor_Initialization(app)

            app.Grid.ColumnWidth(7:8) = {0,0};
            general_ModeRadioGroupSelectionChanged(app)

            startup_timerCreation(app)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            if ~isempty(app.CallingApp)
                app.CallingApp.auxiliarWin3 = [];
            end

            delete(app)
            
        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 1 other component
        function general_LeftPanelMenuSelectionChanged(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.menu_MainGrid.ColumnWidth   = columnWidth;
            app.menuUnderline.Layout.Column = idx;
            app.ControlTabGroup.SelectedTab = app.ControlTabGroup.Children(idx);

            jsBackDoor_Customizations(app, idx)
            focus(app.jsBackDoor)
            
        end

        % Image clicked function: tool_ControlPanelVisibility
        function tool_LeftPanelVisibilityImageClicked(app, event)
            
            focus(app.jsBackDoor)

            if app.Grid.ColumnWidth{2}
                app.tool_ControlPanelVisibility.ImageSource = 'ArrowRight_32.png';
                app.Grid.ColumnWidth(2:3) = {0,0};
            else
                app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                app.Grid.ColumnWidth(2:3) = {325,10};
            end

        end

        % Image clicked function: points_refreshImage
        function points_refreshImageClicked(app, event)
            
            refPoint = app.pointsTable(app.pointsTable.Reference,:);

            points_NodeList(app)

            if ~isequal(refPoint, app.pointsTable(app.pointsTable.Reference,:))
                filter_DistanceColumn(app)
                filter_TableFiltering(app, true)
            end

        end

        % Callback function: not associated with a component
        function points_TreeSelectionChanged(app, event)
            
            if ~isempty(app.points_Tree.SelectedNodes)
                idx  = app.points_Tree.SelectedNodes.NodeData;
    
                app.points_Latitude.Value      = app.pointsTable.Latitude(idx);
                app.points_Longitude.Value     = app.pointsTable.Longitude(idx);
                app.points_AntennaHeight.Value = app.pointsTable.AntennaHeight(idx);
    
                if app.pointsTable.Reference(idx)
                    app.points_referenceButton.Enable = 0;
                else
                    app.points_referenceButton.Enable = 1;
                end
            end
            
        end

        % Menu selected function: points_delButton, points_editRowButton, 
        % ...and 1 other component
        function points_ContextMenuCallbacks(app, event)
            
            idx  = app.points_Tree.SelectedNodes.NodeData;

            if ~isempty(idx)
                editFlag = false;
                refIndex = find(app.pointsTable.Reference, 1);

                switch event.Source
                    case app.points_referenceButton
                        app.pointsTable.Reference      = zeros(height(app.pointsTable), 1, 'logical');
                        app.pointsTable.Reference(idx) = true;

                        editFlag = true;                        
    
                    case app.points_editRowButton
                        if app.points_Grid.ColumnWidth{4}
                            app.points_Grid.ColumnWidth{4} = 0;
        
                            app.points_Latitude.Editable      = 0;
                            app.points_Longitude.Editable     = 0;
                            app.points_AntennaHeight.Editable = 0;
                            points_TreeSelectionChanged(app)
                            return

                        else
                            app.points_Grid.ColumnWidth{4} = 22;
        
                            app.points_Latitude.Editable      = 1;
                            app.points_Longitude.Editable     = 1;
                            app.points_AntennaHeight.Editable = 1;
                            focus(app.points_Latitude)
                        end
    
                    case app.points_delButton                            
                        app.pointsTable(idx,:) = [];

                        if idx == refIndex
                            editFlag = true;
                            if ~isempty(app.pointsTable)
                                app.pointsTable.Reference(1) = true;
                            end
                        end
                end
                
                points_TreeBuilding(app, idx)
    
                if editFlag
                    filter_DistanceColumn(app)
                    filter_TableFiltering(app, true)
                end
            end

        end

        % Value changed function: points_Latitude, points_Longitude
        function points_editButtonPushed(app, event)
            
            switch event.Source
                case app.points_Latitude
                    focus(app.points_Longitude)

                case app.points_Longitude
                    focus(app.points_AntennaHeight)

                case app.points_AntennaHeight
                    focus(app.points_editButton)

                case app.points_editButton
                    refIndex = find(app.pointsTable.Reference, 1);
                    editFlag = true;
                    
                    if isempty(app.pointsTable)
                        idx = 1;
                    else
                        idx  = app.points_Tree.SelectedNodes.NodeData;
                        if idx ~= refIndex
                            editFlag = false;
                        end
                    end

                    newLatitude  = app.points_Latitude.Value;
                    newLongitude = app.points_Longitude.Value;
                    newLocation  = fcn.gpsFindCity(struct('Latitude', newLatitude, 'Longitude', newLongitude));
                    newHeight    = app.points_AntennaHeight.Value;

                    app.pointsTable(idx,:) = {'manual',     ...
                                              newLatitude,  ...
                                              newLongitude, ...
                                              newLocation,  ...
                                              newHeight,    ...
                                              editFlag};

                    points_TreeBuilding(app, idx)
                    
                    if editFlag
                        filter_DistanceColumn(app)
                        filter_TableFiltering(app, true)
                    end

                    % O painel de visualização/edição das coordenadas do ponto
                    % selecionado.
                    app.points_Grid.ColumnWidth{4} = 0;
                    set(app.points_Latitude,      Editable=0)
                    set(app.points_Longitude,     Editable=0)
                    set(app.points_AntennaHeight, Editable=0)
            end

        end

        % Selection changed function: filter_SecondaryTypePanel
        function filter_typePanelSelectionChanged(app, event)
                        
            selectedButton = app.filter_SecondaryTypePanel.SelectedObject;
            switch selectedButton
                case app.filter_SecondaryType1                            % BASE DE DADOS
                    filterType = 'textList1';
                    
                    tempDefault   = cellfun(@(x) strsplit(x, ' | '), app.rfDataHubSummary.Source.Categories, 'UniformOutput', false);
                    filterDefault = {};
                    for ii = 1:numel(tempDefault)
                        filterDefault = [filterDefault, tempDefault{ii}];
                    end
                    filterDefault = unique(filterDefault);

                case {app.filter_SecondaryType2, ...                      % FREQUÊNCIA
                      app.filter_SecondaryType3, ...                      % LARGURA BANDA
                      app.filter_SecondaryType6, ...                      % FISTEL
                      app.filter_SecondaryType7, ...                      % SERVIÇO
                      app.filter_SecondaryType8, ...                      % ESTAÇÃO
                      app.filter_SecondaryType11}                         % DISTÂNCIA
                    filterType = 'numeric';
                    filterDefault = {};

                case {app.filter_SecondaryType4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_SecondaryType5, ...                      % ENTIDADE
                      app.filter_SecondaryType10}                         % MUNICÍPIO
                    filterType = 'textFree';
                    filterDefault = {};

                case app.filter_SecondaryType9                            % UF
                    filterType = 'textList2';
                    filterDefault = app.rfDataHubSummary.State.Categories;

                case app.filter_SecondaryType12                           % ROI
                    filterType = 'ROI';
                    filterDefault = {'CÍRCULO', 'RETÂNGULO', 'POLÍGONO'};
            end

            layout_FilterOperationPanel(app, filterType, filterDefault)
            filter_SecondaryValuePanelSelectionChanged(app)
            
        end

        % Selection changed function: filter_SecondaryValuePanel
        function filter_SecondaryValuePanelSelectionChanged(app, event)
            
            selectedButton = app.filter_SecondaryValuePanel.SelectedObject;
            switch selectedButton
                case {app.filter_SecondaryOperation9, app.filter_SecondaryOperation10}
                    app.filter_SecondaryNumSeparator.Enable = 1;
                    app.filter_SecondaryNumValue2.Enable = 1;
                    
                otherwise
                    app.filter_SecondaryNumSeparator.Enable = 0;
                    app.filter_SecondaryNumValue2.Enable = 0;
            end
            
        end

        % Value changed function: filter_SecondaryReferenciaFilter
        function filter_SecondaryReferenciaFilterValueChanged(app, event)
            
            value = app.filter_SecondaryReferenciaFilter.Value;
            if isempty(value)
                app.filter_SecondaryLogicalOperator.Items = {'E (&&)'};
            else
                app.filter_SecondaryLogicalOperator.Items = {'OU (||)'};
            end
            
        end

        % Image clicked function: filter_SecondaryAddImage
        function filter_addFilter(app, event)
            
            selectedFilterType      = app.filter_SecondaryTypePanel.SelectedObject;
            selectedFilterOperation = app.filter_SecondaryValuePanel.SelectedObject;

            if isempty(app.filter_SecondaryReferenciaFilter.Value)
                Order     = 'Node';
                RelatedID = -1;
            else
                Order     = 'Child';
                RelatedID = str2double(app.filter_SecondaryReferenciaFilter.Value(2:end));
            end
            UUID = char(matlab.lang.internal.uuid());

            switch selectedFilterType
                case {app.filter_SecondaryType1, ...                      % BASE DE DADOS
                      app.filter_SecondaryType9}                          % UF
                    Value = app.filter_SecondaryTextList.Value;

                case {app.filter_SecondaryType2, ...                      % FREQUÊNCIA
                      app.filter_SecondaryType3, ...                      % LARGURA BANDA
                      app.filter_SecondaryType6, ...                      % FISTEL
                      app.filter_SecondaryType7, ...                      % SERVIÇO
                      app.filter_SecondaryType8, ...                      % ESTAÇÃO
                      app.filter_SecondaryType11}                         % DISTÂNCIA

                    if ismember(selectedFilterOperation.Text, {'<>', '><'})
                        Value = [app.filter_SecondaryNumValue1.Value, ...
                                 app.filter_SecondaryNumValue2.Value];
                    else
                        Value = app.filter_SecondaryNumValue1.Value;
                    end

                case {app.filter_SecondaryType4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_SecondaryType5, ...                      % ENTIDADE
                      app.filter_SecondaryType10}                         % MUNICÍPIO
                    Value = app.filter_SecondaryTextFree.Value;

                case app.filter_SecondaryType12                           % ROI
                    hROI = [];

                    plot.axes.Interactivity.DefaultDisable(app.UIAxes1)
                    pause(.1)

                    switch app.filter_SecondaryTextList.Value
                        case 'CÍRCULO';   roiFcn = 'drawcircle';    roiNameArgument = '';
                        case 'RETÂNGULO'; roiFcn = 'drawrectangle'; roiNameArgument = 'Rotatable=true, ';
                        case 'POLÍGONO';  roiFcn = 'drawpolygon';   roiNameArgument = '';
                    end
                    eval(sprintf('hROI = %s(app.UIAxes1, Color=[0.40,0.73,0.88], LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI_%s");', roiFcn, roiNameArgument, UUID))
                    plot.axes.Interactivity.DefaultEnable(app.UIAxes1)

                    if isempty(hROI.Position)
                        delete(hROI)
                        return
                    end
                    addlistener(hROI, 'MovingROI', @app.filter_ROICallbacks);
                    addlistener(hROI, 'ROIMoved',  @app.filter_ROICallbacks);
                    addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plot.axes.Interactivity.DeleteListeners(src));
                    Value = {hROI};                    
            end

            newFilter     = {Order, height(app.filterTable)+1, RelatedID,           ...
                             selectedFilterType.Text, selectedFilterOperation.Text, ...
                             str2double(selectedFilterType.Tag), Value, true, UUID};
            newFilterFlag = true;

            for ii = 1:height(app.filterTable)
                if isequal(app.filterTable{ii,[4,5,7]}, newFilter([4,5,7]))
                    newFilterFlag = false;
                    break
                end
            end

            if ~newFilterFlag
                msg = 'Filtro já incluído!';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                
                return
            end

            app.filterTable(end+1,[1:6,8:9]) = newFilter([1:6,8:9]);
            app.filterTable.Value{end} = Value;

            filter_TreeBuilding(app)
            filter_TableFiltering(app, true)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_SecondaryTree.SelectedNodes)
                        return
                    end
                    idx1 = arrayfun(@(x) x.NodeData, app.filter_SecondaryTree.SelectedNodes);
                    
                    % Identifica se algum dos fluxos selecionado é um nó de
                    % filtros, inserindo na lista os seus filhos.
                    idx1 = [idx1, find(ismember(app.filterTable.RelatedID, idx1))'];

                case app.filter_delAllButton
                    idx1 = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx1)
                % Apaga os ROI's, caso existentes.
                idx2 = find(strcmp(app.filterTable.Type, 'ROI'))';
                for ii = idx2
                    if ismember(ii, idx1)
                        delete(findobj(app.UIAxes1.Children, Tag=sprintf('ROI_%s', app.filterTable.uuid{ii})))
                    end
                end                

                % E depois atualiza a tabela de filtros, a tabela renderizada
                % no app e, por fim, o plot.
                app.filterTable(idx1,:) = [];

                filter_TreeBuilding(app)
                filter_TableFiltering(app, true)
            end

        end

        % Callback function: filter_SecondaryTree
        function filter_SecondaryTreeCheckedNodesChanged(app, event)
            
            hTree             = findobj(app.filter_SecondaryTree, '-not', 'Type', 'uicheckboxtree');
            hTreeNodeDataList = arrayfun(@(x) x.NodeData, hTree);
            hCheckedNodeData  = arrayfun(@(x) x.NodeData, app.filter_SecondaryTree.CheckedNodes);

            disableIndexList  = setdiff(hTreeNodeDataList, hCheckedNodeData);
            enableIndexList   = setdiff((1:height(app.filterTable))', disableIndexList);

            app.filterTable.Enable(disableIndexList) = false;
            app.filterTable.Enable(enableIndexList)  = true;

            filter_TableFiltering(app, true)
            
        end

        % Image clicked function: tool_PDFButton
        function misc_PDFButtonButtonPushed(app, event)
            
            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');

            try
                idx = app.UITable.Selection(1);    
                fileName = fullfile(app.CallingApp.config_Folder_userPath.Value, sprintf('Relatório do Canal - Estação %d.pdf', app.UITable.Data.Station(idx)));

                if ~isfile(fileName)
                    websave(fileName, char(app.UITable.Data.URL(idx)), weboptions(Timeout=30));
                end
                app.chReportHTML.HTMLSource = fileName;
                app.chReportLabel.Text      = sprintf('Estação nº %d', app.UITable.Data.Station(idx));

            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
            end

            delete(d)

        end

        % Image clicked function: tool_ExportButton
        function misc_exportButtonPushed(app, event)
            
                defaultFilename = class.Constants.DefaultFileName(app.CallingApp.config_Folder_userPath.Value, 'RFDataHub', -1);

                [fileName, filePath, fileIndex] = uiputfile({'*.xlsx', 'Excel (*.xlsx)'}, '', defaultFilename);
                figure(app.UIFigure)
                
                if fileIndex
                    d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');

                    try
                        tempRFDataHub = class.RFDataHub.ColumnNames(app.rfDataHub(app.rfDataHubLogical,1:29), 'eng2port');
                        writetable(tempRFDataHub, fullfile(filePath, fileName), 'WriteMode', 'overwritesheet')
                    catch ME
                        appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
                    end

                    delete(d)
                end

        end

        % Callback function: not associated with a component
        function misc_HistogramOrTerrainProfileButtonPushed(app, event)
            
            if event.PreviousValue
                event.Source.Value = true;
                return
            end

            switch event.Source
                case app.tool_HistogramAxesVisibility; app.tool_simulationAxesVisibility.Value = 0;
                otherwise;                             app.tool_HistogramAxesVisibility.Value  = 0;
            end

            layout_CartesianAxesLayout(app)
            drawnow
            
        end

        % Image clicked function: tool_simulationButton
        function misc_TerrainProfileRefreshImage(app, event)
            
            if ~app.tool_simulationAxesVisibility.Value
                app.tool_simulationAxesVisibility.Value = true;
                misc_HistogramOrTerrainProfileButtonPushed(app, struct('Source', app.tool_simulationAxesVisibility, 'PreviousValue', false))
            end

            plot_simulationLink(app)

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
            filter_TableStyle(app, 'SelectionRowChanged')
            plot_SelectedRow(app)
            drawnow
            
        end

        % Image clicked function: tool_LOGButton
        function tool_LOGButtonPushed(app, event)
            
            if ~isempty(app.UITable.Selection)
                global RFDataHubLog

                tableIndex = app.UITable.Selection(1);
                txIndex    = str2double(extractAfter(app.UITable.Data.ID{tableIndex}, '#'));                
                logIndex   = app.rfDataHub.Log(txIndex);

                [logInfo, msgError] = class.RFDataHub.queryLog(RFDataHubLog, logIndex);

                if isempty(logInfo) && isempty(msgError)
                    msg = 'Não evidenciada alteração do registro.';
                elseif isempty(msgError)
                    msg = jsonencode(logInfo, "PrettyPrint", true);
                else
                    msg = msgError;
                end
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
            end

        end

        % Selection changed function: general_ModeRadioGroup
        function general_ModeRadioGroupSelectionChanged(app, event)

            switch app.general_ModeRadioGroup.SelectedObject
                case app.general_ModeSearch
                    app.Tab1_Grid.RowHeight(3:4) = {'1x',0};
                    app.axesTool_TableVisibility.Enable = 1;
                case app.general_ModeLink
                    app.Tab1_Grid.RowHeight(3:4) = {0,'1x'};
                    app.axesTool_TableVisibility.Enable = 0;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.toolGrid = uigridlayout(app.Grid);
            app.tool_ControlPanelVisibility = uiimage(app.toolGrid);
            app.tool_ControlPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_LeftPanelVisibilityImageClicked, true);
            app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.tool_LOGButton = uiimage(app.toolGrid);
            app.tool_LOGButton.ImageClickedFcn = createCallbackFcn(app, @tool_LOGButtonPushed, true);
            app.tool_LOGButton.Tooltip = {'LOG do registro selecionado'};
            app.tool_LOGButton.ImageSource = 'LOG_32.png';
            app.tool_PDFButton = uiimage(app.toolGrid);
            app.tool_PDFButton.ImageClickedFcn = createCallbackFcn(app, @misc_PDFButtonButtonPushed, true);
            app.tool_PDFButton.Tooltip = {'Mostra relatório do canal do registro selecionado'; '(limitado à radiodifusão)'};
            app.tool_PDFButton.ImageSource = 'Publish_PDF_16.png';
            app.tool_simulationButton = uiimage(app.toolGrid);
            app.tool_simulationButton.ImageClickedFcn = createCallbackFcn(app, @misc_TerrainProfileRefreshImage, true);
            app.tool_simulationButton.Tooltip = {'Mostra perfil de terreno entre registro selecionado (TX) '; 'e estação de referência (RX)'};
            app.tool_simulationButton.ImageSource = 'Publish_HTML_16.png';
            app.jsBackDoor = uihtml(app.toolGrid);
            app.tool_ExportButton = uiimage(app.toolGrid);
            app.tool_ExportButton.ImageClickedFcn = createCallbackFcn(app, @misc_exportButtonPushed, true);
            app.tool_ExportButton.ImageSource = 'Export_16.png';
            app.filter_Summary = uiimage(app.toolGrid);
            app.filter_Summary.Tooltip = {'Informações acerca do processo de análise dos dados'};
            app.filter_Summary.ImageSource = 'Info_32.png';
            app.plotPanel = uipanel(app.Grid);
            app.axesToolbarGrid = uigridlayout(app.Grid);
            app.axesTool_RestoreView = uiimage(app.axesToolbarGrid);
            app.axesTool_RestoreView.Tooltip = {'RestoreView'};
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_RegionZoom = uiimage(app.axesToolbarGrid);
            app.axesTool_RegionZoom.Tooltip = {'RegionZoom'};
            app.axesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.axesTool_DataTip = uiimage(app.axesToolbarGrid);
            app.axesTool_DataTip.Tooltip = {'DataTip'};
            app.axesTool_DataTip.ImageSource = 'DataTip_22.png';
            app.axesTool_TableVisibility = uiimage(app.axesToolbarGrid);
            app.axesTool_TableVisibility.Tooltip = {'Distortion'};
            app.axesTool_TableVisibility.ImageSource = 'View_16.png';
            app.ControlTabGrid = uigridlayout(app.Grid);
            app.ControlTabGroup = uitabgroup(app.ControlTabGrid);
            app.Tab_1 = uitab(app.ControlTabGroup);
            app.Tab1_Grid = uigridlayout(app.Tab_1);
            app.general_ModeLabel = uilabel(app.Tab1_Grid);
            app.general_ModeLabel.Text = 'FUNCIONALIDADE';
            app.general_ModeRadioGroup = uibuttongroup(app.Tab1_Grid);
            app.general_ModeRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @general_ModeRadioGroupSelectionChanged, true);
            app.general_ModeSearch = uiradiobutton(app.general_ModeRadioGroup);
            app.general_ModeSearch.Text = 'Pesquisa à base RFDataHub';
            app.general_ModeLink = uiradiobutton(app.general_ModeRadioGroup);
            app.general_ModeLink.Text = 'Perfil de relevo';
            app.general_ModeLink.Value = true;
            app.link_Grid = uigridlayout(app.Tab1_Grid);
            app.link_TableLabel = uilabel(app.link_Grid);
            app.link_TableLabel.Text = 'PONTOS DE INTERESSE';
            app.link_AddStation = uiimage(app.link_Grid);
            app.link_AddStation.ImageSource = 'addSymbol_32.png';
            app.link_Table = uitable(app.link_Grid);
            app.link_Table.ColumnName = {'ID'; 'TIPO'; 'LATITUDE'; 'LONGITUDE'; 'ALTURA'};
            app.link_Table.RowName = {};
            app.link_Table.ColumnEditable = [false true true true true];
            app.link_LinkInfoPanel = uipanel(app.link_Grid);
            app.link_LinkInfoGrid = uigridlayout(app.link_LinkInfoPanel);
            app.link_LinkInfo = uihtml(app.link_LinkInfoGrid);
            app.GridLayout = uigridlayout(app.Tab1_Grid);
            app.search_SelectedRowInfoPanel = uipanel(app.GridLayout);
            app.search_SelectedRowInfoGrid = uigridlayout(app.search_SelectedRowInfoPanel);
            app.search_SelectedRowInfo = uihtml(app.search_SelectedRowInfoGrid);
            app.REGISTROSELECIONADOLabel = uilabel(app.GridLayout);
            app.REGISTROSELECIONADOLabel.Text = 'REGISTRO SELECIONADO';
            app.Tab_2 = uitab(app.ControlTabGroup);
            app.Tab2_Grid = uigridlayout(app.Tab_2);
            app.filter_PrimaryLabel = uilabel(app.Tab2_Grid);
            app.filter_PrimaryLabel.Text = 'PRIMÁRIA';
            app.filter_PrimaryEditImage = uiimage(app.Tab2_Grid);
            app.filter_PrimaryEditImage.ImageSource = 'Edit_32.png';
            app.filter_PrimaryPanel = uipanel(app.Tab2_Grid);
            app.filter_PrimaryGrid = uigridlayout(app.filter_PrimaryPanel);
            app.filter_Primary = uidropdown(app.filter_PrimaryGrid);
            app.filter_Primary.Items = {'Fonte', 'Entidade', 'Serviço', 'UF', 'Município'};
            app.filter_Primary.Value = 'UF';
            app.filter_PrimaryDropDown = uidropdown(app.filter_PrimaryGrid);
            app.filter_PrimaryDropDown.Items = {};
            app.filter_PrimaryDropDown.Value = {};
            app.filter_PrimaryEditField = uieditfield(app.filter_PrimaryGrid, 'text');
            app.filter_PrimaryEditField.Visible = 'off';
            app.filter_SecondaryLabel = uilabel(app.Tab2_Grid);
            app.filter_SecondaryLabel.Text = 'SECUNDÁRIA';
            app.filter_SecondaryTypePanel = uibuttongroup(app.Tab2_Grid);
            app.filter_SecondaryTypePanel.SelectionChangedFcn = createCallbackFcn(app, @filter_typePanelSelectionChanged, true);
            app.filter_SecondaryType1 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType1.Tag = '16';
            app.filter_SecondaryType1.Text = 'Fonte';
            app.filter_SecondaryType1.Value = true;
            app.filter_SecondaryType2 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType2.Tag = '1';
            app.filter_SecondaryType2.Text = 'Frequência';
            app.filter_SecondaryType3 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType3.Tag = '13';
            app.filter_SecondaryType3.Text = 'Largura banda';
            app.filter_SecondaryType4 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType4.Tag = '12';
            app.filter_SecondaryType4.Text = 'Classe emissão';
            app.filter_SecondaryType5 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType5.Tag = '2';
            app.filter_SecondaryType5.Text = 'Entidade';
            app.filter_SecondaryType6 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType6.Tag = '3';
            app.filter_SecondaryType6.Text = 'Fistel';
            app.filter_SecondaryType7 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType7.Tag = '4';
            app.filter_SecondaryType7.Text = 'Serviço';
            app.filter_SecondaryType8 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType8.Tag = '5';
            app.filter_SecondaryType8.Text = 'Estação';
            app.filter_SecondaryType9 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType9.Tag = '10';
            app.filter_SecondaryType9.Text = 'UF';
            app.filter_SecondaryType10 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType10.Tag = '9';
            app.filter_SecondaryType10.Text = 'Município';
            app.filter_SecondaryType11 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType11.Tag = '32';
            app.filter_SecondaryType11.Text = 'Distância';
            app.filter_SecondaryType12 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType12.Tag = '-1';
            app.filter_SecondaryType12.Text = 'ROI';
            app.filter_SecondaryValuePanel = uibuttongroup(app.Tab2_Grid);
            app.filter_SecondaryValuePanel.SelectionChangedFcn = createCallbackFcn(app, @filter_SecondaryValuePanelSelectionChanged, true);
            app.filter_SecondaryOperation1 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation1.Tag = 'numeric+textFree+textList2';
            app.filter_SecondaryOperation1.Tooltip = {'Igual'};
            app.filter_SecondaryOperation1.Text = '=';
            app.filter_SecondaryOperation2 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation2.Tag = 'numeric+textFree+textList2';
            app.filter_SecondaryOperation2.Tooltip = {'Diferente'};
            app.filter_SecondaryOperation2.Text = '≠';
            app.filter_SecondaryOperation3 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation3.Tag = 'ROI+textFree+textList1';
            app.filter_SecondaryOperation3.Tooltip = {'Contém'};
            app.filter_SecondaryOperation3.Text = '⊃';
            app.filter_SecondaryOperation3.Value = true;
            app.filter_SecondaryOperation4 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation4.Tag = 'textFree+textList1';
            app.filter_SecondaryOperation4.Tooltip = {'Não contém'};
            app.filter_SecondaryOperation4.Text = '⊅';
            app.filter_SecondaryOperation5 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation5.Tag = 'numeric';
            app.filter_SecondaryOperation5.Tooltip = {'Menor'};
            app.filter_SecondaryOperation5.Text = '<';
            app.filter_SecondaryOperation6 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation6.Tag = 'numeric';
            app.filter_SecondaryOperation6.Tooltip = {'Menor ou igual'};
            app.filter_SecondaryOperation6.Text = '≤';
            app.filter_SecondaryOperation7 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation7.Tag = 'numeric';
            app.filter_SecondaryOperation7.Tooltip = {'Maior'};
            app.filter_SecondaryOperation7.Text = '>';
            app.filter_SecondaryOperation8 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation8.Tag = 'numeric';
            app.filter_SecondaryOperation8.Tooltip = {'Maior ou igual'};
            app.filter_SecondaryOperation8.Text = '≥';
            app.filter_SecondaryOperation9 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation9.Tag = 'numeric';
            app.filter_SecondaryOperation9.Tooltip = {'Dentro do intervalo'};
            app.filter_SecondaryOperation9.Text = '><';
            app.filter_SecondaryOperation10 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation10.Tag = 'numeric';
            app.filter_SecondaryOperation10.Tooltip = {'Fora do intervalo'};
            app.filter_SecondaryOperation10.Text = '<>';
            app.filter_SecondaryValueSubpanel = uipanel(app.filter_SecondaryValuePanel);
            app.filter_SecondaryValueGrid = uigridlayout(app.filter_SecondaryValueSubpanel);
            app.filter_SecondaryNumValue1 = uieditfield(app.filter_SecondaryValueGrid, 'numeric');
            app.filter_SecondaryNumValue1.Limits = [-1 Inf];
            app.filter_SecondaryNumValue1.ValueDisplayFormat = '%10.10g';
            app.filter_SecondaryNumValue1.Value = -1;
            app.filter_SecondaryNumSeparator = uilabel(app.filter_SecondaryValueGrid);
            app.filter_SecondaryNumSeparator.Text = '-';
            app.filter_SecondaryNumValue2 = uieditfield(app.filter_SecondaryValueGrid, 'numeric');
            app.filter_SecondaryNumValue2.Limits = [-1 Inf];
            app.filter_SecondaryNumValue2.Value = -1;
            app.filter_SecondaryTextFree = uieditfield(app.filter_SecondaryValueGrid, 'text');
            app.filter_SecondaryTextList = uidropdown(app.filter_SecondaryValueGrid);
            app.filter_SecondaryTextList.Items = {};
            app.filter_SecondaryTextList.Value = {};
            app.filter_SecondaryReferenciaFilterLabel = uilabel(app.filter_SecondaryValueGrid);
            app.filter_SecondaryReferenciaFilterLabel.Text = {'Filtro de '; 'referência (ID):'};
            app.filter_SecondaryReferenciaFilter = uidropdown(app.filter_SecondaryValueGrid);
            app.filter_SecondaryReferenciaFilter.Items = {};
            app.filter_SecondaryReferenciaFilter.ValueChangedFcn = createCallbackFcn(app, @filter_SecondaryReferenciaFilterValueChanged, true);
            app.filter_SecondaryReferenciaFilter.Value = {};
            app.filter_SecondaryLogicalOperatorLabel = uilabel(app.filter_SecondaryValueGrid);
            app.filter_SecondaryLogicalOperatorLabel.Text = {'Operador '; 'lógico:'};
            app.filter_SecondaryLogicalOperator = uidropdown(app.filter_SecondaryValueGrid);
            app.filter_SecondaryLogicalOperator.Items = {'E (&&)'};
            app.filter_SecondaryLogicalOperator.Value = 'E (&&)';
            app.filter_SecondaryAddImage = uiimage(app.Tab2_Grid);
            app.filter_SecondaryAddImage.ImageClickedFcn = createCallbackFcn(app, @filter_addFilter, true);
            app.filter_SecondaryAddImage.Tooltip = {'Adicionar filtro'};
            app.filter_SecondaryAddImage.ImageSource = 'addSymbol_32.png';
            app.filter_SecondaryTree = uitree(app.Tab2_Grid, 'checkbox');
            % Assign Checked Nodes
            app.filter_SecondaryTree.CheckedNodesChangedFcn = createCallbackFcn(app, @filter_SecondaryTreeCheckedNodesChanged, true);
            app.points_refreshImage = uiimage(app.Tab2_Grid);
            app.points_refreshImage.ImageClickedFcn = createCallbackFcn(app, @points_refreshImageClicked, true);
            app.points_refreshImage.Tooltip = {'Atualiza estação de referência'};
            app.points_refreshImage.ImageSource = 'Refresh_18.png';
            app.points_Panel = uipanel(app.Tab2_Grid);
            app.points_Grid = uigridlayout(app.points_Panel);
            app.points_LatitudeLabel = uilabel(app.points_Grid);
            app.points_LatitudeLabel.Text = 'Latitude:';
            app.points_Latitude = uieditfield(app.points_Grid, 'numeric');
            app.points_Latitude.ValueDisplayFormat = '%.6f';
            app.points_Latitude.ValueChangedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.points_Latitude.Editable = 'off';
            app.points_Latitude.Value = -1;
            app.points_LongitudeLabel = uilabel(app.points_Grid);
            app.points_LongitudeLabel.Text = 'Longitude:';
            app.points_Longitude = uieditfield(app.points_Grid, 'numeric');
            app.points_Longitude.ValueDisplayFormat = '%.6f';
            app.points_Longitude.ValueChangedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.points_Longitude.Editable = 'off';
            app.points_Longitude.Value = -1;
            app.points_LongitudeLabel_2 = uilabel(app.points_Grid);
            app.points_LongitudeLabel_2.Text = 'Altura (metros):';
            app.points_Longitude_2 = uieditfield(app.points_Grid, 'numeric');
            app.points_Longitude_2.Limits = [0 Inf];
            app.points_Longitude_2.RoundFractionalValues = 'on';
            app.points_Longitude_2.ValueDisplayFormat = '%d';
            app.points_Longitude_2.Editable = 'off';
            app.points_TreeLabel = uilabel(app.Tab2_Grid);
            app.points_TreeLabel.Interpreter = 'html';
            app.points_TreeLabel.Text = {'<b>Estação de referência (RX)</b>'; '<font style="font-size: 9px; color: gray;">(relacionada à coluna calculada "Distância")</font>'};
            app.Image2 = uiimage(app.Tab2_Grid);
            app.Image2.ImageSource = 'Pin_32.png';
            app.Tab_3 = uitab(app.ControlTabGroup);
            app.Tab3_Grid = uigridlayout(app.Tab_3);
            app.config_geoAxesLabel = uilabel(app.Tab3_Grid);
            app.config_geoAxesLabel.Interpreter = 'html';
            app.config_geoAxesLabel.Text = {'EIXO GEOGRÁFICO'; '<p style="color: gray; font-size: 9px; text-align: justify; padding-right: 2px;">Os parâmetros relacionados ao eixo geográfico, com exceção do VEÍCULO, são customizáveis por canal sob análise.</p>'};
            app.config_Refresh = uiimage(app.Tab3_Grid);
            app.config_Refresh.Visible = 'off';
            app.config_Refresh.Tooltip = {'Volta à configuração inicial'};
            app.config_Refresh.ImageSource = 'Refresh_18.png';
            app.config_geoAxesPanel = uipanel(app.Tab3_Grid);
            app.config_geoAxesGrid = uigridlayout(app.config_geoAxesPanel);
            app.config_geoAxesSublabel = uilabel(app.config_geoAxesGrid);
            app.config_geoAxesSublabel.Text = 'Mapa:';
            app.config_geoAxesSubPanel = uipanel(app.config_geoAxesGrid);
            app.config_geoAxesSubGrid = uigridlayout(app.config_geoAxesSubPanel);
            app.config_BasemapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_BasemapLabel.Text = 'Basemap:';
            app.config_Basemap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Basemap.Items = {'none', 'darkwater', 'streets-light', 'streets-dark', 'satellite', 'topographic', 'grayterrain'};
            app.config_Basemap.Value = 'satellite';
            app.config_ColormapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_ColormapLabel.Text = 'Mapa de cor:';
            app.config_Colormap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.config_Colormap.Value = 'winter';
            app.config_route_Label = uilabel(app.config_geoAxesGrid);
            app.config_route_Label.Text = 'Rota:';
            app.config_route_LineStyle = uidropdown(app.config_geoAxesGrid);
            app.config_route_LineStyle.Items = {'none', ':', '-'};
            app.config_route_LineStyle.Value = 'none';
            app.config_route_OutColor = uicolorpicker(app.config_geoAxesGrid);
            app.config_route_OutColor.Value = [0.502 0.502 0.502];
            app.config_route_InColor = uicolorpicker(app.config_geoAxesGrid);
            app.config_route_InColor.Value = [0.8706 0.5412 0.5412];
            app.config_route_Size = uislider(app.config_geoAxesGrid);
            app.config_route_Size.Limits = [1 9];
            app.config_route_Size.MajorTicks = [];
            app.config_route_Size.MinorTicks = [];
            app.config_route_Size.Tooltip = {'Tamanho do marcador'};
            app.config_route_Size.Value = 1;
            app.config_Car_Label = uilabel(app.config_geoAxesGrid);
            app.config_Car_Label.Text = 'Veículo:';
            app.config_Car_LineStyle = uidropdown(app.config_geoAxesGrid);
            app.config_Car_LineStyle.Items = {'none', 'o', 'square', '^'};
            app.config_Car_LineStyle.Value = 'none';
            app.config_Car_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_Car_Size = uislider(app.config_geoAxesGrid);
            app.config_Car_Size.Limits = [1 19];
            app.config_Car_Size.MajorTicks = [];
            app.config_Car_Size.MinorTicks = [];
            app.config_Car_Size.Tooltip = {'Tamanho do marcador'};
            app.config_Car_Size.Value = 10;
            app.config_points_Label = uilabel(app.config_geoAxesGrid);
            app.config_points_Label.Text = {'Pontos de'; 'interesse:'};
            app.config_points_LineStyle = uidropdown(app.config_geoAxesGrid);
            app.config_points_LineStyle.Items = {'none', 'o', 'square', '^'};
            app.config_points_LineStyle.Value = 'none';
            app.config_points_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_points_Color.Value = [0 0 0];
            app.config_points_Size = uislider(app.config_geoAxesGrid);
            app.config_points_Size.Limits = [6 12];
            app.config_points_Size.MajorTicks = [];
            app.config_points_Size.MinorTicks = [];
            app.config_points_Size.Tooltip = {'Tamanho do marcador'};
            app.config_points_Size.Value = 9;
            app.config_xyAxesLabel = uilabel(app.Tab3_Grid);
            app.config_xyAxesLabel.Interpreter = 'html';
            app.config_xyAxesLabel.Text = {'EIXOS CARTESIANOS'; '<p style="color: gray; font-size: 9px; text-align: justify; padding-right: 2px;">Os parâmetros relacionados aos eixos cartesianos não são customizáveis.</p>'};
            app.config_xyAxesPanel = uipanel(app.Tab3_Grid);
            app.config_xyAxesGrid = uigridlayout(app.config_xyAxesPanel);
            app.config_PersistanceLabel = uilabel(app.config_xyAxesGrid);
            app.config_PersistanceLabel.Text = 'Persistência:';
            app.config_PersistanceVisibility = uidropdown(app.config_xyAxesGrid);
            app.config_PersistanceVisibility.Items = {'on', 'off'};
            app.config_PersistanceVisibility.Tooltip = {''};
            app.config_PersistanceVisibility.Value = 'on';
            app.config_chROILabel = uilabel(app.config_xyAxesGrid);
            app.config_chROILabel.Text = 'Canal:';
            app.config_chROIVisibility = uidropdown(app.config_xyAxesGrid);
            app.config_chROIVisibility.Items = {'on', 'off'};
            app.config_chROIVisibility.Tooltip = {''};
            app.config_chROIVisibility.Value = 'on';
            app.config_chROIColor = uicolorpicker(app.config_xyAxesGrid);
            app.config_chROIColor.Value = [0.7216 0.2706 1];
            app.config_chROIColor.Tooltip = {''};
            app.config_chROIEdgeAlpha = uispinner(app.config_xyAxesGrid);
            app.config_chROIEdgeAlpha.Step = 0.1;
            app.config_chROIEdgeAlpha.Limits = [0 1];
            app.config_chROIEdgeAlpha.ValueDisplayFormat = '%.1f';
            app.config_chROIEdgeAlpha.Tooltip = {'Transparência da margem'};
            app.config_chROIFaceAlpha = uispinner(app.config_xyAxesGrid);
            app.config_chROIFaceAlpha.Step = 0.1;
            app.config_chROIFaceAlpha.Limits = [0 1];
            app.config_chROIFaceAlpha.ValueDisplayFormat = '%.1f';
            app.config_chROIFaceAlpha.Tooltip = {'Transparência da face'};
            app.config_chROIFaceAlpha.Value = 0.4;
            app.config_chPowerLabel = uilabel(app.config_xyAxesGrid);
            app.config_chPowerLabel.Text = {'Potência do'; 'canal:'};
            app.config_chPowerVisibility = uidropdown(app.config_xyAxesGrid);
            app.config_chPowerVisibility.Items = {'on', 'off'};
            app.config_chPowerVisibility.Tooltip = {''};
            app.config_chPowerVisibility.Value = 'on';
            app.config_chPowerColor = uicolorpicker(app.config_xyAxesGrid);
            app.config_chPowerColor.Value = [0.5686 1 0];
            app.config_chPowerColor.Tooltip = {''};
            app.config_chPowerEdgeAlpha = uispinner(app.config_xyAxesGrid);
            app.config_chPowerEdgeAlpha.Step = 0.1;
            app.config_chPowerEdgeAlpha.Limits = [0 1];
            app.config_chPowerEdgeAlpha.ValueDisplayFormat = '%.1f';
            app.config_chPowerEdgeAlpha.Tooltip = {'Transparência da margem'};
            app.config_chPowerEdgeAlpha.Value = 1;
            app.config_chPowerFaceAlpha = uispinner(app.config_xyAxesGrid);
            app.config_chPowerFaceAlpha.Step = 0.1;
            app.config_chPowerFaceAlpha.Limits = [0 1];
            app.config_chPowerFaceAlpha.ValueDisplayFormat = '%.1f';
            app.config_chPowerFaceAlpha.Tooltip = {'Transparência da face'};
            app.config_chPowerFaceAlpha.Value = 0.4;
            app.ELEVAOLabel = uilabel(app.Tab3_Grid);
            app.ELEVAOLabel.Text = 'ELEVAÇÃO';
            app.misc_ElevationSourcePanel = uipanel(app.Tab3_Grid);
            app.misc_ElevationSourceGrid = uigridlayout(app.misc_ElevationSourcePanel);
            app.misc_ElevationAPISourceLabel = uilabel(app.misc_ElevationSourceGrid);
            app.misc_ElevationAPISourceLabel.Text = 'Fonte:';
            app.misc_ElevationAPISource = uidropdown(app.misc_ElevationSourceGrid);
            app.misc_ElevationAPISource.Items = {'Open-Elevation', 'MathWorks WMS Server'};
            app.misc_ElevationAPISource.Value = 'Open-Elevation';
            app.misc_PointsPerLinkLabel = uilabel(app.misc_ElevationSourceGrid);
            app.misc_PointsPerLinkLabel.Text = 'Pontos enlace:';
            app.misc_PointsPerLink = uidropdown(app.misc_ElevationSourceGrid);
            app.misc_PointsPerLink.Items = {'64', '128', '256', '512', '1024'};
            app.misc_PointsPerLink.Value = '256';
            app.menu_MainGrid = uigridlayout(app.ControlTabGrid);
            app.menuUnderline = uiimage(app.menu_MainGrid);
            app.menuUnderline.ImageSource = 'LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'RFDATAHUB';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelMenuSelectionChanged, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'mosaic_18Gray.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'FILTRAGEM';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelMenuSelectionChanged, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'Filter_18.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'CONFIGURAÇÕES GERAIS';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelMenuSelectionChanged, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'Settings_18.png';
            app.UITable = uitable(app.Grid);
            app.UITable.ColumnName = {'ID'; 'FREQUÊNCIA|(MHz)'; 'LATITUDE'; 'LONGITUDE'; 'DESCRIÇÃO|(Entidade+Fistel+Multiplicidade+Localidade)'; 'SERVIÇO'; 'ESTAÇÃO'; 'CLASSE|EMISSÃO'; 'LARGURA|(kHz)'; 'URL'; 'DISTÂNCIA|(km)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false false false false false false false false true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.chReportHTML = uihtml(app.Grid);
            app.chReportHTML.HTMLSource = 'pdfLogo.html';
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.points_ContextMenu = uicontextmenu(app.UIFigure);
            app.points_referenceButton = uimenu(app.points_ContextMenu);
            app.points_referenceButton.MenuSelectedFcn = createCallbackFcn(app, @points_ContextMenuCallbacks, true);
            app.points_referenceButton.Text = 'Referência';
            app.points_editRowButton = uimenu(app.points_ContextMenu);
            app.points_editRowButton.MenuSelectedFcn = createCallbackFcn(app, @points_ContextMenuCallbacks, true);
            app.points_editRowButton.Separator = 'on';
            app.points_editRowButton.Text = 'Editar';
            app.points_delButton = uimenu(app.points_ContextMenu);
            app.points_delButton.MenuSelectedFcn = createCallbackFcn(app, @points_ContextMenuCallbacks, true);
            app.points_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRFDataHub_new(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end