classdef winSignalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout8            matlab.ui.container.GridLayout
        GridLayout5            matlab.ui.container.GridLayout
        tool_simulationButton  matlab.ui.control.Button
        Image_2                matlab.ui.control.Image
        jsBackDoor             matlab.ui.control.HTML
        Image                  matlab.ui.control.Image
        Button_globalList      matlab.ui.control.Button
        Button_edit            matlab.ui.control.Button
        Button_del             matlab.ui.control.Button
        Button_save            matlab.ui.control.Button
        Grid1                  matlab.ui.container.GridLayout
        Panel_5                matlab.ui.container.Panel
        GridLayout6            matlab.ui.container.GridLayout
        LOG                    matlab.ui.control.Label
        LOGLabel               matlab.ui.control.Label
        Panel_4                matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        Grid4_2                matlab.ui.container.GridLayout
        StationID              matlab.ui.control.EditField
        StationIDLabel         matlab.ui.control.Label
        Info                   matlab.ui.control.TextArea
        InfoLabel              matlab.ui.control.Label
        RiskLevel_edited       matlab.ui.control.DropDown
        RiskLevel_editedLabel  matlab.ui.control.Label
        Compliance             matlab.ui.control.DropDown
        ComplianceLabel        matlab.ui.control.Label
        Type                   matlab.ui.control.DropDown
        TypeLabel              matlab.ui.control.Label
        RegInfoLabel_edited    matlab.ui.control.Label
        RegInfo_edited         matlab.ui.control.DropDown
        ServiceLabel_edited    matlab.ui.control.Label
        Service_edited         matlab.ui.control.NumericEditField
        Sublabel2              matlab.ui.control.Label
        Label2                 matlab.ui.control.Label
        Grid4                  matlab.ui.container.GridLayout
        Method                 matlab.ui.control.TextArea
        MethodLabel            matlab.ui.control.Label
        StationLabel_raw       matlab.ui.control.Label
        Station_raw            matlab.ui.control.NumericEditField
        ServiceLabel_raw       matlab.ui.control.Label
        Service_raw            matlab.ui.control.NumericEditField
        RegInfo_raw            matlab.ui.control.TextArea
        RegInfoLabel_raw       matlab.ui.control.Label
        Sublabel1              matlab.ui.control.Label
        Label1                 matlab.ui.control.Label
        DescriptionSubLabel    matlab.ui.control.Label
        Description            matlab.ui.control.TextArea
        DescriptionLabel       matlab.ui.control.Label
        Panel_3                matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        Station_dash           matlab.ui.control.Label
        StationLabel_dash      matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        Service_dash           matlab.ui.control.Label
        ServiceLabel_dash      matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Channel                matlab.ui.control.Label
        ChannelLabel           matlab.ui.control.Label
        Grid2                  matlab.ui.container.GridLayout
        UITable                matlab.ui.control.Table
        UIAxes1                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %-------------------------------------------------------------------------%
        CallingApp
        RootFolder

        restoreView = {[0,1], [0,1]}
        row         = 1

        timerObj_startup
        tableSelectionInitialStatus = [true, true]
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj_startup = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj_startup)
        end


        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj_startup)
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app)

                % Criação do eixo e leitura dos dados...
                startup_AxesInteractions(app)
                startup_ReadInitialData(app)

                % Define tamanho mínimo da GUI (aplicável apenas para a versão 
                % desktop do app.
                ccTools.compCustomization(app.UIFigure, 'windowMinSize', class.Constants.windowMinSize);
                drawnow

                focus(app.UITable)
            end
        end


        %------------------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end


        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                   'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                       '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                       '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                   'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

            ccTools.compCustomizationV2(app.jsBackDoor, app.Method,      'textAlign', 'justify')
            ccTools.compCustomizationV2(app.jsBackDoor, app.Description, 'textAlign', 'justify')
            ccTools.compCustomizationV2(app.jsBackDoor, app.Info,        'textAlign', 'justify')
        end


        %-------------------------------------------------------------------------%
        function startup_AxesInteractions(app)
            % Toolbar
            plotFcn.axesInteraction.CartesianToolbar(app.UIAxes1, {'restoreview'});

            % Default interactions
            plotFcn.axesInteraction.CartesianDefaultInteractions(app.UIAxes1, [dataTipInteraction, rulerPanInteraction])

            % Interactions callbacks
            plotFcn.axesInteraction.InteractionsCallbacks({'Restore View'}, app.UIAxes1, app)

            hold(app.UIAxes1, 'on')
        end


        %-------------------------------------------------------------------------%
        function startup_ReadInitialData(app)
            tempData         = app.CallingApp.peaksTable(:, [1, 1, 5:12, 17:18]);
            tempData.Tag     = extractBefore(tempData.Tag, newline);
            tempData.Tag_1   = extractBetween(tempData.Tag_1, 'ID', ':');

            set(app.UITable, 'Data', tempData, 'Selection', 1)
            app.row = 1;

            TableStyle(app)
            SelectedRowValues(app)
        end
        

        %-------------------------------------------------------------------------%
        function TableStyle(app)            
            removeStyle(app.UITable)
            
            if app.tableSelectionInitialStatus(1)
                app.tableSelectionInitialStatus(1) = false;
                addStyle(app.UITable, uistyle('BackgroundColor', '#ddf0ff'), 'row', app.UITable.Selection)
            end

            addStyle(app.UITable, uistyle('HorizontalAlignment', 'left'), 'column', 3:5)
                        
            if ~isempty(app.CallingApp.exceptionList)
                ind1 = [];
                for ii = 1:height(app.CallingApp.exceptionList)
                    ind2 = intersect(find(strcmp(app.CallingApp.peaksTable.Tag,  app.CallingApp.exceptionList.Tag(ii))), ...
                                     find(abs(app.CallingApp.peaksTable.Frequency - app.CallingApp.exceptionList.Frequency(ii)) <= class.Constants.floatDiffTolerance));

                    if ~isempty(ind2)
                        ind1 = [ind1; ind2];
                    end
                end
                
                if ~isempty(ind1)
                    addStyle(app.UITable, uistyle("BackgroundColor", '#c94756', 'FontColor', 'white'), 'row', ind1)
                end
            end            
        end
        
        
        %-------------------------------------------------------------------------%
        function SelectedRowValues(app)            
            % Panel 1
            app.Method.Value      = app.CallingApp.peaksTable.Detection{app.row};
            app.RegInfo_raw.Value = app.CallingApp.peaksTable.Regulatory{app.row};
            app.Service_raw.Value = double(app.CallingApp.peaksTable.Service(app.row));
            app.Station_raw.Value = double(app.CallingApp.peaksTable.Station(app.row));
            app.Description.Value = app.CallingApp.peaksTable.Description{app.row};

            % Panel 2
            idx = exceptionListIndex(app);            
            if isempty(idx)
                app.RegInfo_edited.Value = app.RegInfo_raw.Value;
                RegInfo_editedValueChanged(app)

                app.Service_edited.Value = app.Service_raw.Value;
                app.StationID.Value      = num2str(app.Station_raw.Value);
                
                app.Type.Value           = app.CallingApp.peaksTable.Type{app.row};
                app.Compliance.Value     = app.CallingApp.peaksTable.Irregular{app.row};
                ComplianceValueChanged(app)

                app.RiskLevel_edited.Value = app.CallingApp.peaksTable.RiskLevel{app.row};
                app.Info.Value             = '';

                app.Button_del.Enable      = 0;
                
            else
                app.RegInfo_edited.Value = app.CallingApp.exceptionList.Regulatory(idx);
                RegInfo_editedValueChanged(app)

                app.Service_edited.Value = double(app.CallingApp.exceptionList.Service(idx));
                app.StationID.Value      = num2str(app.CallingApp.exceptionList.Station(idx));

                app.Type.Value           = app.CallingApp.exceptionList.Type(idx);
                app.Compliance.Value     = app.CallingApp.exceptionList.Irregular(idx);
                ComplianceValueChanged(app)

                app.RiskLevel_edited.Value = app.CallingApp.exceptionList.RiskLevel(idx);
                app.Info.Value             = replace(app.CallingApp.exceptionList.Description(idx), '[EXC] ', '');

                app.Button_del.Enable      = 1;
            end

            % Panel 3 (Dashboard)
            app.Channel.Text = sprintf('%.3f MHz ⌂ %.3f kHz', app.CallingApp.peaksTable.Frequency(app.row), ...
                                                              app.CallingApp.peaksTable.BW(app.row));

            app.Service_dash.Text = num2str(app.Service_edited.Value);
            app.Station_dash.Text = app.StationID.Value;

            Plot(app)
            LogCreation(app, idx)
        end


        %-------------------------------------------------------------------------%
        function Plot(app)

            idx = [];
            for ii = 1:numel(app.CallingApp.specData)
                Tag = sprintf('%s\nID %d: %.3f - %.3f MHz', app.CallingApp.specData(ii).Receiver,                  ...
                                                            app.CallingApp.specData(ii).RelatedFiles.ID(1),        ...
                                                            app.CallingApp.specData(ii).MetaData.FreqStart / 1e+6, ...
                                                            app.CallingApp.specData(ii).MetaData.FreqStop  / 1e+6);

                if strcmp(Tag, app.CallingApp.peaksTable.Tag{app.row})
                    idx = ii;
                    break
                end
            end

            cla(app.UIAxes1)
            if ~isempty(idx)
                color1   = [1 .07 .65];   % MaxHold
                color2   = "#005dd1";     % MinHold
                color3   = [1 1 .07];     % Median or mean
                roiColor = [.40,.73,.88]; %[.00 .45 .74]; % Band roi region
                
                % Frequency limits
                [F0_axes, F1_axes] = Plot_xLim(app);
                F0_roi  = app.CallingApp.peaksTable.Frequency(app.row) - app.CallingApp.peaksTable.BW(app.row) / 2000;
                BW_roi  = app.CallingApp.peaksTable.BW(app.row)/1000;

                % X/Y data and level limits
                rawData = app.CallingApp.specData(idx);
                xData   = linspace(rawData.MetaData.FreqStart/1e+6, rawData.MetaData.FreqStop/1e+6, rawData.MetaData.DataPoints);
                
                minY    = min(rawData.Data{3}(:, 1));
                maxY    = max(rawData.Data{3}(:, 3));

                % app.restoreView
                set(app.UIAxes1, XLim=[F0_axes, F1_axes], YLim=[minY, maxY])
                app.restoreView = {app.UIAxes1.XLim, app.UIAxes1.YLim};

                % Plot
                
                area(app.UIAxes1, xData, rawData.Data{3}(:, 1), 'EdgeColor', color2, 'FaceColor', color2, 'BaseValue', minY);
                plot(app.UIAxes1, xData, rawData.Data{3}(:, 2), 'Color', color3);
                plot(app.UIAxes1, xData, rawData.Data{3}(:, 3), 'Color', color1);
                images.roi.Rectangle(app.UIAxes1, Position = [F0_roi, -200, BW_roi, 400], Color = roiColor, Deletable = 0, EdgeAlpha = 0, InteractionsAllowed = 'none');
    
                for ii = 1:numel(app.UIAxes1.Children)
                    plotFcn.DataTipModel(app.UIAxes1.Children(ii), rawData.MetaData.LevelUnit)
                end
                ylabel(app.UIAxes1, sprintf('Nível (%s)', rawData.MetaData.LevelUnit))

            else
                msg = 'Não identificado o fluxo de dados relacionado à emissão...';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
            end
        end


        %-------------------------------------------------------------------------%
        function [F0_axes, F1_axes] = Plot_xLim(app)

            if app.CallingApp.peaksTable.BW(app.row) ~= 0
                F0_axes = app.CallingApp.peaksTable.Frequency(app.row) - 2.5*app.CallingApp.peaksTable.BW(app.row)/1000;
                F1_axes = app.CallingApp.peaksTable.Frequency(app.row) + 2.5*app.CallingApp.peaksTable.BW(app.row)/1000;
            else
                F0_axes = app.CallingApp.peaksTable.Frequency(app.row) - .5;
                F1_axes = app.CallingApp.peaksTable.Frequency(app.row) + .5;
            end
        end


        %-------------------------------------------------------------------------%
        function idx = exceptionListIndex(app)
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(abs(app.CallingApp.exceptionList.Frequency - app.CallingApp.peaksTable.Frequency(app.row)) <= class.Constants.floatDiffTolerance));

            if ~isempty(idx)
                idx = idx(1);
            end
        end


        %-------------------------------------------------------------------------%
        function LogCreation(app, idx)

            if isempty(idx)
                logmsg = 'Não identificada alteração no registro.';

            else
                peakValues      = app.CallingApp.peaksTable(app.row,:);
                exceptionValues = app.CallingApp.exceptionList(idx,:);

                color  = '#c94756';
                logmsg = {sprintf('<b style="color:%s;">Emissão:</b> "%s" → "%s"',                   color, peakValues.Regulatory{1},  exceptionValues.Regulatory{1}); ...
                          sprintf('<b style="color:%s;">Serviço:</b> "%.0f" → "%.0f"',               color, peakValues.Service,        exceptionValues.Service);       ...
                          sprintf('<b style="color:%s;">Estação:</b> "%.0f" → "%.0f"',               color, peakValues.Station,        exceptionValues.Station);       ...
                          sprintf('<b style="color:%s;">Tipo de emissão:</b> "%s" → "%s"',           color, peakValues.Type{1},        exceptionValues.Type{1});       ...
                          sprintf('<b style="color:%s;">Indício de irregularidade:</b> "%s" → "%s"', color, peakValues.Irregular{1},   exceptionValues.Irregular{1});  ...
                          sprintf('<b style="color:%s;">Potencial lesivo:</b> "%s" → "%s"',          color, peakValues.RiskLevel{1},   exceptionValues.RiskLevel{1});  ...
                          sprintf('<b style="color:%s;">Descrição:</b> "%s" → "%s"',                 color, peakValues.Description{1}, exceptionValues.Description{1})};

                if strcmp(peakValues.Description{1}, exceptionValues.Description{1}); logmsg(7) = []; end
                if strcmp(peakValues.RiskLevel{1},   exceptionValues.RiskLevel{1});   logmsg(6) = []; end
                if strcmp(peakValues.Irregular{1},   exceptionValues.Irregular{1});   logmsg(5) = []; end
                if strcmp('Fundamental',             exceptionValues.Type{1});        logmsg(4) = []; end
                if        peakValues.Station   ==    exceptionValues.Station;         logmsg(3) = []; end
                if        peakValues.Service   ==    exceptionValues.Service;         logmsg(2) = []; end
                if strcmp(peakValues.Regulatory{1},  exceptionValues.Regulatory{1});  logmsg(1) = []; end

                logmsg = strjoin(logmsg, '; ');
            end

            app.LOG.Text = ['<p style="text-align: justify; margin-right: 2px;">' logmsg '</p>'];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            appUtil.winPosition(app.UIFigure)
            app.UIFigure.WindowState = 'maximized';
            
            jsBackDoor_Initialization(app)
            startup_timerCreation(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWinFunction(app, 'closeFcn')
            app.CallingApp.auxiliarWin1 = [];
            delete(app)
            
        end

        % Cell selection callback: UITable
        function UITable_CellSelection(app, event)
            
            if app.tableSelectionInitialStatus(2)
                app.tableSelectionInitialStatus(2) = false;
                removeStyle(app.UITable, 1)
            end

            if isempty(event.Indices)
                app.UITable.Selection = app.row;
                
            elseif (app.row ~= event.Indices(1))
                app.row = event.Indices(1);
                SelectedRowValues(app)
            end
            
        end

        % Button pushed function: Button_globalList
        function Button_globalListPushed(app, event)
                        
            ExceptionGlobalList = app.CallingApp.channelObj.Exception;

            if isempty(ExceptionGlobalList)
                msg = 'Não identificada emissão na lista global de exceções contida no arquivo "ChannelLib.json".';

            else
                msg = [];
                for ii = 1:height(ExceptionGlobalList)
                    msg = sprintf('%s\n%s', msg, sprintf('<b>%.3f MHz</b>: "%s"', ExceptionGlobalList.FreqCenter(ii), ExceptionGlobalList.Description{ii}));
                end
            end
            appUtil.modalWindow(app.UIFigure, 'warning', msg);

        end

        % Button pushed function: Button_del
        function Button_delPushed(app, event)
            
            idx = exceptionListIndex(app);

            app.CallingApp.exceptionList(idx,:) = [];
            
            app.row = 1;
            app.UITable.Selection = 1;

            TableStyle(app)            
            SelectedRowValues(app)

            scroll(app.UITable, 'top')

        end

        % Button pushed function: Button_edit
        function Button_edit_Clicked(app, event)
            
            idx = exceptionListIndex(app);
            infoField = strjoin(app.Info.Value);

            if ~strcmp(app.CallingApp.peaksTable.Regulatory{app.row}, app.RegInfo_edited.Value)   || ...
               app.CallingApp.peaksTable.Station(app.row) ~= str2double(app.StationID.Value)      || ...
               ~strcmp(app.CallingApp.peaksTable.Type{app.row}, app.Type.Value)                   || ...
               ~strcmp(app.CallingApp.peaksTable.Irregular{app.row}, app.Compliance.Value)        || ...
               ~strcmp(app.CallingApp.peaksTable.RiskLevel{app.row}, app.RiskLevel_edited.Value)  || ...
               ~isempty(infoField)

                stationInfo = '';
                try
                    stationInfo = jsondecode(infoField);
                catch
                end
    
                if isstruct(stationInfo)
                    Service = str2double(stationInfo.Service);
                    if Service ~= -1; Regulatory = 'Licenciada';     Irregular = 'Não'; RiskLevel = '-';
                    else;             Regulatory = 'Não licenciada'; Irregular = 'Sim'; RiskLevel = 'Baixo';
                    end

                    exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},       ...
                                      app.CallingApp.peaksTable.Frequency(app.row), ...
                                      'Fundamental',                                ...
                                      Regulatory,                                   ...
                                      Service,                                      ...
                                      stationInfo.Station,                          ...
                                      stationInfo.Description,                      ...
                                      stationInfo.Distance,                         ...
                                      Irregular,                                    ...
                                      RiskLevel};
    
                else
                    switch app.RegInfo_edited.Value
                        case 'Licenciada'
                            if isempty(idx)
                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},         ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),   ...
                                                  app.Type.Value,                                 ...
                                                  app.RegInfo_edited.Value,                       ...
                                                  app.CallingApp.peaksTable.Service(app.row),     ...
                                                  app.CallingApp.peaksTable.Station(app.row),     ...
                                                  app.CallingApp.peaksTable.Description{app.row}, ...
                                                  app.CallingApp.peaksTable.Distance{app.row},    ...
                                                  app.Compliance.Value,                           ...
                                                  app.RiskLevel_edited.Value};
                            else
                                if str2double(app.StationID.Value) == app.CallingApp.peaksTable.Station(idx)
                                    Distance = app.CallingApp.peaksTable.Distance{app.row};
                                else
                                    Distance = app.CallingApp.exceptionList.Distance{idx};
                                end

                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},       ...
                                                  app.CallingApp.peaksTable.Frequency(app.row), ...
                                                  app.Type.Value,                               ...
                                                  app.RegInfo_edited.Value,                     ...
                                                  app.Service_edited.Value,                     ...
                                                  str2double(app.StationID.Value),              ...
                                                  infoField,                                    ...
                                                  Distance,                                     ...
                                                  app.Compliance.Value,                         ...
                                                  app.RiskLevel_edited.Value};
                            end

                        otherwise
                            if isempty(infoField)
                                msg = 'Campo "Informações adicionais" não pode ficar vazio.';
                                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                                return
                            end

                            exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},       ...
                                              app.CallingApp.peaksTable.Frequency(app.row), ...
                                              app.Type.Value,                               ...
                                              app.RegInfo_edited.Value,                     ...
                                              -1, -1, ['[EXC] ' infoField], '-',            ...
                                              app.Compliance.Value,                         ...
                                              app.RiskLevel_edited.Value};
                    end
                end

                if isempty(idx); app.CallingApp.exceptionList(end+1,:) = exceptionValue;
                else;            app.CallingApp.exceptionList(idx,:)   = exceptionValue;
                end

                TableStyle(app)            
                SelectedRowValues(app)
            end
            
        end

        % Value changed function: StationID
        function StationIDValueChanged(app, event)
            
            try
                app.StationID.Value = strtrim(app.StationID.Value);
                if (app.StationID.Value == "-1") || (~contains(app.StationID.Value, '#') && isnan(str2double(app.StationID.Value))) || ...
                   (contains(app.StationID.Value, '#') && (numel(app.StationID.Value)==1 || ~strcmp(app.StationID.Value(1), '#') || isnan(str2double(app.StationID.Value(2:end)))))
                        error(['Esse campo deve ser preenchido com o número da estação ou a ID do registro no RFDataHub. ' ...
                               'O seu formato é numérico, com a ressalva de que quando inserido a ID do registro ' ...
                               'deve ser colocado o caractere "#" à frente do número. Por exemplo: 123456 ou #123456.'])
                end

                latNode  = app.CallingApp.peaksTable.Latitude(app.row);
                longNode = app.CallingApp.peaksTable.Longitude(app.row);

                global RFDataHub
                stationInfo = class.RFDataHub.query(RFDataHub, app.StationID.Value, latNode, longNode);

                switch app.CallingApp.peaksTable.Regulatory{app.row}
                    case 'Licenciada'
                        msg1 = sprintf('<font style="font-size:10px;">INDICAÇÃO AUTOMÁTICA</font><br>• Emissão Licenciada<br>• Frequência: %.3f MHz<br>• Descrição: %s<br>• Distância: %s km<br><br>', ...
                                        app.CallingApp.peaksTable.Frequency(app.row), app.CallingApp.peaksTable.Description{app.row}, app.CallingApp.peaksTable.Distance{app.row});
                    otherwise
                        msg1 = sprintf('<font style="font-size:10px;">INDICAÇÃO AUTOMÁTICA</font><br>• Emissão %s<br>• Frequência: %.3f MHz<br><br>', ...
                                        app.CallingApp.peaksTable.Regulatory{app.row}, app.CallingApp.peaksTable.Frequency(app.row));
                end

                Service = stationInfo.Service;
                if Service ~= -1
                    msg2 = sprintf('<font style="font-size:10px;">MANUAL</font><br>• Emissão Licenciada<br>• Frequência(s): %s MHz<br>• Descrição: %s<br>• Distância: %.1f km<br><br>', ...
                                   stationInfo.Frequency, stationInfo.Description, stationInfo.Distance);
                else
                    msg2 = sprintf('<font style="font-size:10px;">MANUAL</font><br>• Emissão Não Licenciada<br>• Frequência(s): %s MHz<br><br>', ...
                                   stationInfo.Frequency);
                end
                
                selection = uiconfirm(app.UIFigure, ['<p style="font-size:11px; text-align:justify;">', msg1, msg2, '<font style="font-size:12px;"><b>Confirma edição?</b></font></p>'], '', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Interpreter', 'html');
                
                if strcmp(selection, 'OK')
                    set(app.Info, 'Value', jsonencode(struct('ID',          stationInfo.ID,                          ...
                                                             'Service',     num2str(stationInfo.Service),            ...
                                                             'Station',     stationInfo.Station,                     ...
                                                             'Description', stationInfo.Description,                 ...
                                                             'Distance',    sprintf('%.1f', stationInfo.Distance))), ...
                                  'Editable', 0)
                    Button_edit_Clicked(app)
                else
                    app.StationID.Value = num2str(app.CallingApp.peaksTable.Station(app.row));
                end

            catch ME
                app.StationID.Value = num2str(app.CallingApp.peaksTable.Station(app.row));                
                appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
            end
            
        end

        % Value changed function: RegInfo_edited
        function RegInfo_editedValueChanged(app, event)
            
            switch app.RegInfo_edited.Value
                case 'Licenciada'
                    set(app.StationID, 'Editable', 1)
                    set(app.Info,    'Editable', 0, 'Value', '')
                    
                    set(app.Type, 'Value', 'Fundamental', 'Enable', 0)
                    app.TypeLabel.Enable = 0;
    
                    set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')

                case {'Não licenciada', 'Não passível de licenciamento'}
                    set(app.Info,    'Editable', 1)
    
                    app.Type.Enable      = 1;
                    app.TypeLabel.Enable = 1;
    
                    switch app.RegInfo_edited.Value
                        case 'Não licenciada'
                            set(app.Compliance, 'Items', {'Sim'})
                            set(app.StationID, 'Editable', 1, 'Value', '-1')
                        case 'Não passível de licenciamento'
                            set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')
                            set(app.StationID, 'Editable', 0, 'Value', '-1')
                    end
            end
            ComplianceValueChanged(app)
            
        end

        % Value changed function: Compliance
        function ComplianceValueChanged(app, event)
            
            if strcmp(app.Compliance.Value, 'Não')
                set(app.RiskLevel_edited,      'Enable', 0, 'Items', {'-'})
                set(app.RiskLevel_editedLabel, 'Enable', 0)
            else
                set(app.RiskLevel_edited,      'Enable', 1, 'Items', {'Baixo', 'Médio', 'Alto'})
                set(app.RiskLevel_editedLabel, 'Enable', 1)
            end

        end

        % Button pushed function: Button_save
        function Button_savePushed(app, event)
            
            report.Controller(app, 'auxApp.winSignalAnalysis')

        end

        % Value changed function: Info
        function InfoValueChanged(app, event)
            
            app.Info.Value = strtrim(app.Info.Value);

        end

        % Button pushed function: tool_simulationButton
        function tool_simulationButtonPushed(app, event)
            
            idx = exceptionListIndex(app);
            if isempty(idx)
                txStationNumber = app.CallingApp.peaksTable.Station(app.row);
            else
                txStationNumber = app.CallingApp.exceptionList.Station(idx);
            end

            if txStationNumber == -1
                msg = 'Funcionalidade aplicável apenas para estações licenciadas...';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                
                return
            end

            if ~isempty(app.CallingApp.auxiliarWin8)
                msg = ['<p style="font-size:12px; text-align:justify;">O módulo de consulta ao RFDataHub está aberto e, ' ...
                       'por essa razão, caso se opte por continuar, todas as inserções de dados inseridas no módulo de '  ...
                       'consulta ao RFDataHub serão perdidas.<br><br><b>Deseja continuar?</b></p>'];
                selection = uiconfirm(app.UIFigure, msg, '', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Interpreter', 'html');
                
                if ~strcmp(selection, 'OK')
                    return
                end
            end

            request = struct('Type',           'TerrainProfilePlot',                                  ...
                             'rxLatitude',      double(app.CallingApp.peaksTable.Latitude(app.row)),  ...
                             'rxLongitude',     double(app.CallingApp.peaksTable.Longitude(app.row)), ...
                             'txStationNumber', txStationNumber);

            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');
            app.CallingApp.auxiliarWinFunction(app, 'tool_simulationButtonPushed', request)
            delete(d)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Análise de emissões';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout8 = uigridlayout(app.UIFigure);
            app.Grid1 = uigridlayout(app.GridLayout8);
            app.UIAxes1 = uiaxes(app.Grid1);
            xlabel(app.UIAxes1, 'Frequência (MHz)')
            ylabel(app.UIAxes1, 'Nível')
            app.Grid2 = uigridlayout(app.Grid1);
            app.UITable = uitable(app.Grid2);
            app.UITable.ColumnName = {'SENSOR'; 'ID'; 'FREQ.|(MHz)'; 'FREQ.|TRUNCADA'; 'LARGURA|BANDA (kHz)'; 'NÍVEL|MÍNIMO'; 'NÍVEL|MÉDIO'; 'NÍVEL|MÁXIMO'; 'OCC.|MÉDIA'; 'OCC.|MÁXIMA'; 'PROVÁVEL EMISSOR'; 'DISTÂNCIA|(km)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.UITable.Multiselect = 'off';
            app.Panel = uipanel(app.Grid1);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout2.Tag = 'COLORLOCKED';
            app.ChannelLabel = uilabel(app.GridLayout2);
            app.ChannelLabel.Text = 'EMISSÃO';
            app.Channel = uilabel(app.GridLayout2);
            app.Channel.Text = '';
            app.Panel_2 = uipanel(app.Grid1);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.GridLayout3.Tag = 'COLORLOCKED';
            app.ServiceLabel_dash = uilabel(app.GridLayout3);
            app.ServiceLabel_dash.Text = 'SERVIÇO';
            app.Service_dash = uilabel(app.GridLayout3);
            app.Service_dash.Text = '';
            app.Panel_3 = uipanel(app.Grid1);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.GridLayout4.Tag = 'COLORLOCKED';
            app.StationLabel_dash = uilabel(app.GridLayout4);
            app.StationLabel_dash.Text = 'ESTAÇÃO';
            app.Station_dash = uilabel(app.GridLayout4);
            app.Station_dash.Text = '';
            app.Panel_4 = uipanel(app.Grid1);
            app.GridLayout7 = uigridlayout(app.Panel_4);
            app.Grid4 = uigridlayout(app.GridLayout7);
            app.DescriptionLabel = uilabel(app.Grid4);
            app.DescriptionLabel.Text = 'Descrição:';
            app.Description = uitextarea(app.Grid4);
            app.Description.Editable = 'off';
            app.DescriptionSubLabel = uilabel(app.Grid4);
            app.DescriptionSubLabel.Text = 'Serviço-Status, Classe, Entidade (Fistel, Estação), Município/UF';
            app.Label1 = uilabel(app.Grid4);
            app.Label1.Text = 'ASPECTOS GERAIS';
            app.Sublabel1 = uilabel(app.Grid4);
            app.Sublabel1.Text = 'Informações gerais acerca do processo de detecção e classificação de emissões.';
            app.RegInfoLabel_raw = uilabel(app.Grid4);
            app.RegInfoLabel_raw.Text = 'Emissão:';
            app.RegInfo_raw = uitextarea(app.Grid4);
            app.RegInfo_raw.Editable = 'off';
            app.Service_raw = uieditfield(app.Grid4, 'numeric');
            app.Service_raw.Limits = [-1 Inf];
            app.Service_raw.RoundFractionalValues = 'on';
            app.Service_raw.ValueDisplayFormat = '%.0f';
            app.Service_raw.Editable = 'off';
            app.Service_raw.Value = -1;
            app.ServiceLabel_raw = uilabel(app.Grid4);
            app.ServiceLabel_raw.Text = 'Serviço:';
            app.Station_raw = uieditfield(app.Grid4, 'numeric');
            app.Station_raw.Limits = [-1 Inf];
            app.Station_raw.RoundFractionalValues = 'on';
            app.Station_raw.ValueDisplayFormat = '%.0f';
            app.Station_raw.Editable = 'off';
            app.Station_raw.Value = -1;
            app.StationLabel_raw = uilabel(app.Grid4);
            app.StationLabel_raw.Text = 'Estação:';
            app.MethodLabel = uilabel(app.Grid4);
            app.MethodLabel.Text = 'Algoritmo detecção:';
            app.Method = uitextarea(app.Grid4);
            app.Method.Editable = 'off';
            app.Grid4_2 = uigridlayout(app.GridLayout7);
            app.Label2 = uilabel(app.Grid4_2);
            app.Label2.Text = 'ANÁLISE QUALITATIVA';
            app.Sublabel2 = uilabel(app.Grid4_2);
            app.Sublabel2.Text = 'Informações que possibilitam uma compreensão mais profunda acerca das condições de uso do espectro de radiofrequências.';
            app.Service_edited = uieditfield(app.Grid4_2, 'numeric');
            app.Service_edited.Limits = [-1 Inf];
            app.Service_edited.RoundFractionalValues = 'on';
            app.Service_edited.ValueDisplayFormat = '%.0f';
            app.Service_edited.Editable = 'off';
            app.Service_edited.Value = -1;
            app.ServiceLabel_edited = uilabel(app.Grid4_2);
            app.ServiceLabel_edited.Text = 'Serviço:';
            app.RegInfo_edited = uidropdown(app.Grid4_2);
            app.RegInfo_edited.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            app.RegInfo_edited.ValueChangedFcn = createCallbackFcn(app, @RegInfo_editedValueChanged, true);
            app.RegInfo_edited.Value = 'Licenciada';
            app.RegInfoLabel_edited = uilabel(app.Grid4_2);
            app.RegInfoLabel_edited.Text = 'Emissão:';
            app.TypeLabel = uilabel(app.Grid4_2);
            app.TypeLabel.Text = 'Tipo de emissão:';
            app.Type = uidropdown(app.Grid4_2);
            app.Type.Items = {'Fundamental', 'Harmônico de fundamental', 'Produto de intermodulação', 'Espúrio', 'Não identificado', 'Não se manifestou', 'Pendente de identificação'};
            app.Type.Value = 'Fundamental';
            app.ComplianceLabel = uilabel(app.Grid4_2);
            app.ComplianceLabel.Text = 'Indício de irregularidade?';
            app.Compliance = uidropdown(app.Grid4_2);
            app.Compliance.Items = {'Não', 'Sim'};
            app.Compliance.ValueChangedFcn = createCallbackFcn(app, @ComplianceValueChanged, true);
            app.Compliance.Value = 'Não';
            app.RiskLevel_editedLabel = uilabel(app.Grid4_2);
            app.RiskLevel_editedLabel.Text = 'Potencial lesivo:';
            app.RiskLevel_edited = uidropdown(app.Grid4_2);
            app.RiskLevel_edited.Items = {'-', 'Baixo', 'Médio', 'Alto'};
            app.RiskLevel_edited.Value = '-';
            app.InfoLabel = uilabel(app.Grid4_2);
            app.InfoLabel.Text = 'Descrição:';
            app.Info = uitextarea(app.Grid4_2);
            app.Info.ValueChangedFcn = createCallbackFcn(app, @InfoValueChanged, true);
            app.StationIDLabel = uilabel(app.Grid4_2);
            app.StationIDLabel.Text = 'Estação/ID:';
            app.StationID = uieditfield(app.Grid4_2, 'text');
            app.StationID.ValueChangedFcn = createCallbackFcn(app, @StationIDValueChanged, true);
            app.Panel_5 = uipanel(app.Grid1);
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.LOGLabel = uilabel(app.GridLayout6);
            app.LOGLabel.Text = 'LOG';
            app.LOG = uilabel(app.GridLayout6);
            app.LOG.Interpreter = 'html';
            app.LOG.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout8);
            app.Button_save = uibutton(app.GridLayout5, 'push');
            app.Button_save.ButtonPushedFcn = createCallbackFcn(app, @Button_savePushed, true);
            app.Button_save.Icon = 'Export_24.png';
            app.Button_save.Tooltip = {'Exportar lista de emissões'};
            app.Button_save.Text = '';
            app.Button_del = uibutton(app.GridLayout5, 'push');
            app.Button_del.ButtonPushedFcn = createCallbackFcn(app, @Button_delPushed, true);
            app.Button_del.Icon = 'LT_redX.png';
            app.Button_del.Enable = 'off';
            app.Button_del.Tooltip = {'Excluir alterações'};
            app.Button_del.Text = '';
            app.Button_edit = uibutton(app.GridLayout5, 'push');
            app.Button_edit.ButtonPushedFcn = createCallbackFcn(app, @Button_edit_Clicked, true);
            app.Button_edit.Icon = 'LT_edit.png';
            app.Button_edit.Text = 'Editar emissão';
            app.Button_globalList = uibutton(app.GridLayout5, 'push');
            app.Button_globalList.ButtonPushedFcn = createCallbackFcn(app, @Button_globalListPushed, true);
            app.Button_globalList.Icon = 'LT_info.png';
            app.Button_globalList.Tooltip = {'Lista global de exceções'};
            app.Button_globalList.Text = '';
            app.Image = uiimage(app.GridLayout5);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'LT_LineV.png';
            app.jsBackDoor = uihtml(app.GridLayout5);
            app.jsBackDoor.HTMLSource = ' ';
            app.Image_2 = uiimage(app.GridLayout5);
            app.Image_2.Enable = 'off';
            app.Image_2.ImageSource = 'LT_LineV.png';
            app.tool_simulationButton = uibutton(app.GridLayout5, 'push');
            app.tool_simulationButton.ButtonPushedFcn = createCallbackFcn(app, @tool_simulationButtonPushed, true);
            app.tool_simulationButton.Icon = 'Publish_HTML_16-8792a26e8b38d73b8ffe54ffae928360.png';
            app.tool_simulationButton.Tooltip = {'Enlace'};
            app.tool_simulationButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSignalAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end