classdef dockDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        fontstylepaddingleft5pxDETECONOASSISTIDAfontLabel  matlab.ui.control.Label
        Document                 matlab.ui.container.GridLayout
        Algorithm                matlab.ui.control.DropDown
        AlgorithmLabel           matlab.ui.control.Label
        btnOK                    matlab.ui.control.Button
        ParametersPanel          matlab.ui.container.Panel
        ParametersGrid           matlab.ui.container.GridLayout
        MeanPanel                matlab.ui.container.Panel
        MeanGrid                 matlab.ui.container.GridLayout
        Prominence1              matlab.ui.control.Spinner
        Prominence1Label         matlab.ui.control.Label
        MaxHoldPanel             matlab.ui.container.Panel
        MaxHoldGrid              matlab.ui.container.GridLayout
        maxOCC                   matlab.ui.control.Spinner
        play_FindPeaks_OCCLabel  matlab.ui.control.Label
        meanOCC                  matlab.ui.control.Spinner
        Prominence2              matlab.ui.control.Spinner
        Prominence2Label         matlab.ui.control.Label
        BW_kHz                   matlab.ui.control.Spinner
        BW_kHzLabel              matlab.ui.control.Label
        Distance                 matlab.ui.control.Spinner
        DistanceLabel            matlab.ui.control.Label
        FindPeaksClass           matlab.ui.control.DropDown
        FindPeaksClassLabel      matlab.ui.control.Label
        Prominence               matlab.ui.control.Spinner
        ProminenceLabel          matlab.ui.control.Label
        THR                      matlab.ui.control.Spinner
        THRLabel                 matlab.ui.control.Label
        NPeaks                   matlab.ui.control.Spinner
        NPeaksLabel              matlab.ui.control.Label
        Trace                    matlab.ui.control.DropDown
        TraceLabel               matlab.ui.control.Label
        btnClose                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Container
        isDocked = true

        mainApp
        specData
        channelObj
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function initivalValues(app)
            idxThread = app.mainApp.play_PlotPanel.UserData.NodeData;
            
            app.Algorithm.Value  = app.specData(idxThread).UserData.reportAlgorithms.Detection.Algorithm;
            set(app.FindPeaksClass, 'Items', [{''}; app.channelObj.FindPeaks.Name], 'Value', '')
            Layout(app)

            switch app.Algorithm.Value
                case 'FindPeaks'
                    app.Trace.Value          = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Fcn;
                    app.NPeaks.Value         = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.NPeaks;
                    app.THR.Value            = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.THR;
                    app.Prominence.Value     = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Prominence;
                    app.Distance.Value       = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Distance_kHz;
                    app.BW_kHz.Value         = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.BW_kHz;

                case 'FindPeaks+OCC'
                    app.Distance.Value       = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Distance_kHz;
                    app.BW_kHz.Value         = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.BW_kHz;
                    app.Prominence1.Value    = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Prominence1;
                    app.Prominence2.Value    = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Prominence2;
                    app.meanOCC.Value        = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.meanOCC;
                    app.maxOCC.Value         = app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.maxOCC;
            end
        end

        %-----------------------------------------------------------------%
        function Layout(app)
            switch app.Algorithm.Value
                case 'FindPeaks'
                    app.ParametersGrid.RowHeight([1 2 5]) = {25,22,0};
                    
                    app.ProminenceLabel.Visible     = 1;
                    app.Prominence.Visible          = 1;
                    app.FindPeaksClassLabel.Visible = 0;
                    app.FindPeaksClass.Visible      = 0;

                case 'FindPeaks+OCC'
                    app.ParametersGrid.RowHeight([1 2 5]) = {0,0,87};

                    app.ProminenceLabel.Visible     = 0;
                    app.Prominence.Visible          = 0;
                    app.FindPeaksClassLabel.Visible = 1;
                    app.FindPeaksClass.Visible      = 1;
            end
        end

        %-----------------------------------------------------------------%
        function editionFlag = checkEdition(app)
            editionFlag = false;

            idxThread = app.mainApp.play_PlotPanel.UserData.NodeData;
            if ~isequal(app.Algorithm.Value, app.specData(idxThread).UserData.reportAlgorithms.Detection.Algorithm)
                editionFlag = true;

            else
                switch app.Algorithm.Value
                    case 'FindPeaks'
                        if ~isequal(app.Trace.Value,       app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Fcn)          || ...
                           ~isequal(app.NPeaks.Value,      app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.NPeaks)       || ...
                           ~isequal(app.THR.Value,         app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.THR)          || ...
                           ~isequal(app.Prominence.Value,  app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Prominence)   || ...
                           ~isequal(app.Distance.Value,    app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Distance_kHz) || ...
                           ~isequal(app.BW_kHz.Value,      app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.BW_kHz)
                            
                            editionFlag = true;
                        end

                    case 'FindPeaks+OCC'
                        if ~isequal(app.Distance.Value,    app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Distance_kHz) || ...
                           ~isequal(app.BW_kHz.Value,      app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.BW_kHz)       || ...
                           ~isequal(app.Prominence1.Value, app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Prominence1)  || ...
                           ~isequal(app.Prominence2.Value, app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.Prominence2)  || ...
                           ~isequal(app.meanOCC.Value,     app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.meanOCC)      || ...
                           ~isequal(app.maxOCC.Value,      app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters.maxOCC)
                            
                            editionFlag = true;
                        end
                end
            end
        end

        %-----------------------------------------------------------------%
        function callingMainApp(app, updateFlag, returnFlag, idxThread)
            ipcMainMatlabCallsHandler(app.mainApp, app, 'REPORT:DETECTION', updateFlag, returnFlag, idxThread)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.mainApp    = mainapp;
            app.specData   = mainapp.specData;
            app.channelObj = mainapp.channelObj;

            initivalValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Value changed function: Algorithm
        function AlgorithmValueChanged(app, event)
            
            Layout(app)
            ParameterValueChanged(app)

        end

        % Value changed function: FindPeaksClass
        function FindPeaksClassValueChanged(app, event)
            
            if ~isempty(app.FindPeaksClass.Value)
                [~, idxFindPeaks] = ismember(app.FindPeaksClass.Value, app.channelObj.FindPeaks.Name);

                if idxFindPeaks    
                    app.Distance.Value    = 1000 * app.channelObj.FindPeaks.Distance(idxFindPeaks);
                    app.BW_kHz.Value      = 1000 * app.channelObj.FindPeaks.BW(idxFindPeaks);
                    app.Prominence1.Value = app.channelObj.FindPeaks.Prominence1(idxFindPeaks);
                    app.Prominence2.Value = app.channelObj.FindPeaks.Prominence2(idxFindPeaks);
                    app.meanOCC.Value     = app.channelObj.FindPeaks.meanOCC(idxFindPeaks);
                    app.maxOCC.Value      = app.channelObj.FindPeaks.maxOCC(idxFindPeaks);
    
                    ParameterValueChanged(app)
                end
            end

        end

        % Value changed function: maxOCC, meanOCC
        function occValueChanged(app, event)
            
            switch event.Source
                case app.meanOCC
                    if app.meanOCC.Value > app.maxOCC.Value
                        app.maxOCC.Value = app.meanOCC.Value;
                    end
                case app.maxOCC
                    if app.maxOCC.Value < app.meanOCC.Value
                        app.meanOCC.Value = app.maxOCC.Value;
                    end
            end
            ParameterValueChanged(app)

        end

        % Value changed function: BW_kHz, Distance, NPeaks, Prominence, 
        % ...and 4 other components
        function ParameterValueChanged(app, event)
            
            if checkEdition(app)
                app.btnOK.Enable = 1;
            else
                app.btnOK.Enable = 0;
            end

        end

        % Callback function: btnClose, btnOK
        function ButtonPushed(app, event)
            
            idxThread = app.mainApp.play_PlotPanel.UserData.NodeData;
            
            pushedButtonTag = event.Source.Tag;
            switch pushedButtonTag
                case 'OK'
                    app.specData(idxThread).UserData.reportAlgorithms.Detection.Algorithm = app.Algorithm.Value;
                    switch app.Algorithm.Value
                        case 'FindPeaks'
                            app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters = struct('Fcn',          app.Trace.Value,       ...
                                                                                                            'NPeaks',       app.NPeaks.Value,      ...
                                                                                                            'THR',          app.THR.Value,         ...
                                                                                                            'Prominence',   app.Prominence.Value,  ...
                                                                                                            'Distance_kHz', app.Distance.Value,    ...
                                                                                                            'BW_kHz',       app.BW_kHz.Value);
                        case 'FindPeaks+OCC'
                            app.specData(idxThread).UserData.reportAlgorithms.Detection.Parameters = struct('Distance_kHz', app.Distance.Value,    ...
                                                                                                            'BW_kHz',       app.BW_kHz.Value,      ...
                                                                                                            'Prominence1',  app.Prominence1.Value, ...
                                                                                                            'Prominence2',  app.Prominence2.Value, ...
                                                                                                            'meanOCC',      app.meanOCC.Value,     ...
                                                                                                            'maxOCC',       app.maxOCC.Value);
                    end

                    updateFlag = true;

                case 'Close'
                    updateFlag = false;
            end

            callingMainApp(app, updateFlag, false, idxThread)
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.btnClose = uiimage(app.GridLayout);
            app.btnClose.ImageClickedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnClose.Tag = 'Close';
            app.btnClose.ImageSource = 'Delete_12SVG.svg';
            app.Document = uigridlayout(app.GridLayout);
            app.ParametersPanel = uipanel(app.Document);
            app.ParametersGrid = uigridlayout(app.ParametersPanel);
            app.TraceLabel = uilabel(app.ParametersGrid);
            app.TraceLabel.Text = {'Tipo de '; 'traço:'};
            app.Trace = uidropdown(app.ParametersGrid);
            app.Trace.Items = {'MinHold', 'Média', 'MaxHold'};
            app.Trace.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.Trace.Value = 'Média';
            app.NPeaksLabel = uilabel(app.ParametersGrid);
            app.NPeaksLabel.Text = {'Número de '; 'picos:'};
            app.NPeaks = uispinner(app.ParametersGrid);
            app.NPeaks.Limits = [1 100];
            app.NPeaks.RoundFractionalValues = 'on';
            app.NPeaks.ValueDisplayFormat = '%.0f';
            app.NPeaks.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.NPeaks.Value = 10;
            app.THRLabel = uilabel(app.ParametersGrid);
            app.THRLabel.Text = {'Threshold'; '(dB):'};
            app.THR = uispinner(app.ParametersGrid);
            app.THR.Step = 10;
            app.THR.RoundFractionalValues = 'on';
            app.THR.ValueDisplayFormat = '%.0f';
            app.THR.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.THR.Value = -Inf;
            app.ProminenceLabel = uilabel(app.ParametersGrid);
            app.ProminenceLabel.Visible = 'off';
            app.ProminenceLabel.Text = {'Proeminência '; '(dB):'};
            app.Prominence = uispinner(app.ParametersGrid);
            app.Prominence.Step = 10;
            app.Prominence.Limits = [1 Inf];
            app.Prominence.RoundFractionalValues = 'on';
            app.Prominence.ValueDisplayFormat = '%.0f';
            app.Prominence.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.Prominence.Visible = 'off';
            app.Prominence.Value = 30;
            app.FindPeaksClassLabel = uilabel(app.ParametersGrid);
            app.FindPeaksClassLabel.Text = {'Classe de '; 'emissão:'};
            app.FindPeaksClass = uidropdown(app.ParametersGrid);
            app.FindPeaksClass.Items = {};
            app.FindPeaksClass.ValueChangedFcn = createCallbackFcn(app, @FindPeaksClassValueChanged, true);
            app.FindPeaksClass.Value = {};
            app.DistanceLabel = uilabel(app.ParametersGrid);
            app.DistanceLabel.Text = {'Distância entre'; 'picos (kHz):'};
            app.Distance = uispinner(app.ParametersGrid);
            app.Distance.Step = 25;
            app.Distance.Limits = [0 Inf];
            app.Distance.RoundFractionalValues = 'on';
            app.Distance.ValueDisplayFormat = '%.0f';
            app.Distance.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.Distance.Value = 25;
            app.BW_kHzLabel = uilabel(app.ParametersGrid);
            app.BW_kHzLabel.Text = {'Largura ocupada '; '(kHz):'};
            app.BW_kHz = uispinner(app.ParametersGrid);
            app.BW_kHz.Step = 10;
            app.BW_kHz.Limits = [0 Inf];
            app.BW_kHz.RoundFractionalValues = 'on';
            app.BW_kHz.ValueDisplayFormat = '%.0f';
            app.BW_kHz.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.BW_kHz.Value = 10;
            app.MaxHoldPanel = uipanel(app.ParametersGrid);
            app.MaxHoldPanel.Title = 'MAXHOLD';
            app.MaxHoldGrid = uigridlayout(app.MaxHoldPanel);
            app.Prominence2Label = uilabel(app.MaxHoldGrid);
            app.Prominence2Label.Text = {'Proeminência'; '(dB):'};
            app.Prominence2 = uispinner(app.MaxHoldGrid);
            app.Prominence2.Step = 10;
            app.Prominence2.Limits = [1 Inf];
            app.Prominence2.RoundFractionalValues = 'on';
            app.Prominence2.ValueDisplayFormat = '%.0f';
            app.Prominence2.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.Prominence2.Value = 30;
            app.meanOCC = uispinner(app.MaxHoldGrid);
            app.meanOCC.Step = 10;
            app.meanOCC.Limits = [0 100];
            app.meanOCC.RoundFractionalValues = 'on';
            app.meanOCC.ValueDisplayFormat = '%.0f';
            app.meanOCC.ValueChangedFcn = createCallbackFcn(app, @occValueChanged, true);
            app.meanOCC.Value = 1;
            app.play_FindPeaks_OCCLabel = uilabel(app.MaxHoldGrid);
            app.play_FindPeaks_OCCLabel.Interpreter = 'html';
            app.play_FindPeaks_OCCLabel.Text = {'Ocupação (%):'; '<p style="line-height:10px; font-size:9px; color:gray;">(Mínima | Máxima)</p>'};
            app.maxOCC = uispinner(app.MaxHoldGrid);
            app.maxOCC.Step = 10;
            app.maxOCC.Limits = [0 100];
            app.maxOCC.RoundFractionalValues = 'on';
            app.maxOCC.ValueDisplayFormat = '%.0f';
            app.maxOCC.ValueChangedFcn = createCallbackFcn(app, @occValueChanged, true);
            app.maxOCC.Value = 10;
            app.MeanPanel = uipanel(app.ParametersGrid);
            app.MeanPanel.Title = 'MÉDIA';
            app.MeanGrid = uigridlayout(app.MeanPanel);
            app.Prominence1Label = uilabel(app.MeanGrid);
            app.Prominence1Label.Text = {'Proeminência'; '(dB):'};
            app.Prominence1 = uispinner(app.MeanGrid);
            app.Prominence1.Step = 10;
            app.Prominence1.Limits = [1 Inf];
            app.Prominence1.RoundFractionalValues = 'on';
            app.Prominence1.ValueDisplayFormat = '%.0f';
            app.Prominence1.ValueChangedFcn = createCallbackFcn(app, @ParameterValueChanged, true);
            app.Prominence1.Value = 10;
            app.btnOK = uibutton(app.Document, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnOK.Tag = 'OK';
            app.btnOK.Enable = 'off';
            app.btnOK.Text = 'OK';
            app.AlgorithmLabel = uilabel(app.Document);
            app.AlgorithmLabel.Text = 'Algoritmo:';
            app.Algorithm = uidropdown(app.Document);
            app.Algorithm.Items = {'FindPeaks', 'FindPeaks+OCC'};
            app.Algorithm.ValueChangedFcn = createCallbackFcn(app, @AlgorithmValueChanged, true);
            app.Algorithm.Value = 'FindPeaks';
            app.fontstylepaddingleft5pxDETECONOASSISTIDAfontLabel = uilabel(app.GridLayout);
            app.fontstylepaddingleft5pxDETECONOASSISTIDAfontLabel.Interpreter = 'html';
            app.fontstylepaddingleft5pxDETECONOASSISTIDAfontLabel.Text = '<font style="padding-left: 5px;">DETECÇÃO NÃO ASSISTIDA</font>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockDetection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end