classdef winAddFiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Grid1              matlab.ui.container.GridLayout
        Image              matlab.ui.control.Image
        Title              matlab.ui.control.Label
        Grid2              matlab.ui.container.GridLayout
        Button_play        matlab.ui.control.Button
        Button_info        matlab.ui.control.Button
        Panel1             matlab.ui.container.Panel
        Grid3              matlab.ui.container.GridLayout
        Mode               matlab.ui.control.EditField
        ModeLabel          matlab.ui.control.Label
        FiletypeLabel      matlab.ui.control.Label
        FiletypeGroup      matlab.ui.container.ButtonGroup
        RadioButton_table  matlab.ui.control.RadioButton
        RadioButton_image  matlab.ui.control.RadioButton
        GridLayout         matlab.ui.container.GridLayout
        Open               matlab.ui.control.Button
        Filename           matlab.ui.control.EditField
        FilenameLabel      matlab.ui.control.Label
        SheetID            matlab.ui.control.NumericEditField
        SheetIDLabel       matlab.ui.control.Label
        GridLayout3        matlab.ui.container.GridLayout
        AddFile            matlab.ui.control.Image
        Tree               matlab.ui.container.Tree
        Node_image         matlab.ui.container.TreeNode
        Node_table         matlab.ui.container.TreeNode
        SelectedNode       matlab.ui.control.TextArea
        SelectedNodeLabel  matlab.ui.control.Label
        ContextMenu        matlab.ui.container.ContextMenu
        Button_del         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
    end
    
    methods (Access = private)
        
        function Fcn_InitialValues(app, Type)

            switch Type
                case 'Project'
                    app.Mode.Value = 'Projeto';

                    app.SelectedNode.Enable      = 0;
                    app.SelectedNodeLabel.Enable = 0;

                case 'specData'
                    app.Mode.Value = 'Fluxo de dados';

                    idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;
                    app.SelectedNode.Value = sprintf('%s\nThreadID %.0f: %.3f - %.3f MHz',  ...
                                                     app.CallingApp.specData(idx).Node,     ...
                                                     app.CallingApp.specData(idx).ThreadID, ...
                                                     app.CallingApp.specData(idx).MetaData.FreqStart / 1e+6, ...
                                                     app.CallingApp.specData(idx).MetaData.FreqStop  / 1e+6);
            end

        end


        function Fcn_TreeBuilding(app)

            if ~isempty(app.Node_image.Children)
                delete(app.Node_image.Children);
            end

            if ~isempty(app.Node_table.Children)
                delete(app.Node_table.Children);
            end

            switch app.Mode.Value
                case 'Projeto'
                    if ~isempty(app.CallingApp.projData.image)
                        for ii = 1:numel(app.CallingApp.projData.image)
                            uitreenode(app.Node_image, 'Text', app.CallingApp.projData.image{ii}, ...
                                                       'NodeData', ii, 'ContextMenu', app.ContextMenu);
                        end
                    end

                    if ~isempty(app.CallingApp.projData.table)    
                        for ii = 1:numel(app.CallingApp.projData.table)
                            uitreenode(app.Node_table, 'Text', jsonencode(app.CallingApp.projData.table{ii}), ...
                                                       'NodeData', ii, 'ContextMenu', app.ContextMenu);
                        end
                    end

                case 'Fluxo de dados'
                    idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;

                    if ~isempty(app.CallingApp.specData(idx).reportAttachments.image)
                        uitreenode(app.Node_image, 'Text', app.CallingApp.specData(idx).reportAttachments.image, ...
                                                   'ContextMenu', app.ContextMenu);
                    end

                    if ~isempty(app.CallingApp.specData(idx).reportAttachments.table.Source)
                        uitreenode(app.Node_table, 'Text', jsonencode(app.CallingApp.specData(idx).reportAttachments.table), ...
                                                   'ContextMenu', app.ContextMenu);
                    end
            end

            expand(app.Tree, 'all')

        end


        function Fcn_SheetID(app)

            [~,~,ext] = fileparts(app.Filename.Value);

            if ext == ".xlsx"
                app.SheetID.Enable      = 1;
                app.SheetIDLabel.Enable = 1;
            else
                app.SheetID.Enable      = 0;
                app.SheetIDLabel.Enable = 0;
            end

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', fullfile(app.RootFolder, 'Icons', 'Toolbar_Icon3.png'))
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Type)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            Fcn_InitialValues(app, Type)
            Fcn_TreeBuilding(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.Report_TreeBuilding
            figure(app.CallingApp.MainApp)
            delete(app)
            
        end

        % Button pushed function: Open
        function Button_openPushed(app, event)
            
            if app.RadioButton_image.Value
                Formats = {'*.jpeg',  'JPEG (*.jpeg)'; ...
                           '*.png',   'PNG (*.png)'};
            else
                Formats = {'*.xlsx', 'Excel (*.xlsx)'; ...
                           '*.csv',  'CSV   (*.csv)';  ...
                           '*.json', 'JSON  (*.json)'; ...
                           '*.txt',  'Text  (*.txt)'};
            end

            [Files, Path, FileType] = uigetfile(Formats, 'Selecione um arquivo', 'MultiSelect', 'off');
            figure(app.UIFigure)
            
            if ~FileType
                return
            end

            app.Tree.SelectedNodes = [];

            app.Filename.Value = fullfile(Path, Files);
            set(app.SheetID, 'Value', 1, 'Enable', 0)
            Fcn_SheetID(app)
            
            app.AddFile.Enable = 1;

        end

        % Image clicked function: AddFile
        function AddFileImageClicked(app, event)
            
            if ~isempty(app.Filename.Value)
                [~,~,ext] = fileparts(app.Filename.Value);

                if ismember(ext, [".jpeg", ".png"]); Type = "image";
                else;                                Type = "table";
                end

                switch app.Mode.Value
                    case 'Projeto'
                        if Type == "image"
                            app.CallingApp.projData.image{end+1} = app.Filename.Value;
                        else
                            app.CallingApp.projData.table{end+1} = struct('Source', app.Filename.Value, 'SheetID', app.SheetID.Value);
                        end

                    case 'Fluxo de dados'
                        idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;
                        
                        if Type == "image"
                            app.CallingApp.specData(idx).reportAttachments.image = app.Filename.Value;
                        else
                            app.CallingApp.specData(idx).reportAttachments.table = struct('Source', app.Filename.Value, 'SheetID', app.SheetID.Value);
                        end
                end
                Fcn_TreeBuilding(app)

            end

            app.Filename.Value = '';
            set(app.SheetID, 'Value', 1, 'Enable', 0)

            app.AddFile.Enable = 0;

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            app.Filename.Value = '';
            set(app.SheetID, 'Value', 1, 'Enable', 0)
            app.AddFile.Enable = 0;

            if ~isempty(app.Tree.SelectedNodes)
                if ismember(app.Tree.SelectedNodes.Parent, [app.Node_image, app.Node_table])    
                    if app.Tree.SelectedNodes.Parent == app.Node_image
                        app.Filename.Value = app.Tree.SelectedNodes.Text;
    
                    else
                        info = jsondecode(app.Tree.SelectedNodes.Text);
    
                        app.Filename.Value = info.Source;
                        app.SheetID.Value  = info.SheetID;
    
                    end
                    Fcn_SheetID(app)
                end
            end

        end

        % Button pushed function: Button_info
        function Button_infoPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'DataBase', 'info.txt'));
            msg  = char(extractBetween(info, sprintf('<winAddFiles>\n'), sprintf('\n</winAddFiles>')));

            Misc_uialertCreation(app, 'info', msg)

        end

        % Menu selected function: Button_del
        function Button_deletePushed(app, event)
            
            [~,~,ext] = fileparts(app.Filename.Value);

            if ismember(ext, [".jpeg", ".png"]); Type = "image";
            else;                                Type = "table";
            end

            switch app.Mode.Value
                case 'Projeto'
                    idx = app.Tree.SelectedNodes.NodeData;

                    if Type == "image"; app.CallingApp.projData.image(idx) = [];
                    else;               app.CallingApp.projData.table(idx) = [];
                    end

                case 'Fluxo de dados'
                    idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;

                    if Type == "image"; app.CallingApp.specData(idx).reportAttachments.image = '';
                    else;               app.CallingApp.specData(idx).reportAttachments.table = struct('Source', '', 'SheetID', 1);
                    end
            end

            Fcn_TreeBuilding(app)
            TreeSelectionChanged(app)

        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)
            
            closeFcn(app)

        end

        % Value changed function: SheetID
        function SheetIDValueChanged(app, event)
            
            if ~app.AddFile.Enable
                switch app.Mode.Value
                    case 'Projeto'
                        idx = app.Tree.SelectedNodes.NodeData;
                        app.CallingApp.projData.table{idx}.SheetID = app.SheetID.Value;
    
                    case 'Fluxo de dados'
                        idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;
                        app.CallingApp.specData(idx).reportAttachments.table.SheetID = app.SheetID.Value;
                end
    
                Fcn_TreeBuilding(app)
                TreeSelectionChanged(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Arquivos Externos';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel1 = uipanel(app.Grid1);
            app.Grid3 = uigridlayout(app.Panel1);
            app.SelectedNodeLabel = uilabel(app.Grid3);
            app.SelectedNodeLabel.Text = 'Fluxo de dados:';
            app.SelectedNode = uitextarea(app.Grid3);
            app.SelectedNode.Editable = 'off';
            app.Tree = uitree(app.Grid3);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node_image = uitreenode(app.Tree);
            app.Node_image.Icon = 'photo.png';
            app.Node_image.Text = 'IMAGEM';
            app.Node_table = uitreenode(app.Tree);
            app.Node_table.Icon = 'Settings_Sheet.png';
            app.Node_table.Text = 'TABELA';
            app.GridLayout3 = uigridlayout(app.Grid3);
            app.AddFile = uiimage(app.GridLayout3);
            app.AddFile.ImageClickedFcn = createCallbackFcn(app, @AddFileImageClicked, true);
            app.AddFile.Enable = 'off';
            app.AddFile.ImageSource = 'General_Add.png';
            app.GridLayout = uigridlayout(app.Grid3);
            app.SheetIDLabel = uilabel(app.GridLayout);
            app.SheetIDLabel.Enable = 'off';
            app.SheetIDLabel.Text = 'Índice planilha (xlsx):';
            app.SheetID = uieditfield(app.GridLayout, 'numeric');
            app.SheetID.Limits = [1 Inf];
            app.SheetID.RoundFractionalValues = 'on';
            app.SheetID.ValueDisplayFormat = '%.0f';
            app.SheetID.ValueChangedFcn = createCallbackFcn(app, @SheetIDValueChanged, true);
            app.SheetID.Enable = 'off';
            app.SheetID.Value = 1;
            app.FilenameLabel = uilabel(app.GridLayout);
            app.FilenameLabel.Text = 'Arquivo:';
            app.Filename = uieditfield(app.GridLayout, 'text');
            app.Filename.Editable = 'off';
            app.Open = uibutton(app.GridLayout, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @Button_openPushed, true);
            app.Open.Icon = 'mainMenu_File.png';
            app.Open.Text = '';
            app.FiletypeGroup = uibuttongroup(app.Grid3);
            app.RadioButton_image = uiradiobutton(app.FiletypeGroup);
            app.RadioButton_image.Text = 'Imagem';
            app.RadioButton_image.Value = true;
            app.RadioButton_table = uiradiobutton(app.FiletypeGroup);
            app.RadioButton_table.Text = 'Tabela';
            app.FiletypeLabel = uilabel(app.Grid3);
            app.FiletypeLabel.Text = 'Tipo:';
            app.ModeLabel = uilabel(app.Grid3);
            app.ModeLabel.Text = 'Modo:';
            app.Mode = uieditfield(app.Grid3, 'text');
            app.Mode.Editable = 'off';
            app.Grid2 = uigridlayout(app.Grid1);
            app.Button_info = uibutton(app.Grid2, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @Button_infoPushed, true);
            app.Button_info.Icon = 'Toolbar_Icon3.png';
            app.Button_info.Text = '';
            app.Button_play = uibutton(app.Grid2, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Icon = 'White_Play.png';
            app.Button_play.Text = 'Confirmar';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Arquivos externos</b>'; '<font>Relação entre fluxo de dados e arquivos externos</font>'; ' '};
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'Report_Icon1.png';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Button_del = uimenu(app.ContextMenu);
            app.Button_del.MenuSelectedFcn = createCallbackFcn(app, @Button_deletePushed, true);
            app.Button_del.Text = 'Deletar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAddFiles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end