classdef winAnatelDB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        mainGrid           matlab.ui.container.GridLayout
        GridLayout4        matlab.ui.container.GridLayout
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        table_Grid         matlab.ui.container.GridLayout
        Export_Grid        matlab.ui.container.GridLayout
        Export_clipboard   matlab.ui.control.Image
        Export_save        matlab.ui.control.Image
        Column11           matlab.ui.control.Label
        Column10           matlab.ui.control.Label
        Column9            matlab.ui.control.Label
        Column8            matlab.ui.control.Label
        Column7            matlab.ui.control.Label
        Column6            matlab.ui.control.Label
        Column5            matlab.ui.control.Label
        Column4            matlab.ui.control.Label
        Column3            matlab.ui.control.Label
        Column2            matlab.ui.control.Label
        Column1            matlab.ui.control.Label
        UITable            matlab.ui.control.Table
        imgShowHide        matlab.ui.control.Image
        filter_Panel       matlab.ui.container.Panel
        mainPanel_Grid     matlab.ui.container.GridLayout
        Tab2_Panel         matlab.ui.container.GridLayout
        filteredSummary    matlab.ui.control.Label
        Tree               matlab.ui.container.Tree
        Hifen              matlab.ui.control.Label
        Value3             matlab.ui.control.NumericEditField
        Value2             matlab.ui.control.NumericEditField
        Value1             matlab.ui.control.EditField
        AddFilter_Grid     matlab.ui.container.GridLayout
        AddFilter          matlab.ui.control.Image
        Type               matlab.ui.control.DropDown
        TypeLabel          matlab.ui.control.Label
        Family             matlab.ui.control.DropDown
        FamilyLabel        matlab.ui.control.Label
        Location_Grid      matlab.ui.container.GridLayout
        LocationRefresh    matlab.ui.control.Image
        Location           matlab.ui.control.DropDown
        LocationLabel      matlab.ui.control.Label
        Tab2_Grid          matlab.ui.container.GridLayout
        Tab2_Image         matlab.ui.control.Image
        Tab2_Title         matlab.ui.control.Label
        Tab1_Panel         matlab.ui.container.GridLayout
        SortRows_Grid      matlab.ui.container.GridLayout
        SortRows_DropDown  matlab.ui.control.DropDown
        SortRows_Image     matlab.ui.control.Image
        SortRows_Label     matlab.ui.control.Label
        rawSummary         matlab.ui.control.Label
        Version_Panel      matlab.ui.container.Panel
        Version_Grid       matlab.ui.container.GridLayout
        Version            matlab.ui.control.Label
        Version_Label      matlab.ui.control.Label
        imgTable           matlab.ui.control.Image
        imgHistogram       matlab.ui.control.Image
        Tab1_Grid          matlab.ui.container.GridLayout
        Tab1_Image         matlab.ui.control.Image
        Tab1_Title         matlab.ui.control.Label
        ContextMenu        matlab.ui.container.ContextMenu
        Delete             matlab.ui.container.Menu
        DeleteAll          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        UIAxes1
        Parameters = struct('Family', {}, 'Type', {}, 'Value', {});
        nodeTable
        
    end
    
    methods (Access = private)

        function Fcn1_AxesCreation(app)

            app.UIAxes1 = geoaxes(app.mainGrid, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater');

            while true
                try
                    addToolbarMapButton(axtoolbar(app.UIAxes1, "default"), "basemap", BasemapNames=["darkwater", "satellite", "streets-light", "streets-dark", "topographic", "streets", "colorterrain", "grayterrain"])

                    app.UIAxes1.Layout.Row    = 1;
                    app.UIAxes1.Layout.Column = 3;

                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';

                    app.UIAxes1.TitleHorizontalAlignment = 'right';
                    set(app.UIAxes1.Title, 'String', 'Title', 'FontSize', 8)
                    set(app.UIAxes1.Subtitle, 'String', 'Subtitle', 'FontSize', 6.5)

                    hold(app.UIAxes1, 'on')

                    break
                catch
                end
            end


            try
                geobasemap(app.UIAxes1, 'streets-light')
            catch
            end

            app.UIAxes2.Interactions = dataTipInteraction;
            app.UIAxes3.Interactions = dataTipInteraction;

            axtoolbar(app.UIAxes2, {'pan', 'zoomin', 'zoomout', 'restoreview'});
            axtoolbar(app.UIAxes3, {'pan', 'zoomin', 'zoomout', 'restoreview'});
            
            enableDefaultInteractivity(app.UIAxes1)
            enableDefaultInteractivity(app.UIAxes2)
            enableDefaultInteractivity(app.UIAxes3)

        end


        function Fcn1_NodeList(app)

            specData  = app.CallingApp.specData;
            app.nodeTable = table('Size', [0, 3], ...
                              'VariableTypes', {'double', 'double', 'cell'}, ...
                              'VariableNames', {'lat', 'long', 'location'});

            app.Location.Items = {};
            for ii = 1:numel(specData)
                if specData(ii).gps.Status
                    msg = sprintf('%.6f, %.6f (%s)', specData(ii).gps.Latitude, ...
                                                     specData(ii).gps.Longitude, ...
                                                     specData(ii).gps.Location);

                    if isempty(app.Location.Items) | ~contains(app.Location.Items, msg)
                        app.Location.Items{end+1} = msg;
                        app.nodeTable(end+1,:) = {specData(ii).gps.Latitude, specData(ii).gps.Longitude, specData(ii).gps.Location};
                    end
                end
            end

        end


        function Fcn2_TreeBuilding(app)

            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end

            Parameters_table = struct2table(app.Parameters);
            FamilyItems      = {};
            if ischar(Parameters_table.Family)
                FamilyItems = {Parameters_table.Family};
            elseif iscell(Parameters_table.Family)
                FamilyItems = unique(Parameters_table.Family);
            end

            for ii = 1:numel(FamilyItems)
                idx1 = find(Parameters_table.Family == string(FamilyItems{ii}));
                
                NodeTree = uitreenode(app.Tree, 'Text', upper(FamilyItems{ii}), ...
                                                'NodeData', idx1, 'ContextMenu', app.ContextMenu);
                
                for jj = idx1'
                    if isnumeric(app.Parameters(jj).Value)
                        if numel(app.Parameters(jj).Value) == 1; Value = sprintf('%g', app.Parameters(jj).Value);
                        else;                                    Value = sprintf('%g e %g', app.Parameters(jj).Value(1), app.Parameters(jj).Value(2));
                        end
                    else
                        Value = sprintf('"%s"', app.Parameters(jj).Value);
                    end

                    uitreenode(NodeTree, 'Text', sprintf('%s %s', app.Parameters(jj).Type(1:end-3), Value), ...
                                         'NodeData', jj, 'ContextMenu', app.ContextMenu);
                end
            end

            expand(app.Tree, 'all')

        end


        function Fcn2_AnatelDB(app)

            global AnatelDB

            anatelDB = AnatelDB;

            anatelDB.Frequency = double(anatelDB.Frequency);
            anatelDB.Distance  = -1*ones(height(anatelDB), 1);

            if ~isempty(app.Tree.Children)
                Parameters_table = struct2table(app.Parameters);
                Tolerance = 1e-5;
                kk = 0;
                idx2 = zeros(height(anatelDB), 4, 'logical');

                % Frequência
                idx1 = find(Parameters_table.Family == "Frequência");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        switch app.Parameters(ii).Type
                            case 'É igual a...'
                                idx2(:,kk) = idx2(:,kk) | (abs(anatelDB.Frequency-app.Parameters(ii).Value) <= Tolerance);
    
                            case 'Está entre...'
                                idx2(:,kk) = idx2(:,kk) | ((anatelDB.Frequency >= app.Parameters(ii).Value(1) - Tolerance) & (anatelDB.Frequency <= app.Parameters(ii).Value(2) + Tolerance));
                        end
                    end
                end

                % Descrição
                idx1 = find(Parameters_table.Family == "Descrição");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        idx2(:,kk) = idx2(:,kk) | contains(string(anatelDB.Description), app.Parameters(ii).Value);
                    end
                end

                % Serviço
                idx1 = find(Parameters_table.Family == "Serviço");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        switch app.Parameters(ii).Type
                            case 'É igual a...'
                                idx2(:,kk) = idx2(:,kk) | anatelDB.Service == int16(app.Parameters(ii).Value);
    
                            case 'Está entre...'
                                idx2(:,kk) = idx2(:,kk) | ((anatelDB.Service >= int16(app.Parameters(ii).Value(1))) & (anatelDB.Service <= int16(app.Parameters(ii).Value(2))));
                        end
                    end
                end

                % Estação
                idx1 = find(Parameters_table.Family == "Estação");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        switch app.Parameters(ii).Type
                            case 'É igual a...'
                                idx2(:,kk) = idx2(:,kk) | anatelDB.Station == int16(app.Parameters(ii).Value);
    
                            case 'Está entre...'
                                idx2(:,kk) = idx2(:,kk) | ((anatelDB.Station >= int32(app.Parameters(ii).Value(1))) & (anatelDB.Station <= int32(app.Parameters(ii).Value(2))));
                        end
                    end
                end

                idx3 = all(idx2(:,1:kk), 2);
                anatelDB(~idx3,:) = [];
                
                % Distância
                if ~isempty(app.Location.Value)
                    if height(anatelDB) & (height(anatelDB) <= 10000)
                        Node = regexp(app.Location.Value, '(?<lat>[-]\d+[.]{1}\d{6}), (?<lon>[-]\d+[.]{1}\d{6}) .*', 'names');
                        Node.lat = str2double(Node.lat);
                        Node.lon = str2double(Node.lon);

                        anatelDB.Distance = double(round(geo_lldistkm_v2(Node, anatelDB(:, 3:4))));
    
                        idx1 = find(Parameters_table.Family == "Distância");                        
                        if ~isempty(idx1)
                            idx2 = zeros(height(anatelDB), 1, 'logical');

                            for ii = idx1'
                                idx2 = idx2 | (anatelDB.Distance <= app.Parameters(ii).Value + Tolerance);
                            end

                            anatelDB(~idx2,:) = [];
                        end                        
                    end
                end
            end            

            [SortRows_idx, SortRows_direction] = Fcn4_SortRows(app);
            app.UITable.Data = sortrows(anatelDB, [SortRows_idx, 2], SortRows_direction);
            app.UITable.Selection = [];

            scroll(app.UITable, 'top')
            UITableSelectionChanged(app)

            app.filteredSummary.Text = {sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> REGISTROS </font>', height(anatelDB));                ...
                                sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> ESTAÇÕES </font>',  numel(unique(anatelDB.Station))); ...
                                sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> SERVIÇOS </font>',  numel(unique(anatelDB.Service)))};

            Fcn3_Plot(app)
            Fcn3_PlotNodes(app)

        end


        function Fcn2_AnatelDB_info(app)

            global AnatelDB
            global AnatelDB_info

            app.UIAxes1.Title.String    = 'anateldb ';
            app.UIAxes1.Subtitle.String = sprintf('%s ', extractBefore(AnatelDB_info.ReleaseDate, ' '));

            app.Version.Text = sprintf('<b style="font-size: 11;">%s</b>\n<font style="font-size: 9;">%s</font>\n\n%s', ...
                                     app.UIAxes1.Title.String,    ...
                                     app.UIAxes1.Subtitle.String, ...
                                     jsonencode(AnatelDB_info, 'PrettyPrint', true));

            app.rawSummary.Text = {sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> REGISTROS </font>', height(AnatelDB));                ...
                                  sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> ESTAÇÕES </font>',  numel(unique(AnatelDB.Station))); ...
                                  sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> SERVIÇOS </font>',  numel(unique(AnatelDB.Service)))};

        end
        
        
        function Fcn3_Plot(app)

            cla(app.UIAxes1)
            hold(app.UIAxes1, "on")    

            p1 = geoscatter(app.UIAxes1, app.UITable.Data.Latitude, app.UITable.Data.Longitude);
            if isempty(app.Tree.Children)
                geolimits(app.UIAxes1, [-34, 4], [-93,-15])
            else
                geolimits(app.UIAxes1, 'auto')
            end

            hold(app.UIAxes1, "off")

            p2 = histogram(app.UIAxes2, app.UITable.Data.Frequency);
            p3 = histogram(app.UIAxes3, categorical(app.UITable.Data.Service));
            
            while true
                try
                    % color = app.CallingApp.General.Colors(4,:);
                    color = [0.38,0.60,0.73];

                    app.UIAxes2.Children.EdgeColor = 'none';
                    app.UIAxes2.Children.FaceColor = color;

                    app.UIAxes3.Children.EdgeColor = 'none';
                    app.UIAxes3.Children.FaceColor = color;

                    break
                catch
                end
            end

            % DataTip            
            if height(app.UITable.Data)
                set(p1.DataTipTemplate, FontName='Calibri', FontSize=10)
                p1.DataTipTemplate.DataTipRows(1) = dataTipTextRow('', app.UITable.Data.Frequency, '%.3f MHz');
                p1.DataTipTemplate.DataTipRows(2) = dataTipTextRow('', app.UITable.Data.Distance, '%.0f km');
                if height(app.UITable.Data) == 1
                    p1.DataTipTemplate.DataTipRows(3) = dataTipTextRow('ID:', {app.UITable.Data.Id});               
                    p1.DataTipTemplate.DataTipRows(4) = dataTipTextRow('', {app.UITable.Data.Description});                
                elseif height(app.UITable.Data) > 1
                    p1.DataTipTemplate.DataTipRows(3) = dataTipTextRow('ID:', app.UITable.Data.Id);
                    p1.DataTipTemplate.DataTipRows(4) = dataTipTextRow('', app.UITable.Data.Description);
                end
                p1.DataTipTemplate.DataTipRows = p1.DataTipTemplate.DataTipRows([3,1,4,2]);

                set(p2.DataTipTemplate, FontName='Calibri', FontSize=10)
                p2.DataTipTemplate.DataTipRows = flip(p2.DataTipTemplate.DataTipRows);
                p2.DataTipTemplate.DataTipRows(1).Label = 'Banda (MHz):';
                p2.DataTipTemplate.DataTipRows(2).Label = 'Registros:';
    
                set(p3.DataTipTemplate, FontName='Calibri', FontSize=10)
                p3.DataTipTemplate.DataTipRows = flip(p3.DataTipTemplate.DataTipRows);
                p3.DataTipTemplate.DataTipRows(1).Label = 'Serviço:';
                p3.DataTipTemplate.DataTipRows(2).Label = 'Registros:';
            end

        end


        function Fcn3_PlotNodes(app)

            if ~isempty(app.nodeTable)
                hold(app.UIAxes1, 'on')

                if height(app.nodeTable) > 1
                    p1 = geoscatter(app.UIAxes1, app.nodeTable.lat, app.nodeTable.long, 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'Tag', 'OthersNodes');
                    
                    set(p1.DataTipTemplate, FontName='Calibri', FontSize=10)
                    p1.DataTipTemplate.DataTipRows(1).Label = 'Lat:';
                    p1.DataTipTemplate.DataTipRows(2).Label = 'Lon:';
                end

                idx = find(strcmp(app.Location.Items, app.Location.Value), 1);
                p2 = geoscatter(app.UIAxes1, app.nodeTable.lat(idx), app.nodeTable.long(idx), 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'MarkerFaceColor', '#A2142F', 'Tag', 'SelectedNode');
                
                set(p2.DataTipTemplate, FontName='Calibri', FontSize=10)
                p2.DataTipTemplate.DataTipRows(1).Label = 'Lat:';
                p2.DataTipTemplate.DataTipRows(2).Label = 'Lon:';

                hold(app.UIAxes1, 'off')
            end

        end


        function [idx, direction] = Fcn4_SortRows(app)

            switch app.SortRows_DropDown.Value
                case 'Frequência';       idx =  2;
                case 'Serviço';          idx =  6;
                case 'Estação';          idx =  7;
                case 'Largura de banda'; idx =  9;
                case 'Distância';        idx = 10;
            end

            switch app.SortRows_Image.ImageSource
                case 'LT_SortRows_ascend.png'; direction = 'ascend';
                otherwise;                     direction = 'descend';
            end

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.UIFigure.Position(4) = 660;
            PanelTab_SelectionChanged(app, struct('Source', app.Tab1_Image))

            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end
            
            Fcn1_AxesCreation(app)
            Fcn1_NodeList(app)
            
            Fcn2_AnatelDB(app)
            Fcn2_AnatelDB_info(app)
            
            FamilyValueChanged(app)
            app.mainGrid.RowHeight(2:3) = {150,0};

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin8 = [];
            delete(app)
            
        end

        % Image clicked function: Tab1_Image, Tab2_Image
        function PanelTab_SelectionChanged(app, event)
            
            switch event.Source
                case app.Tab1_Image; app.mainPanel_Grid.RowHeight([2,4]) = {'1x',0};
                case app.Tab2_Image; app.mainPanel_Grid.RowHeight([2,4]) = {0,'1x'};
            end
            
        end

        % Image clicked function: imgShowHide
        function PanelVisibility_ImageClicked(app, event)
            
            if app.mainGrid.ColumnWidth{1} == 300
                app.imgShowHide.ImageSource = 'LT_HideRight.png';
                app.mainGrid.ColumnWidth{1} = 0;

                app.UITable.ColumnWidth{5}    = 588;
                app.table_Grid.ColumnWidth{6} = 588;
            else
                app.imgShowHide.ImageSource = 'LT_HideLeft.png';
                app.mainGrid.ColumnWidth{1} = 300;

                app.UITable.ColumnWidth{5}    = 288;
                app.table_Grid.ColumnWidth{6} = 288;
            end

        end

        % Image clicked function: AddFilter
        function AddFilterClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            try
                switch app.Family.Value
                    case {'Frequência', 'Distância'}
                        switch app.Type.Value
                            case {'É igual a...', 'É igual ou inferior a...'}
                                if app.Value2.Value <= 0
                                    error('O valor deve ser maior do que zero.')
                                end
                                app.Parameters(end+1).Value = app.Value2.Value;
            
                            case 'Está entre...'
                                if (app.Value2.Value <= 0) || (app.Value3.Value <= 0) || (app.Value2.Value >= app.Value3.Value)
                                    error('Os valores devem ser maiores do que zero e crescentes.')
                                end            
                                app.Parameters(end+1).Value = [app.Value2.Value, app.Value3.Value];
                        end

                    case {'Serviço', 'Estação'}
                        switch app.Type.Value
                            case 'É igual a...'
                                if app.Value2.Value == 0
                                    error('O valor deve ser diferente de zero.')
                                end
                                app.Parameters(end+1).Value = app.Value2.Value;
            
                            case 'Está entre...'
                                if (app.Value2.Value == 0) || (app.Value3.Value == 0) || (app.Value2.Value >= app.Value3.Value)
                                    error('Os valores devem ser diferentes de zero e crescentes.')
                                end            
                                app.Parameters(end+1).Value = [app.Value2.Value, app.Value3.Value];
                        end
                    
                    case 'Descrição'
                        if isempty(deblank(app.Value1.Value))
                            error('O campo textual não pode estar vazio.')
                        end
                        app.Parameters(end+1).Value = app.Value1.Value;
                end
    
                app.Parameters(end).Family = app.Family.Value;
                app.Parameters(end).Type   = app.Type.Value;
                
                Fcn2_TreeBuilding(app)
                Fcn2_AnatelDB(app)

            catch ME
                MessageBox(app, 'warning', ME.message)
            end

            delete(d)

        end

        % Value changed function: Family
        function FamilyValueChanged(app, event)
            
            switch app.Family.Value
                case 'Frequência'
                    app.Type.Items = {'É igual a...', 'Está entre...'};
                    set(app.Value2, 'RoundFractionalValues', 0, 'ValueDisplayFormat', '%.3f')
                    set(app.Value3, 'RoundFractionalValues', 0, 'ValueDisplayFormat', '%.3f')

                case 'Descrição'
                    app.Type.Items = {'Contém...'};

                case {'Serviço', 'Estação'}
                    app.Type.Items = {'É igual a...', 'Está entre...'};
                    set(app.Value2, 'RoundFractionalValues', 1, 'ValueDisplayFormat', '%.0f')
                    set(app.Value3, 'RoundFractionalValues', 1, 'ValueDisplayFormat', '%.0f')

                case 'Distância'
                    app.Type.Items = {'É igual ou inferior a...'};
                    set(app.Value2, 'RoundFractionalValues', 1, 'ValueDisplayFormat', '%.0f')
                    set(app.Value3, 'RoundFractionalValues', 1, 'ValueDisplayFormat', '%.0f')
            end

            TypeValueChanged(app)

        end

        % Value changed function: Type
        function TypeValueChanged(app, event)
            
            switch app.Type.Value
                case {'É igual a...', 'É igual ou inferior a...'}
                    app.Tab2_Panel.RowHeight(7:8) = {0, 22};
                    app.Value1.Enable = 0;
                    app.Value2.Enable = 1;
                    app.Value3.Enable = 0;

                case 'Está entre...'
                    app.Tab2_Panel.RowHeight(7:8) = {0, 22};
                    app.Value1.Enable = 0;
                    app.Value2.Enable = 1;
                    app.Value3.Enable = 1;

                case 'Contém...'
                    app.Tab2_Panel.RowHeight(7:8) = {22, 0};
                    app.Value1.Enable = 1;
                    app.Value2.Enable = 0;
                    app.Value3.Enable = 0;
            end
            
        end

        % Menu selected function: Delete, DeleteAll
        function DeleteFilterClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            switch event.Source
                case app.Delete
                    if isempty(app.Tree.SelectedNodes)
                        return
                    end

                    idx = [app.Tree.SelectedNodes.NodeData];
                    app.Parameters(idx) = [];

                case app.DeleteAll
                    app.Parameters = struct('Family', {}, 'Type', {}, 'Value', {});
            end

            Fcn2_TreeBuilding(app)
            Fcn2_AnatelDB(app)

            delete(d)

        end

        % Value changed function: Location
        function LocationValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            Fcn2_AnatelDB(app)
            delete(d)

        end

        % Callback function
        function BaseMapValueChanged(app, event)
            
            app.UIAxes1.Basemap = app.BaseMap.Value;

        end

        % Image clicked function: LocationRefresh
        function LocationRefreshClicked(app, event)
            
            InitialValue = app.Location.Value;
            Fcn1_NodeList(app)

            if ~isempty(InitialValue)
                idx = find(strcmp(app.Location.Items, InitialValue), 1);
                if ~isempty(idx)
                    app.Location.Value = InitialValue;
                    LocationValueChanged(app)
                else
                    LocationValueChanged(app)
                end

            else
                if ~isempty(app.Location.Value)
                    LocationValueChanged(app)
                end
            end
            
        end

        % Callback function: SortRows_DropDown, SortRows_Image
        function SortRowsCallback(app, event)
            
            switch event.Source
                case app.SortRows_DropDown
                    [idx, direction] = Fcn4_SortRows(app);
                    app.UITable.Data = sortrows(app.UITable.Data, idx, direction);
                    app.UITable.Selection = [];
                    
                    scroll(app.UITable, 'top')
                    UITableSelectionChanged(app)
                
                case app.SortRows_Image
                    if app.SortRows_Image.ImageSource == "LT_SortRows_ascend.png"; app.SortRows_Image.ImageSource = "LT_SortRows_descend.png";
                    else;                                                          app.SortRows_Image.ImageSource = "LT_SortRows_ascend.png";
                    end

                    SortRowsCallback(app, struct('Source', app.SortRows_DropDown))
            end
            
        end

        % Image clicked function: Export_clipboard
        function Button_2Pushed(app, event)
            
            if ~isempty(app.UITable.Selection)
                idx = app.UITable.Selection;
                clipboard('copy', app.UITable.Data.Id{idx})

                MessageBox(app, 'info', sprintf('Copiado para <i>clipboard</i> o ID do registro selecionado: "%s"', app.UITable.Data.Id{idx}))
            end

        end

        % Callback function: UITable
        function UITableSelectionChanged(app, event)

            if ~isempty(app.UITable.Selection)
                app.Export_clipboard.Enable = 1;
            else
                app.Export_clipboard.Enable = 0;
            end
            
        end

        % Image clicked function: Export_save
        function Button_3Pushed(app, event)
            
            global AnatelDB
            global AnatelDB_info

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            userPath = app.CallingApp.menu_userPath.Value;
            tag1 = '';
            if height(AnatelDB) ~= height(app.UITable.Data); tag1 = ' (F)';
            end
            
            kk = 0;
            while true
                try
                    if kk == 0; tag2 = '';
                    else;       tag2 = sprintf(' (%d)', kk);
                    end

                    writetable(app.UITable.Data(:,1:end-1), fullfile(userPath, sprintf('anateldb %s%s%s.xlsx', replace(extractBefore(AnatelDB_info.ReleaseDate, ' '), '/', '_'), tag1, tag2)), 'WriteMode', 'overwritesheet')
                    break
                catch
                    kk = kk+1;
                end
            end

            delete(d)
            MessageBox(app, 'info', sprintf('Salvo arquivo "<b>%s</b>" na pasta de trabalho.', sprintf('anateldb %s%s%s.xlsx', replace(extractBefore(AnatelDB_info.ReleaseDate, ' '), '/', '_'), tag1, tag2)))

        end

        % Image clicked function: imgHistogram, imgTable
        function Image2Clicked(app, event)

            if isequal(app.mainGrid.RowHeight(2:3), {150,0})
                app.mainGrid.RowHeight(2:3) = {0,150};
                set(app.SortRows_Grid.Children, 'Enable', 1)

                app.imgHistogram.ImageSource = 'DriveTest_Scatter_OFF.png';
                app.imgTable.ImageSource     = 'DriveTest_Route_ON.png';

            else
                app.mainGrid.RowHeight(2:3) = {150,0};
                set(app.SortRows_Grid.Children, 'Enable', 0)

                app.imgHistogram.ImageSource = 'DriveTest_Scatter_ON.png';
                app.imgTable.ImageSource     = 'DriveTest_Route_OFF.png';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: anateldb';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.mainGrid = uigridlayout(app.UIFigure);
            app.filter_Panel = uipanel(app.mainGrid);
            app.mainPanel_Grid = uigridlayout(app.filter_Panel);
            app.Tab1_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab1_Grid.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_Grid);
            app.Tab1_Title.Text = 'ASPECTOS GERAIS';
            app.Tab1_Image = uiimage(app.Tab1_Grid);
            app.Tab1_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab1_Panel = uigridlayout(app.mainPanel_Grid);
            app.imgHistogram = uiimage(app.Tab1_Panel);
            app.imgHistogram.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.imgHistogram.ImageSource = 'DriveTest_Scatter_ON.png';
            app.imgTable = uiimage(app.Tab1_Panel);
            app.imgTable.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.imgTable.ImageSource = 'DriveTest_Route_OFF.png';
            app.Version_Label = uilabel(app.Tab1_Panel);
            app.Version_Label.Text = 'Versão:';
            app.Version_Panel = uipanel(app.Tab1_Panel);
            app.Version_Grid = uigridlayout(app.Version_Panel);
            app.Version = uilabel(app.Version_Grid);
            app.Version.Interpreter = 'html';
            app.Version.Text = '';
            app.rawSummary = uilabel(app.Tab1_Panel);
            app.rawSummary.Interpreter = 'html';
            app.rawSummary.Text = '';
            app.SortRows_Label = uilabel(app.Tab1_Panel);
            app.SortRows_Label.Text = 'Ordenamento primário:';
            app.SortRows_Grid = uigridlayout(app.Tab1_Panel);
            app.SortRows_Image = uiimage(app.SortRows_Grid);
            app.SortRows_Image.ImageClickedFcn = createCallbackFcn(app, @SortRowsCallback, true);
            app.SortRows_Image.Enable = 'off';
            app.SortRows_Image.ImageSource = 'LT_SortRows_ascend.png';
            app.SortRows_DropDown = uidropdown(app.SortRows_Grid);
            app.SortRows_DropDown.Items = {'Frequência', 'Serviço', 'Estação', 'Largura de banda', 'Distância'};
            app.SortRows_DropDown.ValueChangedFcn = createCallbackFcn(app, @SortRowsCallback, true);
            app.SortRows_DropDown.Enable = 'off';
            app.SortRows_DropDown.Value = 'Frequência';
            app.Tab2_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab2_Grid.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_Grid);
            app.Tab2_Title.Text = 'FILTROS';
            app.Tab2_Image = uiimage(app.Tab2_Grid);
            app.Tab2_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab2_Image.ImageSource = 'LT_Filter.png';
            app.Tab2_Panel = uigridlayout(app.mainPanel_Grid);
            app.Location_Grid = uigridlayout(app.Tab2_Panel);
            app.LocationLabel = uilabel(app.Location_Grid);
            app.LocationLabel.Text = 'Local da monitoração:';
            app.Location = uidropdown(app.Location_Grid);
            app.Location.Items = {};
            app.Location.ValueChangedFcn = createCallbackFcn(app, @LocationValueChanged, true);
            app.Location.Value = {};
            app.LocationRefresh = uiimage(app.Location_Grid);
            app.LocationRefresh.ImageClickedFcn = createCallbackFcn(app, @LocationRefreshClicked, true);
            app.LocationRefresh.ImageSource = 'LT_refresh.png';
            app.FamilyLabel = uilabel(app.Tab2_Panel);
            app.FamilyLabel.Text = 'Filtro:';
            app.Family = uidropdown(app.Tab2_Panel);
            app.Family.Items = {'Frequência', 'Descrição', 'Serviço', 'Estação', 'Distância'};
            app.Family.ValueChangedFcn = createCallbackFcn(app, @FamilyValueChanged, true);
            app.Family.Value = 'Frequência';
            app.TypeLabel = uilabel(app.Tab2_Panel);
            app.TypeLabel.Text = 'Tipo:';
            app.Type = uidropdown(app.Tab2_Panel);
            app.Type.Items = {'É igual a...', 'Está entre...', 'Contém...'};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @TypeValueChanged, true);
            app.Type.Value = 'É igual a...';
            app.AddFilter_Grid = uigridlayout(app.Tab2_Panel);
            app.AddFilter = uiimage(app.AddFilter_Grid);
            app.AddFilter.ImageClickedFcn = createCallbackFcn(app, @AddFilterClicked, true);
            app.AddFilter.Tooltip = {'Adicionar filtro'};
            app.AddFilter.ImageSource = 'LT_Add.png';
            app.Value1 = uieditfield(app.Tab2_Panel, 'text');
            app.Value2 = uieditfield(app.Tab2_Panel, 'numeric');
            app.Value2.Limits = [-1 Inf];
            app.Value2.Value = -1;
            app.Value3 = uieditfield(app.Tab2_Panel, 'numeric');
            app.Value3.Limits = [-1 Inf];
            app.Value3.Value = -1;
            app.Hifen = uilabel(app.Tab2_Panel);
            app.Hifen.Text = '-';
            app.Tree = uitree(app.Tab2_Panel);
            app.Tree.Multiselect = 'on';
            app.filteredSummary = uilabel(app.Tab2_Panel);
            app.filteredSummary.Interpreter = 'html';
            app.filteredSummary.Text = '';
            app.imgShowHide = uiimage(app.mainGrid);
            app.imgShowHide.ImageClickedFcn = createCallbackFcn(app, @PanelVisibility_ImageClicked, true);
            app.imgShowHide.ImageSource = 'LT_HideLeft.png';
            app.table_Grid = uigridlayout(app.mainGrid);
            app.UITable = uitable(app.table_Grid);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false false false false false false false true];
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.Column1 = uilabel(app.table_Grid);
            app.Column1.Text = {'  ID'; '  '};
            app.Column2 = uilabel(app.table_Grid);
            app.Column2.Text = {'  Frequência'; '  (MHz)'};
            app.Column3 = uilabel(app.table_Grid);
            app.Column3.Text = {'   Latitude'; '  '};
            app.Column4 = uilabel(app.table_Grid);
            app.Column4.Text = {'  Longitude'; '  '};
            app.Column5 = uilabel(app.table_Grid);
            app.Column5.Text = {'  Descrição'; '  '};
            app.Column6 = uilabel(app.table_Grid);
            app.Column6.Text = {'  Serviço'; '  '};
            app.Column7 = uilabel(app.table_Grid);
            app.Column7.Text = {'  Estação'; '  '};
            app.Column8 = uilabel(app.table_Grid);
            app.Column8.Text = {'  Classe'; '  designação'};
            app.Column9 = uilabel(app.table_Grid);
            app.Column9.Text = {'  Largura de'; '  banda (kHz)'};
            app.Column10 = uilabel(app.table_Grid);
            app.Column10.Text = {'  Distância'; '  (km)'};
            app.Column11 = uilabel(app.table_Grid);
            app.Column11.Text = '';
            app.Export_Grid = uigridlayout(app.table_Grid);
            app.Export_save = uiimage(app.Export_Grid);
            app.Export_save.ImageClickedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Export_save.Tooltip = {'Exportar planilha'};
            app.Export_save.ImageSource = 'LT_save.png';
            app.Export_clipboard = uiimage(app.Export_Grid);
            app.Export_clipboard.ImageClickedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Export_clipboard.Tooltip = {'Exportar ID'};
            app.Export_clipboard.ImageSource = 'LT_copy.png';
            app.GridLayout4 = uigridlayout(app.mainGrid);
            app.UIAxes2 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes2, 'Frequência (MHz)')
            ylabel(app.UIAxes2, 'Registros')
            app.UIAxes2.YScale = 'log';
            app.UIAxes3 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes3, 'Serviço')
            ylabel(app.UIAxes3, 'Registros')
            app.UIAxes3.YScale = 'log';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Delete = uimenu(app.ContextMenu);
            app.Delete.MenuSelectedFcn = createCallbackFcn(app, @DeleteFilterClicked, true);
            app.Delete.Text = 'Excluir';
            app.DeleteAll = uimenu(app.ContextMenu);
            app.DeleteAll.MenuSelectedFcn = createCallbackFcn(app, @DeleteFilterClicked, true);
            app.DeleteAll.Text = 'Excluir todos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAnatelDB(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end