classdef winAppAnalise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        home_Grid                       matlab.ui.container.GridLayout
        home_GIF                        matlab.ui.control.Image
        home_Title                      matlab.ui.control.Label
        menu_Grid                       matlab.ui.container.GridLayout
        AppInfo                         matlab.ui.control.Image
        FigurePosition                  matlab.ui.control.Image
        RFDataHubModule                 matlab.ui.control.Image
        jsBackDoor                      matlab.ui.control.HTML
        menu_Button5                    matlab.ui.control.StateButton
        menu_Separator2                 matlab.ui.control.Image
        menu_Button4                    matlab.ui.control.StateButton
        menu_Button3                    matlab.ui.control.StateButton
        menu_Button2                    matlab.ui.control.StateButton
        menu_Separator1                 matlab.ui.control.Image
        menu_Button1                    matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_File                       matlab.ui.container.Tab
        file_Grid                       matlab.ui.container.GridLayout
        file_panelGrid                  matlab.ui.container.GridLayout
        file_FilteringTree              matlab.ui.container.Tree
        file_FilteringAdd               matlab.ui.control.Image
        file_FilteringType3_Description  matlab.ui.control.EditField
        file_FilteringType2_ID          matlab.ui.control.DropDown
        file_FilteringType1_Frequency   matlab.ui.control.DropDown
        file_FilteringTypePanel         matlab.ui.container.ButtonGroup
        file_FilteringType3             matlab.ui.control.RadioButton
        file_FilteringType2             matlab.ui.control.RadioButton
        file_FilteringType1             matlab.ui.control.RadioButton
        file_FilteringLabel             matlab.ui.control.Label
        file_MetadataPanel              matlab.ui.container.Panel
        file_MetadataGrid               matlab.ui.container.GridLayout
        file_Metadata                   matlab.ui.control.HTML
        file_MetadataLabel              matlab.ui.control.Label
        file_docGrid                    matlab.ui.container.GridLayout
        file_Tree                       matlab.ui.container.Tree
        file_TreeLabel                  matlab.ui.control.Label
        file_TitleGrid                  matlab.ui.container.GridLayout
        file_Title                      matlab.ui.control.Label
        file_TitleIcon                  matlab.ui.control.Image
        file_toolGrid                   matlab.ui.container.GridLayout
        file_SpecReadButton             matlab.ui.control.Button
        file_OpenFileButton             matlab.ui.control.Image
        Tab2_Playback                   matlab.ui.container.Tab
        play_Grid                       matlab.ui.container.GridLayout
        play_toolGrid                   matlab.ui.container.GridLayout
        tool_TimestampLabel             matlab.ui.control.Label
        tool_Play                       matlab.ui.control.Image
        tool_LoopControl                matlab.ui.control.Image
        tool_TimestampSlider            matlab.ui.control.Slider
        tool_LayoutRight                matlab.ui.control.Image
        tool_MiscRefresh                matlab.ui.control.Image
        tool_MiscCancel                 matlab.ui.control.Image
        tool_MiscEdit                   matlab.ui.control.Image
        tool_FiscalizaUpdate            matlab.ui.control.Image
        tool_FiscalizaAutoFill          matlab.ui.control.Image
        tool_ReportGenerator            matlab.ui.control.Image
        tool_ReportAnalysis             matlab.ui.control.Image
        tool_ExceptionList              matlab.ui.control.Image
        tool_LayoutLeft                 matlab.ui.control.Image
        play_ControlsGrid               matlab.ui.container.GridLayout
        play_ControlsTab1Grid           matlab.ui.container.GridLayout
        play_ControlsTab1Image          matlab.ui.control.Image
        play_ControlsTab1Label          matlab.ui.control.Label
        misc_ControlsTab1Info           matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        edit_unitPanel                  matlab.ui.container.Panel
        misc_Edit_unitGrid              matlab.ui.container.GridLayout
        edit_unitRefresh                matlab.ui.control.Image
        misc_Edit_kFactorTree           matlab.ui.container.Tree
        misc_Edit_kFactorAdd            matlab.ui.control.Image
        misc_Edit_kFactor               matlab.ui.control.DropDown
        misc_Edit_kFactorLabel          matlab.ui.control.Label
        misc_Edit_unitRaw               matlab.ui.control.EditField
        misc_Edit_unitRawLabel          matlab.ui.control.Label
        edit_gpsPanel                   matlab.ui.container.Panel
        edit_gpsGrid                    matlab.ui.container.GridLayout
        edit_gpsCityButton              matlab.ui.control.Image
        edit_gpsCityPanel               matlab.ui.container.Panel
        edit_gpsCityGrid                matlab.ui.container.GridLayout
        edit_gpsCity                    matlab.ui.control.EditField
        edit_gpsCitySource              matlab.ui.control.EditField
        edit_gpsLocationLabel2          matlab.ui.control.Label
        edit_gpsLocationPanel           matlab.ui.container.Panel
        edit_gpsLocationGrid            matlab.ui.container.GridLayout
        edit_gpsLongitude               matlab.ui.control.NumericEditField
        edit_gpsLatitude                matlab.ui.control.NumericEditField
        edit_gpsSource                  matlab.ui.control.EditField
        edit_gpsLocationLabel1          matlab.ui.control.Label
        misc_Panel1                     matlab.ui.container.Panel
        misc_Grid1                      matlab.ui.container.GridLayout
        misc_DeleteAllLabel             matlab.ui.control.Label
        misc_DeleteAll                  matlab.ui.control.Button
        misc_AddCurveLabel              matlab.ui.control.Label
        misc_AddCurve                   matlab.ui.control.Button
        misc_EditLocalLabel             matlab.ui.control.Label
        misc_EditLocal                  matlab.ui.control.Button
        misc_FilterLabel                matlab.ui.control.Label
        misc_Filter                     matlab.ui.control.Button
        misc_ImportLabel                matlab.ui.control.Label
        misc_Import                     matlab.ui.control.Button
        misc_ExportLabel                matlab.ui.control.Label
        misc_Export                     matlab.ui.control.Button
        misc_Serarator                  matlab.ui.control.Image
        misc_DelLabel                   matlab.ui.control.Label
        misc_Del                        matlab.ui.control.Button
        misc_MergeLabel                 matlab.ui.control.Label
        misc_Merge                      matlab.ui.control.Button
        misc_DuplicateLabel             matlab.ui.control.Label
        misc_Duplicate                  matlab.ui.control.Button
        misc_SaveLabel                  matlab.ui.control.Label
        misc_Save                       matlab.ui.control.Button
        misc_Label1                     matlab.ui.control.Label
        misc_ControlsTab1Grid           matlab.ui.container.GridLayout
        misc_ControlsTab1Image          matlab.ui.control.Image
        misc_ControlsTab1Label          matlab.ui.control.Label
        report_ControlsTab2Info         matlab.ui.container.GridLayout
        report_FiscalizaPanel           matlab.ui.container.Panel
        report_FiscalizaGrid            matlab.ui.container.GridLayout
        report_FiscalizaIcon            matlab.ui.control.Image
        report_FiscalizaRefresh         matlab.ui.control.Image
        report_Fiscaliza_PanelLabel     matlab.ui.control.Label
        report_ControlsTab2Grid         matlab.ui.container.GridLayout
        report_ControlsTab2Image        matlab.ui.control.Image
        report_ControlsTab2Label        matlab.ui.control.Label
        report_ControlsTab1Info         matlab.ui.container.GridLayout
        report_Tree                     matlab.ui.container.CheckBoxTree
        report_TreeAddImage             matlab.ui.control.Image
        report_DocumentPanel            matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        report_IssueLabel               matlab.ui.control.Label
        report_Issue                    matlab.ui.control.NumericEditField
        report_ExternalFiles            matlab.ui.control.Table
        report_ExternalFilesLabel       matlab.ui.control.Label
        report_AddProjectAttachment     matlab.ui.control.Image
        report_Version                  matlab.ui.control.DropDown
        report_VersionLabel             matlab.ui.control.Label
        report_ModelName                matlab.ui.control.DropDown
        report_ModelNameLabel           matlab.ui.control.Label
        report_DocumentPanelLabel       matlab.ui.control.Label
        report_ProjectSave              matlab.ui.control.Image
        report_ProjectOpen              matlab.ui.control.Image
        report_ProjectNew               matlab.ui.control.Image
        report_ProjectName              matlab.ui.control.TextArea
        report_ProjectNameLabel         matlab.ui.control.Label
        report_ThreadAlgorithmsLabel    matlab.ui.control.Label
        report_ControlsTab1Grid         matlab.ui.container.GridLayout
        report_ControlsTab1Image        matlab.ui.control.Image
        report_ProjectWarnIcon          matlab.ui.control.Image
        report_ControlsTab1Label        matlab.ui.control.Label
        play_ControlsTab3Info           matlab.ui.container.GridLayout
        play_FindPeaks_ExternalFilePanel  matlab.ui.container.Panel
        play_FindPeaks_ExternalFileGrid  matlab.ui.container.GridLayout
        play_FindPeaks_FileTemplate     matlab.ui.control.Hyperlink
        play_FindPeaks_ExternalFile     matlab.ui.control.DropDown
        play_FindPeaks_ExternalFileLabel  matlab.ui.control.Label
        play_FindPeaks_Description      matlab.ui.control.TextArea
        play_FindPeaks_DescriptionLabel  matlab.ui.control.Label
        play_FindPeaks_PeakBW           matlab.ui.control.NumericEditField
        play_FindPeaks_PeakBWLabel      matlab.ui.control.Label
        play_FindPeaks_PeakCF           matlab.ui.control.NumericEditField
        play_FindPeaks_PeakCFLabel      matlab.ui.control.Label
        play_FindPeaks_Tree             matlab.ui.container.Tree
        play_FindPeaks_add              matlab.ui.control.Image
        play_FindPeaks_ParametersPanel  matlab.ui.container.Panel
        play_FindPeaks_ParametersGrid   matlab.ui.container.GridLayout
        play_FindPeaks_Class            matlab.ui.control.DropDown
        play_FindPeaks_ClassLabel       matlab.ui.control.Label
        play_FindPeaks_MaxHoldPanel     matlab.ui.container.Panel
        play_FindPeaks_MaxHoldGrid      matlab.ui.container.GridLayout
        play_FindPeaks_maxOCC           matlab.ui.control.Spinner
        play_FindPeaks_meanOCC          matlab.ui.control.Spinner
        play_FindPeaks_OCCLabel         matlab.ui.control.Label
        play_FindPeaks_Prominence2      matlab.ui.control.Spinner
        play_FindPeaks_Prominence2Label  matlab.ui.control.Label
        play_FindPeaks_MeanPanel        matlab.ui.container.Panel
        play_FindPeaks_MeanGrid         matlab.ui.container.GridLayout
        play_FindPeaks_Prominence1      matlab.ui.control.Spinner
        play_FindPeaks_Prominence1Label  matlab.ui.control.Label
        play_FindPeaks_BW               matlab.ui.control.Spinner
        play_FindPeaks_BWLabel          matlab.ui.control.Label
        play_FindPeaks_distance         matlab.ui.control.Spinner
        play_FindPeaks_distanceLabel    matlab.ui.control.Label
        play_FindPeaks_prominence       matlab.ui.control.Spinner
        play_FindPeaks_prominenceLabel  matlab.ui.control.Label
        play_FindPeaks_THR              matlab.ui.control.Spinner
        play_FindPeaks_THRLabel         matlab.ui.control.Label
        play_FindPeaks_Numbers          matlab.ui.control.Spinner
        play_FindPeaks_NumbersLabel     matlab.ui.control.Label
        play_FindPeaks_Trace            matlab.ui.control.DropDown
        play_FindPeaks_TraceLabel       matlab.ui.control.Label
        play_FindPeaks_Algorithm        matlab.ui.control.DropDown
        play_FindPeaks_AlgorithmLabel   matlab.ui.control.Label
        play_FindPeaks_RadioGroup       matlab.ui.container.ButtonGroup
        play_FindPeaks_File             matlab.ui.control.RadioButton
        play_FindPeaks_DataTips         matlab.ui.control.RadioButton
        play_FindPeaks_ROI              matlab.ui.control.RadioButton
        play_FindPeaks_auto             matlab.ui.control.RadioButton
        play_FindPeaks_Label            matlab.ui.control.Label
        play_ControlsTab3Grid           matlab.ui.container.GridLayout
        play_ControlsTab3Image          matlab.ui.control.Image
        play_ControlsTab3Label          matlab.ui.control.Label
        play_ControlsTab2Info           matlab.ui.container.GridLayout
        play_Channel_ShowPlot           matlab.ui.control.Image
        play_BandLimits_Tree            matlab.ui.container.Tree
        play_BandLimits_add             matlab.ui.control.Image
        play_BandLimits_Panel           matlab.ui.container.Panel
        play_BandLimits_Grid            matlab.ui.container.GridLayout
        play_BandLimits_xLim2           matlab.ui.control.NumericEditField
        play_BandLimits_xLabel          matlab.ui.control.Label
        play_BandLimits_xLim1           matlab.ui.control.NumericEditField
        play_BandLimits_Status          matlab.ui.control.CheckBox
        play_Channel_Tree               matlab.ui.container.Tree
        play_Channel_add                matlab.ui.control.Image
        play_Channel_ExternalFilePanel  matlab.ui.container.Panel
        play_Channel_ExternalFileGrid   matlab.ui.container.GridLayout
        play_Channel_FileTemplate       matlab.ui.control.Hyperlink
        play_Channel_ExternalFile       matlab.ui.control.DropDown
        play_Channel_ExternalFileLabel  matlab.ui.control.Label
        play_Channel_Panel              matlab.ui.container.Panel
        play_Channel_Grid               matlab.ui.container.GridLayout
        play_Channel_Sample             matlab.ui.control.Label
        play_Channel_BW                 matlab.ui.control.NumericEditField
        play_Channel_BWLabel            matlab.ui.control.Label
        play_Channel_StepWidth          matlab.ui.control.NumericEditField
        play_Channel_StepWidthLabel     matlab.ui.control.Label
        play_Channel_Class              matlab.ui.control.DropDown
        play_Channel_ClassLabel         matlab.ui.control.Label
        play_Channel_LastChannel        matlab.ui.control.NumericEditField
        play_Channel_LastChannelLabel   matlab.ui.control.Label
        play_Channel_FirstChannel       matlab.ui.control.NumericEditField
        play_Channel_FirstChannelLabel  matlab.ui.control.Label
        play_Channel_nChannels          matlab.ui.control.NumericEditField
        play_Channel_nChannelsLabel     matlab.ui.control.Label
        play_Channel_Name               matlab.ui.control.EditField
        play_Channel_List               matlab.ui.control.DropDown
        play_Channel_ListUpdate         matlab.ui.control.Image
        play_Channel_ListLabel          matlab.ui.control.Label
        play_Channel_RadioGroup         matlab.ui.container.ButtonGroup
        play_Channel_File               matlab.ui.control.RadioButton
        play_Channel_Single             matlab.ui.control.RadioButton
        play_Channel_Multiples          matlab.ui.control.RadioButton
        play_Channel_ReferenceList      matlab.ui.control.RadioButton
        play_Channel_Label              matlab.ui.control.Label
        play_ControlsTab2Grid           matlab.ui.container.GridLayout
        play_ControlsTab2Image          matlab.ui.control.Image
        play_Channel_ShowPlotWarn       matlab.ui.control.Image
        play_ControlsTab2Label          matlab.ui.control.Label
        play_ControlsTab1Info           matlab.ui.container.GridLayout
        play_Customization              matlab.ui.control.CheckBox
        play_Waterfall_Panel            matlab.ui.container.Panel
        play_WaterFallGrid              matlab.ui.container.GridLayout
        play_Waterfall_cLim_Grid2       matlab.ui.container.GridLayout
        play_Waterfall_cLim2            matlab.ui.control.Spinner
        play_Waterfall_cLim_Separation  matlab.ui.control.Label
        play_Waterfall_cLim1            matlab.ui.control.Spinner
        play_Waterfall_cLim_Mode        matlab.ui.control.Image
        play_Waterfall_cLim_Label       matlab.ui.control.Label
        play_Waterfall_MeshStyle        matlab.ui.control.DropDown
        play_Waterfall_MeshStyleLabel   matlab.ui.control.Label
        play_Waterfall_Colormap         matlab.ui.control.DropDown
        play_Waterfall_ColormapLabel    matlab.ui.control.Label
        play_Waterfall_Colorbar         matlab.ui.control.DropDown
        play_Waterfall_ColorbarLabel    matlab.ui.control.Label
        play_Waterfall_Timeline         matlab.ui.control.DropDown
        play_Waterfall_TimelineLabel    matlab.ui.control.Label
        play_Waterfall_Decimation       matlab.ui.control.DropDown
        play_Waterfall_DecimationValue  matlab.ui.control.Label
        play_Waterfall_DecimationLabel  matlab.ui.control.Label
        play_Waterfall_Fcn              matlab.ui.control.DropDown
        play_Waterfall_FcnLabel         matlab.ui.control.Label
        play_OCC_Panel                  matlab.ui.container.Panel
        play_OCCGrid                    matlab.ui.container.GridLayout
        play_OCC_noisePanel             matlab.ui.container.Panel
        play_OCC_noiseGrid              matlab.ui.container.GridLayout
        play_OCC_noiseUsefulSamples     matlab.ui.control.Spinner
        play_OCC_noiseUsefulSamplesLabel  matlab.ui.control.Label
        play_OCC_noiseTrashSamples      matlab.ui.control.Spinner
        play_OCC_noiseTrashSamplesLabel  matlab.ui.control.Label
        play_OCC_noiseFcn               matlab.ui.control.DropDown
        play_OCC_noiseFcnLabel          matlab.ui.control.Label
        play_OCC_noiseLabel             matlab.ui.control.Label
        play_OCC_ceilFactor             matlab.ui.control.DropDown
        play_OCC_ceilFactorLabel        matlab.ui.control.Label
        play_OCC_Offset                 matlab.ui.control.Spinner
        play_OCC_OffsetLabel            matlab.ui.control.Label
        play_OCC_THRCaptured            matlab.ui.control.DropDown
        play_OCC_THR                    matlab.ui.control.Spinner
        play_OCC_THRLabel               matlab.ui.control.Label
        play_OCC_Orientation            matlab.ui.control.DropDown
        play_OCC_OrientationLabel       matlab.ui.control.Label
        play_OCC_IntegrationTimeCaptured  matlab.ui.control.NumericEditField
        play_OCC_IntegrationTime        matlab.ui.control.DropDown
        play_OCC_IntegrationTimeLabel   matlab.ui.control.Label
        play_OCC_Method                 matlab.ui.control.DropDown
        play_OCC_MethodLabel            matlab.ui.control.Label
        play_Persistance_Panel          matlab.ui.container.Panel
        play_PersistanceGrid            matlab.ui.container.GridLayout
        play_Persistance_cLim_Grid2     matlab.ui.container.GridLayout
        play_Persistance_cLim_Separation  matlab.ui.control.Label
        play_Persistance_cLim2          matlab.ui.control.Spinner
        play_Persistance_cLim1          matlab.ui.control.Spinner
        play_Persistance_cLim_Mode      matlab.ui.control.Image
        play_Persistance_cLim_Label     matlab.ui.control.Label
        play_Persistance_Transparency   matlab.ui.control.Spinner
        play_Persistance_TransparencyLabel  matlab.ui.control.Label
        play_Persistance_Colormap       matlab.ui.control.DropDown
        play_Persistance_ColormapLabel  matlab.ui.control.Label
        play_Persistance_WindowSize     matlab.ui.control.DropDown
        play_Persistance_WindowSizeLabel  matlab.ui.control.Label
        play_Persistance_Interpolation  matlab.ui.control.DropDown
        play_Persistance_InterpolationLabel  matlab.ui.control.Label
        play_ControlsPanel              matlab.ui.container.ButtonGroup
        play_RadioButton_Waterfall      matlab.ui.control.RadioButton
        play_RadioButton_Occupancy      matlab.ui.control.RadioButton
        play_RadioButton_Persistance    matlab.ui.control.RadioButton
        play_ControlPanelLabel          matlab.ui.control.Label
        play_GeneralPanel               matlab.ui.container.Panel
        play_OthersGrid                 matlab.ui.container.GridLayout
        play_LimitsPanel                matlab.ui.container.Panel
        play_LimitsGrid                 matlab.ui.container.GridLayout
        play_Limits_yLim2               matlab.ui.control.Spinner
        play_Limits_yLimLabel           matlab.ui.control.Label
        play_Limits_yLim1               matlab.ui.control.Spinner
        play_Limits_xLim2               matlab.ui.control.Spinner
        play_Limits_xLimLabel           matlab.ui.control.Label
        play_Limits_xLim1               matlab.ui.control.Spinner
        play_LimitsRefresh              matlab.ui.control.Image
        play_LimitsPanelLabel           matlab.ui.control.Label
        play_TraceIntegration           matlab.ui.control.DropDown
        play_TraceIntegrationLabel      matlab.ui.control.Label
        play_MinPlotTime                matlab.ui.control.Spinner
        play_MinPlotTimeLabel           matlab.ui.control.Label
        play_LineVisibility             matlab.ui.control.DropDown
        play_LineVisibilityLabel        matlab.ui.control.Label
        play_LayoutRatio                matlab.ui.control.DropDown
        play_LayoutRatioLabel           matlab.ui.control.Label
        play_GeneralPanelLabel          matlab.ui.control.Label
        play_axesToolbar                matlab.ui.container.GridLayout
        axesTool_RestoreView            matlab.ui.control.Image
        axesTool_Pan                    matlab.ui.control.Image
        axesTool_DataTip                matlab.ui.control.Image
        axesTool_Waterfall              matlab.ui.control.Image
        axesTool_Occupancy              matlab.ui.control.Image
        axesTool_Persistance            matlab.ui.control.Image
        axesTool_MaxHold                matlab.ui.control.Image
        axesTool_Average                matlab.ui.control.Image
        axesTool_MinHold                matlab.ui.control.Image
        play_PlotPanel                  matlab.ui.container.Panel
        play_TreeGrid                   matlab.ui.container.GridLayout
        report_DetectionManualMode      matlab.ui.control.CheckBox
        report_ThreadAlgorithmsPanel    matlab.ui.container.Panel
        report_ThreadAlgorithmsGrid     matlab.ui.container.GridLayout
        report_ThreadAlgorithms         matlab.ui.control.HTML
        report_ThreadAlgorithmsRefresh  matlab.ui.control.Image
        report_TreeLabel                matlab.ui.control.Label
        play_MetadataPanel              matlab.ui.container.Panel
        play_MetadataGrid               matlab.ui.container.GridLayout
        play_Metadata                   matlab.ui.control.HTML
        play_MetadataLabel              matlab.ui.control.Label
        play_Tree                       matlab.ui.container.Tree
        play_TreeSort                   matlab.ui.control.Image
        play_TreePanelVisibility        matlab.ui.control.Image
        play_TreeLabel                  matlab.ui.control.Label
        play_TreeTitleGrid              matlab.ui.container.GridLayout
        play_TreeTitle                  matlab.ui.control.Label
        play_TreeTitleImage             matlab.ui.control.Image
        Tab3_Config                     matlab.ui.container.Tab
        config_mainGrid                 matlab.ui.container.GridLayout
        config_Option3Grid              matlab.ui.container.GridLayout
        config_DefaultIssueValuesPanel  matlab.ui.container.Panel
        config_DefaultIssueValuesGrid   matlab.ui.container.GridLayout
        config_ServicoInspecao          matlab.ui.container.CheckBoxTree
        config_ServicoInspecaoLabel     matlab.ui.control.Label
        config_MotivoLAI                matlab.ui.container.CheckBoxTree
        config_MotivoLAILabel           matlab.ui.control.Label
        config_TipoPLAI                 matlab.ui.control.DropDown
        config_TipoPLAILabel            matlab.ui.control.Label
        config_GerarPLAI                matlab.ui.control.DropDown
        config_GerarPLAILabel           matlab.ui.control.Label
        config_CadastroSTEL             matlab.ui.control.DropDown
        config_CadastroSTELLabel        matlab.ui.control.Label
        config_DefaultIssueValuesLock   matlab.ui.control.Image
        config_DefaultIssueValuesLabel  matlab.ui.control.Label
        config_FiscalizaVersion         matlab.ui.container.ButtonGroup
        config_FiscalizaHM              matlab.ui.control.RadioButton
        config_FiscalizaPD              matlab.ui.control.RadioButton
        config_FiscalizaVersionLabel    matlab.ui.control.Label
        config_Option2Grid              matlab.ui.container.GridLayout
        config_SelectedTableColumns     matlab.ui.container.CheckBoxTree
        config_SelectedTableColumnsLabel  matlab.ui.control.Label
        config_MiscelaneousPanel2       matlab.ui.container.Panel
        config_MiscelaneousGrid2        matlab.ui.container.GridLayout
        config_WordCloudColumn          matlab.ui.control.DropDown
        config_WordCloudColumnLabel     matlab.ui.control.Label
        config_WordCloudAlgorithm       matlab.ui.control.DropDown
        config_WordCloudAlgorithmLabel  matlab.ui.control.Label
        config_MiscelaneousLabel2       matlab.ui.control.Label
        config_MiscelaneousPanel1       matlab.ui.container.Panel
        config_MiscelaneousGrid1        matlab.ui.container.GridLayout
        config_nMinWords                matlab.ui.control.DropDown
        config_nMinWordsLabel           matlab.ui.control.Label
        config_nMinCharacters           matlab.ui.control.Spinner
        config_nMinCharactersLabel      matlab.ui.control.Label
        config_MiscelaneousLabel1       matlab.ui.control.Label
        config_SearchModePanel          matlab.ui.container.ButtonGroup
        config_SearchModeListOfWords    matlab.ui.control.RadioButton
        config_SearchModeTokenSuggestion  matlab.ui.control.RadioButton
        config_SearchModeLabel          matlab.ui.control.Label
        config_Option1Grid              matlab.ui.container.GridLayout
        config_FolderMapPanel           matlab.ui.container.Panel
        config_FolderMapGrid            matlab.ui.container.GridLayout
        config_Folder_userPathButton    matlab.ui.control.Image
        config_Folder_userPath          matlab.ui.control.DropDown
        config_Folder_userPathLabel     matlab.ui.control.Label
        config_Folder_pythonPathButton  matlab.ui.control.Image
        config_Folder_pythonPath        matlab.ui.control.EditField
        config_Folder_pythonPathLabel   matlab.ui.control.Label
        config_Folder_DataHubPOSTButton  matlab.ui.control.Image
        config_Folder_DataHubPOST       matlab.ui.control.EditField
        config_Folder_DataHubPOSTLabel  matlab.ui.control.Label
        config_Folder_DataHubGETButton  matlab.ui.control.Image
        config_Folder_DataHubGET        matlab.ui.control.EditField
        config_Folder_DataHubGETLabel   matlab.ui.control.Label
        config_FolderMapLabel           matlab.ui.control.Label
        config_Control                  matlab.ui.container.GridLayout
        config_ButtonGroup              matlab.ui.container.ButtonGroup
        config_Option_Folder            matlab.ui.control.RadioButton
        config_Option_Fiscaliza         matlab.ui.control.RadioButton
        config_Option_Search            matlab.ui.control.RadioButton
        config_Label                    matlab.ui.control.Label
        config_ControlMenu              matlab.ui.container.GridLayout
        config_menuBtn1Grid             matlab.ui.container.GridLayout
        config_menuBtn1Icon             matlab.ui.control.Image
        config_menuBtn1Label            matlab.ui.control.Label
        config_menuUnderline            matlab.ui.control.Image
        file_ContextMenu_Tree1          matlab.ui.container.ContextMenu
        file_ContextMenu_delTree1Node   matlab.ui.container.Menu
        file_ContextMenu_Tree2          matlab.ui.container.ContextMenu
        file_ContextMenu_delTree2Node   matlab.ui.container.Menu
        play_FindPeaks_ContextMenu      matlab.ui.container.ContextMenu
        play_FindPeaks_ContextMenu_edit  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_analog  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_digital  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_del  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_search  matlab.ui.container.Menu
        play_FindPeaks_ContextMenu_Drive  matlab.ui.container.Menu
        play_Channel_ContextMenu        matlab.ui.container.ContextMenu
        play_Channel_ContextMenu_del    matlab.ui.container.Menu
        play_Channel_ContextMenu_addBandLimit  matlab.ui.container.Menu
        play_Channel_ContextMenu_addEmission  matlab.ui.container.Menu
        report_ContextMenu              matlab.ui.container.ContextMenu
        report_ContextMenu_del          matlab.ui.container.Menu
        report_ContextMenu_ExternalFiles  matlab.ui.container.Menu
        play_BandLimits_ContextMenu     matlab.ui.container.ContextMenu
        play_BandLimits_ContextMenu_del  matlab.ui.container.Menu
        edit_ContextMenu                matlab.ui.container.ContextMenu
        edit_ContextMenu_del            matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        % PROPRIEDADES COMUNS A TODOS OS APPS
        %-----------------------------------------------------------------%
        General 
        rootFolder

        % Essa propriedade registra o tipo de execução da aplicação, podendo
        % ser: 'built-in', 'desktopApp' ou 'webApp'.
        executionMode        

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj_startup

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag = {true, ...
                          true, ...
                          true};

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        % Objeto que possibilita integração com o FISCALIZA, consumindo lib
        % escrita em Python (fiscaliza).
        fiscalizaObj

        %-----------------------------------------------------------------%
        % PROPRIEDADES ESPECÍFICAS
        %-----------------------------------------------------------------%
        metaData    = class.metaData.empty
        specData    = class.specData.empty
        projectData

        bandObj
        channelObj

        restoreView = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})
        plotFlag    = 0
        plotLayout  = 1
        idxTime     = 1

        UIAxes1
        UIAxes2
        UIAxes3

        hClearWrite                                                         % ClearWrite (main trace)
        hMinHold
        hAverage
        hMaxHold
        hPersistanceObj                                                     % Persistance Object    
        hSelectedEmission                                                   % Selected marker roi
        hEmissionMarkers                                                    % Markers labels        
        hTHR                                                                % OCC roi/line
        hTHRLabel                                                           % OCC roi/line label ("THR")            
        hWaterfall
        hWaterfallTime                                                      % Waterfall timestamp

        auxiliarWin1                                                        % winSignalAnalysis
        auxiliarWin2                                                        % winDriveTest
        auxiliarWin3                                                        % winRFDataHub        
    end
    
    
    methods (Access = public)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO DO APP
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            app.timerObj_startup = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj_startup)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj_startup)

                % PATH SEARCH
                appName   = class.Constants.appName;
                MFilePath = fileparts(mfilename('fullpath'));
                
                app.rootFolder    = appUtil.RootFolder(appName, MFilePath);
                app.executionMode = appUtil.ExecutionMode(app.UIFigure);
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app, 0)
                jsBackDoor_Customizations(app, 1)

                % Criação das principais variáveis, leitura de arquivos externos 
                % ("GeneralSettings.json", por exemplo), e, posteriormente, atualização 
                % de propriedades de alguns dos componentes da GUI.
                startup_Files2Read(app)
                startup_AppProperties(app)
                startup_GUIComponents(app)
                startup_Axes(app)

                % Inicia módulo de operação paralelo...
                % parpoolCheck()

                % Libera screen... 
                app.TabGroup.Visible       = 1;
                sendEventToHTMLSource(app.jsBackDoor, 'turningBackgroundColorInvisible', struct('componentName', 'app.home_Grid', 'componentDataTag', struct(app.home_Grid).Controller.ViewModel.Id));

                app.menu_Button1.Enable    = 1;
                app.RFDataHubModule.Enable = 1;
                app.AppInfo.Enable         = 1;

                if strcmp(app.executionMode, 'webApp')
                    sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");
                else
                    app.FigurePosition.Enable = 1;
                    appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
                end
                drawnow
            end
        end

        %-----------------------------------------------------------------%
        function startup_Files2Read(app)
            % "GeneralSettings.json"
            [app.General, msgWarning] = appUtil.generalSettingsLoad(class.Constants.appName, app.rootFolder);
            if ~isempty(msgWarning)
                appUtil.modalWindow(app.UIFigure, 'error', msgWarning);
            end
        
            if isempty(app.General.Merge.Distance)
                app.General.Merge.Distance = Inf;
            end
        
            if isempty(app.General.Integration.Trace)
                app.General.Integration.Trace = Inf;
            end
        
            app.General.AppVersion = fcn.startup_Versions("Startup", app.rootFolder);
            app.General.Models     = struct2table(jsondecode(fileread(fullfile(app.rootFolder, 'Template', 'html_General.cfg'))));
            app.General.Report     = '';
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            app.projectData = projectLib(app);
            app.bandObj     = class.Band('appAnalise:PLAYBACK', app);
            app.channelObj  = class.ChannelLib(class.Constants.appName, app.rootFolder);
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            % Salva na propriedade "UserData" as opções de ícone e o índice 
            % da aba, simplificando os ajustes decorrentes de uma alteração...
            app.menu_Button1.UserData     = struct('iconOptions', {{'OpenFile_32White.png', 'OpenFile_32Yellow.png'}}, 'tabGroup', 1);
            app.menu_Button2.UserData     = struct('iconOptions', {{'Playback_32White.png', 'Playback_32Yellow.png'}}, 'tabGroup', 2);
            app.menu_Button3.UserData     = struct('iconOptions', {{'Report_32White.png',   'Report_32Yellow.png'}},   'tabGroup', 2);
            app.menu_Button4.UserData     = struct('iconOptions', {{'Misc_32White.png',     'Misc_32Yellow.png'}},     'tabGroup', 2);
            app.menu_Button5.UserData     = struct('iconOptions', {{'Settings_36White.png', 'Settings_36Yellow.png'}}, 'tabGroup', 3);

            app.play_TreeSort.UserData    = 'Receiver+ID';
            app.file_Tree.UserData        = struct('previousSelectedFileIndex', []);
            app.play_TreePanelVisibility.UserData = struct('Mode', 'PLAYBACK', 'Height', '0x');
            app.play_Channel_ShowPlot.UserData = false;
            
            app.axesTool_Pan.UserData         = false;
            app.axesTool_DataTip.UserData     = false;
            app.axesTool_MinHold.UserData     = struct('Value', false, 'ImageSource', {{'MinHold_32Filled.png', 'MinHold_32.png'}});
            app.axesTool_Average.UserData     = struct('Value', false, 'ImageSource', {{'Average_32Filled.png', 'Average_32.png'}});
            app.axesTool_MaxHold.UserData     = struct('Value', false, 'ImageSource', {{'MaxHold_32Filled.png', 'MaxHold_32.png'}});
            app.axesTool_Persistance.UserData = struct('Value', false);
            app.axesTool_Occupancy.UserData   = struct('Value', false);
            app.axesTool_Waterfall.UserData   = struct('Value', false);

            app.play_PlotPanel.UserData   = [];

            % Painel "PLAYBACK > ASPECTOS GERAIS"
            if ismember(num2str(app.General.Integration.Trace), app.play_TraceIntegration.Items)
                app.play_TraceIntegration.Value = num2str(app.General.Integration.Trace);
            else
                app.General.Integration.Trace = Inf;
            end

            % Painel "PLAYBACK > ASPECTOS GERAIS > PERSISTÊNCIA"
            app.play_Persistance_Interpolation.Value = app.General.Plot.Persistance.Interpolation;
            app.play_Persistance_WindowSize.Value    = app.General.Plot.Persistance.WindowSize;
            app.play_Persistance_Colormap.Value      = app.General.Plot.Persistance.Colormap;
            app.play_Persistance_cLim1.Value         = app.General.Plot.Persistance.LevelLimits(1);
            app.play_Persistance_cLim2.Value         = app.General.Plot.Persistance.LevelLimits(2);
            play_ControlsPanelSelectionChanged(app)

            % Painel "PLAYBACK > ASPECTOS GERAIS > WATERFALL"
            app.play_Waterfall_Fcn.Value             = app.General.Plot.Waterfall.Fcn;
            app.play_Waterfall_Colorbar.Value        = app.General.Plot.Waterfall.Colorbar;
            app.play_Waterfall_Decimation.Value      = app.General.Plot.Waterfall.Decimation;
            app.play_Waterfall_MeshStyle.Value       = app.General.Plot.Waterfall.MeshStyle;
            app.play_Waterfall_Timeline.Value        = app.General.Plot.WaterfallTime.Visible;
            app.play_Waterfall_Colormap.Value        = app.General.Plot.Waterfall.Colormap;
            app.play_Waterfall_cLim1.Value           = app.General.Plot.Waterfall.LevelLimits(1);
            app.play_Waterfall_cLim2.Value           = app.General.Plot.Waterfall.LevelLimits(2);

            % Painel "PLAYBACK > CANAIS"
            channelList = {};
            for ii = 1:numel(app.channelObj.Channel)
                channelList{end+1} = sprintf('%d: %.3f - %.3f MHz (%s)', ii, app.channelObj.Channel(ii).Band(1), ...
                                                                             app.channelObj.Channel(ii).Band(2),     ...
                                                                             app.channelObj.Channel(ii).Name);
            end
            app.play_Channel_List.Items = channelList;
            play_Channel_RadioGroupSelectionChanged(app)
            
            % Painel "PLAYBACK > EMISSÕES"
            app.play_FindPeaks_Class.Items = app.channelObj.FindPeaks.Name;
            play_FindPeaks_ClassValueChanged(app)

            play_FindPeaks_RadioGroupSelectionChanged(app)
            play_FindPeaks_AlgorithmValueChanged(app)

            % Painel "REPORT >> PROJECT"
            app.report_ThreadAlgorithms.HTMLSource = fullfile(app.rootFolder, 'Icons', 'Warning.html');
            app.report_ModelName.Items             = [{''}; app.General.Models.Name];

            % Painel "MISC >> antennaKFactor"
            measCalibration = jsondecode(fileread(fullfile(app.rootFolder, 'Settings', 'measCalibration.json')));
            app.misc_Edit_kFactor.Items = {measCalibration.Name};

            % Painel "CONFIG >> PASTAS"
            if strcmp(app.executionMode, 'webApp')
                % Webapps não suporta uigetdir, então o mapeamento das pastas
                % POST/GET deve ser feito em arquivo externo de configuração...
                app.config_Option_Folder.Enable = 0;
            else
                userPaths = appUtil.UserPaths(app.General.fileFolder.userPath);
                set(app.config_Folder_userPath, 'Items', userPaths, 'Value', userPaths{end})
                app.General.fileFolder.userPath = userPaths{end};
            end

            pythonPath = app.General.fileFolder.pythonPath;
            if isfile(pythonPath)
                pyenv('Version', pythonPath);
                app.config_Folder_pythonPath.Value = pythonPath;
            else
                pyEnv = pyenv;
                app.config_Folder_pythonPath.Value = pyEnv.Executable;
            end

            % Painel "CONFIG >> FISCALIZA" com impacto em "REPORT >> FISCALIZA"
            switch app.General.fiscaliza.systemVersion
                case 'PROD'
                    app.config_FiscalizaPD.Value = 1;
                case 'HOM'
                    app.config_FiscalizaHM.Value = 1;
            end
            fiscalizaLibConnection.config_SystemMode(app)
            fiscalizaLibConnection.config_DefaultValues(app)
        end

        %-----------------------------------------------------------------%
        function startup_Axes(app)
            % Axes creation:
            hParent     = tiledlayout(app.play_PlotPanel, 4, 1, "Padding", "compact", "TileSpacing", "compact");
            app.UIAxes1 = plot.axes.Creation(hParent, 'Cartesian', {'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [2 1];
            
            app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'YScale', 'log'});
            app.UIAxes2.Layout.Tile = 3;
            
            app.UIAxes3 = plot.axes.Creation(hParent, 'Cartesian', {'Layer', 'top', 'Box', 'on', 'XGrid', 'off', 'XMinorGrid', 'off', 'YGrid', 'off', 'YMinorGrid', 'off', 'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes3.Layout.Tile = 4;            

            % Axes colormap:
            plot.axes.Colormap(app.UIAxes1, app.play_Persistance_Colormap.Value)
            plot.axes.Colormap(app.UIAxes3, app.play_Waterfall_Colormap.Value)

            % Axes colorbar:
            play_Waterfall_ColorbarValueChanged(app)

            % Axes fixed labels:
            ylabel(app.UIAxes1, 'Nível (dB)')
            ysecondarylabel(app.UIAxes1, Visible='on')

            ylabel(app.UIAxes2, 'Ocupação (%)')
            xlabel(app.UIAxes3, 'Frequência (MHz)')
            ylabel(app.UIAxes3, 'Instante')
            plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_Occupancy.UserData.Value, app.axesTool_Waterfall.UserData.Value)
            plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_Occupancy.UserData.Value, app.axesTool_Waterfall.UserData.Value, app.play_LayoutRatio)

            % Axes listeners:
            linkaxes([app.UIAxes1, app.UIAxes2, app.UIAxes3], 'x')
            addlistener(app.UIAxes1, 'XLim', 'PostSet', @app.plot_AxesLimitsChanged);
            addlistener(app.UIAxes1, 'YLim', 'PostSet', @app.plot_AxesLimitsChanged);

            % Axes interactions:
            plot.axes.Interactivity.DefaultCreation([app.UIAxes1, app.UIAxes2, app.UIAxes3], [dataTipInteraction, regionZoomInteraction])

            try
                eval(sprintf('opengl %s', app.General.openGL))
            catch
            end
        end


        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource           = ccTools.fcn.jsBackDoorHTMLSource();
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_Listener(app, evt);
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Listener(app, event)
            switch event.HTMLEventName
                case 'credentialDialog'
                    fiscalizaLibConnection.report_Connect(app, event.HTMLEventData, 'OpenConnection')
                case 'BackgroundColorTurnedInvisible'
                    eval(sprintf('delete(%s)', event.HTMLEventData))
                case 'app.file_Tree'
                    file_ContextMenu_delTree1NodeSelected(app)
                case 'app.file_FilteringTree'
                    file_ContextMenu_delTree2NodeSelected(app)
                case 'app.play_Channel_Tree'
                    play_Channel_ContextMenu_delChannelSelected(app)
                case 'app.play_BandLimits_Tree'
                    play_BandLimits_ContextMenu_delSelected(app)
                case 'app.play_FindPeaks_Tree'
                    play_FindPeaks_delEmission(app)
                case 'app.report_Tree'
                    report_ContextMenu_delSelected(app)
            end
            drawnow
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            % O menu gráfico controla, programaticamente, qual das abas de
            % app.TabGroup estará visível. 

            % Lembrando que o MATLAB renderiza em tela apenas as abas visíveis.
            % Por isso as customizações de abas e suas subabas somente é possível 
            % após a renderização da aba.

            switch tabIndex
                case 0 % STARTUP
                    app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        'body',                           ...
                                                                                           'classAttributes', ['--tabButton-border-color: #fff;' ...
                                                                                                               '--tabContainer-border-color: #fff;']));

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                           'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                               '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                               '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);'        ...
                                                                                                               '--mw-backgroundColor-tab: #fff;']));

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                           'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

                otherwise
                    if any(app.jsBackDoorFlag{tabIndex})
                        app.jsBackDoorFlag{tabIndex} = false;

                        switch tabIndex
                            case 1 % FILE            
                                app.file_Tree.UserData          = struct(app.file_Tree).Controller.ViewModel.Id;
                                app.file_FilteringTree.UserData = struct(app.file_FilteringTree).Controller.ViewModel.Id;
                                sendEventToHTMLSource(app.jsBackDoor, 'addKeyDownListener', struct('componentName', 'app.file_Tree',          'componentDataTag', app.file_Tree.UserData,          'keyEvents', "Delete"))
                                sendEventToHTMLSource(app.jsBackDoor, 'addKeyDownListener', struct('componentName', 'app.file_FilteringTree', 'componentDataTag', app.file_FilteringTree.UserData, 'keyEvents', "Delete"))
            
                            case 2 % PLAYBACK+REPORT+MISC
                                ccTools.compCustomizationV2(app.jsBackDoor, app.play_axesToolbar, 'borderBottomLeftRadius', '5px', 'borderBottomRightRadius', '5px')
        
                                app.play_Channel_Tree.UserData    = struct(app.play_Channel_Tree).Controller.ViewModel.Id;
                                app.play_BandLimits_Tree.UserData = struct(app.play_BandLimits_Tree).Controller.ViewModel.Id;
                                app.play_FindPeaks_Tree.UserData  = struct(app.play_FindPeaks_Tree).Controller.ViewModel.Id;
                                app.report_Tree.UserData          = struct(app.report_Tree).Controller.ViewModel.Id;
                                sendEventToHTMLSource(app.jsBackDoor, 'addKeyDownListener', struct('componentName', 'app.play_Channel_Tree',    'componentDataTag', app.play_Channel_Tree.UserData,    'keyEvents', "Delete"))
                                sendEventToHTMLSource(app.jsBackDoor, 'addKeyDownListener', struct('componentName', 'app.play_BandLimits_Tree', 'componentDataTag', app.play_BandLimits_Tree.UserData, 'keyEvents', "Delete"))
                                sendEventToHTMLSource(app.jsBackDoor, 'addKeyDownListener', struct('componentName', 'app.play_FindPeaks_Tree',  'componentDataTag', app.play_FindPeaks_Tree.UserData,  'keyEvents', "Delete"))
                                sendEventToHTMLSource(app.jsBackDoor, 'addKeyDownListener', struct('componentName', 'app.report_Tree',          'componentDataTag', app.report_Tree.UserData,          'keyEvents', "Delete"))
            
                            case 3 % CONFIG
                                % ...
                        end
                    end
            end
        end


        %-----------------------------------------------------------------%
        % CUSTOMIZAÇÃO DECORRENTE DA TROCA DO MODO DE OPERAÇÃO
        %-----------------------------------------------------------------%
        function menu_LayoutControl(app, tabIndex)
            switch tabIndex
                case 1
                    % ...
                case 2
                    if app.menu_Button2.Value
                        app.play_Tree.SelectedNodes       = app.play_PlotPanel.UserData;                            
                        app.play_Tree.Multiselect         = "off";

                        app.play_TreePanelVisibility.Visible       = 1;
                        app.play_TreePanelVisibility.UserData.Mode = 'PLAYBACK';

                        app.play_ControlsGrid.RowHeight   = {22,'1x',22,0,22,0,0,0,0,0,0,0};
                        set(findobj(app.play_toolGrid, 'Tag', 'REPORT'),    Visible=0)
                        set(findobj(app.play_toolGrid, 'Tag', 'FISCALIZA'), Visible=0)
                        set(findobj(app.play_toolGrid, 'Tag', 'MISC'),      Visible=0)

                    elseif app.menu_Button3.Value
                        app.play_Tree.Multiselect         = "on";
                        
                        app.play_TreePanelVisibility.Visible       = 0;
                        app.play_TreePanelVisibility.UserData.Mode = 'REPORT';

                        app.play_ControlsGrid.RowHeight   = {0,0,0,0,0,0,22,'1x',22,0,0,0};
                        set(findobj(app.play_toolGrid, 'Tag', 'REPORT'),    Visible=1)
                        set(findobj(app.play_toolGrid, 'Tag', 'FISCALIZA'), Visible=1, Enable=0)
                        set(findobj(app.play_toolGrid, 'Tag', 'MISC'),      Visible=0)

                    elseif app.menu_Button4.Value
                        app.play_Tree.Multiselect         = "on";
                        
                        switch app.play_TreePanelVisibility.UserData.Mode
                            case 'PLAYBACK'
                                app.play_TreePanelVisibility.Visible = 1;
                            case 'REPORT'
                                app.play_TreePanelVisibility.Visible = 0;
                        end
                        
                        app.play_ControlsGrid.RowHeight   = {0,0,0,0,0,0,0,0,0,0,22,'1x'};
                        set(findobj(app.play_toolGrid, 'Tag', 'REPORT'),    Visible=0)
                        set(findobj(app.play_toolGrid, 'Tag', 'FISCALIZA'), Visible=0)
                        set(findobj(app.play_toolGrid, 'Tag', 'MISC'),      Visible=1, Enable=0)
                    end

                    play_TreeSecundaryPanelVisibility(app)
                    play_TreeSelectionChanged(app, struct('Source', 'menu_LayoutControl'))

                case 3
                    % ...
            end
        end


        %-----------------------------------------------------------------%
        % APAGA COISAS...
        %-----------------------------------------------------------------%
        function DeleteAll(app)
            DeleteProject(app)
            DeleteFigures(app)

            file_DataReaderError(app)
            file_specReadButtonVisibility(app)
        end

        %-----------------------------------------------------------------%
        function DeleteProject(app)
            % Ajusta specData, além de reiniciar variáveis.
            idx = find(arrayfun(@(x) x.UserData.reportFlag, app.specData));
            for ii = idx
                app.specData(ii).UserData.reportFlag = false;
            end

            % Apaga a árvore de fluxos a processar, além de retirar o ícone
            % do relatório na árvore principal de fluxos.
            report_TreeBuilding(app)

            % Reinicia abas FISCALIZAÇÃO e API FISCALIZA.
            Restart(app.projectData)
            app.report_ProjectName.Value = '';
            app.report_Issue.Value       = -1;       
            app.report_ProjectWarnIcon.Visible = 0;

            fiscalizaLibConnection.report_ResetGUI(app)

            % Fecha o módulo externo winSignalAnalysis, caso aberto, por se
            % tratar de um módulo que possibilita edição de exceptionList, 
            % que acabou de ser reinicializada.
            if ~isempty(app.auxiliarWin1)
                delete(app.auxiliarWin1)
                app.auxiliarWin1 = [];
            end
        end

        %-----------------------------------------------------------------%
        function DeleteFigures(app)
            for ii = 1:3
                eval(sprintf('delete(app.auxiliarWin%d)', ii))
                eval(sprintf('app.auxiliarWin%d = [];', ii))
            end

            DeleteTempFigures(app, 'all')
        end

        %-----------------------------------------------------------------%
        function DeleteTempFigures(app, FigureVisibility)
            switch FigureVisibility
                case 'all'; h = findall(groot, 'Type', 'Figure', 'Tag', 'ReportGenerator');
                otherwise;  h = findall(groot, 'Type', 'Figure', 'Tag', 'ReportGenerator', 'Visible', 0);
            end

            if ~isempty(h)
                delete(h)
            end
        end
        

        %-----------------------------------------------------------------%
        % ## Modo "ARQUIVO(S)" ##
        %-----------------------------------------------------------------%
        function file_TreeBuilding(app)
            if ~isempty(app.file_Tree.Children)
                delete(app.file_Tree.Children)
                app.file_Tree.UserData.previousSelectedFileIndex = [];
            end

            if ~isempty(app.metaData)
                file_FilterOptions(app)
                file_FilterCheck(app)

                filteredNodes = [];

                for ii = 1:numel(app.metaData)
                    [~, fileName, fileExt] = fileparts(app.metaData(ii).File);
                    
                    fileNode = uitreenode(app.file_Tree, 'Text',        [fileName fileExt],                         ...
                                                         'NodeData',    struct('level', 1, 'idx1', ii, 'idx2', []), ...
                                                         'ContextMenu', app.file_ContextMenu_Tree1);

                    receiverRawList = {app.metaData(ii).Data.Receiver};
                    [receiverList, ~, receiverIndex] = unique(receiverRawList);

                    if isscalar(receiverList) && isscalar(app.metaData(ii).Data)
                        fileNode.NodeData.idx2 = 1;
                    end
                    
                    for jj = 1:numel(receiverList)
                        idx = find(receiverIndex == jj)';

                        receiverNode = uitreenode(fileNode, 'Text',        fcn.treeReceiverName(receiverList{jj}, 'file_TreeBuilding'), ...
                                                            'NodeData',    struct('level', 2, 'idx1', ii, 'idx2', idx),                 ...
                                                            'Icon',        fcn.treeNodeIcon('Receiver', receiverList{jj}),              ...
                                                            'ContextMenu', app.file_ContextMenu_Tree1);                        
                        for kk = idx
                            if ismember(app.metaData(ii).Data(kk).MetaData.DataType, class.Constants.specDataTypes)
                                nodeTextNote = '';
                                nodeIcon     = fcn.treeNodeIcon('DataType', 'SpectralData');

                            elseif ismember(app.metaData(ii).Data(kk).MetaData.DataType, class.Constants.occDataTypes)
                                nodeTextNote = ' (Ocupação)';
                                nodeIcon     = fcn.treeNodeIcon('DataType', 'Occupancy');

                            else
                                error('winAppAnalise:UnexpectedDataType', 'Unexpeted data type %d', app.metaData(ii).Data(kk).MetaData.DataType)
                            end

                            dataNode = uitreenode(receiverNode, 'Text',        sprintf('ID %d: %.3f - %.3f MHz%s', app.metaData(ii).Data(kk).RelatedFiles.ID(1),                       ...
                                                                                                                   app.metaData(ii).Data(kk).MetaData.FreqStart .* 1e-6,               ...
                                                                                                                   app.metaData(ii).Data(kk).MetaData.FreqStop .* 1e-6, nodeTextNote), ...
                                                                'NodeData',    struct('level', 3, 'idx1', ii, 'idx2', kk),                                                             ...
                                                                'Icon',        nodeIcon,                                                                                               ...
                                                                'ContextMenu', app.file_ContextMenu_Tree1);
                            if ~app.metaData(ii).Data(kk).Enable
                                dataNode.Icon = '';
                                filteredNodes = [filteredNodes, dataNode];
                            end
                        end
                    end
                end

                if ~isempty(filteredNodes)
                    addStyle(app.file_Tree, uistyle('FontColor', [.65,.65,.65]), 'node', filteredNodes)
                end

                app.file_Tree.SelectedNodes = app.file_Tree.Children(1);
                file_TreeSelectionChanged(app)

            else
                app.file_Metadata.HTMLSource              = ' ';
                app.file_FilteringType1_Frequency.Items   = {};
                app.file_FilteringType2_ID.Items          = {};
                app.file_FilteringType3_Description.Value = '';
                
            end
            file_specReadButtonVisibility(app)
        end

        %-----------------------------------------------------------------%
        function file_FilterOptions(app)
            bandList = table('Size', [0,3], ...
                             'VariableTypes', {'double', 'double', 'string'}, ...
                             'VariableNames', {'FreqStart', 'FreqStop', 'Band'});
            IDList   = [];
            for ii = 1:numel(app.metaData)
                for jj = 1:numel(app.metaData(ii).Data)

                    FreqStart = app.metaData(ii).Data(jj).MetaData.FreqStart;
                    FreqStop  = app.metaData(ii).Data(jj).MetaData.FreqStop;

                    bandList(end+1,:) = {FreqStart, FreqStop, sprintf('%.3f - %.3f MHz', FreqStart/1e+6, FreqStop/1e+6)};
                    IDList(end+1,1)   = app.metaData(ii).Data(jj).RelatedFiles.ID(1);
                end
            end
            bandList = sortrows(bandList, {'FreqStart', 'FreqStop'});

            app.file_FilteringType1_Frequency.Items = unique(bandList.Band, "rows", "stable");
            app.file_FilteringType2_ID.Items        = unique(string(sort(IDList)), "rows", "stable");
        end

        %-----------------------------------------------------------------%
        function file_FilterCheck(app)
            hFilter = allchild(app.file_FilteringTree);

            if isempty(hFilter)
                for ii = 1:numel(app.metaData)
                    for jj = 1:numel(app.metaData(ii).Data)
                        app.metaData(ii).Data(jj).Enable = true;
                    end
                end

            else
                filterTextList = strjoin({hFilter.Text}, '\n');                
                filterParser   = struct2table(regexp(filterTextList, '(?<Type>(FREQUÊNCIA|ID|DESCRIÇÃO))[:] (?<Sentence>.*)', 'names', 'dotexceptnewline'));
                if ~iscell(filterParser.Sentence)
                    filterParser.Sentence = {filterParser.Sentence};
                end

                filterSentence_Frequency   = filterParser.Sentence(filterParser.Type == "FREQUÊNCIA");
                filterSentence_ID          = str2double(filterParser.Sentence(filterParser.Type == "ID"));
                filterSentence_Description = filterParser.Sentence(filterParser.Type == "DESCRIÇÃO");

                for ii = 1:numel(app.metaData)
                    for jj = 1:numel(app.metaData(ii).Data)
                        app.metaData(ii).Data(jj).Enable = false;

                        % FREQUÊNCIA
                        if ~isempty(filterSentence_Frequency)
                            dataSentence = sprintf('%.3f - %.3f MHz', app.metaData(ii).Data(jj).MetaData.FreqStart .* 1e-6, ...
                                                                      app.metaData(ii).Data(jj).MetaData.FreqStop  .* 1e-6);
                            if ismember(dataSentence, filterSentence_Frequency)
                                app.metaData(ii).Data(jj).Enable = true;
                                continue
                            end
                        end

                        % ID
                        if ~isempty(filterSentence_ID)
                            dataSentence = app.metaData(ii).Data(jj).RelatedFiles.ID(1);

                            if ismember(dataSentence, filterSentence_ID)
                                app.metaData(ii).Data(jj).Enable = true;
                                continue
                            end
                        end

                        % DESCRIÇÃO
                        if ~isempty(filterSentence_Description)
                            dataSentence = app.metaData(ii).Data(jj).RelatedFiles.Description{1};

                            if any(cellfun(@(x) contains(dataSentence, x, "IgnoreCase", true), replace(filterSentence_Description, '"', '')))
                                app.metaData(ii).Data(jj).Enable = true;
                                continue
                            end
                        end
                    end
                end
            end
        end

        %-----------------------------------------------------------------%
        function file_DataReaderError(app)
            if ~isempty(app.specData)
                delete(app.specData)
                app.specData = class.specData.empty;
            end

            set(findobj(app.menu_Grid, 'Type', 'uistatebutton'), 'Enable', 0)
            set(app.menu_Button1, 'Value', 1, 'Enable', 1)
            menu_mainButtonPushed(app, struct('Source', app.menu_Button1, 'PreviousValue', false)) 
        end

        %-----------------------------------------------------------------%
        function file_specReadButtonVisibility(app)
            if ~isempty(app.metaData)
                app.file_SpecReadButton.Visible = 1;
            else
                app.file_SpecReadButton.Visible = 0;
            end
        end


        %-----------------------------------------------------------------%
        % PLAYBACK >> ÁRVORE PRINCIPAL
        %-----------------------------------------------------------------%
        function play_TreeBuilding(app)
            if ~isempty(app.play_Tree.Children)
                delete(app.play_Tree.Children)
            end
            app.play_Metadata.HTMLSource = ' ';

            receiverRawList = {app.specData.Receiver};
            [receiverList, ~, receiverIndex] = unique(receiverRawList);

            for ii = 1:numel(receiverList)
                idx1 = find(receiverIndex == ii)';

                receiverNode = uitreenode(app.play_Tree, 'Text',     fcn.treeReceiverName(receiverList{ii}, 'play_TreeBuilding'), ...
                                                         'NodeData', idx1,                                                        ...
                                                         'Icon',     fcn.treeNodeIcon('Receiver', receiverList{ii}));
                                
                for jj = idx1
                    specNodeText = misc_nodeTreeText(app, jj);
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.occDataTypes)
                        specNodeText = [specNodeText ' (Ocupação)'];
                    end

                    specNode = uitreenode(receiverNode, 'Text', specNodeText, ...
                                                        'NodeData', jj);

                    % TEMPO DE OBSERVAÇÃO
                    uitreenode(specNode, 'Text', sprintf('%s - %s', datestr(app.specData(jj).Data{1}(1),   'dd/mm/yyyy HH:MM:SS'), ...
                                                                    datestr(app.specData(jj).Data{1}(end), 'dd/mm/yyyy HH:MM:SS')), ...
                                         'NodeData', jj);
                        
                    % GPS
                    if ~app.specData(jj).GPS.Status
                        uitreenode(specNode, 'Text',     'GPS', ...
                                             'NodeData', jj,    ...
                                             'Icon',     'Warn_18.png');
                    else
                        gpsNode = uitreenode(specNode, 'Text', sprintf('%.6f, %.6f (%s)', app.specData(jj).GPS.Latitude,  ...
                                                                                          app.specData(jj).GPS.Longitude, ...
                                                                                          app.specData(jj).GPS.Location), ...
                                                       'NodeData', jj);
                        if app.specData(jj).GPS.Status == -1
                            gpsNode.Icon = 'modeManual_32.png';
                        end
                    end

                    % OCC
                    if ismember(app.specData(jj).MetaData.DataType, class.Constants.specDataTypes)
                        if ~isempty(app.specData(jj).UserData.occMethod.RelatedIndex)
                            occNode = uitreenode(specNode, 'Text', 'Fluxo(s) de ocupação', ...
                                                           'NodeData', jj);
                            
                            idx2 = app.specData(jj).UserData.occMethod.RelatedIndex;
                            for kk = idx2
                                occChildThreadNode = uitreenode(occNode, 'Text', sprintf('Threshold: %d %s (ID %d)', app.specData(kk).MetaData.Threshold,  ...
                                                                                                                     app.specData(kk).MetaData.LevelUnit,  ...
                                                                                                                     app.specData(kk).RelatedFiles.ID(1)), ...
                                                                         'NodeData', jj);

                                if app.specData(jj).UserData.occMethod.SelectedIndex == kk
                                    occChildThreadNode.Icon = 'modeSelected_32.png';
                                end                     
                            end

                            occChildPlaybackNode = uitreenode(occNode, 'Text', 'Ocupação a ser aferida', 'NodeData', jj);
                            if isempty(app.specData(jj).UserData.occMethod.SelectedIndex)
                                occChildPlaybackNode.Icon = 'modeSelected_32.png';
                            end
                        end
                    end
                end
            end
            play_TreeNodeStyle(app)

            for ii = 1:numel(app.play_Tree.Children)
                expand(app.play_Tree.Children(ii));
            end
        end

        %-----------------------------------------------------------------%
        function play_TreeNodeStyle(app)
            % specIndex..: índices dos fluxos de dados de espectro.
            % occIndex...: índices dos fluxos de dados de ocupação.
            % reportIndex: subconjunto de specIndex, representando os índices dos 
            %              fluxos de dados que serão analisados no modo "RELATÓRIO".
        
            removeStyle(app.play_Tree)
        
            hTree1 = allchild(app.play_Tree);
            hTree2 = [];
            for ii = 1:numel(hTree1)
                hTree2 = [hTree2; allchild(hTree1(ii))];
            end
            refTreeIndex = [hTree2.NodeData];
        
            specIndex    = find(arrayfun(@(x) ismember(x.MetaData.DataType, class.Constants.specDataTypes), app.specData));
            reportIndex  = find(arrayfun(@(x) x.UserData.reportFlag, app.specData));
            occIndex     = setdiff(1:numel(app.specData), specIndex);

            [~, specTreeIndex]   = ismember(setdiff(specIndex, reportIndex), refTreeIndex);
            [~, reportTreeIndex] = ismember(reportIndex, refTreeIndex);
            [~, occTreeIndex]    = ismember(occIndex, refTreeIndex);

        
            set(hTree2(specTreeIndex),   Icon=fcn.treeNodeIcon('DataType', 'SpectralData'))
            set(hTree2(reportTreeIndex), Icon='Report_32.png')
            set(hTree2(occTreeIndex),    Icon=fcn.treeNodeIcon('DataType', 'Occupancy'))
        end

        %-----------------------------------------------------------------%
        function play_TreeRebuilding(app, SelectedNodesTextList, hComp)
            % Evidencia células que tiveram os seus valores editados...
            set(hComp, BackgroundColor='#bfbfbf')
            drawnow
        
            % Desenha novamente a árvore, deixando selecionados os mesmos fluxos espectrais
            % que estavam selecionados quando da edição das suas informações de GPS.
            play_TreeBuilding(app)
            drawnow

            hTreeNodes     = findobj(app.play_Tree, '-not', 'Type', 'uitree');
            hTreeNodeText  = arrayfun(@(x) x.Text, hTreeNodes, "UniformOutput", false);
            hTreeNodeIndex = [];
            for ii = 1:numel(SelectedNodesTextList)
                hTreeNodeIndex = [hTreeNodeIndex, find(strcmp(hTreeNodeText, SelectedNodesTextList{ii}), 1)];
            end
            
            if isempty(hTreeNodeIndex)
                app.play_Tree.SelectedNodes = app.play_Tree.Children(1).Children(1);
            else
                app.play_Tree.SelectedNodes = hTreeNodes(hTreeNodeIndex);
            end
            report_TreeBuilding(app)
        
            % Retira evidência às supracitadas células.
            set(hComp, BackgroundColor='white') 
        end

        %-----------------------------------------------------------------%
        function play_TreeSecundaryPanelVisibility(app)
            switch app.play_TreePanelVisibility.UserData.Mode
                case 'PLAYBACK'
                    switch app.play_TreePanelVisibility.UserData.Height
                        case '0x'
                            app.play_TreeGrid.RowHeight(4:9) = {0,0,0,0,0,0};
                        otherwise
                            app.play_TreeGrid.RowHeight(4:9)  = {22,app.play_TreePanelVisibility.UserData.Height,22,0,0,0};
                    end
                case 'REPORT'
                    app.play_TreeGrid.RowHeight(4:9)  = {0,0,0,22,'1x',22};
            end
        end

        %-----------------------------------------------------------------%
        function play_TreeCollapseStyle(app)
            collapse(app.play_Tree, 'all')
            for ii = 1:numel(app.play_Tree.Children)
                expand(app.play_Tree.Children(ii))
            end

            hUnderFocus = [];
            generation = misc_findGenerationOfTreeNode(app, app.play_Tree.SelectedNodes(1));
            
            for ii = 1:numel(app.play_Tree.SelectedNodes)
                hComponent = app.play_Tree.SelectedNodes(ii);
                if isempty(hUnderFocus)
                    hUnderFocus = hComponent;
                end

                switch generation
                    case 2
                        hComponent = hComponent.Parent;
                    case 3
                        hComponent = hComponent.Parent.Parent;
                end
                
                expand(hComponent, 'all')
            end

            scroll(app.play_Tree, hUnderFocus)         
        end


        %-----------------------------------------------------------------%
        % PLAYBACK >> ASPECTOS GERAIS >> OCUPAÇÃO
        %-----------------------------------------------------------------%
        function occInfo = play_OCCInfo(app)
            occInfo = struct('Method',                  app.play_OCC_Method.Value,                      ...
                             'IntegrationTime',         str2double(app.play_OCC_IntegrationTime.Value), ...
                             'IntegrationTimeCaptured', app.play_OCC_IntegrationTimeCaptured.Value,     ...
                             'THR',                     app.play_OCC_THR.Value,                         ...
                             'THRCaptured',             str2double(app.play_OCC_THRCaptured.Value),     ...
                             'Offset',                  app.play_OCC_Offset.Value,                      ...
                             'ceilFactor',              app.play_OCC_ceilFactor.Value,                  ...
                             'noiseFcn',                app.play_OCC_noiseFcn.Value,                    ...
                             'noiseTrashSamples',       app.play_OCC_noiseTrashSamples.Value/100,       ...
                             'noiseUsefulSamples',      app.play_OCC_noiseUsefulSamples.Value/100);

            switch occInfo.Method
                case 'Linear fixo (COLETA)'; occInfo = rmfield(occInfo, {'IntegrationTime',         'THR',         'Offset', 'ceilFactor', 'noiseFcn', 'noiseTrashSamples', 'noiseUsefulSamples'});
                case 'Linear fixo';          occInfo = rmfield(occInfo, {'IntegrationTimeCaptured', 'THRCaptured', 'Offset', 'ceilFactor', 'noiseFcn', 'noiseTrashSamples', 'noiseUsefulSamples'});
                case 'Linear adaptativo';    occInfo = rmfield(occInfo, {'IntegrationTimeCaptured', 'THR', 'THRCaptured', 'ceilFactor'});
                case 'Envoltória do ruído';  occInfo = rmfield(occInfo, {'IntegrationTimeCaptured', 'THR', 'THRCaptured'});
            end
        end

        %-----------------------------------------------------------------%
        function occIndex = play_OCCIndex(app, idx, srcFcn)
            switch srcFcn
                case 'PLAYBACK/REPORT'
                    if isempty(app.specData(idx).UserData.reportOCC)
                        occInfo = play_OCCInfo(app);
                    else
                        occInfo = app.specData(idx).UserData.reportOCC;
                    end
                    play_OCCLayoutStartup(app, idx)

                case 'PLAYBACK'
                    occInfo = play_OCCInfo(app);

                case 'REPORT'
                    occInfo = app.specData(idx).UserData.reportOCC;
            end
            
            occIndex = find(cellfun(@(x) isequal(x, occInfo), {app.specData(idx).UserData.occCache.Info}));
            
            if isempty(occIndex)
                occIndex = numel(app.specData(idx).UserData.occCache)+1;
                occTHR   = class.OCC.Threshold(app.specData(idx), occInfo);

                switch occInfo.Method
                    case 'Linear fixo (COLETA)'
                        occData = app.specData(app.specData(idx).UserData.occMethod.SelectedIndex).Data;

                    otherwise
                        app.specData(idx).UserData.occMethod.SelectedIndex = [];
                        occData = class.OCC.Analysis(app.specData(idx), occInfo, occTHR);
                end

                app.specData(idx).UserData.occCache(occIndex) = struct('Info',  occInfo, ...
                                                                       'THR',   occTHR,  ...
                                                                       'Data', {occData});
            end

            app.specData(idx).UserData.occMethod.CacheIndex   = occIndex;
        end

        %-----------------------------------------------------------------%
        function selectedTreeNode = play_OCCSelectedTreeNode(app, idx)
            selectedTreeNode = [];
            for ii = 1:numel(app.play_Tree.Children)
                for jj = 1:numel(app.play_Tree.Children(ii).Children)
                    if app.play_Tree.Children(ii).Children(jj).NodeData == idx
                        selectedTreeNode = app.play_Tree.Children(ii).Children(jj);
                        break
                    end
                end
            end
        end

        %-----------------------------------------------------------------%
        function play_OCCSelectedTreeNodeIconUpdate(app, idx1)

            selectedTreeNode = play_OCCSelectedTreeNode(app, idx1);

            switch app.play_OCC_Method.Value
                case 'Linear fixo (COLETA)'
                    idx2 = find(strcmp(app.play_OCC_THRCaptured.Items, app.play_OCC_THRCaptured.Value), 1);
                    if isempty(app.specData(idx1).UserData.occMethod.SelectedIndex) || ...
                            (app.specData(idx1).UserData.occMethod.SelectedIndex ~= app.specData(idx1).UserData.occMethod.RelatedIndex(idx2))
                        app.specData(idx1).UserData.occMethod.SelectedIndex = app.specData(idx1).UserData.occMethod.RelatedIndex(idx2);
                    end                    

                otherwise
                    if ~isempty(app.specData(idx1).UserData.occMethod.RelatedIndex)
                        app.specData(idx1).UserData.occMethod.SelectedIndex = [];
                        idx2 = numel(selectedTreeNode.Children(end).Children);
                    end
            end
   
            if exist('idx2', 'var')
                set(selectedTreeNode.Children(end).Children, Icon='')
                selectedTreeNode.Children(end).Children(idx2).Icon = 'modeSelected_32.png';
            end
        end

        %-----------------------------------------------------------------%
        function play_OCCNewPlot(app)
            if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                idx = app.play_PlotPanel.UserData.NodeData;
    
                % Layout
                play_OCCSelectedTreeNodeIconUpdate(app, idx)
                if strcmp(app.play_OCC_Method.Value, 'Linear fixo (COLETA)')
                    idxOCC = app.specData(idx).UserData.occMethod.SelectedIndex;
    
                    app.play_OCC_THRCaptured.Value = num2str(app.specData(idxOCC).MetaData.Threshold);
                    app.play_OCC_IntegrationTimeCaptured.Value = mean(app.specData(idxOCC).RelatedFiles.RevisitTime)/60;
                end
                play_OCCLayoutVisibility(app, app.bandObj.LevelUnit)
                
                % Ao trocar qualquer um dos parâmetros relacionados aos métodos
                % de ocupação embarcados no appAnalise, afere-se, novamente, a
                % ocupação por bin. Isso, contudo, somente ocorre se estiver
                % habilitado o botão de ocupação, deixando visível o resultado
                % no app.axes2.
    
                % Ao inserir um fluxo de espectro para compor o relatório, será
                % definido um método de aferição de ocupação, caso ainda não
                % feita nenhuma simulação. Neste caso, a alteração dos parâmetros 
                % também irão refletir no algoritmo que será aplicado para fins
                % de geração do relatório.
    
                if app.axesTool_Occupancy.UserData.Value
                    % Aferição da ocupação (armazenada no campo "occCache" da 
                    % propriedade "UserData" de app.specData).
                    occIndex = play_OCCIndex(app, idx, 'PLAYBACK');
    
                    % Avalia se o fluxo que está sendo alterado foi incluído no
                    % modo relatório e este é exatamente o fluxo selecionado.
                    if app.specData(idx).UserData.reportFlag
                        app.specData(idx).UserData.reportOCC = app.specData(idx).UserData.occCache(occIndex).Info;
    
                        idxOCC = [];
                        if ~isempty(app.play_Tree.SelectedNodes)
                            idxOCC = unique([app.play_Tree.SelectedNodes.NodeData]);
                        end
        
                        report_Algorithms(app, idxOCC)
                    end
        
                    % Plot                    
                    plot.old_OCC(app, idx, 'Creation', occIndex)
                end
            end
        end

        %-----------------------------------------------------------------%
        function play_OCCLayoutStartup(app, idx)        
            % Ajuste dos itens de app.play_OCC_Method, o qual depende da existência
            % de fluxos de ocupação relacionados aos fluxos de espectro. Lembrando
            % que atualmente os fluxos de ocupação são aqueles gerados pelo Logger.
            if isempty(app.specData(idx).UserData.occMethod.RelatedIndex)
                app.play_OCC_Method.Items      = {'Linear fixo', 'Linear adaptativo', 'Envoltória do ruído'};
                app.play_OCC_THRCaptured.Items = {};        
            else
                app.play_OCC_Method.Items      = {'Linear fixo (COLETA)', 'Linear fixo', 'Linear adaptativo', 'Envoltória do ruído'};
                app.play_OCC_THRCaptured.Items = arrayfun(@(x) num2str(x.MetaData.Threshold), app.specData(app.specData(idx).UserData.occMethod.RelatedIndex), 'UniformOutput', false);
            end        
            
            % Caso não esteja habilitado o botão de ocupação, então o painel de
            % ocupação é desabilitado. Se o fluxo de espectro possui habilitado a 
            % customização do playback, então os campos de todos os principais painéis 
            % (Persistência, Ocupação e Waterfall) serão atualizados em 
            % "layoutFcn.customPlayback".
            % Caso essa funcionalidade não tenha sido habilitada, é necessária
            % atualizadar os valores do painel de Ocupação.
            if app.axesTool_Occupancy.Enable
                play_OCCLayoutUpdate(app, idx)
                play_OCCLayoutVisibility(app, app.specData(idx).MetaData.LevelUnit)        
            else
                hComponents = findobj(app.play_OCCGrid, '-not', {'Type', 'uilabel', '-or', 'Type', 'uigrid', '-or', 'Type', 'uipanel'});
                set(hComponents, Enable=0)
            end
        end
        
        %-----------------------------------------------------------------%
        function play_OCCLayoutUpdate(app, idx)        
            if isempty(app.specData(idx).UserData.occMethod.CacheIndex)
                SelectedIndex = app.specData(idx).UserData.occMethod.SelectedIndex;
                
                if ~isempty(SelectedIndex)
                    app.play_OCC_Method.Value                  = 'Linear fixo (COLETA)';
                    app.play_OCC_IntegrationTimeCaptured.Value = mean(app.specData(SelectedIndex).RelatedFiles.RevisitTime)/60;
                    app.play_OCC_THRCaptured.Value             = num2str(app.specData(SelectedIndex).MetaData.Threshold);
                end        
            else
                occIndex = app.specData(idx).UserData.occMethod.CacheIndex;
                occInfo  = app.specData(idx).UserData.occCache(occIndex).Info;
            
                app.play_OCC_Method.Value = occInfo.Method;
            
                switch occInfo.Method
                    case 'Linear fixo (COLETA)'
                        app.play_OCC_IntegrationTimeCaptured.Value = occInfo.IntegrationTimeCaptured;
                        app.play_OCC_THRCaptured.Value             = num2str(occInfo.THRCaptured);
            
                    case 'Linear fixo'
                        app.play_OCC_IntegrationTime.Value         = num2str(occInfo.IntegrationTime);
                        app.play_OCC_THR.Value                     = occInfo.THR;
            
                    otherwise % 'Linear adaptativo' | 'Envoltória do ruído'            
                        app.play_OCC_IntegrationTime.Value         = num2str(occInfo.IntegrationTime);
                        app.play_OCC_Offset.Value                  = occInfo.Offset;
                        app.play_OCC_noiseFcn.Value                = occInfo.noiseFcn;
                        app.play_OCC_noiseTrashSamples.Value       = 100 * occInfo.noiseTrashSamples;
                        app.play_OCC_noiseUsefulSamples.Value      = 100 * occInfo.noiseUsefulSamples;
            
                        if strcmp(occInfo.Method, 'Envoltória do ruído')
                            app.play_OCC_ceilFactor.Value          = occInfo.ceilFactor;
                        end
                end
            end
        end

        %-----------------------------------------------------------------%
        function play_OCCLayoutVisibility(app, LevelUnit)
            hComponents = findobj(app.play_OCCGrid, '-not', {'Type', 'uilabel', '-or', 'Type', 'uigrid', '-or', 'Type', 'uipanel'});
            set(hComponents, Enable=1)
        
            switch app.play_OCC_Method.Value
                case 'Linear fixo (COLETA)'
                    set(app.play_OCC_IntegrationTime,         Visible=0, Enable=0)
                    set(app.play_OCC_IntegrationTimeCaptured, Visible=1)
        
                    set(app.play_OCC_THRLabel,        Visible=1)
                    set(app.play_OCC_THR,             Visible=0)
                    set(app.play_OCC_THRCaptured,     Visible=1)
        
                    set(app.play_OCC_OffsetLabel,     Visible=0)
                    set(app.play_OCC_Offset,          Visible=0, Enable=0)
                    
                    set(app.play_OCC_ceilFactorLabel, Visible=0)
                    set(app.play_OCC_ceilFactor,      Visible=0, Enable=0)
        
                    set(app.play_OCC_noiseLabel,      Visible=0)
                    set(app.play_OCC_noisePanel,      Visible=0)
                    set(findobj(app.play_OCC_noiseGrid.Children, '-not', 'Type', 'uilabel'), Enable=0)
        
                    play_OCCLayoutVisibilityUpdate(app, LevelUnit)
        
                case 'Linear fixo'
                    set(app.play_OCC_IntegrationTime,         Visible=1)
                    set(app.play_OCC_IntegrationTimeCaptured, Visible=0, Enable=0)
        
                    set(app.play_OCC_THRLabel,        Visible=1)
                    set(app.play_OCC_THR,             Visible=1)
                    set(app.play_OCC_THRCaptured,     Visible=0)
        
                    set(app.play_OCC_OffsetLabel,     Visible=0)
                    set(app.play_OCC_Offset,          Visible=0, Enable=0)
                    
                    set(app.play_OCC_ceilFactorLabel, Visible=0)
                    set(app.play_OCC_ceilFactor,      Visible=0, Enable=0)
        
                    set(app.play_OCC_noiseLabel,      Visible=0)
                    set(app.play_OCC_noisePanel,      Visible=0)
                    set(findobj(app.play_OCC_noiseGrid.Children, '-not', 'Type', 'uilabel'), Enable=0)
        
                    play_OCCLayoutVisibilityUpdate(app, LevelUnit)
        
                otherwise % {'Linear adaptativo', 'Envoltória do ruído adaptativo'}
                    set(app.play_OCC_IntegrationTime,         Visible=1)
                    set(app.play_OCC_IntegrationTimeCaptured, Visible=0, Enable=0)
        
                    set(app.play_OCC_THRLabel,    Visible=0)
                    set(app.play_OCC_THR,         Visible=0, Enable=0)
                    set(app.play_OCC_THRCaptured, Visible=0, Enable=0)
                    
                    set(app.play_OCC_OffsetLabel, Visible=1)
                    set(app.play_OCC_Offset,      Visible=1)
        
                    set(app.play_OCC_noiseLabel,  Visible=1)
                    set(app.play_OCC_noisePanel,  Visible=1)
        
                    switch app.play_OCC_Method.Value
                        case'Linear adaptativo'
                            set(app.play_OCC_ceilFactorLabel, Visible=0)
                            set(app.play_OCC_ceilFactor,      Visible=0, Enable=0)
        
                        case 'Envoltória do ruído'
                            set(app.play_OCC_ceilFactorLabel, Visible=1)
                            set(app.play_OCC_ceilFactor,      Visible=1)
                    end
            end
        end        
        
        %-------------------------------------------------------------------------%
        function play_OCCLayoutVisibilityUpdate(app, LevelUnit)        
            app.play_OCC_THRLabel.Text = sprintf('Valor (%s):', LevelUnit);
        
            switch LevelUnit
                case 'dBm'
                    if app.play_OCC_THR.Value > 0                            
                        app.play_OCC_THR.Value = -80;
                    end        
                case 'dBµV'
                    if app.play_OCC_THR.Value < 0
                        app.play_OCC_THR.Value = 27;
                    end
                case 'dBµV/m'
                    if app.play_OCC_THR.Value < 0
                        app.play_OCC_THR.Value = 40;
                    end
            end
        end


        %-----------------------------------------------------------------%
        % % PLAYBACK >> CANAIS
        %-----------------------------------------------------------------%
        function play_Channel_TreeBuilding(app, idx, srcFcn)
            if ~isempty(app.play_Channel_Tree.Children)
                delete(app.play_Channel_Tree.Children)                
            end

            % Canais incluídos automaticamente, os quais constam em "ChannelLib.json".
            channelLibIndex = app.specData(idx).UserData.channelLibIndex;
            if ~isempty(channelLibIndex)
                for ii = channelLibIndex'
                    uitreenode(app.play_Channel_Tree, 'Text', sprintf('%.3f - %.3f MHz (%s)',             ...
                                                                      app.channelObj.Channel(ii).Band(1), ...
                                                                      app.channelObj.Channel(ii).Band(2), ...
                                                                      app.channelObj.Channel(ii).Name),   ...
                                                      'NodeData', struct('src', 'channelLib', 'idx', ii), ...
                                                      'ContextMenu', app.play_Channel_ContextMenu);
                end
            end

            % Canais incluídos manualmente pelo usuário.
            channelManual = app.specData(idx).UserData.channelManual;
            if ~isempty(channelManual)
                manualNodes   = [];
                for kk = 1:numel(channelManual)
                    if channelManual(kk).FirstChannel == channelManual(kk).LastChannel
                        treeText = sprintf('%.3f MHz (%s)', channelManual(kk).FirstChannel, channelManual(kk).Name);
                    else
                        treeText = sprintf('%.3f - %.3f MHz (%s)', channelManual(kk).FirstChannel, channelManual(kk).LastChannel, channelManual(kk).Name);
                    end
    
                    manualNodes = [manualNodes, uitreenode(app.play_Channel_Tree, 'Text', treeText,                               ...
                                                                                  'NodeData', struct('src', 'manual', 'idx', kk), ...
                                                                                  'ContextMenu', app.play_Channel_ContextMenu)];
                end            
                addStyle(app.play_Channel_Tree, uistyle('FontColor', '#c94756'), 'node', manualNodes)
            end

            % Seleciona o primeiro fluxo e chama TreeSelectionChanged, caso
            % aplicável.
            if ~isempty(app.play_Channel_Tree.Children)
                app.play_Channel_Tree.SelectedNodes = app.play_Channel_Tree.Children(1);
            end
            play_Channel_TreeSelectionChanged(app, struct('Source', srcFcn))
        end

        %-----------------------------------------------------------------%
        function play_ChannelListSample(app, FreqList)
            nFreqList = numel(FreqList);

            if nFreqList == 0
                app.play_Channel_Sample.Text = '';
            elseif nFreqList <= 4
                app.play_Channel_Sample.Text = strjoin(string(FreqList) + " MHz", ', ');
            else
                app.play_Channel_Sample.Text = strjoin(string(FreqList(1:2)) + " MHz", ', ') + " ... " + strjoin(string(FreqList(end-1:end)) + " MHz", ', ');
            end
        end

        %-----------------------------------------------------------------%
        function play_BandLimits_updateEmissions(app, idx, newIndex, updatePlotFlag)
            % Analisa se as emissões incluídas/editadas pertencem às subfaixas 
            % sob análise. 
            %
            % Trigger:
            % (a) Inclusão automática de emissões. 
            %     Callback "play_FindPeaks_addEmission(app, event)"
            % (b) Edição manual da frequência central da emissão selecionada.
            %     Callback "play_FindPeaks_editEmission(app, event)"
            % (c) Ajuste do ROI, diretamente no eixo.
            %     Função auxiliar à "plot.draw2D.ClearWrite_old" ("mkrLineROI")
            arguments
                app
                idx
                newIndex
                updatePlotFlag = true
            end

            fcn.Detection_BandLimits(app.specData(idx))

            if updatePlotFlag
                selectedEmission = find(app.specData(idx).UserData.Emissions.Index == newIndex(1), 1);
                if isempty(selectedEmission)
                    if ~isempty(app.specData(idx).UserData.Emissions)
                        selectedEmission = 1;
                    else
                        delete(app.hSelectedEmission)
                        app.hSelectedEmission = [];
                    end
                end
                plot.draw2D.ClearWrite_old(app, idx, 'PeakValueChanged', selectedEmission)
            end
        end

        %-----------------------------------------------------------------%
        function play_BandLimits_Layout(app, idx)

            if app.play_BandLimits_Status.Value
                app.specData(idx).UserData.bandLimitsStatus = true;
                
                set(app.play_BandLimits_Grid.Children, Enable=1)
                app.play_BandLimits_add.Enable  = 1;
                app.play_BandLimits_Tree.Enable = 1;

            else
                app.specData(idx).UserData.bandLimitsStatus = false;

                set(findobj(app.play_BandLimits_Grid, 'Type', 'uinumericeditfield'), Enable=0)
                app.play_BandLimits_add.Enable  = 0;
                app.play_BandLimits_Tree.Enable = 0;
            end
        end

        %-----------------------------------------------------------------%
        function play_BandLimits_TreeBuilding(app, idx)
            
            if ~isempty(app.play_BandLimits_Tree.Children)
                delete(app.play_BandLimits_Tree.Children)
            end

            bandLimitsTable = app.specData(idx).UserData.bandLimitsTable;
            for ii = 1:height(bandLimitsTable)
                uitreenode(app.play_BandLimits_Tree, 'Text', sprintf('%.3f - %.3f MHz', bandLimitsTable.FreqStart(ii), bandLimitsTable.FreqStop(ii)), ...
                                                     'NodeData', ii, 'ContextMenu', app.play_BandLimits_ContextMenu);
            end
        end


        %-----------------------------------------------------------------%
        % PLAYBACK >> EMISSÕES
        %-----------------------------------------------------------------%
        function play_EmissionList(app, idx, selectedEmission)
            if ~isempty(app.play_FindPeaks_Tree.Children)
                delete(app.play_FindPeaks_Tree.Children)
            end

            if ~isempty(app.specData(idx).UserData.Emissions)
                for ii = 1:height(app.specData(idx).UserData.Emissions)
                    if app.specData(idx).UserData.Emissions.isTruncated(ii); Icon = 'signalTruncated_32.png';
                    else;                                                    Icon = 'signalUntruncated_32.png';
                    end

                    if isempty(app.specData(idx).UserData.Emissions.UserData{ii}); DriveTest = '';
                    else;                                                          DriveTest = ' (DT)';
                    end

                    uitreenode(app.play_FindPeaks_Tree, 'Text', sprintf('%d: %.3f MHz ⌂ %.1f kHz%s', ii, app.specData(idx).UserData.Emissions{ii,2}, app.specData(idx).UserData.Emissions{ii,3}, DriveTest), ...
                                                        'NodeData', ii, 'Icon', Icon, 'ContextMenu', app.play_FindPeaks_ContextMenu);
                end

                app.play_FindPeaks_Tree.SelectedNodes = app.play_FindPeaks_Tree.Children(selectedEmission);
            end
            play_FindPeaks_TreeSelectionChanged(app)
        end

        %-----------------------------------------------------------------%
        function play_UpdatePeaksTable(app, idxThreads, operationType)
            idxReport = find(arrayfun(@(x) x.UserData.reportFlag, app.specData));

            if isempty(idxReport)
                if ~isempty(app.auxiliarWin1)
                    delete(app.auxiliarWin1)
                    app.auxiliarWin1 = [];
                end

            else
                if any(ismember(idxThreads, idxReport))
                    report.Controller(app, operationType)
    
                    if ~isempty(app.auxiliarWin1) && isvalid(app.auxiliarWin1)
                        renderProjectDataOnScreen(app.auxiliarWin1)
                    end
                end
            end
        end

        
        %-----------------------------------------------------------------%
        % ## LAYOUT ##
        %-----------------------------------------------------------------%
        function play_Layout_PersistancePanel(app)
            % Lista de componentes passíveis de mudança no seus status, a
            % depender do plot Persistance.
            hComponents = [app.play_Persistance_cLim1, ...
                           app.play_Persistance_cLim2, ...
                           app.play_Persistance_cLim_Mode];

            if ~isempty(app.hPersistanceObj)
                set(hComponents, 'Enable', 1)

                if ~strcmp(app.play_Persistance_WindowSize.Value, 'full')
                    app.play_Persistance_cLim_Mode.Enable = 0;
                    set(app.play_Persistance_cLim_Grid2.Children, 'Enable', 0)
                end

                app.restoreView(1).cLim = app.UIAxes1.CLim;
                app.play_Persistance_cLim1.Value = app.UIAxes1.CLim(1);
                app.play_Persistance_cLim2.Value = app.UIAxes1.CLim(2);

            else
                set(hComponents, 'Enable', 0)
            end
        end

        %-----------------------------------------------------------------%
        function play_Layout_WaterfallPanel(app)
            % Lista de componentes passíveis de mudança no seus status, a
            % depender do plot Waterfall.
            hComponents = [app.play_Waterfall_Decimation, ...
                           app.play_Waterfall_MeshStyle,  ...
                           app.play_Waterfall_Colorbar,   ...
                           app.play_Waterfall_cLim1,      ...                           
                           app.play_Waterfall_cLim2,      ...
                           app.play_Waterfall_cLim_Mode];

            if app.axesTool_Waterfall.UserData.Value
                switch app.play_Waterfall_Fcn.Value
                    case 'image'
                        set(hComponents(3:end), 'Enable', 1)
                        app.play_Waterfall_Decimation.Value = 'auto';

                    case 'mesh'
                        set(hComponents, 'Enable', 1)
                end    

                app.restoreView(3).yLim = app.UIAxes3.YLim;
                app.restoreView(3).cLim = app.UIAxes3.CLim;

                app.play_Waterfall_cLim1.Value = double(app.UIAxes3.CLim(1));
                app.play_Waterfall_cLim2.Value = double(app.UIAxes3.CLim(2));

            else
                set(hComponents, 'Enable', 0)
            end
        end


        %-----------------------------------------------------------------%
        % ## PLOT ##
        %-----------------------------------------------------------------%
        function prePlot_restartProperties(app, axesLimits)
            cla([app.UIAxes1, app.UIAxes2, app.UIAxes3])

            app.UIAxes1.UserData.CLimMode = 'auto';
            app.UIAxes3.UserData.CLimMode = 'auto';

            app.restoreView(1) = struct('ID', 'app.UIAxes1', 'xLim', axesLimits.xLim, 'yLim', axesLimits.yLevelLim, 'cLim', 'auto');
            app.restoreView(2) = struct('ID', 'app.UIAxes2', 'xLim', axesLimits.xLim, 'yLim', [0, 100],             'cLim', 'auto');
            app.restoreView(3) = struct('ID', 'app.UIAxes3', 'xLim', axesLimits.xLim, 'yLim', axesLimits.yTimeLim,  'cLim', axesLimits.cLim);
        
            app.hClearWrite       = [];
            app.hMinHold          = [];
            app.hAverage          = [];
            app.hMaxHold          = [];
            app.hPersistanceObj   = [];
            app.hSelectedEmission = [];
            app.hEmissionMarkers  = [];
            app.hTHR              = [];
            app.hTHRLabel         = [];
            app.hWaterfall        = [];
            app.hWaterfallTime    = [];

            app.idxTime = 1;
            app.tool_TimestampSlider.Value = 0;
        end

        %-----------------------------------------------------------------%
        function prePlot_checkNSweepsAndDataType(app, idx)
            if app.bandObj.nSweeps > 2                
                app.axesTool_Persistance.Enable = 1;
                app.axesTool_Waterfall.Enable   = 1;

                if ~ismember(app.specData(idx).MetaData.DataType, class.Constants.occDataTypes)
                    app.axesTool_Occupancy.Enable = 1;
                else
                    app.axesTool_Occupancy.Enable = 0;
                    app.axesTool_Occupancy.UserData.Value = false;
                    
                    plot.old_OCC(app, idx, 'Delete', [])                    % !! PONTO DE REVISÃO !!
                end
            else                
                app.axesTool_Persistance.Enable = 0;
                app.axesTool_Persistance.UserData.Value = false;

                app.axesTool_Waterfall.Enable   = 0;
                app.axesTool_Waterfall.UserData.Value = false;

                app.axesTool_Occupancy.Enable   = 0;
                app.axesTool_Occupancy.UserData.Value = false;

                plot.old_OCC(app, idx, 'Delete', [])                        % !! PONTO DE REVISÃO !!           
            end
        end

        %-----------------------------------------------------------------%
        function treeNode = prePlot_findSelectedNodeRoot(app, idx)
            hTreeNodes     = findobj(app.play_Tree, '-not', 'Type', 'uitree');
            hTreeNodeData  = arrayfun(@(x) x.NodeData, hTreeNodes, "UniformOutput", false);
            hTreeNodeIndex = find(cellfun(@(x) isequal(idx, x), hTreeNodeData))';
        
            for ii = hTreeNodeIndex
                generation = misc_findGenerationOfTreeNode(app, hTreeNodes(ii));
                if generation == 1
                    treeNode = hTreeNodes(ii);
                    break
                end
            end
        end

        %-----------------------------------------------------------------%
        function prePlot_HTMLPanels(app, idx)
            nodeSummary = fcn.SelectedNodeSummary(app, 'app.specData', idx);
            app.play_Metadata.HTMLSource  = textFormatGUI.struct2PrettyPrintList(nodeSummary, 'delete', '11px');

            ysecondarylabel(app.UIAxes1, {app.specData(idx).Receiver; sprintf('%.3f - %.3f MHz', app.bandObj.FreqStart, app.bandObj.FreqStop)})            
            app.tool_TimestampLabel.Text  = sprintf('1 de %d\n%s', app.bandObj.nSweeps, app.specData(idx).Data{1}(1));
        end

        %-----------------------------------------------------------------%
        function prePlot_customPlayback(app, idx)
            % Em relação à customização do PLAYBACK,
            % (a) Até a v. 1.67, o appAnalise possibilitava a customização de onze parâmetros
            %     do PLAYBACK. Desde a v. 1.80, o app aumentou esse número para vinte
            %     (praticamente todos!). Além disso, os nomes dos parâmetros foram compactados, 
            %     o que demandou ajuste no leitor .MAT, mantendo a compatibilidade com os .MAT
            %     gerado em versões anteriores do app.
            
            % (b) A atualização do componente app.play_LayoutRatio deve ser posterior
            %     à atualização dos componentes app.play_Occupancy e app.play_Waterfall.
            
            % (c) A atualização dos limites dos eixos x e y é realizada automaticamente,
            %     seguindo os limites do eixos app.axes1.
            %     addlistener(app.axes1, 'XLim', 'PostSet', @app.plot_xLimitsUpdate);
            %     addlistener(app.axes1, 'YLim', 'PostSet', @app.plot_yLimitsUpdate);

            switch app.specData(idx).UserData.customPlayback.Type
                case 'auto'
                    app.play_Customization.Value = 0;

                case 'manual'
                    app.play_Customization.Value = 1;

                    app.axesTool_MinHold.UserData.Value      = app.specData(idx).UserData.customPlayback.Parameters.Controls.MinHold;
                    app.axesTool_Average.UserData.Value      = app.specData(idx).UserData.customPlayback.Parameters.Controls.Average;
                    app.axesTool_MaxHold.UserData.Value      = app.specData(idx).UserData.customPlayback.Parameters.Controls.MaxHold;
                    app.axesTool_Persistance.UserData.Value  = app.specData(idx).UserData.customPlayback.Parameters.Controls.Persistance;
                    app.axesTool_Occupancy.UserData.Value    = app.specData(idx).UserData.customPlayback.Parameters.Controls.Occupancy;
                    app.axesTool_Waterfall.UserData.Value    = app.specData(idx).UserData.customPlayback.Parameters.Controls.Waterfall;
        
                    app.play_LayoutRatio.Items   = {app.specData(idx).UserData.customPlayback.Parameters.Controls.LayoutRatio};
                    plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_Occupancy.UserData.Value, app.axesTool_Waterfall.UserData.Value, app.play_LayoutRatio)
                    
                    app.play_Persistance_Interpolation.Value = app.specData(idx).UserData.customPlayback.Parameters.Persistance.Interpolation;
                    app.play_Persistance_WindowSize.Value    = app.specData(idx).UserData.customPlayback.Parameters.Persistance.WindowSize;
                    app.play_Persistance_Transparency.Value  = app.specData(idx).UserData.customPlayback.Parameters.Persistance.Transparency;
                    app.play_Persistance_Colormap.Value      = app.specData(idx).UserData.customPlayback.Parameters.Persistance.Colormap;
        
                    if app.play_Persistance_WindowSize.Value == "full"
                        app.play_Persistance_cLim1.Value = app.specData(idx).UserData.customPlayback.Parameters.Persistance.LevelLimits(1);
                        app.play_Persistance_cLim2.Value = app.specData(idx).UserData.customPlayback.Parameters.Persistance.LevelLimits(2);
                    end

                    % A visibilidade e posição da Colobar não é tratada como 
                    % customização do playback... e por isso o componente
                    % específico não é atualizado com a informação presente
                    % em app.specData.
                    app.play_Waterfall_Fcn.Value         = app.specData(idx).UserData.customPlayback.Parameters.Waterfall.Fcn;
                    app.play_Waterfall_Decimation.Value  = app.specData(idx).UserData.customPlayback.Parameters.Waterfall.Decimation;
                    app.play_Waterfall_MeshStyle.Value   = app.specData(idx).UserData.customPlayback.Parameters.Waterfall.MeshStyle;            
                    app.play_Waterfall_Colormap.Value    = app.specData(idx).UserData.customPlayback.Parameters.Waterfall.Colormap;
                    app.play_Waterfall_cLim1.Value       = app.specData(idx).UserData.customPlayback.Parameters.Waterfall.LevelLimits(1);
                    app.play_Waterfall_cLim2.Value       = app.specData(idx).UserData.customPlayback.Parameters.Waterfall.LevelLimits(2);        
                    app.play_Waterfall_Timeline.Value    = app.specData(idx).UserData.customPlayback.Parameters.WaterfallTime.Visible;
            end
        end

        %-----------------------------------------------------------------%
        function prePlot_updatingGeneralSettings(app)
            app.General.Plot.Persistance = struct('Interpolation', app.play_Persistance_Interpolation.Value, ...
                                                  'WindowSize',    app.play_Persistance_WindowSize.Value,    ...
                                                  'Transparency',  app.play_Persistance_Transparency.Value,  ...
                                                  'Colormap',      app.play_Persistance_Colormap.Value,      ...
                                                  'LevelLimits',  [app.play_Persistance_cLim1.Value, app.play_Persistance_cLim2.Value]);


            app.General.Plot.Waterfall   = struct('Fcn',           app.play_Waterfall_Fcn.Value,        ...
                                                  'Decimation',    app.play_Waterfall_Decimation.Value, ...
                                                  'MeshStyle',     app.play_Waterfall_MeshStyle.Value,  ...
                                                  'Colormap',      app.play_Waterfall_Colormap.Value,   ...
                                                  'Colorbar',      app.play_Waterfall_Colorbar.Value,   ...
                                                  'LevelLimits',  [app.play_Waterfall_cLim1.Value, app.play_Waterfall_cLim2.Value]);

            app.General.Plot.WaterfallTime.Visible = app.play_Waterfall_Timeline.Value;

            if app.axesTool_Persistance.UserData.Value && strcmp(app.UIAxes1.UserData.CLimMode, 'auto')
                app.General.Plot.Persistance.LevelLimits = [0, 0];
            end

            if app.axesTool_Waterfall.UserData.Value && strcmp(app.UIAxes3.UserData.CLimMode, 'auto')
                app.General.Plot.Waterfall.LevelLimits = [0, 0];
            end
        end

        %-----------------------------------------------------------------%
        function prePlot_updatingCustomProperties(app, idx)
            if app.play_Customization.Value
                % Aqui são atualizados todos os controles do customPlayback, 
                % exceto os DataTips, os quais são atualizados apenas quando
                % é selecionado outro fluxo espectral.

                app.specData(idx).UserData.customPlayback.Type = 'manual';
                app.specData(idx).UserData.customPlayback.Parameters.Controls      = struct('MinHold',          app.axesTool_MinHold.UserData.Value,     ...
                                                                                            'Average',          app.axesTool_Average.UserData.Value,     ...
                                                                                            'MaxHold',          app.axesTool_MaxHold.UserData.Value,     ...
                                                                                            'Persistance',      app.axesTool_Persistance.UserData.Value, ...
                                                                                            'Occupancy',        app.axesTool_Occupancy.UserData.Value,   ...
                                                                                            'Waterfall',        app.axesTool_Waterfall.UserData.Value,   ...
                                                                                            'LayoutRatio',      app.play_LayoutRatio.Value,              ...
                                                                                            'FrequencyLimits', [app.play_Limits_xLim1.Value, app.play_Limits_xLim2.Value],  ...
                                                                                            'LevelLimits',     [app.play_Limits_yLim1.Value, app.play_Limits_yLim2.Value]);
                app.specData(idx).UserData.customPlayback.Parameters.Persistance   = struct('Interpolation',    app.play_Persistance_Interpolation.Value, ...
                                                                                            'WindowSize',       app.play_Persistance_WindowSize.Value, ...
                                                                                            'Transparency',     app.play_Persistance_Transparency.Value, ...
                                                                                            'Colormap',         app.play_Persistance_Colormap.Value, ...
                                                                                            'LevelLimits',     [app.play_Persistance_cLim1.Value, app.play_Persistance_cLim2.Value]);
                app.specData(idx).UserData.customPlayback.Parameters.Waterfall     = struct('Fcn',              app.play_Waterfall_Fcn.Value, ...
                                                                                            'Decimation',       app.play_Waterfall_Decimation.Value, ...
                                                                                            'MeshStyle',        app.play_Waterfall_MeshStyle.Value, ...
                                                                                            'Colormap',         app.play_Waterfall_Colormap.Value, ...
                                                                                            'LevelLimits',     [app.play_Waterfall_cLim1.Value, app.play_Waterfall_cLim2.Value]);
                app.specData(idx).UserData.customPlayback.Parameters.WaterfallTime = struct('Visible',          app.play_Waterfall_Timeline.Value, ...
                                                                                            'ZData',           [1000, 1000]);
            else
                app.specData(idx).UserData.customPlayback = struct('Type', 'auto', 'Parameters', []);
            end
        end

        %-----------------------------------------------------------------%
        function prePlot_updatingRestoreView(app, idx)
            axesLimits = Limits(app.bandObj, idx);

            app.restoreView(1).xLim = axesLimits.xLim;
            app.restoreView(2).xLim = axesLimits.xLim;
            app.restoreView(3).xLim = axesLimits.xLim;

            app.restoreView(1).yLim = axesLimits.yLevelLim;
            app.restoreView(2).yLim = axesLimits.yLevelLim;
            app.restoreView(3).yLim = axesLimits.yLevelLim;

            app.restoreView(3).cLim = axesLimits.cLim;
        end

        %-----------------------------------------------------------------%
        function plot_startupFcn(app, idx)        
            % !! ESSENCIAL !!
            % O handle do elemento selecionado na árvore de fluxos, para o 
            % qual será plotado os gráficos, fica armazenado na propriedade 
            % "UserData", do componente app.play_PlotPanel.
            app.play_PlotPanel.UserData = prePlot_findSelectedNodeRoot(app, idx);

            % O objeto app.bandObj armazena propriedades de app.specData(idx) 
            % que simplifica o processo do plot, em especial na passagem de 
            % argumentos para as funções plot.draw2D e plot.draw3D.
            axesLimits = update(app.bandObj, idx);

            prePlot_restartProperties(app, axesLimits)
            prePlot_checkNSweepsAndDataType(app, idx)

            % Painel "PLAYBACK > HTMLPANEL"
            prePlot_HTMLPanels(app, idx)

            % Painel "PLAYBACK > PLAYBACK"
            % Ajuste dos paineis de controle "Persistance", "Occupancy" e
            % "Waterfall" (inserindo valores do customPlayback, caso existentes).
            prePlot_customPlayback(app, idx)
            prePlot_updatingGeneralSettings(app)
            play_OCCLayoutStartup(app, idx)                                 % !! PONTO DE REVISÃO !!

            % Painel "PLAYBACK > CANAIS >> INCLUSÃO DE CANAIS"
            play_Channel_TreeBuilding(app, idx, 'plot_startupFcn')

            % Painel "PLAYBACK > CANAIS >> SUBFAIXAS"
            FreqStart = app.bandObj.FreqStart;
            FreqStop  = app.bandObj.FreqStop;

            app.play_BandLimits_Status.Value = app.specData(idx).UserData.bandLimitsStatus;
        
            app.play_BandLimits_xLim1.Limits = [-Inf, Inf];
            app.play_BandLimits_xLim2.Limits = [-Inf, Inf];
            set(app.play_BandLimits_xLim1, 'Value', FreqStart, 'Limits', [FreqStart, FreqStop])
            set(app.play_BandLimits_xLim2, 'Value', FreqStop,  'Limits', [FreqStart, FreqStop])
        
            play_BandLimits_Layout(app, idx)
            play_BandLimits_TreeBuilding(app, idx)

            % Painel "PLAYBACK >> EMISSÕES"
            play_EmissionList(app, idx, 1)
        end

        %-----------------------------------------------------------------%
        function plot_Draw(app, idx)
            % No processo de inicialização do PLOT, que ocorre toda vez que é
            % alterado o fluxo espectral selecionado, na árvore principal do
            % appAnalise, os eixos são limpos e os handles dos principais
            % componentes do plot são apagados. app.hClearWrite vazio significa 
            % que deverá ser desenhado um novo PLOT. Por outro lado, caso não 
            % seja vazio, então o PLOT deverá ser atualizado.
        
            if isempty(app.hClearWrite)
                % Essa configuração aqui dispara o trigger de alteração de
                % 'XLim' e 'YLim', executando o callback
                % @(src,evt)plot_AxesLimitsChanged(app,src,evt)
                set(app.UIAxes1, 'XLim', app.restoreView(1).xLim, 'YLim', app.restoreView(1).yLim)
                ylabel(app.UIAxes1, sprintf('Nível (%s)', app.bandObj.LevelUnit))
        
                % (a) ClearWrite, MinHold, Average, e MaxHold
                for plotTag = ["ClearWrite", "MinHold", "Average", "MaxHold"]
                    if ismember(plotTag, {'MinHold', 'Average', 'MaxHold'}) && ~eval(sprintf('app.axesTool_%s.UserData.Value', plotTag))
                        continue
                    end
        
                    eval(sprintf('app.h%s = plot.draw2D.OrdinaryLine(app.UIAxes1, app.bandObj, idx, "%s");', plotTag, plotTag))
                    plot.datatip.Template(eval(sprintf('app.h%s', plotTag)), "Frequency+Level", app.bandObj.LevelUnit)
                end
                
                % (b) Persistance
                if app.axesTool_Persistance.UserData.Value
                    plot_Draw_Persistance(app, 'Creation', idx)
                end

                % Emissões
                plot.draw2D.ClearWrite_old(app, idx, 'InitialPlot', 1)

                % BandLimits & Channels
                plot.draw2D.horizontalSetOfLines(app.UIAxes1, app.bandObj, idx, 'BandLimits')
                plot_Draw_Channels(app, idx)
        
                % Occupancy
                if app.axesTool_Occupancy.UserData.Value
                    occIndex = play_OCCIndex(app, idx, 'PLAYBACK');
                    plot.old_OCC(app, idx, 'Creation', occIndex)
                end
        
                % Waterfall
                if app.axesTool_Waterfall.UserData.Value
                    plot_Draw_Waterfall(app, idx)
                end
        
                % customPlayback >> DataTips
                if ~isempty(app.specData(idx).UserData.customPlayback.Parameters)
                    dtConfig = app.specData(idx).UserData.customPlayback.Parameters.Datatip;
                    dtParent = [app.UIAxes1, app.UIAxes2, app.UIAxes3];
                    plot.datatip.Create('customPlayback', dtConfig, dtParent)
                end
        
            else
                % ClearWrite, MinHold, Average, e MaxHold
                for plotTag = ["ClearWrite", "MinHold", "Average", "MaxHold"]
                    if ismember(plotTag, {'MinHold', 'Average', 'MaxHold'})
                        if ~eval(sprintf('app.axesTool_%s.UserData.Value', plotTag)) || isinf(app.General.Integration.Trace)
                            continue
                        end
                    end
        
                    eval(sprintf('plot.draw2D.OrdinaryLineUpdate(app.h%s, app.bandObj, idx, plotTag);', plotTag))
                end
        
                for ii = 1:numel(app.hEmissionMarkers)
                    app.hEmissionMarkers(ii).Position(2) = app.hClearWrite.YData(app.hClearWrite.MarkerIndices(ii));
                end
        
                % Persistance
                plot_Draw_Persistance(app, 'Update', idx)

                % WaterfallTime
                if app.axesTool_Waterfall.UserData.Value && ~isempty(app.hWaterfallTime) && strcmp(app.play_Waterfall_Timeline.Value, 'on')
                    plot.draw2D.OrdinaryLineUpdate(app.hWaterfallTime, app.bandObj, idx, 'WaterfallTime');
                end
            end
            drawnow
        end

        %-----------------------------------------------------------------%
        function plot_Draw_Persistance(app, operationType, idx)
            switch operationType
                case 'Creation'
                    if strcmp(app.play_Persistance_WindowSize.Value, 'full')
                        nPersistancePoints    = app.bandObj.DataPoints * app.bandObj.nSweeps;
                        nMaxPersistancePoints = class.Constants.nMaxPersistancePoints;

                        if nPersistancePoints > nMaxPersistancePoints
                            msgQuestion   = sprintf(['A persistência do fluxo espectral selecionado demanda o cálculo do histograma bidimensional com mais de %.0f milhões de pontos, '  ...
                                                     'superior ao limite recomendado (cerca de %.0f milhões pontos).\n\nDeseja alterar a janela da persistência para computar apenas '   ...
                                                     'as últimos 512 varreduras?! Em caso negativo, o plot poderá demorar mais de 10 segundos para ser finalizado.'],                    ...
                                                     nPersistancePoints/1e+6, nMaxPersistancePoints/1e+6);
                            userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                            if userSelection == "Sim"
                                app.play_Persistance_WindowSize.Value = '512';
                                play_Persistance_Callbacks(app, struct('Source', app.play_Persistance_WindowSize))

                                if app.idxTime < 512
                                    app.idxTime = 512;
                                    app.tool_TimestampSlider.Value = 100 * app.idxTime/app.bandObj.nSweeps;
                                    tool_TimestampSliderValueChanging(app, struct('Value', app.tool_TimestampSlider.Value))
                                end
                                return
                            end
                        end
                    end

                    app.hPersistanceObj = plot.draw3D.Persistance('Creation', app.hPersistanceObj, app.UIAxes1, app.bandObj, idx);
                    play_Layout_PersistancePanel(app)

                case 'Update'
                    if app.axesTool_Persistance.UserData.Value && ~strcmp(app.play_Persistance_WindowSize.Value, 'full')
                        app.hPersistanceObj = plot.draw3D.Persistance('Update', app.hPersistanceObj, app.UIAxes1, app.bandObj, idx);
                        play_Layout_PersistancePanel(app)
                    end

                case 'Delete'
                    app.hPersistanceObj = plot.draw3D.Persistance('Delete', app.hPersistanceObj);
            end
        end

        %-----------------------------------------------------------------%
        function plot_Draw_Waterfall(app, idx)
            prePlot_updatingGeneralSettings(app)
            prePlot_updatingCustomProperties(app, idx)

            [app.hWaterfall, app.play_Waterfall_DecimationValue.Text] = plot.draw3D.Waterfall('Creation', app.UIAxes3, app.bandObj, idx);
            plot.axes.Layout.YLabel(app.hWaterfall, app.axesTool_Waterfall.UserData.Value)
            play_Layout_WaterfallPanel(app)

            if strcmp(app.play_Waterfall_Timeline.Value, 'on')
                app.hWaterfallTime = plot.draw2D.OrdinaryLine(app.UIAxes3, app.bandObj, idx, 'WaterfallTime');
            end
        end

        %-----------------------------------------------------------------%
        function plot_Draw_Channels(app, idx)
            if ~isempty(app.play_Channel_Tree.SelectedNodes) && app.play_Channel_ShowPlot.UserData
                srcChannel = app.play_Channel_Tree.SelectedNodes.NodeData.src;
                idxChannel = app.play_Channel_Tree.SelectedNodes.NodeData.idx;

                switch srcChannel
                    case 'channelLib'
                        srcRawTable = app.channelObj.Channel(idxChannel);
                    case 'manual'
                        srcRawTable = app.specData(idx).UserData.channelManual(idxChannel);
                end
                
                if ~isempty(srcRawTable)
                    channelBW = srcRawTable.ChannelBW; % MHz
                    if channelBW <= 0
                        app.play_Channel_ShowPlotWarn.Visible = 1;
                        return
                    else
                        app.play_Channel_ShowPlotWarn.Visible = 0;
                    end
    
                    if ~isempty(srcRawTable.FreqList)
                        FreqList = srcRawTable.FreqList;
                    else
                        FreqList = (srcRawTable.FirstChannel:srcRawTable.StepWidth:srcRawTable.LastChannel)';
                    end

                    srcTable = table(FreqList-channelBW/2, FreqList+channelBW/2, 'VariableNames', {'FreqStart', 'FreqStop'});    
                    plot.draw2D.horizontalSetOfLines(app.UIAxes1, app.bandObj, idx, 'Channels', srcTable)
                else
                    delete(findobj(app.UIAxes1, 'Tag', 'Channels'))
                end
            else
                delete(findobj(app.UIAxes1, 'Tag', 'Channels'))
            end
        end

        %-----------------------------------------------------------------%
        function plot_AxesLimitsChanged(app, src, evt)
            switch src.Name
                case 'XLim'
                    app.play_Limits_xLim1.Value = round(evt.AffectedObject.XLim(1), 3);
                    app.play_Limits_xLim2.Value = round(evt.AffectedObject.XLim(2), 3);

                case 'YLim'
                    app.play_Limits_yLim1.Value = round(double(evt.AffectedObject.YLim(1)), 1);
                    app.play_Limits_yLim2.Value = round(double(evt.AffectedObject.YLim(2)), 1);
            end
        end


        %-----------------------------------------------------------------%
        function [idx, nSweeps] = plot_updateIndex(app)
            % Os valores do índice e do número de varredura se referem ao
            % novo fluxo espectral selecionado, e não àquele que estava
            % sendo apresentado.
            idx = app.play_Tree.SelectedNodes.NodeData;
            nSweeps = numel(app.specData(idx).Data{1});
            
            plot_startupFcn(app, idx)
            plot_Draw(app, idx)

            app.plotFlag = 1;
        end     
        
        %-----------------------------------------------------------------%
        function plot_deleteLines(app, Tag)
            eval(sprintf('delete(app.h%s)', Tag));
            eval(sprintf('app.h%s = [];', Tag));
        end

        %-----------------------------------------------------------------%
        function plot_mainLoop(app, idx, nSweeps)
            while app.idxTime <= nSweeps
                % A variável app.plotFlag pode assumir os valores -1 | 0 | 1.
                % - -1: alteração de fluxo espectral
                % -  0: finalização do plot
                % -  1: atualização do plot
                switch app.plotFlag
                    case -1; [idx, nSweeps] = plot_updateIndex(app);
                    case  0; return
                end
                sweepTic = tic;
                
                plot_Draw(app, idx)
                app.tool_TimestampLabel.Text   = sprintf('%d de %d\n%s', app.idxTime, nSweeps, app.specData(idx).Data{1}(app.idxTime));
                app.tool_TimestampSlider.Value = 100 * app.idxTime/nSweeps;
                
                pause(max(app.play_MinPlotTime.Value/1000-toc(sweepTic), .001))
                
                % Reload Flag
                if app.idxTime == nSweeps
                    if app.tool_LoopControl.Tag == "loop"
                        app.idxTime = 1;
                    else
                        break
                    end
                else
                    app.idxTime = app.idxTime+1;
                end                
            end
            
            play_PlaybackToolbarButtonCallback(app, struct('Source', app.tool_Play))
        end


        %-----------------------------------------------------------------%
        % ## Modo "RELATÓRIO" ##
        %-----------------------------------------------------------------%
        function report_TreeBuilding(app)            
            if ~isempty(app.report_Tree.Children)
                delete(app.report_Tree.Children);
            end            
            
            % Atualizando a árvore principal de fluxos de dados, destacando
            % os fluxos incluídos para análise (no modo RELATÓRIO).
            play_TreeNodeStyle(app)

            % E, posteriormente, ajusta os elementos do painel do modo
            % RELATÓRIO.
            idxThreads = find(arrayfun(@(x) x.UserData.reportFlag, app.specData));
            if isempty(idxThreads)
                app.projectData.peaksTable(:,:) = [];
                app.tool_ReportAnalysis.Enable  = 0;
                app.tool_ReportGenerator.Enable = 0;
            else
                app.tool_ReportAnalysis.Enable  = 1;
                report_ModelNameValueChanged(app)                
                
                [receiverList, ~, ic] = unique({app.specData(idxThreads).Receiver});                
                for ii = 1:numel(receiverList)
                    idx2 = find(ic == ii)';
                    Category = uitreenode(app.report_Tree, 'Text', receiverList{ii},                               ...
                                                           'NodeData', idxThreads(idx2),                                 ...
                                                           'Icon', fcn.treeNodeIcon('Receiver', receiverList{ii}), ...
                                                           'ContextMenu', app.report_ContextMenu);
                    
                    for jj = idx2
                        idx3 = idxThreads(jj);
                        Node = uitreenode(Category, 'Text', misc_nodeTreeText(app, idx3), ...
                                                    'NodeData', idx3,                     ...
                                                    'ContextMenu', app.report_ContextMenu);

                        if ~isempty(app.specData(idx3).UserData.reportExternalFiles)
                            Node.Icon = 'attach_32.png';
                        end
                    end
                end
                expand(app.report_Tree, 'all')
            end

            play_TreeSelectionChanged(app, struct('Source', 'report_TreeBuilding'))
            play_UpdatePeaksTable(app, idxThreads, 'report.AddOrDeleteThread')
        end

        %-----------------------------------------------------------------%
        function report_Algorithms(app, idx)
            if isscalar(idx) && app.specData(idx).UserData.reportFlag
                % Depois insere informações no painel de algoritmos.
                if app.specData(idx).UserData.bandLimitsStatus && height(app.specData(idx).UserData.bandLimitsTable)
                    detectionBands = strjoin(arrayfun(@(x,y) sprintf('%.3f - %.3f MHz', x, y), app.specData(idx).UserData.bandLimitsTable.FreqStart, ...
                                                                                               app.specData(idx).UserData.bandLimitsTable.FreqStop, 'UniformOutput', false), ', ');
                else
                    detectionBands = sprintf('%.3f - %.3f MHz', app.specData(idx).MetaData.FreqStart/1e+6, ...
                                                                app.specData(idx).MetaData.FreqStop /1e+6);
                end

                % Organização da informação que é apresentada no painel de
                % algoritmos.
                dataStruct            = struct('group', 'OCUPAÇÃO',               'value', app.specData(idx).UserData.reportOCC);
                dataStruct(2)         = struct('group', 'DETECÇÃO ASSISTIDA',     'value', struct('Origin',     'PLAYBACK', ...
                                                                                                  'BandLimits', detectionBands));
                if ~app.specData(idx).UserData.reportDetection.ManualMode
                    dataStruct(end+1) = struct('group', 'DETECÇÃO NÃO ASSISTIDA', 'value', struct('Origin',     'RELATÓRIO', ...
                                                                                                  'BandLimits',  detectionBands,     ...
                                                                                                  'Algorithm',   app.specData(idx).UserData.reportDetection.Algorithm, ...
                                                                                                  'Parameters',  jsonencode(app.specData(idx).UserData.reportDetection.Parameters)));
                end
                dataStruct(end+1)     = struct('group', 'CLASSIFICAÇÃO',          'value', struct('Algorithm',   app.specData(idx).UserData.reportClassification.Algorithm, ...
                                                                                                  'Parameters',  app.specData(idx).UserData.reportClassification.Parameters));
                
                app.report_ThreadAlgorithms.HTMLSource    = textFormatGUI.struct2PrettyPrintList(dataStruct);
                app.report_ThreadAlgorithmsRefresh.Enable = 1;
                set(app.report_DetectionManualMode, Value=app.specData(idx).UserData.reportDetection.ManualMode, Enable=1)

            else
                app.report_ThreadAlgorithms.HTMLSource    = fullfile(app.rootFolder, 'Icons', 'Warning.html');
                app.report_ThreadAlgorithmsRefresh.Enable = 0;
                set(app.report_DetectionManualMode, Value=0, Enable=0)
            end            
        end

        %-----------------------------------------------------------------%
        function report_LayoutPlaybackOnly(app)

            if app.report_DetectionManualMode.Value
                set(findall(groot, 'Parent', app.report_DetectionGrid, 'Tag', 'Detection'), 'Enable', 0)
            else
                set(findall(groot, 'Parent', app.report_DetectionGrid, 'Tag', 'Detection'), 'Enable', 1)
            end
        end

        %-----------------------------------------------------------------%
        function report_ProjectDataGUI(app)
            app.report_ProjectName.Value  = app.projectData.file;
            app.report_Issue.Value        = app.projectData.issue;
            app.report_ModelName.Value    = app.projectData.documentModel;
            app.report_ExternalFiles.Data = app.projectData.externalFiles;
        end

        %-----------------------------------------------------------------%
        function status = report_checkValidIssueID(app)
            status = (app.report_Issue.Value > 0) && (app.report_Issue.Value < inf);
        end


        %-----------------------------------------------------------------%
        % EDIT
        %-----------------------------------------------------------------%
        function SelectedNodesTextList = edit_SelectedNodesText(app)
            SelectedNodesTextList = {};
            for ii = 1:numel(app.play_Tree.SelectedNodes)
                generation = misc_findGenerationOfTreeNode(app, app.play_Tree.SelectedNodes(ii));

                switch generation
                    case 0
                        NN = numel(app.play_Tree.SelectedNodes(ii).Children);
                        SelectedNodesTextList(end+1:end+NN) = {app.play_Tree.SelectedNodes(ii).Text};
                    case 1
                        SelectedNodesTextList{end+1} = app.play_Tree.SelectedNodes(ii).Text;
                    case 2
                        SelectedNodesTextList{end+1} = app.play_Tree.SelectedNodes(ii).Parent.Text;
                    case 3
                        SelectedNodesTextList{end+1} = app.play_Tree.SelectedNodes(ii).Parent.Parent.Text;
                end

            end
            SelectedNodesTextList = unique(SelectedNodesTextList);
        end

        %-----------------------------------------------------------------%
        function idx = edit_gpsFindIndex(app)

            % Identifica os fluxos espectrais selecionados, verificando se 
            % o local de monitoração que gerou a informação é único.
            idx = unique([app.play_Tree.SelectedNodes.NodeData]);

            if ~isempty(idx)
                gpsArray = arrayfun(@(x) x.GPS, app.specData(idx));
                for ii = numel(gpsArray):-1:2
                    if isequal(gpsArray(ii), gpsArray(1))
                        gpsArray(ii) = [];
                    end
                end

                if numel(gpsArray) > 1
                    idx = [];                
                end
            end
        end

        %-----------------------------------------------------------------%
        function edit_gpsLayout(app, clickEffect)
            idx = edit_gpsFindIndex(app);
            SelectedNodesTextList = edit_SelectedNodesText(app);
            
            if ~isempty(idx)    
                % gpsPanel
                switch app.specData(idx(1)).GPS.Status
                    case -1;   app.edit_gpsSource.Value = 'manual';
                    otherwise; app.edit_gpsSource.Value = 'auto';
                end
                app.edit_gpsLatitude.Value    = app.specData(idx(1)).GPS.Latitude;
                app.edit_gpsLongitude.Value   = app.specData(idx(1)).GPS.Longitude;                
    
                % gpsCityPanel
                app.edit_gpsCitySource.Value  = app.specData(idx(1)).GPS.LocationSource;
                app.edit_gpsCity.Value        = app.specData(idx(1)).GPS.Location;

                app.tool_MiscEdit.Enable     = 1;
                app.edit_gpsCityButton.Enable = 1;

                switch clickEffect
                    case 'City'
                        hComp = [app.edit_gpsCitySource, ...
                                 app.edit_gpsCity];
                        play_TreeRebuilding(app, SelectedNodesTextList, hComp)

                    case 'City+Latitude+Longitude'
                        hComp = [app.edit_gpsSource,     ...
                                 app.edit_gpsLatitude,   ...
                                 app.edit_gpsLongitude,  ...
                                 app.edit_gpsCitySource, ...
                                 app.edit_gpsCity];
                        play_TreeRebuilding(app, SelectedNodesTextList, hComp)

                    case 'Latitude+Longitude'
                        hComp = [app.edit_gpsSource,     ...
                                 app.edit_gpsLatitude,   ...
                                 app.edit_gpsLongitude];
                        play_TreeRebuilding(app, SelectedNodesTextList, hComp)
                end                
            else
                app.edit_gpsSource.Value      = '';
                app.edit_gpsLatitude.Value    = -1;
                app.edit_gpsLongitude.Value   = -1;
                app.edit_gpsCitySource.Value  = '';
                app.edit_gpsCity.Value        = '';

                app.tool_MiscEdit.Enable     = 0;
                app.edit_gpsCityButton.Enable = 0;
            end

            if ismember('manual', {app.edit_gpsSource.Value, app.edit_gpsCitySource.Value})
                app.tool_MiscRefresh.Enable = 1;
            else
                app.tool_MiscRefresh.Enable = 0;
            end

            set(findobj(app.edit_gpsLocationGrid, '-not', {'Type', 'uilabel', '-or', 'Type', 'uibutton', '-or', 'Type', 'uigridlayout'}), Editable=0)
            set(findobj(app.edit_gpsCityGrid,     '-not', {'Type', 'uilabel', '-or', 'Type', 'uibutton', '-or', 'Type', 'uigridlayout'}), Editable=0)
        end

        %-----------------------------------------------------------------%
        function edit_gpsUpdate(app, idx, Latitude, Longitude)
            app.specData(idx).GPS.Status        = -1;
            app.specData(idx).GPS.Count         = 1;
            app.specData(idx).GPS.Latitude      = Latitude;
            app.specData(idx).GPS.Longitude     = Longitude;
            app.specData(idx).GPS.Latitude_std  = 0;
            app.specData(idx).GPS.Longitude_std = 0;
            app.specData(idx).GPS.stdRange      = [100 100 100];
        end

        %-----------------------------------------------------------------%
        function edit_unitLevelLayout(app)
            if ~isempty(app.misc_Edit_kFactorTree.Children)
                delete(app.misc_Edit_kFactorTree.Children)
            end

            idx = unique([app.play_Tree.SelectedNodes.NodeData]);

            if isscalar(idx)
                measCalibration = app.specData(idx).UserData.measCalibration;
                if ~isempty(measCalibration)
                    app.misc_Edit_unitRaw.Value = measCalibration.oldUnitLevel{1};

                    for ii = 1:height(measCalibration)
                        uitreenode(app.misc_Edit_kFactorTree, 'Text', sprintf('%s → %s: "%s"', measCalibration.oldUnitLevel{ii}, ...
                                                                                               measCalibration.newUnitLevel{ii}, ...
                                                                                               measCalibration.Name{ii}),        ...
                                                              'NodeData', ii, 'ContextMenu', app.edit_ContextMenu);
                    end
                else
                    app.misc_Edit_unitRaw.Value = app.specData(idx).MetaData.LevelUnit;
                end
                app.misc_Edit_kFactorAdd.Enable = 1;
            else
                app.misc_Edit_unitRaw.Value     = '';
                app.misc_Edit_kFactorAdd.Enable = 0;
            end

            if ~isempty(app.misc_Edit_kFactorTree.Children)
                app.edit_unitRefresh.Enable = 1;
            else
                app.edit_unitRefresh.Enable = 0;
            end
        end

        %-----------------------------------------------------------------%
        function edit_unitLevelCorrection(app, OperationType, idx1, idx2, Name)
            try
                fcn.measCalibration(app, OperationType, idx1, idx2, Name)
            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', ME.message);
            end
        end


        %-----------------------------------------------------------------%
        % FUNÇÕES QUE ATENDEM MAIS DE UM MÓDULO
        %-----------------------------------------------------------------%
        function nodeText = misc_nodeTreeText(app, idx)
            ThreadID  = app.specData(idx).RelatedFiles.ID(1);
            FreqStart = app.specData(idx).MetaData.FreqStart / 1e+6;
            FreqStop  = app.specData(idx).MetaData.FreqStop  / 1e+6;

            switch app.play_TreeSort.UserData
                case 'Receiver+ID'
                    nodeText = sprintf('ID %d: %.3f - %.3f MHz', ThreadID, FreqStart, FreqStop);
                case 'Receiver+Frequency'
                    nodeText = sprintf('%.3f - %.3f MHz (ID %d)', FreqStart, FreqStop, ThreadID);
            end
        end

        %-----------------------------------------------------------------%
        function misc_updateLastVisitedFolder(app, filePath)
            % <ToDo> EMD - 14/08/2024 - Eliminar a criação de campos em app.General, 
            % de forma que a estrutura seja fixa e orientada apenas aquilo que consta 
            % no arquivo "GeneralSettings.json". Aqui precisarei migrar a informação 
            % para o objeto app.projectObj.</ToDo>
            app.General.fileFolder.lastVisited = filePath;
            appUtil.generalSettingsSave(class.Constants.appName, app.rootFolder, app.General, app.executionMode, {'AppVersion', 'Models', 'Report'})
        end

        %-----------------------------------------------------------------%
        function generation = misc_findGenerationOfTreeNode(app, treeNode)
            referenceNode = treeNode;
            generation    = 0;
            while true
                if referenceNode.Parent ~= app.play_Tree
                    referenceNode = referenceNode.Parent;
                    generation = generation+1;
                else
                    break
                end
            end
        end

        %-----------------------------------------------------------------%
        function userSelection = misc_checkIfAuxiliarAppIsOpen(app, operationType)
            userSelection = 'Sim';

            if ~isempty(app.auxiliarWin1) || ~isempty(app.auxiliarWin2)
                msgQuestion   = sprintf(['A operação "%s" demanda que os módulos auxiliares "SignalAnalysis" e "DriveTest" sejam fechados, '        ...
                                         'caso abertos, pois as informações espectrais consumidas por esses módulos poderão ficar desatualizadas. ' ...
                                         'Deseja continuar?'], operationType);
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                if userSelection == "Sim"
                    cellfun(@(x) delete(x), {app.auxiliarWin1, app.auxiliarWin2})
                    app.auxiliarWin1 = [];
                    app.auxiliarWin2 = [];
                end
            end
        end


        %-----------------------------------------------------------------%
        % MODO "MISCELÂNEAS"
        %-----------------------------------------------------------------%
        function misc_SaveSpectralData(app, idx)
            defaultFilename = class.Constants.DefaultFileName(app.config_Folder_userPath.Value, 'SpectralData', -1);
            nameFormatMap   = {'*.mat',    'appAnalise (*.mat)'; ...
                               '*.bin',    'Logger (*.bin)';     ...
                               '*.sm1809', 'SM1809 (*.sm1809)'};

            [fileName, filePath, fileIndex] = uiputfile(nameFormatMap, '', defaultFilename);
            figure(app.UIFigure)

            % As mensagens de erro apresentadas a seguir já explicitam as
            % limitações dos formatos "CRFS Bin" e "SM1809". O formato  "MAT",
            % por outro lado, não possui limitação.
            switch fileIndex
                case 0
                    return

                case 2 % CRFS Bin v. 5
                    receiverList = unique({app.specData(idx).Receiver});
                    if (numel(receiverList) > 1) || ~contains(receiverList, 'RFeye', 'IgnoreCase', true)
                        msgWarning = 'O formato de arquivo CRFS Bin não possibilita o armazenamento de dados gerados por mais de um sensor, ou por um sensor que não seja um RFeye.';
                    end

                case 3 % SM1809
                    receiverList = unique({app.specData(idx).Receiver});
                    if (numel(receiverList) > 1)
                        msgWarning = 'O formato de arquivo SM1809 não possibilita o armazenamento de dados gerados por mais de um sensor.';
                    elseif any(ismember(arrayfun(@(x) x.MetaData.DataType, app.specData(idx)), class.Constants.occDataTypes))
                        msgWarning = 'O formato de arquivo SM1809 não possibilita o armazenamento de fluxos de ocupação.';
                    end
            end

            if exist('msgWarning', 'var')
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                return
            end

            fileName = fullfile(filePath, fileName);
            switch fileIndex
                case 1; fileWriter.MAT(fileName, 'SpectralData', app.specData(idx));
                case 2; fileWriter.CRFSBin(fileName, app.specData(idx));
                case 3; fileWriter.SM1809(fileName, app.specData(idx));
            end
        end

        %-----------------------------------------------------------------%
        function misc_ExportUserData(app, idx)
            defaultFilename = class.Constants.DefaultFileName(app.config_Folder_userPath.Value, 'UserData', -1);
            nameFormatMap   = {'*.mat', 'appAnalise (*.mat)'};

            [fileName, filePath, fileIndex] = uiputfile(nameFormatMap, '', defaultFilename);
            figure(app.UIFigure)

            if ~fileIndex
                return
            end
            
            prjInfo  = struct('exceptionList', app.projectData.exceptionList);
            fileName = fullfile(filePath, fileName);
            fileWriter.MAT(fileName, 'UserData', app.specData(idx), prjInfo);
        end

        %-----------------------------------------------------------------%
        function misc_ImportUserData(app)
            [fileFullPath, fileFolder] = appUtil.modalWindow(app.UIFigure, 'uigetfile', '', {'*.mat', 'appAnalise (*.mat)'}, app.General.fileFolder.lastVisited);
            if isempty(fileFullPath)
                return
            end
            misc_updateLastVisitedFolder(app, fileFolder)
            
            fileReader.MAT_UserData(app, fileFullPath)
        end


        %-----------------------------------------------------------------%
        % AUXILIAR FUNCTIONS TO OTHERS APPS OR EXTERNAL FUNCTIONS
        %-----------------------------------------------------------------%
        function appBackDoor(app, callingApp, operationType, varargin)
            figureFocusFlag = true;

            switch class(callingApp)
                case 'auxApp.winSignalAnalysis'
                    switch operationType
                        case 'closeFcn'
                            report_SaveWarn(app)

                        case 'EditOrDeleteEmission'
                            idxThread = varargin{1};
                            idxEmission = varargin{2};

                            if isequal(idxThread, app.play_PlotPanel.UserData.NodeData)
                                plot.draw2D.ClearWrite_old(app, idxThread, 'PeakValueChanged', idxEmission)
                            end                            
                            play_UpdatePeaksTable(app, idxThread, 'signalAnalysis.EditOrDeleteEmission')
                    end
                    
                % case 'auxApp.winAddFiles'
                %     if strcmp(varargin{1}, 'Fluxo de dados')
                %         report_TreeBuilding(app)
                %         app.report_Tree.SelectedNodes = app.report_Tree.Children(1).Children(1);
                %     end
                % 
                % case {'auxApp.winClassification', 'auxApp.winDetection'}
                %     idx = varargin{1};
                %     report_Algorithms(app, idx)
                %     report_SaveWarn(app)

                otherwise
                    error('UnexpectedCall')
                    % OUTROS APPS AUXILIARES
            end

            if figureFocusFlag
                figure(app.UIFigure)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            try
                % WARNING MESSAGES
                appUtil.disablingWarningMessages()
                
                % GUI STARTUP
                app.home_Title.Text = sprintf('<p><font  style="font-size: 16px; color: black;"><b>appAnalise </b></font><br>v. %s </p></div>', class.Constants.appVersion);

                % !! ÁREA DE AJUSTES DA GUI (EM AMBIENTE DE DESENVOLVIMENTO)
                % E EXPERIMENTOS !!
                app.UIFigure.Position(4) = 660;
                app.TabGroup.Visible     = 0;
                app.home_Grid.Layout.Row = 2;
                app.GridLayout.RowHeight = {44, '1x'};
                app.play_TreeGrid.RowHeight(4:9) = {0,0,0,0,0,0};
                config_ButtonGroupSelectionChanged(app)
                % !! APAGAR ISSO DEPOIS !!

                appUtil.winPosition(app.UIFigure)
                jsBackDoor_Initialization(app)
                startup_timerCreation(app)

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            % RUNNING PROJECT CHECK
            projectName = char(app.report_ProjectName.Value);
            if ~isempty(projectName) && app.report_ProjectWarnIcon.Visible
                msgQuestion   = sprintf(['O projeto aberto - registrado no arquivo <b>"%s"</b> - foi alterado.\n\n' ...
                                         'Deseja descartar essas alterações? Caso não, favor salvá-las no modo RELATÓRIO.'], projectName);
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                if userSelection == "Não"
                    return
                end
            end

            % DELETE TEMP FILES
            delete(fullfile(app.General.fileFolder.tempPath, '~Report*.html'))
            delete(fullfile(app.General.fileFolder.tempPath, '~Image*.*'))

            delete(fullfile(app.config_Folder_userPath.Value, '~Report*.html'))
            delete(fullfile(app.config_Folder_userPath.Value, '~Image*.*'))

            % DELETE APPS
            DeleteFigures(app)

            % TIMER
            h = timerfindall;
            if ~isempty(h)
                stop(h); delete(h); clear h
            end

            % PROGRESS DIALOG
            delete(app.progressDialog)

            % MATLAB RUNTIME
            % Ao fechar um webapp, o MATLAB WebServer demora uns 10 segundos para
            % fechar o Runtime que suportava a sessão do webapp. Dessa forma, a 
            % liberação do recurso, que ocorre com a inicialização de uma nova 
            % sessão do Runtime, fica comprometida.
            appUtil.killingMATLABRuntime(app.executionMode)

            delete(app)
            
        end

        % Value changed function: menu_Button1, menu_Button2, 
        % ...and 3 other components
        function menu_mainButtonPushed(app, event)

            clickedButton = event.Source;

            if event.PreviousValue
                clickedButton.Value = true;
                return
            end

            tabIndex      = clickedButton.UserData.tabGroup;
          % tabIndex      = str2double(clickedButton.Tag);

            nonClickedButtons = findobj(app.menu_Grid, 'Type', 'uistatebutton', '-not', 'Tag', clickedButton.Tag);
            arrayfun(@(x) set(x, 'Value', 0, 'Icon', x.UserData.iconOptions{1}), nonClickedButtons)
            set(clickedButton, 'Icon', clickedButton.UserData.iconOptions{2})
            
            app.TabGroup.SelectedTab = app.TabGroup.Children(tabIndex);
            drawnow

            % A customização somente pode tem efeito se os componentes
            % já tiverem sido renderizados no HTML. Por essa razão, inclui-se 
            % um drawnow após a mudança da aba.
            jsBackDoor_Customizations(app, tabIndex)
            menu_LayoutControl(app, tabIndex)
            
        end

        % Image clicked function: RFDataHubModule
        function menu_OpenRFDataHubModule(app, event)
            % <ReviewNote> EMD - 14/08/2024</ReviewNote>
            if isempty(app.auxiliarWin3) || ~isvalid(app.auxiliarWin3)
                msgQuestion   = ['O RFDataHub é uma ETL de dados de estações de telecomunicações composta por '       ...
                                 'registros extraídos de bases de dados do MOSAICO, STEL, SRD, ICAO, AISWEB, GEOAIS ' ...
                                 'e REDEMET.<br><br>Deseja abrir o módulo de consulta ao RFDataHub?'];
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                if userSelection == "Não"
                    return
                end

                app.progressDialog.Visible = 'visible';
                app.auxiliarWin3 = auxApp.winRFDataHub(app);
                app.progressDialog.Visible = 'hidden';

            else
                figure(app.auxiliarWin3.UIFigure)
            end

        end

        % Image clicked function: AppInfo, FigurePosition
        function menu_ToolbarImageCliced(app, event)

            focus(app.jsBackDoor)

            switch event.Source
                case app.FigurePosition
                    app.UIFigure.Position(3:4) = class.Constants.windowSize;
                    appUtil.winPosition(app.UIFigure)

                case app.AppInfo
                    if isempty(app.AppInfo.Tag)
                        app.progressDialog.Visible = 'visible';
                        app.AppInfo.Tag = fcn.htmlCode_appInfo(app.General, app.rootFolder, app.executionMode);
                        app.progressDialog.Visible = 'hidden';
                    end

                    msgInfo = app.AppInfo.Tag;
                    appUtil.modalWindow(app.UIFigure, 'info', msgInfo);
            end

        end

        % Image clicked function: file_OpenFileButton
        function file_ButtonPushed_OpenFile(app, event)
            % <ReviewNote> EMD - 14/08/2024</ReviewNote>
            [fileName, filePath] = uigetfile({'*.bin;*.dbm;*.mat', 'Binários (*.bin,*.dbm,*.mat)'; ...
                                              '*.csv;*.sm1809',    'Textuais (*.csv,*.sm1809)'},   ...
                                              '', app.General.fileFolder.lastVisited, 'MultiSelect', 'on');
            figure(app.UIFigure)

            if isequal(fileName, 0)
                return
            elseif ~iscell(fileName)
                fileName = {fileName};
            end
            misc_updateLastVisitedFolder(app, filePath)

            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento a leitura de metadados do(s) arquivo(s) selecionado(s).');            
            
            repeteadFiles = {};
            for ii = 1:numel(fileName)
                d.Message = sprintf('Em andamento a leitura de metadados do arquivo:\n•&thinsp;%s\n\n%d de %d', fileName{ii}, ii, numel(fileName));
                
                fileFullPath  = fullfile(filePath, fileName{ii});
                [~,~,fileExt] = fileparts(fileFullPath);

                relatedFiles  = RelatedFiles(app.metaData);

                switch lower(fileExt)
                    case {'.bin', '.dbm', '.sm1809', '.csv'}
                        if ~any(contains(relatedFiles, fileName(ii), 'IgnoreCase', true))
                            idx = numel(app.metaData)+1;
                            
                            app.metaData(idx).File = fileFullPath;
                            app.metaData(idx).Type = 'Spectral data';
                        else
                            repeteadFiles(end+1) = fileName(ii);
                            continue
                        end
                        
                    case '.mat'
                        lastwarn('')
                        load(fileFullPath, '-mat', 'prj_Type', 'prj_RelatedFiles')
                        [~, warnID] = lastwarn;
                        
                        % Um projeto .MAT pode conter informações geradas por mais
                        % de um arquivo .BIN, por exemplo. Por essa razão, certifica-se
                        % que nenhum dos arquivos relacionados ao projeto já foram 
                        % lidos anteriormente.
                        if strcmp(warnID, 'MATLAB:load:variableNotFound')
                            msgWarning = sprintf('O arquivo indicado a seguir não foi gerado pelo appAnalise ou appColeta.\n•&thinsp;%s', fileName{ii});
                            appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);                                                        
                            continue
                            
                        elseif any(strcmpi(fileFullPath, {app.metaData.File}))
                            msgWarning = sprintf('O arquivo indicado a seguir já tinha sido lido.\n•&thinsp;%s', fileName{ii});
                            appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);                            
                            continue
                            
                        elseif any(contains(relatedFiles, prj_RelatedFiles, 'IgnoreCase', true))
                            msgWarning = sprintf(['O arquivo indicado a seguir não será lido por já ter sido lido ao menos um arquivo relacionado ao ' ...
                                           'projeto appAnalise.\n•&thinsp;%s\n\nArquivo(s) relacionado(s) ao projeto appAnalise já lido(s):\n%s'],   ...
                                           fileName{ii}, strjoin(cellfun(@(x) sprintf('•&thinsp;%s', x), relatedFiles(contains(relatedFiles, prj_RelatedFiles, 'IgnoreCase', true)), 'UniformOutput', false), '\n'));
                            appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);                            
                            continue

                        elseif ~isempty(app.metaData) && strcmp(prj_Type, 'Project data') && ismember('Project data', {app.metaData.Type})
                            msgWarning = sprintf('O arquivo indicado a seguir não será lido porque já foram lidos os metadados de outro projeto appAnalise.\n•&thinsp;%s', fileName{ii});
                            appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);                            
                            continue
                            
                        else
                            idx = numel(app.metaData)+1;
                            
                            app.metaData(idx).File = fileFullPath;
                            app.metaData(idx).Type = prj_Type;
                        end
                end
                
                try
                    % Chama método público da classe "class.metaData".
                    InitialFileReading(app.metaData(idx))

                catch ME
                    appUtil.modalWindow(app.UIFigure, 'error', ME.message);

                    % Se ocorre um erro no processo de leitura, e esse erro
                    % não for um dos mapeados, o objeto app.metaData será reiniciado.
                    switch ME.identifier
                        case {'metaData:Read:NotImplementedReader', 'metaData:Read:UnexpectedFileFormat', 'metaData:Read:EmptySpectralData'}
                            delete(app.metaData(idx))
                            app.metaData(idx) = [];

                        otherwise
                            delete(app.metaData)
                            app.metaData = class.metaData.empty;

                            fclose all;
                            break
                    end
                end
            end
            
            if ~isempty(repeteadFiles)
                msgWarning = sprintf('Os metadados do(s) arquivo(s) indicado(s) a seguir já tinham sido lidos.\n%s', strjoin(strcat("•&thinsp;", repeteadFiles), '\n'));
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
            end

            file_TreeBuilding(app)
            focus(app.file_Tree)

        end

        % Button pushed function: file_SpecReadButton
        function file_ButtonPushed_SpecRead(app, event)
            % <ReviewNote> EMD - 22/08/2024</ReviewNote>
            % Verificar se há ao menos um fluxo a ser lido...
            flag = false;
            for ii = 1:numel(app.metaData)
                for jj = 1:numel(app.metaData(ii).Data)
                    if app.metaData(ii).Data(jj).Enable
                        flag = true;
                        break
                    end
                end
            end

            if ~flag
                appUtil.modalWindow(app.UIFigure, 'warning', 'Não há fluxo de informação a ser lido...');
                return
            end

            % Verifica se os módulos auxiliares abaixo descritos estão abertos.
            % - auxiliarWin1: winSignalAnalysis
            % - auxiliarWin2: winDriveTest
            if strcmp(misc_checkIfAuxiliarAppIsOpen(app, 'RELER INFORMAÇÃO ESPECTRAL'), 'Não')
                return
            end

            % Limpa eventuais informações de um projeto antigo, no modo REPORT. 
            % Essas informações serão preenchidas, caso esteja sendo lido um arquivo 
            % MAT (de projeto) no método "read" da classe "class.specData".
            app.report_ProjectName.Value = '';

            % Reinicia a variável, caso não vazia...
            if ~isempty(app.specData)
                delete(app.specData)
                app.specData = class.specData.empty;
            end
           
            builtinDialog = [];
            try
                [app.specData, builtinDialog] = read(app.specData, app.metaData, app);

                % Constroi a árvore de fluxos espectrais, deixando selecionado
                % o primeiro dos fluxos. E constroi a árvore de fluxos espectrais
                % que eventualmente foram incluídos em um projeto.
                play_TreeBuilding(app)
                app.play_Tree.SelectedNodes = app.play_Tree.Children(1).Children(1);
                report_TreeBuilding(app)
    
                % Habilita botões do menu principal - PLAYBACK, REPORT e MISC -
                % abrindo programaticamente o modo PLAYBACK.
                set(findobj(app.menu_Grid, 'Type', 'uistatebutton'), 'Enable', 1)
                app.menu_Button2.Value = 1;
                menu_mainButtonPushed(app, struct('Source', app.menu_Button2, 'PreviousValue', false))
    
                % Desabilita botão, inviabilizando leitura do mesmo conjunto de
                % dados.
                app.file_SpecReadButton.Visible = 0;

            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
                file_DataReaderError(app)
            end

            delete(builtinDialog)

        end

        % Selection changed function: file_Tree
        function file_TreeSelectionChanged(app, event)
            
            if ~isempty(app.metaData)
                % Caso seja selecionado apenas um nó, apresenta-se os
                % metadados relacionados, além de habilitar os botões do 
                % toolbar.
                if isscalar(app.file_Tree.SelectedNodes)
                    idxFile   = app.file_Tree.SelectedNodes.NodeData.idx1;
                    idxThread = app.file_Tree.SelectedNodes.NodeData.idx2;

                    if ~isequal(app.file_Tree.UserData.previousSelectedFileIndex, idxFile)
                        app.file_Tree.UserData.previousSelectedFileIndex = idxFile;

                        collapse(app.file_Tree)                        
                        expand(app.file_Tree.Children(idxFile), 'all')
                        scroll(app.file_Tree, app.file_Tree.SelectedNodes)
                    end

                    fileSummary = fcn.SelectedNodeSummary(app, 'app.metaData', {idxFile, idxThread});
                    app.file_Metadata.HTMLSource = textFormatGUI.struct2PrettyPrintList(fileSummary, 'delete');
                else
                    app.file_Metadata.HTMLSource = ' ';
                end

            else
                % Desabilita o painel de metadados...
                app.file_Metadata.HTMLSource   = ' ';
            end
            
        end

        % Selection changed function: file_FilteringTypePanel
        function file_FilteringTypeChanged(app, event)

            switch app.file_FilteringTypePanel.SelectedObject
                case app.file_FilteringType1; app.file_panelGrid.RowHeight(6:8) = {22,0,0};
                case app.file_FilteringType2; app.file_panelGrid.RowHeight(6:8) = {0,22,0};
                case app.file_FilteringType3; app.file_panelGrid.RowHeight(6:8) = {0,0,22};
            end

        end

        % Image clicked function: file_FilteringAdd
        function file_FilteringAddClicked(app, event)
            
            focus(app.file_FilteringTree)

            switch app.file_FilteringTypePanel.SelectedObject
                case app.file_FilteringType1                                % Faixa de frequência
                    if isempty(app.file_FilteringType1_Frequency.Value)
                        return
                    end
                    newFilterText = sprintf('FREQUÊNCIA: %s', app.file_FilteringType1_Frequency.Value);
                    
                case app.file_FilteringType2                                % ID
                    if isempty(app.file_FilteringType2_ID.Value)
                        return
                    end
                    newFilterText = sprintf('ID: %s', app.file_FilteringType2_ID.Value);
                    
                case app.file_FilteringType3                                % Descrição
                    app.file_FilteringType3_Description.Value = upper(strtrim(app.file_FilteringType3_Description.Value));
                    
                    if isempty(app.file_FilteringType3_Description.Value)
                        return
                    else
                        newFilterText = sprintf('DESCRIÇÃO: "%s"', app.file_FilteringType3_Description.Value);
                    end
            end

            hComponents = allchild(app.file_FilteringTree);
            if ~isempty(hComponents) && ismember(newFilterText, {hComponents.Text})
                return
            end

            uitreenode(app.file_FilteringTree, 'Text',        newFilterText, ...
                                               'ContextMenu', app.file_ContextMenu_Tree2);
            file_TreeBuilding(app)

        end

        % Menu selected function: file_ContextMenu_delTree1Node
        function file_ContextMenu_delTree1NodeSelected(app, event)
            % <ReviewNote>EMD - 14/08/2024</ReviewNote>
            idxTable = table('Size', [0, 3],                                ...
                             'VariableTypes', {'double', 'double', 'cell'}, ...
                             'VariableNames', {'level', 'idx1', 'idx2'});

            for ii = 1:numel(app.file_Tree.SelectedNodes)
                idx = find(idxTable.idx1 == app.file_Tree.SelectedNodes(ii).NodeData.idx1, 1);
                if isempty(idx)
                    idxTable(end+1,:)   = {app.file_Tree.SelectedNodes(ii).NodeData.level, app.file_Tree.SelectedNodes(ii).NodeData.idx1, {app.file_Tree.SelectedNodes(ii).NodeData.idx2}};
                else
                    idxTable(idx,[1,3]) = {min([idxTable{idx,1}, app.file_Tree.SelectedNodes(ii).NodeData.level]), {unique([cell2mat(idxTable{idx,3}), app.file_Tree.SelectedNodes(ii).NodeData.idx2])}};
                end
            end

            idxTable = sortrows(idxTable, 'idx1');
            for kk = height(idxTable):-1:1
                idx1 = idxTable.idx1(kk);
                idx2 = idxTable.idx2{kk};

                switch idxTable.level(kk)
                    case 1
                        delete(app.metaData(idx1))
                        app.metaData(idx1) = [];

                    otherwise
                        if isequal(idxTable.idx2{kk}, 1:numel(app.metaData(idx1).Data))
                            delete(app.metaData(idx1))
                            app.metaData(idx1) = [];
                        else
                            delete(app.metaData(idx1).Data(idx2))
                            app.metaData(idx1).Data(idx2)    = [];
                            app.metaData(idx1).Samples(idx2) = [];
                            app.metaData(idx1).Memory        = EstimatedMemory(app.metaData, idx1);
                        end
                end
            end
            
            file_TreeBuilding(app)

        end

        % Menu selected function: file_ContextMenu_delTree2Node
        function file_ContextMenu_delTree2NodeSelected(app, event)
            
            if ~isempty(app.file_FilteringTree.SelectedNodes)
                delete(app.file_FilteringTree.SelectedNodes)
                file_TreeBuilding(app)
            end

        end

        % Image clicked function: play_ControlsTab1Image, 
        % ...and 4 other components
        function play_TabGroupVisibility(app, event)

            switch event.Source
                case app.play_ControlsTab1Image
                    app.play_ControlsGrid.RowHeight(2:2:12) = {'1x',0,0,0,0,0};
                
                case app.play_ControlsTab2Image
                    app.play_ControlsGrid.RowHeight(2:2:12) = {0,'1x',0,0,0,0};
                
                case app.play_ControlsTab3Image
                    app.play_ControlsGrid.RowHeight(2:2:12) = {0,0,'1x',0,0,0};
                
                case app.report_ControlsTab1Image
                    app.play_ControlsGrid.RowHeight(2:2:12) = {0,0,0,'1x',0,0};
                    set(findobj(app.play_toolGrid, 'Tag', 'FISCALIZA'), Enable=0)
                
                case app.report_ControlsTab2Image
                    app.play_ControlsGrid.RowHeight(2:2:12) = {0,0,0,0,'1x',0};
                    fiscalizaLibConnection.report_ToolbarStatus(app)
                
                case app.report_ControlsTab2Image
                    app.play_ControlsGrid.RowHeight(2:2:12) = {0,0,0,0,0,'1x'};
                    fiscalizaLibConnection.report_StaticButtonPushed(app, event)
            end

        end

        % Image clicked function: play_TreePanelVisibility, play_TreeSort, 
        % ...and 2 other components
        function play_PanelsVisibility(app, event)
            
            switch event.Source
                %---------------------------------------------------------%
                case app.play_TreePanelVisibility
                    switch app.play_TreePanelVisibility.UserData.Height
                        case '0x'; app.play_TreePanelVisibility.UserData.Height = '1x';
                        case '1x'; app.play_TreePanelVisibility.UserData.Height = '0x';
                    end
                    play_TreeSecundaryPanelVisibility(app)

                %---------------------------------------------------------%
                case app.play_TreeSort
                    if app.plotFlag
                        msgWarning = 'Necessário interromper o playback antes de reordernar os fluxos espectrais...';
                        appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                        return
                    end

                    presentValue   = strcmp({'Receiver+ID', 'Receiver+Frequency'}, app.play_TreeSort.UserData);
                    possibleValue  = char(setdiff({'Receiver+ID', 'Receiver+Frequency'}, app.play_TreeSort.UserData));
                    tempDictionary = dictionary([true false], ["(ATUAL)", ""]);

                    msgQuestion    = sprintf(['Os fluxos espectrais podem ser ordenadas das formas:\n' ...
                                              '&thinsp;&thinsp;(a) Receiver+ID %s\n'                   ...
                                              '&thinsp;&thinsp;(b) Receiver+Frequency %s\n\n'          ...
                                              'Confirma a troca, ordenando os fluxos por "<b>%s</b>"?!?'], tempDictionary(presentValue(1)), tempDictionary(presentValue(2)), possibleValue);
                    userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                    if userSelection == "Não"
                        return
                    end
                    app.play_TreeSort.UserData = possibleValue;
                    app.specData = sort(app.specData, possibleValue);

                    play_TreeBuilding(app)
                    app.play_Tree.SelectedNodes = app.play_Tree.Children(1).Children(1);
                    report_TreeBuilding(app)

                %---------------------------------------------------------%
                case app.tool_LayoutLeft
                    if app.play_Grid.ColumnWidth{1}
                        app.play_Grid.ColumnWidth{1} = 0;
                        app.tool_LayoutLeft.ImageSource = 'ArrowRight_32.png';
                    else
                        app.play_Grid.ColumnWidth{1} = 325;
                        app.tool_LayoutLeft.ImageSource = 'ArrowLeft_32.png';
                    end

                case app.tool_LayoutRight
                    if app.play_Grid.ColumnWidth{5}
                        app.play_Grid.ColumnWidth{5} = 0;
                        app.tool_LayoutRight.ImageSource = 'ArrowLeft_32.png';
                    else
                        app.play_Grid.ColumnWidth{5} = 325;
                        app.tool_LayoutRight.ImageSource = 'ArrowRight_32.png';
                    end
            end

            if app.play_Tree.Enable
                focus(app.play_Tree)
            else
                focus(app.jsBackDoor)
            end

        end

        % Selection changed function: play_Tree
        function play_TreeSelectionChanged(app, event)
            
            idx = [];
            if ~isempty(app.play_Tree.SelectedNodes)
                idx = unique([app.play_Tree.SelectedNodes.NodeData]);
            end

            if isscalar(idx) || isempty(app.play_PlotPanel.UserData) || ~isvalid(app.play_PlotPanel.UserData)
                idx = idx(1);
                
                % Garante que apenas ficará selecionado um único nó da árvore.
                % Posteriormente, ajusta visualização da árvore, expandindo
                % os nós relacionados ao nó selecionado.
                if isempty(app.play_PlotPanel.UserData) || ~isvalid(app.play_PlotPanel.UserData) || ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                    play_TreeCollapseStyle(app)
                end                

                % Em relação ao FLUXO ANTERIORMENTE SELECIONADO, atualiza-se
                % o controle de customização do playback, caso habilitado.
                if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData) && ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                    play_CustomizationValueChanged(app)
                end

                % Ao plotar dados relacionados a um fluxo de espectro, salva-se
                % o handle do nó selecionado da árvore na propriedade "UserData"
                % do painel app.play_PlotPanel. A propriedade "NodeData" deste 
                % nó armazena o índice do app.specData. Se for alterada a seleção 
                % da árvore, e isso resultar na escolha de um único fluxo de 
                % espectro, atualiza-se o plot.
                if app.plotFlag
                    if ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                        app.plotFlag = -1;
                    end
                else
                    if isempty(app.play_PlotPanel.UserData) || ~isvalid(app.play_PlotPanel.UserData) || ~isequal(app.play_PlotPanel.UserData.NodeData, idx)
                        plot_startupFcn(app, idx)
                        plot_Draw(app, idx)
                    end
                end
            end

            % Aspectos relacionados aos outros modos - REPORT (app.menu_Button3) e EDIT (app.menu_Button4).
            if app.menu_Button3.Value
                app.report_Tree.SelectedNodes = [];
                
                if exist('event', 'var') && (isequal(event.Source, app.play_Tree) || ismember(event.Source, {'menu_LayoutControl', 'report_TreeBuilding'}))
                    report_Algorithms(app, app.play_PlotPanel.UserData.NodeData)
                end

            elseif app.menu_Button4.Value
                edit_gpsLayout(app, '')
                edit_unitLevelLayout(app)
            end
            drawnow
            
        end

        % Image clicked function: axesTool_Average, axesTool_DataTip, 
        % ...and 7 other components
        function play_AxesToolbarCallbacks(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;

            switch event.Source
                case app.axesTool_RestoreView
                    plot.axes.Interactivity.CustomRestoreViewFcn(app.UIAxes1, [app.UIAxes2, app.UIAxes3], app)

                case app.axesTool_Pan
                    app.axesTool_Pan.UserData = ~app.axesTool_Pan.UserData;
                    if app.axesTool_Pan.UserData
                        app.axesTool_Pan.ImageSource = 'Pan_32Filled.png';
                        if app.axesTool_DataTip.UserData
                            play_AxesToolbarCallbacks(app, struct('Source', app.axesTool_DataTip))
                        end
                    else
                        app.axesTool_Pan.ImageSource = 'Pan_32.png';
                    end

                    plot.axes.Interactivity.CustomPanFcn(struct('Value', app.axesTool_Pan.UserData), app.UIAxes1, [app.UIAxes2, app.UIAxes3]);

                case app.axesTool_DataTip
                    app.axesTool_DataTip.UserData = ~app.axesTool_DataTip.UserData;
                    if app.axesTool_DataTip.UserData
                        app.axesTool_DataTip.ImageSource = 'DataTip_22Filled.png';
                        if app.axesTool_Pan.UserData
                            play_AxesToolbarCallbacks(app, struct('Source', app.axesTool_Pan))
                        end
                    else
                        app.axesTool_DataTip.ImageSource = 'DataTip_22.png';
                    end

                    plot.axes.Interactivity.DataCursorMode(app.UIFigure, app.axesTool_DataTip.UserData)

                case {app.axesTool_MinHold, app.axesTool_Average, app.axesTool_MaxHold}
                    event.Source.UserData.Value = ~event.Source.UserData.Value;
                    if event.Source.UserData.Value
                        event.Source.ImageSource = event.Source.UserData.ImageSource{1};

                        hObject = plot.draw2D.OrdinaryLine(app.UIAxes1, app.bandObj, idx, event.Source.Tag);
                        plot.datatip.Template(hObject, 'Frequency+Level', app.bandObj.LevelUnit)
                        plot.axes.StackingOrder.execute(app.UIAxes1, app.bandObj.Context)
            
                        eval(sprintf('app.h%s = hObject;', event.Source.Tag));

                    else
                        event.Source.ImageSource = event.Source.UserData.ImageSource{2};
                        plot_deleteLines(app, event.Source.Tag)
                    end

                case app.axesTool_Persistance
                    app.axesTool_Persistance.UserData.Value = ~app.axesTool_Persistance.UserData.Value;
                    if app.axesTool_Persistance.UserData.Value
                        app.play_RadioButton_Persistance.Value = 1;
                        play_ControlsPanelSelectionChanged(app)

                        prePlot_updatingGeneralSettings(app)
                        prePlot_updatingCustomProperties(app, idx)
                        plot_Draw_Persistance(app, 'Creation', idx)
                    else
                        plot_Draw_Persistance(app, 'Delete', -1)
                    end

                case app.axesTool_Occupancy
                    app.axesTool_Occupancy.UserData.Value = ~app.axesTool_Occupancy.UserData.Value;
                    if app.axesTool_Occupancy.UserData.Value
                        app.play_RadioButton_Occupancy.Value = 1;
                        play_ControlsPanelSelectionChanged(app)

                        occIndex = play_OCCIndex(app, idx, 'PLAYBACK/REPORT');
                        plot.old_OCC(app, idx, 'Creation', occIndex)
                    else
                        plot.old_OCC(app, idx, 'Delete', -1)
                    end
                    play_OCCLayoutVisibility(app, app.bandObj.LevelUnit)

                    plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_Occupancy.UserData.Value, app.axesTool_Waterfall.UserData.Value)
                    plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_Occupancy.UserData.Value, app.axesTool_Waterfall.UserData.Value, app.play_LayoutRatio)

                case app.axesTool_Waterfall
                    app.axesTool_Waterfall.UserData.Value = ~app.axesTool_Waterfall.UserData.Value;
                    if app.axesTool_Waterfall.UserData.Value
                        if isempty(app.hWaterfall)
                            plot_Draw_Waterfall(app, idx)
                        else
                            play_Layout_WaterfallPanel(app)
                        end

                        app.play_RadioButton_Waterfall.Value = 1;
                        play_ControlsPanelSelectionChanged(app)
                    else
                        play_Layout_WaterfallPanel(app)
                    end                    

                    plot.axes.Layout.XLabel([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_Occupancy.UserData.Value, app.axesTool_Waterfall.UserData.Value)
                    plot.axes.Layout.RatioAspect([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.axesTool_Occupancy.UserData.Value, app.axesTool_Waterfall.UserData.Value, app.play_LayoutRatio)
            end
            drawnow

        end

        % Image clicked function: tool_LoopControl, tool_Play
        function play_PlaybackToolbarButtonCallback(app, event)
            
            switch event.Source
                case app.tool_Play
                    idx = [];
                    if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                        idx = app.play_PlotPanel.UserData.NodeData;
                    end
        
                    if idx && ~app.plotFlag                        
                        app.plotFlag = 1;
                        app.tool_Play.ImageSource = 'stop_32.png';                
                        plot_mainLoop(app, idx, numel(app.specData(idx).Data{1}))
        
                    else
                        app.plotFlag = 0;
                        app.tool_Play.ImageSource = 'play_32.png';
                    end

                %---------------------------------------------------------%
                case app.tool_LoopControl
                    switch app.tool_LoopControl.Tag
                        case 'loop';   set(app.tool_LoopControl, Tag='direct', ImageSource='playbackStraight_32Blue.png')
                        case 'direct'; set(app.tool_LoopControl, Tag='loop',   ImageSource='playbackLoop_32Blue.png')
                    end
            end

        end

        % Value changing function: tool_TimestampSlider
        function tool_TimestampSliderValueChanging(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            nSweeps = app.bandObj.nSweeps;
            
            app.idxTime = round(event.Value/100 * nSweeps);
            if app.idxTime < 1
                app.idxTime = 1;
            elseif app.idxTime > nSweeps
                app.idxTime = nSweeps;
            end

            if ~app.plotFlag
                app.hClearWrite.YData = app.specData(idx).Data{2}(:, app.idxTime)';
    
                for ii = 1:numel(app.hEmissionMarkers)
                    app.hEmissionMarkers(ii).Position(2) = app.hClearWrite.YData(app.hClearWrite.MarkerIndices(ii));
                end
                
                plot_Draw_Persistance(app, 'Update', idx)

                if app.axesTool_Waterfall.UserData.Value && ~isempty(app.hWaterfallTime) && strcmp(app.play_Waterfall_Timeline.Value, 'on')
                    plot.draw2D.OrdinaryLineUpdate(app.hWaterfallTime, app.bandObj, idx, 'WaterfallTime');
                end

                app.tool_TimestampLabel.Text = sprintf('%d de %d\n%s', app.idxTime, nSweeps, app.specData(idx).Data{1}(app.idxTime));
            end
            
        end

        % Value changed function: play_LayoutRatio
        function play_ControlButtonPushed(app, event)

            plot.axes.Layout.Visibility([app.UIAxes1, app.UIAxes2, app.UIAxes3], app.play_LayoutRatio.Value, app.bandObj.Context)

        end

        % Value changed function: play_LineVisibility
        function play_LineVisibilityValueChanged(app, event)
            
            app.General.Plot.ClearWrite.Visible = app.play_LineVisibility.Value;

            try
                set(app.hClearWrite,      'Visible', app.play_LineVisibility.Value)
                set(app.hEmissionMarkers, 'Visible', app.play_LineVisibility.Value)
            catch
            end

        end

        % Value changed function: play_TraceIntegration
        function play_TraceIntegrationValueChanged(app, event)

            app.General.Integration.Trace = str2double(event.Value);

            if isinf(app.General.Integration.Trace) || strcmp(event.PreviousValue, 'Inf')
                if ~isempty(app.hMinHold)
                    plot_deleteLines(app, 'MinHold')
                    play_AxesToolbarCallbacks(app, struct('Source', app.axesTool_MinHold))
                end
    
                if ~isempty(app.hAverage)
                    plot_deleteLines(app, 'Average')
                    play_AxesToolbarCallbacks(app, struct('Source', app.axesTool_Average))
                end
    
                if ~isempty(app.hMaxHold)
                    plot_deleteLines(app, 'MaxHold')
                    play_AxesToolbarCallbacks(app, struct('Source', app.axesTool_MaxHold))
                end
            end

        end

        % Value changed function: play_Limits_xLim1, play_Limits_xLim2
        function play_xLimitsValueChanged(app, event)
            
            FreqRange = app.play_Limits_xLim2.Value - app.play_Limits_xLim1.Value;
            if FreqRange > 0
                app.UIAxes1.XLim = [app.play_Limits_xLim1.Value, app.play_Limits_xLim2.Value];
            else
                switch event.Source.Tag
                    case 'FreqStart'; app.play_Limits_xLim1.Value = event.PreviousValue;
                    case 'FreqStop';  app.play_Limits_xLim2.Value = event.PreviousValue;
                end
            end

        end

        % Value changed function: play_Limits_yLim1, play_Limits_yLim2
        function play_yLimitsValueChanged(app, event)

            LevelRange = app.play_Limits_yLim2.Value - app.play_Limits_yLim1.Value;
            if LevelRange > 0
                app.UIAxes1.YLim = [app.play_Limits_yLim1.Value, app.play_Limits_yLim2.Value];
            else
                switch event.Source.Tag
                    case 'MinLevel'; app.play_Limits_yLim1.Value = event.PreviousValue;
                    case 'MaxLevel'; app.play_Limits_yLim2.Value = event.PreviousValue;
                end
            end

        end

        % Selection changed function: play_ControlsPanel
        function play_ControlsPanelSelectionChanged(app, event)
            
            switch app.play_ControlsPanel.SelectedObject
                case app.play_RadioButton_Persistance; app.play_ControlsTab1Info.RowHeight(5:7) = {'1x',0,0};
                case app.play_RadioButton_Occupancy;   app.play_ControlsTab1Info.RowHeight(5:7) = {0,'1x',0};
                case app.play_RadioButton_Waterfall;   app.play_ControlsTab1Info.RowHeight(5:7) = {0,0,'1x'};
            end
            
        end

        % Value changed function: play_Customization
        function play_CustomizationValueChanged(app, event)
            
            if ~isempty(app.play_PlotPanel.UserData) && isvalid(app.play_PlotPanel.UserData)
                idx = app.play_PlotPanel.UserData.NodeData;
                prePlot_updatingCustomProperties(app, idx)

                if app.play_Customization.Value
                    [ParentTag, DataIndex] = plot.datatip.Search(app.UIFigure);
                    app.specData(idx).UserData.customPlayback.Parameters.Datatip = struct('ParentTag', ParentTag, 'DataIndex', DataIndex);    
                end            
            end            
            
        end

        % Callback function: play_Persistance_Colormap, 
        % ...and 4 other components
        function play_Persistance_Callbacks(app, event)
            
            % Os callbacks aqui descritos são aplicáveis apenas se estiver
            % habilitado o plot Persistance.
            if ~app.axesTool_Persistance.UserData.Value
                return
            end

            switch event.Source
                case app.play_Persistance_Interpolation
                    app.hPersistanceObj.handle.Interpolation = app.play_Persistance_Interpolation.Value;

                case app.play_Persistance_WindowSize
                    idx = app.play_PlotPanel.UserData.NodeData;

                    plot_Draw_Persistance(app, 'Delete', -1)

                    prePlot_updatingGeneralSettings(app)
                    prePlot_updatingCustomProperties(app, idx)
                    plot_Draw_Persistance(app, 'Creation', idx)

                case app.play_Persistance_Transparency
                    app.hPersistanceObj.handle.CData(isnan(app.hPersistanceObj.handle.CData)) = 0; 
                    app.hPersistanceObj.handle.AlphaData = double(logical(app.hPersistanceObj.handle.CData)) * app.play_Persistance_Transparency.Value;

                case app.play_Persistance_Colormap
                    plot.axes.Colormap(app.UIAxes1, app.play_Persistance_Colormap.Value)

                case app.play_Persistance_cLim_Mode
                    app.UIAxes1.CLimMode = 'auto';
                    app.play_Persistance_cLim1.Value = app.UIAxes1.CLim(1);
                    app.play_Persistance_cLim2.Value = app.UIAxes1.CLim(2);

                    app.UIAxes1.UserData.CLimMode = 'auto';
            end                
            drawnow
            
        end

        % Value changed function: play_Persistance_cLim1, 
        % ...and 1 other component
        function play_Persistance_cLimValueChanged(app, event)

            cLim = [app.play_Persistance_cLim1.Value, app.play_Persistance_cLim2.Value];
            if issorted(cLim, 'strictascend')
                app.UIAxes1.CLim = cLim;
                app.UIAxes1.UserData.CLimMode = 'manual';
            else
                switch event.Source
                    case app.play_Persistance_cLim1; app.play_Persistance_cLim1.Value = event.PreviousValue;
                    case app.play_Persistance_cLim2; app.play_Persistance_cLim2.Value = event.PreviousValue;
                end
            end

        end

        % Callback function: play_Waterfall_Colormap, 
        % ...and 5 other components
        function play_Waterfall_Callbacks(app, event)
            
            % Os callbacks aqui descritos são aplicáveis apenas se estiver
            % habilitado o plot Waterfall.
            if ~app.axesTool_Waterfall.UserData.Value
                return
            end

            app.progressDialog.Visible = 'visible';

            idx = app.play_PlotPanel.UserData.NodeData;
            switch event.Source
                %---------------------------------------------------------%
                case {app.play_Waterfall_Fcn, app.play_Waterfall_Decimation}
                    prePlot_updatingGeneralSettings(app)
                    prePlot_updatingCustomProperties(app, idx)

                    [~, ~, XData, YData] = plot.datatip.Search(app.UIAxes3);
                    app.hWaterfall = plot.draw3D.Waterfall('Delete', app.hWaterfall);
                    plot_Draw_Waterfall(app, idx)

                    if ~isempty(XData)
                        if event.Source == app.play_Waterfall_Fcn
                            for ii = 1:numel(YData)
                                switch event.Value
                                    case 'image'
                                        YData{ii} = Timestamp2idxTime(app.bandObj, idx, YData{ii});
                                    case 'mesh'
                                        YData{ii} = idxTime2Timestamp(app.bandObj, idx, YData{ii});
                                end
                            end
                        end

                        dtConfig = struct('XData', XData, 'YData', YData);
                        dtParent = app.hWaterfall;
                        plot.datatip.Create('redrawWaterfall', dtConfig, dtParent)                        
                    end

                %---------------------------------------------------------%
                case app.play_Waterfall_MeshStyle
                    app.hWaterfall.MeshStyle = app.play_Waterfall_MeshStyle.Value;

                %---------------------------------------------------------%
                case app.play_Waterfall_Timeline
                    switch app.play_Waterfall_Timeline.Value
                        case 'on'
                            prePlot_updatingGeneralSettings(app)
                            prePlot_updatingCustomProperties(app, idx)

                            app.hWaterfallTime = plot.draw2D.OrdinaryLine(app.UIAxes3, app.bandObj, idx, 'WaterfallTime');
                            plot.axes.StackingOrder.execute(app.UIAxes3, app.bandObj.Context)

                        case 'off'
                            if ~isempty(app.hWaterfallTime)
                                delete(app.hWaterfallTime) 
                                app.hWaterfallTime = [];
                            end
                    end

                %---------------------------------------------------------%
                case app.play_Waterfall_Colormap
                    plot.axes.Colormap(app.UIAxes3, app.play_Waterfall_Colormap.Value)

                %---------------------------------------------------------%
                case app.play_Waterfall_cLim_Mode
                    % Para que o eixo volte às configurações automáticas de
                    % CLim, e considerando como foi construída a classe Band, 
                    % em especial o seu método Limits, apaga-se temporariamente 
                    % a informação de customização... 
                    app.specData(idx).UserData.customPlayback = struct('Type', 'auto', 'Parameters', []);
                    
                    prePlot_updatingRestoreView(app, idx)
                    app.UIAxes3.CLim = app.restoreView(3).cLim;
                    app.UIAxes3.UserData.CLimMode = 'auto';

                    play_Layout_WaterfallPanel(app)

                    % E após os ajustes do eixo e do painel de Waterfall, 
                    % recria-se a informação de customização, caso aplicável.
                    prePlot_updatingGeneralSettings(app)
                    prePlot_updatingCustomProperties(app, idx)
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Value changed function: play_Waterfall_Colorbar
        function play_Waterfall_ColorbarValueChanged(app, event)
            
            plot.axes.Colorbar(app.UIAxes3, app.play_Waterfall_Colorbar.Value)
            
        end

        % Value changed function: play_Waterfall_cLim1, 
        % ...and 1 other component
        function play_Waterfall_cLimValueChanged(app, event)

            cLim = [app.play_Waterfall_cLim1.Value, app.play_Waterfall_cLim2.Value];
            if issorted(cLim, 'strictascend')
                app.UIAxes3.CLim = cLim;
                app.UIAxes3.UserData.CLimMode = 'manual';
                
            else
                switch event.Source
                    case app.play_Waterfall_cLim1; app.play_Waterfall_cLim1.Value = event.PreviousValue;
                    case app.play_Waterfall_cLim2; app.play_Waterfall_cLim2.Value = event.PreviousValue;
                end
            end
            
        end

        % Value changed function: play_OCC_IntegrationTime, 
        % ...and 8 other components
        function play_Occupancy_Callbacks(app, event)
            
            play_OCCNewPlot(app)
            
        end

        % Selection changed function: play_Channel_RadioGroup
        function play_Channel_RadioGroupSelectionChanged(app, event)
            
            switch app.play_Channel_RadioGroup.SelectedObject
                case app.play_Channel_File
                    app.play_ControlsTab2Info.RowHeight(3:4) = {0, 80};

                otherwise
                    app.play_ControlsTab2Info.RowHeight(3:4) = {210, 0};

                    switch app.play_Channel_RadioGroup.SelectedObject
                        case app.play_Channel_ReferenceList
                            app.play_Channel_ListUpdate.Visible  = 1;
                            app.play_Channel_Grid.RowHeight(2:3) = {22,0};
                            
                            set(findobj(app.play_Channel_Grid, 'Type', 'uinumericeditfield'), 'Enable', 1, 'Editable', 0)
                            play_Channel_AutomaticChannelListValueChanged(app)
        
                        case app.play_Channel_Multiples
                            app.play_Channel_ListUpdate.Visible  = 0;
                            app.play_Channel_Grid.RowHeight(2:3) = {0,22};
                            
                            set(findobj(app.play_Channel_Grid, 'Type', 'uinumericeditfield'), 'Enable', 1, 'Editable', 1)
                            app.play_Channel_nChannels.Editable  = 0;
                            app.play_Channel_Class.Items         = app.channelObj.FindPeaks.Name;
        
                        case app.play_Channel_Single
                            app.play_Channel_ListUpdate.Visible  = 0;
                            app.play_Channel_Grid.RowHeight(2:3) = {0,22};                            
                            
                            set(app.play_Channel_nChannels,                 'Editable', 0, 'Value',  1)
                            set(app.play_Channel_FirstChannel, 'Enable', 1, 'Editable', 1)
                            set(app.play_Channel_BW,           'Enable', 1, 'Editable', 1)
                            set(app.play_Channel_LastChannel,  'Enable', 0, 'Editable', 0)
                            set(app.play_Channel_StepWidth,    'Enable', 0, 'Editable', 0, 'Value', -1)                            
                            app.play_Channel_Class.Items         = app.channelObj.FindPeaks.Name;
                            play_Channel_FirstChannelValueChanged(app)
                    end
            end
            
        end

        % Image clicked function: play_Channel_ListUpdate
        function play_Channel_AutomaticChannelListUpdate(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            app.specData(idx).UserData.channelLibIndex = FindRelatedBands(app.channelObj, app.specData(idx));
            play_Channel_TreeBuilding(app, idx, 'play_Channel_ListUpdateImageClicked')

        end

        % Value changed function: play_Channel_List
        function play_Channel_AutomaticChannelListValueChanged(app, event)

            idxChannel   = str2double(extractBefore(app.play_Channel_List.Value, ': '));

            FirstChannel = app.channelObj.Channel(idxChannel).FirstChannel;
            LastChannel  = app.channelObj.Channel(idxChannel).LastChannel;
            StepWidth    = app.channelObj.Channel(idxChannel).StepWidth;

            app.play_Channel_FirstChannel.Value = FirstChannel;
            app.play_Channel_LastChannel.Value  = LastChannel;
            app.play_Channel_StepWidth.Value    = max(-1, StepWidth * 1000);
            app.play_Channel_BW.Value           = max(-1, app.channelObj.Channel(idxChannel).ChannelBW * 1000);
            app.play_Channel_Class.Items        = {app.channelObj.Channel(idxChannel).FindPeaksName};

            if ~isempty(app.channelObj.Channel(idxChannel).FreqList)
                FreqList = app.channelObj.Channel(idxChannel).FreqList;
            else
                FreqList = FirstChannel:StepWidth:LastChannel;
            end
            app.play_Channel_nChannels.Value    = numel(FreqList);
            play_ChannelListSample(app, FreqList)
            
        end

        % Value changed function: play_Channel_FirstChannel, 
        % ...and 2 other components
        function play_Channel_FirstChannelValueChanged(app, event)
            
            FreqList = [];

            switch app.play_Channel_RadioGroup.SelectedObject
                case app.play_Channel_Multiples
                    if (app.play_Channel_LastChannel.Value >= app.play_Channel_FirstChannel.Value) && (app.play_Channel_StepWidth.Value > 0)
                        FreqList = app.play_Channel_FirstChannel.Value:app.play_Channel_StepWidth.Value/1000:app.play_Channel_LastChannel.Value;                        
                        app.play_Channel_nChannels.Value = numel(FreqList);
                    else
                        app.play_Channel_nChannels.Value = -1;
                    end

                case app.play_Channel_Single
                    app.play_Channel_LastChannel.Value = app.play_Channel_FirstChannel.Value;
                    FreqList = app.play_Channel_FirstChannel.Value;
            end

            play_ChannelListSample(app, FreqList)
            
        end

        % Selection changed function: play_Channel_Tree
        function play_Channel_TreeSelectionChanged(app, event)

            if ~isempty(app.play_Channel_Tree.SelectedNodes)
                idx = app.play_PlotPanel.UserData.NodeData;

                srcChannel = app.play_Channel_Tree.SelectedNodes.NodeData.src;
                idxChannel = app.play_Channel_Tree.SelectedNodes.NodeData.idx;

                switch srcChannel
                    case 'channelLib'
                        app.play_Channel_ReferenceList.Value = 1;
                        play_Channel_RadioGroupSelectionChanged(app)
    
                        app.play_Channel_List.Value = app.play_Channel_List.Items{idxChannel};
                        play_Channel_AutomaticChannelListValueChanged(app)

                    case 'manual'
                        FirstChannel = app.specData(idx).UserData.channelManual(idxChannel).FirstChannel;
                        LastChannel  = app.specData(idx).UserData.channelManual(idxChannel).LastChannel;
                        StepWidth    = app.specData(idx).UserData.channelManual(idxChannel).StepWidth;
                        FreqList     = app.specData(idx).UserData.channelManual(idxChannel).FreqList;

                        if (StepWidth > 0) || ~isempty(FreqList)
                            app.play_Channel_Multiples.Value = 1;
                            if isempty(FreqList)
                                FreqList = FirstChannel:StepWidth:LastChannel;
                            end
                        else
                            app.play_Channel_Single.Value    = 1;
                            FreqList = FirstChannel;
                        end
                        play_Channel_RadioGroupSelectionChanged(app)
    
                        app.play_Channel_Name.Value          = app.specData(idx).UserData.channelManual(idxChannel).Name;
                        app.play_Channel_FirstChannel.Value  = FirstChannel;
                        app.play_Channel_LastChannel.Value   = LastChannel;
                        app.play_Channel_StepWidth.Value     = max(-1, StepWidth * 1000);
                        app.play_Channel_BW.Value            = max(-1, app.specData(idx).UserData.channelManual(idxChannel).ChannelBW * 1000);
                        set(app.play_Channel_Class, 'Items', app.channelObj.FindPeaks.Name, 'Value', app.specData(idx).UserData.channelManual(idxChannel).FindPeaksName)
            
                        app.play_Channel_nChannels.Value     = numel(FreqList);
                        play_ChannelListSample(app, FreqList)
                end

                plot_Draw_Channels(app, idx)
            end
            
        end

        % Image clicked function: play_Channel_add
        function play_Channel_addChannel(app, event)
            
            focus(app.play_Channel_Tree)

            try
                idx = app.play_PlotPanel.UserData.NodeData;
                idxThreads = idx;

                switch app.play_Channel_RadioGroup.SelectedObject
                    %-----------------------------------------------------%
                    case app.play_Channel_ReferenceList
                        chIndex = str2double(extractBefore(app.play_Channel_List.Value, ': '));

                        channel2Add   = app.channelObj.Channel(chIndex);
                        typeOfChannel = 'channelLib';

                    %-----------------------------------------------------%
                    case {app.play_Channel_Multiples, app.play_Channel_Single}
                        channelName = strtrim(app.play_Channel_Name.Value);
                        refNames    = {app.specData(idx).UserData.channelManual.Name};

                        if isempty(channelName)
                            error('winAppAnalise:play_Channel_addChannel', 'O nome de um registro de canalização não pode ser vazio.')
                        elseif ismember(channelName, refNames)
                            msgQuestion   = sprintf(['Já existe uma canalização inserida manualmente com o nome "%s". ' ...
                                                     'Deseja editar as informações desse registro?'], channelName);
                            userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                            
                            if strcmp(userSelection, 'Não')
                                return
                            end
                        end

                        FirstChannel  = app.play_Channel_FirstChannel.Value;
                        LastChannel   = app.play_Channel_LastChannel.Value;
                        channelBW     = max(0, app.play_Channel_BW.Value/1000); 
                        StepWidth     = app.play_Channel_StepWidth.Value/1000;
                        if StepWidth < 0
                            StepWidth = -1;
                        end

                        channel2Add   = struct('Name',          channelName,                                         ...
                                               'Band',          [FirstChannel-channelBW/2; LastChannel+channelBW/2], ...
                                               'FirstChannel',  FirstChannel,                                        ...
                                               'LastChannel',   LastChannel,                                         ...
                                               'StepWidth',     StepWidth,                                           ...
                                               'ChannelBW',     channelBW,                                           ...
                                               'FreqList',      [],                                                  ...
                                               'Reference',     'Inclusão manual',                                   ...
                                               'FindPeaksName', app.play_Channel_Class.Value);
                        typeOfChannel = 'manual';
    
                    %-----------------------------------------------------%
                    case app.play_Channel_File
                        [fileFullPath, fileFolder] = appUtil.modalWindow(app.UIFigure, 'uigetfile', '', {'*.json', 'appAnalise (*.json)'}, app.General.fileFolder.lastVisited);
                        if isempty(fileFullPath)
                            return
                        end
                        misc_updateLastVisitedFolder(app, fileFolder)
    
                        channel2Add   = readFileWithChannel2Add(app.channelObj, fileFullPath);

                        msgQuestion   = sprintf(['Foram extraídos os registros %s, os quais serão incluídos na lista de canais manuais do ' ...
                                                 'fluxo espectral selecionado, caso se sobreponham à faixa de frequência, substituindo '    ...
                                                 'eventuais canalizações inseridas manualmente que tenham um dos supracitados nomes.\n\n'   ...
                                                 'Deseja analisar a inclusão desses registros para os outros fluxos?'], textFormatGUI.cellstr2ListWithQuotes({channel2Add.Name}));
                        userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                        
                        if strcmp(userSelection, 'Sim')
                            idxThreads = 1:numel(app.specData);
                        end
                        typeOfChannel = 'manual';
                end

                % Valida o novo registro, incluindo-o depois, caso não retorne 
                % erro na validação.
                for ii = 1:numel(channel2Add)
                    channelCell2Add = struct2cell(channel2Add(ii));
                    checkIfNewChannelIsValid(app.channelObj, channelCell2Add{:})                    
                end
                addChannel(app.channelObj, typeOfChannel, app.specData, idxThreads, channel2Add)

                % Por fim, reescreve a árvore...
                play_Channel_TreeBuilding(app, idx, 'play_Channel_addChannel')

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end

        end

        % Menu selected function: play_Channel_ContextMenu_del
        function play_Channel_ContextMenu_delChannelSelected(app, event)
            
            % Operação realizada a partir do menu de contexto, apagando a
            % canalização selecionada.

            if ~isempty(app.play_Channel_Tree.SelectedNodes)
                idx = app.play_PlotPanel.UserData.NodeData;

                srcChannel = app.play_Channel_Tree.SelectedNodes.NodeData.src;
                idxChannel = app.play_Channel_Tree.SelectedNodes.NodeData.idx;

                switch srcChannel
                    case 'channelLib'
                        app.specData(idx).UserData.channelLibIndex = setdiff(app.specData(idx).UserData.channelLibIndex, idxChannel);
                    case 'manual'
                        app.specData(idx).UserData.channelManual(idxChannel) = [];
                end
                
                play_Channel_TreeBuilding(app, idx, 'play_Channel_ContextMenu_delChannel')
            end

        end

        % Menu selected function: play_Channel_ContextMenu_addBandLimit
        function play_Channel_ContextMenu_addBandLimitSelected(app, event)
            
            % Operação realizada a partir do menu de contexto, inserindo os
            % limites da canalização selecionada como limite de detecção de 
            % emissões.

            if ~isempty(app.play_Channel_Tree.SelectedNodes)
                idx = app.play_PlotPanel.UserData.NodeData;

                srcChannel = app.play_Channel_Tree.SelectedNodes.NodeData.src;
                idxChannel = app.play_Channel_Tree.SelectedNodes.NodeData.idx;

                switch srcChannel
                    case 'channelLib'
                        xLim1 = app.channelObj.Channel(idxChannel).Band(1);
                        xLim2 = app.channelObj.Channel(idxChannel).Band(2);
                    case 'manual'
                        xLim1 = app.specData(idx).UserData.channelManual(idxChannel).Band(1);
                        xLim2 = app.specData(idx).UserData.channelManual(idxChannel).Band(2);
                end

                xLim1 = max(xLim1, app.play_BandLimits_xLim1.Limits(1));
                xLim2 = min(xLim2, app.play_BandLimits_xLim1.Limits(2));

                app.play_BandLimits_xLim1.Value = xLim1;
                app.play_BandLimits_xLim2.Value = xLim2;

                if ~app.play_BandLimits_Status.Value
                    app.play_BandLimits_Status.Value = 1;
                    play_BandLimits_StatusValueChanged(app)
                end
                play_BandLimits_addImageClicked(app)
            end

        end

        % Menu selected function: play_Channel_ContextMenu_addEmission
        function play_Channel_ContextMenu_addEmissionSelected(app, event)
            
            % Operação realizada a partir do menu de contexto, inserindo os
            % canais da canalização selecionada como emissões.

            if ~isempty(app.play_Channel_Tree.SelectedNodes)
                idx = app.play_PlotPanel.UserData.NodeData;

                srcChannel = app.play_Channel_Tree.SelectedNodes.NodeData.src;
                idxChannel = app.play_Channel_Tree.SelectedNodes.NodeData.idx;

                switch srcChannel
                    case 'channelLib'
                        specChannel = app.channelObj.Channel(idxChannel);
                    case 'manual'
                        specChannel = app.specData(idx).UserData.channelManual(idxChannel);
                end

                newFreq  = specChannel.FreqList;
                if isempty(newFreq)
                    newFreq = (specChannel.FirstChannel:specChannel.StepWidth:specChannel.LastChannel)';
                end
                newIndex = freq2idx(app.bandObj, newFreq .* 1e+6);

                newBW    = specChannel.ChannelBW;
                if newBW < 0
                    newBW = 0;
                end
                newBW    = newBW * 1000;                                    % Em kHz
    
                NN = numel(newIndex);
                if NN
                    app.specData(idx).UserData.Emissions(end+1:end+NN,1:5) = table(newIndex, newFreq, repmat(newBW, NN, 1), true(NN, 1), repmat({jsonencode(struct('Algorithm', 'Manual'))}, NN, 1));
                    play_BandLimits_updateEmissions(app, idx, newIndex)
                    play_UpdatePeaksTable(app, idx, 'playback.AddEditOrDeleteEmission')
                end
            end

        end

        % Selection changed function: play_FindPeaks_RadioGroup
        function play_FindPeaks_RadioGroupSelectionChanged(app, event)
            
            switch app.play_FindPeaks_RadioGroup.SelectedObject
                case app.play_FindPeaks_auto
                    play_FindPeaks_AlgorithmValueChanged(app)

                case app.play_FindPeaks_File
                    app.play_ControlsTab3Info.RowHeight(3:4) = {0,80};

                otherwise % ROI | DataTips
                    app.play_ControlsTab3Info.RowHeight(3:4) = {0,0};
            end
            
        end

        % Value changed function: play_FindPeaks_Algorithm
        function play_FindPeaks_AlgorithmValueChanged(app, event)
            
            switch app.play_FindPeaks_Algorithm.Value
                case 'FindPeaks'
                    app.play_ControlsTab3Info.RowHeight(3:4) = {174,0};
                    app.play_FindPeaks_ParametersGrid.RowHeight([3 4 7]) = {25,22,0};
                    
                    app.play_FindPeaks_prominenceLabel.Visible = 1;
                    app.play_FindPeaks_prominence.Visible      = 1;

                    app.play_FindPeaks_ClassLabel.Visible = 0;
                    app.play_FindPeaks_Class.Visible      = 0;

                case 'FindPeaks+OCC'
                    app.play_ControlsTab3Info.RowHeight(3:4) = {210,0};
                    app.play_FindPeaks_ParametersGrid.RowHeight([3 4 7]) = {0,0,88};

                    app.play_FindPeaks_prominenceLabel.Visible = 0;
                    app.play_FindPeaks_prominence.Visible      = 0;

                    app.play_FindPeaks_ClassLabel.Visible = 1;
                    app.play_FindPeaks_Class.Visible      = 1;
            end

        end

        % Selection changed function: play_FindPeaks_Tree
        function play_FindPeaks_TreeSelectionChanged(app, event)
            
            if isscalar(app.play_FindPeaks_Tree.SelectedNodes)
                idx1 = app.play_PlotPanel.UserData.NodeData;
                idx2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;

                set(app.play_FindPeaks_PeakCF, 'Value', round(app.specData(idx1).UserData.Emissions.Frequency(idx2), 3), 'Enable', 1)
                set(app.play_FindPeaks_PeakBW, 'Value', round(app.specData(idx1).UserData.Emissions.BW(idx2), 3),        'Enable', 1)

                emissionInfo = jsondecode(app.specData(idx1).UserData.Emissions.Detection{idx2});
                if isfield(emissionInfo, 'Description')
                    set(app.play_FindPeaks_Description, 'Value', emissionInfo.Description, 'Enable', 1)
                else
                    set(app.play_FindPeaks_Description, 'Value', '', 'Enable', 1)
                end

                if strcmp(app.play_FindPeaks_Tree.SelectedNodes.Icon, 'signalTruncated_32.png')
                    app.play_FindPeaks_ContextMenu_digital.Enable = 0;
                    app.play_FindPeaks_ContextMenu_analog.Enable  = 1;
                else
                    app.play_FindPeaks_ContextMenu_digital.Enable = 1;
                    app.play_FindPeaks_ContextMenu_analog.Enable  = 0;
                end
                app.play_FindPeaks_ContextMenu_search.Enable = 1;
                app.play_FindPeaks_ContextMenu_Drive.Enable  = 1;

                if exist('event', 'var')
                    plot.draw2D.ClearWrite_old(app, idx1, 'TreeSelectionChanged', [])
                end

            else
                set(app.play_FindPeaks_PeakCF,      'Value', -1, 'Enable', 0)
                set(app.play_FindPeaks_PeakBW,      'Value', -1, 'Enable', 0)
                set(app.play_FindPeaks_Description, 'Value', '', 'Enable', 0)

                set(app.play_FindPeaks_ContextMenu_edit.Children, 'Enable', 1)
                app.play_FindPeaks_ContextMenu_search.Enable = 0;
                app.play_FindPeaks_ContextMenu_Drive.Enable  = 0;
            end
            
        end

        % Image clicked function: play_FindPeaks_add
        function play_FindPeaks_addEmission(app, event)
            
            focus(app.play_FindPeaks_Tree)

            idx = app.play_PlotPanel.UserData.NodeData;
            newIndex = [];

            switch app.play_FindPeaks_RadioGroup.SelectedObject
                %---------------------------------------------------------%
                case app.play_FindPeaks_File
                    switch app.play_FindPeaks_ExternalFile.Value
                        case 'Generic (csv, txt, json, xls, xlsx)'
                            fileFormats = {'*.csv;*.txt;*.json;*.xls;*.xlsx', '(*.csv,*.txt,*.json,*.xls,*.xlsx)'};
                        case 'Romes (csv)'
                            fileFormats = {'*.csv', '(*.csv)'};
                    end

                    [fileFullPath, fileFolder, fileExt] = appUtil.modalWindow(app.UIFigure, 'uigetfile', '', fileFormats, app.General.fileFolder.lastVisited);
                    if isempty(fileFullPath)
                        return
                    end
                    misc_updateLastVisitedFolder(app, fileFolder)

                    try
                        switch fileExt
                            case '.json'
                                rawTable = struct2table(jsondecode(fileread(fileFullPath)), 'AsArray', true);
                            otherwise
                                switch app.play_FindPeaks_ExternalFile.Value
                                    case 'Generic (csv, txt, json, xls, xlsx)'
                                        rawTable = readtable(fileFullPath);
                                    case 'Romes (csv)'
                                        rawTable = fileReader.RomesCSV(fileFullPath);
                                end
                        end
                        rawTable.Properties.VariableNames = {'Frequency', 'BW', 'Description'};

                        if ~isempty(rawTable)
                            msgQuestion   = sprintf(['Foram extraídos registros de emissões centralizadas em %s.\n\nEssas emissões serão incluídas na lista ' ...
                                                     'de emissões do fluxo espectral selecionado, caso se sobreponham à faixa de frequência.\n\nDeseja '      ...
                                                     'analisar a inclusão desses registros para os outros fluxos?'],                                          ...
                                                     strjoin(string(sort(rawTable.Frequency))+" MHz", ', '));
                            userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                            if strcmp(userSelection, 'Sim')
                                idxThreads  = 1:numel(app.specData);
                                tempBandObj = class.Band('appAnalise:PLAYBACK', app);
                            else
                                idxThreads  = idx;
                                tempBandObj = app.bandObj;
                            end
                            
                            for ii = idxThreads
                                update(tempBandObj, ii);
                                tempBandTable = rawTable;

                                [newIndex, invalidIndex] = freq2idx(tempBandObj, tempBandTable.Frequency .* 1e+6, 'OnlyCheck');
                                if all(invalidIndex)
                                    continue
                                end

                                tempBandTable(invalidIndex,:) = [];
                                newIndex(invalidIndex)        = [];

                                newFreq  = tempBandTable.Frequency;         % Em MHz
                                newBW    = tempBandTable.BW;                % Em kHz
                                
                                Method   = {};
                                for jj = 1:height(tempBandTable)
                                    Method{jj,1} = jsonencode(struct('Algorithm', 'ExternalFile', 'Description', tempBandTable.Description{jj}));
                                end

                                NN = numel(newIndex);
                                if NN
                                    app.specData(ii).UserData.Emissions(end+1:end+NN,1:5) = table(newIndex, newFreq, newBW, true(numel(newIndex),1), Method);
                                    play_BandLimits_updateEmissions(app, ii, newIndex, ii==idx)
                                    play_UpdatePeaksTable(app, ii, 'playback.AddEditOrDeleteEmission')
                                end
                            end
                        end

                    catch ME
                        appUtil.modalWindow(app.UIFigure, 'warning', ME.message);
                        return
                    end

                otherwise
                    switch app.play_FindPeaks_RadioGroup.SelectedObject
                        %-------------------------------------------------%
                        case app.play_FindPeaks_auto
                            switch app.play_FindPeaks_Algorithm.Value
                                case 'FindPeaks'
                                    Attributes = struct('Algorithm',  app.play_FindPeaks_Algorithm.Value,  ...
                                                        'Fcn',        app.play_FindPeaks_Trace.Value,      ...
                                                        'NPeaks',     app.play_FindPeaks_Numbers.Value,    ...
                                                        'THR',        app.play_FindPeaks_THR.Value,        ...
                                                        'Prominence', app.play_FindPeaks_prominence.Value, ...
                                                        'Distance',   app.play_FindPeaks_distance.Value,   ...
                                                        'BW',         app.play_FindPeaks_BW.Value);
        
                                    [newIndex, newFreq, newBW, Method] = fcn.Detection_FindPeaks(app.specData, idx, Attributes);
        
                                case 'FindPeaks+OCC'
                                    Attributes = struct('Algorithm',   app.play_FindPeaks_Algorithm.Value,   ...
                                                        'Distance',    app.play_FindPeaks_distance.Value,    ...
                                                        'BW',          app.play_FindPeaks_BW.Value,          ...
                                                        'Prominence1', app.play_FindPeaks_Prominence1.Value, ...
                                                        'Prominence2', app.play_FindPeaks_Prominence2.Value, ...
                                                        'meanOCC',     app.play_FindPeaks_meanOCC.Value,     ...
                                                        'maxOCC',      app.play_FindPeaks_maxOCC.Value);
                                    
                                    [newIndex, newFreq, newBW, Method] = fcn.Detection_FindPeaksPlusOCC(app, app.specData, idx, Attributes);
                            end    
                            newBW  = newBW * 1000;

                        %-------------------------------------------------%
                        case app.play_FindPeaks_ROI
                            % Incluir uma emissõe por ROI demanda eliminar
                            % interações do plot.
                            app.play_FindPeaks_add.Enable = 0;
                            if app.axesTool_DataTip.UserData
                                play_AxesToolbarCallbacks(app, struct('Source', app.axesTool_DataTip))
                            end
                            plot.axes.Interactivity.DefaultDisable([app.UIAxes1, app.UIAxes2, app.UIAxes3])
                            drawnow
                            
                            mkrTemp  = drawrectangle(app.UIAxes1, Color=[0.40,0.73,0.88], LineWidth=1, Tag='mkrTemp');
                            app.play_FindPeaks_add.Enable = 1;
        
                            plot.axes.Interactivity.DefaultEnable([app.UIAxes1, app.UIAxes2, app.UIAxes3])
            
                            newFreq  = mkrTemp.Position(1) + mkrTemp.Position(3)/2; % Em MHz
                            newBW    = mkrTemp.Position(3) * 1000;                  % Em kHz
                            newIndex = freq2idx(app.bandObj, newFreq .* 1e+6);
                            Method   = jsonencode(struct('Algorithm', 'Manual'));
        
                            % Se for desenhada uma ROI fora dos limites do eixo,
                            % a função freq2idx retornará ela nas extremidades do 
                            % eixo. Nesse caso, exclui-se a ROI.                            
                            if ismember(newIndex, [1, app.specData(idx).MetaData.DataPoints])                    
                               delete(mkrTemp)
                               return
                            end
        
                        %-------------------------------------------------%
                        case app.play_FindPeaks_DataTips
                            hDataTips = findobj('Type', 'DataTip');
                            if isempty(hDataTips)
                                return
                            end
            
                            newFreq = [];
                            ii = 0;
                            while ii < numel(hDataTips)
                                ii = ii+1;
                                if isequal(hDataTips(ii).Parent.Parent.Parent.Parent, app.play_PlotPanel)
                                    newFreq(end+1,1) = double(round(hDataTips(ii).X, 3));   % Em MHz
                                end
                            end
                            newBW    = zeros(numel(newFreq), 1);
                            newIndex = freq2idx(app.bandObj, newFreq .* 1e+6);
                            Method   = repmat({jsonencode(struct('Algorithm', 'Manual'))}, numel(newFreq), 1);
                    end
                
                    if ischar(Method)
                        Method = {Method};
                    end
    
                    NN = numel(newIndex);
                    if NN
                        app.specData(idx).UserData.Emissions(end+1:end+NN,1:5) = table(newIndex, newFreq, newBW, true(numel(newIndex),1), Method);
                        play_BandLimits_updateEmissions(app, idx, newIndex)
                        play_UpdatePeaksTable(app, idx, 'playback.AddEditOrDeleteEmission')
                    else
                        if exist('mkrTemp', 'var')
                            delete(mkrTemp)
                        end
                    end
            end
            
            if isempty(newIndex)
                appUtil.modalWindow(app.UIFigure, 'warning', 'Não identificado pico que atenda as condições estabelecidas.');
            end
            
        end

        % Menu selected function: play_FindPeaks_ContextMenu_del
        function play_FindPeaks_delEmission(app, event)
            
            if ~isempty(app.play_FindPeaks_Tree.SelectedNodes)
                idx = app.play_PlotPanel.UserData.NodeData;
                idxEmission = [app.play_FindPeaks_Tree.SelectedNodes.NodeData];
                app.specData(idx).UserData.Emissions(idxEmission,:) = [];
                
                plot.draw2D.ClearWrite_old(app, idx, 'DeleteButtonPushed', 1)
                play_UpdatePeaksTable(app, idx, 'playback.AddEditOrDeleteEmission')
            end
            
        end

        % Value changed function: play_FindPeaks_Description, 
        % ...and 2 other components
        function play_FindPeaks_editEmission(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            idxEmission = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;

            emissionInfo = jsondecode(app.specData(idx).UserData.Emissions.Detection{idxEmission});

            switch event.Source
                case app.play_FindPeaks_PeakCF
                    if (app.play_FindPeaks_PeakCF.Value*1e+6 < app.specData(idx).MetaData.FreqStart) || ...
                       (app.play_FindPeaks_PeakCF.Value*1e+6 > app.specData(idx).MetaData.FreqStop)
                    
                       app.play_FindPeaks_PeakCF.Value = round(app.specData(idx).UserData.Emissions.Frequency(idxEmission), 3);
                       return
                    end
                    
                    newIndex = freq2idx(app.bandObj, app.play_FindPeaks_PeakCF.Value*1e+6);
                    app.play_FindPeaks_PeakCF.Value = app.bandObj.xArray(newIndex);

                    emissionInfo.Algorithm = 'Manual';                    
                    app.specData(idx).UserData.Emissions(idxEmission,[1:2, 5]) = {newIndex, app.play_FindPeaks_PeakCF.Value, jsonencode(emissionInfo, 'ConvertInfAndNaN', false)};
                    play_BandLimits_updateEmissions(app, idx, newIndex)

                case app.play_FindPeaks_PeakBW
                    emissionInfo.Algorithm = 'Manual';
                    app.specData(idx).UserData.Emissions(idxEmission,[3, 5]) = {app.play_FindPeaks_PeakBW.Value, jsonencode(emissionInfo, 'ConvertInfAndNaN', false)};
                    plot.draw2D.ClearWrite_old(app, idx, 'PeakValueChanged', idxEmission)

                case app.play_FindPeaks_Description
                    userDescription = strtrim(app.play_FindPeaks_Description.Value);
                    userDescription(cellfun(@(x) isempty(x), userDescription)) = [];
                    if isempty(userDescription)
                        userDescription = '';
                    end
                    app.play_FindPeaks_Description.Value = userDescription;
                    
                    emissionInfo.Description = userDescription;
                    app.specData(idx).UserData.Emissions.Detection{idxEmission} = jsonencode(emissionInfo, 'ConvertInfAndNaN', false);
            end

            play_UpdatePeaksTable(app, idx, 'playback.AddEditOrDeleteEmission')
            
        end

        % Menu selected function: play_FindPeaks_ContextMenu_analog, 
        % ...and 1 other component
        function play_FindPeaks_TruncatedEmission(app, event)

            idx = app.play_PlotPanel.UserData.NodeData;

            for ii = 1:numel(app.play_FindPeaks_Tree.SelectedNodes)
                idxEmission = app.play_FindPeaks_Tree.SelectedNodes(ii).NodeData;
    
                switch event.Source.Text
                    case 'Truncar frequência'
                        app.specData(idx).UserData.Emissions.isTruncated(idxEmission) = 1;
                        app.play_FindPeaks_Tree.SelectedNodes(ii).Icon = 'signalTruncated_32.png';
    
                    case 'Não truncar'
                        app.specData(idx).UserData.Emissions.isTruncated(idxEmission) = 0;
                        app.play_FindPeaks_Tree.SelectedNodes(ii).Icon = 'signalUntruncated_32.png';
                end
            end            
            play_FindPeaks_TreeSelectionChanged(app)            
            play_UpdatePeaksTable(app, idx, 'playback.AddEditOrDeleteEmission')
            
        end

        % Menu selected function: play_FindPeaks_ContextMenu_search
        function play_FindPeaks_Classification(app, event)
            
            global RFDataHub

            idx1 = app.play_PlotPanel.UserData.NodeData;
            idx2 = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;

            % Inicialmente, trunca-se a frequência da emissão selecionada, 
            % caso aplicável.            
            if app.specData(idx1).UserData.Emissions.isTruncated(idx2)
                Truncated = TruncatedFrequency(app.channelObj, app.specData(idx1), idx2);
            else
                Truncated = app.specData(idx1).UserData.Emissions.Frequency(idx2);
            end            

            % Identifica na base de dados offline (anateldb) aqueles registros 
            % que possuem a mesma frequência (tolerância: 1e-5).
            idx3 = find(abs(RFDataHub.Frequency - Truncated) <= class.Constants.floatDiffTolerance);
            if ~isempty(idx3)
                auxDistance      = deg2km(distance(app.specData(idx1).GPS.Latitude, app.specData(idx1).GPS.Longitude, RFDataHub.Latitude(idx3), RFDataHub.Longitude(idx3)));
                [Distance, idx4] = min(auxDistance);
                Description      = class.RFDataHub.Description(RFDataHub, idx3(idx4));

                if app.specData(idx1).UserData.Emissions.isTruncated(idx2)
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) foi truncada em <b>%.5f MHz</b>.\n\n'                            ...
                                   'Foram identificados <b>%d registros</b> de estações cujas frequências centrais coincidem com %.3f MHz.\n\n' ...
                                   'A estação mais próxima do local de monitoração está a <b>%.1f km</b>, tendo como descrição "<b>%s</b>"'],   ...
                                   app.specData(idx1).UserData.Emissions.Frequency(idx2), Truncated, numel(idx3), Truncated, Distance, Description);
                else
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) não foi truncada.\n\n'                                           ...
                                   'Foram identificados <b>%d registros</b> de estações cujas frequências centrais coincidem com %.3f MHz.\n\n' ...
                                   'A estação mais próxima do local de monitoração está a <b>%.1f km</b>, tendo como descrição "<b>%s</b>"'],   ...
                                   app.specData(idx1).UserData.Emissions.Frequency(idx2), numel(idx3), Truncated, Distance, Description);
                end

            else
                if app.specData(idx1).UserData.Emissions.isTruncated(idx2)
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) foi truncada em <b>%.5f MHz</b>.\n\n'           ...
                                   'Não foi identificado registro de estação cuja frequência central coincide com %.5f MHz.'], ...
                                   app.specData(idx1).UserData.Emissions.Frequency(idx2), Truncated, Truncated);
                else
                    msg = sprintf(['A frequência central da emissão (%.3f MHz) não foi truncada.\n\n'                          ...
                                   'Não foi identificado registro de estação cuja frequência central coincide com %.5f MHz.'], ...
                                   app.specData(idx1).UserData.Emissions.Frequency(idx2), Truncated);
                end
            end

            appUtil.modalWindow(app.UIFigure, 'warning', msg);

        end

        % Menu selected function: play_FindPeaks_ContextMenu_Drive
        function play_FindPeaks_DriveTest(app, event)
            
            if isempty(app.auxiliarWin2) || ~isvalid(app.auxiliarWin2)
                idx1 = app.play_PlotPanel.UserData.NodeData;
    
                msg = '';
                if ~ismember(app.specData(idx1).MetaData.DataType, [1, 2])
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas arquivos gerados pelo appColeta.';
                elseif app.specData(idx1).GPS.Count <= 1
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas fluxo de espectro que possua registrado as coordenadas geográficas de ao menos dois locais de monitoração.';
                elseif app.specData(idx1).GPS.Count ~= numel(app.specData(idx1).Data{1})
                    msg = 'A funcionalidade "DRIVE-TEST" aceita apenas fluxo de espectro cujo número de coordenadas geográficas registrado seja igual ao número de varreduras (relação um para um).';
                end

                if ~isempty(msg)
                    appUtil.modalWindow(app.UIFigure, 'warning', msg);
                    return
                end

                % app.progressDialog.Visible = 'visible';

                idx2 = app.play_FindPeaks_Tree.SelectedNodes.NodeData;
                app.auxiliarWin2 = auxApp.winDriveTest(app, idx1, idx2);

                % app.progressDialog.Visible = 'hidden';
                
            else
                figure(app.auxiliarWin2.UIFigure)
            end

        end

        % Value changed function: play_FindPeaks_maxOCC, 
        % ...and 1 other component
        function play_FindPeaks_OCCValueChanged(app, event)

            switch event.Source
                case app.play_FindPeaks_meanOCC
                    if app.play_FindPeaks_meanOCC.Value > app.play_FindPeaks_maxOCC.Value
                        app.play_FindPeaks_maxOCC.Value = app.play_FindPeaks_meanOCC.Value;
                    end

                case app.play_FindPeaks_maxOCC
                    if app.play_FindPeaks_maxOCC.Value < app.play_FindPeaks_meanOCC.Value
                        app.play_FindPeaks_meanOCC.Value = app.play_FindPeaks_maxOCC.Value;
                    end
            end

        end

        % Value changed function: play_FindPeaks_Class
        function play_FindPeaks_ClassValueChanged(app, event)
            
            idx = find(strcmp(app.channelObj.FindPeaks.Name, app.play_FindPeaks_Class.Value), 1);

            app.play_FindPeaks_distance.Value    = 1000 * app.channelObj.FindPeaks.Distance(idx);
            app.play_FindPeaks_BW.Value          = 1000 * app.channelObj.FindPeaks.BW(idx);
            app.play_FindPeaks_Prominence1.Value = app.channelObj.FindPeaks.Prominence1(idx);
            app.play_FindPeaks_Prominence2.Value = app.channelObj.FindPeaks.Prominence2(idx);
            app.play_FindPeaks_meanOCC.Value     = app.channelObj.FindPeaks.meanOCC(idx);
            app.play_FindPeaks_maxOCC.Value      = app.channelObj.FindPeaks.maxOCC(idx);
                        
        end

        % Value changed function: play_BandLimits_Status
        function play_BandLimits_StatusValueChanged(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            play_BandLimits_Layout(app, idx)

            if app.play_BandLimits_Status.Value                      && ...
                ~isempty(app.specData(idx).UserData.bandLimitsTable) && ...
                ~isempty(app.specData(idx).UserData.Emissions)

                msgQuestion   = 'Confirma a reanálise das emissões, eventualmente eliminando aquelas que não estão em uma das subfaixas sob análise?';
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                if userSelection == "Não"
                    app.play_BandLimits_Status.Value = 0;
                    play_BandLimits_Layout(app, idx)
                    return
                end

                % Identificar o índice da emissão selecionada, para o qual
                % foi desenhado um ROI no app.axes1.
                idxEmission = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;
                newIndex = app.specData(idx).UserData.Emissions.Index(idxEmission);

                play_BandLimits_updateEmissions(app, idx, newIndex)
                play_UpdatePeaksTable(app, idx, 'playback.AddEditOrDeleteEmission')
            end

            plot.draw2D.horizontalSetOfLines(app.UIAxes1, app.bandObj, idx, 'BandLimits')
            
        end

        % Image clicked function: play_BandLimits_add
        function play_BandLimits_addImageClicked(app, event)
            
            focus(app.play_BandLimits_Tree)

            idx = app.play_PlotPanel.UserData.NodeData;
            bandLimitsTable = app.specData(idx).UserData.bandLimitsTable;

            % Validações:
            if app.play_BandLimits_xLim2.Value <= app.play_BandLimits_xLim1.Value                    
                appUtil.modalWindow(app.UIFigure, 'warning', 'Subfaixa inválida.');
                return

            elseif any((bandLimitsTable.FreqStart <= app.play_BandLimits_xLim1.Value) & (bandLimitsTable.FreqStop >= app.play_BandLimits_xLim2.Value))
                appUtil.modalWindow(app.UIFigure, 'warning', 'Subfaixa já contemplada.');
                return
            end

            % Compara subfaixa a analisar inserida com os registros já existentes.
            xLim1 = app.play_BandLimits_xLim1.Value;
            xLim2 = app.play_BandLimits_xLim2.Value;

            % Passo 1 de 2
            Flag = true;
            for ii = 1:height(bandLimitsTable)
                if (xLim1 <= bandLimitsTable.FreqStart(ii)) && (xLim2 >= bandLimitsTable.FreqStop(ii))
                    bandLimitsTable(ii,:) = {xLim1, xLim2};
                    Flag = false;
                    continue
                
                elseif (xLim1 <= bandLimitsTable.FreqStart(ii)) && (xLim2 > bandLimitsTable.FreqStart(ii)) && (xLim2 < bandLimitsTable.FreqStop(ii))
                    bandLimitsTable(ii,1) = {xLim1};
                    Flag = false;
                    continue

                elseif (xLim1 > bandLimitsTable.FreqStart(ii)) && (xLim1 < bandLimitsTable.FreqStop(ii)) && (xLim2 >= bandLimitsTable.FreqStop(ii))
                    bandLimitsTable(ii,2) = {xLim2};
                    Flag = false;
                    continue
                end
            end

            if Flag
                bandLimitsTable(end+1,:) = {xLim1, xLim2};
            end
            bandLimitsTable = sortrows(bandLimitsTable, 'FreqStart');

            % Passo 2 de 2
            for ii = height(bandLimitsTable):-1:2
                if bandLimitsTable.FreqStart(ii) <= bandLimitsTable.FreqStop(ii-1)
                    bandLimitsTable(ii-1,2) = {bandLimitsTable.FreqStop(ii)};
                    bandLimitsTable(ii,:)   = [];
                end
            end

            if isempty(app.specData(idx).UserData.bandLimitsTable) && ...
              ~isempty(app.specData(idx).UserData.Emissions)

                msgQuestion   = 'Confirma a reanálise das emissões, eventualmente eliminando aquelas que não estão em uma das subfaixas sob análise?';
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                if userSelection == "Não"
                    return
                end

                % Identificar o índice da emissão selecionada, para o qual
                % foi desenhado um ROI no app.axes1.
                idxEmission = app.play_FindPeaks_Tree.SelectedNodes(1).NodeData;
                newIndex = app.specData(idx).UserData.Emissions.Index(idxEmission);

                app.specData(idx).UserData.bandLimitsTable = bandLimitsTable;
                play_BandLimits_updateEmissions(app, idx, newIndex)
                play_UpdatePeaksTable(app, idx, 'playback.AddEditOrDeleteEmission')

            else
                app.specData(idx).UserData.bandLimitsTable = bandLimitsTable;
            end

            play_BandLimits_TreeBuilding(app, idx)
            plot.draw2D.horizontalSetOfLines(app.UIAxes1, app.bandObj, idx, 'BandLimits')

        end

        % Menu selected function: play_BandLimits_ContextMenu_del
        function play_BandLimits_ContextMenu_delSelected(app, event)
            
            if ~isempty(app.play_BandLimits_Tree.SelectedNodes)
                idx = app.play_PlotPanel.UserData.NodeData;
                idxBandLimits = [app.play_BandLimits_Tree.SelectedNodes.NodeData];
                
                if ~isempty(app.specData(idx).UserData.Emissions) && height(app.specData(idx).UserData.bandLimitsTable) > 1
                    msgQuestion   = 'Confirma a reanálise das emissões, eventualmente eliminando aquelas que não estão em uma das subfaixas sob análise?';
                    userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                    if userSelection == "Não"
                        return
                    end
                end
    
                app.specData(idx).UserData.bandLimitsTable(idxBandLimits,:) = [];
                if exist('userSelection', 'var')
                    play_BandLimits_updateEmissions(app, idx, app.specData(idx).UserData.Emissions.Index)
                    play_UpdatePeaksTable(app, idx,'playback.AddEditOrDeleteEmission')
                end
    
                play_BandLimits_TreeBuilding(app, idx)
                plot.draw2D.horizontalSetOfLines(app.UIAxes1, app.bandObj, idx, 'BandLimits')
            end

        end

        % Button pushed function: misc_DeleteAll
        function misc_DeleteAllButtonPushed(app, event)

            msgQuestion   = ['Confirma a exclusão dos fluxos espectrais e das informações auxiliares ' ...
                             'a cada um dos fluxos, dentre elas a lista de exceções? Ou a exclusão apenas das figuras auxiliares?'];
            userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Excluir tudo', 'Apenas figuras', 'Cancelar'}, 3, 3);
            
            switch userSelection
                case 'Excluir tudo'
                    DeleteAll(app)
                case 'Apenas figuras'
                    DeleteFigures(app)
            end

        end

        % Callback function
        function report_SaveProjectButtonPushed(app, event)
            
            if isempty(app.report_Tree.Children)
                msg = 'A criação/edição de um projeto somente é possível quando há ao menos um fluxo espectral a processar.';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                return
            end
            
            if isempty(app.report_ProjectName.Value{1})
                defaultFilename = class.Constants.DefaultFileName(app.config_Folder_userPath.Value, 'ProjectData', app.report_Issue.Value);
            else
                defaultFilename = app.report_ProjectName.Value{1};
            end
            
            [fileName, filePath, fileIndex] = uiputfile({'*.mat', 'appAnalise (*.mat)'}, '', defaultFilename);
            figure(app.UIFigure)
            
            if fileIndex
                app.progressDialog.Visible = 'visible';

                reportTemplateIndex = find(strcmp(app.report_ModelName.Items, app.report_ModelName.Value), 1);
                [idx, reportInfo] = report.GeneralInfo(app, 'Report', reportTemplateIndex);
                prjInfo = struct('reportInfo',    rmfield(reportInfo, 'Filename'), ...
                                 'peaksTable',    app.projectData.peaksTable,                  ...
                                 'exceptionList', app.projectData.exceptionList);
                
                fileName = fullfile(filePath, fileName);
                fileWriter.MAT(fileName, 'ProjectData', app.specData(idx), prjInfo)
                
                app.report_ProjectName.Value = fileName;
                app.report_ProjectWarnIcon.Visible   = 0;

                app.progressDialog.Visible = 'hidden';
            end

        end

        % Callback function: report_AddProjectAttachment, 
        % ...and 1 other component
        function report_ExternalFilesMenuSelected(app, event)
            
            focus(app.report_ExternalFiles)

            if isempty(app.report_Tree.Children)
                msg = 'O relacionamento de arquivos externos ao projeto somente é possível se existir ao menos um fluxo espectral a processar.';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                return
            end

            switch event.Source
                case app.report_AddProjectAttachment
                    Type = 'Project';

                case app.report_ContextMenu_ExternalFiles
                    if isscalar(app.report_Tree.SelectedNodes.NodeData)
                        Type = 'specData';
                    else
                        msg = 'Os arquivos externos ao fluxo espectral - uma imagem e uma tabela - devem ser incluídos individualmente. Portanto, deve-se selecionar apenas um único fluxo espectral.';
                        appUtil.modalWindow(app.UIFigure, 'warning', msg);
                        return
                    end
            end

            auxApp.winAddFiles(app, Type)

        end

        % Value changed function: report_Issue
        function report_SaveWarn(app, event)

            app.report_ProjectWarnIcon.Visible = 1;
            
        end

        % Image clicked function: report_TreeAddImage
        function report_TreeAddImagePushed(app, event)
            
            focus(app.report_Tree)

            if ~isempty(app.play_Tree.SelectedNodes)
                idxThreads = unique([app.play_Tree.SelectedNodes.NodeData]);
    
                msg = '';
                if any(ismember(arrayfun(@(x) x.MetaData.DataType, app.specData(idxThreads)), class.Constants.occDataTypes))
                    msg = 'É possível a inclusão apenas de fluxos de espectro. Os fluxos de ocupação automaticamente serão inclusos na edição do relatório, desde que selecionado nos equivalentes fluxos de espectro.';
                elseif any(~arrayfun(@(x) x.GPS.Status, app.specData(idxThreads)))
                    msg = 'Necessária a edição de informações de coordenadas geográficas de fluxo(s) de dados.';                    
                elseif any(arrayfun(@(x) numel(x.Data{1}), app.specData(idxThreads)) == 1)
                    msg = 'É possível a inclusão de fluxo(s) de dados que possua(m) ao menos duas amostras.';
                end

                if ~isempty(msg)
                    appUtil.modalWindow(app.UIFigure, 'warning', msg);
                    return
                end

                if any(~ismember(idxThreads, find(arrayfun(@(x) x.UserData.reportFlag, app.specData))))
                    app.progressDialog.Visible = 'visible';

                    class.userData.reportProperties_DefaultValues(app.specData, idxThreads, app)
                    report_TreeBuilding(app)
                    report_SaveWarn(app)

                    app.progressDialog.Visible = 'hidden';
                end
            end

        end

        % Menu selected function: report_ContextMenu_del
        function report_ContextMenu_delSelected(app, event)
            
            if ~isempty(app.report_Tree.SelectedNodes)
                idx = app.report_Tree.SelectedNodes.NodeData;

                for ii = idx
                    app.specData(ii).UserData.reportFlag           = false;
                    app.specData(ii).UserData.reportOCC            = [];
                    app.specData(ii).UserData.reportDetection      = [];
                    app.specData(ii).UserData.reportClassification = [];
                end

                report_TreeBuilding(app)
                report_SaveWarn(app)
            end

        end

        % Value changed function: report_DetectionManualMode
        function report_DetectionManualModeValueChanged(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;
            app.specData(idx).UserData.reportDetection.ManualMode = double(app.report_DetectionManualMode.Value);

            report_Algorithms(app, idx)

        end

        % Image clicked function: tool_ReportAnalysis, tool_ReportGenerator
        function report_playButtonPushed(app, event)
            
            switch event.Source
                case app.tool_ReportAnalysis;  Mode = 'Preview';
                case app.tool_ReportGenerator; Mode = 'Report';
            end

            % EXCLUSÃO FIGURAS INVISÍVEIS:
            DeleteTempFigures(app, 'invisible')
            
            % VALIDAÇÕES:
            if ~sum(arrayfun(@(x) x.UserData.reportFlag, app.specData))
                msgWarning = 'Necessário incluir ao menos um fluxo espectral na lista de fluxos a processar.';
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                return

            elseif app.plotFlag
                msgWarning = 'Necessário interromper o playback antes de inicializar análise para geração do relatório.';
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                return

            elseif strcmp(Mode, 'Report') && strcmp(app.report_Version.Value, 'Definitiva')
                if ~report_checkValidIssueID(app)
                    msgWarning = sprintf('O número da inspeção "%.0f" é inválido.', app.report_Issue.Value);
                    appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                    return
                end

                msgQuestion   = sprintf('Confirma que se trata de monitoração relacionada à Inspeção nº %.0f?', app.report_Issue.Value);
                userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                if userSelection == "Não"
                    return
                end
                
            elseif strcmp(Mode, 'Preview')
                if isempty(app.report_Tree.CheckedNodes)
                    msgWarning = 'Necessário selecionar ao menos um dos fluxos espectrais a processar.';
                    appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                    return
                end
            end

            report.Controller(app, Mode)
            if ~isempty(app.auxiliarWin1) && isvalid(app.auxiliarWin1)
                renderProjectDataOnScreen(app.auxiliarWin1)
            end

            % LAYOUT:
            % Esse modo "REPORT" pode detectar, automaticamente, novas
            % emissões. Essa informação é salva na própria app.specData.
            % Necesário, portanto, atualizar screen.
            app.play_Tree.SelectedNodes = app.play_PlotPanel.UserData;
            app.play_PlotPanel.UserData = [];
            play_TreeSelectionChanged(app)

        end

        % Image clicked function: tool_ExceptionList
        function report_OpenSignalAnalysis(app, event)
            
            if ~isempty(app.projectData.peaksTable)
                if isempty(app.auxiliarWin1) || ~isvalid(app.auxiliarWin1)
                    app.progressDialog.Visible = 'visible';                    
                    app.auxiliarWin1 = auxApp.winSignalAnalysis(app);
                    app.progressDialog.Visible = 'hidden';

                else
                    figure(app.auxiliarWin1.UIFigure)
                end

            else
                msg = 'Funcionalidade acessível apenas quando detectada ao menos uma emissão nos fluxos espectrais a processar.';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
            end

        end

        % Callback function
        function report_AlgorithmButtonPushed(app, event)
            
            switch event.Source
                case app.report_occButton
                    msg = 'A alteração do método de aferição da ocupação por <i>bin</i> do fluxo espectral deve ser feita diretamente no modo PLAYBACK.';
                    appUtil.modalWindow(app.UIFigure, 'warning', msg);

                case app.report_DetectionButton
                    auxApp.winDetection(app);

                case app.report_ClassificationButton
                    auxApp.winClassification(app);
            end

        end

        % Image clicked function: report_FiscalizaRefresh, 
        % ...and 2 other components
        function report_FiscalizaStaticButtonPushed(app, event)
            
            fiscalizaLibConnection.report_StaticButtonPushed(app, event)

        end

        % Callback function: edit_gpsCity, edit_gpsCityButton, 
        % ...and 4 other components
        function edit_gpsCallbacks(app, event)
            
            idx = edit_gpsFindIndex(app);
            if isempty(idx)
                return
            end

            switch event.Source
                %---------------------------------------------------------%
                case app.tool_MiscRefresh
                    fcn.gpsProperty(app.specData, idx)
                    edit_gpsLayout(app, 'City+Latitude+Longitude')
                
                %---------------------------------------------------------%
                case app.tool_MiscEdit
                    app.edit_gpsLatitude.Editable  = 1;
                    app.edit_gpsLongitude.Editable = 1;
                    focus(app.edit_gpsLatitude)

                %---------------------------------------------------------%
                case app.edit_gpsLatitude
                    focus(app.edit_gpsLongitude)
                    
                %---------------------------------------------------------%
                case app.edit_gpsLongitude
                
                    gps = struct('Latitude',  app.edit_gpsLatitude.Value, ...
                                 'Longitude', app.edit_gpsLongitude.Value);

                    if ~strcmp(app.edit_gpsCitySource.Value, 'manual')
                        [cityName, ~, locInfo] = fcn.gpsFindCity(gps);
                    end

                    for ii = idx
                        edit_gpsUpdate(app, ii, gps.Latitude, gps.Longitude)

                        if ~strcmp(app.edit_gpsCitySource.Value, 'manual')
                            app.specData(ii).GPS.Location       = cityName;
                            app.specData(ii).GPS.LocationSource = locInfo.infoSource;
                        end
                        
                        app.specData(ii).GPS.Edited = true;
                    end
                    edit_gpsLayout(app, 'Latitude+Longitude')

                %---------------------------------------------------------%
                case app.edit_gpsCityButton
                    app.edit_gpsCity.Editable = 1;
                    focus(app.edit_gpsCity)

                %---------------------------------------------------------%
                case app.edit_gpsCity
                    [cityName, Latitude, Longitude] = fcn.gpsFindCoordinates(event.Value);                    
                    if ~isempty(cityName)        
                        msgQuestion   = sprintf(['Coordenadas geográficas atuais:\n•&thinsp;Latitude: %.6f\n•&thinsp;Longitude: %.6f\n\n'               ...
                                                 'Coordenadas geográficas da sede de <b>%s</b>:\n•&thinsp;Latitude: %.6f\n•&thinsp;Longitude: %.6f\n\n' ...
                                                 'Deseja atualizar, além da localidade, as informações de Latitude e Longitude?'],        ...
                                                 app.edit_gpsLatitude.Value, app.edit_gpsLongitude.Value, cityName, Latitude, Longitude);
                        userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não', 'Cancelar'}, 1, 3);
                        if userSelection == "Cancelar"
                            set(app.edit_gpsCity, Editable=0, Value=event.PreviousValue)
                            return
                        end

                        for ii = idx
                            if selection == "Sim"
                                edit_gpsUpdate(app, ii, Latitude, Longitude)
                            end
                            
                            app.specData(ii).GPS.Location       = cityName;
                            app.specData(ii).GPS.LocationSource = 'manual';                            
                            app.specData(ii).GPS.Edited         = true;
                        end

                        switch selection
                            case 'Sim'; edit_gpsLayout(app, 'City+Latitude+Longitude')
                            case 'Não'; edit_gpsLayout(app, 'City')
                        end
                        
                    else
                        set(app.edit_gpsCity, Editable=0, Value=event.PreviousValue)
                        
                        msg = sprintf(['Não encontrada em base do IBGE o município <b>"%s"</b>. Favor corrigir ' ...
                                       'eventual erro na grafia, inserindo os acentos, no formato Município/UF'], event.Value);
                        appUtil.modalWindow(app.UIFigure, 'warning', msg);                        
                    end
            end

        end

        % Callback function: edit_ContextMenu_del, edit_unitRefresh, 
        % ...and 1 other component
        function edit_unitLevelCallbacks(app, event)
            
            focus(app.misc_Edit_kFactorTree)

            idx1 = unique([app.play_Tree.SelectedNodes.NodeData]);
            if numel(idx1) ~= 1
                return
            end

            switch event.Source
                %---------------------------------------------------------%
                case app.edit_unitRefresh
                    idx2 = 1:height(app.specData(idx1).UserData.measCalibration);
                    Name = app.specData(idx1).UserData.measCalibration.Name;
                    edit_unitLevelCorrection(app, 'Remove', idx1, idx2, Name)

                %---------------------------------------------------------%
                case app.misc_Edit_kFactorAdd
                    Name = app.misc_Edit_kFactor.Value;
                    edit_unitLevelCorrection(app, 'Add', idx1, [], Name)

                %---------------------------------------------------------%
                case app.edit_ContextMenu_del
                    idx2 = app.misc_Edit_kFactorTree.SelectedNodes.NodeData;
                    Name = app.specData(idx1).UserData.measCalibration.Name(idx2);
                    edit_unitLevelCorrection(app, 'Remove', idx1, idx2, Name)
            end
            edit_unitLevelLayout(app)

        end

        % Button pushed function: misc_Del, misc_Duplicate, misc_Export, 
        % ...and 3 other components
        function misc_OperationsCallbacks(app, event)
            
            if app.plotFlag
                play_PlaybackToolbarButtonCallback(app, struct('Source', app.tool_Play))
            end

            idx = unique([app.play_Tree.SelectedNodes.NodeData]);
            try
                switch event.Source
                    %-----------------------------------------------------%
                    case app.misc_Save
                        misc_SaveSpectralData(app, idx)

                    %-----------------------------------------------------%
                    case app.misc_Duplicate
                        for ii = 1:numel(idx)
                            app.specData(end+1) = copy(app.specData(idx(ii)), {});
                        end
                        error('CORRIGIR')
                        sortIndex    = class.specData.read_SortData(app.specData);
                        app.specData = app.specData(sortIndex);                        
                    
                    %-----------------------------------------------------%
                    case app.misc_Merge
                        if strcmp(misc_checkIfAuxiliarAppIsOpen(app, 'MESCLAR FLUXOS'), 'Não')
                            return
                        end
                        
                        app.specData = merge(app.specData, idx, app.UIFigure);

                        % Reinicia os valores de ocupação...
                        idx = idx(1);
                        if ~isempty(app.specData(idx).UserData.reportOCC)
                            app.specData(idx).UserData.reportOCC = [];
                            app.specData(idx).UserData.occCache  = struct('Info', {}, 'THR', {}, 'Data', {});
                            app.specData(idx).UserData.occMethod.CacheIndex = [];

                            class.userData.reportProperties_DefaultValues(app, idx)
                        end
    
                    %-----------------------------------------------------%
                    case app.misc_Del
                        if strcmp(misc_checkIfAuxiliarAppIsOpen(app, 'DELETAR FLUXO'), 'Não')
                            return
                        end

                        delete(app.specData(idx))
                        app.specData(idx) = [];

                        if isempty(app.specData)
                            DeleteAll(app)
                            return
                        end
    
                    %-----------------------------------------------------%
                    case app.misc_Export
                        misc_ExportUserData(app, idx)
    
                    %-----------------------------------------------------%
                    case app.misc_Import
                        misc_ImportUserData(app)
                end

                SelectedNodesTextList = edit_SelectedNodesText(app);
                play_TreeRebuilding(app, SelectedNodesTextList, hComp)

            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', ME.message);
            end

        end

        % Selection changed function: config_ButtonGroup
        function config_ButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.config_ButtonGroup.SelectedObject;
            switch selectedButton
                case app.config_Option_Search
                    app.config_mainGrid.ColumnWidth(2:4) = {'1x',0,0};
                case app.config_Option_Fiscaliza
                    app.config_mainGrid.ColumnWidth(2:4) = {0,'1x',0};
                case app.config_Option_Folder
                    app.config_mainGrid.ColumnWidth(2:4) = {0,0,'1x'};
            end

        end

        % Image clicked function: report_ProjectNew, report_ProjectOpen, 
        % ...and 1 other component
        function report_ProjectToolbarImageClicked(app, event)
            
            switch event.Source
                case app.report_ProjectNew
                    if isempty(app.report_Tree.Children)
                        appUtil.modalWindow(app.UIFigure, 'warning', 'Operação aplicável apenas quando a lista de fluxos espectrais a processar não está vazia.');
                        return
                    end

                    msgQuestion   = 'Deseja excluir a lista de fluxos espectrais a processar, iniciando um novo projeto?';
                    userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                    if userSelection == "Não"
                        return
                    end
                    
                    DeleteProject(app)

                %---------------------------------------------------------%
                case app.report_ProjectOpen
                    % if ~isempty(app.listOfProducts)
                    %     msgQuestion   = 'Ao abrir um projeto, a lista de produtos sob análise será sobrescrita. Confirma a operação?';
                    %     userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                    %     if userSelection == "Não"
                    %         return
                    %     end
                    % end

                    % !! PENDENTE !! GERAR DOIS ARQUIVOS. UM MAT SPECTRAL
                    % DATA. E OUTRO PROJECT DATA, DA NOVA VERSÃO, QUE SÓ
                    % TEM OS DADOS ANALISADOS, E UM PONTEIRO COM A LISTA DE
                    % ARQUIVOS BRUTOS. ELES PODEM POSSUIR O MESMO NOME, MAS
                    % COM UMA EXTENSÃO DIFERENTE. .MAT E .PRJ (POR
                    % EXEMPLO).
                    
                    % !! CHECA SE OS ARQUIVOS BINÁRIOS COINCIDEM COM O QUE É
                    % NECESSÁRIO NO PROJETO !!

                    % [fileName, filePath] = uigetfile({'*.mat', 'SCH (*.mat)'}, '', app.General.fileFolder.lastVisited);
                    % figure(app.UIFigure)
                    % 
                    % if fileName
                    %     fileFullPath = fullfile(filePath, fileName);
                    % 
                    %     try
                    %         [~, ~, variables, ~]         = readFile.MAT(fileFullPath);
                    %         app.listOfProducts           = variables.listOfProducts;
                    % 
                    %         % Atualizando os componentes da GUI...
                    %         app.report_ProjectName.Value = fileFullPath;
                    %         app.report_Issue.Value       = variables.projectIssue;
                    %         app.report_Entity.Value      = variables.entityName;
                    %         app.report_EntityID.Value    = variables.entityID;
                    %         app.report_EntityType.Value  = variables.entityType;
                    % 
                    %         report_UpdatingTable(app)
                    %         report_TableSelectionChanged(app)
                    % 
                    %         report_ListOfHomProductsUpdating(app)
                    %         app.report_ProjectWarnIcon.Visible = 0;
                    % 
                    %         app.General.fileFolder.lastVisited = filePath;
                    %         appUtil.generalSettingsSave(class.Constants.appName, app.rootFolder, app.General, app.executionMode)
                    % 
                    %     catch ME
                    %         appUtil.modalWindow(app.UIFigure, 'error', ME.message);
                    %     end
                    % end

                %---------------------------------------------------------%
                case app.report_ProjectSave
                    if isempty(app.report_Tree.Children)
                        appUtil.modalWindow(app.UIFigure, 'warning', 'Operação aplicável apenas quando a lista de fluxos espectrais a processar não está vazia.');
                        return
                    end

                    if ~isempty(app.report_ProjectName.Value{1})
                        defaultFilename = app.report_ProjectName.Value{1};
                    else
                        defaultFilename = class.Constants.DefaultFileName(app.config_Folder_userPath.Value, 'ProjectData', app.report_Issue.Value);
                    end

                    [fileName, filePath] = uiputfile({'*.mat', 'appAnalise (*.mat)'}, '', defaultFilename);
                    figure(app.UIFigure)
                    
                    if fileName
                        app.progressDialog.Visible = 'visible';

                        variables = struct('listOfProducts', app.listOfProducts,           ...
                                           'projectName',    fullfile(filePath, fileName), ...
                                           'projectIssue',   app.report_Issue.Value,       ...
                                           'entityName',     app.report_Entity.Value,      ...
                                           'entityID',       app.report_EntityID.Value,    ...
                                           'entityType',     app.report_EntityType.Value);
                        userData  = [];

                        msgError  = writeFile.MAT(fullfile(filePath, fileName), 'ProjectData', 'SCH', variables, userData);
                        if ~isempty(msgError)
                            appUtil.modalWindow(app.UIFigure, 'error', msgError);
                            return
                        end

                    % !! PENDENTE !! GERAR DOIS ARQUIVOS. UM MAT SPECTRAL
                    % DATA. E OUTRO PROJECT DATA, DA NOVA VERSÃO, QUE SÓ
                    % TEM OS DADOS ANALISADOS, E UM PONTEIRO COM A LISTA DE
                    % ARQUIVOS BRUTOS. ELES PODEM POSSUIR O MESMO NOME, MAS
                    % COM UMA EXTENSÃO DIFERENTE. .MAT E .PRJ (POR
                    % EXEMPLO).

                        reportTemplateIndex = find(strcmp(app.report_ModelName.Items, app.report_ModelName.Value), 1);
                        [idx, reportInfo]   = report.GeneralInfo(app, 'Report', reportTemplateIndex);
                        prjInfo = struct('projectName',   fullfile(filePath, fileName),    ...
                                         'projectIssue',  app.report_Issue.Value,          ...
                                         'entityType',    app.report_EntityType.Value,     ...
                                         'entityID',      app.report_EntityID.Value,       ...
                                         'entityName',    app.report_Entity.Value,         ...
                                         'docModel',      app.report_ModelName.Value,      ...
                                         'reportInfo',    rmfield(reportInfo, 'Filename'), ...
                                         'projData',      app.projData,                    ...
                                         'peaksTable',    app.projectData.peaksTable,                  ...
                                         'exceptionList', app.projectData.exceptionList);
                        
                        fileName = fullfile(filePath, fileName);
                        fileWriter.MAT([fileName '.prj'], 'ProjectData', prjInfo)
                        fileWriter.MAT([fileName '.mat'], 'ProjectData', app.specData(idx))

                        app.report_ProjectName.Value = fullfile(filePath, fileName);
                        app.report_ProjectWarnIcon.Visible = 0;

                        app.progressDialog.Visible = 'hidden';
                    end
            end

        end

        % Image clicked function: play_LimitsRefresh
        function play_LimitsRefreshImageClicked(app, event)
            
            idx = app.play_PlotPanel.UserData.NodeData;

            % Para que o eixo volte às configurações automáticas de
            % XLim e YLim, e considerando como foi construída a classe Band, 
            % em especial o seu método Limits, apaga-se temporariamente 
            % a informação de customização... 
            app.specData(idx).UserData.customPlayback = struct('Type', 'auto', 'Parameters', []);

            prePlot_updatingRestoreView(app, idx)
            set(app.UIAxes1, 'XLim', app.restoreView(1).xLim, 'YLim', app.restoreView(1).yLim)

            % E após os ajustes do eixo e do painel de spinners (que é atualizado
            % automaticamente), recria-se a informação de customização, caso aplicável.
            prePlot_updatingGeneralSettings(app)
            prePlot_updatingCustomProperties(app, idx)

        end

        % Callback function: play_Channel_FileTemplate, 
        % ...and 1 other component
        function config_DownloadFileTemplate(app, event)
            
            switch event.Source
                case app.play_Channel_FileTemplate
                    msgQuestion   = ['O arquivo genérico que possibilita a inclusão de canais é composto por uma estrutura com nove campos:<br>'            ...
                                     '<font style="font-size: 11px;">•&thinsp;Campo 1: Identificador;<br>'                                                  ...
                                     '•&thinsp;Campo 2: Faixa de frequência, em kHZ;<br>'                                                                   ...
                                     '•&thinsp;Campos 3-4: Frequências centrais de canais - o primeiro e o último, respectivamente -, em MHz;<br>'          ...
                                     '•&thinsp;Campo 5: Distância entre frequências centrais de canais adjacentes, em MHz;<br>'                             ...
                                     '•&thinsp;Campo 6: Largura dos canais, em MHz;<br>'                                                                    ...
                                     '•&thinsp;Campo 7: Lista de frequências centrais dos canais, caso não sejam regularmente espaçados;<br>'               ...
                                     '•&thinsp;Campo 8: Referência textual sobre a canalização;<br>'                                                        ...
                                     '•&thinsp;Campo 9: Natureza das emissões, podendo ser "Aircraft", "Cellular", "FM", "TV" ou "Unknown".</font><br><br>' ...
                                     'Deseja fazer download do arquivo modelo?'];
                    fileName      = 'appAnaliseTemplate - addChannelList.json';

                case app.play_FindPeaks_FileTemplate
                    msgQuestion   = ['O arquivo genérico que possibilita a inclusão de emissões é composto por uma tabela com três colunas:<br>' ...
                                     '<font style="font-size: 11px;">•&thinsp;Coluna 1: Frequência, em MHZ;<br>'                                 ...
                                     '•&thinsp;Coluna 2: Largura ocupada, em kHZ; e<br>'                                                         ...
                                     '•&thinsp;Coluna 3: Descrição textual da emissão.</font><br><br>'                                           ...
                                     'Deseja fazer download dos arquivos modelos?'];
                    fileName      = 'appAnaliseTemplate - addEmission.zip';
            end

            userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
            if userSelection == "Sim"
                try
                    originFileName  = fullfile(app.rootFolder, 'Template', fileName);
                    finalFolderName = app.General.fileFolder.userPath;
                    copyfile(originFileName, finalFolderName, 'f')
                    
                    appUtil.modalWindow(app.UIFigure, 'info', sprintf('Arquivo "<b>%s</b>" salvo na pasta "%s".', fileName, finalFolderName));

                catch ME
                    appUtil.modalWindow(app.UIFigure, 'error', ME.message);
                end
            end

        end

        % Image clicked function: play_Channel_ShowPlot
        function play_Channel_ShowPlotImageClicked(app, event)
            
            app.play_Channel_ShowPlot.UserData = ~app.play_Channel_ShowPlot.UserData;
            
            if app.play_Channel_ShowPlot.UserData
                app.play_Channel_ShowPlot.ImageSource = 'Eye_32.png';
            else
                app.play_Channel_ShowPlot.ImageSource = 'EyeNegative_32.png';
                app.play_Channel_ShowPlotWarn.Visible = 0;                
            end

            idx = app.play_PlotPanel.UserData.NodeData;
            plot_Draw_Channels(app, idx)

        end

        % Image clicked function: report_ThreadAlgorithmsRefresh
        function report_ThreadAlgorithmsRefreshImageClicked(app, event)
            
            pause(1)

        end

        % Value changed function: report_ModelName
        function report_ModelNameValueChanged(app, event)
            
            if ~isempty(app.report_ModelName.Value)
                app.tool_ReportGenerator.Enable = 1;
            else
                app.tool_ReportGenerator.Enable = 0;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnaliseV2 R2024a';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'icon_48.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout.Tooltip = {''};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_File = uitab(app.TabGroup);
            app.Tab1_File.Title = 'FILE';
            app.file_Grid = uigridlayout(app.Tab1_File);
            app.file_toolGrid = uigridlayout(app.file_Grid);
            app.file_OpenFileButton = uiimage(app.file_toolGrid);
            app.file_OpenFileButton.ImageClickedFcn = createCallbackFcn(app, @file_ButtonPushed_OpenFile, true);
            app.file_OpenFileButton.Tooltip = {'Seleciona arquivos'};
            app.file_OpenFileButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Import_24.png');
            app.file_SpecReadButton = uibutton(app.file_toolGrid, 'push');
            app.file_SpecReadButton.ButtonPushedFcn = createCallbackFcn(app, @file_ButtonPushed_SpecRead, true);
            app.file_SpecReadButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Run_24.png');
            app.file_SpecReadButton.Visible = 'off';
            app.file_SpecReadButton.Text = 'Inicia análise';
            app.file_docGrid = uigridlayout(app.file_Grid);
            app.file_TitleGrid = uigridlayout(app.file_docGrid);
            app.file_TitleGrid.Tag = 'COLORLOCKED';
            app.file_TitleIcon = uiimage(app.file_TitleGrid);
            app.file_TitleIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addFiles_32.png');
            app.file_Title = uilabel(app.file_TitleGrid);
            app.file_Title.Text = 'ARQUIVOS';
            app.file_TreeLabel = uilabel(app.file_docGrid);
            app.file_TreeLabel.Text = 'LISTA DE ARQUIVOS';
            app.file_Tree = uitree(app.file_docGrid);
            app.file_Tree.Multiselect = 'on';
            app.file_Tree.SelectionChangedFcn = createCallbackFcn(app, @file_TreeSelectionChanged, true);
            app.file_panelGrid = uigridlayout(app.file_Grid);
            app.file_MetadataLabel = uilabel(app.file_panelGrid);
            app.file_MetadataLabel.Text = 'METADADOS';
            app.file_MetadataPanel = uipanel(app.file_panelGrid);
            app.file_MetadataGrid = uigridlayout(app.file_MetadataPanel);
            app.file_Metadata = uihtml(app.file_MetadataGrid);
            app.file_Metadata.HTMLSource = ' ';
            app.file_FilteringLabel = uilabel(app.file_panelGrid);
            app.file_FilteringLabel.Text = 'FILTROS';
            app.file_FilteringTypePanel = uibuttongroup(app.file_panelGrid);
            app.file_FilteringTypePanel.SelectionChangedFcn = createCallbackFcn(app, @file_FilteringTypeChanged, true);
            app.file_FilteringType1 = uiradiobutton(app.file_FilteringTypePanel);
            app.file_FilteringType1.Text = 'Faixa de Frequência';
            app.file_FilteringType1.Value = true;
            app.file_FilteringType2 = uiradiobutton(app.file_FilteringTypePanel);
            app.file_FilteringType2.Text = 'ID';
            app.file_FilteringType3 = uiradiobutton(app.file_FilteringTypePanel);
            app.file_FilteringType3.Text = 'Descrição';
            app.file_FilteringType1_Frequency = uidropdown(app.file_panelGrid);
            app.file_FilteringType1_Frequency.Items = {};
            app.file_FilteringType1_Frequency.Value = {};
            app.file_FilteringType2_ID = uidropdown(app.file_panelGrid);
            app.file_FilteringType2_ID.Items = {};
            app.file_FilteringType2_ID.Value = {};
            app.file_FilteringType3_Description = uieditfield(app.file_panelGrid, 'text');
            app.file_FilteringAdd = uiimage(app.file_panelGrid);
            app.file_FilteringAdd.ImageClickedFcn = createCallbackFcn(app, @file_FilteringAddClicked, true);
            app.file_FilteringAdd.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.file_FilteringTree = uitree(app.file_panelGrid);
            app.file_FilteringTree.Multiselect = 'on';
            app.Tab2_Playback = uitab(app.TabGroup);
            app.Tab2_Playback.Title = 'PLAYBACK+REPORT+MISC';
            app.play_Grid = uigridlayout(app.Tab2_Playback);
            app.play_TreeGrid = uigridlayout(app.play_Grid);
            app.play_TreeTitleGrid = uigridlayout(app.play_TreeGrid);
            app.play_TreeTitleGrid.Tag = 'COLORLOCKED';
            app.play_TreeTitleImage = uiimage(app.play_TreeTitleGrid);
            app.play_TreeTitleImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Classification_32.png');
            app.play_TreeTitle = uilabel(app.play_TreeTitleGrid);
            app.play_TreeTitle.Text = 'DADOS';
            app.play_TreeLabel = uilabel(app.play_TreeGrid);
            app.play_TreeLabel.Text = 'FLUXOS ESPECTRAIS';
            app.play_TreePanelVisibility = uiimage(app.play_TreeGrid);
            app.play_TreePanelVisibility.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.play_TreePanelVisibility.Tooltip = {'Mostra metadados'};
            app.play_TreePanelVisibility.ImageSource = fullfile(pathToMLAPP, 'Icons', 'layout3_32px.png');
            app.play_TreeSort = uiimage(app.play_TreeGrid);
            app.play_TreeSort.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.play_TreeSort.Tooltip = {'Reordena fluxos'};
            app.play_TreeSort.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Sort_32.png');
            app.play_Tree = uitree(app.play_TreeGrid);
            app.play_Tree.Multiselect = 'on';
            app.play_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_TreeSelectionChanged, true);
            app.play_MetadataLabel = uilabel(app.play_TreeGrid);
            app.play_MetadataLabel.Text = 'METADADOS';
            app.play_MetadataPanel = uipanel(app.play_TreeGrid);
            app.play_MetadataGrid = uigridlayout(app.play_MetadataPanel);
            app.play_Metadata = uihtml(app.play_MetadataGrid);
            app.play_Metadata.HTMLSource = ' ';
            app.report_TreeLabel = uilabel(app.play_TreeGrid);
            app.report_TreeLabel.Text = 'ALGORITMOS';
            app.report_ThreadAlgorithmsRefresh = uiimage(app.play_TreeGrid);
            app.report_ThreadAlgorithmsRefresh.ImageClickedFcn = createCallbackFcn(app, @report_ThreadAlgorithmsRefreshImageClicked, true);
            app.report_ThreadAlgorithmsRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.report_ThreadAlgorithmsPanel = uipanel(app.play_TreeGrid);
            app.report_ThreadAlgorithmsGrid = uigridlayout(app.report_ThreadAlgorithmsPanel);
            app.report_ThreadAlgorithms = uihtml(app.report_ThreadAlgorithmsGrid);
            app.report_DetectionManualMode = uicheckbox(app.play_TreeGrid);
            app.report_DetectionManualMode.ValueChangedFcn = createCallbackFcn(app, @report_DetectionManualModeValueChanged, true);
            app.report_DetectionManualMode.Enable = 'off';
            app.report_DetectionManualMode.Text = 'Restringir detecção de emissões ao PLAYBACK.';
            app.play_PlotPanel = uipanel(app.play_Grid);
            app.play_axesToolbar = uigridlayout(app.play_Grid);
            app.axesTool_MinHold = uiimage(app.play_axesToolbar);
            app.axesTool_MinHold.ImageClickedFcn = createCallbackFcn(app, @play_AxesToolbarCallbacks, true);
            app.axesTool_MinHold.Tag = 'MinHold';
            app.axesTool_MinHold.Tooltip = {'MinHold'};
            app.axesTool_MinHold.ImageSource = fullfile(pathToMLAPP, 'Icons', 'MinHold_32.png');
            app.axesTool_Average = uiimage(app.play_axesToolbar);
            app.axesTool_Average.ImageClickedFcn = createCallbackFcn(app, @play_AxesToolbarCallbacks, true);
            app.axesTool_Average.Tag = 'Average';
            app.axesTool_Average.Tooltip = {'Média'};
            app.axesTool_Average.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Average_32.png');
            app.axesTool_MaxHold = uiimage(app.play_axesToolbar);
            app.axesTool_MaxHold.ImageClickedFcn = createCallbackFcn(app, @play_AxesToolbarCallbacks, true);
            app.axesTool_MaxHold.Tag = 'MaxHold';
            app.axesTool_MaxHold.Tooltip = {'MaxHold'};
            app.axesTool_MaxHold.ImageSource = fullfile(pathToMLAPP, 'Icons', 'MaxHold_32.png');
            app.axesTool_Persistance = uiimage(app.play_axesToolbar);
            app.axesTool_Persistance.ImageClickedFcn = createCallbackFcn(app, @play_AxesToolbarCallbacks, true);
            app.axesTool_Persistance.Tag = 'Persistance';
            app.axesTool_Persistance.Tooltip = {'Persistência'};
            app.axesTool_Persistance.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Persistance_36.png');
            app.axesTool_Occupancy = uiimage(app.play_axesToolbar);
            app.axesTool_Occupancy.ImageClickedFcn = createCallbackFcn(app, @play_AxesToolbarCallbacks, true);
            app.axesTool_Occupancy.Tag = 'Ocuppancy';
            app.axesTool_Occupancy.Tooltip = {'Ocupação'};
            app.axesTool_Occupancy.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Occupancy_32Gray.png');
            app.axesTool_Waterfall = uiimage(app.play_axesToolbar);
            app.axesTool_Waterfall.ImageClickedFcn = createCallbackFcn(app, @play_AxesToolbarCallbacks, true);
            app.axesTool_Waterfall.Tag = 'Waterfall';
            app.axesTool_Waterfall.Tooltip = {'Waterfall'};
            app.axesTool_Waterfall.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Waterfall_24.png');
            app.axesTool_DataTip = uiimage(app.play_axesToolbar);
            app.axesTool_DataTip.ImageClickedFcn = createCallbackFcn(app, @play_AxesToolbarCallbacks, true);
            app.axesTool_DataTip.Tooltip = {'DataTip'};
            app.axesTool_DataTip.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DataTip_22.png');
            app.axesTool_Pan = uiimage(app.play_axesToolbar);
            app.axesTool_Pan.ImageClickedFcn = createCallbackFcn(app, @play_AxesToolbarCallbacks, true);
            app.axesTool_Pan.Tooltip = {'Pan'};
            app.axesTool_Pan.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Pan_32.png');
            app.axesTool_RestoreView = uiimage(app.play_axesToolbar);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @play_AxesToolbarCallbacks, true);
            app.axesTool_RestoreView.Tooltip = {'RestoreView'};
            app.axesTool_RestoreView.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Home_18.png');
            app.play_ControlsGrid = uigridlayout(app.play_Grid);
            app.play_ControlsTab1Info = uigridlayout(app.play_ControlsGrid);
            app.play_GeneralPanelLabel = uilabel(app.play_ControlsTab1Info);
            app.play_GeneralPanelLabel.Text = 'ASPECTOS GERAIS';
            app.play_GeneralPanel = uipanel(app.play_ControlsTab1Info);
            app.play_OthersGrid = uigridlayout(app.play_GeneralPanel);
            app.play_LayoutRatioLabel = uilabel(app.play_OthersGrid);
            app.play_LayoutRatioLabel.Text = {'Razão aspecto'; 'dos eixos:'};
            app.play_LayoutRatio = uidropdown(app.play_OthersGrid);
            app.play_LayoutRatio.Items = {'1:0:0'};
            app.play_LayoutRatio.ValueChangedFcn = createCallbackFcn(app, @play_ControlButtonPushed, true);
            app.play_LayoutRatio.Value = '1:0:0';
            app.play_LineVisibilityLabel = uilabel(app.play_OthersGrid);
            app.play_LineVisibilityLabel.Text = 'Visibilidade ClearWrite:';
            app.play_LineVisibility = uidropdown(app.play_OthersGrid);
            app.play_LineVisibility.Items = {'on', 'off'};
            app.play_LineVisibility.ValueChangedFcn = createCallbackFcn(app, @play_LineVisibilityValueChanged, true);
            app.play_LineVisibility.Value = 'on';
            app.play_MinPlotTimeLabel = uilabel(app.play_OthersGrid);
            app.play_MinPlotTimeLabel.Text = {'Tempo mínimo'; 'escrita (ms):'};
            app.play_MinPlotTime = uispinner(app.play_OthersGrid);
            app.play_MinPlotTime.Step = 25;
            app.play_MinPlotTime.Limits = [50 1000];
            app.play_MinPlotTime.RoundFractionalValues = 'on';
            app.play_MinPlotTime.ValueDisplayFormat = '%.0f';
            app.play_MinPlotTime.Value = 50;
            app.play_TraceIntegrationLabel = uilabel(app.play_OthersGrid);
            app.play_TraceIntegrationLabel.Text = 'Fator integração:';
            app.play_TraceIntegration = uidropdown(app.play_OthersGrid);
            app.play_TraceIntegration.Items = {'3', '10', '100', 'Inf'};
            app.play_TraceIntegration.ValueChangedFcn = createCallbackFcn(app, @play_TraceIntegrationValueChanged, true);
            app.play_TraceIntegration.Value = 'Inf';
            app.play_LimitsPanelLabel = uilabel(app.play_OthersGrid);
            app.play_LimitsPanelLabel.Text = 'Limites';
            app.play_LimitsRefresh = uiimage(app.play_OthersGrid);
            app.play_LimitsRefresh.ImageClickedFcn = createCallbackFcn(app, @play_LimitsRefreshImageClicked, true);
            app.play_LimitsRefresh.Tooltip = {'Retorna à configuração padrão'};
            app.play_LimitsRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.play_LimitsPanel = uipanel(app.play_OthersGrid);
            app.play_LimitsGrid = uigridlayout(app.play_LimitsPanel);
            app.play_Limits_xLim1 = uispinner(app.play_LimitsGrid);
            app.play_Limits_xLim1.ValueDisplayFormat = '%.3f';
            app.play_Limits_xLim1.ValueChangedFcn = createCallbackFcn(app, @play_xLimitsValueChanged, true);
            app.play_Limits_xLim1.Tag = 'FreqStart';
            app.play_Limits_xLim1.Tooltip = {''};
            app.play_Limits_xLimLabel = uilabel(app.play_LimitsGrid);
            app.play_Limits_xLimLabel.Text = 'Frequência';
            app.play_Limits_xLim2 = uispinner(app.play_LimitsGrid);
            app.play_Limits_xLim2.ValueDisplayFormat = '%.3f';
            app.play_Limits_xLim2.ValueChangedFcn = createCallbackFcn(app, @play_xLimitsValueChanged, true);
            app.play_Limits_xLim2.Tag = 'FreqStop';
            app.play_Limits_xLim2.Tooltip = {''};
            app.play_Limits_yLim1 = uispinner(app.play_LimitsGrid);
            app.play_Limits_yLim1.Step = 5;
            app.play_Limits_yLim1.ValueDisplayFormat = '%.1f';
            app.play_Limits_yLim1.ValueChangedFcn = createCallbackFcn(app, @play_yLimitsValueChanged, true);
            app.play_Limits_yLim1.Tag = 'MinLevel';
            app.play_Limits_yLim1.Tooltip = {''};
            app.play_Limits_yLimLabel = uilabel(app.play_LimitsGrid);
            app.play_Limits_yLimLabel.Text = 'Nível';
            app.play_Limits_yLim2 = uispinner(app.play_LimitsGrid);
            app.play_Limits_yLim2.Step = 5;
            app.play_Limits_yLim2.ValueDisplayFormat = '%.1f';
            app.play_Limits_yLim2.ValueChangedFcn = createCallbackFcn(app, @play_yLimitsValueChanged, true);
            app.play_Limits_yLim2.Tag = 'MaxLevel';
            app.play_Limits_yLim2.Tooltip = {''};
            app.play_ControlPanelLabel = uilabel(app.play_ControlsTab1Info);
            app.play_ControlPanelLabel.Text = 'CONTROLES';
            app.play_ControlsPanel = uibuttongroup(app.play_ControlsTab1Info);
            app.play_ControlsPanel.SelectionChangedFcn = createCallbackFcn(app, @play_ControlsPanelSelectionChanged, true);
            app.play_RadioButton_Persistance = uiradiobutton(app.play_ControlsPanel);
            app.play_RadioButton_Persistance.Text = 'Persistência';
            app.play_RadioButton_Persistance.Value = true;
            app.play_RadioButton_Occupancy = uiradiobutton(app.play_ControlsPanel);
            app.play_RadioButton_Occupancy.Text = 'Ocupação';
            app.play_RadioButton_Waterfall = uiradiobutton(app.play_ControlsPanel);
            app.play_RadioButton_Waterfall.Text = 'Waterfall';
            app.play_Persistance_Panel = uipanel(app.play_ControlsTab1Info);
            app.play_PersistanceGrid = uigridlayout(app.play_Persistance_Panel);
            app.play_Persistance_InterpolationLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_InterpolationLabel.Text = 'Interpolação:';
            app.play_Persistance_Interpolation = uidropdown(app.play_PersistanceGrid);
            app.play_Persistance_Interpolation.Items = {'nearest', 'bilinear'};
            app.play_Persistance_Interpolation.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Interpolation.Value = 'nearest';
            app.play_Persistance_WindowSizeLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_WindowSizeLabel.Text = 'Janela:';
            app.play_Persistance_WindowSize = uidropdown(app.play_PersistanceGrid);
            app.play_Persistance_WindowSize.Items = {'128', '256', '512', 'full'};
            app.play_Persistance_WindowSize.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_WindowSize.Value = 'full';
            app.play_Persistance_ColormapLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_ColormapLabel.Text = 'Mapa de cor:';
            app.play_Persistance_Colormap = uidropdown(app.play_PersistanceGrid);
            app.play_Persistance_Colormap.Items = {'winter', 'parula', 'turbo'};
            app.play_Persistance_Colormap.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Colormap.Value = 'winter';
            app.play_Persistance_TransparencyLabel = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_TransparencyLabel.Text = 'Transparência:';
            app.play_Persistance_Transparency = uispinner(app.play_PersistanceGrid);
            app.play_Persistance_Transparency.Step = 0.05;
            app.play_Persistance_Transparency.Limits = [0.2 1];
            app.play_Persistance_Transparency.ValueDisplayFormat = '%.2f';
            app.play_Persistance_Transparency.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_Transparency.Value = 1;
            app.play_Persistance_cLim_Label = uilabel(app.play_PersistanceGrid);
            app.play_Persistance_cLim_Label.Text = 'Limites (%):';
            app.play_Persistance_cLim_Mode = uiimage(app.play_PersistanceGrid);
            app.play_Persistance_cLim_Mode.ImageClickedFcn = createCallbackFcn(app, @play_Persistance_Callbacks, true);
            app.play_Persistance_cLim_Mode.Enable = 'off';
            app.play_Persistance_cLim_Mode.Tooltip = {'Retorna à configuração padrão'};
            app.play_Persistance_cLim_Mode.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.play_Persistance_cLim_Grid2 = uigridlayout(app.play_PersistanceGrid);
            app.play_Persistance_cLim1 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim1.Step = 0.1;
            app.play_Persistance_cLim1.Limits = [0 Inf];
            app.play_Persistance_cLim1.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim1.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_cLimValueChanged, true);
            app.play_Persistance_cLim1.Enable = 'off';
            app.play_Persistance_cLim1.Tooltip = {''};
            app.play_Persistance_cLim2 = uispinner(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim2.Limits = [0 Inf];
            app.play_Persistance_cLim2.ValueDisplayFormat = '%.3f';
            app.play_Persistance_cLim2.ValueChangedFcn = createCallbackFcn(app, @play_Persistance_cLimValueChanged, true);
            app.play_Persistance_cLim2.Enable = 'off';
            app.play_Persistance_cLim2.Tooltip = {''};
            app.play_Persistance_cLim2.Value = 1;
            app.play_Persistance_cLim_Separation = uilabel(app.play_Persistance_cLim_Grid2);
            app.play_Persistance_cLim_Separation.Enable = 'off';
            app.play_Persistance_cLim_Separation.Text = '-';
            app.play_OCC_Panel = uipanel(app.play_ControlsTab1Info);
            app.play_OCCGrid = uigridlayout(app.play_OCC_Panel);
            app.play_OCC_MethodLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_MethodLabel.Text = 'Tipo de threshold:';
            app.play_OCC_Method = uidropdown(app.play_OCCGrid);
            app.play_OCC_Method.Items = {'Linear fixo (COLETA)', 'Linear fixo', 'Linear adaptativo', 'Envoltória do ruído'};
            app.play_OCC_Method.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_Method.Value = 'Linear fixo (COLETA)';
            app.play_OCC_IntegrationTimeLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_IntegrationTimeLabel.Text = 'Integração (min):';
            app.play_OCC_IntegrationTime = uidropdown(app.play_OCCGrid);
            app.play_OCC_IntegrationTime.Items = {'1', '5', '15', '30', '60'};
            app.play_OCC_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_IntegrationTime.Tag = 'Factor';
            app.play_OCC_IntegrationTime.Value = '15';
            app.play_OCC_IntegrationTimeCaptured = uieditfield(app.play_OCCGrid, 'numeric');
            app.play_OCC_IntegrationTimeCaptured.Limits = [0 Inf];
            app.play_OCC_IntegrationTimeCaptured.ValueDisplayFormat = '%.0f';
            app.play_OCC_IntegrationTimeCaptured.Editable = 'off';
            app.play_OCC_IntegrationTimeCaptured.Visible = 'off';
            app.play_OCC_OrientationLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_OrientationLabel.Text = 'Orientação:';
            app.play_OCC_Orientation = uidropdown(app.play_OCCGrid);
            app.play_OCC_Orientation.Items = {'bin'};
            app.play_OCC_Orientation.Value = 'bin';
            app.play_OCC_THRLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_THRLabel.Tag = 'THR';
            app.play_OCC_THRLabel.Text = 'Valor (dBm):';
            app.play_OCC_THR = uispinner(app.play_OCCGrid);
            app.play_OCC_THR.RoundFractionalValues = 'on';
            app.play_OCC_THR.ValueDisplayFormat = '%d';
            app.play_OCC_THR.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_THR.Tag = 'THR';
            app.play_OCC_THR.Value = -80;
            app.play_OCC_THRCaptured = uidropdown(app.play_OCCGrid);
            app.play_OCC_THRCaptured.Items = {};
            app.play_OCC_THRCaptured.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_THRCaptured.Tag = 'Factor';
            app.play_OCC_THRCaptured.Visible = 'off';
            app.play_OCC_THRCaptured.Value = {};
            app.play_OCC_OffsetLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_OffsetLabel.Tag = 'Offset';
            app.play_OCC_OffsetLabel.Visible = 'off';
            app.play_OCC_OffsetLabel.Text = 'OffSet (dB):';
            app.play_OCC_Offset = uispinner(app.play_OCCGrid);
            app.play_OCC_Offset.Limits = [3 30];
            app.play_OCC_Offset.RoundFractionalValues = 'on';
            app.play_OCC_Offset.ValueDisplayFormat = '%d';
            app.play_OCC_Offset.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_Offset.Tag = 'Offset';
            app.play_OCC_Offset.Visible = 'off';
            app.play_OCC_Offset.Value = 12;
            app.play_OCC_ceilFactorLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_ceilFactorLabel.Tag = 'Factor';
            app.play_OCC_ceilFactorLabel.Visible = 'off';
            app.play_OCC_ceilFactorLabel.Text = 'Ceifamento:';
            app.play_OCC_ceilFactor = uidropdown(app.play_OCCGrid);
            app.play_OCC_ceilFactor.Items = {'1𝜎', '2𝜎', '3𝜎'};
            app.play_OCC_ceilFactor.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_ceilFactor.Tag = 'Factor';
            app.play_OCC_ceilFactor.Visible = 'off';
            app.play_OCC_ceilFactor.Value = '2𝜎';
            app.play_OCC_noiseLabel = uilabel(app.play_OCCGrid);
            app.play_OCC_noiseLabel.Visible = 'off';
            app.play_OCC_noiseLabel.Text = 'Parâmetros relacionados à estimativa do piso de ruído:';
            app.play_OCC_noisePanel = uipanel(app.play_OCCGrid);
            app.play_OCC_noisePanel.Visible = 'off';
            app.play_OCC_noiseGrid = uigridlayout(app.play_OCC_noisePanel);
            app.play_OCC_noiseFcnLabel = uilabel(app.play_OCC_noiseGrid);
            app.play_OCC_noiseFcnLabel.Text = 'Função estatística:';
            app.play_OCC_noiseFcn = uidropdown(app.play_OCC_noiseGrid);
            app.play_OCC_noiseFcn.Items = {'mean', 'median'};
            app.play_OCC_noiseFcn.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_noiseFcn.Value = 'mean';
            app.play_OCC_noiseTrashSamplesLabel = uilabel(app.play_OCC_noiseGrid);
            app.play_OCC_noiseTrashSamplesLabel.Text = 'Descartadas (%):';
            app.play_OCC_noiseTrashSamples = uispinner(app.play_OCC_noiseGrid);
            app.play_OCC_noiseTrashSamples.Limits = [0 10];
            app.play_OCC_noiseTrashSamples.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_noiseUsefulSamplesLabel = uilabel(app.play_OCC_noiseGrid);
            app.play_OCC_noiseUsefulSamplesLabel.Text = 'Úteis (%):';
            app.play_OCC_noiseUsefulSamples = uispinner(app.play_OCC_noiseGrid);
            app.play_OCC_noiseUsefulSamples.Limits = [10 90];
            app.play_OCC_noiseUsefulSamples.ValueChangedFcn = createCallbackFcn(app, @play_Occupancy_Callbacks, true);
            app.play_OCC_noiseUsefulSamples.Value = 20;
            app.play_Waterfall_Panel = uipanel(app.play_ControlsTab1Info);
            app.play_WaterFallGrid = uigridlayout(app.play_Waterfall_Panel);
            app.play_Waterfall_FcnLabel = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_FcnLabel.Text = 'Função:';
            app.play_Waterfall_Fcn = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Fcn.Items = {'image', 'mesh'};
            app.play_Waterfall_Fcn.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Fcn.Value = 'image';
            app.play_Waterfall_DecimationLabel = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_DecimationLabel.Text = 'Decimação:';
            app.play_Waterfall_DecimationValue = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_DecimationValue.Text = 'auto';
            app.play_Waterfall_Decimation = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Decimation.Items = {'auto', '1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.play_Waterfall_Decimation.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Decimation.Enable = 'off';
            app.play_Waterfall_Decimation.Value = 'auto';
            app.play_Waterfall_TimelineLabel = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_TimelineLabel.Text = 'Timeline:';
            app.play_Waterfall_Timeline = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Timeline.Items = {'on', 'off'};
            app.play_Waterfall_Timeline.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Timeline.Value = 'on';
            app.play_Waterfall_ColorbarLabel = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_ColorbarLabel.Text = 'Legenda de cor:';
            app.play_Waterfall_Colorbar = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Colorbar.Items = {'off', 'west', 'east', 'eastoutside'};
            app.play_Waterfall_Colorbar.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_ColorbarValueChanged, true);
            app.play_Waterfall_Colorbar.Enable = 'off';
            app.play_Waterfall_Colorbar.Value = 'off';
            app.play_Waterfall_ColormapLabel = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_ColormapLabel.Text = 'Mapa de cor:';
            app.play_Waterfall_Colormap = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.play_Waterfall_Colormap.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_Colormap.Value = 'winter';
            app.play_Waterfall_MeshStyleLabel = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_MeshStyleLabel.Text = 'MeshStyle:';
            app.play_Waterfall_MeshStyle = uidropdown(app.play_WaterFallGrid);
            app.play_Waterfall_MeshStyle.Items = {'row', 'both'};
            app.play_Waterfall_MeshStyle.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_MeshStyle.Enable = 'off';
            app.play_Waterfall_MeshStyle.Value = 'both';
            app.play_Waterfall_cLim_Label = uilabel(app.play_WaterFallGrid);
            app.play_Waterfall_cLim_Label.Text = 'Limites (dB):';
            app.play_Waterfall_cLim_Mode = uiimage(app.play_WaterFallGrid);
            app.play_Waterfall_cLim_Mode.ImageClickedFcn = createCallbackFcn(app, @play_Waterfall_Callbacks, true);
            app.play_Waterfall_cLim_Mode.Enable = 'off';
            app.play_Waterfall_cLim_Mode.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.play_Waterfall_cLim_Grid2 = uigridlayout(app.play_WaterFallGrid);
            app.play_Waterfall_cLim1 = uispinner(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim1.Step = 5;
            app.play_Waterfall_cLim1.RoundFractionalValues = 'on';
            app.play_Waterfall_cLim1.ValueDisplayFormat = '%.0f';
            app.play_Waterfall_cLim1.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_cLimValueChanged, true);
            app.play_Waterfall_cLim1.Enable = 'off';
            app.play_Waterfall_cLim1.Tooltip = {''};
            app.play_Waterfall_cLim_Separation = uilabel(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim_Separation.Enable = 'off';
            app.play_Waterfall_cLim_Separation.Text = '-';
            app.play_Waterfall_cLim2 = uispinner(app.play_Waterfall_cLim_Grid2);
            app.play_Waterfall_cLim2.Step = 5;
            app.play_Waterfall_cLim2.RoundFractionalValues = 'on';
            app.play_Waterfall_cLim2.ValueDisplayFormat = '%.0f';
            app.play_Waterfall_cLim2.ValueChangedFcn = createCallbackFcn(app, @play_Waterfall_cLimValueChanged, true);
            app.play_Waterfall_cLim2.Enable = 'off';
            app.play_Waterfall_cLim2.Tooltip = {''};
            app.play_Waterfall_cLim2.Value = 1;
            app.play_Customization = uicheckbox(app.play_ControlsTab1Info);
            app.play_Customization.ValueChangedFcn = createCallbackFcn(app, @play_CustomizationValueChanged, true);
            app.play_Customization.Text = 'Customizar controles do plot.';
            app.play_ControlsTab2Grid = uigridlayout(app.play_ControlsGrid);
            app.play_ControlsTab2Grid.Tag = 'COLORLOCKED';
            app.play_ControlsTab2Label = uilabel(app.play_ControlsTab2Grid);
            app.play_ControlsTab2Label.Text = 'CANAIS';
            app.play_Channel_ShowPlotWarn = uiimage(app.play_ControlsTab2Grid);
            app.play_Channel_ShowPlotWarn.Enable = 'off';
            app.play_Channel_ShowPlotWarn.Visible = 'off';
            app.play_Channel_ShowPlotWarn.Tooltip = {'O plot dos canais é possível apenas se definida,'; 'na canalização, a sua largura de banda.'};
            app.play_Channel_ShowPlotWarn.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Warn_18.png');
            app.play_ControlsTab2Image = uiimage(app.play_ControlsTab2Grid);
            app.play_ControlsTab2Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.play_ControlsTab2Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Channel_32.png');
            app.play_ControlsTab2Info = uigridlayout(app.play_ControlsGrid);
            app.play_Channel_Label = uilabel(app.play_ControlsTab2Info);
            app.play_Channel_Label.Text = 'INCLUSÃO DE CANAIS';
            app.play_Channel_RadioGroup = uibuttongroup(app.play_ControlsTab2Info);
            app.play_Channel_RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @play_Channel_RadioGroupSelectionChanged, true);
            app.play_Channel_ReferenceList = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_ReferenceList.Text = {'Canalização'; 'de referência'};
            app.play_Channel_ReferenceList.Value = true;
            app.play_Channel_Multiples = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_Multiples.Text = {'Faixa de'; 'frequência'};
            app.play_Channel_Single = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_Single.Text = 'Canal';
            app.play_Channel_File = uiradiobutton(app.play_Channel_RadioGroup);
            app.play_Channel_File.Text = 'Arquivo';
            app.play_Channel_Panel = uipanel(app.play_ControlsTab2Info);
            app.play_Channel_Grid = uigridlayout(app.play_Channel_Panel);
            app.play_Channel_ListLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_ListLabel.Text = 'Referência:';
            app.play_Channel_ListUpdate = uiimage(app.play_Channel_Grid);
            app.play_Channel_ListUpdate.ImageClickedFcn = createCallbackFcn(app, @play_Channel_AutomaticChannelListUpdate, true);
            app.play_Channel_ListUpdate.Tooltip = {'Retorna à configuração padrão'};
            app.play_Channel_ListUpdate.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.play_Channel_List = uidropdown(app.play_Channel_Grid);
            app.play_Channel_List.Items = {};
            app.play_Channel_List.ValueChangedFcn = createCallbackFcn(app, @play_Channel_AutomaticChannelListValueChanged, true);
            app.play_Channel_List.Value = {};
            app.play_Channel_Name = uieditfield(app.play_Channel_Grid, 'text');
            app.play_Channel_Name.CharacterLimits = [0 128];
            app.play_Channel_nChannelsLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_nChannelsLabel.Text = {'Qtd.'; 'canais:'};
            app.play_Channel_nChannels = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_nChannels.Limits = [-1 Inf];
            app.play_Channel_nChannels.RoundFractionalValues = 'on';
            app.play_Channel_nChannels.ValueDisplayFormat = '%d';
            app.play_Channel_nChannels.Editable = 'off';
            app.play_Channel_nChannels.Value = 1;
            app.play_Channel_FirstChannelLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_FirstChannelLabel.Text = {'Frequência central'; '1º canal (MHz):'};
            app.play_Channel_FirstChannel = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_FirstChannel.Limits = [0 Inf];
            app.play_Channel_FirstChannel.ValueDisplayFormat = '%.3f';
            app.play_Channel_FirstChannel.ValueChangedFcn = createCallbackFcn(app, @play_Channel_FirstChannelValueChanged, true);
            app.play_Channel_LastChannelLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_LastChannelLabel.Text = {'Frequência central'; 'n-ésimo canal:'};
            app.play_Channel_LastChannel = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_LastChannel.Limits = [0 Inf];
            app.play_Channel_LastChannel.ValueDisplayFormat = '%.3f';
            app.play_Channel_LastChannel.ValueChangedFcn = createCallbackFcn(app, @play_Channel_FirstChannelValueChanged, true);
            app.play_Channel_ClassLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_ClassLabel.Text = {'Classe de '; 'emissão:'};
            app.play_Channel_Class = uidropdown(app.play_Channel_Grid);
            app.play_Channel_Class.Items = {};
            app.play_Channel_Class.Value = {};
            app.play_Channel_StepWidthLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_StepWidthLabel.Text = {'Espaçamento '; 'canais (kHz):'};
            app.play_Channel_StepWidth = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_StepWidth.Limits = [-1 Inf];
            app.play_Channel_StepWidth.ValueDisplayFormat = '%.1f';
            app.play_Channel_StepWidth.ValueChangedFcn = createCallbackFcn(app, @play_Channel_FirstChannelValueChanged, true);
            app.play_Channel_BWLabel = uilabel(app.play_Channel_Grid);
            app.play_Channel_BWLabel.Text = {'Largura canal'; '(kHz):'};
            app.play_Channel_BW = uieditfield(app.play_Channel_Grid, 'numeric');
            app.play_Channel_BW.Limits = [-1 Inf];
            app.play_Channel_BW.ValueDisplayFormat = '%.1f';
            app.play_Channel_Sample = uilabel(app.play_Channel_Grid);
            app.play_Channel_Sample.Interpreter = 'html';
            app.play_Channel_Sample.Text = '<p style="text-align: justify;">101.700 MHz, 101.900 MHz, 102.100 MHz, 102.300 MHz, 102.500 MHz, 102.700 MHz...</font>';
            app.play_Channel_ExternalFilePanel = uipanel(app.play_ControlsTab2Info);
            app.play_Channel_ExternalFileGrid = uigridlayout(app.play_Channel_ExternalFilePanel);
            app.play_Channel_ExternalFileLabel = uilabel(app.play_Channel_ExternalFileGrid);
            app.play_Channel_ExternalFileLabel.Text = 'Formato:';
            app.play_Channel_ExternalFile = uidropdown(app.play_Channel_ExternalFileGrid);
            app.play_Channel_ExternalFile.Items = {'Generic (json)'};
            app.play_Channel_ExternalFile.Value = 'Generic (json)';
            app.play_Channel_FileTemplate = uihyperlink(app.play_Channel_ExternalFileGrid);
            app.play_Channel_FileTemplate.HyperlinkClickedFcn = createCallbackFcn(app, @config_DownloadFileTemplate, true);
            app.play_Channel_FileTemplate.Text = 'Download modelo do arquivo';
            app.play_Channel_add = uiimage(app.play_ControlsTab2Info);
            app.play_Channel_add.ImageClickedFcn = createCallbackFcn(app, @play_Channel_addChannel, true);
            app.play_Channel_add.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.play_Channel_Tree = uitree(app.play_ControlsTab2Info);
            app.play_Channel_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_Channel_TreeSelectionChanged, true);
            app.play_BandLimits_Status = uicheckbox(app.play_ControlsTab2Info);
            app.play_BandLimits_Status.ValueChangedFcn = createCallbackFcn(app, @play_BandLimits_StatusValueChanged, true);
            app.play_BandLimits_Status.Text = 'Limitar detecção de emissões a subfaixa(s)';
            app.play_BandLimits_Panel = uipanel(app.play_ControlsTab2Info);
            app.play_BandLimits_Grid = uigridlayout(app.play_BandLimits_Panel);
            app.play_BandLimits_xLim1 = uieditfield(app.play_BandLimits_Grid, 'numeric');
            app.play_BandLimits_xLim1.ValueDisplayFormat = '%.3f';
            app.play_BandLimits_xLim1.Enable = 'off';
            app.play_BandLimits_xLabel = uilabel(app.play_BandLimits_Grid);
            app.play_BandLimits_xLabel.Text = 'Frequência';
            app.play_BandLimits_xLim2 = uieditfield(app.play_BandLimits_Grid, 'numeric');
            app.play_BandLimits_xLim2.ValueDisplayFormat = '%.3f';
            app.play_BandLimits_xLim2.Enable = 'off';
            app.play_BandLimits_add = uiimage(app.play_ControlsTab2Info);
            app.play_BandLimits_add.ImageClickedFcn = createCallbackFcn(app, @play_BandLimits_addImageClicked, true);
            app.play_BandLimits_add.Enable = 'off';
            app.play_BandLimits_add.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.play_BandLimits_Tree = uitree(app.play_ControlsTab2Info);
            app.play_BandLimits_Tree.Multiselect = 'on';
            app.play_BandLimits_Tree.Enable = 'off';
            app.play_Channel_ShowPlot = uiimage(app.play_ControlsTab2Info);
            app.play_Channel_ShowPlot.ImageClickedFcn = createCallbackFcn(app, @play_Channel_ShowPlotImageClicked, true);
            app.play_Channel_ShowPlot.Tooltip = {''};
            app.play_Channel_ShowPlot.ImageSource = fullfile(pathToMLAPP, 'Icons', 'EyeNegative_32.png');
            app.play_ControlsTab3Grid = uigridlayout(app.play_ControlsGrid);
            app.play_ControlsTab3Grid.Tag = 'COLORLOCKED';
            app.play_ControlsTab3Label = uilabel(app.play_ControlsTab3Grid);
            app.play_ControlsTab3Label.Text = 'EMISSÕES';
            app.play_ControlsTab3Image = uiimage(app.play_ControlsTab3Grid);
            app.play_ControlsTab3Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.play_ControlsTab3Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Detection_32.png');
            app.play_ControlsTab3Info = uigridlayout(app.play_ControlsGrid);
            app.play_FindPeaks_Label = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_Label.Text = 'DETECÇÃO DE EMISSÕES';
            app.play_FindPeaks_RadioGroup = uibuttongroup(app.play_ControlsTab3Info);
            app.play_FindPeaks_RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_RadioGroupSelectionChanged, true);
            app.play_FindPeaks_auto = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_auto.Text = 'Automática';
            app.play_FindPeaks_auto.Value = true;
            app.play_FindPeaks_ROI = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_ROI.Text = 'ROI';
            app.play_FindPeaks_DataTips = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_DataTips.Text = 'DataTips';
            app.play_FindPeaks_File = uiradiobutton(app.play_FindPeaks_RadioGroup);
            app.play_FindPeaks_File.Text = 'Arquivo';
            app.play_FindPeaks_ParametersPanel = uipanel(app.play_ControlsTab3Info);
            app.play_FindPeaks_ParametersGrid = uigridlayout(app.play_FindPeaks_ParametersPanel);
            app.play_FindPeaks_AlgorithmLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_AlgorithmLabel.Text = 'Algoritmo:';
            app.play_FindPeaks_Algorithm = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Algorithm.Items = {'FindPeaks', 'FindPeaks+OCC'};
            app.play_FindPeaks_Algorithm.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_AlgorithmValueChanged, true);
            app.play_FindPeaks_Algorithm.Value = 'FindPeaks';
            app.play_FindPeaks_TraceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_TraceLabel.Text = {'Tipo de '; 'traço:'};
            app.play_FindPeaks_Trace = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Trace.Items = {'MinHold', 'Média', 'MaxHold'};
            app.play_FindPeaks_Trace.Value = 'Média';
            app.play_FindPeaks_NumbersLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_NumbersLabel.Text = {'Número de '; 'picos:'};
            app.play_FindPeaks_Numbers = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Numbers.Limits = [1 100];
            app.play_FindPeaks_Numbers.RoundFractionalValues = 'on';
            app.play_FindPeaks_Numbers.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Numbers.Value = 10;
            app.play_FindPeaks_THRLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_THRLabel.Text = {'Threshold'; '(dB):'};
            app.play_FindPeaks_THR = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_THR.Step = 10;
            app.play_FindPeaks_THR.RoundFractionalValues = 'on';
            app.play_FindPeaks_THR.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_THR.Value = -Inf;
            app.play_FindPeaks_prominenceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_prominenceLabel.Text = {'Proeminência '; '(dB):'};
            app.play_FindPeaks_prominence = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_prominence.Step = 10;
            app.play_FindPeaks_prominence.Limits = [1 Inf];
            app.play_FindPeaks_prominence.RoundFractionalValues = 'on';
            app.play_FindPeaks_prominence.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_prominence.Value = 30;
            app.play_FindPeaks_distanceLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distanceLabel.Text = {'Distância entre'; 'picos (kHz):'};
            app.play_FindPeaks_distance = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_distance.Step = 25;
            app.play_FindPeaks_distance.Limits = [0 Inf];
            app.play_FindPeaks_distance.RoundFractionalValues = 'on';
            app.play_FindPeaks_distance.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_distance.Value = 25;
            app.play_FindPeaks_BWLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_BWLabel.Text = 'Largura ocupada (kHz):';
            app.play_FindPeaks_BW = uispinner(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_BW.Step = 10;
            app.play_FindPeaks_BW.Limits = [0 Inf];
            app.play_FindPeaks_BW.RoundFractionalValues = 'on';
            app.play_FindPeaks_BW.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_BW.Value = 10;
            app.play_FindPeaks_MeanPanel = uipanel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_MeanPanel.Title = 'MÉDIA';
            app.play_FindPeaks_MeanGrid = uigridlayout(app.play_FindPeaks_MeanPanel);
            app.play_FindPeaks_Prominence1Label = uilabel(app.play_FindPeaks_MeanGrid);
            app.play_FindPeaks_Prominence1Label.Text = {'Proeminência'; '(dB):'};
            app.play_FindPeaks_Prominence1 = uispinner(app.play_FindPeaks_MeanGrid);
            app.play_FindPeaks_Prominence1.Step = 10;
            app.play_FindPeaks_Prominence1.Limits = [1 Inf];
            app.play_FindPeaks_Prominence1.RoundFractionalValues = 'on';
            app.play_FindPeaks_Prominence1.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Prominence1.Value = 10;
            app.play_FindPeaks_MaxHoldPanel = uipanel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_MaxHoldPanel.Title = 'MAXHOLD';
            app.play_FindPeaks_MaxHoldGrid = uigridlayout(app.play_FindPeaks_MaxHoldPanel);
            app.play_FindPeaks_Prominence2Label = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_Prominence2Label.Text = {'Proeminência'; '(dB):'};
            app.play_FindPeaks_Prominence2 = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_Prominence2.Step = 10;
            app.play_FindPeaks_Prominence2.Limits = [1 Inf];
            app.play_FindPeaks_Prominence2.RoundFractionalValues = 'on';
            app.play_FindPeaks_Prominence2.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_Prominence2.Value = 30;
            app.play_FindPeaks_OCCLabel = uilabel(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_OCCLabel.Interpreter = 'html';
            app.play_FindPeaks_OCCLabel.Text = {'Ocupação (%):'; '<p style="line-height:10px; font-size:9px; color:gray;">(Mínima | Máxima)</p>'};
            app.play_FindPeaks_meanOCC = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_meanOCC.Step = 10;
            app.play_FindPeaks_meanOCC.Limits = [0 100];
            app.play_FindPeaks_meanOCC.RoundFractionalValues = 'on';
            app.play_FindPeaks_meanOCC.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_meanOCC.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_OCCValueChanged, true);
            app.play_FindPeaks_meanOCC.Value = 1;
            app.play_FindPeaks_maxOCC = uispinner(app.play_FindPeaks_MaxHoldGrid);
            app.play_FindPeaks_maxOCC.Step = 10;
            app.play_FindPeaks_maxOCC.Limits = [0 100];
            app.play_FindPeaks_maxOCC.RoundFractionalValues = 'on';
            app.play_FindPeaks_maxOCC.ValueDisplayFormat = '%.0f';
            app.play_FindPeaks_maxOCC.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_OCCValueChanged, true);
            app.play_FindPeaks_maxOCC.Value = 10;
            app.play_FindPeaks_ClassLabel = uilabel(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_ClassLabel.Visible = 'off';
            app.play_FindPeaks_ClassLabel.Text = {'Classe de '; 'emissão:'};
            app.play_FindPeaks_Class = uidropdown(app.play_FindPeaks_ParametersGrid);
            app.play_FindPeaks_Class.Items = {'Genérico', 'Radiocomunicação', 'Radiodifusão (FM)', 'Radiodifusão (TV)'};
            app.play_FindPeaks_Class.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_ClassValueChanged, true);
            app.play_FindPeaks_Class.Visible = 'off';
            app.play_FindPeaks_Class.Value = 'Genérico';
            app.play_FindPeaks_add = uiimage(app.play_ControlsTab3Info);
            app.play_FindPeaks_add.ImageClickedFcn = createCallbackFcn(app, @play_FindPeaks_addEmission, true);
            app.play_FindPeaks_add.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.play_FindPeaks_Tree = uitree(app.play_ControlsTab3Info);
            app.play_FindPeaks_Tree.Multiselect = 'on';
            app.play_FindPeaks_Tree.SelectionChangedFcn = createCallbackFcn(app, @play_FindPeaks_TreeSelectionChanged, true);
            app.play_FindPeaks_PeakCFLabel = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_PeakCFLabel.Text = 'Frequência central (MHz):';
            app.play_FindPeaks_PeakCF = uieditfield(app.play_ControlsTab3Info, 'numeric');
            app.play_FindPeaks_PeakCF.ValueDisplayFormat = '%.3f';
            app.play_FindPeaks_PeakCF.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_editEmission, true);
            app.play_FindPeaks_PeakCF.Enable = 'off';
            app.play_FindPeaks_PeakBWLabel = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_PeakBWLabel.Text = 'Largura ocupada (kHz):';
            app.play_FindPeaks_PeakBW = uieditfield(app.play_ControlsTab3Info, 'numeric');
            app.play_FindPeaks_PeakBW.ValueDisplayFormat = '%.3f';
            app.play_FindPeaks_PeakBW.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_editEmission, true);
            app.play_FindPeaks_PeakBW.Enable = 'off';
            app.play_FindPeaks_DescriptionLabel = uilabel(app.play_ControlsTab3Info);
            app.play_FindPeaks_DescriptionLabel.Text = {'Informações'; 'complementares:'};
            app.play_FindPeaks_Description = uitextarea(app.play_ControlsTab3Info);
            app.play_FindPeaks_Description.ValueChangedFcn = createCallbackFcn(app, @play_FindPeaks_editEmission, true);
            app.play_FindPeaks_Description.Enable = 'off';
            app.play_FindPeaks_ExternalFilePanel = uipanel(app.play_ControlsTab3Info);
            app.play_FindPeaks_ExternalFileGrid = uigridlayout(app.play_FindPeaks_ExternalFilePanel);
            app.play_FindPeaks_ExternalFileLabel = uilabel(app.play_FindPeaks_ExternalFileGrid);
            app.play_FindPeaks_ExternalFileLabel.Text = 'Formato:';
            app.play_FindPeaks_ExternalFile = uidropdown(app.play_FindPeaks_ExternalFileGrid);
            app.play_FindPeaks_ExternalFile.Items = {'Generic (csv, txt, json, xls, xlsx)', 'Romes (csv)'};
            app.play_FindPeaks_ExternalFile.Value = 'Generic (csv, txt, json, xls, xlsx)';
            app.play_FindPeaks_FileTemplate = uihyperlink(app.play_FindPeaks_ExternalFileGrid);
            app.play_FindPeaks_FileTemplate.HyperlinkClickedFcn = createCallbackFcn(app, @config_DownloadFileTemplate, true);
            app.play_FindPeaks_FileTemplate.Text = 'Download modelo do arquivo';
            app.report_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.report_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.report_ControlsTab1Label = uilabel(app.report_ControlsTab1Grid);
            app.report_ControlsTab1Label.Text = 'PROJETO';
            app.report_ProjectWarnIcon = uiimage(app.report_ControlsTab1Grid);
            app.report_ProjectWarnIcon.Visible = 'off';
            app.report_ProjectWarnIcon.Tooltip = {'Pendente salvar projeto'};
            app.report_ProjectWarnIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Warn_18.png');
            app.report_ControlsTab1Image = uiimage(app.report_ControlsTab1Grid);
            app.report_ControlsTab1Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.report_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Report_32.png');
            app.report_ControlsTab1Info = uigridlayout(app.play_ControlsGrid);
            app.report_ThreadAlgorithmsLabel = uilabel(app.report_ControlsTab1Info);
            app.report_ThreadAlgorithmsLabel.Text = 'FLUXOS A PROCESSAR';
            app.report_ProjectNameLabel = uilabel(app.report_ControlsTab1Info);
            app.report_ProjectNameLabel.Text = 'ARQUIVO';
            app.report_ProjectName = uitextarea(app.report_ControlsTab1Info);
            app.report_ProjectName.Tag = 'file';
            app.report_ProjectName.Editable = 'off';
            app.report_ProjectNew = uiimage(app.report_ControlsTab1Info);
            app.report_ProjectNew.ImageClickedFcn = createCallbackFcn(app, @report_ProjectToolbarImageClicked, true);
            app.report_ProjectNew.Tooltip = {'Cria novo projeto'};
            app.report_ProjectNew.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addFiles_32.png');
            app.report_ProjectOpen = uiimage(app.report_ControlsTab1Info);
            app.report_ProjectOpen.ImageClickedFcn = createCallbackFcn(app, @report_ProjectToolbarImageClicked, true);
            app.report_ProjectOpen.Tooltip = {'Abre projeto'};
            app.report_ProjectOpen.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.report_ProjectSave = uiimage(app.report_ControlsTab1Info);
            app.report_ProjectSave.ImageClickedFcn = createCallbackFcn(app, @report_ProjectToolbarImageClicked, true);
            app.report_ProjectSave.Tooltip = {'Salva projeto'};
            app.report_ProjectSave.ImageSource = fullfile(pathToMLAPP, 'Icons', 'saveFile_32.png');
            app.report_DocumentPanelLabel = uilabel(app.report_ControlsTab1Info);
            app.report_DocumentPanelLabel.Text = 'ATIVIDADE DE INSPEÇÃO';
            app.report_DocumentPanel = uipanel(app.report_ControlsTab1Info);
            app.GridLayout4 = uigridlayout(app.report_DocumentPanel);
            app.report_ModelNameLabel = uilabel(app.GridLayout4);
            app.report_ModelNameLabel.Text = 'Modelo do relatório:';
            app.report_ModelName = uidropdown(app.GridLayout4);
            app.report_ModelName.Items = {};
            app.report_ModelName.ValueChangedFcn = createCallbackFcn(app, @report_ModelNameValueChanged, true);
            app.report_ModelName.Tag = 'documentModel';
            app.report_ModelName.Value = {};
            app.report_VersionLabel = uilabel(app.GridLayout4);
            app.report_VersionLabel.Text = 'Versão:';
            app.report_Version = uidropdown(app.GridLayout4);
            app.report_Version.Items = {'Preliminar', 'Definitiva'};
            app.report_Version.Value = 'Preliminar';
            app.report_AddProjectAttachment = uiimage(app.GridLayout4);
            app.report_AddProjectAttachment.ImageClickedFcn = createCallbackFcn(app, @report_ExternalFilesMenuSelected, true);
            app.report_AddProjectAttachment.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.report_ExternalFilesLabel = uilabel(app.GridLayout4);
            app.report_ExternalFilesLabel.Text = 'Arquivos externos:';
            app.report_ExternalFiles = uitable(app.GridLayout4);
            app.report_ExternalFiles.ColumnName = {'ID'; 'TAG'; 'ARQUIVO'};
            app.report_ExternalFiles.RowName = {};
            app.report_ExternalFiles.Tag = 'externalFiles';
            app.report_Issue = uieditfield(app.GridLayout4, 'numeric');
            app.report_Issue.Limits = [-1 Inf];
            app.report_Issue.RoundFractionalValues = 'on';
            app.report_Issue.ValueDisplayFormat = '%d';
            app.report_Issue.ValueChangedFcn = createCallbackFcn(app, @report_SaveWarn, true);
            app.report_Issue.Tag = 'issue';
            app.report_Issue.Value = -1;
            app.report_IssueLabel = uilabel(app.GridLayout4);
            app.report_IssueLabel.Text = 'Inspeção:';
            app.report_TreeAddImage = uiimage(app.report_ControlsTab1Info);
            app.report_TreeAddImage.ImageClickedFcn = createCallbackFcn(app, @report_TreeAddImagePushed, true);
            app.report_TreeAddImage.Tooltip = {''};
            app.report_TreeAddImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.report_Tree = uitree(app.report_ControlsTab1Info, 'checkbox');
            app.report_ControlsTab2Grid = uigridlayout(app.play_ControlsGrid);
            app.report_ControlsTab2Grid.Tag = 'COLORLOCKED';
            app.report_ControlsTab2Label = uilabel(app.report_ControlsTab2Grid);
            app.report_ControlsTab2Label.Text = 'API FISCALIZA';
            app.report_ControlsTab2Image = uiimage(app.report_ControlsTab2Grid);
            app.report_ControlsTab2Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.report_ControlsTab2Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Redmine_18.png');
            app.report_ControlsTab2Info = uigridlayout(app.play_ControlsGrid);
            app.report_Fiscaliza_PanelLabel = uilabel(app.report_ControlsTab2Info);
            app.report_Fiscaliza_PanelLabel.Text = 'ASPECTOS GERAIS';
            app.report_FiscalizaRefresh = uiimage(app.report_ControlsTab2Info);
            app.report_FiscalizaRefresh.ImageClickedFcn = createCallbackFcn(app, @report_FiscalizaStaticButtonPushed, true);
            app.report_FiscalizaRefresh.Tooltip = {'Atualiza informações da inspeção'};
            app.report_FiscalizaRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.report_FiscalizaPanel = uipanel(app.report_ControlsTab2Info);
            app.report_FiscalizaGrid = uigridlayout(app.report_FiscalizaPanel);
            app.report_FiscalizaIcon = uiimage(app.report_FiscalizaGrid);
            app.report_FiscalizaIcon.Tag = 'FiscalizaPlaceHolder';
            app.report_FiscalizaIcon.Enable = 'off';
            app.report_FiscalizaIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Redmine_512.png');
            app.misc_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.misc_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.misc_ControlsTab1Label = uilabel(app.misc_ControlsTab1Grid);
            app.misc_ControlsTab1Label.Text = 'MISCELÂNEAS';
            app.misc_ControlsTab1Image = uiimage(app.misc_ControlsTab1Grid);
            app.misc_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Misc_32.png');
            app.misc_ControlsTab1Info = uigridlayout(app.play_ControlsGrid);
            app.misc_Label1 = uilabel(app.misc_ControlsTab1Info);
            app.misc_Label1.Text = 'OPERAÇÃO';
            app.misc_Panel1 = uipanel(app.misc_ControlsTab1Info);
            app.misc_Grid1 = uigridlayout(app.misc_Panel1);
            app.misc_Save = uibutton(app.misc_Grid1, 'push');
            app.misc_Save.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Save.Icon = fullfile(pathToMLAPP, 'Icons', 'saveFile_32.png');
            app.misc_Save.Tooltip = {''};
            app.misc_Save.Text = '';
            app.misc_SaveLabel = uilabel(app.misc_Grid1);
            app.misc_SaveLabel.Text = {'Salvar'; 'fluxo(s)'};
            app.misc_Duplicate = uibutton(app.misc_Grid1, 'push');
            app.misc_Duplicate.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Duplicate.Icon = fullfile(pathToMLAPP, 'Icons', 'duplicateFile_32.png');
            app.misc_Duplicate.Tooltip = {''};
            app.misc_Duplicate.Text = '';
            app.misc_DuplicateLabel = uilabel(app.misc_Grid1);
            app.misc_DuplicateLabel.Text = {'Duplicar'; 'fluxo(s)'};
            app.misc_Merge = uibutton(app.misc_Grid1, 'push');
            app.misc_Merge.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Merge.Icon = fullfile(pathToMLAPP, 'Icons', 'Merge_32.png');
            app.misc_Merge.Tooltip = {''};
            app.misc_Merge.Text = '';
            app.misc_MergeLabel = uilabel(app.misc_Grid1);
            app.misc_MergeLabel.Text = {'Mesclar'; 'fluxos'};
            app.misc_Del = uibutton(app.misc_Grid1, 'push');
            app.misc_Del.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Del.Icon = fullfile(pathToMLAPP, 'Icons', 'Delete_32Red.png');
            app.misc_Del.Tooltip = {''};
            app.misc_Del.Text = '';
            app.misc_DelLabel = uilabel(app.misc_Grid1);
            app.misc_DelLabel.Text = {'Excluir'; 'fluxo(s)'};
            app.misc_Serarator = uiimage(app.misc_Grid1);
            app.misc_Serarator.Enable = 'off';
            app.misc_Serarator.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV.png');
            app.misc_Export = uibutton(app.misc_Grid1, 'push');
            app.misc_Export.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Export.Icon = fullfile(pathToMLAPP, 'Icons', 'Export_24.png');
            app.misc_Export.Tooltip = {''};
            app.misc_Export.Text = '';
            app.misc_ExportLabel = uilabel(app.misc_Grid1);
            app.misc_ExportLabel.Text = {'Exportar'; 'análise'};
            app.misc_Import = uibutton(app.misc_Grid1, 'push');
            app.misc_Import.ButtonPushedFcn = createCallbackFcn(app, @misc_OperationsCallbacks, true);
            app.misc_Import.Icon = fullfile(pathToMLAPP, 'Icons', 'Import_24.png');
            app.misc_Import.Tooltip = {''};
            app.misc_Import.Text = '';
            app.misc_ImportLabel = uilabel(app.misc_Grid1);
            app.misc_ImportLabel.Text = {'Importar'; 'análise'};
            app.misc_Filter = uibutton(app.misc_Grid1, 'push');
            app.misc_Filter.Icon = fullfile(pathToMLAPP, 'Icons', 'Filter_18.png');
            app.misc_Filter.Tooltip = {'Exclui todas as informações espectrais'};
            app.misc_Filter.Text = '';
            app.misc_FilterLabel = uilabel(app.misc_Grid1);
            app.misc_FilterLabel.Text = 'Filtrar fluxo(s)';
            app.misc_EditLocal = uibutton(app.misc_Grid1, 'push');
            app.misc_EditLocal.Icon = fullfile(pathToMLAPP, 'Icons', 'Pin_32.png');
            app.misc_EditLocal.Tooltip = {'Exclui todas as informações espectrais'};
            app.misc_EditLocal.Text = '';
            app.misc_EditLocalLabel = uilabel(app.misc_Grid1);
            app.misc_EditLocalLabel.Text = {'Editar'; 'Local'};
            app.misc_AddCurve = uibutton(app.misc_Grid1, 'push');
            app.misc_AddCurve.Icon = fullfile(pathToMLAPP, 'Icons', 'RFFilter_32.png');
            app.misc_AddCurve.Tooltip = {'Exclui todas as informações espectrais'};
            app.misc_AddCurve.Text = '';
            app.misc_AddCurveLabel = uilabel(app.misc_Grid1);
            app.misc_AddCurveLabel.Text = {'Aplicar'; 'correção'};
            app.misc_DeleteAll = uibutton(app.misc_Grid1, 'push');
            app.misc_DeleteAll.ButtonPushedFcn = createCallbackFcn(app, @misc_DeleteAllButtonPushed, true);
            app.misc_DeleteAll.Icon = fullfile(pathToMLAPP, 'Icons', 'Trash_32.png');
            app.misc_DeleteAll.Tooltip = {'Exclui todas as informações espectrais'};
            app.misc_DeleteAll.Text = '';
            app.misc_DeleteAllLabel = uilabel(app.misc_Grid1);
            app.misc_DeleteAllLabel.Text = 'Reiniciar análise';
            app.edit_gpsPanel = uipanel(app.misc_ControlsTab1Info);
            app.edit_gpsPanel.Title = 'LOCAL DA MONITORAÇÃO';
            app.edit_gpsGrid = uigridlayout(app.edit_gpsPanel);
            app.edit_gpsLocationLabel1 = uilabel(app.edit_gpsGrid);
            app.edit_gpsLocationLabel1.Interpreter = 'html';
            app.edit_gpsLocationLabel1.Text = {'Coordenadas geográficas:'; '<p style="line-height:10px; font-size:9px; color:gray;">(Fonte | Latitude | Longitude)</p>'};
            app.edit_gpsLocationPanel = uipanel(app.edit_gpsGrid);
            app.edit_gpsLocationGrid = uigridlayout(app.edit_gpsLocationPanel);
            app.edit_gpsSource = uieditfield(app.edit_gpsLocationGrid, 'text');
            app.edit_gpsSource.Editable = 'off';
            app.edit_gpsSource.Value = 'auto';
            app.edit_gpsLatitude = uieditfield(app.edit_gpsLocationGrid, 'numeric');
            app.edit_gpsLatitude.Limits = [-90 90];
            app.edit_gpsLatitude.ValueDisplayFormat = '%.6f';
            app.edit_gpsLatitude.ValueChangedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsLatitude.Editable = 'off';
            app.edit_gpsLatitude.Value = -1;
            app.edit_gpsLongitude = uieditfield(app.edit_gpsLocationGrid, 'numeric');
            app.edit_gpsLongitude.Limits = [-180 180];
            app.edit_gpsLongitude.ValueDisplayFormat = '%.6f';
            app.edit_gpsLongitude.ValueChangedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsLongitude.Editable = 'off';
            app.edit_gpsLongitude.Value = -1;
            app.edit_gpsLocationLabel2 = uilabel(app.edit_gpsGrid);
            app.edit_gpsLocationLabel2.Interpreter = 'html';
            app.edit_gpsLocationLabel2.Text = {'Localidade:'; '<p style="line-height:10px; font-size:9px; color:gray;">(Fonte | Município/UF)</p>'};
            app.edit_gpsCityPanel = uipanel(app.edit_gpsGrid);
            app.edit_gpsCityGrid = uigridlayout(app.edit_gpsCityPanel);
            app.edit_gpsCitySource = uieditfield(app.edit_gpsCityGrid, 'text');
            app.edit_gpsCitySource.Editable = 'off';
            app.edit_gpsCitySource.Value = 'API';
            app.edit_gpsCity = uieditfield(app.edit_gpsCityGrid, 'text');
            app.edit_gpsCity.ValueChangedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsCity.Editable = 'off';
            app.edit_gpsCityButton = uiimage(app.edit_gpsGrid);
            app.edit_gpsCityButton.ImageClickedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.edit_gpsCityButton.Tooltip = {'Habilita edição do campo "Município/UF"'};
            app.edit_gpsCityButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.edit_unitPanel = uipanel(app.misc_ControlsTab1Info);
            app.edit_unitPanel.Title = 'UNIDADE DE MEDIDA';
            app.misc_Edit_unitGrid = uigridlayout(app.edit_unitPanel);
            app.misc_Edit_unitRawLabel = uilabel(app.misc_Edit_unitGrid);
            app.misc_Edit_unitRawLabel.Interpreter = 'html';
            app.misc_Edit_unitRawLabel.Text = {'Unidade original:'; '<p style="line-height:10px; font-size:9px; color:gray;">(coleta)</p>'};
            app.misc_Edit_unitRaw = uieditfield(app.misc_Edit_unitGrid, 'text');
            app.misc_Edit_unitRaw.Editable = 'off';
            app.misc_Edit_kFactorLabel = uilabel(app.misc_Edit_unitGrid);
            app.misc_Edit_kFactorLabel.Interpreter = 'html';
            app.misc_Edit_kFactorLabel.Text = {'Curva de correção:'; '<p style="line-height:10px; font-size:9px; color:gray;">(calibração, fator-k)</p>'};
            app.misc_Edit_kFactor = uidropdown(app.misc_Edit_unitGrid);
            app.misc_Edit_kFactor.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (Argus)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (690 MHz - 6 GHz)'};
            app.misc_Edit_kFactor.Value = '';
            app.misc_Edit_kFactorAdd = uiimage(app.misc_Edit_unitGrid);
            app.misc_Edit_kFactorAdd.ImageClickedFcn = createCallbackFcn(app, @edit_unitLevelCallbacks, true);
            app.misc_Edit_kFactorAdd.ImageSource = fullfile(pathToMLAPP, 'Icons', 'addSymbol_32.png');
            app.misc_Edit_kFactorTree = uitree(app.misc_Edit_unitGrid);
            app.edit_unitRefresh = uiimage(app.misc_Edit_unitGrid);
            app.edit_unitRefresh.ImageClickedFcn = createCallbackFcn(app, @edit_unitLevelCallbacks, true);
            app.edit_unitRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.Panel_4 = uipanel(app.misc_ControlsTab1Info);
            app.Panel_4.Title = 'Panel';
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = 'filtro temporal. datepicker, spinner e árvore registrando os vários filtros temporais';
            app.play_ControlsTab1Grid = uigridlayout(app.play_ControlsGrid);
            app.play_ControlsTab1Grid.Tag = 'COLORLOCKED';
            app.play_ControlsTab1Label = uilabel(app.play_ControlsTab1Grid);
            app.play_ControlsTab1Label.Text = 'PLAYBACK';
            app.play_ControlsTab1Image = uiimage(app.play_ControlsTab1Grid);
            app.play_ControlsTab1Image.ImageClickedFcn = createCallbackFcn(app, @play_TabGroupVisibility, true);
            app.play_ControlsTab1Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Playback_32.png');
            app.play_toolGrid = uigridlayout(app.play_Grid);
            app.tool_LayoutLeft = uiimage(app.play_toolGrid);
            app.tool_LayoutLeft.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.tool_LayoutLeft.ImageSource = fullfile(pathToMLAPP, 'Icons', 'ArrowLeft_32.png');
            app.tool_ExceptionList = uiimage(app.play_toolGrid);
            app.tool_ExceptionList.ImageClickedFcn = createCallbackFcn(app, @report_OpenSignalAnalysis, true);
            app.tool_ExceptionList.Tag = 'REPORT';
            app.tool_ExceptionList.Visible = 'off';
            app.tool_ExceptionList.Tooltip = {'Edita provável emissor de emissões'};
            app.tool_ExceptionList.ImageSource = fullfile(pathToMLAPP, 'Icons', 'exceptionList_32.png');
            app.tool_ReportAnalysis = uiimage(app.play_toolGrid);
            app.tool_ReportAnalysis.ImageClickedFcn = createCallbackFcn(app, @report_playButtonPushed, true);
            app.tool_ReportAnalysis.Tag = 'REPORT';
            app.tool_ReportAnalysis.Enable = 'off';
            app.tool_ReportAnalysis.Visible = 'off';
            app.tool_ReportAnalysis.Tooltip = {'Identifica emissões do(s) fluxo(s) selecionado(s)'};
            app.tool_ReportAnalysis.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Zoom_32.png');
            app.tool_ReportGenerator = uiimage(app.play_toolGrid);
            app.tool_ReportGenerator.ImageClickedFcn = createCallbackFcn(app, @report_playButtonPushed, true);
            app.tool_ReportGenerator.Tag = 'REPORT';
            app.tool_ReportGenerator.Enable = 'off';
            app.tool_ReportGenerator.Visible = 'off';
            app.tool_ReportGenerator.Tooltip = {'Gera relatório'};
            app.tool_ReportGenerator.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Publish_HTML_16.png');
            app.tool_FiscalizaAutoFill = uiimage(app.play_toolGrid);
            app.tool_FiscalizaAutoFill.ImageClickedFcn = createCallbackFcn(app, @report_FiscalizaStaticButtonPushed, true);
            app.tool_FiscalizaAutoFill.Tag = 'FISCALIZA';
            app.tool_FiscalizaAutoFill.Enable = 'off';
            app.tool_FiscalizaAutoFill.Visible = 'off';
            app.tool_FiscalizaAutoFill.Tooltip = {'Preenche campos automaticamente'};
            app.tool_FiscalizaAutoFill.ImageSource = fullfile(pathToMLAPP, 'Icons', 'AutoFill_36Blue.png');
            app.tool_FiscalizaUpdate = uiimage(app.play_toolGrid);
            app.tool_FiscalizaUpdate.ImageClickedFcn = createCallbackFcn(app, @report_FiscalizaStaticButtonPushed, true);
            app.tool_FiscalizaUpdate.Tag = 'FISCALIZA';
            app.tool_FiscalizaUpdate.Enable = 'off';
            app.tool_FiscalizaUpdate.Visible = 'off';
            app.tool_FiscalizaUpdate.Tooltip = {'Atualiza inspeção no FISCALIZA'};
            app.tool_FiscalizaUpdate.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Up_24.png');
            app.tool_MiscEdit = uiimage(app.play_toolGrid);
            app.tool_MiscEdit.ImageClickedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.tool_MiscEdit.Tag = 'MISC';
            app.tool_MiscEdit.Visible = 'off';
            app.tool_MiscEdit.Tooltip = {'Habilita edição dos campos "Latitude" e "Longitude"'};
            app.tool_MiscEdit.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_32.png');
            app.tool_MiscCancel = uiimage(app.play_toolGrid);
            app.tool_MiscCancel.Tag = 'MISC';
            app.tool_MiscCancel.Visible = 'off';
            app.tool_MiscCancel.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Delete_32Red.png');
            app.tool_MiscRefresh = uiimage(app.play_toolGrid);
            app.tool_MiscRefresh.ImageClickedFcn = createCallbackFcn(app, @edit_gpsCallbacks, true);
            app.tool_MiscRefresh.Tag = 'MISC';
            app.tool_MiscRefresh.Visible = 'off';
            app.tool_MiscRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18.png');
            app.tool_LayoutRight = uiimage(app.play_toolGrid);
            app.tool_LayoutRight.ImageClickedFcn = createCallbackFcn(app, @play_PanelsVisibility, true);
            app.tool_LayoutRight.ImageSource = fullfile(pathToMLAPP, 'Icons', 'ArrowRight_32.png');
            app.tool_TimestampSlider = uislider(app.play_toolGrid);
            app.tool_TimestampSlider.MajorTicks = [0 50 100];
            app.tool_TimestampSlider.MajorTickLabels = {''};
            app.tool_TimestampSlider.ValueChangingFcn = createCallbackFcn(app, @tool_TimestampSliderValueChanging, true);
            app.tool_TimestampSlider.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50 52.5 55 57.5 60 62.5 65 67.5 70 72.5 75 77.5 80 82.5 85 87.5 90 92.5 95 97.5 100];
            app.tool_LoopControl = uiimage(app.play_toolGrid);
            app.tool_LoopControl.ImageClickedFcn = createCallbackFcn(app, @play_PlaybackToolbarButtonCallback, true);
            app.tool_LoopControl.Tag = 'loop';
            app.tool_LoopControl.Tooltip = {'Loop do playback'};
            app.tool_LoopControl.ImageSource = fullfile(pathToMLAPP, 'Icons', 'playbackLoop_32Blue.png');
            app.tool_Play = uiimage(app.play_toolGrid);
            app.tool_Play.ImageClickedFcn = createCallbackFcn(app, @play_PlaybackToolbarButtonCallback, true);
            app.tool_Play.Tooltip = {'Playback'};
            app.tool_Play.ImageSource = fullfile(pathToMLAPP, 'Icons', 'play_32.png');
            app.tool_TimestampLabel = uilabel(app.play_toolGrid);
            app.tool_TimestampLabel.Text = {'22 de 328 '; '22/02/2022 08:00:00 '};
            app.Tab3_Config = uitab(app.TabGroup);
            app.Tab3_Config.Title = 'CONFIG';
            app.config_mainGrid = uigridlayout(app.Tab3_Config);
            app.config_Control = uigridlayout(app.config_mainGrid);
            app.config_ControlMenu = uigridlayout(app.config_Control);
            app.config_menuUnderline = uiimage(app.config_ControlMenu);
            app.config_menuUnderline.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineH.png');
            app.config_menuBtn1Grid = uigridlayout(app.config_ControlMenu);
            app.config_menuBtn1Label = uilabel(app.config_menuBtn1Grid);
            app.config_menuBtn1Label.Text = 'Configurações';
            app.config_menuBtn1Icon = uiimage(app.config_menuBtn1Grid);
            app.config_menuBtn1Icon.Tag = '1';
            app.config_menuBtn1Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Settings_18.png');
            app.config_Label = uilabel(app.config_Control);
            app.config_Label.Text = 'PARÂMETROS A CONFIGURAR';
            app.config_ButtonGroup = uibuttongroup(app.config_Control);
            app.config_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @config_ButtonGroupSelectionChanged, true);
            app.config_Option_Search = uiradiobutton(app.config_ButtonGroup);
            app.config_Option_Search.Text = 'Pesquisa';
            app.config_Option_Search.Value = true;
            app.config_Option_Fiscaliza = uiradiobutton(app.config_ButtonGroup);
            app.config_Option_Fiscaliza.Text = 'API Fiscaliza';
            app.config_Option_Folder = uiradiobutton(app.config_ButtonGroup);
            app.config_Option_Folder.Text = 'Mapeamento de pastas';
            app.config_Option1Grid = uigridlayout(app.config_mainGrid);
            app.config_FolderMapLabel = uilabel(app.config_Option1Grid);
            app.config_FolderMapLabel.Text = 'MAPEAMENTO DE PASTAS';
            app.config_FolderMapPanel = uipanel(app.config_Option1Grid);
            app.config_FolderMapGrid = uigridlayout(app.config_FolderMapPanel);
            app.config_Folder_DataHubGETLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETLabel.Text = 'DataHub - GET:';
            app.config_Folder_DataHubGET = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_DataHubGET.Editable = 'off';
            app.config_Folder_DataHubGETButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETButton.Tag = 'DataHub_GET';
            app.config_Folder_DataHubGETButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Folder_DataHubPOSTLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_DataHubPOSTLabel.Text = 'DataHub - POST:';
            app.config_Folder_DataHubPOST = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_DataHubPOST.Editable = 'off';
            app.config_Folder_DataHubPOSTButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_DataHubPOSTButton.Tag = 'DataHub_POST';
            app.config_Folder_DataHubPOSTButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Folder_pythonPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_pythonPathLabel.Text = 'Pasta do ambiente virtual Python (lib fiscaliza):';
            app.config_Folder_pythonPath = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_pythonPath.Editable = 'off';
            app.config_Folder_pythonPathButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_pythonPathButton.Tag = 'pythonPath';
            app.config_Folder_pythonPathButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Folder_userPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_userPathLabel.Text = 'Pasta do usuário:';
            app.config_Folder_userPath = uidropdown(app.config_FolderMapGrid);
            app.config_Folder_userPath.Items = {''};
            app.config_Folder_userPath.Value = '';
            app.config_Folder_userPathButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_userPathButton.Tag = 'userPath';
            app.config_Folder_userPathButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Option2Grid = uigridlayout(app.config_mainGrid);
            app.config_SearchModeLabel = uilabel(app.config_Option2Grid);
            app.config_SearchModeLabel.Text = 'MODO';
            app.config_SearchModePanel = uibuttongroup(app.config_Option2Grid);
            app.config_SearchModeTokenSuggestion = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeTokenSuggestion.Tag = 'strcmp';
            app.config_SearchModeTokenSuggestion.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à palavra que está sendo escrita, sugerindo <i>tokens</i> relacionados.</p>';
            app.config_SearchModeTokenSuggestion.Interpreter = 'html';
            app.config_SearchModeTokenSuggestion.Value = true;
            app.config_SearchModeListOfWords = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeListOfWords.Tag = 'contains';
            app.config_SearchModeListOfWords.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à uma lista de palavras separadas por vírgulas. Não há sugestão de <i>tokens</i> relacionados.</p>';
            app.config_SearchModeListOfWords.Interpreter = 'html';
            app.config_MiscelaneousLabel1 = uilabel(app.config_Option2Grid);
            app.config_MiscelaneousLabel1.Text = 'ALGORITMO SUGESTÃO DE TOKENS';
            app.config_MiscelaneousPanel1 = uipanel(app.config_Option2Grid);
            app.config_MiscelaneousGrid1 = uigridlayout(app.config_MiscelaneousPanel1);
            app.config_nMinCharactersLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinCharactersLabel.Text = 'Qtd. mínima caracteres:';
            app.config_nMinCharacters = uispinner(app.config_MiscelaneousGrid1);
            app.config_nMinCharacters.Limits = [2 4];
            app.config_nMinCharacters.RoundFractionalValues = 'on';
            app.config_nMinCharacters.ValueDisplayFormat = '%d';
            app.config_nMinCharacters.Value = 2;
            app.config_nMinWordsLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinWordsLabel.Interpreter = 'html';
            app.config_nMinWordsLabel.Text = 'Qtd. mínima <i>tokens</i>:';
            app.config_nMinWords = uidropdown(app.config_MiscelaneousGrid1);
            app.config_nMinWords.Items = {'20', '50', '100'};
            app.config_nMinWords.Value = '20';
            app.config_MiscelaneousLabel2 = uilabel(app.config_Option2Grid);
            app.config_MiscelaneousLabel2.Text = 'ANOTAÇÃO DO TIPO "WORDCLOUD"';
            app.config_MiscelaneousPanel2 = uipanel(app.config_Option2Grid);
            app.config_MiscelaneousGrid2 = uigridlayout(app.config_MiscelaneousPanel2);
            app.config_WordCloudAlgorithmLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudAlgorithmLabel.Text = 'Algoritmo gráfico:';
            app.config_WordCloudAlgorithm = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudAlgorithm.Items = {'D3.js', 'MATLAB built-in'};
            app.config_WordCloudAlgorithm.Value = 'D3.js';
            app.config_WordCloudColumnLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumnLabel.Text = 'Pesquisa orientada à coluna:';
            app.config_WordCloudColumn = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumn.Items = {'Modelo', 'Nome Comercial'};
            app.config_WordCloudColumn.Value = 'Modelo';
            app.config_SelectedTableColumnsLabel = uilabel(app.config_Option2Grid);
            app.config_SelectedTableColumnsLabel.Text = 'VISIBILIDADE DE COLUNAS';
            app.config_SelectedTableColumns = uitree(app.config_Option2Grid, 'checkbox');
            app.config_Option3Grid = uigridlayout(app.config_mainGrid);
            app.config_FiscalizaVersionLabel = uilabel(app.config_Option3Grid);
            app.config_FiscalizaVersionLabel.Text = 'VERSÃO DO SISTEMA';
            app.config_FiscalizaVersion = uibuttongroup(app.config_Option3Grid);
            app.config_FiscalizaPD = uiradiobutton(app.config_FiscalizaVersion);
            app.config_FiscalizaPD.Text = 'FISCALIZA PRODUÇÃO';
            app.config_FiscalizaHM = uiradiobutton(app.config_FiscalizaVersion);
            app.config_FiscalizaHM.Text = 'FISCALIZA <font style="color: red;">HOMOLOGAÇÃO</font> (versão destinada a testes)';
            app.config_FiscalizaHM.Interpreter = 'html';
            app.config_FiscalizaHM.Value = true;
            app.config_DefaultIssueValuesLabel = uilabel(app.config_Option3Grid);
            app.config_DefaultIssueValuesLabel.Text = 'VALORES PADRÕES DE CAMPOS DA INSPEÇÃO';
            app.config_DefaultIssueValuesLock = uiimage(app.config_Option3Grid);
            app.config_DefaultIssueValuesLock.ImageSource = fullfile(pathToMLAPP, 'Icons', 'lockClose_18Gray.png');
            app.config_DefaultIssueValuesPanel = uipanel(app.config_Option3Grid);
            app.config_DefaultIssueValuesGrid = uigridlayout(app.config_DefaultIssueValuesPanel);
            app.config_CadastroSTELLabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_CadastroSTELLabel.Text = 'Entidade com cadastro STEL?';
            app.config_CadastroSTEL = uidropdown(app.config_DefaultIssueValuesGrid);
            app.config_CadastroSTEL.Items = {};
            app.config_CadastroSTEL.Enable = 'off';
            app.config_CadastroSTEL.Value = {};
            app.config_GerarPLAILabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_GerarPLAILabel.Text = 'Gerar PLAI?';
            app.config_GerarPLAI = uidropdown(app.config_DefaultIssueValuesGrid);
            app.config_GerarPLAI.Items = {};
            app.config_GerarPLAI.Enable = 'off';
            app.config_GerarPLAI.Value = {};
            app.config_TipoPLAILabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_TipoPLAILabel.Text = 'Tipo do PLAI:';
            app.config_TipoPLAI = uidropdown(app.config_DefaultIssueValuesGrid);
            app.config_TipoPLAI.Items = {};
            app.config_TipoPLAI.Enable = 'off';
            app.config_TipoPLAI.Value = {};
            app.config_MotivoLAILabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_MotivoLAILabel.Text = 'Motivo de LAI:';
            app.config_MotivoLAI = uitree(app.config_DefaultIssueValuesGrid, 'checkbox');
            app.config_MotivoLAI.Enable = 'off';
            app.config_ServicoInspecaoLabel = uilabel(app.config_DefaultIssueValuesGrid);
            app.config_ServicoInspecaoLabel.Text = 'Serviços da Inspeção:';
            app.config_ServicoInspecao = uitree(app.config_DefaultIssueValuesGrid, 'checkbox');
            app.config_ServicoInspecao.Enable = 'off';
            app.menu_Grid = uigridlayout(app.GridLayout);
            app.menu_Grid.Tag = 'COLORLOCKED';
            app.menu_Button1 = uibutton(app.menu_Grid, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button1.Tag = 'FILE';
            app.menu_Button1.Enable = 'off';
            app.menu_Button1.Tooltip = {''};
            app.menu_Button1.Icon = fullfile(pathToMLAPP, 'Icons', 'OpenFile_32Yellow.png');
            app.menu_Button1.Text = '';
            app.menu_Separator1 = uiimage(app.menu_Grid);
            app.menu_Separator1.Enable = 'off';
            app.menu_Separator1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV_White.png');
            app.menu_Button2 = uibutton(app.menu_Grid, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button2.Tag = 'PLAYBACK';
            app.menu_Button2.Enable = 'off';
            app.menu_Button2.Tooltip = {''};
            app.menu_Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'Playback_32White.png');
            app.menu_Button2.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button3.Tag = 'REPORT';
            app.menu_Button3.Enable = 'off';
            app.menu_Button3.Tooltip = {''};
            app.menu_Button3.Icon = fullfile(pathToMLAPP, 'Icons', 'Report_32White.png');
            app.menu_Button3.Text = '';
            app.menu_Button4 = uibutton(app.menu_Grid, 'state');
            app.menu_Button4.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button4.Tag = 'MISC';
            app.menu_Button4.Enable = 'off';
            app.menu_Button4.Tooltip = {''};
            app.menu_Button4.Icon = fullfile(pathToMLAPP, 'Icons', 'Misc_32White.png');
            app.menu_Button4.Text = '';
            app.menu_Separator2 = uiimage(app.menu_Grid);
            app.menu_Separator2.Enable = 'off';
            app.menu_Separator2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV_White.png');
            app.menu_Button5 = uibutton(app.menu_Grid, 'state');
            app.menu_Button5.ValueChangedFcn = createCallbackFcn(app, @menu_mainButtonPushed, true);
            app.menu_Button5.Tag = 'CONFIG';
            app.menu_Button5.Enable = 'off';
            app.menu_Button5.Tooltip = {''};
            app.menu_Button5.Icon = fullfile(pathToMLAPP, 'Icons', 'Settings_36White.png');
            app.menu_Button5.Text = '';
            app.jsBackDoor = uihtml(app.menu_Grid);
            app.RFDataHubModule = uiimage(app.menu_Grid);
            app.RFDataHubModule.ImageClickedFcn = createCallbackFcn(app, @menu_OpenRFDataHubModule, true);
            app.RFDataHubModule.Enable = 'off';
            app.RFDataHubModule.ImageSource = fullfile(pathToMLAPP, 'Icons', 'mosaic_32White.png');
            app.FigurePosition = uiimage(app.menu_Grid);
            app.FigurePosition.ImageClickedFcn = createCallbackFcn(app, @menu_ToolbarImageCliced, true);
            app.FigurePosition.Enable = 'off';
            app.FigurePosition.ImageSource = fullfile(pathToMLAPP, 'Icons', 'layout1_32White.png');
            app.AppInfo = uiimage(app.menu_Grid);
            app.AppInfo.ImageClickedFcn = createCallbackFcn(app, @menu_ToolbarImageCliced, true);
            app.AppInfo.Enable = 'off';
            app.AppInfo.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Dots_32White.png');
            app.home_Grid = uigridlayout(app.GridLayout);
            app.home_Title = uilabel(app.home_Grid);
            app.home_Title.Interpreter = 'html';
            app.home_Title.Text = '<p><font  style="font-size: 16px; color: black;"><b>appAnalise </b></font><br>v. 1.80 </p></div>';
            app.home_GIF = uiimage(app.home_Grid);
            app.home_GIF.Enable = 'off';
            app.home_GIF.ImageSource = fullfile(pathToMLAPP, 'Icons', 'SplashScreen.gif');
            app.file_ContextMenu_Tree1 = uicontextmenu(app.UIFigure);
            app.file_ContextMenu_delTree1Node = uimenu(app.file_ContextMenu_Tree1);
            app.file_ContextMenu_delTree1Node.MenuSelectedFcn = createCallbackFcn(app, @file_ContextMenu_delTree1NodeSelected, true);
            app.file_ContextMenu_delTree1Node.Text = 'Excluir';
            app.file_ContextMenu_Tree2 = uicontextmenu(app.UIFigure);
            app.file_ContextMenu_delTree2Node = uimenu(app.file_ContextMenu_Tree2);
            app.file_ContextMenu_delTree2Node.MenuSelectedFcn = createCallbackFcn(app, @file_ContextMenu_delTree2NodeSelected, true);
            app.file_ContextMenu_delTree2Node.Text = 'Excluir';
            app.play_FindPeaks_ContextMenu = uicontextmenu(app.UIFigure);
            app.play_FindPeaks_ContextMenu_edit = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_edit.Text = 'Editar';
            app.play_FindPeaks_ContextMenu_analog = uimenu(app.play_FindPeaks_ContextMenu_edit);
            app.play_FindPeaks_ContextMenu_analog.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_TruncatedEmission, true);
            app.play_FindPeaks_ContextMenu_analog.Text = 'Não truncar';
            app.play_FindPeaks_ContextMenu_digital = uimenu(app.play_FindPeaks_ContextMenu_edit);
            app.play_FindPeaks_ContextMenu_digital.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_TruncatedEmission, true);
            app.play_FindPeaks_ContextMenu_digital.Text = 'Truncar frequência';
            app.play_FindPeaks_ContextMenu_del = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_delEmission, true);
            app.play_FindPeaks_ContextMenu_del.Text = 'Excluir';
            app.play_FindPeaks_ContextMenu_search = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_search.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_Classification, true);
            app.play_FindPeaks_ContextMenu_search.Separator = 'on';
            app.play_FindPeaks_ContextMenu_search.Text = 'Consultar';
            app.play_FindPeaks_ContextMenu_Drive = uimenu(app.play_FindPeaks_ContextMenu);
            app.play_FindPeaks_ContextMenu_Drive.MenuSelectedFcn = createCallbackFcn(app, @play_FindPeaks_DriveTest, true);
            app.play_FindPeaks_ContextMenu_Drive.Text = 'Drive-test';
            app.play_Channel_ContextMenu = uicontextmenu(app.UIFigure);
            app.play_Channel_ContextMenu_del = uimenu(app.play_Channel_ContextMenu);
            app.play_Channel_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @play_Channel_ContextMenu_delChannelSelected, true);
            app.play_Channel_ContextMenu_del.Text = 'Excluir';
            app.play_Channel_ContextMenu_addBandLimit = uimenu(app.play_Channel_ContextMenu);
            app.play_Channel_ContextMenu_addBandLimit.MenuSelectedFcn = createCallbackFcn(app, @play_Channel_ContextMenu_addBandLimitSelected, true);
            app.play_Channel_ContextMenu_addBandLimit.Separator = 'on';
            app.play_Channel_ContextMenu_addBandLimit.Text = 'Adicionar limites à detecção';
            app.play_Channel_ContextMenu_addEmission = uimenu(app.play_Channel_ContextMenu);
            app.play_Channel_ContextMenu_addEmission.MenuSelectedFcn = createCallbackFcn(app, @play_Channel_ContextMenu_addEmissionSelected, true);
            app.play_Channel_ContextMenu_addEmission.Text = 'Adicionar canais como emissões';
            app.report_ContextMenu = uicontextmenu(app.UIFigure);
            app.report_ContextMenu_del = uimenu(app.report_ContextMenu);
            app.report_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @report_ContextMenu_delSelected, true);
            app.report_ContextMenu_del.Separator = 'on';
            app.report_ContextMenu_del.Text = 'Excluir';
            app.report_ContextMenu_ExternalFiles = uimenu(app.report_ContextMenu);
            app.report_ContextMenu_ExternalFiles.MenuSelectedFcn = createCallbackFcn(app, @report_ExternalFilesMenuSelected, true);
            app.report_ContextMenu_ExternalFiles.Text = 'Arquivos externos';
            app.play_BandLimits_ContextMenu = uicontextmenu(app.UIFigure);
            app.play_BandLimits_ContextMenu_del = uimenu(app.play_BandLimits_ContextMenu);
            app.play_BandLimits_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @play_BandLimits_ContextMenu_delSelected, true);
            app.play_BandLimits_ContextMenu_del.Text = 'Excluir';
            app.edit_ContextMenu = uicontextmenu(app.UIFigure);
            app.edit_ContextMenu_del = uimenu(app.edit_ContextMenu);
            app.edit_ContextMenu_del.MenuSelectedFcn = createCallbackFcn(app, @edit_unitLevelCallbacks, true);
            app.edit_ContextMenu_del.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAppAnalise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end