classdef winClassification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Grid1                 matlab.ui.container.GridLayout
        Image                 matlab.ui.control.Image
        Title                 matlab.ui.control.Label
        Grid2                 matlab.ui.container.GridLayout
        Button2               matlab.ui.control.Button
        Button1               matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        Grid3                 matlab.ui.container.GridLayout
        Panel_3               matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        bwLim2                matlab.ui.control.Spinner
        bwLim2Label           matlab.ui.control.Label
        bwLim1                matlab.ui.control.Spinner
        bwLim1Label           matlab.ui.control.Label
        Panel_2               matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        Multiplier            matlab.ui.control.Spinner
        MultiplierLabel       matlab.ui.control.Label
        Contour               matlab.ui.control.Spinner
        ContourLabel          matlab.ui.control.Label
        EmrelaodistnciaLabel  matlab.ui.control.Label
        bwLabel               matlab.ui.control.Label
        Algorithm             matlab.ui.control.DropDown
        AlgorithmLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
    end
    
    methods (Access = private)

        function Fcn_InitialValues(app)

            idx1 = app.CallingApp.Report_Tree.SelectedNodes.NodeData;
            
            app.Algorithm.Value  = app.CallingApp.specData(idx1).reportClassification.Algorithm;

            InitialValues        = jsondecode(app.CallingApp.specData(idx1).reportClassification.Parameters);
            app.Contour.Value    = InitialValues.Contour;
            app.Multiplier.Value = InitialValues.ClassMultiplier;
            app.bwLim1.Value     = InitialValues.bwFactors(1);
            app.bwLim2.Value     = InitialValues.bwFactors(2);

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            Fcn_InitialValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winClassification>', '</winClassification>')));

            MessageBox(app, 'info', msg)
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;

            app.CallingApp.specData(idx).reportClassification.Algorithm  = app.Algorithm.Value;
            app.CallingApp.specData(idx).reportClassification.Parameters = jsonencode(struct('Contour',        app.Contour.Value,     ...
                                                                                             'ClassMultiplier', app.Multiplier.Value, ...
                                                                                             'bwFactors', [app.bwLim1.Value, app.bwLim2.Value]));

            app.CallingApp.Report_Layout
            app.CallingApp.auxWin_SignalAnalysis
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.Grid1);
            app.Grid3 = uigridlayout(app.Panel);
            app.AlgorithmLabel = uilabel(app.Grid3);
            app.AlgorithmLabel.Text = 'Algoritmo:';
            app.Algorithm = uidropdown(app.Grid3);
            app.Algorithm.Items = {'appAnálise v. 1.20'};
            app.Algorithm.Value = 'appAnálise v. 1.20';
            app.bwLabel = uilabel(app.Grid3);
            app.bwLabel.Text = 'Em relação à largura ocupada:';
            app.EmrelaodistnciaLabel = uilabel(app.Grid3);
            app.EmrelaodistnciaLabel.Text = 'Em relação à distância:';
            app.Panel_2 = uipanel(app.Grid3);
            app.GridLayout = uigridlayout(app.Panel_2);
            app.ContourLabel = uilabel(app.GridLayout);
            app.ContourLabel.Tag = 'Offset';
            app.ContourLabel.Text = {'Distância máxima entre sensor e estação sob análise (km):'; '(exceto FM e TV)'};
            app.Contour = uispinner(app.GridLayout);
            app.Contour.Step = 10;
            app.Contour.Limits = [10 200];
            app.Contour.RoundFractionalValues = 'on';
            app.Contour.ValueDisplayFormat = '%d';
            app.Contour.Tag = 'Offset';
            app.Contour.Value = 30;
            app.MultiplierLabel = uilabel(app.GridLayout);
            app.MultiplierLabel.Tag = 'THR';
            app.MultiplierLabel.Text = 'Fator multiplicador do contorno protegido de FM e TV na aferição da distâcia máxima:';
            app.Multiplier = uispinner(app.GridLayout);
            app.Multiplier.Limits = [1 4];
            app.Multiplier.RoundFractionalValues = 'on';
            app.Multiplier.ValueDisplayFormat = '%d';
            app.Multiplier.Tag = 'THR';
            app.Multiplier.Value = 2;
            app.Panel_3 = uipanel(app.Grid3);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.bwLim1Label = uilabel(app.GridLayout2);
            app.bwLim1Label.Tag = 'Offset';
            app.bwLim1Label.Text = 'Fator de tolerância inferior (%):';
            app.bwLim1 = uispinner(app.GridLayout2);
            app.bwLim1.Step = 10;
            app.bwLim1.Limits = [10 100];
            app.bwLim1.RoundFractionalValues = 'on';
            app.bwLim1.ValueDisplayFormat = '%d';
            app.bwLim1.Tag = 'Offset';
            app.bwLim1.Value = 90;
            app.bwLim2Label = uilabel(app.GridLayout2);
            app.bwLim2Label.Tag = 'Offset';
            app.bwLim2Label.Text = 'Fator de tolerância superior (%):';
            app.bwLim2 = uispinner(app.GridLayout2);
            app.bwLim2.Step = 10;
            app.bwLim2.Limits = [10 300];
            app.bwLim2.RoundFractionalValues = 'on';
            app.bwLim2.ValueDisplayFormat = '%d';
            app.bwLim2.Tag = 'Offset';
            app.bwLim2.Value = 300;
            app.Grid2 = uigridlayout(app.Grid1);
            app.Button1 = uibutton(app.Grid2, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Icon = 'LT_info.png';
            app.Button1.Text = '';
            app.Button2 = uibutton(app.Grid2, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_play.png');
            app.Button2.Text = 'Confirmar';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Classificação</b>'; '<font>Comparação com bases de dados, indicando provável gerador de cada emissão</font>'; ' '};
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'LT_Classification.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winClassification(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end