classdef winCredential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Grid1                 matlab.ui.container.GridLayout
        IssueLabel            matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        Title                 matlab.ui.control.Label
        Button                matlab.ui.control.Button
        CheckBox              matlab.ui.control.CheckBox
        Issue                 matlab.ui.control.NumericEditField
        Panel                 matlab.ui.container.Panel
        Grid2                 matlab.ui.container.GridLayout
        SenhaEditFieldLabel   matlab.ui.control.Label
        Password              matlab.ui.control.HTML
        Login                 matlab.ui.control.EditField
        UsurioEditFieldLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        
    end
    
    methods (Access = private)
        
        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Position, prjIssue)
            
            app.CallingApp = mainapp;

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.Password.HTMLSource = sprintf('<!DOCTYPE html>\n<html>\n<head>\n\t<script type="text/javascript">\n\t\tfunction setup(htmlComponent) {\n\t\t\tvar dom = document.getElementById("pwrd");\n\t\t\twindow.updateData = function (newData) {\n\t\t\t\thtmlComponent.Data = dom.value;\n\t\t\t}\n\t\t}\n\t</script>\n</head>\n<body>\n\t<input type="password" id="pwrd" onchange="updateData()" style="padding-left: 3px; width: 384px; height: 16px"></input>\n</body>\n</html>');
            
            x0 = fix(Position(1)+(Position(3)-app.UIFigure.Position(3))/2);
            y0 = fix(Position(2)+(Position(4)-app.UIFigure.Position(4))/2);
            app.UIFigure.Position(1:2) = [x0, y0];

            app.Issue.Value = prjIssue;
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin3 = [];
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if ~isempty(app.Login.Value) && ~isempty(app.Password.Data) && (app.Issue.Value > 0)
                try
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                    d.Message = '<font style="font-size:12;">Aguardando resposta do Fiscaliza...</font>';
                    
                    pyModule    = py.importlib.import_module('fiscaliza.info');
                    py.importlib.reload(pyModule);
                    
                    issueInfo   = pyModule.detalhar_issue(pyargs('issue', num2str(app.Issue.Value), ...
                                                                 'login', app.Login.Value,       ...
                                                                 'senha', app.Password.Data,     ...
                                                                 'teste', logical(app.CheckBox.Value)));
                    
                    issueInfo   = Misc_py2matRedmine(issueInfo, 1);
                    
                    app.CallingApp.auxWin3 = winFiscaliza(app.CallingApp, pyModule, app.Login.Value, app.Password.Data, logical(app.CheckBox.Value), issueInfo, app.Issue.Value);
                    drawnow nocallbacks
                    
                    delete(app)

                catch ME
                    MessageBox(app, 'error', getReport(ME))
                end
                    
            else
                MessageBox(app, 'warning', 'Ajustar credenciais ou inspeção.')
            end
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            
            if app.CheckBox.Value
                app.Title.Text = {'<b style="color: #a2142f; font-size: 24;">Fiscaliza</b><font style="font-size: 10;">HOMOLOGAÇÃO</font>'; '<font>Sistema de gestão da fiscalização da Agência</font>'; ' '};
            else
                app.Title.Text = {'<b style="color: #a2142f; font-size: 24;">Fiscaliza</b>'; '<font>Sistema de gestão da fiscalização da Agência</font>'; ' '};
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Label = uilabel(app.Grid1);
            app.Label.Text = 'Credenciais:';
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.UsurioEditFieldLabel = uilabel(app.Grid2);
            app.UsurioEditFieldLabel.Text = 'Usuário:';
            app.Login = uieditfield(app.Grid2, 'text');
            app.Password = uihtml(app.Grid2);
            app.SenhaEditFieldLabel = uilabel(app.Grid2);
            app.SenhaEditFieldLabel.Text = 'Senha:';
            app.Issue = uieditfield(app.Grid1, 'numeric');
            app.Issue.Limits = [-1 Inf];
            app.Issue.RoundFractionalValues = 'on';
            app.Issue.ValueDisplayFormat = '%d';
            app.Issue.Value = -1;
            app.CheckBox = uicheckbox(app.Grid1);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'HOMOLOGAÇÃO';
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_play.png');
            app.Button.Text = 'Confirmar';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #a2142f; font-size: 24;">Fiscaliza</b>'; '<font>Sistema de gestão da fiscalização da Agência</font>'; ' '};
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'LT_redmine.png';
            app.IssueLabel = uilabel(app.Grid1);
            app.IssueLabel.Text = 'Inspeção:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winCredential(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end