classdef winDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Grid1                matlab.ui.container.GridLayout
        Image                matlab.ui.control.Image
        Title                matlab.ui.control.Label
        Grid2                matlab.ui.container.GridLayout
        Button2              matlab.ui.control.Button
        Button1              matlab.ui.control.Button
        Panel1               matlab.ui.container.Panel
        Grid3                matlab.ui.container.GridLayout
        Algorithm            matlab.ui.control.DropDown
        AlgorithmLabel       matlab.ui.control.Label
        GridLayout6          matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        Panel3               matlab.ui.container.Panel
        Grid5                matlab.ui.container.GridLayout
        C2_maxOCC            matlab.ui.control.Spinner
        C2_maxOCCLabel       matlab.ui.control.Label
        C2_meanOCC           matlab.ui.control.Spinner
        C2_meanOCCLabel      matlab.ui.control.Label
        C2_Proeminence       matlab.ui.control.Spinner
        C2_ProeminenceLabel  matlab.ui.control.Label
        Label2               matlab.ui.control.Label
        GridLayout4          matlab.ui.container.GridLayout
        Panel2               matlab.ui.container.Panel
        Grid4                matlab.ui.container.GridLayout
        C1_Fcn               matlab.ui.control.DropDown
        C1_FcnLabel          matlab.ui.control.Label
        C1_ProeminenceLabel  matlab.ui.control.Label
        C1_Proeminence       matlab.ui.control.Spinner
        Label1               matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        Cn_BW                matlab.ui.control.Spinner
        Cn_BWLabel           matlab.ui.control.Label
        Cn_minDist           matlab.ui.control.Spinner
        Cn_minDistLabel      matlab.ui.control.Label
        Label1_2             matlab.ui.control.Label
        ClassLabel           matlab.ui.control.Label
        Class                matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
    end
    
    methods (Access = private)
        
        function Fcn_InitialValues(app)

            global peaksReference
            BandList = struct2table(peaksReference);

            idx1 = app.CallingApp.Report_Tree.SelectedNodes.NodeData;

            app.Algorithm.Value  = app.CallingApp.specData(idx1).reportDetection.Algorithm;
            
            InitialValues = jsondecode(app.CallingApp.specData(idx1).reportDetection.Parameters);
            InitialArray  = [InitialValues.Cn_minDist * 1000; ...
                             InitialValues.Cn_BW      * 1000; ...
                             InitialValues.C2_meanOCC;        ...
                             InitialValues.C2_maxOCC];

            idx2 = [];
            for ii = 1:height(BandList)
                if isequal(InitialArray, BandList.Settings{ii})
                    idx2 = ii;
                    break
                end
            end
            
            if ~isempty(idx2)
                switch BandList.Description{idx2}
                    case {'TV_VHF1', 'TV_VHF2', 'TV_UHF'}
                        app.Class.Value = 'Radiodifusão (TV)';
                    case 'FM'
                        app.Class.Value = 'Radiodifusão (FM)';
                    case 'Aircraft'
                        app.Class.Value = 'Radiocomunicação';
                end 
            else
                app.Class.Value = 'Genérico';
            end

            app.Cn_minDist.Value     = InitialValues.Cn_minDist;
            app.Cn_BW.Value          = InitialValues.Cn_BW;
            app.C1_Fcn.Value         = InitialValues.C1_Fcn;
            app.C1_Proeminence.Value = InitialValues.C1_Proeminence;
            app.C2_Proeminence.Value = InitialValues.C2_Proeminence;
            app.C2_meanOCC.Value     = InitialValues.C2_meanOCC;
            app.C2_maxOCC.Value      = InitialValues.C2_maxOCC;

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            Fcn_InitialValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Value changed function: Class
        function ClassValueChanged(app, event)

            global peaksReference
            BandList = struct2table(peaksReference);
            
            idx = [];
            switch app.Class.Value
                case 'Radiodifusão (FM)'; idx = find(strcmp(BandList.Description, 'FM'),   1);
                case 'Radiodifusão (TV)'; idx = find(contains(BandList.Description, 'TV'), 1);
                case 'Radiocomunicação';  idx = find(strcmp(BandList.Description, 'Aircraft'), 1);
            end
            
            app.C1_Proeminence.Value = 10;
            app.C2_Proeminence.Value = 30;
            app.C1_Fcn.Value         = 'Média';
            
            if ~isempty(idx)
                app.Cn_minDist.Value = peaksReference(idx).Settings(1) / 1000;
                app.Cn_BW.Value      = peaksReference(idx).Settings(2) / 1000;
                app.C2_meanOCC.Value = peaksReference(idx).Settings(3);
                app.C2_maxOCC.Value  = peaksReference(idx).Settings(4);
            else
                app.Cn_minDist.Value = 25;
                app.Cn_BW.Value      = 10;
                app.C2_meanOCC.Value = 1;
                app.C2_maxOCC.Value  = 10;
            end

        end

        % Value changed function: C2_maxOCC, C2_meanOCC
        function C2_OCCValueChanged(app, event)
            
            switch event.Source
                case app.C2_meanOCC
                    if app.C2_meanOCC.Value > app.C2_maxOCC.Value
                        app.C2_maxOCC.Value = app.C2_meanOCC.Value;
                    end

                case app.C2_maxOCC
                    if app.C2_maxOCC.Value < app.C2_meanOCC.Value
                        app.C2_meanOCC.Value = app.C2_maxOCC.Value;
                    end
            end

        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winDetection>', '</winDetection>')));

            MessageBox(app, 'info', msg)

        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;

            app.CallingApp.specData(idx).reportDetection.Parameters = jsonencode(struct('Cn_minDist',     app.Cn_minDist.Value,     ...
                                                                                        'Cn_BW',          app.Cn_BW.Value,          ...
                                                                                        'C1_Fcn',         app.C1_Fcn.Value,         ...
                                                                                        'C1_Proeminence', app.C1_Proeminence.Value, ...
                                                                                        'C2_Proeminence', app.C2_Proeminence.Value, ...
                                                                                        'C2_meanOCC',     app.C2_meanOCC.Value,     ...
                                                                                        'C2_maxOCC',      app.C2_maxOCC.Value));
            app.CallingApp.Report_Layout
            app.CallingApp.auxWin_SignalAnalysis
            closeFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel1 = uipanel(app.Grid1);
            app.Grid3 = uigridlayout(app.Panel1);
            app.Class = uidropdown(app.Grid3);
            app.Class.Items = {'Genérico', 'Radiocomunicação', 'Radiodifusão (FM)', 'Radiodifusão (TV)'};
            app.Class.ValueChangedFcn = createCallbackFcn(app, @ClassValueChanged, true);
            app.Class.Value = 'Genérico';
            app.ClassLabel = uilabel(app.Grid3);
            app.ClassLabel.Text = 'Classe:';
            app.GridLayout6 = uigridlayout(app.Grid3);
            app.GridLayout2 = uigridlayout(app.GridLayout6);
            app.Label1_2 = uilabel(app.GridLayout2);
            app.Label1_2.Text = {'Aspectos '; 'gerais:'};
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.Cn_minDistLabel = uilabel(app.GridLayout3);
            app.Cn_minDistLabel.Text = {'Distância mínima entre'; 'picos (kHz):'};
            app.Cn_minDist = uispinner(app.GridLayout3);
            app.Cn_minDist.Step = 10;
            app.Cn_minDist.Limits = [0 Inf];
            app.Cn_minDist.RoundFractionalValues = 'on';
            app.Cn_minDist.ValueDisplayFormat = '%.0f';
            app.Cn_minDist.Tooltip = {''};
            app.Cn_minDist.Value = 25;
            app.Cn_BWLabel = uilabel(app.GridLayout3);
            app.Cn_BWLabel.Text = {'Largura mínima '; 'ocupada (kHz):'};
            app.Cn_BW = uispinner(app.GridLayout3);
            app.Cn_BW.Step = 10;
            app.Cn_BW.Limits = [0 Inf];
            app.Cn_BW.RoundFractionalValues = 'on';
            app.Cn_BW.ValueDisplayFormat = '%.0f';
            app.Cn_BW.Tooltip = {''};
            app.Cn_BW.Value = 10;
            app.GridLayout4 = uigridlayout(app.GridLayout6);
            app.Label1 = uilabel(app.GridLayout4);
            app.Label1.Text = 'Especificidades relacionadas à curva de tendência central:';
            app.Panel2 = uipanel(app.GridLayout4);
            app.Grid4 = uigridlayout(app.Panel2);
            app.C1_Proeminence = uispinner(app.Grid4);
            app.C1_Proeminence.Step = 10;
            app.C1_Proeminence.Limits = [1 Inf];
            app.C1_Proeminence.RoundFractionalValues = 'on';
            app.C1_Proeminence.ValueDisplayFormat = '%.0f';
            app.C1_Proeminence.Value = 10;
            app.C1_ProeminenceLabel = uilabel(app.Grid4);
            app.C1_ProeminenceLabel.Text = {'Proeminência'; 'minima (dB):'};
            app.C1_FcnLabel = uilabel(app.Grid4);
            app.C1_FcnLabel.Text = {'Função'; 'estatística:'};
            app.C1_Fcn = uidropdown(app.Grid4);
            app.C1_Fcn.Items = {'Média', 'Mediana'};
            app.C1_Fcn.Value = 'Média';
            app.GridLayout5 = uigridlayout(app.GridLayout6);
            app.Label2 = uilabel(app.GridLayout5);
            app.Label2.Text = 'Especificidades relacionadas à curva de máximo:';
            app.Panel3 = uipanel(app.GridLayout5);
            app.Grid5 = uigridlayout(app.Panel3);
            app.C2_ProeminenceLabel = uilabel(app.Grid5);
            app.C2_ProeminenceLabel.Text = {'Proeminência'; 'mínima (dB):'};
            app.C2_Proeminence = uispinner(app.Grid5);
            app.C2_Proeminence.Step = 10;
            app.C2_Proeminence.Limits = [1 Inf];
            app.C2_Proeminence.RoundFractionalValues = 'on';
            app.C2_Proeminence.ValueDisplayFormat = '%.0f';
            app.C2_Proeminence.Value = 30;
            app.C2_meanOCCLabel = uilabel(app.Grid5);
            app.C2_meanOCCLabel.Text = {'Ocupação média '; 'mínima (%):'};
            app.C2_meanOCC = uispinner(app.Grid5);
            app.C2_meanOCC.Step = 10;
            app.C2_meanOCC.Limits = [0 100];
            app.C2_meanOCC.RoundFractionalValues = 'on';
            app.C2_meanOCC.ValueDisplayFormat = '%.0f';
            app.C2_meanOCC.ValueChangedFcn = createCallbackFcn(app, @C2_OCCValueChanged, true);
            app.C2_meanOCC.Value = 1;
            app.C2_maxOCCLabel = uilabel(app.Grid5);
            app.C2_maxOCCLabel.Text = {'Ocupação máxima '; 'mínima (%):'};
            app.C2_maxOCC = uispinner(app.Grid5);
            app.C2_maxOCC.Step = 10;
            app.C2_maxOCC.Limits = [0 100];
            app.C2_maxOCC.RoundFractionalValues = 'on';
            app.C2_maxOCC.ValueDisplayFormat = '%.0f';
            app.C2_maxOCC.ValueChangedFcn = createCallbackFcn(app, @C2_OCCValueChanged, true);
            app.C2_maxOCC.Value = 10;
            app.AlgorithmLabel = uilabel(app.Grid3);
            app.AlgorithmLabel.Text = 'Algoritmo:';
            app.Algorithm = uidropdown(app.Grid3);
            app.Algorithm.Items = {'appAnálise v. 1.00'};
            app.Algorithm.Value = 'appAnálise v. 1.00';
            app.Grid2 = uigridlayout(app.Grid1);
            app.Button1 = uibutton(app.Grid2, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Icon = 'LT_info.png';
            app.Button1.Text = '';
            app.Button2 = uibutton(app.Grid2, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_play.png');
            app.Button2.Text = 'Confirmar';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Detecção de emissões</b>'; '<font>Escolha do algoritmo de detecção de emissões</font>'; ' '};
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'LT_Detection.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDetection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end