classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        mainGrid                  matlab.ui.container.GridLayout
        imgShowHide               matlab.ui.control.Image
        mainPanel                 matlab.ui.container.Panel
        mainPanel_Grid            matlab.ui.container.GridLayout
        Tab5_Panel                matlab.ui.container.GridLayout
        legend_Panel              matlab.ui.container.Panel
        legend_Grid               matlab.ui.container.GridLayout
        legend_Location           matlab.ui.control.DropDown
        legend_LocationLabel      matlab.ui.control.Label
        legend_Visibility         matlab.ui.control.DropDown
        legend_VisibilityLabel    matlab.ui.control.Label
        legend_PanelLabel         matlab.ui.control.Label
        chPowerSource_Panel       matlab.ui.container.Panel
        chPowerSource_Grid        matlab.ui.container.GridLayout
        Smoothed_Panel            matlab.ui.container.Panel
        Smoothed_Grid             matlab.ui.container.GridLayout
        Smoothed_Factor           matlab.ui.control.Spinner
        Smoothed_FactorLabel      matlab.ui.control.Label
        Smoothed_Type             matlab.ui.control.DropDown
        Smoothed_TypeLabel        matlab.ui.control.Label
        chPowerSource_smoothed    matlab.ui.control.CheckBox
        chPowerSource_binTable    matlab.ui.control.CheckBox
        chPowerSource_locTable    matlab.ui.control.CheckBox
        chPowerSource_rawTable    matlab.ui.control.CheckBox
        chPowerSource_PanelLabel  matlab.ui.control.Label
        Tab5_Grid                 matlab.ui.container.GridLayout
        Tab5_Title                matlab.ui.control.Label
        Tab5_Image                matlab.ui.control.Image
        Tab4_Panel                matlab.ui.container.GridLayout
        mapPlot_Grid              matlab.ui.container.GridLayout
        mapPlot_Panel2            matlab.ui.container.Panel
        mapPlot_Grid2             matlab.ui.container.GridLayout
        mapPlot_Property2Panel    matlab.ui.container.Panel
        mapPlot_Property2Grid     matlab.ui.container.GridLayout
        mapPlot_size2             matlab.ui.control.Slider
        imgScatter_Title          matlab.ui.control.Label
        Colormap                  matlab.ui.control.DropDown
        ColormapLabel             matlab.ui.control.Label
        mapPlot_ButtonGroup       matlab.ui.container.ButtonGroup
        Source_binData            matlab.ui.control.RadioButton
        Source_locData            matlab.ui.control.RadioButton
        Source_rawData            matlab.ui.control.RadioButton
        mapPlot_ButtonGroupLabel  matlab.ui.control.Label
        mapPlot_Title2            matlab.ui.control.Label
        mapPlot_Panel1            matlab.ui.container.Panel
        mapPlot_Grid1             matlab.ui.container.GridLayout
        mapPlot_Grid1A            matlab.ui.container.GridLayout
        mapPlot_Grid1A1           matlab.ui.container.GridLayout
        mapPlot_Property1Panel    matlab.ui.container.Panel
        mapPlot_Property1Grid     matlab.ui.container.GridLayout
        mapPlot_size1             matlab.ui.control.Slider
        mapPlot_color1            matlab.ui.control.Button
        mapPlot_Property1Label    matlab.ui.control.Label
        mapPlot_Type              matlab.ui.control.DropDown
        mapPlot_TypeLabel         matlab.ui.control.Label
        mapPlot_Tree              matlab.ui.container.CheckBoxTree
        mapPlot_hCar              matlab.ui.container.TreeNode
        mapPlot_hBin              matlab.ui.container.TreeNode
        mapPlot_Subtitle12        matlab.ui.control.Label
        mapPlot_Panel11           matlab.ui.container.Panel
        mapPlot_Grid11            matlab.ui.container.GridLayout
        imgRoute_Panel            matlab.ui.container.Panel
        imgRoute_Grid             matlab.ui.container.GridLayout
        imgRoute_Size             matlab.ui.control.Slider
        imgRoute_Color2           matlab.ui.control.Button
        imgRoute_Color1           matlab.ui.control.Button
        imgRoute_PropertyLabel    matlab.ui.control.Label
        imgRoute_Type             matlab.ui.control.DropDown
        imgRoute_TypeLabel        matlab.ui.control.Label
        mapPlot_Subtitle11        matlab.ui.control.Label
        mapPlot_Title1            matlab.ui.control.Label
        imgDensity                matlab.ui.control.Image
        imgScatter                matlab.ui.control.Image
        imgRoute                  matlab.ui.control.Image
        Tab4_Grid                 matlab.ui.container.GridLayout
        Tab4_Title                matlab.ui.control.Label
        Tab4_Image                matlab.ui.control.Image
        Tab3_Panel                matlab.ui.container.GridLayout
        binTable_Fcn              matlab.ui.control.DropDown
        binTable_Grid             matlab.ui.container.GridLayout
        ExcelFile_Grid2           matlab.ui.container.GridLayout
        ExcelFile_Button2         matlab.ui.control.Image
        binTable_Info             matlab.ui.control.Label
        binTable_Col4             matlab.ui.control.Label
        binTable_Col3             matlab.ui.control.Label
        binTable_Col2             matlab.ui.control.Label
        binTable_Col1             matlab.ui.control.Label
        binTable_UITable          matlab.ui.control.Table
        binTable_FcnLabel         matlab.ui.control.Label
        binTable_Distance         matlab.ui.control.Spinner
        binTable_DistanceLabel    matlab.ui.control.Label
        binTable_Type             matlab.ui.control.DropDown
        binTable_TypeLabel        matlab.ui.control.Label
        Tab3_Grid                 matlab.ui.container.GridLayout
        Tab3_Title                matlab.ui.control.Label
        Tab3_Image                matlab.ui.control.Image
        Tab2_Panel                matlab.ui.container.GridLayout
        locTable_Fcn              matlab.ui.control.DropDown
        locTable_FcnLabel         matlab.ui.control.Label
        locTable_Grid             matlab.ui.container.GridLayout
        ExcelFile_Grid1           matlab.ui.container.GridLayout
        ExcelFile_Button1         matlab.ui.control.Image
        locTable_Info             matlab.ui.control.Label
        locTable_Col4             matlab.ui.control.Label
        locTable_Col3             matlab.ui.control.Label
        locTable_Col2             matlab.ui.control.Label
        locTable_Col1             matlab.ui.control.Label
        locTable_UITable          matlab.ui.control.Table
        locTable_Decimal          matlab.ui.control.Spinner
        locTable_DecimalLabel     matlab.ui.control.Label
        locTable_Type             matlab.ui.control.DropDown
        locTable_TypeLabel        matlab.ui.control.Label
        Tab2_Grid                 matlab.ui.container.GridLayout
        Tab2_Title                matlab.ui.control.Label
        Tab2_Image                matlab.ui.control.Image
        Tab1_Panel                matlab.ui.container.GridLayout
        FilterTreeInfo            matlab.ui.control.Label
        FilterAction_Grid2        matlab.ui.container.GridLayout
        FilterAction_del          matlab.ui.control.Image
        FilterAction_Grid1        matlab.ui.container.GridLayout
        FilterAction_add          matlab.ui.control.Image
        FilterTree2               matlab.ui.container.Tree
        FilterTree1               matlab.ui.container.Tree
        levelFilterNode           matlab.ui.container.TreeNode
        levelFilter_thr           matlab.ui.container.TreeNode
        geoFilterNode             matlab.ui.container.TreeNode
        geoFilter_Circle          matlab.ui.container.TreeNode
        geoFilter_Rectangle       matlab.ui.container.TreeNode
        geoFilter_Polygon         matlab.ui.container.TreeNode
        FilterTreeLabel           matlab.ui.control.Label
        rawDataInfo               matlab.ui.control.TextArea
        Emissions                 matlab.ui.control.DropDown
        Emissions_Grid            matlab.ui.container.GridLayout
        EmissionsRefresh          matlab.ui.control.Image
        EmissionsLabel            matlab.ui.control.Label
        Tab1_Grid                 matlab.ui.container.GridLayout
        Tab1_Lock                 matlab.ui.control.Image
        Tab1_Title                matlab.ui.control.Label
        Tab1_Image                matlab.ui.control.Image
        UIAxes2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        General = struct('hCar', struct('marker', 's', 'color', [1,0,0], 'size', 5), ...
                         'hBin', struct('marker', '^', 'color', [0,0,0], 'size', 5))
        
        specData
        Index
        
        specTable                                                           % General table (instantaneous channel power)
        roiFilters = table('Size', [0, 4],                                      ...
                          'VariableTypes', {'cell', 'cell', 'cell', 'double'}, ...
                          'VariableNames', {'type', 'subtype', 'handle', 'enable'})
        
        filtTable                                                           % Filtered specData
        locTable                                                            % Summarized filtTable (Location Criterium)
        binTable                                                            % Summarized filtTable (Data Binning Criterium)

        UIAxes1
        hRoute_inROI
        hRoute_outROI
        hBin
        hCar
        hScatter
        hDensity

        % UIAxes2
        hChannelPower
        hSmoothedData
        hROI_Label        
        
    end
    
    properties (Access = public)

        hROI_Line

    end

    
    methods (Access = private)

        function Fcn1_AxesCreation(app)

            app.UIAxes1 = geoaxes(app.mainGrid, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater');

            while true
                try
                    addToolbarMapButton(axtoolbar(app.UIAxes1, "default"), "basemap", BasemapNames=["darkwater", "satellite", "streets-light", "streets-dark", "topographic", "streets", "colorterrain", "grayterrain"])

                    app.UIAxes1.Layout.Row    = 1;
                    app.UIAxes1.Layout.Column = 3;
                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';

                    colormap(app.UIAxes1, app.Colormap.Value)
                    hold(app.UIAxes1, 'on')

                    break
                catch
                end
            end

            try
                geobasemap(app.UIAxes1, 'satellite')
            catch
            end
      
            app.UIAxes2.Interactions = [];
            axtoolbar(app.UIAxes2, {'pan', 'zoomin', 'zoomout', 'restoreview'});

        end
        

        function Fcn2_General(app)   

            Fcn2_General_Emissions(app)
            Fcn2_General_MetaData(app)
            Fcn2_General_specTable(app)

            expand(app.FilterTree1, 'all')
            app.mapPlot_Tree.SelectedNodes = app.mapPlot_hCar;
        
        end


        function Fcn2_General_Emissions(app)

            emissionTable = app.specData.Emissions;

            if isempty(emissionTable)
                error('Tabela de emissões está vazia!')
            end
            
            app.Emissions.Items = {};
            for ii = 1:height(emissionTable)
                app.Emissions.Items{end+1} = sprintf('%d: %.3f MHz @ %.3f kHz',        ...
                                                          ii,                          ...
                                                          emissionTable.Frequency(ii), ...
                                                          emissionTable.BW(ii));
            end

            try
                app.Emissions.Value = app.Emissions.Items{app.Index(2)};
            catch
            end

        end


        function Fcn2_General_MetaData(app)

            metaData = '';
            for ii = 1:height(app.specData.RelatedFiles)
                ObservationTime = sprintf('%s - %s', datestr(app.specData.RelatedFiles.BeginTime(ii), 'dd/mm/yyyy HH:MM:SS'), ...
                                                     datestr(app.specData.RelatedFiles.EndTime(ii),   'dd/mm/yyyy HH:MM:SS'));

                metaData = sprintf(['%s• %s\n' ...
                                    '  %s\n' ...
                                    '  %.0f varreduras, %.3f seg (tempo de revisita médio)\n\n'], ...
                                    metaData, ...
                                    app.specData.RelatedFiles.Name{ii}, ...
                                    ObservationTime, ...
                                    app.specData.RelatedFiles.Samples(ii), ...
                                    app.specData.RelatedFiles.RevisitTime(ii));
            end

            app.rawDataInfo.Value = sprintf(['Faixa: %.3f - %.3f MHz\n\n'  ...
                                      'Pontos por traço: %.0f\n'    ...
                                      'Unidade: %s\n'               ...
                                      'Resolução: %.3f kHz\n\n%s'], ...
                                      app.specData.MetaData.FreqStart / 1e+6,  ...
                                      app.specData.MetaData.FreqStop  / 1e+6,  ...
                                      app.specData.MetaData.DataPoints,        ...
                                      app.specData.MetaData.metaString{1},     ...
                                      app.specData.MetaData.Resolution / 1e+3, ...
                                      metaData);

        end
       

        function Fcn2_General_specTable(app)

            timestamp = app.specData.Data{1}';
            
            Lat  = [];
            Long = [];
            for ii = 1:numel(app.specData.RelatedGPS)
                Lat  = [Lat;  app.specData.RelatedGPS{ii}.Matrix(:,1)];
                Long = [Long; app.specData.RelatedGPS{ii}.Matrix(:,2)];
            end
            
            chPower = Fcn4_chPower(app);
            app.specTable = table(timestamp, Lat, Long, chPower);

        end


        function Fcn2_General_TreeBuilding(app)

            if ~isempty(app.FilterTree2.Children)
                delete(app.FilterTree2.Children)
            end

            if ~isempty(app.roiFilters)
                for ii = 1:height(app.roiFilters)
                    uitreenode(app.FilterTree2, 'Text', app.roiFilters.subtype{ii}, 'NodeData', ii);
                end

                set(app.FilterTree2, 'SelectedNodes', app.FilterTree2.Children(end), 'Enable', 1)
                FilterTree2_SelectionChanged(app)

                app.FilterAction_del.Enable = 1;
            else
                app.FilterAction_del.Enable = 0;
            end

            if isempty(find(app.roiFilters.subtype == "THRESHOLD", 1))
                s = uistyle("FontColor", [0,0,0]);                    
                app.levelFilter_thr.NodeData = 1;
            else
                s = uistyle("FontColor", [.8,.8,.8]);
                app.levelFilter_thr.NodeData = [];
            end
            addStyle(app.FilterTree1, s, 'node', app.levelFilter_thr)

        end


        function Fcn3_updateInfo(app, src, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            Fcn4_geoTables(app)
            Fcn5_UITables(app)
            Fcn6_Plots(app)

            delete(d)

        end
        
        
        function Fcn4_geoTables(app)

            Fcn4_filtTable(app)
            Fcn4_locTable(app)
            Fcn4_binTable(app)

        end
        

        function Fcn4_filtTable(app)

            idy  = ones(height(app.specTable), 1, 'logical');

            idx1 = find(app.roiFilters.type == "Level");
            idx2 = find(app.roiFilters.type == "Geographic ROI");

            if ~isempty(idx1) & ~isempty(idx2)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.roiFilters.handle{ii}.Position(3);
                end

                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.roiFilters.handle{ii}, app.specTable.Lat, app.specTable.Long));
                end

                idy = and(idy1, idy2);

            elseif ~isempty(idx1)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.roiFilters.handle{ii}.Position(3);
                end

                idy = idy1;

            elseif ~isempty(idx2)
                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.roiFilters.handle{ii}, app.specTable.Lat, app.specTable.Long));
                end

                idy = idy2;                
            end
            
            if ~sum(idy)
                MessageBox(app, 'warning', 'Não é obtida nenhuma amostra ao aplicar o filtro. Por essa razão, esse filtro será excluído automaticamente.')
                app.FilterTree2.SelectedNodes = app.FilterTree2.Children(end);
                FilterAction_delCallback(app)
                return
            end

            app.specTable.filtered = idy;
            app.specTable.PK1      = int32(1:height(app.specTable))';

            app.filtTable = app.specTable(idy,2:4);
            [app.filtTable.LatEq, app.filtTable.LongEq] = grn2eqa(app.filtTable.Lat, app.filtTable.Long);

            app.filtTable.FK1 = find(idy);
            app.filtTable.FK2 = -1*ones(height(app.filtTable), 1, 'int32');
            app.filtTable.FK3 = app.filtTable.FK2;

            app.FilterTreeInfo.Text = sprintf('%.0f%%', 100*sum(idy)/height(app.specTable));
            if ~isempty(idx1)
                app.roiFilters.handle{idx1}.Position(2) = height(app.filtTable);
            end

        end


        function Fcn4_locTable(app)

            lat_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.locTable_Decimal.Value), app.filtTable.Lat),  '\n')';
            lon_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.locTable_Decimal.Value), app.filtTable.Long), '\n')';
            latlong    = string(lat_String(1:end-1)) + ", " + string(lon_String(1:end-1));

            [latlong_String, latlong_Index, app.filtTable.FK2] = unique(latlong, 'stable');
            
            PK2      = int32(1:numel(latlong_String))';
            locLat   = app.filtTable.Lat(latlong_Index);
            locLong  = app.filtTable.Long(latlong_Index);
            locCount = accumarray(app.filtTable.FK2, 1);
            locPower = Fcn4_locPower(app);

            app.locTable = table(locLat, locLong, locPower, locCount, PK2);  

        end


        function Fcn4_binTable(app)

            [binLat, binLong, binCount] = hista(app.filtTable.Lat, app.filtTable.Long, (app.binTable_Distance.Value/1000)^2);            
            [binLatEq, binLongEq] = grn2eqa(binLat, binLong);
            PK3 = int32(1:numel(binLat))';
            
            dist = pdist2([app.filtTable.LongEq, app.filtTable.LatEq], [binLongEq, binLatEq]);            
            [~, app.filtTable.FK3] = min(dist, [], 2);
            binPower = Fcn4_binPower(app);

            app.binTable = table(binLat, binLong, binPower, binCount, PK3);

        end


        function chPower = Fcn4_chPower(app)

            idx = find(strcmp(app.Emissions.Items, app.Emissions.Value), 1);
            FreqCenter = app.specData.Emissions.Frequency(idx) * 1e+6;
            BW         = app.specData.Emissions.BW(idx)        * 1e+3;

            chPower = Misc_chPower(app.specData, [FreqCenter-BW/2, FreqCenter+BW/2]);

        end


        function locPower = Fcn4_locPower(app)

            switch app.locTable_Fcn.Value
                case 'Mínima';  locFcn = @min;
                case 'Média';   locFcn = @(x) pow2db(  mean(db2pow(x)));
                case 'Mediana'; locFcn = @(x) pow2db(median(db2pow(x)));
                case 'RMS';     locFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'Máxima';  locFcn = @max;
            end
            locPower = splitapply(locFcn, app.filtTable.chPower, app.filtTable.FK2);

        end


        function binPower = Fcn4_binPower(app)

            switch app.binTable_Fcn.Value
                case 'Mínima';  binFcn = @min;
                case 'Média';   binFcn = @(x) pow2db(  mean(db2pow(x)));
                case 'Mediana'; binFcn = @(x) pow2db(median(db2pow(x)));
                case 'RMS';     binFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'Máxima';  binFcn = @max;
            end
            binPower = splitapply(binFcn, app.filtTable.chPower, app.filtTable.FK3);

        end


        function Fcn5_UITables(app)

            Fcn5_UITable_locTable(app)
            Fcn5_UITable_binTable(app)

        end


        function Fcn5_UITable_locTable(app)

            locLat_String  = cellfun(@(x) sprintf('%.6f', x), num2cell(app.locTable.locLat),  'UniformOutput', false);
            locLong_String = cellfun(@(x) sprintf('%.6f', x), num2cell(app.locTable.locLong), 'UniformOutput', false);
            app.locTable_UITable.Data = table(app.locTable{:,5}, locLat_String, locLong_String, app.locTable{:,4});

            s = uistyle("HorizontalAlignment", 'right');
            addStyle(app.locTable_UITable, s, "column", [2,3])

            [locCount_min, locCount_max] = bounds(app.locTable.locCount);

            ptDist = deg2km(distance('gc', [app.locTable.locLat(1:end-1), app.locTable.locLong(1:end-1)], [app.locTable.locLat(2:end), app.locTable.locLong(2:end)]));
            [ptDist_min, ptDist_max] = bounds(ptDist*1000);
            ptDist_mean = mean(ptDist)*1000; 
            
            auxString0 = sprintf('%.0f pontos', height(app.locTable));
            auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', ptDist_min, ptDist_mean, ptDist_max);
            auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto',    locCount_min, locCount_max);

            app.locTable_Info.Text = sprintf('%s\n%s\n%.0f |–––––––%s| %.0f varreduras/ponto', auxString0, auxString1, locCount_min, repmat('–', 1, numel(auxString1)-numel(auxString2)+1), locCount_max);

        end


        function Fcn5_UITable_binTable(app)

            binLat_String  = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLat),  'UniformOutput', false);
            binLong_String = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLong), 'UniformOutput', false);
            app.binTable_UITable.Data = table(app.binTable{:,5}, binLat_String, binLong_String, app.binTable{:,4});

            s = uistyle("HorizontalAlignment", 'right');
            addStyle(app.binTable_UITable, s, "column", [2,3])

            [binCount_min, binCount_max] = bounds(app.binTable.binCount);

            auxString0 = sprintf('%.0f pontos', height(app.binTable));
            auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', app.binTable_Distance.Value, app.binTable_Distance.Value, app.binTable_Distance.Value);
            auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto',    binCount_min, binCount_max);

            app.binTable_Info.Text = sprintf('%s\n%s\n%.0f |–––––––%s| %.0f varreduras/ponto', auxString0, auxString1, binCount_min, repmat('–', 1, numel(auxString1)-numel(auxString2)+1), binCount_max);

        end


        function Fcn6_Plots(app)

            Fcn6_Plot_chPower(app)
            Fcn6_Plot_Route(app)
            Fcn6_Plot_Scatter(app)
            Fcn6_Plot_Density(app)
            Fcn6_Plot_Car(app)
            drawnow

            Plot1_routeSource(app)

        end


        function Fcn6_Plot_chPower(app)

            idx = 1;
            try
                if app.hROI_Line.Position(1) <= height(app.filtTable)
                    idx = round(app.hROI_Line.Position(1));
                end
            catch
            end

            colors = app.CallingApp.General.Colors;
            
            h = findobj(app.UIAxes2.Children, '-not', 'Tag', 'FilterLevel_THRESHOLD');
            delete(h)

            hold(app.UIAxes2, 'on')
            app.UIAxes2.XAxis.Exponent = 0;

            [minY, maxY] = bounds(app.filtTable.chPower);
            set(app.UIAxes2, 'XLim', [1, height(app.filtTable)], 'YLim', [minY, maxY], ...
                             'XTick', round(linspace(1, height(app.filtTable), 5)))
                                
            plot(app.UIAxes2, app.filtTable.chPower,                    'Tag', 'rawTable', 'Color', colors(4,:), 'Visible', app.chPowerSource_rawTable.Value);
            plot(app.UIAxes2, app.locTable.locPower(app.filtTable.FK2), 'Tag', 'locTable', 'Color', colors(1,:), 'Visible', app.chPowerSource_locTable.Value);
            plot(app.UIAxes2, app.binTable.binPower(app.filtTable.FK3), 'Tag', 'binTable', 'Color', colors(2,:), 'Visible', app.chPowerSource_binTable.Value);

            app.hSmoothedData = plot(app.UIAxes2, smoothdata(app.filtTable.chPower, app.Smoothed_Type.Value, 'SmoothingFactor', app.Smoothed_Factor.Value), 'Color', colors(3,:), 'Visible', app.chPowerSource_smoothed.Value);
            app.hROI_Line     = images.roi.Line(app.UIAxes2, 'Position', [idx minY; idx maxY], 'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate');
            app.hROI_Label    = text(app.UIAxes2, idx, app.UIAxes2.YLim(1)+.94*diff(app.UIAxes2.YLim), sprintf('  %.0f dBm', app.filtTable.chPower(idx)), 'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red', 'FontSmoothing', 'on');
                                
            DataName = {'Dados brutos', 'Pontos de medição', 'Data Binning', 'Curva suave'};

            addlistener(app.hROI_Line, 'MovingROI', @app.timeROICallback);
            addlistener(app.hROI_Line, 'ROIMoved',  @app.timeROICallback);
            notify(app.hROI_Line, 'MovingROI')

            legend(app.UIAxes2, DataName)
            set(app.UIAxes2.Legend, Color=[1,1,1], FontSize=6, Visible=app.legend_Visibility.Value, Location=app.legend_Location.Value, Box='off', Color=[.98,.98,.98])
            hold(app.UIAxes2, 'off')

        end


        function Fcn6_Plot_Route(app)

            delete([app.hRoute_outROI, app.hRoute_inROI, app.hBin])

            outROITable = app.specTable(~app.specTable.filtered,:);
            inROITable  = app.filtTable;

            if app.imgRoute.ImageSource == "DriveTest_Route_ON.png"; Visibility = 1;
            else;                                                    Visibility = 0;
            end

            app.hRoute_outROI = geoscatter(app.UIAxes1, outROITable.Lat, outROITable.Long, 'filled', 'Marker', '.', 'MarkerFaceColor', app.imgRoute_Color1.BackgroundColor, 'MarkerEdgeColor', app.imgRoute_Color1.BackgroundColor, 'SizeData', 50*app.imgRoute_Size.Value, 'Visible', Visibility);
            switch app.imgRoute_Type.Value
                case 'Ponto';     app.hRoute_inROI = geoscatter(app.UIAxes1, inROITable.Lat, inROITable.Long, 'filled', 'Marker', '.', 'MarkerFaceColor', app.imgRoute_Color2.BackgroundColor, 'MarkerEdgeColor', app.imgRoute_Color2.BackgroundColor, 'SizeData', 50*app.imgRoute_Size.Value, 'Visible', Visibility);
                case 'Linha';     app.hRoute_inROI = geoplot(app.UIAxes1,    inROITable.Lat, inROITable.Long, 'Color',     app.imgRoute_Color2.BackgroundColor, 'LineWidth', .5*app.imgRoute_Size.Value, 'Visible', Visibility);
            end

            app.hBin = geoscatter(app.UIAxes1, app.binTable.binLat, app.binTable.binLong, 'filled', ...
                                  'Marker', app.General.hBin.marker, 'MarkerFaceColor', app.General.hBin.color, 'MarkerEdgeColor', app.General.hBin.color, ...
                                  'SizeData', 2*app.General.hBin.size, 'Visible', 0);

        end


        function Fcn6_Plot_Car(app)

            delete(app.hCar)

            idx = round(app.hROI_Line.Position(1));
            app.hCar = geoscatter(app.UIAxes1, app.filtTable.Lat(idx), app.filtTable.Long(idx), 'filled', ...
                                  'Marker', app.General.hCar.marker, 'MarkerFaceColor', app.General.hCar.color, 'MarkerEdgeColor', 'black', ...
                                  'SizeData', 10*app.General.hCar.size, 'Visible', 0);

        end
        

        function Fcn6_Plot_Scatter(app)

            delete(app.hScatter)

            switch app.mapPlot_ButtonGroup.SelectedObject
                case app.Source_rawData; tempTable = app.filtTable;
                case app.Source_locData; tempTable = app.locTable;
                case app.Source_binData; tempTable = app.binTable;
            end

            if app.imgScatter.ImageSource == "DriveTest_Scatter_ON.png"; Visibility = 1;
            else;                                                        Visibility = 0;
            end

            app.hScatter = geoscatter(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, [], tempTable{:,3}, 'filled', ...
                                      'SizeData', 20*app.mapPlot_size2.Value,                                ...
                                      'Visible', Visibility);

        end


        function Fcn6_Plot_Density(app)

            delete(app.hDensity)
            drawnow

            switch app.mapPlot_ButtonGroup.SelectedObject
                case app.Source_rawData; tempTable = app.filtTable;
                case app.Source_locData; tempTable = app.locTable;
                case app.Source_binData; tempTable = app.binTable;
            end

            weights = tempTable{:,3};
            if min(weights) < 0
                weights = weights+abs(min(weights));
            end

            if app.imgDensity.ImageSource == "DriveTest_Density_ON.png"; Visibility = 1;
            else;                                                        Visibility = 0;
            end

            app.hDensity = geodensityplot(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, weights,              ...
                                          'FaceColor','interp',                                              ...
                                          'Radius', app.binTable_Distance.Value*app.mapPlot_size2.Value, ...
                                          'Visible', Visibility);

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
                case 'close';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'CloseFcn', @(~,~)closeFcn(app));
            end

        end


        function timeROICallback(app, src, event)
            
            idx = round(event.Source.Position(1));

            switch(event.EventName)
                case 'MovingROI'
                    disableDefaultInteractivity(app.UIAxes2)                    
                    try
                        app.hROI_Label.Position(1) = idx;
                        app.hROI_Label.String      = sprintf('  %.0f dBm', app.filtTable.chPower(idx));
                        set(app.hCar, 'LatitudeData', app.filtTable.Lat(idx), 'LongitudeData', app.filtTable.Long(idx))

                        if strcmp(app.Tab1_Lock.ImageSource, 'play_lockClose.png')
                            app.CallingApp.auxWin_DriveTest(100*app.filtTable.FK1(idx)/app.specData.Samples)
                        end
                    catch
                    end
                    
                case 'ROIMoved'
                    enableDefaultInteractivity(app.UIAxes2)

                    [minY, maxY] = bounds(app.filtTable.chPower);
                    app.hROI_Line.Position = [idx minY; idx maxY];
            end
            
        end

    end
    
    
    methods (Access = public)

        function timeROICallback_auxFcn(app, timeIndex)

            idx = find(app.filtTable.FK1 == timeIndex, 1);

            if ~isempty(idx)
                app.hROI_Line.Position(1:2) = idx;
                app.hROI_Label.Position(1)  = idx;
                app.hROI_Label.String       = sprintf('  %.0f dBm', app.filtTable.chPower(idx));
                set(app.hCar, 'LatitudeData', app.filtTable.Lat(idx), 'LongitudeData', app.filtTable.Long(idx))
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx1, idx2, Tag)
            
            app.UIFigure.Position(4) = 660;
            PanelTab_SelectionChanged(app, struct('Source', app.Tab1_Image))

            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.UIFigure.Tag = Tag;
            
            app.specData = app.CallingApp.specData(idx1);
            app.Index    = [idx1, idx2];

            Fcn1_AxesCreation(app)
            Fcn2_General(app)
            Fcn3_updateInfo(app, [], [])

            set(app.CallingApp.mainInfo_Lock, 'Enable', 0, 'ImageSource', 'play_lockClose.png')
            app.CallingApp.mainInfo_Tree.Enable = 0;
            
            drawnow nocallbacks
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            set(app.CallingApp.mainInfo_Lock, 'Enable', 1, 'ImageSource', 'play_lockOpen.png')
            app.CallingApp.mainInfo_Tree.Enable = 1;

            delete(app)
            
        end

        % Image clicked function: Tab1_Image, Tab2_Image, Tab3_Image, 
        % Tab4_Image, Tab5_Image
        function PanelTab_SelectionChanged(app, event)
            
            switch event.Source
                case app.Tab1_Image; app.mainPanel_Grid.RowHeight([2,4,6,8,10]) = {'1x',0,0,0,0};
                case app.Tab2_Image; app.mainPanel_Grid.RowHeight([2,4,6,8,10]) = {0,'1x',0,0,0};
                case app.Tab3_Image; app.mainPanel_Grid.RowHeight([2,4,6,8,10]) = {0,0,'1x',0,0};
                case app.Tab4_Image; app.mainPanel_Grid.RowHeight([2,4,6,8,10]) = {0,0,0,'1x',0};
                case app.Tab5_Image; app.mainPanel_Grid.RowHeight([2,4,6,8,10]) = {0,0,0,0,'1x'};
            end

        end

        % Image clicked function: imgShowHide
        function PanelVisibility_ImageClicked(app, event)
            
            if app.mainGrid.ColumnWidth{1} == 300
                app.imgShowHide.ImageSource = 'LT_HideRight.png';
                app.mainGrid.ColumnWidth{1} = 0;
            else
                app.imgShowHide.ImageSource = 'LT_HideLeft.png';
                app.mainGrid.ColumnWidth{1} = 300;
            end

        end

        % Image clicked function: Tab1_Lock
        function PlotLink_ImageClicked(app, event)

            if app.CallingApp.mainInfo_Tree.Enable
                Fields = {'Emissions', 'reportFlag', 'reportOCC', 'reportDetection', 'reportClassification', 'reportAttachments'};
    
                idx = [];
                for ii = 1:numel(app.CallingApp.specData)
                    if isequal(rmfield(app.CallingApp.specData(ii), Fields), rmfield(app.specData, Fields))
                        idx = ii;
                        break
                    end
                end
    
                if isempty(idx)
                    MessageBox(app, 'warning', 'Não identificado o fluxo de dados...')
                    return
                end

                if app.CallingApp.mainInfo_Tree.SelectedNodes.NodeData ~= idx
                    if isempty(app.CallingApp.fig.UserData) || (app.CallingApp.fig.UserData.NodeData ~= idx)
                        hh = [];
                        for ii = 1:numel(app.CallingApp.mainInfo_Tree.Children)
                            for jj = 1:numel(app.CallingApp.mainInfo_Tree.Children(ii).Children)
                                if app.CallingApp.mainInfo_Tree.Children(ii).Children(jj).NodeData == idx
                                    hh = app.CallingApp.mainInfo_Tree.Children(ii).Children(jj);
                                    break
                                end
                            end
                        end
        
                        if app.CallingApp.plotFlag
                            app.CallingApp.toolbarCallbacks_playback([], []);
                            drawnow nocallbacks
                        end                        

                        app.CallingApp.fig.UserData = [];
                        app.CallingApp.auxWin_MaskDraw('Plot_PlaybackMode')
        
                        app.CallingApp.mainInfo_Tree.SelectedNodes = hh;
                        app.CallingApp.auxWin_MaskDraw('Plot_TreeSelectionChanged')
                        
                        app.hROI_Line.Position(1:2) = 1;
                        notify(app.hROI_Line, 'MovingROI')
                    end
                end

                app.CallingApp.mainInfo_Tree.Enable = 0;
                set(app.CallingApp.mainInfo_Lock, 'Enable', 0, 'ImageSource', 'play_lockClose.png')
                app.Tab1_Lock.ImageSource = 'play_lockClose.png';

            else
                app.CallingApp.mainInfo_Tree.Enable = 1;
                set(app.CallingApp.mainInfo_Lock, 'Enable', 1, 'ImageSource', 'play_lockOpen.png')
                app.Tab1_Lock.ImageSource = 'play_lockOpen.png';
            end

        end

        % Image clicked function: EmissionsRefresh
        function Emissions_RefreshListClicked(app, event)
            
            preSelection = extractAfter(app.Emissions.Value, ': ');
            
            try
                app.specData = app.CallingApp.specData(app.Index(1));
                Fcn2_General_Emissions(app)
    
                idx = find(contains(app.Emissions.Items, preSelection), 1);
                if ~isempty(idx)
                    app.Emissions.Value = app.Emissions.Items(idx);
                end
                
                Fcn2_General_MetaData(app)
                Fcn2_General_specTable(app)
                Fcn3_updateInfo(app, [], [])

            catch ME
                MessageBox(app, 'close', getReport(ME))
            end

        end

        % Value changed function: Emissions
        function Emissions_ValueChanged(app, event)

            Fcn2_General_specTable(app)
            Fcn3_updateInfo(app, [], [])
            
        end

        % Selection changed function: FilterTree1
        function FilterTree1_SelectionChanged(app, event)
            
            app.FilterAction_add.Enable = 0;

            try
                if ~isempty(app.FilterTree1.SelectedNodes.NodeData)
                    app.FilterAction_add.Enable = 1;
                end
            catch
            end
            
        end

        % Selection changed function: FilterTree2
        function FilterTree2_SelectionChanged(app, event)
            
            idx = app.FilterTree2.SelectedNodes.NodeData;            

            for ii = 1:height(app.roiFilters)
                if ii ~= idx
                    set(app.roiFilters.handle{ii}, 'InteractionsAllowed', 'none')

                else
                    if app.roiFilters.type == "Level"
                        set(app.roiFilters.handle{ii}, 'InteractionsAllowed',  'translate')
                    else
                        set(app.roiFilters.handle{ii}, 'InteractionsAllowed',  'all')
                    end
                end
            end
            
        end

        % Image clicked function: FilterAction_add
        function FilterAction_addCallback(app, event)
            
            NodeData = app.FilterTree1.SelectedNodes.NodeData;
            
            app.FilterTree1.SelectedNodes = [];
            FilterTree1_SelectionChanged(app)

            set(app.FilterTree2, 'SelectedNodes', [], 'Enable', 0)
            set(app.FilterAction_del, 'Enable', 0)
            
            switch NodeData
                case 1
                    Type = 'Level'; SubType = 'THRESHOLD';                        
                    THR  = min(app.filtTable.chPower);
                    hROI = images.roi.Line(app.UIAxes2, 'Position', [1 THR; height(app.filtTable) THR], 'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate', 'Tag', 'FilterLevel_THRESHOLD');                
                
                case 2
                    Type = 'Geographic ROI'; SubType = 'CÍRCULO';
                    hROI = drawcircle(app.UIAxes1,    'Deletable', 0, 'Tag', 'ROI_Circle');
                
                case 3
                    Type = 'Geographic ROI'; SubType = 'RETÂNGULO';
                    hROI = drawrectangle(app.UIAxes1, 'Deletable', 0, 'Tag', 'ROI_Rectangle', 'Rotatable', true);
                
                case 4
                    Type = 'Geographic ROI'; SubType = 'POLÍGONO';
                    hROI = drawpolygon(app.UIAxes1,   'Deletable', 0, 'Tag', 'ROI_Polygon');
            end

            addlistener(hROI, 'ROIMoved',  @app.Fcn3_updateInfo);

            app.roiFilters(end+1,:) = {Type, SubType, {hROI}, 1};
            Fcn2_General_TreeBuilding(app)
            Fcn3_updateInfo(app, [], [])

        end

        % Image clicked function: FilterAction_del
        function FilterAction_delCallback(app, event)
            
            idx = app.FilterTree2.SelectedNodes.NodeData;

            app.FilterTree1.SelectedNodes = [];
            FilterTree1_SelectionChanged(app)
            
            delete(app.roiFilters.handle{idx}); 
            app.roiFilters(idx,:) = [];
            
            Fcn2_General_TreeBuilding(app)
            Fcn3_updateInfo(app, [], [])

        end

        % Value changed function: locTable_Decimal, locTable_Fcn
        function locTable_ValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            
            Fcn4_locTable(app)
            Fcn5_UITable_locTable(app)

            if app.Source_locData.Value
                Fcn6_Plots(app)
            else
                h = findall(app.UIAxes2, 'Tag', 'locTable');
                h.YData = app.locTable.locPower(app.filtTable.FK2);
            end

            delete(d)
            
        end

        % Value changed function: binTable_Distance, binTable_Fcn
        function binTable_ValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            
            Fcn4_binTable(app)
            Fcn5_UITable_binTable(app)

            if app.Source_binData.Value
                Fcn6_Plots(app)
            else
                set(app.hBin, 'LatitudeData', app.binTable.binLat, 'LongitudeData', app.binTable.binLong)
                
                h = findall(app.UIAxes2, 'Tag', 'binTable');
                h.YData = app.binTable.binPower(app.filtTable.FK3);
            end

            delete(d)
            
        end

        % Callback function: imgRoute_Type, mapPlot_ButtonGroup
        function Plots_RefreshCallback(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            Fcn6_Plots(app)
            delete(d)

        end

        % Image clicked function: imgDensity, imgRoute, imgScatter
        function Plot1_MainButtonClicked(app, event)
            
            switch event.Source
                case app.imgRoute
                    if app.hRoute_inROI.Visible
                        set([app.hRoute_outROI, app.hRoute_inROI], 'Visible', 0)
                        app.imgRoute.ImageSource = 'DriveTest_Route_OFF.png';
                    else
                        set([app.hRoute_outROI, app.hRoute_inROI], 'Visible', 1)
                        app.imgRoute.ImageSource = 'DriveTest_Route_ON.png';
                    end

                case app.imgScatter
                    if app.hScatter.Visible
                        app.hScatter.Visible = 0;
                        app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
                    else
                        app.hScatter.Visible = 1;
                        app.hDensity.Visible = 0;

                        app.imgScatter.ImageSource = 'DriveTest_Scatter_ON.png';
                        app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
                    end

                case app.imgDensity
                    if app.hDensity.Visible
                        app.hDensity.Visible = 0;
                        app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
                    else
                        app.hScatter.Visible = 0;
                        app.hDensity.Visible = 1;

                        app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
                        app.imgDensity.ImageSource = 'DriveTest_Density_ON.png';
                    end
            end

        end

        % Selection changed function: mapPlot_Tree
        function Plot1_TreeSelectionChanged(app, event)
            
            switch app.mapPlot_Tree.SelectedNodes.NodeData
                case 1
                    app.mapPlot_Type.Value = app.General.hCar.marker;
                    app.mapPlot_color1.BackgroundColor = app.General.hCar.color;
                    app.mapPlot_size1.Value = app.General.hCar.size;

                case 2
                    app.mapPlot_Type.Value = app.General.hBin.marker;
                    app.mapPlot_color1.BackgroundColor = app.General.hBin.color;
                    app.mapPlot_size1.Value = app.General.hBin.size;
            end

        end

        % Callback function: mapPlot_Tree
        function Plot1_routeSource(app, event)
            
            if ~isempty(app.mapPlot_Tree.CheckedNodes)
                set(app.hCar, 'Visible', ismember(1, [app.mapPlot_Tree.CheckedNodes.NodeData]))
                set(app.hBin, 'Visible', ismember(2, [app.mapPlot_Tree.CheckedNodes.NodeData]))
            else
                set(app.hCar, 'Visible', 0)
                set(app.hBin, 'Visible', 0)
            end

        end

        % Button pushed function: imgRoute_Color1, imgRoute_Color2, 
        % mapPlot_color1
        function Plot1_ColorCallback(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                event.Source.BackgroundColor = selectedColor;

                try
                    switch event.Source
                        case app.imgRoute_Color1
                            set(app.hRoute_outROI, 'Color', selectedColor)
                        
                        case app.imgRoute_Color2
                            switch app.imgRoute_Type.Value
                                case 'Ponto'
                                    set(app.hRoute_inROI, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
                                case 'Linha'
                                    set(app.hRoute_inROI, 'Color', selectedColor)
                            end

                        case app.mapPlot_color1
                            switch app.mapPlot_Tree.SelectedNodes.NodeData
                                case 1
                                    set(app.hCar, 'MarkerFaceColor', selectedColor)
                                    app.General.hCar.color = selectedColor;
                                case 2
                                    set(app.hBin, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
                                    app.General.hBin.color = selectedColor;
                            end
                    end
                catch
                end
            end

        end

        % Value changing function: imgRoute_Size, mapPlot_size1
        function Plot1_SizeChangingCallback(app, event)
            
            try
                switch event.Source
                    case app.imgRoute_Size
                        set(app.hRoute_outROI, 'LineWidth', .5*event.Value)

                        switch app.imgRoute_Type.Value
                            case 'Ponto'; set(app.hRoute_inROI, 'SizeData',  50*event.Value)
                            case 'Linha'; set(app.hRoute_inROI, 'LineWidth', .5*event.Value)
                        end

                    case app.mapPlot_size1
                        switch app.mapPlot_Tree.SelectedNodes.NodeData
                            case 1
                                set(app.hCar, 'SizeData', 10*event.Value)
                                app.General.hCar.size = event.Value;
                            case 2
                                set(app.hBin, 'SizeData',  2*event.Value)
                                app.General.hBin.size = event.Value;
                        end
                end
            catch
            end            

        end

        % Value changed function: mapPlot_size2
        function Plot1_SizeChangedCallback(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            if app.imgScatter.ImageSource == "DriveTest_Scatter_ON.png"
                set(app.hScatter, 'SizeData', 20*event.Value)
            end

            if app.imgDensity.ImageSource == "DriveTest_Density_ON.png"
                set(app.hDensity, 'Radius', app.binTable_Distance.Value*event.Value)
            end
            drawnow

            delete(d)

        end

        % Value changed function: mapPlot_Type
        function Plot1_MarkerCallback(app, event)
            
            try
                switch app.mapPlot_Tree.SelectedNodes.NodeData
                    case 1
                        set(app.hCar, 'Marker', event.Value)
                        app.General.hCar.marker = event.Value;

                    case 2
                        set(app.hBin, 'Marker', event.Value)
                        app.General.hBin.marker = event.Value;
                end
            catch
            end

        end

        % Value changed function: Colormap
        function Plot1_ColormapValueChanged(app, event)
            
            colormap(app.UIAxes1, app.Colormap.Value)
            
        end

        % Value changed function: chPowerSource_binTable, 
        % chPowerSource_locTable, chPowerSource_rawTable
        function Plot2_chPowerSource(app, event)
            
            switch event.Source
                case app.chPowerSource_rawTable; Tag = 'rawTable';
                case app.chPowerSource_locTable; Tag = 'locTable';
                case app.chPowerSource_binTable; Tag = 'binTable';
            end

            h = findall(app.UIAxes2, 'Tag', Tag);
            if event.Source.Value
                h.Visible = 1;
            else
                h.Visible = 0;
            end

        end

        % Value changed function: chPowerSource_smoothed
        function Plot2_chPowerSource_smoothedData(app, event)
            
            if app.chPowerSource_smoothed.Value
                set(app.Smoothed_Grid.Children, 'Enable', 1)
                app.hSmoothedData.Visible = 1;

            else
                set(app.Smoothed_Grid.Children, 'Enable', 0)
                app.hSmoothedData.Visible = 0;
            end            
            
        end

        % Value changed function: Smoothed_Factor, Smoothed_Type
        function Plot2_smoothedDataUpdate(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");            
            app.hSmoothedData.YData = smoothdata(app.filtTable.chPower, app.Smoothed_Type.Value, 'SmoothingFactor', app.Smoothed_Factor.Value);
            delete(d)
            
        end

        % Value changed function: legend_Location, legend_Visibility
        function Plot2_LegendVisibilityValueChanged(app, event)
            
            switch event.Source
                case app.legend_Visibility
                    app.UIAxes2.Legend.Visible = app.legend_Visibility.Value;

                case app.legend_Location
                    app.UIAxes2.Legend.Location = app.legend_Location.Value;
            end
            
        end

        % Image clicked function: ExcelFile_Button1, ExcelFile_Button2
        function ExcelFile_ButtonPushed(app, event)
            
            [Filename, FilePath] = uiputfile({'*.xlsx',    '(*.xlsx)'}, ...
                                              'Selecione a pasta e o nome do arquivo', fullfile(app.CallingApp.menu_userPath.Value, sprintf('Drive-Test_%s.xlsx', datestr(datetime('now'), 'yyyy.mm.dd_THH.MM.SS'))));

            figure(app.UIFigure)
    
            if Filename
                d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

                try
                    tempFilters = app.roiFilters(:, 1:2);
                    for ii = 1:height(app.roiFilters)
                        switch app.roiFilters.subtype{ii}
                            case 'THRESHOLD'; tempFilters.Parameters{ii} = struct('Threshold', app.roiFilters.handle{ii}.Position(3));
                            case 'CÍRCULO';   tempFilters.Parameters{ii} = struct('Center',    app.roiFilters.handle{ii}.Center,   'Radius',        app.roiFilters.handle{ii}.Radius);
                            case 'RETÂNGULO'; tempFilters.Parameters{ii} = struct('Position',  app.roiFilters.handle{ii}.Position, 'RotationAngle', app.roiFilters.handle{ii}.RotationAngle);
                            case 'POLÍGONO';  tempFilters.Parameters{ii} = struct('Position',  app.roiFilters.handle{ii}.Position);
                        end
                    end
    
                    Filename = fullfile(FilePath, Filename);
        
                    fileID = fopen(replace(Filename, '.xlsx', '.cfg'), "wt");
                    fprintf(fileID, '%s', sprintf('EMISSÃO:\n%s\n\n\n', extractAfter(app.Emissions.Value, ': ')));
                    fprintf(fileID, '%s', sprintf('INFORMAÇÕES GERAIS:\n%s\n\n', strjoin(app.rawDataInfo.Value, '\n')));
                    fprintf(fileID, 'FILTRO(S):\n%s\n\n\n',             jsonencode(tempFilters, 'PrettyPrint', true));
                    fprintf(fileID, 'PONTOS DE MEDIÇÃO:\n%s\n%s\n\n\n', jsonencode(struct('Type', app.locTable_Type.Value, 'Decimals', app.locTable_Decimal.Value,  'Fcn', app.locTable_Fcn.Value)), app.locTable_Info.Text);
                    fprintf(fileID, 'DATA BINNING:\n%s\n%s',            jsonencode(struct('Type', app.binTable_Type.Value, 'Distance', app.binTable_Distance.Value, 'Fcn', app.binTable_Fcn.Value)), app.binTable_Info.Text);
                    fclose(fileID);
        
                    writetable(app.specTable,              Filename, 'FileType', 'spreadsheet', 'WriteMode', 'replacefile',    'Sheet', 'specTable')
                    writetable(app.filtTable(:,[1:3,6:8]), Filename, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'filtTable')
                    writetable(app.locTable,               Filename, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'locTable')
                    writetable(app.binTable,               Filename, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'binTable')

                catch ME
                    MessageBox(app, 'error', getReport(ME))
                end

                delete(d)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Drive-test';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.mainGrid = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.mainGrid);
            xlabel(app.UIAxes2, 'Amostra')
            ylabel(app.UIAxes2, 'Potência canal (dBm)')
            app.mainPanel = uipanel(app.mainGrid);
            app.mainPanel_Grid = uigridlayout(app.mainPanel);
            app.Tab1_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab1_Grid.Tag = 'COLORLOCKED';
            app.Tab1_Image = uiimage(app.Tab1_Grid);
            app.Tab1_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab1_Title = uilabel(app.Tab1_Grid);
            app.Tab1_Title.Text = 'ASPECTOS GERAIS';
            app.Tab1_Lock = uiimage(app.Tab1_Grid);
            app.Tab1_Lock.ImageClickedFcn = createCallbackFcn(app, @PlotLink_ImageClicked, true);
            app.Tab1_Lock.ImageSource = 'play_lockClose.png';
            app.Tab1_Panel = uigridlayout(app.mainPanel_Grid);
            app.Emissions_Grid = uigridlayout(app.Tab1_Panel);
            app.EmissionsLabel = uilabel(app.Emissions_Grid);
            app.EmissionsLabel.Text = 'Emissão:';
            app.EmissionsRefresh = uiimage(app.Emissions_Grid);
            app.EmissionsRefresh.ImageClickedFcn = createCallbackFcn(app, @Emissions_RefreshListClicked, true);
            app.EmissionsRefresh.ImageSource = 'LT_refresh.png';
            app.Emissions = uidropdown(app.Tab1_Panel);
            app.Emissions.Items = {};
            app.Emissions.ValueChangedFcn = createCallbackFcn(app, @Emissions_ValueChanged, true);
            app.Emissions.Value = {};
            app.rawDataInfo = uitextarea(app.Tab1_Panel);
            app.rawDataInfo.Editable = 'off';
            app.FilterTreeLabel = uilabel(app.Tab1_Panel);
            app.FilterTreeLabel.Text = 'Filtros:';
            app.FilterTree1 = uitree(app.Tab1_Panel);
            app.FilterTree1.SelectionChangedFcn = createCallbackFcn(app, @FilterTree1_SelectionChanged, true);
            app.levelFilterNode = uitreenode(app.FilterTree1);
            app.levelFilterNode.Icon = 'LT_OCC.png';
            app.levelFilterNode.Text = 'NÍVEL';
            app.levelFilter_thr = uitreenode(app.levelFilterNode);
            app.levelFilter_thr.NodeData = 1;
            app.levelFilter_thr.Text = 'THRESHOLD';
            app.geoFilterNode = uitreenode(app.FilterTree1);
            app.geoFilterNode.Icon = 'DriveTest_Scatter_OFF.png';
            app.geoFilterNode.Text = 'GEOGRÁFICO';
            app.geoFilter_Circle = uitreenode(app.geoFilterNode);
            app.geoFilter_Circle.NodeData = 2;
            app.geoFilter_Circle.Text = 'CÍRCULO';
            app.geoFilter_Rectangle = uitreenode(app.geoFilterNode);
            app.geoFilter_Rectangle.NodeData = 3;
            app.geoFilter_Rectangle.Text = 'RETÂNGULO';
            app.geoFilter_Polygon = uitreenode(app.geoFilterNode);
            app.geoFilter_Polygon.NodeData = 4;
            app.geoFilter_Polygon.Text = 'POLÍGONO';
            app.FilterTree2 = uitree(app.Tab1_Panel);
            app.FilterTree2.SelectionChangedFcn = createCallbackFcn(app, @FilterTree2_SelectionChanged, true);
            app.FilterAction_Grid1 = uigridlayout(app.Tab1_Panel);
            app.FilterAction_add = uiimage(app.FilterAction_Grid1);
            app.FilterAction_add.ImageClickedFcn = createCallbackFcn(app, @FilterAction_addCallback, true);
            app.FilterAction_add.Enable = 'off';
            app.FilterAction_add.ImageSource = 'LT_play.png';
            app.FilterAction_Grid2 = uigridlayout(app.Tab1_Panel);
            app.FilterAction_del = uiimage(app.FilterAction_Grid2);
            app.FilterAction_del.ImageClickedFcn = createCallbackFcn(app, @FilterAction_delCallback, true);
            app.FilterAction_del.Enable = 'off';
            app.FilterAction_del.ImageSource = 'LT_redX.png';
            app.FilterTreeInfo = uilabel(app.Tab1_Panel);
            app.FilterTreeInfo.Text = '100%';
            app.Tab2_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab2_Grid.Tag = 'COLORLOCKED';
            app.Tab2_Image = uiimage(app.Tab2_Grid);
            app.Tab2_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab2_Image.ImageSource = 'Instr_Global.png';
            app.Tab2_Title = uilabel(app.Tab2_Grid);
            app.Tab2_Title.Text = 'PONTOS DE MEDIÇÃO';
            app.Tab2_Panel = uigridlayout(app.mainPanel_Grid);
            app.locTable_TypeLabel = uilabel(app.Tab2_Panel);
            app.locTable_TypeLabel.Text = 'Tipo:';
            app.locTable_Type = uidropdown(app.Tab2_Panel);
            app.locTable_Type.Items = {'Coordenadas truncadas'};
            app.locTable_Type.Enable = 'off';
            app.locTable_Type.Value = 'Coordenadas truncadas';
            app.locTable_DecimalLabel = uilabel(app.Tab2_Panel);
            app.locTable_DecimalLabel.Text = 'Casas decimais:';
            app.locTable_Decimal = uispinner(app.Tab2_Panel);
            app.locTable_Decimal.Limits = [1 10];
            app.locTable_Decimal.RoundFractionalValues = 'on';
            app.locTable_Decimal.ValueDisplayFormat = '%.0f';
            app.locTable_Decimal.ValueChangedFcn = createCallbackFcn(app, @locTable_ValueChanged, true);
            app.locTable_Decimal.Value = 6;
            app.locTable_Grid = uigridlayout(app.Tab2_Panel);
            app.locTable_UITable = uitable(app.locTable_Grid);
            app.locTable_UITable.ColumnName = {''; ''; ''; ''};
            app.locTable_UITable.RowName = {};
            app.locTable_UITable.SelectionType = 'row';
            app.locTable_Col1 = uilabel(app.locTable_Grid);
            app.locTable_Col1.Text = {'  ID'; '  '};
            app.locTable_Col2 = uilabel(app.locTable_Grid);
            app.locTable_Col2.Text = {'  Latitude'; '  '};
            app.locTable_Col3 = uilabel(app.locTable_Grid);
            app.locTable_Col3.Text = {'  Longitude'; '  '};
            app.locTable_Col4 = uilabel(app.locTable_Grid);
            app.locTable_Col4.Text = {'  Varreduras'; '  '};
            app.locTable_Info = uilabel(app.locTable_Grid);
            app.locTable_Info.Text = {'10 |––––––––––––| 90 varreduras/ponto'; 'dee'; 'dede'};
            app.ExcelFile_Grid1 = uigridlayout(app.locTable_Grid);
            app.ExcelFile_Button1 = uiimage(app.ExcelFile_Grid1);
            app.ExcelFile_Button1.ImageClickedFcn = createCallbackFcn(app, @ExcelFile_ButtonPushed, true);
            app.ExcelFile_Button1.Tooltip = {'Exportar tabela completa'};
            app.ExcelFile_Button1.ImageSource = 'LT_save.png';
            app.locTable_FcnLabel = uilabel(app.Tab2_Panel);
            app.locTable_FcnLabel.Text = 'Operação de sumarização:';
            app.locTable_Fcn = uidropdown(app.Tab2_Panel);
            app.locTable_Fcn.Items = {'Mínima', 'Média', 'Mediana', 'RMS', 'Máxima'};
            app.locTable_Fcn.ValueChangedFcn = createCallbackFcn(app, @locTable_ValueChanged, true);
            app.locTable_Fcn.Value = 'Média';
            app.Tab3_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab3_Grid.Tag = 'COLORLOCKED';
            app.Tab3_Image = uiimage(app.Tab3_Grid);
            app.Tab3_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab3_Image.ImageSource = 'LT_Detection.png';
            app.Tab3_Title = uilabel(app.Tab3_Grid);
            app.Tab3_Title.Text = 'DATA BINNING';
            app.Tab3_Panel = uigridlayout(app.mainPanel_Grid);
            app.binTable_TypeLabel = uilabel(app.Tab3_Panel);
            app.binTable_TypeLabel.Text = 'Tipo:';
            app.binTable_Type = uidropdown(app.Tab3_Panel);
            app.binTable_Type.Items = {'Grid retangular'};
            app.binTable_Type.Enable = 'off';
            app.binTable_Type.Value = 'Grid retangular';
            app.binTable_DistanceLabel = uilabel(app.Tab3_Panel);
            app.binTable_DistanceLabel.Interpreter = 'html';
            app.binTable_DistanceLabel.Text = 'Distância <i>bins</i> (metros):';
            app.binTable_Distance = uispinner(app.Tab3_Panel);
            app.binTable_Distance.Step = 50;
            app.binTable_Distance.Limits = [50 500];
            app.binTable_Distance.RoundFractionalValues = 'on';
            app.binTable_Distance.ValueDisplayFormat = '%.0f';
            app.binTable_Distance.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Distance.Value = 100;
            app.binTable_FcnLabel = uilabel(app.Tab3_Panel);
            app.binTable_FcnLabel.Text = 'Operação de sumarização:';
            app.binTable_Grid = uigridlayout(app.Tab3_Panel);
            app.binTable_UITable = uitable(app.binTable_Grid);
            app.binTable_UITable.ColumnName = {''; ''; ''; ''};
            app.binTable_UITable.RowName = {};
            app.binTable_UITable.SelectionType = 'row';
            app.binTable_Col1 = uilabel(app.binTable_Grid);
            app.binTable_Col1.Text = {'  ID'; '  '};
            app.binTable_Col2 = uilabel(app.binTable_Grid);
            app.binTable_Col2.Text = {'  Latitude'; ' '};
            app.binTable_Col3 = uilabel(app.binTable_Grid);
            app.binTable_Col3.Text = {'  Longitude'; '  '};
            app.binTable_Col4 = uilabel(app.binTable_Grid);
            app.binTable_Col4.Text = {'  Varreduras'; '  '};
            app.binTable_Info = uilabel(app.binTable_Grid);
            app.binTable_Info.Text = {'10 |––––––––––––| 90 varreduras/ponto'; 'dede'; 'dee'};
            app.ExcelFile_Grid2 = uigridlayout(app.binTable_Grid);
            app.ExcelFile_Button2 = uiimage(app.ExcelFile_Grid2);
            app.ExcelFile_Button2.ImageClickedFcn = createCallbackFcn(app, @ExcelFile_ButtonPushed, true);
            app.ExcelFile_Button2.Tooltip = {'Exportar tabela completa'};
            app.ExcelFile_Button2.ImageSource = 'LT_save.png';
            app.binTable_Fcn = uidropdown(app.Tab3_Panel);
            app.binTable_Fcn.Items = {'Mínima', 'Média', 'Mediana', 'RMS', 'Máxima'};
            app.binTable_Fcn.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Fcn.Value = 'Média';
            app.Tab4_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab4_Grid.Tag = 'COLORLOCKED';
            app.Tab4_Image = uiimage(app.Tab4_Grid);
            app.Tab4_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab4_Image.ImageSource = 'LT_Layers.png';
            app.Tab4_Title = uilabel(app.Tab4_Grid);
            app.Tab4_Title.Text = 'CAMADAS (MAPA)';
            app.Tab4_Panel = uigridlayout(app.mainPanel_Grid);
            app.imgRoute = uiimage(app.Tab4_Panel);
            app.imgRoute.ImageClickedFcn = createCallbackFcn(app, @Plot1_MainButtonClicked, true);
            app.imgRoute.Tooltip = {'Rota'};
            app.imgRoute.ImageSource = 'DriveTest_Route_ON.png';
            app.imgScatter = uiimage(app.Tab4_Panel);
            app.imgScatter.ImageClickedFcn = createCallbackFcn(app, @Plot1_MainButtonClicked, true);
            app.imgScatter.Tooltip = {'Distorção'};
            app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
            app.imgDensity = uiimage(app.Tab4_Panel);
            app.imgDensity.ImageClickedFcn = createCallbackFcn(app, @Plot1_MainButtonClicked, true);
            app.imgDensity.Tooltip = {'Densidade'};
            app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
            app.mapPlot_Grid = uigridlayout(app.Tab4_Panel);
            app.mapPlot_Title1 = uilabel(app.mapPlot_Grid);
            app.mapPlot_Title1.Text = 'Gráfico rota:';
            app.mapPlot_Panel1 = uipanel(app.mapPlot_Grid);
            app.mapPlot_Grid1 = uigridlayout(app.mapPlot_Panel1);
            app.mapPlot_Subtitle11 = uilabel(app.mapPlot_Grid1);
            app.mapPlot_Subtitle11.Text = 'Rota:';
            app.mapPlot_Panel11 = uipanel(app.mapPlot_Grid1);
            app.mapPlot_Grid11 = uigridlayout(app.mapPlot_Panel11);
            app.imgRoute_TypeLabel = uilabel(app.mapPlot_Grid11);
            app.imgRoute_TypeLabel.Text = 'Tipo:';
            app.imgRoute_Type = uidropdown(app.mapPlot_Grid11);
            app.imgRoute_Type.Items = {'Ponto', 'Linha'};
            app.imgRoute_Type.ValueChangedFcn = createCallbackFcn(app, @Plots_RefreshCallback, true);
            app.imgRoute_Type.Value = 'Ponto';
            app.imgRoute_PropertyLabel = uilabel(app.mapPlot_Grid11);
            app.imgRoute_PropertyLabel.Text = 'Cor/Tamanho:';
            app.imgRoute_Panel = uipanel(app.mapPlot_Grid11);
            app.imgRoute_Grid = uigridlayout(app.imgRoute_Panel);
            app.imgRoute_Color1 = uibutton(app.imgRoute_Grid, 'push');
            app.imgRoute_Color1.ButtonPushedFcn = createCallbackFcn(app, @Plot1_ColorCallback, true);
            app.imgRoute_Color1.Text = '';
            app.imgRoute_Color2 = uibutton(app.imgRoute_Grid, 'push');
            app.imgRoute_Color2.ButtonPushedFcn = createCallbackFcn(app, @Plot1_ColorCallback, true);
            app.imgRoute_Color2.Text = '';
            app.imgRoute_Size = uislider(app.imgRoute_Grid);
            app.imgRoute_Size.Limits = [1 9];
            app.imgRoute_Size.MajorTicks = [];
            app.imgRoute_Size.MajorTickLabels = {''};
            app.imgRoute_Size.ValueChangingFcn = createCallbackFcn(app, @Plot1_SizeChangingCallback, true);
            app.imgRoute_Size.MinorTicks = [];
            app.imgRoute_Size.Value = 1;
            app.mapPlot_Subtitle12 = uilabel(app.mapPlot_Grid1);
            app.mapPlot_Subtitle12.Text = 'Visualizações secundárias:';
            app.mapPlot_Grid1A = uigridlayout(app.mapPlot_Grid1);
            app.mapPlot_Tree = uitree(app.mapPlot_Grid1A, 'checkbox');
            app.mapPlot_Tree.SelectionChangedFcn = createCallbackFcn(app, @Plot1_TreeSelectionChanged, true);
            app.mapPlot_hCar = uitreenode(app.mapPlot_Tree);
            app.mapPlot_hCar.NodeData = 1;
            app.mapPlot_hCar.Text = 'Veículo';
            app.mapPlot_hBin = uitreenode(app.mapPlot_Tree);
            app.mapPlot_hBin.NodeData = 2;
            app.mapPlot_hBin.Text = 'Grid retangular (Data Binning)';
            % Assign Checked Nodes
            app.mapPlot_Tree.CheckedNodes = [app.mapPlot_hCar];
            % Assign Checked Nodes
            app.mapPlot_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @Plot1_routeSource, true);
            app.mapPlot_Grid1A1 = uigridlayout(app.mapPlot_Grid1A);
            app.mapPlot_TypeLabel = uilabel(app.mapPlot_Grid1A1);
            app.mapPlot_TypeLabel.Text = 'Marcador:';
            app.mapPlot_Type = uidropdown(app.mapPlot_Grid1A1);
            app.mapPlot_Type.Items = {'o', 's', 'd', '^', 'v', '>', '<', 'p', 'h'};
            app.mapPlot_Type.ValueChangedFcn = createCallbackFcn(app, @Plot1_MarkerCallback, true);
            app.mapPlot_Type.Value = 's';
            app.mapPlot_Property1Label = uilabel(app.mapPlot_Grid1A1);
            app.mapPlot_Property1Label.Text = 'Cor/Tamanho:';
            app.mapPlot_Property1Panel = uipanel(app.mapPlot_Grid1A1);
            app.mapPlot_Property1Grid = uigridlayout(app.mapPlot_Property1Panel);
            app.mapPlot_color1 = uibutton(app.mapPlot_Property1Grid, 'push');
            app.mapPlot_color1.ButtonPushedFcn = createCallbackFcn(app, @Plot1_ColorCallback, true);
            app.mapPlot_color1.Text = '';
            app.mapPlot_size1 = uislider(app.mapPlot_Property1Grid);
            app.mapPlot_size1.Limits = [1 9];
            app.mapPlot_size1.MajorTicks = [];
            app.mapPlot_size1.MajorTickLabels = {''};
            app.mapPlot_size1.ValueChangingFcn = createCallbackFcn(app, @Plot1_SizeChangingCallback, true);
            app.mapPlot_size1.MinorTicks = [];
            app.mapPlot_size1.Value = 5;
            app.mapPlot_Title2 = uilabel(app.mapPlot_Grid);
            app.mapPlot_Title2.Text = 'Gráficos distorção/densidade:';
            app.mapPlot_Panel2 = uipanel(app.mapPlot_Grid);
            app.mapPlot_Grid2 = uigridlayout(app.mapPlot_Panel2);
            app.mapPlot_ButtonGroupLabel = uilabel(app.mapPlot_Grid2);
            app.mapPlot_ButtonGroupLabel.Text = 'Fonte da informação gráfica:';
            app.mapPlot_ButtonGroup = uibuttongroup(app.mapPlot_Grid2);
            app.mapPlot_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Plots_RefreshCallback, true);
            app.Source_rawData = uiradiobutton(app.mapPlot_ButtonGroup);
            app.Source_rawData.Text = 'Dados brutos';
            app.Source_rawData.Value = true;
            app.Source_locData = uiradiobutton(app.mapPlot_ButtonGroup);
            app.Source_locData.Text = 'Dados processados (Pontos de medição)';
            app.Source_binData = uiradiobutton(app.mapPlot_ButtonGroup);
            app.Source_binData.Text = 'Dados processados (Data Binning)';
            app.ColormapLabel = uilabel(app.mapPlot_Grid2);
            app.ColormapLabel.Text = 'Mapa de cor:';
            app.Colormap = uidropdown(app.mapPlot_Grid2);
            app.Colormap.Items = {'hot', 'summer', 'winter', 'jet'};
            app.Colormap.ValueChangedFcn = createCallbackFcn(app, @Plot1_ColormapValueChanged, true);
            app.Colormap.Value = 'jet';
            app.imgScatter_Title = uilabel(app.mapPlot_Grid2);
            app.imgScatter_Title.Text = 'Raio:';
            app.mapPlot_Property2Panel = uipanel(app.mapPlot_Grid2);
            app.mapPlot_Property2Grid = uigridlayout(app.mapPlot_Property2Panel);
            app.mapPlot_size2 = uislider(app.mapPlot_Property2Grid);
            app.mapPlot_size2.Limits = [1 9];
            app.mapPlot_size2.MajorTicks = [];
            app.mapPlot_size2.MajorTickLabels = {''};
            app.mapPlot_size2.ValueChangedFcn = createCallbackFcn(app, @Plot1_SizeChangedCallback, true);
            app.mapPlot_size2.MinorTicks = [];
            app.mapPlot_size2.Value = 5;
            app.Tab5_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab5_Grid.Tag = 'COLORLOCKED';
            app.Tab5_Image = uiimage(app.Tab5_Grid);
            app.Tab5_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab5_Image.ImageSource = 'LT_playback.png';
            app.Tab5_Title = uilabel(app.Tab5_Grid);
            app.Tab5_Title.Text = 'CAMADAS (EIXO CARTESIANO)';
            app.Tab5_Panel = uigridlayout(app.mainPanel_Grid);
            app.chPowerSource_PanelLabel = uilabel(app.Tab5_Panel);
            app.chPowerSource_PanelLabel.Text = 'Curvas:';
            app.chPowerSource_Panel = uipanel(app.Tab5_Panel);
            app.chPowerSource_Grid = uigridlayout(app.chPowerSource_Panel);
            app.chPowerSource_rawTable = uicheckbox(app.chPowerSource_Grid);
            app.chPowerSource_rawTable.ValueChangedFcn = createCallbackFcn(app, @Plot2_chPowerSource, true);
            app.chPowerSource_rawTable.Text = 'Dados brutos';
            app.chPowerSource_rawTable.Value = true;
            app.chPowerSource_locTable = uicheckbox(app.chPowerSource_Grid);
            app.chPowerSource_locTable.ValueChangedFcn = createCallbackFcn(app, @Plot2_chPowerSource, true);
            app.chPowerSource_locTable.Text = 'Dados processados (Pontos de medição)';
            app.chPowerSource_binTable = uicheckbox(app.chPowerSource_Grid);
            app.chPowerSource_binTable.ValueChangedFcn = createCallbackFcn(app, @Plot2_chPowerSource, true);
            app.chPowerSource_binTable.Text = 'Dados processados (Data Binning)';
            app.chPowerSource_smoothed = uicheckbox(app.chPowerSource_Grid);
            app.chPowerSource_smoothed.ValueChangedFcn = createCallbackFcn(app, @Plot2_chPowerSource_smoothedData, true);
            app.chPowerSource_smoothed.Text = 'Curva suave';
            app.Smoothed_Panel = uipanel(app.chPowerSource_Grid);
            app.Smoothed_Grid = uigridlayout(app.Smoothed_Panel);
            app.Smoothed_TypeLabel = uilabel(app.Smoothed_Grid);
            app.Smoothed_TypeLabel.Enable = 'off';
            app.Smoothed_TypeLabel.Text = 'Tipo:';
            app.Smoothed_Type = uidropdown(app.Smoothed_Grid);
            app.Smoothed_Type.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.Smoothed_Type.ValueChangedFcn = createCallbackFcn(app, @Plot2_smoothedDataUpdate, true);
            app.Smoothed_Type.Enable = 'off';
            app.Smoothed_Type.Value = 'gaussian';
            app.Smoothed_FactorLabel = uilabel(app.Smoothed_Grid);
            app.Smoothed_FactorLabel.Enable = 'off';
            app.Smoothed_FactorLabel.Text = 'Fator:';
            app.Smoothed_Factor = uispinner(app.Smoothed_Grid);
            app.Smoothed_Factor.Step = 0.1;
            app.Smoothed_Factor.Limits = [0 1];
            app.Smoothed_Factor.ValueDisplayFormat = '%.2f';
            app.Smoothed_Factor.ValueChangedFcn = createCallbackFcn(app, @Plot2_smoothedDataUpdate, true);
            app.Smoothed_Factor.Enable = 'off';
            app.Smoothed_Factor.Value = 0.25;
            app.legend_PanelLabel = uilabel(app.Tab5_Panel);
            app.legend_PanelLabel.Text = 'Legenda:';
            app.legend_Panel = uipanel(app.Tab5_Panel);
            app.legend_Grid = uigridlayout(app.legend_Panel);
            app.legend_VisibilityLabel = uilabel(app.legend_Grid);
            app.legend_VisibilityLabel.Text = 'Visibilidade:';
            app.legend_Visibility = uidropdown(app.legend_Grid);
            app.legend_Visibility.Items = {'on', 'off'};
            app.legend_Visibility.ValueChangedFcn = createCallbackFcn(app, @Plot2_LegendVisibilityValueChanged, true);
            app.legend_Visibility.Value = 'off';
            app.legend_LocationLabel = uilabel(app.legend_Grid);
            app.legend_LocationLabel.Text = 'Posição:';
            app.legend_Location = uidropdown(app.legend_Grid);
            app.legend_Location.Items = {'northeast', 'northwest', 'northeastoutside', 'southeastoutside'};
            app.legend_Location.ValueChangedFcn = createCallbackFcn(app, @Plot2_LegendVisibilityValueChanged, true);
            app.legend_Location.Value = 'northeast';
            app.imgShowHide = uiimage(app.mainGrid);
            app.imgShowHide.ImageClickedFcn = createCallbackFcn(app, @PanelVisibility_ImageClicked, true);
            app.imgShowHide.ImageSource = 'LT_HideLeft.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end