classdef winFiscaliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout17             matlab.ui.container.GridLayout
        GridLayout21             matlab.ui.container.GridLayout
        Button_Play              matlab.ui.control.Button
        Button_Report            matlab.ui.control.Button
        Separator2               matlab.ui.control.Image
        Separator1               matlab.ui.control.Image
        Button_ChangeIssue       matlab.ui.control.StateButton
        Button_SEI               matlab.ui.control.Button
        Button_Fiscaliza         matlab.ui.control.Button
        Title                    matlab.ui.control.Label
        Sublabel2                matlab.ui.control.Label
        Panel_4                  matlab.ui.container.Panel
        Grid4                    matlab.ui.container.GridLayout
        GridLayout20             matlab.ui.container.GridLayout
        JournalLabel             matlab.ui.control.Label
        Journal                  matlab.ui.control.TextArea
        ReportFile               matlab.ui.control.EditField
        ReportFileLabel          matlab.ui.control.Label
        CheckBox                 matlab.ui.control.CheckBox
        Services                 matlab.ui.control.TextArea
        ServicesLabel            matlab.ui.control.Label
        Hyperlink2               matlab.ui.control.Hyperlink
        Grid4A                   matlab.ui.container.GridLayout
        CityLabel                matlab.ui.control.Label
        City                     matlab.ui.control.TextArea
        Panel_9                  matlab.ui.container.Panel
        GridLayout13             matlab.ui.container.GridLayout
        Longitude                matlab.ui.control.NumericEditField
        LongitudeLabel           matlab.ui.control.Label
        Latitude                 matlab.ui.control.NumericEditField
        LatitudeLabel            matlab.ui.control.Label
        Sublabel7                matlab.ui.control.Label
        Grid4B                   matlab.ui.container.GridLayout
        Panel_15                 matlab.ui.container.Panel
        GridLayout19             matlab.ui.container.GridLayout
        EmissionsCount3          matlab.ui.control.NumericEditField
        EmissionsCount3Label     matlab.ui.control.Label
        EmissionsCount2          matlab.ui.control.NumericEditField
        EmissionsCount2Label     matlab.ui.control.Label
        EmissionsCount1Label     matlab.ui.control.Label
        EmissionsCount1          matlab.ui.control.NumericEditField
        EmissionsLabel           matlab.ui.control.Label
        Panel_10                 matlab.ui.container.Panel
        GridLayout14             matlab.ui.container.GridLayout
        Limit2                   matlab.ui.control.NumericEditField
        Limit2Label              matlab.ui.control.Label
        Limit1                   matlab.ui.control.NumericEditField
        Limit1Label              matlab.ui.control.Label
        BandLimits               matlab.ui.control.Label
        Label3                   matlab.ui.control.Label
        Label2                   matlab.ui.control.Label
        Panel1                   matlab.ui.container.Panel
        Grid3                    matlab.ui.container.GridLayout
        GridLayout18             matlab.ui.container.GridLayout
        issueDescriptionLabel    matlab.ui.control.Label
        issueDescription         matlab.ui.control.TextArea
        issueStatus              matlab.ui.control.EditField
        issueStatusLabel         matlab.ui.control.Label
        issueDesiredStatus       matlab.ui.control.DropDown
        issueDesiredStatusLabel  matlab.ui.control.Label
        Grid3C                   matlab.ui.container.GridLayout
        WorkersTree              matlab.ui.container.CheckBoxTree
        ResponsableLabel         matlab.ui.control.Label
        Responsable              matlab.ui.control.DropDown
        WorkersTreeLabel         matlab.ui.control.Label
        Grid3B                   matlab.ui.container.GridLayout
        Panel_14                 matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        Hours4Label              matlab.ui.control.Label
        Hours4                   matlab.ui.control.NumericEditField
        Hours3                   matlab.ui.control.NumericEditField
        Hours3Label              matlab.ui.control.Label
        Hours2Label              matlab.ui.control.Label
        Hours2                   matlab.ui.control.NumericEditField
        Hours1Label              matlab.ui.control.Label
        Hours1                   matlab.ui.control.NumericEditField
        DatePicker2              matlab.ui.control.DatePicker
        DateLabel2               matlab.ui.control.Label
        DateLabel1               matlab.ui.control.Label
        DatePicker1              matlab.ui.control.DatePicker
        Sublabel3                matlab.ui.control.Label
        Grid3A                   matlab.ui.container.GridLayout
        Process                  matlab.ui.control.EditField
        ProcessLabel             matlab.ui.control.Label
        IssueNumber              matlab.ui.control.NumericEditField
        IssueNumberLabel         matlab.ui.control.Label
        IssueYearLabel           matlab.ui.control.Label
        issueYear                matlab.ui.control.EditField
        issueType                matlab.ui.control.EditField
        issueTypeLabel           matlab.ui.control.Label
        issueClassLabel          matlab.ui.control.Label
        issueClass               matlab.ui.control.EditField
        issuePriorityLabel       matlab.ui.control.Label
        issuePriority            matlab.ui.control.EditField
        Image                    matlab.ui.control.Image
        Panel_13                 matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        Sublabel1                matlab.ui.control.Label
        ChannelLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        
        pyMod
        Login
        Password
        Teste
        
        ISSUE
        FinalReport = []

        Status = {'Rascunho', 'Aguardando Execução', 'Em andamento', 'Relatando', 'Relatada'}
        
    end
    
    methods (Access = private)
        
        function Fcn_Layout(app, Flag)
            
            switch Flag
                case 'On'
                    if ~strcmp(app.issueStatus.Value, 'Relatada')
                        set(app.CheckBox, 'Value', 1, 'Enable', 1)
                    else
                        set(app.CheckBox, 'Value', 0, 'Enable', 0)
                    end

                    app.Latitude.FontColor        = [0,0,0];
                    app.Longitude.FontColor       = [0,0,0];
                    app.Limit1.FontColor          = [0,0,0];
                    app.Limit2.FontColor          = [0,0,0];
                    app.EmissionsCount1.FontColor = [0,0,0];
                    app.EmissionsCount2.FontColor = [0,0,0];
                    app.EmissionsCount3.FontColor = [0,0,0];

                case 'Off'
                    set(app.CheckBox, 'Value',  0, 'Enable', 0)
                    app.ReportFile.Value = '';
                    app.Services.Value   = '';
                    app.Journal.Value    = '';
                    app.City.Value       = '';
                    
                    app.Latitude.FontColor        = [.94,.94,.94];
                    app.Longitude.FontColor       = [.94,.94,.94];
                    app.Limit1.FontColor          = [.94,.94,.94];
                    app.Limit2.FontColor          = [.94,.94,.94];
                    app.EmissionsCount1.FontColor = [.94,.94,.94];
                    app.EmissionsCount2.FontColor = [.94,.94,.94];
                    app.EmissionsCount3.FontColor = [.94,.94,.94];
            end
                        
        end
                
        
        function Fcn_infoReport(app, filename)

            try
                load(replace(filename, '.html', '.mat'), 'ReportProject');
                app.FinalReport = ReportProject;
                
                app.ReportFile.Value = filename;
                
                app.Latitude.Value   = app.FinalReport.Latitude;
                app.Longitude.Value  = app.FinalReport.Longitude;
                app.City.Value       = app.FinalReport.City';
                
                app.Limit1.Value     = app.FinalReport.F0 / 1e+6;
                app.Limit2.Value     = app.FinalReport.F1 / 1e+6;
                
                app.EmissionsCount1.Value = app.FinalReport.emissionsValue1;
                app.EmissionsCount2.Value = app.FinalReport.emissionsValue2;
                app.EmissionsCount3.Value = app.FinalReport.emissionsValue3;
                
                app.Services.Value = app.FinalReport.Services{1};
                for ii = 2:numel(app.FinalReport.Services)
                    app.Services.Value = sprintf('%s, %s', app.Services.Value{1}, app.FinalReport.Services{ii});
                end
                
                for ii = 1:height(app.FinalReport.tableJournal)
                    app.Journal.Value{ii} = jsonencode(app.FinalReport.tableJournal(ii,:));
                end

            catch ME
                report = getReport(ME);
                uialert(app.UIFigure, report, 'appAnálise', 'Interpreter','html');
            end
            
        end
        
        
        function Fcn_infoRedmine(app, issueInfo)
            
            if app.Teste; URL = sprintf('https://sistemashm.anatel.gov.br/fiscaliza/issues/%s', issueInfo.id);
            else;         URL = sprintf('https://sistemas.anatel.gov.br/fiscaliza/issues/%s',   issueInfo.id);
            end
            app.IssueNumber.Value = str2double(issueInfo.id);
            app.Button_Fiscaliza.Tag = URL;
            
            app.Sublabel1.Text = issueInfo.subject;
            app.Sublabel2.Text = sprintf('Inspeção relacionada à ação nº %d\n%s', issueInfo.id_ACAO, issueInfo.Atualizado);
            
            if ~isempty(issueInfo.Numero_Sei_do_Processo)
                app.Process.Value = issueInfo.Numero_Sei_do_Processo.numero;
                app.Button_SEI.Tag      = issueInfo.Numero_Sei_do_Processo.link_acesso;
            else
                app.Process.Value = '';
                app.Button_SEI.Tag      = '';
            end
            
            app.DatePicker1.BackgroundColor = [1,1,1];
            app.DatePicker2.BackgroundColor = [1,1,1];
            if ~isempty(issueInfo.start_date); app.DatePicker1.Value = datetime(issueInfo.start_date); end
            if ~isempty(issueInfo.due_date);   app.DatePicker2.Value = datetime(issueInfo.due_date);   end
            
            app.issueStatus.Value = issueInfo.status;
            if ismember(issueInfo.status, ["Relatada", "Conferida"])
                Fcn_relatedIssue(app)
                app.issueDesiredStatus.Items = {issueInfo.status};
            else
                idx = find(strcmp(issueInfo.status, app.Status));
                set(app.issueDesiredStatus, Items = app.Status(idx:end), Value = 'Relatando')
            end
            
            app.issuePriority.Value = issueInfo.priority;
            app.issueClass.Value    = issueInfo.Classe_da_Inspecao;
            app.issueYear.Value     = issueInfo.Ano;
            app.issueType.Value     = issueInfo.Tipo_de_Inspecao;
            
            if ~isempty(issueInfo.description)
                app.issueDescription.Value = issueInfo.description;
            elseif ~isempty(app.FinalReport)
                Fcn_defaultDescription(app)
            else
                app.issueDescription.Value = '';
            end
            
            if ~isempty(issueInfo.Horas_de_Preparacao);   app.Hours1.Value = str2double(issueInfo.Horas_de_Preparacao);   end
            if ~isempty(issueInfo.Horas_de_Deslocamento); app.Hours2.Value = str2double(issueInfo.Horas_de_Deslocamento); end
            if ~isempty(issueInfo.Horas_de_Execucao);     app.Hours3.Value = str2double(issueInfo.Horas_de_Execucao);     end
            if ~isempty(issueInfo.Horas_de_Conclusao);    app.Hours4.Value = str2double(issueInfo.Horas_de_Conclusao);    end
            
            if isfield(issueInfo, 'Relatorio_de_Monitoramento') && ~isempty(issueInfo.Relatorio_de_Monitoramento)
                set(app.Hyperlink2, 'Text', sprintf('SEI nº %s', issueInfo.Relatorio_de_Monitoramento.numero), ...
                                    'URL',  issueInfo.Relatorio_de_Monitoramento.link_acesso, ...
                                    'Visible', 1)
                set(app.CheckBox,   'Value', 0, 'Enable', 1, 'Text', 'Substituir relatório carregado no SEI.')
            elseif isempty(app.FinalReport)
                app.Hyperlink2.Visible = 0;
                set(app.CheckBox, 'Value', 0, 'Enable', 0, 'Text', 'Criar relatório no SEI.')
            else
                app.Hyperlink2.Visible = 0;
                set(app.CheckBox, 'Value', 1, 'Enable', 1, 'Text', 'Criar relatório no SEI.')
            end
            
            for ii = 1:numel(issueInfo.Users)
                uitreenode(app.WorkersTree, 'Text', issueInfo.Users{ii});
                if ismember(issueInfo.Users{ii}, issueInfo.Fiscais)
                    app.WorkersTree.CheckedNodes = [app.WorkersTree.CheckedNodes; app.WorkersTree.Children(ii)];
                end
            end
            
            app.Responsable.Items = [{''}, issueInfo.Users];
            if ~isempty(issueInfo.Fiscal_Responsavel)
                app.Responsable.Value = issueInfo.Fiscal_Responsavel;
            end
            
        end
        
        
        function Fcn_defaultDescription(app)
            
            app.issueDescription.Value = sprintf(['Monitoração da(s) faixa(s) %s, no(s) município(s) %s, no âmbito ' ...
                                             'da demanda de fiscalização registrada na Ação nº %d.'],           ...
                                             strjoin(app.FinalReport.Bands, ', '), strjoin(app.City.Value, ', '), app.ISSUE.id_ACAO);
            
        end
        
        
        function Fcn_relatedIssue(app)
            
            set(app.DatePicker1, 'Enable',   0, 'BackgroundColor', [.94,.94,.94])
            set(app.DatePicker2, 'Enable',   0, 'BackgroundColor', [.94,.94,.94])
            
            set(app.issueDescription, 'Editable', 0, 'BackgroundColor', [.8,.8,.8])
            set(app.Hours1,      'Editable', 0, 'BackgroundColor', [.94,.94,.94])
            set(app.Hours2,      'Editable', 0, 'BackgroundColor', [.94,.94,.94])
            set(app.Hours3,      'Editable', 0, 'BackgroundColor', [.94,.94,.94])
            set(app.Hours4,      'Editable', 0, 'BackgroundColor', [.94,.94,.94])
            
            set(app.WorkersTree,       'Enable',   0, 'BackgroundColor', [.94,.94,.94])
            set(app.Responsable, 'Enable',   0, 'BackgroundColor', [.94,.94,.94])
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, pyModule, USR, PWD, teste, issueInfo, issueNumber)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            app.pyMod    = pyModule;
            app.Login    = USR;
            app.Password = PWD;
            app.Teste    = teste;
            
            if app.Teste
                app.Title.Text = {'<b style="color: #a2142f; font-size: 24;">Fiscaliza</b><font style="font-size: 10;">HOMOLOGAÇÃO</font>'; '<font>Sistema de gestão da fiscalização da Agência</font>'; ' '};
            else
                app.Title.Text = {'<b style="color: #a2142f; font-size: 24;">Fiscaliza</b>'; '<font>Sistema de gestão da fiscalização da Agência</font>'; ' '};
            end

            app.ISSUE = issueInfo;
            
            if ~isempty(app.CallingApp.General.Report)
                Fcn_Layout(app, 'On')
                Fcn_infoReport(app, app.CallingApp.General.Report)
            else
                Fcn_Layout(app, 'Off')
            end
            Fcn_infoRedmine(app, issueInfo)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin3 = [];
            delete(app)
            
        end

        % Button pushed function: Button_Fiscaliza
        function Button_FiscalizaPushed(app, event)
            
            if ~isempty(app.Button_Fiscaliza.Tag)
                web(app.Button_Fiscaliza.Tag)
            end

        end

        % Button pushed function: Button_SEI
        function Button_SEIPushed(app, event)
            
            if ~isempty(app.Button_SEI.Tag)
                web(app.Button_SEI.Tag)
            end

        end

        % Value changed function: Button_ChangeIssue
        function Button_ChangeIssuePushed(app, event)
            
            if app.Button_ChangeIssue.Value
                set(app.IssueNumber, 'Editable', 1, 'BackgroundColor', [1,1,1])
                
                set(app.GridLayout21.Children, 'Enable', 0)
                app.Button_ChangeIssue.Enable = 1;
                
            else
                set(app.IssueNumber, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.GridLayout21.Children, 'Enable', 1)
            end

        end

        % Value changed function: IssueNumber
        function IssueNumberValueChanged(app, event)
            
            selection = uiconfirm(app.UIFigure, 'Confirma alteração da inspeção?', ...
                                  'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question');
            
            switch selection
                case 'OK'
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Aguardando resposta do Fiscaliza...');
                    try
                        issueInfo   = app.pyMod.detalhar_issue(pyargs('inspecao', num2str(app.IssueNumber.Value), ...
                                                                      'login', app.Login,                         ...
                                                                      'senha', app.Password,                      ...
                                                                      'teste', app.Teste));
                        
                        issueInfo   = Misc_py2matRedmine(issueInfo, 1);
                        Fcn_infoRedmine(app, issueInfo)
                    
                    catch ME
                        report = getReport(ME);
                        uialert(app.UIFigure, report, 'appAnálise', 'Interpreter','html');

                        app.IssueNumber.Value = event.PreviousValue;
                    end

                    delete(d)

                case 'Cancelar'
                    app.IssueNumber.Value = event.PreviousValue;
            end
            app.Button_ChangeIssue.Value = 0;
            Button_ChangeIssuePushed(app)

        end

        % Button pushed function: Button_Report
        function Button_openPushed(app, event)
            
            if ~strcmp(app.issueStatus.Value, 'Relatada')
                [filename, Path] = uigetfile({'Report_*.html', '(*.html)'}, ...
                                          'Selecione um arquivo', 'MultiSelect', 'off');
                figure(app.UIFigure)
                                
                if filename
                    File = fullfile(Path, filename);

                    % VALIDAÇÃO 1: EXISTÊNCIA DOS ARQUIVOS ACESSÓRIOS NOS
                    % FORMATOS ".MAT" E ".JSON"
                    Flag = [];
                    if ~isfile(replace(File, '.html', '.mat'));  Flag = 1;
                    end

                    if ~isfile(replace(File, '.html', '.json')); Flag = [Flag, 2];
                    end

                    if ~isempty(Flag)
                        msg = string(sprintf('<font style="font-size: 12;">Não encontrado(s) o(s) <b>arquivo(s) acessório(s)</b> ao projeto indicado(s) a seguir.</font>'));
                        if ismember(1, Flag); msg(end+1) = sprintf('<font style="font-size: 12;">• %s</font>', replace(filename, '.html', '.mat'));
                        end

                        if ismember(2, Flag); msg(end+1) = sprintf('<font style="font-size: 12;">• %s</font>',  replace(filename, '.html', '.json'));
                        end
                        
                        uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html')
                        return
                    end

                    % VALIDAÇÃO 2: O ARQUIVO NO FORMATO ".JSON" FOI ESCRITO 
                    % CORRETAMENTE.
                    try
                        jsondecode(fileread(replace(File, '.html', '.json')));
                    catch ME
                        report = getReport(ME);
                        uialert(app.UIFigure, report, 'appAnálise', 'Interpreter','html');
                        return
                    end

                    Fcn_Layout(app, 'On')
                    Fcn_infoReport(app, File)
                    
                    if isempty(strjoin(app.issueDescription.Value))
                        Fcn_defaultDescription(app)
                    end
                end
            else
                uialert(app.UIFigure, 'A inspeção já está no estado "Relatada".', 'appAnálise', 'Icon', 'warning')
            end

        end

        % Button pushed function: Button_Play
        function Button_PlayPushed(app, event)
            
            % VALIDAÇÃO 1: EXISTÊNCIA DO RELATÓRIO.
            if isempty(app.ReportFile.Value)
                uialert(app.UIFigure, 'Favor incluir relatório definitivo...', 'appAnálise', 'Icon', 'warning')
                return
            end

            % VALIDAÇÃO 2: PREENCHIMENTO DE CAMPOS OBRIGATÓRIOS.
            Hours = app.Hours1.Value + app.Hours2.Value + app.Hours3.Value + app.Hours4.Value;
            if isempty(strjoin(app.issueDescription.Value)) | ~Hours | isempty(app.WorkersTree.CheckedNodes) | ...
               isnat(app.DatePicker1.Value) | isnat(app.DatePicker2.Value) | isempty(app.Responsable.Value)
                uialert(app.UIFigure, 'Ao menos um dos campos obrigatórios está pendente de edição.', 'appAnálise', 'Icon', 'warning')
                return

            % VALIDAÇÃO 3: DATA FINAL DEVE SER MAIOR DO QUE INICIAL.
            elseif app.DatePicker1.Value > app.DatePicker2.Value
                uialert(app.UIFigure, 'A data limite deve ser posterior à previsão de início.', 'appAnálise', 'Icon', 'warning')
                return
            end
            
            % Início do processo...
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Em andamento a validação dos dados para submeter ao Fiscaliza.');
            
            workersList = {app.WorkersTree.CheckedNodes(1).Text};
            for ii = 2:numel(app.WorkersTree.CheckedNodes)
                workersList = [workersList, app.WorkersTree.CheckedNodes(ii).Text];
            end
            workersList = py.list(workersList);
            serviceList = py.list(app.FinalReport.Services');
            
            Cities = {};
            for ii = 1:numel(app.City.Value)
                Cities{ii} = sprintf('%s/%s', app.City.Value{ii}(end-1:end), app.City.Value{ii}(1:end-3));
            end
            Cities = py.list(Cities);
            
            tableJournal = py.list({jsonencode(app.FinalReport.tableJournal)});
            
            rawData = py.dict(pyargs('Classe_da_Inspecao',            'Técnica',                       ...
                                     'Tipo_de_Inspecao',              'Uso do Espectro - Monitoração', ...
                                     'description',                   strjoin(app.issueDescription.Value), ...
                                     'Fiscal_Responsavel',            app.Responsable.Value,           ...
                                     'Fiscais',                       workersList,                     ...
                                     'Html',                          app.ReportFile.Value,            ...
                                     'Gerar_Relatorio',               int8(app.CheckBox.Value),        ...
                                     'Frequencia_Inicial',            app.Limit1.Value,                ...
                                     'Unidade_da_Frequencia_Inicial', 'MHz',                           ...
                                     'Frequencia_Final',              app.Limit2.Value,                ...
                                     'Unidade_da_Frequencia_Final',   'MHz',                           ...
                                     'start_date',                    datestr(app.DatePicker1.Value, 'yyyy-mm-dd'), ...
                                     'due_date',                      datestr(app.DatePicker2.Value, 'yyyy-mm-dd'), ...
                                     'UF_Municipio',                  Cities,                           ...
                                     'Servicos_da_Inspecao',          serviceList,                      ...
                                     'Qnt_de_emissoes_na_faixa',      int32(app.EmissionsCount1.Value), ...
                                     'Emissoes_nao_autorizadas',      int32(10),                        ... % CAMPO JÁ EXCLUÍDO NO FISCALIZA HOMOLOGAÇÃO
                                     'Qtd_Licenciadas',               int32(app.EmissionsCount2.Value), ...
                                     'Qtd_Identificadas',             int32(app.EmissionsCount3.Value), ...
                                     'Horas_de_Preparacao',           int32(app.Hours1.Value),          ...
                                     'Horas_de_Deslocamento',         int32(app.Hours2.Value),          ...
                                     'Horas_de_Execucao',             int32(app.Hours3.Value),          ...
                                     'Horas_de_Conclusao',            int32(app.Hours4.Value),          ...
                                     'Latitude',                      round(app.Latitude.Value,  6),    ...
                                     'Longitude',                     round(app.Longitude.Value, 6),    ...
                                     'Uso_de_PF',                     'Não se aplica PF - uso apenas de formulários', ...
                                     'Acao_de_risco_a_vida_criada',   'Não',   ...
                                     'Impossibilidade_acesso_online', '0',     ...
                                     'Reservar_Instrumentos',         '0',     ...
                                     'Utilizou_algum_instrumento',    int8(0), ...
                                     'notes',                         tableJournal, ...
                                     'uploads',                       py.list({py.dict(pyargs('path',         replace(app.ReportFile.Value, 'html', 'json'), ...
                                                                                              'filename',     'Info.json',                                   ...
                                                                                              'description',  'Informações gerais acerca da fiscalização (sensor, período de observação, faixas monitoradas, emissões identificadas etc).', ...
                                                                                              'content_type', '.json'))})));
            
            try
%                 finalData = app.pyMod.validar_dicionario(pyargs('data_dict', rawData,            ...
%                                                                 'inspecao',  num2str(app.IssueNumber.Value), ...
%                                                                 'login',     app.Login,          ...
%                                                                 'senha',     app.Password,       ...
%                                                                 'teste',     app.Teste));
%                 
                d.Message = 'Em andamento a carga dos dados no Fiscaliza.';
                
                finalStatus   = app.issueDesiredStatus.Value;
                replaceReport = false;
                if app.CheckBox.Value && ~isempty(app.Hyperlink2.Text)
                    replaceReport = true;
                else
                    if ~isempty(app.Hyperlink2.Text); finalStatus = 'Relatada';
                    end
                end

                updateModule    = py.importlib.import_module('fiscaliza.update');
                py.importlib.reload(updateModule);

                
                updateModule.relatar_inspecao(pyargs('dados',    rawData,                      ...
                                                  'inspecao', num2str(app.IssueNumber.Value), ...
                                                  'login',    app.Login,          ...
                                                  'senha',    app.Password,       ...
                                                  'teste',    app.Teste,          ...
                                                  'parar_em', finalStatus,        ...
                                                  'substituir_relatorio', replaceReport));

                infoModule    = py.importlib.import_module('fiscaliza.info');
                py.importlib.reload(infoModule);

                
                d.Message = 'Em andamento a leitura dos novos valores dos campos...';
                issueInfo = infoModule.detalhar_issue(pyargs('issue', num2str(app.IssueNumber.Value), ...
                                                            'login', app.Login, 'senha', app.Password, 'teste', app.Teste));
                
                issueInfo = Misc_py2matRedmine(issueInfo, 0);
                Fcn_infoRedmine(app, issueInfo)
                
            catch ME
                report = getReport(ME);
                uialert(app.UIFigure, report, 'appAnálise', 'Interpreter','html');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Fiscaliza';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout17 = uigridlayout(app.UIFigure);
            app.Panel_13 = uipanel(app.GridLayout17);
            app.GridLayout2 = uigridlayout(app.Panel_13);
            app.ChannelLabel = uilabel(app.GridLayout2);
            app.ChannelLabel.Text = 'INSPEÇÃO';
            app.Sublabel1 = uilabel(app.GridLayout2);
            app.Sublabel1.Interpreter = 'html';
            app.Sublabel1.Text = '';
            app.Image = uiimage(app.GridLayout17);
            app.Image.ImageSource = 'Report_Icon6.png';
            app.Panel1 = uipanel(app.GridLayout17);
            app.Grid3 = uigridlayout(app.Panel1);
            app.Grid3A = uigridlayout(app.Grid3);
            app.issuePriority = uieditfield(app.Grid3A, 'text');
            app.issuePriority.Editable = 'off';
            app.issuePriorityLabel = uilabel(app.Grid3A);
            app.issuePriorityLabel.Text = 'Prioridade:';
            app.issueClass = uieditfield(app.Grid3A, 'text');
            app.issueClass.Editable = 'off';
            app.issueClassLabel = uilabel(app.Grid3A);
            app.issueClassLabel.Text = 'Classe:';
            app.issueTypeLabel = uilabel(app.Grid3A);
            app.issueTypeLabel.Text = 'Tipo:';
            app.issueType = uieditfield(app.Grid3A, 'text');
            app.issueType.Editable = 'off';
            app.issueYear = uieditfield(app.Grid3A, 'text');
            app.issueYear.Editable = 'off';
            app.IssueYearLabel = uilabel(app.Grid3A);
            app.IssueYearLabel.Text = 'Ano execução:';
            app.IssueNumberLabel = uilabel(app.Grid3A);
            app.IssueNumberLabel.Text = 'Inspeção:';
            app.IssueNumber = uieditfield(app.Grid3A, 'numeric');
            app.IssueNumber.Limits = [-1 Inf];
            app.IssueNumber.RoundFractionalValues = 'on';
            app.IssueNumber.ValueDisplayFormat = '%.0f';
            app.IssueNumber.ValueChangedFcn = createCallbackFcn(app, @IssueNumberValueChanged, true);
            app.IssueNumber.Editable = 'off';
            app.IssueNumber.Value = -1;
            app.ProcessLabel = uilabel(app.Grid3A);
            app.ProcessLabel.Text = 'Processo:';
            app.Process = uieditfield(app.Grid3A, 'text');
            app.Process.Editable = 'off';
            app.Grid3B = uigridlayout(app.Grid3);
            app.Sublabel3 = uilabel(app.Grid3B);
            app.Sublabel3.Text = 'Estimativas de horas:';
            app.DatePicker1 = uidatepicker(app.Grid3B);
            app.DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.DatePicker1.Editable = 'off';
            app.DateLabel1 = uilabel(app.Grid3B);
            app.DateLabel1.Text = 'Previsão de início:';
            app.DateLabel2 = uilabel(app.Grid3B);
            app.DateLabel2.Text = 'Data limite:';
            app.DatePicker2 = uidatepicker(app.Grid3B);
            app.DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.DatePicker2.Editable = 'off';
            app.Panel_14 = uipanel(app.Grid3B);
            app.GridLayout3 = uigridlayout(app.Panel_14);
            app.Hours1 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours1.Limits = [0 Inf];
            app.Hours1.RoundFractionalValues = 'on';
            app.Hours1.ValueDisplayFormat = '%.0f';
            app.Hours1Label = uilabel(app.GridLayout3);
            app.Hours1Label.Text = 'Preparação:';
            app.Hours2 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours2.Limits = [0 Inf];
            app.Hours2.RoundFractionalValues = 'on';
            app.Hours2.ValueDisplayFormat = '%.0f';
            app.Hours2Label = uilabel(app.GridLayout3);
            app.Hours2Label.Text = 'Deslocamento:';
            app.Hours3Label = uilabel(app.GridLayout3);
            app.Hours3Label.Text = 'Execução:';
            app.Hours3 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours3.Limits = [0 Inf];
            app.Hours3.RoundFractionalValues = 'on';
            app.Hours3.ValueDisplayFormat = '%.0f';
            app.Hours4 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours4.Limits = [0 Inf];
            app.Hours4.RoundFractionalValues = 'on';
            app.Hours4.ValueDisplayFormat = '%.0f';
            app.Hours4Label = uilabel(app.GridLayout3);
            app.Hours4Label.Text = 'Conclusão:';
            app.Grid3C = uigridlayout(app.Grid3);
            app.WorkersTreeLabel = uilabel(app.Grid3C);
            app.WorkersTreeLabel.Text = 'Fiscais envolvidos na fiscalização:';
            app.Responsable = uidropdown(app.Grid3C);
            app.Responsable.Items = {};
            app.Responsable.Value = {};
            app.ResponsableLabel = uilabel(app.Grid3C);
            app.ResponsableLabel.Text = 'Fiscal responsável:';
            app.WorkersTree = uitree(app.Grid3C, 'checkbox');
            app.GridLayout18 = uigridlayout(app.Grid3);
            app.issueDesiredStatusLabel = uilabel(app.GridLayout18);
            app.issueDesiredStatusLabel.Text = {'Situação '; 'pretendida:'};
            app.issueDesiredStatus = uidropdown(app.GridLayout18);
            app.issueDesiredStatus.Items = {'Rascunho', 'Aguardando Execução', 'Em andamento', 'Relatando', 'Relatada'};
            app.issueDesiredStatus.Value = 'Aguardando Execução';
            app.issueStatusLabel = uilabel(app.GridLayout18);
            app.issueStatusLabel.Text = {'Situação '; 'atual:'};
            app.issueStatus = uieditfield(app.GridLayout18, 'text');
            app.issueStatus.Editable = 'off';
            app.issueDescription = uitextarea(app.GridLayout18);
            app.issueDescriptionLabel = uilabel(app.GridLayout18);
            app.issueDescriptionLabel.Text = 'Descrição da inspeção:';
            app.Label2 = uilabel(app.GridLayout17);
            app.Label2.Text = 'ASPECTOS GERAIS:';
            app.Label3 = uilabel(app.GridLayout17);
            app.Label3.Text = 'ASPECTOS TÉCNICOS:';
            app.Panel_4 = uipanel(app.GridLayout17);
            app.Grid4 = uigridlayout(app.Panel_4);
            app.Grid4B = uigridlayout(app.Grid4);
            app.BandLimits = uilabel(app.Grid4B);
            app.BandLimits.Text = 'Limites da(s) faixa(s) monitorada(s):';
            app.Panel_10 = uipanel(app.Grid4B);
            app.GridLayout14 = uigridlayout(app.Panel_10);
            app.Limit1Label = uilabel(app.GridLayout14);
            app.Limit1Label.Text = 'Inferior (MHz):';
            app.Limit1 = uieditfield(app.GridLayout14, 'numeric');
            app.Limit1.ValueDisplayFormat = '%.6f';
            app.Limit1.Editable = 'off';
            app.Limit2Label = uilabel(app.GridLayout14);
            app.Limit2Label.Text = 'Superior (MHz):';
            app.Limit2 = uieditfield(app.GridLayout14, 'numeric');
            app.Limit2.ValueDisplayFormat = '%.6f';
            app.Limit2.Editable = 'off';
            app.Limit2.Value = 6000;
            app.EmissionsLabel = uilabel(app.Grid4B);
            app.EmissionsLabel.Text = 'Emissões:';
            app.Panel_15 = uipanel(app.Grid4B);
            app.GridLayout19 = uigridlayout(app.Panel_15);
            app.EmissionsCount1 = uieditfield(app.GridLayout19, 'numeric');
            app.EmissionsCount1.Editable = 'off';
            app.EmissionsCount1Label = uilabel(app.GridLayout19);
            app.EmissionsCount1Label.Text = 'Identificadas:';
            app.EmissionsCount2Label = uilabel(app.GridLayout19);
            app.EmissionsCount2Label.Text = 'Licenciadas:';
            app.EmissionsCount2 = uieditfield(app.GridLayout19, 'numeric');
            app.EmissionsCount2.Editable = 'off';
            app.EmissionsCount3Label = uilabel(app.GridLayout19);
            app.EmissionsCount3Label.Text = 'Identificadas:';
            app.EmissionsCount3 = uieditfield(app.GridLayout19, 'numeric');
            app.EmissionsCount3.Editable = 'off';
            app.Grid4A = uigridlayout(app.Grid4);
            app.Sublabel7 = uilabel(app.Grid4A);
            app.Sublabel7.Text = 'Coordenadas geográficas:';
            app.Panel_9 = uipanel(app.Grid4A);
            app.GridLayout13 = uigridlayout(app.Panel_9);
            app.LatitudeLabel = uilabel(app.GridLayout13);
            app.LatitudeLabel.Text = 'Latitude:';
            app.Latitude = uieditfield(app.GridLayout13, 'numeric');
            app.Latitude.ValueDisplayFormat = '%.6f';
            app.Latitude.Editable = 'off';
            app.Latitude.Value = -1;
            app.LongitudeLabel = uilabel(app.GridLayout13);
            app.LongitudeLabel.Text = 'Longitude:';
            app.Longitude = uieditfield(app.GridLayout13, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.6f';
            app.Longitude.Editable = 'off';
            app.Longitude.Value = -1;
            app.City = uitextarea(app.Grid4A);
            app.City.Editable = 'off';
            app.CityLabel = uilabel(app.Grid4A);
            app.CityLabel.Text = 'Município(s):';
            app.Hyperlink2 = uihyperlink(app.Grid4);
            app.Hyperlink2.Visible = 'off';
            app.Hyperlink2.Text = '';
            app.ServicesLabel = uilabel(app.Grid4);
            app.ServicesLabel.Text = 'Serviço(s) relacionado(s) à(s) faixa(s) monitorada(s) ou emissões identificadas:';
            app.Services = uitextarea(app.Grid4);
            app.Services.Editable = 'off';
            app.CheckBox = uicheckbox(app.Grid4);
            app.CheckBox.Text = 'Criar relatório no SEI.';
            app.ReportFileLabel = uilabel(app.Grid4);
            app.ReportFileLabel.Text = {'Relatório de monitoração:'; '(Tipologia SEI: "Relatório de Atividades")'};
            app.ReportFile = uieditfield(app.Grid4, 'text');
            app.ReportFile.Editable = 'off';
            app.GridLayout20 = uigridlayout(app.Grid4);
            app.Journal = uitextarea(app.GridLayout20);
            app.Journal.Editable = 'off';
            app.JournalLabel = uilabel(app.GridLayout20);
            app.JournalLabel.Text = 'Informações a inserir no histórico da inspeção sob a forma de tabela:';
            app.Sublabel2 = uilabel(app.GridLayout17);
            app.Sublabel2.Text = '';
            app.Title = uilabel(app.GridLayout17);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #a2142f; font-size: 24;">Fiscaliza</b><font style="font-size: 10;">HOMOLOGAÇÃO</font>'; '<font>Sistema de gestão da fiscalização da Agência</font>'; ' '};
            app.GridLayout21 = uigridlayout(app.GridLayout17);
            app.Button_Fiscaliza = uibutton(app.GridLayout21, 'push');
            app.Button_Fiscaliza.ButtonPushedFcn = createCallbackFcn(app, @Button_FiscalizaPushed, true);
            app.Button_Fiscaliza.Icon = 'Report_Icon6.png';
            app.Button_Fiscaliza.Tooltip = {''};
            app.Button_Fiscaliza.Text = '';
            app.Button_SEI = uibutton(app.GridLayout21, 'push');
            app.Button_SEI.ButtonPushedFcn = createCallbackFcn(app, @Button_SEIPushed, true);
            app.Button_SEI.Icon = 'SEI.png';
            app.Button_SEI.Tooltip = {''};
            app.Button_SEI.Text = '';
            app.Button_ChangeIssue = uibutton(app.GridLayout21, 'state');
            app.Button_ChangeIssue.ValueChangedFcn = createCallbackFcn(app, @Button_ChangeIssuePushed, true);
            app.Button_ChangeIssue.Tooltip = {'Trocar inspeção'};
            app.Button_ChangeIssue.Icon = 'mainMenu_Edit.png';
            app.Button_ChangeIssue.Text = '';
            app.Separator1 = uiimage(app.GridLayout21);
            app.Separator1.ImageSource = 'Line.png';
            app.Separator2 = uiimage(app.GridLayout21);
            app.Separator2.ImageSource = 'Line.png';
            app.Button_Report = uibutton(app.GridLayout21, 'push');
            app.Button_Report.ButtonPushedFcn = createCallbackFcn(app, @Button_openPushed, true);
            app.Button_Report.Icon = 'mainMenu_File.png';
            app.Button_Report.Tooltip = {'Abrir relatório'};
            app.Button_Report.Text = '';
            app.Button_Play = uibutton(app.GridLayout21, 'push');
            app.Button_Play.ButtonPushedFcn = createCallbackFcn(app, @Button_PlayPushed, true);
            app.Button_Play.Icon = 'White_Play.png';
            app.Button_Play.Text = 'Iniciar relato';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winFiscaliza(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end