classdef winMaskDraw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Grid                matlab.ui.container.GridLayout
        Panel_3             matlab.ui.container.Panel
        limitsGrid          matlab.ui.container.GridLayout
        Plot                matlab.ui.control.CheckBox
        BandsList           matlab.ui.control.TextArea
        BandsListLabel      matlab.ui.control.Label
        bandLabel_3         matlab.ui.control.Label
        Panel_2             matlab.ui.container.Panel
        GridLayout5         matlab.ui.container.GridLayout
        MaskFile1           matlab.ui.control.EditField
        MaskFile1Label      matlab.ui.control.Label
        ThreadList          matlab.ui.control.TextArea
        Thread              matlab.ui.control.DropDown
        ThreadLabel         matlab.ui.control.Label
        GridLayout4         matlab.ui.container.GridLayout
        THR1                matlab.ui.control.Spinner
        THR1Label           matlab.ui.control.Label
        AutoMeasure         matlab.ui.control.CheckBox
        bandPanel           matlab.ui.container.Panel
        bandGrid            matlab.ui.container.GridLayout
        xLim2               matlab.ui.control.Spinner
        xLimLabel           matlab.ui.control.Label
        xLim1               matlab.ui.control.Spinner
        bandLabel           matlab.ui.control.Label
        maskPanel           matlab.ui.container.Panel
        maskGrid            matlab.ui.container.GridLayout
        PlaybackOnly        matlab.ui.control.CheckBox
        GridLayout2         matlab.ui.container.GridLayout
        Tree1Refresh        matlab.ui.control.Image
        Tree1Label          matlab.ui.control.Label
        THR2Grid            matlab.ui.container.GridLayout
        THR2                matlab.ui.control.Spinner
        THR2Label           matlab.ui.control.Label
        newMaskLabel        matlab.ui.control.Label
        refMask             matlab.ui.control.TextArea
        refMaskLabel        matlab.ui.control.Label
        newMask             matlab.ui.control.TextArea
        Tree2               matlab.ui.container.Tree
        Tree2Label          matlab.ui.control.Label
        Tree1               matlab.ui.container.Tree
        limitsPanelLabel_2  matlab.ui.control.Label
        limitsPanelLabel    matlab.ui.control.Label
        warnGrid            matlab.ui.container.GridLayout
        warnImage           matlab.ui.control.Image
        Title               matlab.ui.control.Label
        Image               matlab.ui.control.Image
        EffGrid             matlab.ui.container.GridLayout
        Eff_newMaskPanel    matlab.ui.container.Panel
        Eff_newMaskGrid     matlab.ui.container.GridLayout
        Eff_newMask         matlab.ui.control.Label
        Eff_newMaskLabel    matlab.ui.control.Label
        Eff_oldMaskPanel    matlab.ui.container.Panel
        Eff_oldMaskGrid     matlab.ui.container.GridLayout
        Eff_refMask         matlab.ui.control.Label
        Eff_refMaskLabel    matlab.ui.control.Label
        ButtonGrid          matlab.ui.container.GridLayout
        Separator1          matlab.ui.control.Image
        Playback_Toolbar    matlab.ui.container.GridLayout
        Separator2_2        matlab.ui.control.Image
        Plot_WaterFall      matlab.ui.control.Button
        Plot_MaxHold        matlab.ui.control.Button
        Plot_MeanHold       matlab.ui.control.Button
        Plot_MinHold        matlab.ui.control.Button
        Separator3          matlab.ui.control.Image
        Separator2          matlab.ui.control.Image
        Plot_Interaction    matlab.ui.control.Button
        Plot_RestoreView    matlab.ui.control.Button
        Button_open         matlab.ui.control.Button
        Button_edit         matlab.ui.control.StateButton
        Button_refresh      matlab.ui.control.Button
        Button_save         matlab.ui.control.Button
        Button_info         matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        CallingApp

        RootFolder
        UserPath

        axes1
        roiMask
        
        Index
        refMaskInfo
        newMaskInfo

        Bands
        emissionTable
        unmaskedTable

        Mode

    end
        
    
    methods (Access = private)

        function Fcn1_MaskFiles(app, filename1, filename2)
            
            arguments
                app       winMaskDraw
                filename1 char = fullfile(app.RootFolder, 'DataBase', 'mask.csv')
                filename2 char = fullfile(app.RootFolder, 'DataBase', 'maskBands.csv')
            end

            try
                % MÁSCARA REFERÊNCIA
                app.refMask.Value = fileread(filename1);
                app.refMaskInfo   = Fcn1_Aux1_MaskInfo(app, app.refMask.Value);                

                % FAIXA SOB ANÁLISE
                app.Bands = readtable(filename2);
                app.Bands = sortrows(app.Bands, 'FreqStart');

                app.BandsList.Value = '';
                for ii = 1:height(app.Bands)
                    app.BandsList.Value{ii} = sprintf('Faixa %d: %.3f - %.3f MHz', ii, app.Bands.FreqStart(ii), app.Bands.FreqStop(ii));
                end

                % ARQUIVO
                app.MaskFile1.Value = filename1;

            catch ME
                Misc_uialertCreation(app, 'error', report(ME))
            end

        end


        function MaskInfo = Fcn1_Aux1_MaskInfo(app, maskText)
                
            MaskInfo.Table           = struct2table(regexp(strjoin(maskText, '\n'), '(?<FreqStart>\d*),(?<FreqStop>\d*),(?<THR>[-]{0,1}\d*)', 'names'));
            MaskInfo.Table.FreqStart = str2double(MaskInfo.Table.FreqStart) / 1e+3;
            MaskInfo.Table.FreqStop  = str2double(MaskInfo.Table.FreqStop)  / 1e+3;
            MaskInfo.Table.THR       = str2double(MaskInfo.Table.THR);

            MaskInfo.THR       = MaskInfo.Table.THR;
            MaskInfo.FreqStart = MaskInfo.Table.FreqStart(1);
            MaskInfo.FreqStop  = MaskInfo.Table.FreqStop(end);

            MaskInfo.unmasked  = table('Size', [height(MaskInfo.Table)-1, 3],         ...
                                       'VariableTypes', {'double', 'double', 'cell'}, ...
                                       'VariableNames', {'Frequency', 'BW', 'Source'});

            MaskInfo.unmasked.BW(:)        = MaskInfo.Table.FreqStart(2:end)  - MaskInfo.Table.FreqStop(1:end-1);
            MaskInfo.unmasked.Frequency(:) = MaskInfo.Table.FreqStop(1:end-1) + MaskInfo.unmasked.BW/2;
            MaskInfo.unmasked.Source(:)    = {'refMask'};

        end


        function Fcn2_infoPanel(app)
    
            % FLUXOS DE DADOS            
            Items = repmat({''}, numel(app.Index), 1);
            for ii = 1:numel(app.Index)
                Items{ii} = sprintf('ThreadID %.0f: %.3f - %.3f MHz', app.CallingApp.specData(app.Index(ii)).ThreadID,                  ...
                                                                      app.CallingApp.specData(app.Index(ii)).MetaData.FreqStart / 1e+6, ...
                                                                      app.CallingApp.specData(app.Index(ii)).MetaData.FreqStop  / 1e+6);
            end
            app.Thread.Items     = Items;
            app.ThreadList.Value = Items;

            % LIMITES
            app.xLim1.Value     = min(app.refMaskInfo.FreqStart, app.Bands.FreqStart(1));
            app.xLim1.Limits(2) = app.Bands.FreqStart(1);

            app.xLim2.Value     = max(app.refMaskInfo.FreqStop, app.Bands.FreqStop(end));
            app.xLim2.Limits(1) = app.Bands.FreqStop(end);
            
            % THRESHOLD
            app.THR1Label.Text = replace(app.THR1Label.Text, 'dBm', app.CallingApp.specData(app.Index(1)).MetaData.metaString{1});
            app.THR1.Value     = app.refMaskInfo.THR(1);

        end


        function Fcn3_treePanel(app)
            
            % EMISSÕES IDENTIFICADAS NO MODO "PLAYBACK" E, OPCIONALMENTE, AS 
            % EMISSÕES DESMASCARADAS DA MÁSCARA DE REFERÊNCIA
            Fcn3_Aux1_emissionTable(app)
            Fcn3_Aux2_Tree1Building(app)
            
            % EMISSÕES DESMASCARADAS
            Fcn3_Aux3_unmaskedTable(app)
            Fcn3_Aux4_Tree2Building(app)
            
            % MÁSCARA EM EDIÇÃO (FORMA TEXTUAL)
            app.newMask.Value = Fcn3_Aux5_maskPrint(app);
            app.newMaskInfo   = Fcn1_Aux1_MaskInfo(app, app.newMask.Value);

        end            


        function Fcn3_Aux1_emissionTable(app)

            app.emissionTable = table('Size', [0, 3], ...
                                      'VariableTypes', {'double', 'double', 'cell'}, ...
                                      'VariableNames', {'Frequency', 'BW', 'Source'});

            for ii = app.Index
                if ~isempty(app.CallingApp.specData(ii).Emissions)
                    HH = height(app.CallingApp.specData(ii).Emissions);

                    app.emissionTable(end+1:end+HH,:) = table(round(app.CallingApp.specData(ii).Emissions.Frequency, 3), ...
                                                              app.CallingApp.specData(ii).Emissions.BW / 1e+3, ...
                                                              repmat({'Playback'}, HH, 1));
                end
            end

            if ~app.PlaybackOnly.Value
                app.emissionTable = [app.emissionTable; app.refMaskInfo.unmasked];
            end

            app.emissionTable  = sortrows(app.emissionTable, 'Frequency');

        end


        function Fcn3_Aux2_Tree1Building(app)

            if ~isempty(app.Tree1.Children)
                removeStyle(app.Tree1)
                delete(app.Tree1.Children)
            end

            s = uistyle('FontColor', [.5,.5,.5]);

            for ii = 1:height(app.emissionTable)
                uitreenode(app.Tree1, 'Text', sprintf('%d: %.3f MHz @ %.3f kHz',       ...
                                                      ii,                              ...
                                                      app.emissionTable.Frequency(ii), ...
                                                      app.emissionTable.BW(ii) * 1e+3));
                
                if app.emissionTable.Source{ii} == "refMask"
                    addStyle(app.Tree1, s, 'node', app.Tree1.Children(ii))
                end
            end
            
        end


        function Fcn3_Aux3_unmaskedTable(app)

            app.unmaskedTable = app.emissionTable(:,1:2);
            app.unmaskedTable.THR(:) = app.THR1.Value;

            NN = height(app.unmaskedTable);
            for ii = NN:-1:2
                emission1_F0 = app.unmaskedTable.Frequency(ii-1) - app.unmaskedTable.BW(ii-1)/2;
                emission1_F1 = app.unmaskedTable.Frequency(ii-1) + app.unmaskedTable.BW(ii-1)/2;

                emission2_F0 = app.unmaskedTable.Frequency(ii) - app.unmaskedTable.BW(ii)/2;
                emission2_F1 = app.unmaskedTable.Frequency(ii) + app.unmaskedTable.BW(ii)/2;

                if emission1_F1 >=  emission2_F0
                    unmasked_F0  = min(emission1_F0,  emission2_F0);
                    unmasked_F1  = max(emission1_F1,  emission2_F1);

                    unmasked_Freq = mean([unmasked_F0, unmasked_F1]);
                    unmasked_BW   = diff([unmasked_F0, unmasked_F1]);
                    unmasked_THR  = max(app.unmaskedTable.THR(ii-1:ii));

                    app.unmaskedTable(ii-1,:) = {unmasked_Freq, unmasked_BW, unmasked_THR};
                    app.unmaskedTable(ii,  :) = [];
                end
            end

            app.unmaskedTable.FreqStart = app.unmaskedTable.Frequency - app.unmaskedTable.BW/2;
            app.unmaskedTable.FreqStop  = app.unmaskedTable.Frequency + app.unmaskedTable.BW/2;

        end


        function Fcn3_Aux4_Tree2Building(app)

            if ~isempty(app.Tree2.Children)
                delete(app.Tree2.Children)
            end

            Unit = app.CallingApp.specData(app.Index(1)).MetaData.metaString{1};
            
            for ii = 1:height(app.unmaskedTable)
               uitreenode(app.Tree2, 'Text', sprintf('%d: %.3f MHz @ %.3f kHz  Ⱶ  %d %s', ... % Algorismos: ∆ Ʌ Ո Ⱶ
                                                     ii,                               ...
                                                     app.unmaskedTable.Frequency(ii),  ...
                                                     app.unmaskedTable.BW(ii) * 1e+3,  ...
                                                     app.unmaskedTable.THR(ii),        ...
                                                     Unit),                            ...
                                      'NodeData', ii);
            end

            ButtonPushed_edit(app)
            
        end


        function maskText = Fcn3_Aux5_maskPrint(app)

            maskText  = sprintf('Start(kHz),Stop(kHz),Threshold');

            tempBands = app.Bands;
            tempBands.FreqStart(1)  = app.xLim1.Value;
            tempBands.FreqStop(end) = app.xLim2.Value;

            for ii = 1:height(tempBands)
                F0_band = tempBands.FreqStart(ii);
                F1_band = tempBands.FreqStop(ii);

                tempTable = app.unmaskedTable;
                tempTable.RelPos_F0 = (app.unmaskedTable.FreqStop  >= F0_band);
                tempTable.RelPos_F1 = (app.unmaskedTable.FreqStart <= F1_band);

                tempTable.Position  = tempTable.RelPos_F0 & tempTable.RelPos_F1;

                idx = find(tempTable.Position)';
                if isempty(idx)
                    maskText = sprintf('%s\n%.0f,%.0f,%.0f', maskText, F0_band * 1e+3, F1_band * 1e+3, app.THR1.Value);

                else
                    for jj = idx
                        F0_unmasked  = tempTable.FreqStart(jj);
                        F1_unmasked  = tempTable.FreqStop(jj);
                        THR_unmasked = tempTable.THR(jj);
    
                        if (F0_unmasked <= F0_band) && (F1_unmasked >= F1_band)
                            break
    
                        elseif F0_unmasked <= F0_band
                            F0_band = F1_unmasked;
                            if jj == idx(end)
                                maskText = sprintf('%s\n%.0f,%.0f,%.0f', maskText, F0_band * 1e+3, F1_band * 1e+3, THR_unmasked);
                            end
    
                        elseif F1_unmasked >= F1_band
                            maskText = sprintf('%s\n%.0f,%.0f,%.0f', maskText, F0_band * 1e+3, F0_unmasked * 1e+3, app.THR1.Value);
    
                        else
                            maskText = sprintf('%s\n%.0f,%.0f,%.0f', maskText, F0_band*1e+3, F0_unmasked*1e+3, app.THR1.Value);
                            F0_band  = F1_unmasked;

                            if jj == idx(end)
                                maskText = sprintf('%s\n%.0f,%.0f,%.0f', maskText, F1_unmasked * 1e+3, F1_band * 1e+3, THR_unmasked);
                            end
                        end
                    end
                end
            end

        end


        function Fcn4_Efficiency(app, Flag)

            if app.AutoMeasure.Value
                switch Flag
                    case 'both'
                        app.Eff_refMask.Text = Fcn4_Aux1_main(app, app.refMaskInfo);
                        app.Eff_newMask.Text = Fcn4_Aux1_main(app, app.newMaskInfo);
    
                    case 'newMask'
                        app.Eff_newMask.Text = Fcn4_Aux1_main(app, app.newMaskInfo);
                end

            else
                app.warnImage.Visible = 1;
            end

        end
        
        
        function eff = Fcn4_Aux1_main(app, MaskInfo)

            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
            d.Message = '<font style="font-size:12;">Em andamento aferição da efetivadade da máscara espectral...</font>';

            idx = find(strcmp(app.Thread.Items, app.Thread.Value), 1);
            idx = app.Index(idx);

            refMaskArray = Fcn4_Aux2_maskArray(app, idx, MaskInfo);
            refOCCArray  = zeros(app.CallingApp.specData(idx).MetaData.DataPoints, app.CallingApp.specData(idx).Samples, 'uint8');
            
            refOCCArray(bsxfun(@gt, app.CallingApp.specData(idx).Data{2}, refMaskArray')) = 1;

            eff = 1 - sum(any(refOCCArray)) / app.CallingApp.specData(idx).Samples;
            eff = sprintf('%.1f %%', 100*eff);

            delete(d)
                      
        end


        function MaskArray = Fcn4_Aux2_maskArray(app, idx, MaskInfo)
            
            % Limitar igual a 1000 (inatingível)
            MaskArray = ones(1, app.CallingApp.specData(idx).MetaData.DataPoints) * 1e+3;

            freq = linspace(app.CallingApp.specData(idx).MetaData.FreqStart /1e+6, ...
                            app.CallingApp.specData(idx).MetaData.FreqStop / 1e+6, ...
                            app.CallingApp.specData(idx).MetaData.DataPoints);
            
            MaskTable = Fcn4_Aux3_maskTable(app, MaskInfo);
            
            % Subtituição do limiar inatingível pelo threshold da faixa
            for ii = 1:height(MaskTable)
                MaskArray(freq >= MaskTable(ii,1) & freq <= MaskTable(ii,2)) = MaskTable(ii,3);
            end             
             
        end


        function MaskTable = Fcn4_Aux3_maskTable(app, MaskInfo)
            
            if isempty(MaskInfo.unmasked)
                if isempty(MaskInfo.THR)
                    MaskTable = [];
                else
                    MaskTable = [MaskInfo.FreqStart, MaskInfo.FreqStop, MaskInfo.THR];
                end
                
            else
                FreqStart = [MaskInfo.FreqStart; MaskInfo.unmasked.Frequency + ceil(MaskInfo.unmasked.BW/2)];
                FreqStop  = [MaskInfo.unmasked.Frequency - ceil(MaskInfo.unmasked.BW/2); MaskInfo.FreqStop];
                MaskTable = [FreqStart, FreqStop, MaskInfo.THR];
            end
            
        end


        function Misc_startup(app)

            Fcn3_treePanel(app)

            if ~app.AutoMeasure.Value
                app.AutoMeasure.Value = 1;
                Fcn4_Efficiency(app, 'both')
                
                app.AutoMeasure.Value = 0;

            else
                Fcn4_Efficiency(app, 'both')
            end

        end


        function Misc_roiCallback(app, src, event)
            
            switch(event.EventName)
                case{'MovingROI'}
                    disableDefaultInteractivity(app.axes1)
                    
                case{'ROIMoved'}
                    enableDefaultInteractivity(app.axes1)

                    idx1    = event.Source.UserData;
                    newTHR = round(event.CurrentPosition(1,2));

                    app.newMask.Value{idx1+1} = replace(app.newMask.Value{idx1+1}, sprintf(',%.0f', event.PreviousPosition(1,2)), sprintf(',%.0f', newTHR));
                    event.Source.Position    = [event.PreviousPosition(:,1), repmat(newTHR, 2, 1)];

                    try
                        if idx1 == 1
                            app.newMask.Value{2} = replace(app.newMask.Value{2}, sprintf(',%.0f', event.PreviousPosition(1,2)), sprintf(',%.0f', newTHR));
                            app.newMaskInfo      = Fcn1_Aux1_MaskInfo(app, app.newMask.Value);

                        else
                            Frequency = round(mean([app.newMaskInfo.Table.FreqStart(idx1), app.newMaskInfo.Table.FreqStop(idx1-1)]), 3);
                            idx2 = find(app.unmaskedTable.Frequency == Frequency, 1);

                            if ~isempty(idx2)
                                app.unmaskedTable.THR(idx2) = newTHR;
    
                                Fcn3_Aux4_Tree2Building(app)
    
                                app.newMask.Value = Fcn3_Aux5_maskPrint(app);
                                app.newMaskInfo   = Fcn1_Aux1_MaskInfo(app, app.newMask.Value);

                            else
                                'PASSOU POR AQUI!!!'
                            end
                        end

                        Fcn4_Efficiency(app, 'newMask')
                    catch ME
                        ME
                    end
            end
            
        end


        function Misc_updateTHR(app, idx, newTHR)

            Unit = app.CallingApp.specData(app.Index(1)).MetaData.metaString{1};


            app.unmaskedTable.THR(idx)   = newTHR;
            app.Tree2.Children(idx).Text = sprintf('%d: %.3f MHz @ %.3f kHz  Ⱶ  %d %s', ...
                                                     idx,                               ...
                                                     app.unmaskedTable.Frequency(idx),  ...
                                                     app.unmaskedTable.BW(idx) * 1e+3,  ...
                                                     newTHR, Unit);

        end


        function Misc_EditButton(app)

            if app.Button_edit.Value
                set(app.Button_edit, 'Enable', 0, 'Value', 0)
                ButtonPushed_edit(app)
            end
            
        end


        function Misc_Plot(app)

            delete(findall(groot, 'Tag', 'roiMask'))

            for ii = 1:height(app.newMaskInfo.Table)
                FreqStart = app.newMaskInfo.Table.FreqStart(ii);
                FreqStop  = app.newMaskInfo.Table.FreqStop(ii);
                Threshold = app.newMaskInfo.Table.THR(ii);

                app.roiMask(ii) = drawline(app.axes1, 'Color', 'red', 'InteractionsAllowed', 'translate',      ...
                                                      'LineWidth', 1, 'MarkerSize', 4,                         ...
                                                      'Position', [FreqStart, Threshold; FreqStop, Threshold], ...
                                                      'UserData', ii, 'Tag', 'roiMask');

                addlistener(app.roiMask(ii), 'MovingROI', @app.Misc_roiCallback);
                addlistener(app.roiMask(ii), 'ROIMoved',  @app.Misc_roiCallback);
            end

            app.axes1.Parent.Visible = 1;
            figure(app.axes1.Parent)

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', fullfile(app.RootFolder, 'Icons', 'Toolbar_Icon3.png'))
            end

        end        
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);   
           
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            app.UserPath   = app.CallingApp.menu_userPath.Value;
            app.axes1      = app.CallingApp.axes1;
            app.Index      = idx;

            Fcn1_MaskFiles(app)
            Fcn2_infoPanel(app)
            Misc_startup(app)

            delete(findall(groot, 'Tag', 'roiMask'))

        end

        % Close request function: UIFigure
        function closeRequest(app, event)

            app.CallingApp.auxWin4 = [];
            delete(findall(groot, 'Tag', 'roiMask'))

            delete(app)
            
        end

        % Button pushed function: Button_info
        function ButtonPushed_info(app, event)
            
            msg = fileread(fullfile(app.RootFolder, 'DataBase', 'MaskDraw_info.txt'));
            Misc_uialertCreation(app, 'info', msg)

        end

        % Value changed function: Button_edit
        function ButtonPushed_edit(app, event)
            
            if app.Button_edit.Value
                set(app.THR2Grid.Children, 'Enable', 1)
            else
                set(app.THR2Grid.Children, 'Enable', 0)
            end

        end

        % Button pushed function: Button_save
        function ButtonPushed_save(app, event)
            
            [Filename, FilePath] = uiputfile({'*.csv',    '(*.csv)'}, ...
                                              'Selecione a pasta e o nome do arquivo', fullfile(app.UserPath, 'mask.csv'));
            figure(app.UIFigure)
    
            if Filename
                fileID = fopen(fullfile(FilePath, Filename), 'w');
                fprintf(fileID, '%s', strjoin(app.newMask.Value, '\n'));
                fclose(fileID);

                msg = sprintf('Criado arquivo %s.', Filename);
                Misc_uialertCreation(app, 'info', msg)
            end
            
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            
            idx = app.Tree2.SelectedNodes.NodeData;
            app.THR2.Value = app.unmaskedTable.THR(idx);

            app.Button_edit.Enable = 1;

        end

        % Callback function: Button_open, Button_refresh, PlaybackOnly, 
        % THR1, THR2, Thread, Tree1Refresh, xLim1, xLim2
        function refreshCallback(app, event)
            
            switch event.Source
                case app.Button_open
                    [Filename, Filepath] = uigetfile({'*.csv', '(*.csv)'}, ...
                                                      'Selecione um arquivo de máscara espectral', 'MultiSelect', 'off');
                    figure(app.UIFigure)
                    
                    if Filename
                        d = uiprogressdlg(app.UIFigure, "Indeterminate", "on");
                        d.Message = '<font style="font-size:12;">Em andamento...</font>';
                        
                        Fcn1_MaskFiles(app, fullfile(Filepath, Filename))
                        Misc_startup(app)
                        
                        delete(d)
                    end
                    app.warnImage.Visible = 0;

                case app.Button_refresh
                    Misc_startup(app)        
                    app.warnImage.Visible = 0;

                case app.Thread
                    idx = find(strcmp(app.Thread.Items, app.Thread.Value));
                    % Algoritmo:
                    % Passo 1: identifica o treenode da seleção
                    % Passo 2: se for diferente do UserData da figura,
                    % troca UserData
                    % Passo 3: Simula ida pro Modo Playback

                    % SE ESTIVER HABILITADO O PLOT, TROCAR O FLUXO, CASO
                    % NECESSÁRIO!!!

                     Fcn4_Efficiency(app, 'both')
                
                case {app.xLim1, app.xLim2}
                    app.newMask.Value = Fcn3_Aux5_maskPrint(app);
                    app.newMaskInfo   = Fcn1_Aux1_MaskInfo(app, app.newMask.Value);  
                    
                    Fcn4_Efficiency(app, 'newMask')

                case app.THR1
                    newTHR = app.THR1.Value; 
                    Unit   = app.CallingApp.specData(app.Index(1)).MetaData.metaString{1};

                    idx = find(app.unmaskedTable.THR == event.PreviousValue)';
                    for ii = idx
                        app.Tree2.Children(ii).Text = sprintf('%d: %.3f MHz @ %.3f kHz  Ⱶ  %d %s', ...
                                                                ii,                                ...
                                                                app.unmaskedTable.Frequency(ii),   ...
                                                                app.unmaskedTable.BW(ii) * 1e+3,   ...
                                                                newTHR, Unit);
                    end
        
                    if ~isempty(idx)
                        app.unmaskedTable.THR(idx) = newTHR;

                        app.newMask.Value = Fcn3_Aux5_maskPrint(app);
                        app.newMaskInfo   = Fcn1_Aux1_MaskInfo(app, app.newMask.Value);

                        Fcn4_Efficiency(app, 'newMask')
                    end
                
                case app.THR2
                    newTHR = app.THR2.Value;
                    
                    idx = app.Tree2.SelectedNodes.NodeData;
                    app.newMask.Value{idx+2} = replace(app.newMask.Value{idx+2}, sprintf(',%.0f', event.PreviousValue), sprintf(',%.0f', newTHR));
                    Misc_updateTHR(app, idx, newTHR)

                    Fcn4_Efficiency(app, 'newMask')

                case {app.Tree1Refresh, app.PlaybackOnly}
                    Fcn3_treePanel(app)
                    Fcn4_Efficiency(app, 'newMask')                    
            end

            PlotValueChanged(app)

            if event.Source ~= app.THR2
                Misc_EditButton(app)
            end

        end

        % Value changed function: Plot
        function PlotValueChanged(app, event)

            if app.Plot.Value
                Misc_Plot(app)
                app.Playback_Toolbar.Visible = 1;
            else
                app.Playback_Toolbar.Visible = 0;
            end

        end

        % Button pushed function: Plot_Interaction, Plot_MaxHold, 
        % Plot_MeanHold, Plot_MinHold, Plot_RestoreView, Plot_WaterFall
        function PlotInteractions(app, event)
            
            switch event.Source
                case app.Plot_RestoreView
                    app.CallingApp.auxWin_MaskDraw('RestoreView')

                case app.Plot_Interaction
                    app.CallingApp.auxWin_MaskDraw('Interaction')

                case app.Plot_MinHold
                    app.CallingApp.auxWin_MaskDraw('MinHold')

                case app.Plot_MeanHold
                    app.CallingApp.auxWin_MaskDraw('MeanHold')

                case app.Plot_MaxHold
                    app.CallingApp.auxWin_MaskDraw('MaxHold')

                case app.Plot_WaterFall
                    app.CallingApp.auxWin_MaskDraw('WaterFall')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Máscara espectral';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.ButtonGrid = uigridlayout(app.Grid);
            app.Button_info = uibutton(app.ButtonGrid, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_info, true);
            app.Button_info.Icon = 'Toolbar_Icon3.png';
            app.Button_info.Text = '';
            app.Button_save = uibutton(app.ButtonGrid, 'push');
            app.Button_save.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_save, true);
            app.Button_save.Icon = 'White_Save.png';
            app.Button_save.Text = 'Salvar arquivo';
            app.Button_refresh = uibutton(app.ButtonGrid, 'push');
            app.Button_refresh.ButtonPushedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.Button_refresh.Icon = 'General_Refresh.png';
            app.Button_refresh.Tooltip = {'Atualiza listas de emissões,'; 'máscara em edição e efetividade'};
            app.Button_refresh.Text = '';
            app.Button_edit = uibutton(app.ButtonGrid, 'state');
            app.Button_edit.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed_edit, true);
            app.Button_edit.Enable = 'off';
            app.Button_edit.Tooltip = {'Edição manual do threshold'};
            app.Button_edit.Icon = 'mainMenu_Edit.png';
            app.Button_edit.Text = '';
            app.Button_open = uibutton(app.ButtonGrid, 'push');
            app.Button_open.ButtonPushedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.Button_open.Icon = 'mainMenu_File.png';
            app.Button_open.Tooltip = {'Abrir arquivo'; '(Máscara referência)'};
            app.Button_open.Text = '';
            app.Playback_Toolbar = uigridlayout(app.ButtonGrid);
            app.Playback_Toolbar.Visible = 'off';
            app.Plot_RestoreView = uibutton(app.Playback_Toolbar, 'push');
            app.Plot_RestoreView.ButtonPushedFcn = createCallbackFcn(app, @PlotInteractions, true);
            app.Plot_RestoreView.Icon = 'Playback_Icon1.png';
            app.Plot_RestoreView.Tooltip = {'Visualização padrão'};
            app.Plot_RestoreView.Text = '';
            app.Plot_Interaction = uibutton(app.Playback_Toolbar, 'push');
            app.Plot_Interaction.ButtonPushedFcn = createCallbackFcn(app, @PlotInteractions, true);
            app.Plot_Interaction.Icon = 'Pan.png';
            app.Plot_Interaction.Tooltip = {'Interação pan'};
            app.Plot_Interaction.Text = '';
            app.Separator2 = uiimage(app.Playback_Toolbar);
            app.Separator2.ImageSource = 'Line.png';
            app.Separator3 = uiimage(app.Playback_Toolbar);
            app.Separator3.ImageSource = 'Line.png';
            app.Plot_MinHold = uibutton(app.Playback_Toolbar, 'push');
            app.Plot_MinHold.ButtonPushedFcn = createCallbackFcn(app, @PlotInteractions, true);
            app.Plot_MinHold.Icon = '1200px-BellcurveMinHold.png';
            app.Plot_MinHold.Tooltip = {'MinHold'};
            app.Plot_MinHold.Text = '';
            app.Plot_MeanHold = uibutton(app.Playback_Toolbar, 'push');
            app.Plot_MeanHold.ButtonPushedFcn = createCallbackFcn(app, @PlotInteractions, true);
            app.Plot_MeanHold.Icon = '1200px-BellcurveMean.png';
            app.Plot_MeanHold.Tooltip = {'Tendência central'};
            app.Plot_MeanHold.Text = '';
            app.Plot_MaxHold = uibutton(app.Playback_Toolbar, 'push');
            app.Plot_MaxHold.ButtonPushedFcn = createCallbackFcn(app, @PlotInteractions, true);
            app.Plot_MaxHold.Icon = '1200px-Bellcurve3.png';
            app.Plot_MaxHold.Tooltip = {'MaxHold'};
            app.Plot_MaxHold.Text = '';
            app.Plot_WaterFall = uibutton(app.Playback_Toolbar, 'push');
            app.Plot_WaterFall.ButtonPushedFcn = createCallbackFcn(app, @PlotInteractions, true);
            app.Plot_WaterFall.Icon = 'Waterfall8.png';
            app.Plot_WaterFall.Tooltip = {'Waterfall'};
            app.Plot_WaterFall.Text = '';
            app.Separator2_2 = uiimage(app.Playback_Toolbar);
            app.Separator2_2.ImageSource = 'Line.png';
            app.Separator1 = uiimage(app.ButtonGrid);
            app.Separator1.ImageSource = 'Line.png';
            app.EffGrid = uigridlayout(app.Grid);
            app.Eff_oldMaskPanel = uipanel(app.EffGrid);
            app.Eff_oldMaskGrid = uigridlayout(app.Eff_oldMaskPanel);
            app.Eff_refMaskLabel = uilabel(app.Eff_oldMaskGrid);
            app.Eff_refMaskLabel.Text = {'EFETIVIDADE'; 'MÁSCARA REFERÊNCIA'};
            app.Eff_refMask = uilabel(app.Eff_oldMaskGrid);
            app.Eff_refMask.Text = '';
            app.Eff_newMaskPanel = uipanel(app.EffGrid);
            app.Eff_newMaskGrid = uigridlayout(app.Eff_newMaskPanel);
            app.Eff_newMaskLabel = uilabel(app.Eff_newMaskGrid);
            app.Eff_newMaskLabel.Text = {'EFETIVIDADE'; 'MÁSCARA EM EDIÇÃO'};
            app.Eff_newMask = uilabel(app.Eff_newMaskGrid);
            app.Eff_newMask.Text = '';
            app.Image = uiimage(app.Grid);
            app.Image.ImageSource = 'Playback_Icon3.png';
            app.Title = uilabel(app.Grid);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Máscara espectral</b>'; 'Geração de arquivo de máscara espectral compatível com o Logger'; ' '};
            app.warnGrid = uigridlayout(app.Grid);
            app.warnImage = uiimage(app.warnGrid);
            app.warnImage.Visible = 'off';
            app.warnImage.ImageSource = 'Warn_Red.png';
            app.limitsPanelLabel = uilabel(app.Grid);
            app.limitsPanelLabel.Text = 'ASPECTOS GERAIS';
            app.limitsPanelLabel_2 = uilabel(app.Grid);
            app.limitsPanelLabel_2.Text = 'LISTA DE EMISSÕES E MÁSCARAS ESPECTRAIS';
            app.maskPanel = uipanel(app.Grid);
            app.maskGrid = uigridlayout(app.maskPanel);
            app.Tree1 = uitree(app.maskGrid);
            app.Tree2Label = uilabel(app.maskGrid);
            app.Tree2Label.Interpreter = 'html';
            app.Tree2Label.Text = {'<b>Emissões desmascaradas:</b>'; '<font color=#808080  style="font-size:9;">(Frequência central, largura ocupada e <i>threshold</i>)</font>'};
            app.Tree2 = uitree(app.maskGrid);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.newMask = uitextarea(app.maskGrid);
            app.newMask.Editable = 'off';
            app.refMaskLabel = uilabel(app.maskGrid);
            app.refMaskLabel.Text = {'Máscara'; 'referência:'};
            app.refMask = uitextarea(app.maskGrid);
            app.refMask.Editable = 'off';
            app.newMaskLabel = uilabel(app.maskGrid);
            app.newMaskLabel.Text = {'Máscara '; 'em edição:'};
            app.THR2Grid = uigridlayout(app.maskGrid);
            app.THR2Label = uilabel(app.THR2Grid);
            app.THR2Label.Interpreter = 'html';
            app.THR2Label.Enable = 'off';
            app.THR2Label.Text = '<i>Threshold</i> (dBm):';
            app.THR2 = uispinner(app.THR2Grid);
            app.THR2.Step = 5;
            app.THR2.RoundFractionalValues = 'on';
            app.THR2.ValueDisplayFormat = '%.0f';
            app.THR2.ValueChangedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.THR2.Enable = 'off';
            app.THR2.Value = -80;
            app.GridLayout2 = uigridlayout(app.maskGrid);
            app.Tree1Label = uilabel(app.GridLayout2);
            app.Tree1Label.Interpreter = 'html';
            app.Tree1Label.Text = {'<b>Emissões:</b>'; '<font color=#808080  style="font-size:9;">(Frequência central e largura ocupada)</font>'};
            app.Tree1Refresh = uiimage(app.GridLayout2);
            app.Tree1Refresh.ImageClickedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.Tree1Refresh.ImageSource = 'General_Refresh.png';
            app.PlaybackOnly = uicheckbox(app.maskGrid);
            app.PlaybackOnly.ValueChangedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.PlaybackOnly.Text = 'Restringir emissões às identificadas no modo "PLAYBACK".';
            app.Panel_3 = uipanel(app.Grid);
            app.limitsGrid = uigridlayout(app.Panel_3);
            app.bandLabel = uilabel(app.limitsGrid);
            app.bandLabel.Text = 'Limites (MHz):';
            app.bandPanel = uipanel(app.limitsGrid);
            app.bandGrid = uigridlayout(app.bandPanel);
            app.xLim1 = uispinner(app.bandGrid);
            app.xLim1.Step = 10;
            app.xLim1.Limits = [10 18000];
            app.xLim1.RoundFractionalValues = 'on';
            app.xLim1.ValueDisplayFormat = '%.0f';
            app.xLim1.ValueChangedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.xLim1.Tag = 'FreqStart';
            app.xLim1.Tooltip = {''};
            app.xLim1.Value = 20;
            app.xLimLabel = uilabel(app.bandGrid);
            app.xLimLabel.Text = 'x';
            app.xLim2 = uispinner(app.bandGrid);
            app.xLim2.Step = 10;
            app.xLim2.Limits = [10 18000];
            app.xLim2.RoundFractionalValues = 'on';
            app.xLim2.ValueDisplayFormat = '%.0f';
            app.xLim2.ValueChangedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.xLim2.Tag = 'FreqStop';
            app.xLim2.Tooltip = {''};
            app.xLim2.Value = 6000;
            app.AutoMeasure = uicheckbox(app.limitsGrid);
            app.AutoMeasure.Text = 'Cálculo automático da efetividade.';
            app.AutoMeasure.Value = true;
            app.GridLayout4 = uigridlayout(app.limitsGrid);
            app.THR1Label = uilabel(app.GridLayout4);
            app.THR1Label.Interpreter = 'html';
            app.THR1Label.Text = '<i>Threshold</i> base (dBm):';
            app.THR1 = uispinner(app.GridLayout4);
            app.THR1.Step = 5;
            app.THR1.RoundFractionalValues = 'on';
            app.THR1.ValueDisplayFormat = '%.0f';
            app.THR1.ValueChangedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.THR1.Value = -80;
            app.Panel_2 = uipanel(app.limitsGrid);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.ThreadLabel = uilabel(app.GridLayout5);
            app.ThreadLabel.Text = 'Fluxo de dados referência:';
            app.Thread = uidropdown(app.GridLayout5);
            app.Thread.Items = {};
            app.Thread.ValueChangedFcn = createCallbackFcn(app, @refreshCallback, true);
            app.Thread.Value = {};
            app.ThreadList = uitextarea(app.GridLayout5);
            app.ThreadList.Editable = 'off';
            app.MaskFile1Label = uilabel(app.GridLayout5);
            app.MaskFile1Label.Text = 'Máscara referência:';
            app.MaskFile1 = uieditfield(app.GridLayout5, 'text');
            app.MaskFile1.Editable = 'off';
            app.bandLabel_3 = uilabel(app.limitsGrid);
            app.bandLabel_3.Text = 'Fontes de informação para compor lista de emissões:';
            app.BandsListLabel = uilabel(app.limitsGrid);
            app.BandsListLabel.Text = 'Faixas sob análise:';
            app.BandsList = uitextarea(app.limitsGrid);
            app.BandsList.Editable = 'off';
            app.Plot = uicheckbox(app.limitsGrid);
            app.Plot.ValueChangedFcn = createCallbackFcn(app, @PlotValueChanged, true);
            app.Plot.Text = 'Apresentação gráfica da máscara em edição.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMaskDraw(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end