classdef winOCC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Grid1                matlab.ui.container.GridLayout
        Grid2                matlab.ui.container.GridLayout
        Button1              matlab.ui.control.Button
        Button2              matlab.ui.control.Button
        Image                matlab.ui.control.Image
        Title                matlab.ui.control.Label
        Panel1               matlab.ui.container.Panel
        Grid6                matlab.ui.container.GridLayout
        Sublabel1            matlab.ui.control.Label
        Panel5               matlab.ui.container.Panel
        Grid7                matlab.ui.container.GridLayout
        noise_Fcn            matlab.ui.control.DropDown
        noise_FcnLabel       matlab.ui.control.Label
        noise_tSamples       matlab.ui.control.Spinner
        noise_tSamplesLabel  matlab.ui.control.Label
        noise_uSamplesLabel  matlab.ui.control.Label
        noise_uSamples       matlab.ui.control.Spinner
        occ_FactorLabel      matlab.ui.control.Label
        occ_Factor           matlab.ui.control.DropDown
        occ_TimeLabel        matlab.ui.control.Label
        occ_Time             matlab.ui.control.Spinner
        occ_Offset           matlab.ui.control.Spinner
        occ_OffsetLabel      matlab.ui.control.Label
        occ_Value            matlab.ui.control.Spinner
        occ_ValueLabel       matlab.ui.control.Label
        occ_Type             matlab.ui.control.DropDown
        occ_TypeLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        
    end
    
    methods (Access = private)
        
        function Layout_Report_Tabs(app, Flag, Mode)
            
            switch Flag
                case 'peakTab'
                    if strcmp(Mode, 'On')
                        app.Report_peakClass.Enable    = 1;
                        app.Report_peakDistance.Enable = 1;
                        app.Report_peakBW.Enable       = 1;
                        app.Report_Label7.Enable       = 1;
                        app.Report_Label9.Enable       = 1;
                        
                        app.Report_peakClassLabel.Enable    = 1;
                        app.Report_peakDistanceLabel.Enable = 1;
                        app.Report_peakBWLabel.Enable       = 1;

                        set(app.Report_Grid10.Children, 'Enable', 1)
                        set(app.Report_Grid11.Children, 'Enable', 1)
                        set(app.PlaybackEmissionsOnly,  'Enable', 1)
                    else
                        app.Report_peakClass.Enable    = 0;
                        app.Report_peakDistance.Enable = 0;
                        app.Report_peakBW.Enable       = 0;
                        app.Report_Label7.Enable       = 0;
                        app.Report_Label9.Enable       = 0;
                        
                        app.Report_peakClassLabel.Enable    = 0;
                        app.Report_peakDistanceLabel.Enable = 0;
                        app.Report_peakBWLabel.Enable       = 0;
    
                        set(app.Report_Grid10.Children, 'Enable', 0)
                        set(app.Report_Grid11.Children, 'Enable', 0)
                        set(app.PlaybackEmissionsOnly,  'Enable', 0)
                    end

                case 'occTab'
                    if strcmp(Mode, 'On')
                        app.occ_Type.Enable   = 1;
                        app.occ_Value.Enable  = 1;
                        app.occ_Offset.Enable = 1;
                        app.occ_Time.Enable   = 1;
                        app.occ_Factor.Enable = 1;
                        
                        app.occ_TypeLabel.Enable   = 1;
                        app.occ_ValueLabel.Enable  = 1;
                        app.occ_OffsetLabel.Enable = 1;
                        app.occ_TimeLabel.Enable   = 1;
                        app.occ_FactorLabel.Enable = 1;
                        
                        set(app.Grid7.Children, 'Enable', 1)
                    else
                        app.occ_Type.Enable   = 0;
                        app.occ_Value.Enable  = 0;
                        app.occ_Offset.Enable = 0;
                        app.occ_Time.Enable   = 0;
                        app.occ_Factor.Enable = 0;
                        
                        app.occ_TypeLabel.Enable   = 0;
                        app.occ_ValueLabel.Enable  = 0;
                        app.occ_OffsetLabel.Enable = 0;
                        app.occ_TimeLabel.Enable   = 0;
                        app.occ_FactorLabel.Enable = 0;
    
                        set(app.Grid7.Children, 'Enable', 0)
                    end
            end
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin8 = [];
            delete(app)
            
        end

        % Callback function
        function ClassValueChanged(app, event)

            global peaksReference
            BandList = struct2table(peaksReference);
            
            idx = [];
            switch app.Class.Value
                case 'Radiodifusão (FM)'; idx = find(strcmp(BandList.Description, 'FM'),   1);
                case 'Radiodifusão (TV)'; idx = find(contains(BandList.Description, 'TV'), 1);
                case 'Radiocomunicação';  idx = find(strcmp(BandList.Description, 'Aircraft'), 1);
            end
            
            app.C1_Proeminence.Value = 10;
            app.C2_Proeminence.Value = 30;
            app.C1_Fcn.Value         = 'Média';
            
            if ~isempty(idx)
                app.Cn_minDist.Value = peaksReference(idx).Settings(1) / 1000;
                app.Cn_BW.Value      = peaksReference(idx).Settings(2) / 1000;
                app.C2_meanOCC.Value = peaksReference(idx).Settings(3);
                app.C2_maxOCC.Value  = peaksReference(idx).Settings(4);
            else
                app.Cn_minDist.Value = 25;
                app.Cn_BW.Value      = 10;
                app.C2_meanOCC.Value = 1;
                app.C2_maxOCC.Value  = 10;
            end

        end

        % Callback function
        function C2_meanOCCValueChanged(app, event)
            
            if app.C2_meanOCC.Value > app.C2_maxOCC.Value
                app.C2_maxOCC.Value = app.C2_meanOCC.Value;
            end

        end

        % Callback function
        function C2_maxOCCValueChanged(app, event)
            
            if app.C2_maxOCC.Value < app.C2_meanOCC.Value
                app.C2_meanOCC.Value = app.C2_maxOCC.Value;
            end

        end

        % Value changed function: occ_Type
        function occ_TypeValueChanged(app, event)
            
            idx = app.CallingApp.Report_Tree.SelectedNodes.NodeData;
            
            switch app.occ_Type.Value
                case 'Linear fixo'
                    Layout_Report_Tabs(app, 'occTab', 'Off')
                    
                    app.occ_Type.Enable       = 1;
                    app.occ_TypeLabel.Enable  = 1;
                    app.occ_Value.Enable      = 1;
                    app.occ_ValueLabel.Enable = 1;
                    app.occ_Time.Enable       = 1;
                    app.occ_TimeLabel.Enable  = 1;
                    
                    switch app.CallingApp.specData(idx).MetaData.metaString{1}
                        case 'dBm'
                            app.occ_ValueLabel.Text = 'Valor (dBm):';
                            app.occ_Value.Value     = -80;
    
                        case 'dBμV'
                            app.occ_ValueLabel.Text = 'Valor (dBμV):';
                            app.occ_Value.Value     = 27;
                        
                        case 'dBμV/m'
                            app.occ_ValueLabel.Text = 'Valor (dBμV/m):';
                            app.occ_Value.Value     = 40;
                    end
                    
                case 'Linear adaptativo'
                    Layout_Report_Tabs(app, 'occTab', 'On')
                    
                    app.occ_Value.Enable       = 0;
                    app.occ_ValueLabel.Enable  = 0;
                    app.occ_Factor.Enable      = 0;
                    app.occ_FactorLabel.Enable = 0;
                    
                case 'Piso de ruído (Offset)'
                    Layout_Report_Tabs(app, 'occTab', 'On')
                    
                    app.occ_Value.Enable      = 0;
                    app.occ_ValueLabel.Enable = 0;
            end

        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
                % JSON
                % Campo detection terá apenas as informações da tela
                % principal do appAnálise. As peculiaridades dos algoritmos
                % irão nesse campo JSON.
            
%             switch app.Report_occType.Value
%                 case 'Linear fixo'                    
%                     if strcmp(Flag, 'On')
%                         app.specData(ind).report_occInfo = struct('Type',      app.Report_occType.Value,  ...
%                                                                   'Value',     app.Report_occValue.Value, ...
%                                                                   'Time',      app.Report_occTime.Value,  ...
%                                                                   'Offset',    [], ...
%                                                                   'ConfLevel', [], ...
%                                                                   'statsFcn',  '', ...
%                                                                   'Samples',   []);
%                     end
%                     
%                 case 'Linear adaptativo'
%                     if strcmp(Flag, 'On')
%                         app.specData(ind).report_occInfo = struct('Type',      app.Report_occType.Value,   ...
%                                                                   'Value',     [],                         ...
%                                                                   'Time',      app.Report_occTime.Value,   ...
%                                                                   'Offset',    app.Report_occOffset.Value, ...
%                                                                   'ConfLevel', [],                         ...
%                                                                   'statsFcn',  app.Report_occFcn.Value,    ...
%                                                                   'Samples',   [app.Report_tSamples.Value, app.Report_uSamples.Value]);
%                     end
%                     
%                 case 'Piso de ruído (Offset)'
%                     if strcmp(Flag, 'On')
%                         app.specData(ind).report_occInfo = struct('Type',      app.Report_occType.Value,      ...
%                                                                   'Value',     [],                            ...
%                                                                   'Time',      app.Report_occTime.Value,      ...
%                                                                   'Offset',    app.Report_occOffset.Value,    ...
%                                                                   'ConfLevel', app.Report_occConfLevel.Value, ...
%                                                                   'statsFcn',  app.Report_occFcn.Value,       ...
%                                                                   'Samples',   [app.Report_tSamples.Value, app.Report_uSamples.Value]);
%                     end
%             end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel1 = uipanel(app.Grid1);
            app.Grid6 = uigridlayout(app.Panel1);
            app.occ_TypeLabel = uilabel(app.Grid6);
            app.occ_TypeLabel.Text = 'Método:';
            app.occ_Type = uidropdown(app.Grid6);
            app.occ_Type.Items = {'Linear adaptativo', 'Linear fixo', 'Piso de ruído (Offset)'};
            app.occ_Type.ValueChangedFcn = createCallbackFcn(app, @occ_TypeValueChanged, true);
            app.occ_Type.Value = 'Linear adaptativo';
            app.occ_ValueLabel = uilabel(app.Grid6);
            app.occ_ValueLabel.Text = 'Valor (dBm):';
            app.occ_Value = uispinner(app.Grid6);
            app.occ_Value.RoundFractionalValues = 'on';
            app.occ_Value.ValueDisplayFormat = '%d';
            app.occ_Value.Value = -80;
            app.occ_OffsetLabel = uilabel(app.Grid6);
            app.occ_OffsetLabel.Text = 'OffSet:';
            app.occ_Offset = uispinner(app.Grid6);
            app.occ_Offset.Limits = [5 12];
            app.occ_Offset.Value = 12;
            app.occ_Time = uispinner(app.Grid6);
            app.occ_Time.Limits = [1 60];
            app.occ_Time.Value = 15;
            app.occ_TimeLabel = uilabel(app.Grid6);
            app.occ_TimeLabel.Text = 'Tempo de integração (min):';
            app.occ_Factor = uidropdown(app.Grid6);
            app.occ_Factor.Items = {'68', '95', '99'};
            app.occ_Factor.Value = '95';
            app.occ_FactorLabel = uilabel(app.Grid6);
            app.occ_FactorLabel.Text = 'Fator ceifamento:';
            app.Panel5 = uipanel(app.Grid6);
            app.Grid7 = uigridlayout(app.Panel5);
            app.noise_uSamples = uispinner(app.Grid7);
            app.noise_uSamples.Limits = [10 90];
            app.noise_uSamples.Value = 20;
            app.noise_uSamplesLabel = uilabel(app.Grid7);
            app.noise_uSamplesLabel.Text = {'Amostras'; 'úteis (%):'};
            app.noise_tSamplesLabel = uilabel(app.Grid7);
            app.noise_tSamplesLabel.Text = {'Amostras'; 'descartadas (%):'};
            app.noise_tSamples = uispinner(app.Grid7);
            app.noise_tSamples.Limits = [0 10];
            app.noise_FcnLabel = uilabel(app.Grid7);
            app.noise_FcnLabel.Text = {'Função'; 'estatística:'};
            app.noise_Fcn = uidropdown(app.Grid7);
            app.noise_Fcn.Items = {'Mediana', 'Média'};
            app.noise_Fcn.Value = 'Média';
            app.Sublabel1 = uilabel(app.Grid6);
            app.Sublabel1.Text = 'Relacionados à estimativa do piso de ruído:';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Ocupação</b>'; '<font>Aferição de...</font>'; ' '};
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'peakdetection.png';
            app.Grid2 = uigridlayout(app.Grid1);
            app.Button2 = uibutton(app.Grid2, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'White_Play.png';
            app.Button2.Text = 'Confirmar';
            app.Button1 = uibutton(app.Grid2, 'push');
            app.Button1.Icon = 'Toolbar_Icon3.png';
            app.Button1.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winOCC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end