classdef winRFDataHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        axesToolbarGrid                 matlab.ui.container.GridLayout
        axesTool_RegionZoom             matlab.ui.control.Image
        axesTool_RestoreView            matlab.ui.control.Image
        plotPanel                       matlab.ui.container.Panel
        chReportHTML                    matlab.ui.control.HTML
        UITable                         matlab.ui.control.Table
        ControlTabGrid                  matlab.ui.container.GridLayout
        menu_MainGrid                   matlab.ui.container.GridLayout
        menu_Button3Grid                matlab.ui.container.GridLayout
        menu_Button3Icon                matlab.ui.control.Image
        menu_Button3Label               matlab.ui.control.Label
        menu_Button2Grid                matlab.ui.container.GridLayout
        menu_Button2Icon                matlab.ui.control.Image
        menu_Button2Label               matlab.ui.control.Label
        menu_Button1Grid                matlab.ui.container.GridLayout
        menu_Button1Icon                matlab.ui.control.Image
        menu_Button1Label               matlab.ui.control.Label
        menuUnderline                   matlab.ui.control.Image
        ControlTabGroup                 matlab.ui.container.TabGroup
        Tab_1                           matlab.ui.container.Tab
        Tab1_Grid                       matlab.ui.container.GridLayout
        filter_refRXEdit_2              matlab.ui.control.Image
        search_SelectedRowInfoPanel     matlab.ui.container.Panel
        search_SelectedRowInfoGrid      matlab.ui.container.GridLayout
        PlotPanel                       matlab.ui.container.Panel
        stationInfo                     matlab.ui.control.HTML
        filter_refTXPanel               matlab.ui.container.Panel
        filter_refTXGrid                matlab.ui.container.GridLayout
        filter_refTXHeight              matlab.ui.control.NumericEditField
        filter_refTXHeightLabel         matlab.ui.control.Label
        filter_refTXLongitude           matlab.ui.control.NumericEditField
        filter_refTXLongitudeLabel      matlab.ui.control.Label
        filter_refTXLatitude            matlab.ui.control.NumericEditField
        filter_refTXLatitudeLabel       matlab.ui.control.Label
        filter_refTXLabel               matlab.ui.control.Label
        filter_refTXIcon                matlab.ui.control.Image
        Tab_2                           matlab.ui.container.Tab
        Tab2_Grid                       matlab.ui.container.GridLayout
        filter_refRXPanel               matlab.ui.container.Panel
        filter_refRXGrid                matlab.ui.container.GridLayout
        filter_refRXHeight              matlab.ui.control.NumericEditField
        filter_refRXHeightLabel         matlab.ui.control.Label
        filter_refRXLongitude           matlab.ui.control.NumericEditField
        filter_refRXLongitudeLabel      matlab.ui.control.Label
        filter_refRXLatitude            matlab.ui.control.NumericEditField
        filter_refRXLatitudeLabel       matlab.ui.control.Label
        filter_refRXEdit                matlab.ui.control.Image
        filter_refRXLabel               matlab.ui.control.Label
        filter_refRXIcon                matlab.ui.control.Image
        filter_Tree                     matlab.ui.container.CheckBoxTree
        filter_AddImage                 matlab.ui.control.Image
        filter_SecondaryValuePanel      matlab.ui.container.ButtonGroup
        filter_SecondaryValueSubpanel   matlab.ui.container.Panel
        filter_SecondaryValueGrid       matlab.ui.container.GridLayout
        filter_SecondaryLogicalOperator  matlab.ui.control.DropDown
        filter_SecondaryLogicalOperatorLabel  matlab.ui.control.Label
        filter_SecondaryReferenceFilter  matlab.ui.control.DropDown
        filter_SecondaryReferenceFilterLabel  matlab.ui.control.Label
        filter_SecondaryTextList        matlab.ui.control.DropDown
        filter_SecondaryTextFree        matlab.ui.control.EditField
        filter_SecondaryNumValue2       matlab.ui.control.NumericEditField
        filter_SecondaryNumSeparator    matlab.ui.control.Label
        filter_SecondaryNumValue1       matlab.ui.control.NumericEditField
        filter_SecondaryOperation10     matlab.ui.control.ToggleButton
        filter_SecondaryOperation9      matlab.ui.control.ToggleButton
        filter_SecondaryOperation8      matlab.ui.control.ToggleButton
        filter_SecondaryOperation7      matlab.ui.control.ToggleButton
        filter_SecondaryOperation6      matlab.ui.control.ToggleButton
        filter_SecondaryOperation5      matlab.ui.control.ToggleButton
        filter_SecondaryOperation4      matlab.ui.control.ToggleButton
        filter_SecondaryOperation3      matlab.ui.control.ToggleButton
        filter_SecondaryOperation2      matlab.ui.control.ToggleButton
        filter_SecondaryOperation1      matlab.ui.control.ToggleButton
        filter_SecondaryTypePanel       matlab.ui.container.ButtonGroup
        filter_SecondaryType12          matlab.ui.control.RadioButton
        filter_SecondaryType11          matlab.ui.control.RadioButton
        filter_SecondaryType10          matlab.ui.control.RadioButton
        filter_SecondaryType9           matlab.ui.control.RadioButton
        filter_SecondaryType8           matlab.ui.control.RadioButton
        filter_SecondaryType7           matlab.ui.control.RadioButton
        filter_SecondaryType6           matlab.ui.control.RadioButton
        filter_SecondaryType5           matlab.ui.control.RadioButton
        filter_SecondaryType4           matlab.ui.control.RadioButton
        filter_SecondaryType3           matlab.ui.control.RadioButton
        filter_SecondaryType2           matlab.ui.control.RadioButton
        filter_SecondaryType1           matlab.ui.control.RadioButton
        filter_SecondaryLabel           matlab.ui.control.Label
        Tab_3                           matlab.ui.container.Tab
        Tab4_Grid                       matlab.ui.container.GridLayout
        config_FolderMapPanel           matlab.ui.container.Panel
        config_FolderMapGrid            matlab.ui.container.GridLayout
        config_Folder_userPathButton    matlab.ui.control.Image
        config_Folder_userPath          matlab.ui.control.DropDown
        config_Folder_userPathLabel     matlab.ui.control.Label
        config_Folder_DataHubGETButton  matlab.ui.control.Image
        config_Folder_DataHubGET        matlab.ui.control.EditField
        config_Folder_DataHubGETLabel   matlab.ui.control.Label
        misc_ElevationSourcePanel       matlab.ui.container.Panel
        misc_ElevationSourceGrid        matlab.ui.container.GridLayout
        misc_PointsPerLink              matlab.ui.control.DropDown
        misc_PointsPerLinkLabel         matlab.ui.control.Label
        misc_ElevationAPISource         matlab.ui.control.DropDown
        misc_ElevationAPISourceLabel    matlab.ui.control.Label
        ELEVAOLabel                     matlab.ui.control.Label
        config_xyAxesLabel              matlab.ui.control.Label
        config_geoAxesPanel             matlab.ui.container.Panel
        config_geoAxesGrid              matlab.ui.container.GridLayout
        config_Station_Size             matlab.ui.control.Slider
        config_Station_Color            matlab.ui.control.ColorPicker
        config_Station_DataTipVisibility  matlab.ui.control.DropDown
        config_Station_Label            matlab.ui.control.Label
        config_RX_Size                  matlab.ui.control.Slider
        config_RX_Color                 matlab.ui.control.ColorPicker
        config_RX_Visibility            matlab.ui.control.DropDown
        config_RX_Label                 matlab.ui.control.Label
        config_TX_Size                  matlab.ui.control.Slider
        config_TX_Color                 matlab.ui.control.ColorPicker
        config_TX_DataTipVisibility     matlab.ui.control.DropDown
        config_TX_Label                 matlab.ui.control.Label
        config_geoAxesSubPanel          matlab.ui.container.Panel
        config_geoAxesSubGrid           matlab.ui.container.GridLayout
        config_Colormap                 matlab.ui.control.DropDown
        config_ColormapLabel            matlab.ui.control.Label
        config_Basemap                  matlab.ui.control.DropDown
        config_BasemapLabel             matlab.ui.control.Label
        config_geoAxesSublabel          matlab.ui.control.Label
        config_Refresh                  matlab.ui.control.Image
        config_geoAxesLabel             matlab.ui.control.Label
        toolGrid                        matlab.ui.container.GridLayout
        tableInfoNRows                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        axesTool_simulationButton       matlab.ui.control.Image
        axesTool_PDFButton              matlab.ui.control.Image
        axesTool_TableVisibility        matlab.ui.control.Image
        tool_ExportButton               matlab.ui.control.Image
        jsBackDoor                      matlab.ui.control.HTML
        tool_ControlPanelVisibility     matlab.ui.control.Image
        filter_ContextMenu              matlab.ui.container.ContextMenu
        filter_delButton                matlab.ui.container.Menu
        filter_delAllButton             matlab.ui.container.Menu
        points_ContextMenu              matlab.ui.container.ContextMenu
        points_referenceButton          matlab.ui.container.Menu
        points_editRowButton            matlab.ui.container.Menu
        points_delButton                matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false

        CallingApp
        General
        rootFolder

        % Essa propriedade registra o tipo de execução da aplicação, podendo
        % ser: 'built-in', 'desktopApp' ou 'webApp'.
        executionMode
        
        % Essa propriedade registra se o RFDataHub está em modo "standalone"
        % (true) ou como módulo do appAnalise (false).
        standaloneFlag

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % O MATLAB não renderiza alguns dos componentes de abas (do TabGroup) 
        % não visíveis. E a customização de componentes, usando a lib ccTools, 
        % somente é possível após a sua renderização. Controla-se a aplicação 
        % da customizaçao por meio dessa propriedade jsBackDoorFlag.
        jsBackDoorFlag = {true, ...
                          true, ...
                          true, ...
                          true};

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        % Propriedades do app.
        specData = class.specData.empty

        %-----------------------------------------------------------------%
        % ESPECIFICIDADES AUXAPP.WINDRIVETEST
        %-----------------------------------------------------------------%
        rfDataHub
        rfDataHubLOG
        rfDataHubSummary
        
        UIAxes1
        UIAxes2
        UIAxes3
        restoreView  = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})

        elevationObj = RF.Elevation
        ChannelReportObj

        pointsTable  = table('Size',          [0, 5],                                            ...
                             'VariableTypes', {'cell', 'double', 'double', 'double', 'logical'}, ...
                             'VariableNames', {'Type', 'Latitude', 'Longitude', 'AntennaHeight', 'Reference'})
        filterTable  = table('Size',          [0, 9],                                                                      ...
                             'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                             'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'})
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end

        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            % O menu gráfico controla, programaticamente, qual das abas de
            % app.ControlTabGroup estará visível. 

            % Lembrando que o MATLAB renderiza em tela apenas as abas visíveis.
            % Por isso as customizações de abas e suas subabas somente é possível 
            % após a renderização da aba.

            switch tabIndex
                case 0 % STARTUP
                    if app.isDocked
                        app.progressDialog = app.CallingApp.progressDialog;
                    else
                        app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
                    end

                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                           'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                               '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                               '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));
        
                    sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                           'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

                    % uialert, uiprogressdialog, uiconfirm
                    % sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mwDialog', ...
                    %                                                                        'classAttributes',  'background-color: white;'));
                    % 
                    % sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mwDialog .mwDialogTitleBar', ...
                    %                                                                        'classAttributes',  'background-color: #f0f0f0;'));
                    % 
                    % sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mwDialog .mwDialogTitleBar .mwCloseNode', ...
                    %                                                                        'classAttributes',  'background-color: #f0f0f0;'));
                    
                    ccTools.compCustomizationV2(app.jsBackDoor, app.ControlTabGroup, 'transparentHeader', 'transparent')
                    ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'borderBottomLeftRadius', '5px', 'borderBottomRightRadius', '5px')
                    ccTools.compCustomizationV2(app.jsBackDoor, app.PlotPanel,       'backgroundColor', 'transparent')

                otherwise
                    if any(app.jsBackDoorFlag{tabIndex})
                        app.jsBackDoorFlag{tabIndex} = false;

                        switch tabIndex
                            case 1 % RFDATAHUB
                                % ...            
                            case 2 % FILTRAGEM
                                % ...
                            case 3 % CONFIGURAÇÕES GERAIS
                                % ...
                        end
                    end
            end
        end

        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)                
                startup_Controller(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)            
            % Customiza as aspectos estéticos de alguns dos componentes da GUI 
            % (diretamente em JS).
            jsBackDoor_Customizations(app, 0)
            jsBackDoor_Customizations(app, 1)

            app.progressDialog.Visible = 'visible';

            if app.standaloneFlag
                % PATH SEARCH
                appName        = class.Constants.appName;
                MFilePath      = fileparts(mfilename('fullpath'));
                app.rootFolder = appUtil.RootFolder(appName, MFilePath);
                app.executionMode = appUtil.ExecutionMode(app.UIFigure);

                % "GeneralSettings.json"
                startup_Files2Read(app)

                % userPath
                if ~strcmp(app.executionMode, 'webApp')
                    userPaths = appUtil.UserPaths(app.General.fileFolder.userPath);
                    set(app.config_Folder_userPath, 'Items', userPaths, 'Value', userPaths{end})
                    app.General.fileFolder.userPath = userPaths{end};
                end
            end

            switch app.executionMode
                case 'webApp'
                    % Webapp não suporta uigetdir, então o mapeamento das pastas
                    % POST/GET deve ser feito em arquivo externo de configuração...
                    app.config_Folder_userPathButton.Enable = 0;

                otherwise
                    % Define tamanho mínimo do app (não aplicável à versão webapp).
                    if ~app.isDocked
                        appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
                    end
            end

            startup_AppProperties(app)
            startup_AxesCreation(app)
            startup_GUIComponents(app)

            filter_TableFiltering(app)

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function startup_Files2Read(app)
            % "GeneralSettings.json"
            [app.General, msgWarning] = appUtil.generalSettingsLoad(class.Constants.appName, app.rootFolder);
            if ~isempty(msgWarning)
                appUtil.modalWindow(app.UIFigure, 'error', msgWarning);
            end
        
            app.General.AppVersion = fcn.startup_Versions("Startup", app.rootFolder);
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            % O controle do registro selecionado, do PDF e do plot "RFLink"
            % é organizado na propriedade "UserData" de app.emissionInfo
            % (Painel HTML).
            app.stationInfo.UserData = struct('idxRFDataHub', [], ...
                                              'PDFDocStatus', false, ...
                                              'RFLinkStatus', false);

            % refRX
            filter_getReferenceRX(app)

            % app.rfDataHub
            global RFDataHub
            global RFDataHubLog
            
            app.rfDataHub = RFDataHub;
            app.rfDataHubLOG = RFDataHubLog;
            
            % Contorna erro da função inROI, que retorna como se todos os
            % pontos estivessem internos ao ROI, quando as coordenadas
            % estão em float32. No float64 isso não acontece... aberto BUG
            % na Mathworks, que indicou estar ciente.
            app.rfDataHub.Latitude    = double(app.rfDataHub.Latitude);
            app.rfDataHub.Longitude   = double(app.rfDataHub.Longitude);

            app.rfDataHub.ID          = "#" + string((1:height(RFDataHub))');
            app.rfDataHub.Description = "[" + string(RFDataHub.Source) + "] " + string(RFDataHub.Status) + ", " + string(RFDataHub.StationClass) + ", " + string(RFDataHub.Name) + ", " + string(RFDataHub.Location) + "/" + string(RFDataHub.State) + " (M=" + string(RFDataHub.MergeCount) + ")";
            filter_calculateDistanceColumn(app)
                        
            % app.rfDataHubSummary
            app.rfDataHubSummary = summary(RFDataHub);

            % A coluna "Source" possui agrupamentos da fonte dos dados,
            % decorrente da mesclagem de estações.
            tempSourceList = cellfun(@(x) strsplit(x, ' | '), app.rfDataHubSummary.Source.Categories, 'UniformOutput', false);
            app.rfDataHubSummary.Source.RawCategories = unique(horzcat(tempSourceList{:}))';

            % A coluna "Location" não está sendo corretamente ordenada por
            % conta dos caracteres especiais.
            tempLocationList = textAnalysis.preProcessedData(app.rfDataHubSummary.Location.Categories);
            [app.rfDataHubSummary.Location.CacheCategories, idxSort] = sort(tempLocationList);
            app.rfDataHubSummary.Location.Categories = app.rfDataHubSummary.Location.Categories(idxSort);

            % lastPrimarySearch
            filter_getReferenceSearch(app)
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            hParent     = tiledlayout(app.plotPanel, 2, 2, "Padding", "none", "TileSpacing", "none");

            % Eixo geográfico: MAPA
            app.UIAxes1 = plot.axes.Creation(hParent, 'Geographic', {'Basemap', app.config_Basemap.Value, ...
                                                                     'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [2, 2];

            set(app.UIAxes1.LatitudeAxis,  'TickLabels', {}, 'Color', 'none')
            set(app.UIAxes1.LongitudeAxis, 'TickLabels', {}, 'Color', 'none')
            geolimits(app.UIAxes1, 'auto')
            plot.axes.Colormap(app.UIAxes1, app.config_Colormap.Value)

            % Eixo cartesiano: PERFIL DE RELEVO
            app.UIAxes2 = plot.axes.Creation(hParent, 'Cartesian', {'XGrid', 'off', 'XMinorGrid', 'off', 'XTick', [], 'XColor', [.8,.8,.8], 'XLimitMethod', 'padded', ...
                                                                    'YGrid', 'off', 'YMinorGrid', 'off', 'YTick', [], 'YColor', 'none',                               ...
                                                                    'Color', 'none', 'Clipping', 'off', 'LineWidth', 2, 'Layer', 'top', 'Visible', 'off'});
            app.UIAxes2.Layout.Tile = 3;
            app.UIAxes2.Layout.TileSpan = [1 2];
            app.UIAxes2.XAxis.TickLabelFormat = '%.1f';

            % Eixo cartesiano: DIAGRAMA DE RADIAÇÃO DA ANTENA
            app.PlotPanel.AutoResizeChildren = 'off';
            app.UIAxes3 = polaraxes(app.PlotPanel, 'Units',             'normalized',    ...
                                                   'Position',          [.05,.05,.9,.9], ...
                                                   'ThetaZeroLocation', 'top',           ...
                                                   'FontSize',          9,               ...
                                                   'Color',             'none',          ...
                                                   'ThetaTick',         0,               ...
                                                   'ThetaDir',          'clockwise',     ...
                                                   'RTickLabel',        {});
            hold(app.UIAxes3, 'on')

            % Axes interactions:
            plot.axes.Interactivity.DefaultCreation(app.UIAxes1, [dataTipInteraction, zoomInteraction, panInteraction])
            plot.axes.Interactivity.DefaultCreation(app.UIAxes2, dataTipInteraction)
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            app.axesTool_TableVisibility.UserData  = true;
            app.axesTool_PDFButton.UserData        = false;
            app.axesTool_simulationButton.UserData = false;
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function [idxRFDataHub, idxSelectedRow] = getRFDataHubIndex(app)
            if isempty(app.UITable.Selection)
                idxRFDataHub   = [];
                idxSelectedRow = 0;
                
            else
                idxSelectedRow = app.UITable.Selection(1);
                idxVirtual     = app.UITable.Data.ID(idxSelectedRow);    
                idxRFDataHub   = str2double(extractAfter(idxVirtual, '#'));
            end
        end

        %-----------------------------------------------------------------%
        function filter_getReferenceRX(app)
            refRXFlag = false;
            for ii = 1:numel(app.specData)
                if app.specData(ii).GPS.Status
                    AntennaHeight = [];
                    if isfield(app.specData(ii).MetaData.Antenna, 'Height')
                        AntennaHeight = str2double(extractBefore(app.specData(ii).MetaData.Antenna.Height, 'm'));
                    end

                    if isempty(AntennaHeight) || isnan(AntennaHeight) || (AntennaHeight <= 0) || isinf(AntennaHeight)
                        AntennaHeight = app.General.RFDataHub.DefaultRX.Height;
                    end

                    app.filter_refRXLatitude.Value  = app.specData(ii).GPS.Latitude;
                    app.filter_refRXLongitude.Value = app.specData(ii).GPS.Longitude;
                    app.filter_refRXHeight.Value    = AntennaHeight;
                    
                    refRXFlag = true;
                    break
                end
            end

            if ~refRXFlag
                if ~isempty(app.General.RFDataHub.lastSearch)
                    app.filter_refRXLatitude.Value  = app.General.RFDataHub.lastSearch.RX.Latitude;
                    app.filter_refRXLongitude.Value = app.General.RFDataHub.lastSearch.RX.Longitude;
                    app.filter_refRXHeight.Value    = app.General.RFDataHub.lastSearch.RX.Height;
    
                else
                    app.filter_refRXLatitude.Value  = app.General.RFDataHub.DefaultRX.Latitude;
                    app.filter_refRXLongitude.Value = app.General.RFDataHub.DefaultRX.Longitude;
                    app.filter_refRXHeight.Value    = app.General.RFDataHub.DefaultRX.Height;
                end
            end

            app.pointsTable(end+1,:) = {'RX',                            ...
                                        app.filter_refRXLatitude.Value,  ...
                                        app.filter_refRXLongitude.Value, ...
                                        app.filter_refRXHeight.Value,    ...
                                        true};
        end

        %-----------------------------------------------------------------%
        function filter_calculateDistanceColumn(app)
            app.rfDataHub.Distance = round(single(deg2km(distance(app.rfDataHub.Latitude,         ...
                                                                  app.rfDataHub.Longitude,        ...
                                                                  app.filter_refRXLatitude.Value, ...
                                                                  app.filter_refRXLongitude.Value))), 1);
        end

        %-----------------------------------------------------------------%
        function filter_getReferenceSearch(app)
            if ~isempty(app.General.RFDataHub.lastSearch)
                filterType      = app.General.RFDataHub.lastSearch.Filter.ColumnLabel;
                filterValue     = app.General.RFDataHub.lastSearch.Filter.Value;
                filterOperation = app.General.RFDataHub.lastSearch.Filter.Operation;

            else
                filterType      = app.General.RFDataHub.DefaultFilter.ColumnLabel;
                filterValue     = app.General.RFDataHub.DefaultFilter.Value;
                filterOperation = app.General.RFDataHub.DefaultFilter.Operation;
            end

            hFilterNames        = findobj(app.filter_SecondaryTypePanel.Children,  'Type', 'uiradiobutton');
            hFilterOperations   = findobj(app.filter_SecondaryValuePanel.Children, 'Type', 'uitogglebutton');
            hFilterValues       = [app.filter_SecondaryNumValue1, ...
                                   app.filter_SecondaryNumValue2, ...
                                   app.filter_SecondaryTextFree,  ...
                                   app.filter_SecondaryTextList];

            listOfFilterNames   = {hFilterNames.Text};
            listOfOperations    = {hFilterOperations.Text};

            [~, idxFilter]      = ismember(filterType,      listOfFilterNames);
            [~, idxOperation]   = ismember(filterOperation, listOfOperations);

            if ~isempty(idxFilter) && ~isempty(idxOperation)
                hFilterNames(idxFilter).Value   = true;
                filter_typePanelSelectionChanged(app)

                hFilterOperations(idxOperation).Value = true;
                filter_SecondaryValuePanelSelectionChanged(app)

                hFilterValues   = hFilterValues(arrayfun(@(x) x.Visible, hFilterValues));
                for ii = 1:numel(hFilterValues)
                    hFilterValues(ii).Value = filterValue(ii);
                end
            end

            columnName       = filter_FilterType2ColumnNames(app, filterType);
            [~, columnIndex] = ismember(columnName, app.rfDataHub.Properties.VariableNames);

            newFilter = {'Node', 1, -3, filterType, filterOperation, columnIndex, filterValue, true, char(matlab.lang.internal.uuid())};

            filter_AddNewFilter(app, newFilter)
            filter_TreeBuilding(app)
        end

        %-----------------------------------------------------------------%
        function filter_AddNewFilter(app, newFilter)
            app.filterTable(end+1,[1:6,8:9]) = newFilter([1:6,8:9]);
            app.filterTable.Value{end} = newFilter{7};
        end

        %-----------------------------------------------------------------%
        function columnName = filter_FilterType2ColumnNames(app, filterType)
            filterTypes = ["Fonte", "Frequência", "Largura banda", "Classe emissão", "Entidade", "Fistel", "Serviço", "Estação", "UF",    "Município", "Distância"];
            columnNames = ["Source", "Frequency", "BW",            "EmissionClass",  "Name",     "Fistel", "Service", "Station", "State", "Location",  "Distance"];
            d = dictionary(filterTypes, columnNames);

            columnName = d(filterType);            
        end

        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            idx1 = find(strcmp(app.filterTable.Order, 'Node'))';
            if ~isempty(idx1)
                checkedNodes = {};
                for ii = idx1
                    parentNode = uitreenode(app.filter_Tree, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(ii),                        ...
                                                                                                            app.filterTable.Type{ii},                      ...
                                                                                                            app.filterTable.Operation{ii},                 ...
                                                                                                            filter_Value(app, app.filterTable.Value{ii})), ...
                                                             'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                    if app.filterTable.Enable(ii)
                        checkedNodes = [checkedNodes, parentNode];
                    end
    
                    idx2 = find(app.filterTable.RelatedID == ii)';                
                    for jj = idx2
                        childNode = uitreenode(parentNode, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(jj),                        ...
                                                                                                          app.filterTable.Type{jj},                      ...
                                                                                                          app.filterTable.Operation{jj},                 ...
                                                                                                          filter_Value(app, app.filterTable.Value{jj})), ...
                                                           'NodeData', jj, 'ContextMenu', app.filter_ContextMenu);
    
                        if app.filterTable.Enable(jj)
                            checkedNodes = [checkedNodes, childNode];
                        end
                    end
                end
                app.filter_Tree.CheckedNodes = checkedNodes;
                expand(app.filter_Tree, 'all')
            end

            app.filter_SecondaryReferenceFilter.Items = [{''}, cellstr("#" + string((idx1)))];
        end

        %-----------------------------------------------------------------%
        function filter_TableFiltering(app)
            app.progressDialog.Visible = 'visible';

            % Identifica registro inicialmente selecionado da tabela.
            initialSelectedRowID = '';
            if ~isempty(app.UITable.Selection)
                initialSelectedRowID = app.UITable.Data.ID{app.UITable.Selection(1)};
            end

            % Filtragem, preenchendo a tabela e o seu label (nº de linhas).
            idxRFDataHubArray = find(fcn.TableFiltering(app.rfDataHub, app.filterTable));
            columnGUINames    = {'ID', 'Frequency', 'Description', 'Service', 'Station', 'BW', 'Distance'};

            set(app.UITable, 'Selection', [], 'Data', app.rfDataHub(idxRFDataHubArray, columnGUINames))
            [app.UITable.Data, idxSort] = sortrows(app.UITable.Data, 'Distance');
            app.UITable.UserData = idxRFDataHubArray(idxSort);

            app.tableInfoNRows.Text = sprintf('#%d', numel(idxRFDataHubArray));

            % Aplicando a seleção inicial da tabela, caso aplicável.
            idxSelectedRow = 0;
            if ~isempty(app.UITable.Data)
                if ~isempty(initialSelectedRowID)
                    [~, idxSelectedRow] = ismember(initialSelectedRowID, app.UITable.Data.ID);
                end

                if ~idxSelectedRow
                    idxSelectedRow = 1;
                end

                app.UITable.Selection = [idxSelectedRow, 1];
                scroll(app.UITable, 'Row', idxSelectedRow)
            end            
            
            % Plots.
            plot_Stations(app)
            plot_RX(app)
            UITableSelectionChanged(app, struct('Source', app.UITable))
            
            plot.axes.StackingOrder.execute(app.UIAxes1, 'appAnalise:RFDATAHUB')
            app.restoreView(1) = struct('ID', 'app.UIAxes1', 'xLim', app.UIAxes1.LatitudeLimits, 'yLim', app.UIAxes1.LongitudeLimits, 'cLim', 'auto');

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function filter_AddStyle2Table(app, idxSelectedRow)
            removeStyle(app.UITable)
            if idxSelectedRow
                addStyle(app.UITable, uistyle('BackgroundColor', '#d8ebfa'), 'row', idxSelectedRow)
            end
        end


        %-----------------------------------------------------------------%
        % PLOT
        %-----------------------------------------------------------------%
        function plot_Stations(app)
            delete(findobj(app.UIAxes1.Children, '-not', 'Tag', 'FilterROI'))

            if ~isempty(app.UITable.Data)
                geolimits(app.UIAxes1, 'auto')

                idxRFDataHubArray = app.UITable.UserData;
                latitudeArray     = app.rfDataHub.Latitude(idxRFDataHubArray);
                longitudeArray    = app.rfDataHub.Longitude(idxRFDataHubArray);

                hStations = geoscatter(app.UIAxes1, latitudeArray, longitudeArray, ...
                    'MarkerEdgeColor', app.config_Station_Color.Value,             ...
                    'SizeData',        app.config_Station_Size.Value,              ...
                    'Tag', 'Stations');
                plot.datatip.Template(hStations, 'winRFDataHub.Geographic', app.UITable.Data)
            end
        end

        %-----------------------------------------------------------------%
        function plot_RX(app)
            RX = struct('Latitude',  app.filter_refRXLatitude.Value, ...
                        'Longitude', app.filter_refRXLongitude.Value);

            geoscatter(app.UIAxes1, RX.Latitude, RX.Longitude,     ...
                'Marker',          '^',                            ...
                'MarkerEdgeColor', app.config_RX_Color.Value,      ...
                'MarkerFaceColor', app.config_RX_Color.Value,      ...
                'SizeData',        44*app.config_RX_Size.Value,    ...
                'PickableParts',   'none',                         ...
                'Visible',         app.config_RX_Visibility.Value, ...
                'Tag',             'RX');
        end

        %-----------------------------------------------------------------%
        function plot_TX(app, idxRFDataHub, idxSelectedRow)            
            if isempty(idxRFDataHub)
                delete(findobj(app.UIAxes1.Children, 'Tag', 'TX'))

            else
                TX = struct('Latitude',  app.rfDataHub.Latitude(idxRFDataHub), ...
                            'Longitude', app.rfDataHub.Longitude(idxRFDataHub));

                % DataTip
                hTXDataTip = findobj(app.UIAxes1.Children, 'Type', 'datatip', 'Tag', 'TX');
                if isempty(hTXDataTip)
                    hStations = findobj(app.UIAxes1.Children, 'Tag', 'Stations');
                    datatip(hStations,                                     ...
                        'DataIndex',       idxSelectedRow,                 ...
                        'PickableParts',   'none',                         ...
                        'Visible',         app.config_TX_DataTipVisibility.Value, ...
                        'Tag',             'TX');
                else
                    hTXDataTip.DataIndex = idxSelectedRow;
                end

                % Scatter
                hTXScatter = findobj(app.UIAxes1.Children, 'Type', 'scatter', 'Tag', 'TX');
                if isempty(hTXScatter)
                    geoscatter(app.UIAxes1, TX.Latitude, TX.Longitude,   ...
                        'LineWidth',       2,                            ...
                        'Marker',          'o',                          ...
                        'MarkerEdgeColor', app.config_TX_Color.Value,    ...
                        'MarkerFaceColor', app.config_TX_Color.Value,    ...
                        'SizeData',        20*app.config_TX_Size.Value , ...
                        'PickableParts',   'none',                       ...
                        'Tag',             'TX');
                else
                    set(hTXScatter, 'LatitudeData',  TX.Latitude, ...
                                    'LongitudeData', TX.Longitude)
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_createRFLinkPlot(app)
            delete(findobj(app.UIAxes1.Children, 'Tag', 'RFLink'))
            cla(app.UIAxes2)
            delete(findobj(app.UIAxes3.Children, 'Tag', 'Azimuth'))

            if app.axesTool_simulationButton.UserData
                idxRFDataHub = getRFDataHubIndex(app);
                try
                    % OBJETOS TX e RX
                    [txObj, rxObj] = RFLinkObjects(app, idxRFDataHub);
        
                    % ELEVAÇÃO DO LINK TX-RX
                    [wayPoints3D, msgWarning] = Get(app.elevationObj, txObj, rxObj);
                    if ~isempty(msgWarning)
                        appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                    end
        
                    % PLOT: RFLink Map
                    geoplot(app.UIAxes1, wayPoints3D(:,1), wayPoints3D(:,2), Color='#c94756', LineStyle='-.', PickableParts='none', Tag='RFLink');
                    
                    % PLOT: RFLink
                    plot.RFLink(app.UIAxes2, txObj, rxObj, wayPoints3D, 'light')
    
                    % PLOT: RFLink Azimuth
                    Azimuth = deg2rad(app.UIAxes2.UserData.Azimuth);
                    polarplot(app.UIAxes3, Azimuth,            app.UIAxes3.RLim(1), 'MarkerEdgeColor', '#c94756', 'MarkerFaceColor', '#c94756', 'Marker', 'o', 'Tag', 'Azimuth')
                    polarplot(app.UIAxes3, Azimuth,            app.UIAxes3.RLim(2), 'MarkerEdgeColor', '#c94756', 'MarkerFaceColor', '#c94756', 'Marker', '^', 'Tag', 'Azimuth')
                    polarplot(app.UIAxes3, [Azimuth, Azimuth], app.UIAxes3.RLim,    'Color', '#c94756', 'LineStyle', '-.', 'Tag', 'Azimuth')
                    
                    % PLOT: RFLink Legend
                    txID = sprintf(' \\bfTX\n ID: %s\n Frequência: %.3f MHz\n Latitude: %.6fº\n Longitude: %.6fº\n Altura: %.1fm', ...
                        app.rfDataHub.ID(idxRFDataHub), txObj.TransmitterFrequency/1e+6, txObj.Latitude, txObj.Longitude, txObj.AntennaHeight);
                    rxID = sprintf('\\bfRX \nLatitude: %.6fº \nLongitude: %.6fº\n Altura: %.1fm', ...
                        rxObj.Latitude, rxObj.Longitude, rxObj.AntennaHeight);
    
                    if app.UIAxes2.UserData.TXAntennaElevation >= app.UIAxes2.UserData.RXAntennaElevation
                        txIDHeight = 0;
                        txIDAlign  = 'bottom';
                    else
                        txIDHeight = .95;
                        txIDAlign  = 'top';
                    end
    
                    text(app.UIAxes2, 0, txIDHeight, txID, Units='normalized', FontSize=10, Interpreter='tex', VerticalAlignment=txIDAlign, PickableParts='none');
                    text(app.UIAxes2, 1, 0,          rxID, Units='normalized', FontSize=10, Interpreter='tex', VerticalAlignment='bottom',  PickableParts='none', HorizontalAlignment='right');
    
                    app.stationInfo.UserData.RFLinkStatus = true;
                    
                catch
                    app.stationInfo.UserData.RFLinkStatus = false;
                end
            end

            plot_PolarAxesVisibility(app)
        end

        %-----------------------------------------------------------------%
        function plot_PolarAxesVisibility(app)
            % Visibilidade do eixo polar, com diagrama de radiação da
            % antena e azimute do enlace, caso aplicáveis.
            if isempty(app.UIAxes3.Children)
                app.search_SelectedRowInfoGrid.RowHeight{2} = 0;
            else
                app.search_SelectedRowInfoGrid.RowHeight{2} = 166;
            end
        end

        %-----------------------------------------------------------------%
        function [txSite, rxSite] = RFLinkObjects(app, idxRFDataHub)
            % txSite e rxSite estão como struct, mas basta mudar para "txsite" e 
            % "rxsite" que eles poderão ser usados em predições, uma vez que os 
            % campos da estrutura são idênticos às propriedades dos objetos.

            % TX
            txSite = struct('Name',                 'TX',                                                 ...
                            'TransmitterFrequency', double(app.rfDataHub.Frequency(idxRFDataHub)) * 1e+6, ...
                            'Latitude',             app.filter_refTXLatitude.Value,                       ...
                            'Longitude',            app.filter_refTXLongitude.Value,                      ...
                            'AntennaHeight',        app.filter_refTXHeight.Value);

            % RX
            rxSite = struct('Name',                 'RX',                            ...
                            'Latitude',             app.filter_refRXLatitude.Value,  ...
                            'Longitude',            app.filter_refRXLongitude.Value, ...
                            'AntennaHeight',        app.filter_refRXHeight.Value);
        end

        %-----------------------------------------------------------------%
        function restartChannelReport(app)
            app.chReportHTML.HTMLSource = 'pdfLogo.html';
            app.stationInfo.UserData.PDFDocStatus = false;
        end

        %-----------------------------------------------------------------%
        function getChannelReport(app, idxRFDataHub, operationType)
            arguments
                app
                idxRFDataHub
                operationType char {mustBeMember(operationType, {'OnlyCache', 'Cache+RealTime', 'RealTime'})}
            end

            URL = char(app.rfDataHub.URL(idxRFDataHub));
            if strcmp(URL, '-1')
                if app.stationInfo.UserData.PDFDocStatus
                    restartChannelReport(app)
                end
                return
            end

            % Caso a URL seja válida, cria-se objeto ChannelReport, caso
            % não existente.            
            if isempty(app.ChannelReportObj)
                app.ChannelReportObj = RF.ChannelReport;
            end

            % A operação poderá ser demorada caso seja do tipo "Cache+RealTime" 
            % ou "RealTime". 
            if ismember(operationType, {'Cache+RealTime', 'RealTime'})
                app.progressDialog.Visible = 'visible';
            end

            [idxCache, msgError] = Get(app.ChannelReportObj, URL, operationType);
            if ~isempty(idxCache)
                app.axesTool_PDFButton.UserData = true;
                app.GridLayout.ColumnWidth(7:8) = {10,'1x'};

                app.chReportHTML.HTMLSource = app.ChannelReportObj.cacheMapping.File{idxCache};
                app.stationInfo.UserData.PDFDocStatus = true;

            else
                restartChannelReport(app)

                if ismember(operationType, {'Cache+RealTime', 'RealTime'}) && ~isempty(msgError)
                    appUtil.modalWindow(app.UIFigure, 'error', msgError);
                end
            end

            if strcmp(app.progressDialog.Visible, 'visible')
                app.progressDialog.Visible = 'hidden';
            end
        end


        %-----------------------------------------------------------------%
        % AINDA NÃO REVISADO DAQUI PRA BAIXO... :(
        %-----------------------------------------------------------------%
        function layout_FilterOperationPanel(app, filterType, filterDefault)            
            layout_FilterDefaultValues(app)
            filter_SecondaryReferenceFilterValueChanged(app)

            hComp = findobj(app.filter_SecondaryValuePanel, 'Type', 'uitogglebutton');
            hCompTagFlag = contains(arrayfun(@(x) x.Tag, hComp, 'UniformOutput', false), filterType);

            set(hComp(hCompTagFlag),  Enable=1)
            set(hComp(~hCompTagFlag), Enable=0)

            selectedButton = app.filter_SecondaryValuePanel.SelectedObject;
            if ~selectedButton.Enable
                switch filterType
                    case {'textList1', 'ROI'}
                        app.filter_SecondaryOperation3.Value = 1; % CONTÉM

                    otherwise
                        app.filter_SecondaryOperation1.Value = 1; % IGUAL
                end
            end

            switch filterType
                case 'numeric'
                    app.filter_SecondaryNumValue1.Visible    = 1;
                    app.filter_SecondaryNumSeparator.Visible = 1;
                    app.filter_SecondaryNumValue2.Visible    = 1;
                    app.filter_SecondaryTextFree.Visible     = 0;
                    app.filter_SecondaryTextList.Visible     = 0;

                case 'textFree'
                    app.filter_SecondaryNumValue1.Visible    = 0;
                    app.filter_SecondaryNumSeparator.Visible = 0;
                    app.filter_SecondaryNumValue2.Visible    = 0;
                    app.filter_SecondaryTextFree.Visible     = 1;
                    app.filter_SecondaryTextList.Visible     = 0;

                case {'textList1', 'textList2', 'ROI'}
                    app.filter_SecondaryNumValue1.Visible    = 0;
                    app.filter_SecondaryNumSeparator.Visible = 0;
                    app.filter_SecondaryNumValue2.Visible    = 0;
                    app.filter_SecondaryTextFree.Visible     = 0;
                    app.filter_SecondaryTextList.Visible     = 1;
                    app.filter_SecondaryTextList.Items       = filterDefault;
            end
        end


        %-----------------------------------------------------------------%
        function points_NodeList(app)
            app.pointsTable(:,:) = [];

            for ii = 1:numel(app.CallingApp.specData)
                if app.CallingApp.specData(ii).GPS.Status
                    AntennaHeight = -1;
                    if isfield(app.CallingApp.specData(ii).MetaData.Antenna, 'Height')
                        AntennaHeight = str2double(extractBefore(app.CallingApp.specData(ii).MetaData.Antenna.Height, 'm'));
                    end

                    newPoint      = {'auto',                                    ...
                                     app.CallingApp.specData(ii).GPS.Latitude,  ...
                                     app.CallingApp.specData(ii).GPS.Longitude, ...
                                     app.CallingApp.specData(ii).GPS.Location,  ...
                                     AntennaHeight,                             ...
                                     false};
                    newPointFlag = true;

                    for jj = 1:height(app.pointsTable)
                        if isequal(newPoint(2:5), table2cell(app.pointsTable(jj,2:5)))
                            newPointFlag = false;
                            break
                        end
                    end

                    if newPointFlag
                        app.pointsTable(end+1,:) = newPoint;
                    end
                end
            end

            if isempty(app.pointsTable)
                % Caso não tenha um ponto de monitoração, insere-se a sede
                % da Anatel. Isso garante a visualização do mapa centralizada
                % em Brasília/DF.
                app.pointsTable(1,:) = {'manual', -15.805479, -47.882499, 'Brasília/DF', 30, true};
            else
                app.pointsTable.Reference(1) = true;
            end            

            points_TreeBuilding(app, 1)
        end

        %-----------------------------------------------------------------%
        function points_TreeBuilding(app, selectedID)
            if ~isempty(app.points_Tree.Children)
                delete(app.points_Tree.Children)
                drawnow nocallbacks
            end

            if ~isempty(app.pointsTable)
                for ii = 1:height(app.pointsTable)
                    antennaHeight = '';
                    if app.pointsTable.AntennaHeight(ii) > 0
                        antennaHeight = sprintf(', %dm', app.pointsTable.AntennaHeight(ii));
                    end

                    nodeTree = uitreenode(app.points_Tree, 'Text', sprintf('#%d: %s (%.6fº, %.6fº%s)', ii, app.pointsTable.Location{ii},  ...
                                                                                                           app.pointsTable.Latitude(ii),  ...
                                                                                                           app.pointsTable.Longitude(ii), ...
                                                                                                           antennaHeight),                ...
                                                           'NodeData', ii, 'ContextMenu', app.points_ContextMenu);
                    if app.pointsTable.Reference(ii)
                        nodeTree.Icon = 'Pin_18.png';
                    end
                end

                if ~ismember(selectedID, 1:height(app.pointsTable))
                    selectedID = 1;
                end
                app.points_Tree.SelectedNodes = app.points_Tree.Children(selectedID);

            else
                app.filter_refRXGrid.ColumnWidth{4} = 22;
                set(app.filter_refRXLatitude,      Value=-1, Editable=1)
                set(app.filter_refRXLongitude,     Value=-1, Editable=1)
                set(app.points_AntennaHeight, Value=-1, Editable=1)
            end

            points_TreeSelectionChanged(app)
        end

        %-----------------------------------------------------------------%
        function layout_FilterDefaultValues(app)
            app.filter_SecondaryNumValue1.Value       = -1;
            app.filter_SecondaryNumValue2.Value       = -1;
            app.filter_SecondaryTextFree.Value        = '';
            app.filter_SecondaryTextList.Items        = {};
            app.filter_SecondaryLogicalOperator.Items = {'E (&&)'};
        end

        function layout_CartesianAxesLayout(app)
            if app.tool_HistogramAxesVisibility.Value
                app.tool_simulationAxesVisibility.Value = 0;

                layout_CartesianAxesSwitch(app, 'Visible',   2, [1 2])
                layout_CartesianAxesSwitch(app, 'Visible',   3, [3 1])
                layout_CartesianAxesSwitch(app, 'Invisible', 4, -1)

            else
                app.tool_simulationAxesVisibility.Value = 1;

                layout_CartesianAxesSwitch(app, 'Invisible', 2, -1)
                layout_CartesianAxesSwitch(app, 'Invisible', 3, -1)
                layout_CartesianAxesSwitch(app, 'Visible',   4, [1 3])
            end
        end
        
        %-----------------------------------------------------------------%
        function layout_CartesianAxesSwitch(app, axesVisibility, axesID, axesTiledPosition)
            switch axesVisibility
                case 'Invisible'
                    eval(sprintf('set(app.UIAxes%d,          Visible=0)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Children, Visible=0)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Toolbar,  Visible=0)', axesID))

                case 'Visible'
                    eval(sprintf('set(app.UIAxes%d,          Visible=1)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Children, Visible=1)', axesID))
                    eval(sprintf('set(app.UIAxes%d.Toolbar,  Visible=1)', axesID))
    
                    eval(sprintf('app.UIAxes%d.Layout.Tile = axesTiledPosition(1);', axesID))
                    eval(sprintf('app.UIAxes%d.Layout.TileSpan = [1 axesTiledPosition(2)];', axesID))
            end
        end

        %-----------------------------------------------------------------%
        function layout_simulationLink(app, idx)
            simulationFlag = false;

            app.axesTool_PDFButton.Enable = 0;
            if ~isempty(idx)
                if app.UITable.Data.URL(idx) ~= "-1"
                    app.axesTool_PDFButton.Enable = 1;
                end

                if ~isempty(app.pointsTable)
                    simulationFlag = true;
                end
            end

            if simulationFlag
                app.link_LinkInfo.HTMLSource = textFormatGUI.struct2PrettyPrintList(misc_simulationLinkSummary(app, idx), 'delete');
                app.axesTool_simulationButton.Enable    = 1;

            else
                app.link_LinkInfo.HTMLSource = ' ';
                app.axesTool_simulationButton.Enable    = 0;
            end
        end

        %-----------------------------------------------------------------%
        function linkSummary = misc_simulationLinkSummary(app, idx)
            [txObj, rxObj]  = misc_simulationObjects(app, idx);
            txObj.Frequency = sprintf('%.3f MHz', txObj.TransmitterFrequency/1e+6);

            linkSummary(1)  = struct('group', 'ESTAÇÃO TRANSMISSORA (TX)', 'value', txObj);
            linkSummary(2)  = struct('group', 'ESTAÇÃO RECEPTORA (RX)',    'value', rxObj);
        end

        %-----------------------------------------------------------------%
        function [txObj, rxObj] = misc_simulationObjects(app, idxStation)
            txIndex = str2double(extractAfter(app.UITable.Data.ID{idxStation}, '#'));
            rxIndex = find(app.pointsTable.Reference, 1);

            txObj = struct('TransmitterFrequency', app.rfDataHub.Frequency(txIndex) * 1e+6,                ...
                           'Latitude',             app.rfDataHub.Latitude(txIndex),                        ...
                           'Longitude',            app.rfDataHub.Longitude(txIndex),                       ...
                           'AntennaHeight',        str2double(char(app.rfDataHub.AntennaHeight(txIndex))), ...
                           'AntennaPattern',       char(app.rfDataHub.AntennaPattern(txIndex)),            ...
                           'Description',          app.UITable.Data.Description{idxStation},               ...
                           'Service',              app.rfDataHub.Service(txIndex),                         ...
                           'Station',              app.rfDataHub.Station(txIndex),                         ...
                           'ID',                   app.UITable.Data.ID{idxStation});

            rxObj = struct('Latitude',             app.pointsTable.Latitude(rxIndex),  ...
                           'Longitude',            app.pointsTable.Longitude(rxIndex), ...
                           'AntennaHeight',        app.pointsTable.AntennaHeight(rxIndex));
        end

        %-----------------------------------------------------------------%
        function filterValue = filter_Value(app, filterValue)
            if isnumeric(filterValue)
                if isscalar(filterValue)
                    filterValue = string(filterValue);
                else
                    filterValue = "[" + strjoin(string(filterValue), ', ') + "]";
                end
            elseif ischar(filterValue)
                filterValue = sprintf('"%s"', upper(filterValue));
            else
                filterValue = '⌂';
            end
        end

        %-----------------------------------------------------------------%
        function filter_ROICallbacks(app, src, event)
            switch(event.EventName)
                case 'MovingROI'
                    plot.axes.Interactivity.DefaultDisable(app.UIAxes1)
                    
                case 'ROIMoved'
                    plot.axes.Interactivity.DefaultEnable(app.UIAxes1)
                    
                    filter_TableFiltering(app)
                    if isvalid(event.Source)
                        uistack(event.Source, 'top')
                    end
            end            
        end


        %-----------------------------------------------------------------%
        function plot_simulationLink(app)
            % VALIDAÇÕES
            if isempty(app.UITable.Selection) || isempty(app.pointsTable)
                return
            end
            idx = app.UITable.Selection(1);

            d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');
            
            % OBJETOS TX e RX
            [txObj, rxObj] = misc_simulationObjects(app, idx);       

            % ELEVAÇÃO DO LINK TX-RX
            nPoints = str2double(app.misc_PointsPerLink.Value);
            Server  = app.misc_ElevationAPISource.Value;

            [wayPoints3D, msgWarning] = Get(app.elevationObj, txObj, rxObj, nPoints, false, Server);
            if ~isempty(msgWarning)
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
            end

            % PLOT: MAPA
            delete(findobj(app.UIAxes1, 'Tag', 'RFLinkView', '-or', 'Tag', 'RFLink'))
            geoplot(app.UIAxes1, wayPoints3D(:,1), wayPoints3D(:,2), Color='#c94756', LineStyle='-.', PickableParts='none', Tag='RFLinkView');

            % PLOT: RFLink
            plot.RFLink(app.UIAxes2, txObj, rxObj, wayPoints3D)

            delete(d)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            try
                % Inicialmente, verifica se foi passado mainapp como argumento 
                % de entrada. 
                % • Caso sim, trata-se de app executado como módulo do appAnalise.
                % • Caso não, trata-se de app executado em modo standalone.
                if nargin == 1
                    app.standaloneFlag = true;
    
                    appUtil.disablingWarningMessages()
                    set(app.UIFigure, 'Name', 'RFDataHub', 'Icon', 'mosaic_18Gray.png')
                else
                    app.standaloneFlag = false;
    
                    app.CallingApp    = mainapp;
                    app.General       = mainapp.General;
                    app.rootFolder    = mainapp.rootFolder;
                    app.executionMode = mainapp.executionMode;
                    app.specData      = mainapp.specData;
                end
    
                app.GridLayout.ColumnWidth(7:8) = {0,0};
                jsBackDoor_Initialization(app)
    
                % Em sendo executado como módulo do appAnalise, o app pode
                % estar em modo DOCK ou UNDOCK, o que é definido em configuração
                % no próprio appAnalise.
                if app.isDocked
                    drawnow
                    startup_Controller(app)
                else
                    appUtil.winPosition(app.UIFigure)
                    startup_timerCreation(app)
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            if ~isempty(app.CallingApp)
                appBackDoor(app.CallingApp, app, 'closeFcn')
            end
            delete(app)
            
        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 1 other component
        function general_LeftPanelMenuSelectionChanged(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.menu_MainGrid.ColumnWidth   = columnWidth;
            app.menuUnderline.Layout.Column = idx;
            app.ControlTabGroup.SelectedTab = app.ControlTabGroup.Children(idx);

            jsBackDoor_Customizations(app, idx)
            focus(app.jsBackDoor)
            
        end

        % Image clicked function: axesTool_PDFButton, 
        % ...and 3 other components
        function tool_InteractionImageClicked(app, event)
            
            focus(app.jsBackDoor)
            
            switch event.Source
                case app.tool_ControlPanelVisibility
                    if app.GridLayout.ColumnWidth{2}
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowRight_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {0,0};
                    else
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {325,10};
                    end

                case app.axesTool_TableVisibility
                    app.axesTool_TableVisibility.UserData = ~app.axesTool_TableVisibility.UserData;
                    if app.axesTool_TableVisibility.UserData
                        app.GridLayout.RowHeight(4:5) = {10,'.4x'};
                    else
                        app.GridLayout.RowHeight(4:5) = {0,0};
                    end

                case app.axesTool_PDFButton
                    app.axesTool_PDFButton.UserData = ~app.axesTool_PDFButton.UserData;
                    if app.axesTool_PDFButton.UserData
                        app.GridLayout.ColumnWidth(7:8) = {10,'1x'};
                    else
                        app.GridLayout.ColumnWidth(7:8) = {0,0};
                    end

                case app.axesTool_simulationButton
                    app.axesTool_simulationButton.UserData = ~app.axesTool_simulationButton.UserData;
                    if app.axesTool_simulationButton.UserData
                        app.UIAxes1.Layout.TileSpan = [1,1];
                        set(findobj(app.UIAxes2), 'Visible', 1)
                    else
                        app.UIAxes1.Layout.TileSpan = [2,2];
                        set(findobj(app.UIAxes2), 'Visible', 0)
                    end
                    plot_createRFLinkPlot(app)
            end

        end

        % Image clicked function: tool_ExportButton
        function tool_exportButtonPushed(app, event)
            
                defaultFilename = class.Constants.DefaultFileName(app.config_Folder_userPath.Value, 'RFDataHub', -1);

                [fileName, filePath, fileIndex] = uiputfile({'*.xlsx', 'Excel (*.xlsx)'}, '', defaultFilename);
                figure(app.UIFigure)
                
                if fileIndex
                    d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento...');

                    try
                        idxRFDataHubArray = app.UITable.UserData;
                        tempRFDataHub = class.RFDataHub.ColumnNames(app.rfDataHub(idxRFDataHubArray,1:29), 'eng2port');
                        writetable(tempRFDataHub, fullfile(filePath, fileName), 'WriteMode', 'overwritesheet')
                    catch ME
                        appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
                    end

                    delete(d)
                end

        end

        % Image clicked function: axesTool_RegionZoom, axesTool_RestoreView
        function axesTool_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.axesTool_RestoreView
                    geolimits(app.UIAxes1, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.axesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes1, app.axesTool_RegionZoom)
            end

        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            
            [idxRFDataHub, idxSelectedRow] = getRFDataHubIndex(app);
            
            if isequal(app.stationInfo.UserData.idxRFDataHub, idxRFDataHub)
                return
            end

            % Reinicializa componentes da GUI.
            filter_AddStyle2Table(app, idxSelectedRow)
            delete(findobj(app.UIAxes1.Children, 'Tag', 'TX'))
            cla(app.UIAxes3)

            app.stationInfo.UserData.idxRFDataHub = idxRFDataHub;
            
            if isempty(idxRFDataHub)
                app.filter_refTXLatitude.Value  = -1;
                app.filter_refTXLongitude.Value = -1;
                app.filter_refTXHeight.Value    = 0;
                app.stationInfo.HTMLSource      = '';
                restartChannelReport(app)

            else
                % Estação transmissora - TX
                txAntennaHeight = str2double(char(app.rfDataHub.AntennaHeight(idxRFDataHub)));
                if txAntennaHeight <= 0
                    txAntennaHeight = 10;
                end

                app.filter_refTXLatitude.Value  = double(app.rfDataHub.Latitude(idxRFDataHub));
                app.filter_refTXLongitude.Value = double(app.rfDataHub.Longitude(idxRFDataHub));
                app.filter_refTXHeight.Value    = txAntennaHeight;

                % Painel HTML
                htmlContent = auxApp.rfdatahub.htmlCode_StationInfo(app.rfDataHub, idxRFDataHub, app.rfDataHubLOG);
                app.stationInfo.HTMLSource = htmlContent;
                    
                % Plot "AntennaPattern"
                % O bloco try/catch protege possível erro no parser da informação
                % do Mosaico. Como exposto em class.RFDataHub.parsingAntennaPattern
                % foram identificados quatro formas de armazenar a informação.
                if app.rfDataHub.AntennaPattern(idxRFDataHub) ~= "-1"
                    try
                        [angle, gain] = class.RFDataHub.parsingAntennaPattern(app.rfDataHub.AntennaPattern(idxRFDataHub), 360);
                        polarplot(app.UIAxes3, angle, gain, 'Tag', 'AntennaPattern')
                    catch
                    end
                end

                % Plot "TX"
                plot_TX(app, idxRFDataHub, idxSelectedRow)

                % Plot "RFLink"
                plot_createRFLinkPlot(app)                

                % Atualiza "Relatório Canal", do MOSAICO, apenas se o relatório 
                % estiver em cache.
                getChannelReport(app, idxRFDataHub, 'OnlyCache')
            end
            
        end

        % Menu selected function: points_delButton, points_editRowButton, 
        % ...and 1 other component
        function points_ContextMenuCallbacks(app, event)
            
            idx  = app.points_Tree.SelectedNodes.NodeData;

            if ~isempty(idx)
                editFlag = false;
                refIndex = find(app.pointsTable.Reference, 1);

                switch event.Source
                    case app.points_referenceButton
                        app.pointsTable.Reference      = zeros(height(app.pointsTable), 1, 'logical');
                        app.pointsTable.Reference(idx) = true;

                        editFlag = true;                        
    
                    case app.points_editRowButton
                        if app.filter_refRXGrid.ColumnWidth{4}
                            app.filter_refRXGrid.ColumnWidth{4} = 0;
        
                            app.filter_refRXLatitude.Editable      = 0;
                            app.filter_refRXLongitude.Editable     = 0;
                            app.points_AntennaHeight.Editable = 0;
                            points_TreeSelectionChanged(app)
                            return

                        else
                            app.filter_refRXGrid.ColumnWidth{4} = 22;
        
                            app.filter_refRXLatitude.Editable      = 1;
                            app.filter_refRXLongitude.Editable     = 1;
                            app.points_AntennaHeight.Editable = 1;
                            focus(app.filter_refRXLatitude)
                        end
    
                    case app.points_delButton                            
                        app.pointsTable(idx,:) = [];

                        if idx == refIndex
                            editFlag = true;
                            if ~isempty(app.pointsTable)
                                app.pointsTable.Reference(1) = true;
                            end
                        end
                end
                
                points_TreeBuilding(app, idx)
    
                if editFlag
                    filter_calculateDistanceColumn(app)
                    filter_TableFiltering(app)
                end
            end

        end

        % Value changed function: filter_refRXLatitude, 
        % ...and 1 other component
        function points_editButtonPushed(app, event)
            
            switch event.Source
                case app.filter_refRXLatitude
                    focus(app.filter_refRXLongitude)

                case app.filter_refRXLongitude
                    focus(app.points_AntennaHeight)

                case app.points_AntennaHeight
                    focus(app.points_editButton)

                case app.points_editButton
                    refIndex = find(app.pointsTable.Reference, 1);
                    editFlag = true;
                    
                    if isempty(app.pointsTable)
                        idx = 1;
                    else
                        idx  = app.points_Tree.SelectedNodes.NodeData;
                        if idx ~= refIndex
                            editFlag = false;
                        end
                    end

                    newLatitude  = app.filter_refRXLatitude.Value;
                    newLongitude = app.filter_refRXLongitude.Value;
                    newLocation  = fcn.gpsFindCity(struct('Latitude', newLatitude, 'Longitude', newLongitude));
                    newHeight    = app.points_AntennaHeight.Value;

                    app.pointsTable(idx,:) = {'manual',     ...
                                              newLatitude,  ...
                                              newLongitude, ...
                                              newLocation,  ...
                                              newHeight,    ...
                                              editFlag};

                    points_TreeBuilding(app, idx)
                    
                    if editFlag
                        filter_calculateDistanceColumn(app)
                        filter_TableFiltering(app)
                    end

                    % O painel de visualização/edição das coordenadas do ponto
                    % selecionado.
                    app.filter_refRXGrid.ColumnWidth{4} = 0;
                    set(app.filter_refRXLatitude,      Editable=0)
                    set(app.filter_refRXLongitude,     Editable=0)
                    set(app.points_AntennaHeight, Editable=0)
            end

        end

        % Selection changed function: filter_SecondaryTypePanel
        function filter_typePanelSelectionChanged(app, event)
                        
            selectedButton = app.filter_SecondaryTypePanel.SelectedObject;
            switch selectedButton
                case app.filter_SecondaryType1                            % BASE DE DADOS
                    filterType    = 'textList1';
                    filterDefault = app.rfDataHubSummary.Source.RawCategories;

                case {app.filter_SecondaryType2, ...                      % FREQUÊNCIA
                      app.filter_SecondaryType3, ...                      % LARGURA BANDA
                      app.filter_SecondaryType6, ...                      % FISTEL
                      app.filter_SecondaryType7, ...                      % SERVIÇO
                      app.filter_SecondaryType8, ...                      % ESTAÇÃO
                      app.filter_SecondaryType11}                         % DISTÂNCIA
                    filterType    = 'numeric';
                    filterDefault = {};

                case {app.filter_SecondaryType4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_SecondaryType5, ...                      % ENTIDADE
                      app.filter_SecondaryType10}                         % MUNICÍPIO
                    filterType    = 'textFree';
                    filterDefault = {};

                case app.filter_SecondaryType9                            % UF
                    filterType    = 'textList2';
                    filterDefault = app.rfDataHubSummary.State.Categories;

                case app.filter_SecondaryType12                           % ROI
                    filterType    = 'ROI';
                    filterDefault = {'ROI:Círculo', 'ROI:Retângulo', 'ROI:Polígono'};
            end

            layout_FilterOperationPanel(app, filterType, filterDefault)
            filter_SecondaryValuePanelSelectionChanged(app)
            
        end

        % Selection changed function: filter_SecondaryValuePanel
        function filter_SecondaryValuePanelSelectionChanged(app, event)
            
            selectedButton = app.filter_SecondaryValuePanel.SelectedObject;
            switch selectedButton
                case {app.filter_SecondaryOperation9, app.filter_SecondaryOperation10}
                    app.filter_SecondaryNumSeparator.Visible = 1;
                    app.filter_SecondaryNumValue2.Visible    = 1;
                    
                otherwise
                    app.filter_SecondaryNumSeparator.Visible = 0;
                    app.filter_SecondaryNumValue2.Visible    = 0;
            end
            
        end

        % Value changed function: filter_SecondaryReferenceFilter
        function filter_SecondaryReferenceFilterValueChanged(app, event)
            
            value = app.filter_SecondaryReferenceFilter.Value;
            if isempty(value)
                app.filter_SecondaryLogicalOperator.Items = {'E (&&)'};
            else
                app.filter_SecondaryLogicalOperator.Items = {'OU (||)'};
            end
            
        end

        % Image clicked function: filter_AddImage
        function filter_addFilter(app, event)
            
            selectedFilterType      = app.filter_SecondaryTypePanel.SelectedObject;
            selectedFilterOperation = app.filter_SecondaryValuePanel.SelectedObject;

            if isempty(app.filter_SecondaryReferenceFilter.Value)
                Order     = 'Node';
                RelatedID = -1;
            else
                Order     = 'Child';
                RelatedID = str2double(app.filter_SecondaryReferenceFilter.Value(2:end));
            end
            UUID = char(matlab.lang.internal.uuid());

            switch selectedFilterType
                case {app.filter_SecondaryType1, ...                      % BASE DE DADOS
                      app.filter_SecondaryType9}                          % UF
                    Value = app.filter_SecondaryTextList.Value;

                case {app.filter_SecondaryType2, ...                      % FREQUÊNCIA
                      app.filter_SecondaryType3, ...                      % LARGURA BANDA
                      app.filter_SecondaryType6, ...                      % FISTEL
                      app.filter_SecondaryType7, ...                      % SERVIÇO
                      app.filter_SecondaryType8, ...                      % ESTAÇÃO
                      app.filter_SecondaryType11}                         % DISTÂNCIA

                    if ismember(selectedFilterOperation.Text, {'<>', '><'})
                        Value = [app.filter_SecondaryNumValue1.Value, ...
                                 app.filter_SecondaryNumValue2.Value];
                    else
                        Value = app.filter_SecondaryNumValue1.Value;
                    end

                case {app.filter_SecondaryType4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_SecondaryType5, ...                      % ENTIDADE
                      app.filter_SecondaryType10}                         % MUNICÍPIO
                    Value = app.filter_SecondaryTextFree.Value;

                case app.filter_SecondaryType12                           % ROI
                    hROI = [];

                    plot.axes.Interactivity.DefaultDisable(app.UIAxes1)
                    pause(.1)

                    switch app.filter_SecondaryTextList.Value
                        case 'ROI:Círculo';   roiFcn = 'drawcircle';    roiNameArgument = '';
                        case 'ROI:Retângulo'; roiFcn = 'drawrectangle'; roiNameArgument = 'Rotatable=true, ';
                        case 'ROI:Polígono';  roiFcn = 'drawpolygon';   roiNameArgument = '';
                    end
                    eval(sprintf('hROI = %s(app.UIAxes1, Color=[0.40,0.73,0.88], LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI", UserData="%s");', roiFcn, roiNameArgument, UUID))
                    plot.axes.Interactivity.DefaultEnable(app.UIAxes1)

                    if isempty(hROI.Position)
                        delete(hROI)
                        return
                    end
                    addlistener(hROI, 'MovingROI', @app.filter_ROICallbacks);
                    addlistener(hROI, 'ROIMoved',  @app.filter_ROICallbacks);
                    addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plot.axes.Interactivity.DeleteROIListeners(src));
                    Value = {hROI};                    
            end

            newFilter     = {Order, height(app.filterTable)+1, RelatedID,           ...
                             selectedFilterType.Text, selectedFilterOperation.Text, ...
                             str2double(selectedFilterType.Tag), Value, true, UUID};
            newFilterFlag = true;

            for ii = 1:height(app.filterTable)
                if isequal(app.filterTable{ii,[4,5,7]}, newFilter([4,5,7]))
                    newFilterFlag = false;
                    break
                end
            end

            if ~newFilterFlag
                msg = 'Filtro já incluído!';
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                if exist('hROI', 'var')
                    delete(hROI)
                end
                
                return
            end

            filter_AddNewFilter(app, newFilter)
            filter_TreeBuilding(app)
            filter_TableFiltering(app)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_Tree.SelectedNodes)
                        return
                    end
                    idx1 = arrayfun(@(x) x.NodeData, app.filter_Tree.SelectedNodes);
                    
                    % Identifica se algum dos fluxos selecionado é um nó de
                    % filtros, inserindo na lista os seus filhos.
                    idx1 = [idx1, find(ismember(app.filterTable.RelatedID, idx1))'];

                case app.filter_delAllButton
                    idx1 = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx1)
                % Apaga os ROI's, caso existentes.
                idx2 = find(strcmp(app.filterTable.Type, 'ROI'))';
                for ii = idx2
                    if ismember(ii, idx1)
                        delete(findobj(app.UIAxes1.Children, Tag=sprintf('ROI_%s', app.filterTable.uuid{ii})))
                    end
                end                

                % E depois atualiza a tabela de filtros, a tabela renderizada
                % no app e, por fim, o plot.
                app.filterTable(idx1,:) = [];

                filter_TreeBuilding(app)
                filter_TableFiltering(app)
            end

        end

        % Callback function: filter_Tree
        function filter_TreeCheckedNodesChanged(app, event)
            
            hTree             = findobj(app.filter_Tree, '-not', 'Type', 'uicheckboxtree');
            hTreeNodeDataList = arrayfun(@(x) x.NodeData, hTree);
            hCheckedNodeData  = arrayfun(@(x) x.NodeData, app.filter_Tree.CheckedNodes);

            disableIndexList  = setdiff(hTreeNodeDataList, hCheckedNodeData);
            enableIndexList   = setdiff((1:height(app.filterTable))', disableIndexList);

            app.filterTable.Enable(disableIndexList) = false;
            app.filterTable.Enable(enableIndexList)  = true;

            filter_TableFiltering(app)
            
        end

        % Callback function: config_RX_Color, config_Station_Color, 
        % ...and 1 other component
        function config_geoAxesColorParameterChanged(app, event)
            
            selectedColor = event.Source.Value;

            switch event.Source
                case app.config_Station_Color
                    set(findobj(app.UIAxes1.Children, 'Tag', 'Stations'), 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                case app.config_TX_Color
                    set(findobj(app.UIAxes1.Children, 'Type', 'scatter', 'Tag', 'TX'), 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                case app.config_RX_Color
                    set(findobj(app.UIAxes1.Children, 'Tag', 'RX'),  'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
            end

        end

        % Callback function: config_Basemap, config_Colormap, 
        % ...and 4 other components
        function config_geoAxesOthersParametersChanged(app, event)
            
            switch event.Source
                case app.config_Basemap
                    if ~strcmp(app.UIAxes1.Basemap, event.Value)
                        switch event.Value
                            case 'none'
                                set(app.UIAxes1, 'Basemap', event.Value, 'Box', 'on', 'AxisColor', [.8,.8,.8])
                                app.GridLayout.ColumnWidth{4} = 0;
                                ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'backgroundColor', 'transparent')

                            otherwise
                                set(app.UIAxes1, 'Basemap', event.Value, 'Box', 'off', 'AxisColor', 'white')
                                app.GridLayout.ColumnWidth{4} = 5;
                                ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'backgroundColor', '#ffffff')
                        end

                        initialStationColor = app.config_Station_Color.Value;
                        switch event.Value
                            case {'streets-dark', 'satellite'}
                                if isequal(initialStationColor, [0.7882 0.2784 0.3412])
                                    StationDarkColor = [0,1,1];
                                    app.config_Station_Color.Value = StationDarkColor;
                                    set(findobj(app.UIAxes1.Children, 'Tag', 'Stations'), 'MarkerEdgeColor', StationDarkColor)
                                end                                

                            otherwise
                                if isequal(initialStationColor, [0,1,1])
                                    StationLightColor = [0.7882 0.2784 0.3412];
                                    app.config_Station_Color.Value = StationLightColor;
                                    set(findobj(app.UIAxes1.Children, 'Tag', 'Stations'), 'MarkerEdgeColor', StationLightColor)
                                end
                        end
                    end

                case app.config_Colormap
                    if strcmp(app.UIAxes1.UserData.Colormap, event.Value)
                        return
                    end

                    plot.axes.Colormap(app.UIAxes1, event.Value)

                case app.config_TX_DataTipVisibility
                    set(findobj(app.UIAxes1.Children, 'Type', 'datatip', 'Tag', 'TX'), 'Visible', app.config_TX_DataTipVisibility.Value)

                case app.config_Station_Size
                    set(findobj(app.UIAxes1.Children, 'Type', 'scatter', 'Tag', 'Stations'), 'SizeData', event.Value)

                case app.config_TX_Size
                    set(findobj(app.UIAxes1.Children, 'Type', 'scatter', 'Tag', 'TX'),       'SizeData', 20*event.Value)

                case app.config_RX_Size
                    set(findobj(app.UIAxes1.Children,                    'Tag', 'RX'),       'SizeData', 44*event.Value)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.toolGrid = uigridlayout(app.GridLayout);
            app.tool_ControlPanelVisibility = uiimage(app.toolGrid);
            app.tool_ControlPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.jsBackDoor = uihtml(app.toolGrid);
            app.tool_ExportButton = uiimage(app.toolGrid);
            app.tool_ExportButton.ImageClickedFcn = createCallbackFcn(app, @tool_exportButtonPushed, true);
            app.tool_ExportButton.ImageSource = 'Export_16.png';
            app.axesTool_TableVisibility = uiimage(app.toolGrid);
            app.axesTool_TableVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.axesTool_TableVisibility.Tooltip = {'Visibilidade da tabela'};
            app.axesTool_TableVisibility.ImageSource = 'View_16.png';
            app.axesTool_PDFButton = uiimage(app.toolGrid);
            app.axesTool_PDFButton.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.axesTool_PDFButton.Enable = 'off';
            app.axesTool_PDFButton.Tooltip = {'Relatório do registro selecionado'; '(limitado à radiodifusão)'};
            app.axesTool_PDFButton.ImageSource = 'Publish_PDF_16.png';
            app.axesTool_simulationButton = uiimage(app.toolGrid);
            app.axesTool_simulationButton.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.axesTool_simulationButton.Tooltip = {'Perfil de terreno entre registro selecionado (TX) '; 'e estação de referência (RX)'};
            app.axesTool_simulationButton.ImageSource = 'Publish_HTML_16.png';
            app.Image = uiimage(app.toolGrid);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineV.png');
            app.tableInfoNRows = uilabel(app.toolGrid);
            app.tableInfoNRows.Text = '# 0 ';
            app.ControlTabGrid = uigridlayout(app.GridLayout);
            app.ControlTabGroup = uitabgroup(app.ControlTabGrid);
            app.Tab_1 = uitab(app.ControlTabGroup);
            app.Tab1_Grid = uigridlayout(app.Tab_1);
            app.filter_refTXIcon = uiimage(app.Tab1_Grid);
            app.filter_refTXIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Pin_32.png');
            app.filter_refTXLabel = uilabel(app.Tab1_Grid);
            app.filter_refTXLabel.Interpreter = 'html';
            app.filter_refTXLabel.Text = {'<b>Estação transmissora - TX</b>'; '<font style="font-size: 9px; color: gray;">(Registro selecionado em tabela)</font>'};
            app.filter_refTXPanel = uipanel(app.Tab1_Grid);
            app.filter_refTXGrid = uigridlayout(app.filter_refTXPanel);
            app.filter_refTXLatitudeLabel = uilabel(app.filter_refTXGrid);
            app.filter_refTXLatitudeLabel.Text = 'Latitude:';
            app.filter_refTXLatitude = uieditfield(app.filter_refTXGrid, 'numeric');
            app.filter_refTXLatitude.ValueDisplayFormat = '%.6f';
            app.filter_refTXLatitude.Editable = 'off';
            app.filter_refTXLatitude.Value = -1;
            app.filter_refTXLongitudeLabel = uilabel(app.filter_refTXGrid);
            app.filter_refTXLongitudeLabel.Text = 'Longitude:';
            app.filter_refTXLongitude = uieditfield(app.filter_refTXGrid, 'numeric');
            app.filter_refTXLongitude.ValueDisplayFormat = '%.6f';
            app.filter_refTXLongitude.Editable = 'off';
            app.filter_refTXLongitude.Value = -1;
            app.filter_refTXHeightLabel = uilabel(app.filter_refTXGrid);
            app.filter_refTXHeightLabel.Text = 'Altura (metros):';
            app.filter_refTXHeight = uieditfield(app.filter_refTXGrid, 'numeric');
            app.filter_refTXHeight.Limits = [0 Inf];
            app.filter_refTXHeight.RoundFractionalValues = 'on';
            app.filter_refTXHeight.ValueDisplayFormat = '%d';
            app.filter_refTXHeight.Editable = 'off';
            app.search_SelectedRowInfoPanel = uipanel(app.Tab1_Grid);
            app.search_SelectedRowInfoGrid = uigridlayout(app.search_SelectedRowInfoPanel);
            app.stationInfo = uihtml(app.search_SelectedRowInfoGrid);
            app.stationInfo.HTMLSource = ' ';
            app.PlotPanel = uipanel(app.search_SelectedRowInfoGrid);
            app.filter_refRXEdit_2 = uiimage(app.Tab1_Grid);
            app.filter_refRXEdit_2.ImageSource = 'Edit_32.png';
            app.Tab_2 = uitab(app.ControlTabGroup);
            app.Tab2_Grid = uigridlayout(app.Tab_2);
            app.filter_SecondaryLabel = uilabel(app.Tab2_Grid);
            app.filter_SecondaryLabel.Text = 'FILTRO';
            app.filter_SecondaryTypePanel = uibuttongroup(app.Tab2_Grid);
            app.filter_SecondaryTypePanel.SelectionChangedFcn = createCallbackFcn(app, @filter_typePanelSelectionChanged, true);
            app.filter_SecondaryType1 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType1.Tag = '16';
            app.filter_SecondaryType1.Text = 'Fonte';
            app.filter_SecondaryType1.Value = true;
            app.filter_SecondaryType2 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType2.Tag = '1';
            app.filter_SecondaryType2.Text = 'Frequência';
            app.filter_SecondaryType3 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType3.Tag = '13';
            app.filter_SecondaryType3.Text = 'Largura banda';
            app.filter_SecondaryType4 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType4.Tag = '12';
            app.filter_SecondaryType4.Text = 'Classe emissão';
            app.filter_SecondaryType5 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType5.Tag = '2';
            app.filter_SecondaryType5.Text = 'Entidade';
            app.filter_SecondaryType6 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType6.Tag = '3';
            app.filter_SecondaryType6.Text = 'Fistel';
            app.filter_SecondaryType7 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType7.Tag = '4';
            app.filter_SecondaryType7.Text = 'Serviço';
            app.filter_SecondaryType8 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType8.Tag = '5';
            app.filter_SecondaryType8.Text = 'Estação';
            app.filter_SecondaryType9 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType9.Tag = '10';
            app.filter_SecondaryType9.Text = 'UF';
            app.filter_SecondaryType10 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType10.Tag = '9';
            app.filter_SecondaryType10.Text = 'Município';
            app.filter_SecondaryType11 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType11.Tag = '32';
            app.filter_SecondaryType11.Text = 'Distância';
            app.filter_SecondaryType12 = uiradiobutton(app.filter_SecondaryTypePanel);
            app.filter_SecondaryType12.Tag = '-1';
            app.filter_SecondaryType12.Text = 'ROI';
            app.filter_SecondaryValuePanel = uibuttongroup(app.Tab2_Grid);
            app.filter_SecondaryValuePanel.SelectionChangedFcn = createCallbackFcn(app, @filter_SecondaryValuePanelSelectionChanged, true);
            app.filter_SecondaryOperation1 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation1.Tag = 'numeric+textFree+textList2';
            app.filter_SecondaryOperation1.Tooltip = {'Igual'};
            app.filter_SecondaryOperation1.Text = '=';
            app.filter_SecondaryOperation1.Value = true;
            app.filter_SecondaryOperation2 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation2.Tag = 'numeric+textFree+textList2';
            app.filter_SecondaryOperation2.Tooltip = {'Diferente'};
            app.filter_SecondaryOperation2.Text = '≠';
            app.filter_SecondaryOperation3 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation3.Tag = 'ROI+textFree+textList1';
            app.filter_SecondaryOperation3.Tooltip = {'Contém'};
            app.filter_SecondaryOperation3.Text = '⊃';
            app.filter_SecondaryOperation4 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation4.Tag = 'textFree+textList1';
            app.filter_SecondaryOperation4.Tooltip = {'Não contém'};
            app.filter_SecondaryOperation4.Text = '⊅';
            app.filter_SecondaryOperation5 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation5.Tag = 'numeric';
            app.filter_SecondaryOperation5.Tooltip = {'Menor'};
            app.filter_SecondaryOperation5.Text = '<';
            app.filter_SecondaryOperation6 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation6.Tag = 'numeric';
            app.filter_SecondaryOperation6.Tooltip = {'Menor ou igual'};
            app.filter_SecondaryOperation6.Text = '≤';
            app.filter_SecondaryOperation7 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation7.Tag = 'numeric';
            app.filter_SecondaryOperation7.Tooltip = {'Maior'};
            app.filter_SecondaryOperation7.Text = '>';
            app.filter_SecondaryOperation8 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation8.Tag = 'numeric';
            app.filter_SecondaryOperation8.Tooltip = {'Maior ou igual'};
            app.filter_SecondaryOperation8.Text = '≥';
            app.filter_SecondaryOperation9 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation9.Tag = 'numeric';
            app.filter_SecondaryOperation9.Tooltip = {'Dentro do intervalo'};
            app.filter_SecondaryOperation9.Text = '><';
            app.filter_SecondaryOperation10 = uitogglebutton(app.filter_SecondaryValuePanel);
            app.filter_SecondaryOperation10.Tag = 'numeric';
            app.filter_SecondaryOperation10.Tooltip = {'Fora do intervalo'};
            app.filter_SecondaryOperation10.Text = '<>';
            app.filter_SecondaryValueSubpanel = uipanel(app.filter_SecondaryValuePanel);
            app.filter_SecondaryValueGrid = uigridlayout(app.filter_SecondaryValueSubpanel);
            app.filter_SecondaryNumValue1 = uieditfield(app.filter_SecondaryValueGrid, 'numeric');
            app.filter_SecondaryNumValue1.Limits = [-1 Inf];
            app.filter_SecondaryNumValue1.ValueDisplayFormat = '%10.10g';
            app.filter_SecondaryNumValue1.Visible = 'off';
            app.filter_SecondaryNumValue1.Value = -1;
            app.filter_SecondaryNumSeparator = uilabel(app.filter_SecondaryValueGrid);
            app.filter_SecondaryNumSeparator.Visible = 'off';
            app.filter_SecondaryNumSeparator.Text = '-';
            app.filter_SecondaryNumValue2 = uieditfield(app.filter_SecondaryValueGrid, 'numeric');
            app.filter_SecondaryNumValue2.Limits = [-1 Inf];
            app.filter_SecondaryNumValue2.Visible = 'off';
            app.filter_SecondaryNumValue2.Value = -1;
            app.filter_SecondaryTextFree = uieditfield(app.filter_SecondaryValueGrid, 'text');
            app.filter_SecondaryTextFree.Visible = 'off';
            app.filter_SecondaryTextList = uidropdown(app.filter_SecondaryValueGrid);
            app.filter_SecondaryTextList.Items = {};
            app.filter_SecondaryTextList.Value = {};
            app.filter_SecondaryReferenceFilterLabel = uilabel(app.filter_SecondaryValueGrid);
            app.filter_SecondaryReferenceFilterLabel.Text = {'Filtro de '; 'referência (ID):'};
            app.filter_SecondaryReferenceFilter = uidropdown(app.filter_SecondaryValueGrid);
            app.filter_SecondaryReferenceFilter.Items = {};
            app.filter_SecondaryReferenceFilter.ValueChangedFcn = createCallbackFcn(app, @filter_SecondaryReferenceFilterValueChanged, true);
            app.filter_SecondaryReferenceFilter.Value = {};
            app.filter_SecondaryLogicalOperatorLabel = uilabel(app.filter_SecondaryValueGrid);
            app.filter_SecondaryLogicalOperatorLabel.Text = {'Operador '; 'lógico:'};
            app.filter_SecondaryLogicalOperator = uidropdown(app.filter_SecondaryValueGrid);
            app.filter_SecondaryLogicalOperator.Items = {'E (&&)'};
            app.filter_SecondaryLogicalOperator.Value = 'E (&&)';
            app.filter_AddImage = uiimage(app.Tab2_Grid);
            app.filter_AddImage.ImageClickedFcn = createCallbackFcn(app, @filter_addFilter, true);
            app.filter_AddImage.Tooltip = {'Adicionar filtro'};
            app.filter_AddImage.ImageSource = 'addSymbol_32.png';
            app.filter_Tree = uitree(app.Tab2_Grid, 'checkbox');
            % Assign Checked Nodes
            app.filter_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @filter_TreeCheckedNodesChanged, true);
            app.filter_refRXIcon = uiimage(app.Tab2_Grid);
            app.filter_refRXIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Pin_32Triangle.png');
            app.filter_refRXLabel = uilabel(app.Tab2_Grid);
            app.filter_refRXLabel.Interpreter = 'html';
            app.filter_refRXLabel.Text = {'<b>Estação receptora - RX</b>'; '<font style="font-size: 9px; color: gray;">(Referência da coluna calculada "Distância" e do enlace)</font>'};
            app.filter_refRXEdit = uiimage(app.Tab2_Grid);
            app.filter_refRXEdit.ImageSource = 'Edit_32.png';
            app.filter_refRXPanel = uipanel(app.Tab2_Grid);
            app.filter_refRXGrid = uigridlayout(app.filter_refRXPanel);
            app.filter_refRXLatitudeLabel = uilabel(app.filter_refRXGrid);
            app.filter_refRXLatitudeLabel.Text = 'Latitude:';
            app.filter_refRXLatitude = uieditfield(app.filter_refRXGrid, 'numeric');
            app.filter_refRXLatitude.ValueDisplayFormat = '%.6f';
            app.filter_refRXLatitude.ValueChangedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.filter_refRXLatitude.Editable = 'off';
            app.filter_refRXLatitude.Value = -1;
            app.filter_refRXLongitudeLabel = uilabel(app.filter_refRXGrid);
            app.filter_refRXLongitudeLabel.Text = 'Longitude:';
            app.filter_refRXLongitude = uieditfield(app.filter_refRXGrid, 'numeric');
            app.filter_refRXLongitude.ValueDisplayFormat = '%.6f';
            app.filter_refRXLongitude.ValueChangedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.filter_refRXLongitude.Editable = 'off';
            app.filter_refRXLongitude.Value = -1;
            app.filter_refRXHeightLabel = uilabel(app.filter_refRXGrid);
            app.filter_refRXHeightLabel.Text = 'Altura (metros):';
            app.filter_refRXHeight = uieditfield(app.filter_refRXGrid, 'numeric');
            app.filter_refRXHeight.Limits = [0 Inf];
            app.filter_refRXHeight.RoundFractionalValues = 'on';
            app.filter_refRXHeight.ValueDisplayFormat = '%d';
            app.filter_refRXHeight.Editable = 'off';
            app.Tab_3 = uitab(app.ControlTabGroup);
            app.Tab4_Grid = uigridlayout(app.Tab_3);
            app.config_geoAxesLabel = uilabel(app.Tab4_Grid);
            app.config_geoAxesLabel.Interpreter = 'html';
            app.config_geoAxesLabel.Text = {'EIXO GEOGRÁFICO'; '<p style="color: gray; font-size: 9px; text-align: justify; padding-right: 2px;">Os parâmetros relacionados ao eixo geográfico, com exceção do VEÍCULO, são customizáveis por canal sob análise.</p>'};
            app.config_Refresh = uiimage(app.Tab4_Grid);
            app.config_Refresh.Visible = 'off';
            app.config_Refresh.Tooltip = {'Volta à configuração inicial'};
            app.config_Refresh.ImageSource = 'Refresh_18.png';
            app.config_geoAxesPanel = uipanel(app.Tab4_Grid);
            app.config_geoAxesGrid = uigridlayout(app.config_geoAxesPanel);
            app.config_geoAxesSublabel = uilabel(app.config_geoAxesGrid);
            app.config_geoAxesSublabel.Text = 'Mapa:';
            app.config_geoAxesSubPanel = uipanel(app.config_geoAxesGrid);
            app.config_geoAxesSubGrid = uigridlayout(app.config_geoAxesSubPanel);
            app.config_BasemapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_BasemapLabel.Text = 'Basemap:';
            app.config_Basemap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Basemap.Items = {'none', 'darkwater', 'streets-light', 'streets-dark', 'satellite', 'topographic', 'grayterrain'};
            app.config_Basemap.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Basemap.Value = 'satellite';
            app.config_ColormapLabel = uilabel(app.config_geoAxesSubGrid);
            app.config_ColormapLabel.Text = 'Mapa de cor:';
            app.config_Colormap = uidropdown(app.config_geoAxesSubGrid);
            app.config_Colormap.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.config_Colormap.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Colormap.Value = 'winter';
            app.config_TX_Label = uilabel(app.config_geoAxesGrid);
            app.config_TX_Label.Text = {'Estação'; 'transmissora - TX:'};
            app.config_TX_DataTipVisibility = uidropdown(app.config_geoAxesGrid);
            app.config_TX_DataTipVisibility.Items = {'on', 'off'};
            app.config_TX_DataTipVisibility.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_TX_DataTipVisibility.Value = 'off';
            app.config_TX_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_TX_Color.Value = [0.7882 0.2784 0.3373];
            app.config_TX_Color.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_TX_Color.Enable = 'off';
            app.config_TX_Size = uislider(app.config_geoAxesGrid);
            app.config_TX_Size.Limits = [1 3];
            app.config_TX_Size.MajorTicks = [];
            app.config_TX_Size.ValueChangingFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_TX_Size.MinorTicks = [];
            app.config_TX_Size.Tooltip = {'Tamanho do marcador'};
            app.config_TX_Size.Value = 1;
            app.config_RX_Label = uilabel(app.config_geoAxesGrid);
            app.config_RX_Label.Text = {'Estação'; 'receptora - RX:'};
            app.config_RX_Visibility = uidropdown(app.config_geoAxesGrid);
            app.config_RX_Visibility.Items = {'on', 'off'};
            app.config_RX_Visibility.Enable = 'off';
            app.config_RX_Visibility.Value = 'on';
            app.config_RX_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_RX_Color.Value = [0.7882 0.2784 0.3373];
            app.config_RX_Color.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_RX_Color.Enable = 'off';
            app.config_RX_Size = uislider(app.config_geoAxesGrid);
            app.config_RX_Size.Limits = [1 3];
            app.config_RX_Size.MajorTicks = [];
            app.config_RX_Size.ValueChangingFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_RX_Size.MinorTicks = [];
            app.config_RX_Size.Tooltip = {'Tamanho do marcador'};
            app.config_RX_Size.Value = 1;
            app.config_Station_Label = uilabel(app.config_geoAxesGrid);
            app.config_Station_Label.Text = 'Estações RFDataHub:';
            app.config_Station_DataTipVisibility = uidropdown(app.config_geoAxesGrid);
            app.config_Station_DataTipVisibility.Items = {'on', 'off'};
            app.config_Station_DataTipVisibility.Enable = 'off';
            app.config_Station_DataTipVisibility.Value = 'on';
            app.config_Station_Color = uicolorpicker(app.config_geoAxesGrid);
            app.config_Station_Color.Value = [0 1 1];
            app.config_Station_Color.ValueChangedFcn = createCallbackFcn(app, @config_geoAxesColorParameterChanged, true);
            app.config_Station_Color.Enable = 'off';
            app.config_Station_Size = uislider(app.config_geoAxesGrid);
            app.config_Station_Size.Limits = [1 36];
            app.config_Station_Size.MajorTicks = [];
            app.config_Station_Size.ValueChangingFcn = createCallbackFcn(app, @config_geoAxesOthersParametersChanged, true);
            app.config_Station_Size.MinorTicks = [];
            app.config_Station_Size.Tooltip = {'Tamanho do marcador'};
            app.config_Station_Size.Value = 1;
            app.config_xyAxesLabel = uilabel(app.Tab4_Grid);
            app.config_xyAxesLabel.Interpreter = 'html';
            app.config_xyAxesLabel.Text = {'EIXOS CARTESIANOS'; '<p style="color: gray; font-size: 9px; text-align: justify; padding-right: 2px;">Os parâmetros relacionados aos eixos cartesianos não são customizáveis.</p>'};
            app.ELEVAOLabel = uilabel(app.Tab4_Grid);
            app.ELEVAOLabel.Text = 'ELEVAÇÃO';
            app.misc_ElevationSourcePanel = uipanel(app.Tab4_Grid);
            app.misc_ElevationSourceGrid = uigridlayout(app.misc_ElevationSourcePanel);
            app.misc_ElevationAPISourceLabel = uilabel(app.misc_ElevationSourceGrid);
            app.misc_ElevationAPISourceLabel.Text = 'Fonte:';
            app.misc_ElevationAPISource = uidropdown(app.misc_ElevationSourceGrid);
            app.misc_ElevationAPISource.Items = {'Open-Elevation', 'MathWorks WMS Server'};
            app.misc_ElevationAPISource.Value = 'Open-Elevation';
            app.misc_PointsPerLinkLabel = uilabel(app.misc_ElevationSourceGrid);
            app.misc_PointsPerLinkLabel.Text = 'Pontos enlace:';
            app.misc_PointsPerLink = uidropdown(app.misc_ElevationSourceGrid);
            app.misc_PointsPerLink.Items = {'64', '128', '256', '512', '1024'};
            app.misc_PointsPerLink.Value = '256';
            app.config_FolderMapPanel = uipanel(app.Tab4_Grid);
            app.config_FolderMapGrid = uigridlayout(app.config_FolderMapPanel);
            app.config_Folder_DataHubGETLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETLabel.Text = 'DataHub - GET:';
            app.config_Folder_DataHubGET = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_DataHubGET.Editable = 'off';
            app.config_Folder_DataHubGETButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_DataHubGETButton.Tag = 'DataHub_GET';
            app.config_Folder_DataHubGETButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_Folder_userPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_userPathLabel.Text = 'Pasta do usuário:';
            app.config_Folder_userPath = uidropdown(app.config_FolderMapGrid);
            app.config_Folder_userPath.Items = {''};
            app.config_Folder_userPath.Value = '';
            app.config_Folder_userPathButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_userPathButton.Tag = 'userPath';
            app.config_Folder_userPathButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.menu_MainGrid = uigridlayout(app.ControlTabGrid);
            app.menuUnderline = uiimage(app.menu_MainGrid);
            app.menuUnderline.ImageSource = 'LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'RFDATAHUB';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelMenuSelectionChanged, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'mosaic_18Gray.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'FILTRAGEM';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelMenuSelectionChanged, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'Filter_18.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'CONFIGURAÇÕES GERAIS';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @general_LeftPanelMenuSelectionChanged, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'Settings_18.png';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'ID'; 'FREQUÊNCIA|(MHz)'; 'DESCRIÇÃO|(Entidade+Fistel+Multiplicidade+Localidade)'; 'SERVIÇO'; 'ESTAÇÃO'; 'LARGURA|(kHz)'; 'DISTÂNCIA|(km)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false true true true true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.chReportHTML = uihtml(app.GridLayout);
            app.chReportHTML.HTMLSource = 'pdfLogo.html';
            app.plotPanel = uipanel(app.GridLayout);
            app.axesToolbarGrid = uigridlayout(app.GridLayout);
            app.axesTool_RestoreView = uiimage(app.axesToolbarGrid);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RestoreView.Tooltip = {'RestoreView'};
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_RegionZoom = uiimage(app.axesToolbarGrid);
            app.axesTool_RegionZoom.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RegionZoom.Tooltip = {'RegionZoom'};
            app.axesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.points_ContextMenu = uicontextmenu(app.UIFigure);
            app.points_referenceButton = uimenu(app.points_ContextMenu);
            app.points_referenceButton.MenuSelectedFcn = createCallbackFcn(app, @points_ContextMenuCallbacks, true);
            app.points_referenceButton.Text = 'Referência';
            app.points_editRowButton = uimenu(app.points_ContextMenu);
            app.points_editRowButton.MenuSelectedFcn = createCallbackFcn(app, @points_ContextMenuCallbacks, true);
            app.points_editRowButton.Separator = 'on';
            app.points_editRowButton.Text = 'Editar';
            app.points_delButton = uimenu(app.points_ContextMenu);
            app.points_delButton.MenuSelectedFcn = createCallbackFcn(app, @points_ContextMenuCallbacks, true);
            app.points_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRFDataHub(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end