classdef winReport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Grid1                         matlab.ui.container.GridLayout
        GridLayout6                   matlab.ui.container.GridLayout
        Tree2_2                       matlab.ui.container.Tree
        Tree2                         matlab.ui.container.Tree
        FontedeinformaoDropDown       matlab.ui.control.DropDown
        FontedeinformaoDropDownLabel  matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        HTML                          matlab.ui.control.HTML
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        FreeText                      matlab.ui.control.TextArea
        ContentLabel                  matlab.ui.control.Label
        Grid1B                        matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        Button_del_4                  matlab.ui.control.Button
        Button_del_3                  matlab.ui.control.Button
        Button_del_2                  matlab.ui.control.Button
        Button_del                    matlab.ui.control.Button
        Button_play                   matlab.ui.control.Button
        PButton                       matlab.ui.control.Button
        Button_edit                   matlab.ui.control.StateButton
        GridLayout                    matlab.ui.container.GridLayout
        Image_up                      matlab.ui.control.Image
        Image_down                    matlab.ui.control.Image
        Image_add                     matlab.ui.control.Image
        Tree                          matlab.ui.container.Tree
        Model                         matlab.ui.control.DropDown
        ModelLabel                    matlab.ui.control.Label
        Logo                          matlab.ui.control.Image
        Title                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        ModelInfo
        Report

        i = ["OBJETIVO",    "mainMenu_Home.png"; ...
             "INTRODUÇÃO",  "Toolbar_Icon3.png"; ...
             "METODOLOGIA", "Filter.png"; ...
             "RESULTADOS",  "1200px-BellcurveMean_Edited2.png"; ...
             "ANÁLISE",     "TruncatedPeak.png"; ...
             "CONCLUSÃO",   "Pin.png"]
        
    end
    
    methods (Access = private)

        function Fcn_ReadModels(app)

            app.ModelInfo   = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'DataBase', 'InspectionType.cfg'))));
            app.Model.Items = app.ModelInfo.Name;

        end


        function Fcn_ReadTemplate(app)

            idx = find(strcmp(app.ModelInfo.Name, app.Model.Value));
            app.Report = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'DataBase', app.ModelInfo.Template{idx}))));

            Fcn_TreeBuilding(app)

        end


        function Fcn_TreeBuilding(app)

            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end

            idx1 = 0;            
            for ii = 1:height(app.Report)
                if app.Report.Type{ii} == "Item"
                    idx1 = idx1+1;
                    idx2 = find(app.i(:,1) == app.Report.Data{ii}.String);

                    uitreenode(app.Tree, 'Text', sprintf('ITEM %.0f: %s', idx1, app.Report.Data{ii}.String), ...
                                         'NodeData', ii, 'UserData', ii, 'Icon', app.i(idx2,2));
                else
                    uitreenode(app.Tree.Children(idx1), 'Text', app.Report.Type{ii}, ...
                                                        'NodeData', ii, 'UserData', ii);
                end
            end
            expand(app.Tree, 'all')

            if ~isempty(app.Tree.Children)
                app.Tree.SelectedNodes = app.Tree.Children(1);
                TreeSelectionChanged(app)
            end

        end

        
        function Fcn_Layout(app, Mode)

            switch Mode
                case 'Edit'
                    if app.Button_edit.Value
                        app.Button_del.Enable  = 0;
                        app.Button_play.Enable = 0;

                        app.Image_up.Enable   = 0;
                        app.Image_down.Enable = 0;
                    else
                        app.Button_del.Enable  = 1;
                        app.Button_play.Enable = 1;
                    end

                case 'TreeSelectionChanged'
                    if ~isempty(app.Tree.Children)
                        app.Button_play.Enable = 1;
                    else
                        app.Button_play.Enable = 0;
                    end
        
                    app.Button_edit.Enable = 1;
                    app.Button_del.Enable  = 1;
            end
            Fcn_Layout_UpDown(app)
            
        end


        function Fcn_Layout_UpDown(app)

            Parent = app.Tree.SelectedNodes.Parent;
            
            NN   = numel(Parent.Children);
            idx1 = find([Parent.Children.NodeData] == Parent.Children(1).NodeData);
            idx2 = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);

            if NN == 1
                app.Image_up.Enable   = 0;
                app.Image_down.Enable = 0;

            elseif idx2 == idx1
                app.Image_up.Enable   = 0;
                app.Image_down.Enable = 1;

            elseif idx2 - idx1 + 1 == NN
                app.Image_up.Enable   = 1;
                app.Image_down.Enable = 0;

            else
                app.Image_up.Enable   = 1;
                app.Image_down.Enable = 1;
            end

        end
        

        function Fcn_NodeData(app)

            for ii = 1:numel(app.Tree.Children)
                app.Tree.Children(ii).NodeData = ii;

                for jj = 1:numel(app.Tree.Children(ii).Children)
                    app.Tree.Children(ii).Children(jj).NodeData = ii+jj;
                end
            end

            TreeSelectionChanged(app)

        end

        
        function Fcn_updateValues(app)

            idx = app.Tree.SelectedNodes.UserData;
            app.FreeText.Value = jsonencode(app.Report.Data{idx}, 'PrettyPrint', true);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            Fcn_ReadModels(app)
            Fcn_ReadTemplate(app)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin7 = [];
            delete(app)
            
        end

        % Value changed function: Model
        function ModelValueChanged(app, event)
            
            Fcn_ReadTemplate(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            if ~isempty(app.Tree.SelectedNodes)
                Fcn_updateValues(app)
            end

            Fcn_Layout(app, 'TreeSelectionChanged')

        end

        % Image clicked function: Image_down, Image_up
        function Image_orderClicked(app, event)
            
            Parent = app.Tree.SelectedNodes.Parent;
            
            idx = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);
            switch event.Source
                case app.Image_down
                    Parent.Children(idx:idx+1) = flip(Parent.Children(idx:idx+1));
                    app.Tree.SelectedNodes       = Parent.Children(idx+1);

                case app.Image_up
                    Parent.Children(idx-1:idx) = flip(Parent.Children(idx-1:idx));
                    app.Tree.SelectedNodes       = Parent.Children(idx-1);
            end

            Fcn_NodeData(app)
            
        end

        % Image clicked function: Image_add
        function Image_addClicked(app, event)
            
            if app.Type.Value == "Item"
                value = app.DropDownText.Value;
            else
                value = deblank(strjoin(app.FreeText.Value));
            end

            if ~isempty(value)
                if app.Button_edit.Value
                    idx = app.Tree.SelectedNodes.NodeData;
                    app.Tree.Children(idx).Text = jsonencode(struct('Type',    app.Type.Value,     ...
                                                                   'Editable', app.Editable.Value, ...
                                                                   'String',   value));

                    app.Button_edit.Value = 0;
                else
                    idx = numel(app.Tree.Children)+1;
                    uitreenode(app.Tree, 'Text', jsonencode(struct('Type',     app.Type.Value,     ...
                                                                   'Editable', app.Editable.Value, ...
                                                                   'String',   value)),            ...
                                         'NodeData', idx);
                end
                TreeSelectionChanged(app)

            else
                uialert(app.UIFigure, 'O campo "Conteúdo textual" não pode ficar vazio.', 'appAnálise', 'Icon', 'warning')
            end

        end

        % Value changed function: Button_edit
        function Button_editPushed(app, event)
            
            Fcn_Layout(app, 'Edit')
            Fcn_updateValues(app)

        end

        % Button pushed function: Button_del
        function Button_delPushed(app, event)
            
            if ~isempty(app.Tree.SelectedNodes)
                idx = app.Tree.SelectedNodes.NodeData;
                delete(app.Tree.Children(idx));

                if ~isempty(app.Tree.Children)
                    app.Tree.SelectedNodes = app.Tree.Children(1);
                end

                Fcn_NodeData(app)
            end

        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)
            
            Template = {};
            listFlag = 0;

            kk = 0;
            for ii = 1:numel(app.Tree.Children)
                msg = jsondecode(app.Tree.Children(ii).Text);

                if ismember(msg.Type, ["Item", "Paragraph"])
                    kk = kk+1;
                    ReportTemplate{kk,1} = struct('Type', msg.Type, ...
                                                  'Data', struct('Editable', msg.Editable, 'String', msg.String));

                    listFlag = 0;
                
                else
                    if ~listFlag
                        kk = kk+1;
                        ReportTemplate{kk,1} = struct('Type', msg.Type, ...
                                                      'Data', struct('Editable', msg.Editable, 'String', msg.String));
                    else
                        ReportTemplate{kk,1}.Data(end+1) = struct('Editable', msg.Editable, 'String', msg.String);
                    end
                    listFlag = 1;
                end
            end

            Report = [app.CallingApp.Report; ReportTemplate];
            if Report{1}.Type ~= "Item"
                uialert(app.UIFigure, 'O relatório deve iniciar com um elemento do tipo "Item".')
            
            else
                app.CallingApp.Report = Report;
                app.CallingApp.TreeBuilding
                closeFcn(app)
            end

        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            
%             try
                htmlContent = sprintf('%s\n\n%s', fileread(fullfile(app.RootFolder, 'Template', 'html_DocumentStyle.txt')), ...
                                                  fileread(fullfile(app.RootFolder, 'Template', 'html_DocumentTableStyle.txt')));

                htmlContent = replace(htmlContent, '<html lang="pt-br">', '<html {zoom: 80%} lang="pt-br">');
            
                for ii = 1:numel(app.Tree.Children)
                    idx = app.Tree.Children(ii).UserData;

                    htmlContent = sprintf('%s\n\n%s', htmlContent, ReportGenerator_HTML(app.Report(idx,:)));

                    for jj = 1:numel(app.Tree.Children(ii).Children)
                        idx = app.Tree.Children(ii).Children(jj).UserData;

                        switch app.Report.Type{idx}
                            case "Image"
                                arg2 = 1;
                                arg3 = "Image_2022.04.19_T16.51.42_ThreadID110.png";

                            case "Table"
                                arg2 = 3;
                                arg3 = jsondecode(fileread([pwd '\Temp\Report_2022.04.17_T15.50.03_FinalVersion.json']));
                                arg3 = struct2table(arg3.MeasurementData);
                                arg3 = arg3(:, [3,5,15,16]);

                            otherwise
                                arg2 = [];
                                arg3 = [];
                        end

                        htmlContent = sprintf('%s\n\n%s', htmlContent, ReportGenerator_HTML(app.Report(idx,:), {arg2, arg3}));
                    end
                end

                app.HTML.HTMLSource = htmlContent;

%             catch ME
%                 uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
%             end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Relatório</b>'; '<font>Criação de modelo (Item, parágrafo, lista, tabela e imagem)</font>'; ' '};
            app.Logo = uiimage(app.Grid1);
            app.Logo.ImageSource = 'mainMenu_Report.png';
            app.GridLayout3 = uigridlayout(app.Grid1);
            app.ModelLabel = uilabel(app.GridLayout3);
            app.ModelLabel.Text = 'Modelo:';
            app.Model = uidropdown(app.GridLayout3);
            app.Model.Items = {'PMEC 2022', 'PMRD 2022', 'PMEF 2022', 'Rádio pirata', 'Drive-test'};
            app.Model.ValueChangedFcn = createCallbackFcn(app, @ModelValueChanged, true);
            app.Model.Value = 'PMEC 2022';
            app.Tree = uitree(app.GridLayout3);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GridLayout = uigridlayout(app.GridLayout3);
            app.Image_add = uiimage(app.GridLayout);
            app.Image_add.ImageClickedFcn = createCallbackFcn(app, @Image_addClicked, true);
            app.Image_add.ImageSource = 'General_Add.png';
            app.Image_down = uiimage(app.GridLayout);
            app.Image_down.ImageClickedFcn = createCallbackFcn(app, @Image_orderClicked, true);
            app.Image_down.Enable = 'off';
            app.Image_down.ImageSource = 'General_Down2.png';
            app.Image_up = uiimage(app.GridLayout);
            app.Image_up.ImageClickedFcn = createCallbackFcn(app, @Image_orderClicked, true);
            app.Image_up.Enable = 'off';
            app.Image_up.ImageSource = 'General_Up2.png';
            app.Grid1B = uigridlayout(app.GridLayout3);
            app.Button_edit = uibutton(app.Grid1B, 'state');
            app.Button_edit.ValueChangedFcn = createCallbackFcn(app, @Button_editPushed, true);
            app.Button_edit.Enable = 'off';
            app.Button_edit.Icon = 'mainMenu_Edit.png';
            app.Button_edit.Text = '';
            app.PButton = uibutton(app.Grid1B, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = 'P';
            app.Button_play = uibutton(app.Grid1B, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Icon = 'White_Play.png';
            app.Button_play.Enable = 'off';
            app.Button_play.Text = 'Confirmar';
            app.Button_del = uibutton(app.Grid1B, 'push');
            app.Button_del.ButtonPushedFcn = createCallbackFcn(app, @Button_delPushed, true);
            app.Button_del.Icon = 'Report_Icon3.png';
            app.Button_del.Enable = 'off';
            app.Button_del.Text = '';
            app.Button_del_2 = uibutton(app.Grid1B, 'push');
            app.Button_del_2.Icon = 'Report_Icon1.png';
            app.Button_del_2.Enable = 'off';
            app.Button_del_2.Text = '';
            app.Button_del_3 = uibutton(app.Grid1B, 'push');
            app.Button_del_3.Icon = 'mainMenu_File.png';
            app.Button_del_3.Enable = 'off';
            app.Button_del_3.Text = '';
            app.Button_del_4 = uibutton(app.Grid1B, 'push');
            app.Button_del_4.Icon = 'mainMenu_Save.png';
            app.Button_del_4.Enable = 'off';
            app.Button_del_4.Text = '';
            app.Image = uiimage(app.Grid1B);
            app.Image.ImageSource = 'Line.png';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ContentLabel = uilabel(app.GridLayout4);
            app.ContentLabel.Text = 'Conteúdo:';
            app.FreeText = uitextarea(app.GridLayout4);
            app.FreeText.Editable = 'off';
            app.GridLayout5 = uigridlayout(app.Grid1);
            app.GridLayout2 = uigridlayout(app.GridLayout5);
            app.HTML = uihtml(app.GridLayout2);
            app.GridLayout6 = uigridlayout(app.Grid1);
            app.FontedeinformaoDropDownLabel = uilabel(app.GridLayout6);
            app.FontedeinformaoDropDownLabel.Text = 'Fonte de informação:';
            app.FontedeinformaoDropDown = uidropdown(app.GridLayout6);
            app.FontedeinformaoDropDown.Items = {'specData', 'peaksTable'};
            app.FontedeinformaoDropDown.Value = 'specData';
            app.Tree2 = uitree(app.GridLayout6);
            app.Tree2_2 = uitree(app.GridLayout6);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winReport(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end