classdef winRepository < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout22              matlab.ui.container.GridLayout
        GridLayout23              matlab.ui.container.GridLayout
        SortRows                  SortRows
        GridLayout25              matlab.ui.container.GridLayout
        DashboardFilter5          DashboardFilter
        DashboardFilter4          DashboardFilter
        DashboardFilter3          DashboardFilter
        DashboardFilter2          DashboardFilter
        DashboardFilter1          DashboardFilter
        Panel_11                  matlab.ui.container.Panel
        GridLayout24              matlab.ui.container.GridLayout
        FilesNumberLabel          matlab.ui.control.Label
        FilesNumber               matlab.ui.control.Label
        Subtitle                  matlab.ui.control.Label
        Title                     matlab.ui.control.Label
        GridLayout21              matlab.ui.container.GridLayout
        Table_Label9              matlab.ui.control.Label
        Table_Label8              matlab.ui.control.Label
        Table_Label7              matlab.ui.control.Label
        Table_Label6              matlab.ui.control.Label
        Table_Label5              matlab.ui.control.Label
        Table_Label4              matlab.ui.control.Label
        Table_Label3              matlab.ui.control.Label
        Table_Label2              matlab.ui.control.Label
        Table_Label1              matlab.ui.control.Label
        Table                     matlab.ui.control.Table
        Button3_Read              matlab.ui.control.Button
        Button2_Download          matlab.ui.control.Button
        GridLayout18              matlab.ui.container.GridLayout
        Logo                      matlab.ui.control.Image
        Button1_Filter            matlab.ui.control.Button
        PalavrachaveLabel         matlab.ui.control.Label
        Keyword                   matlab.ui.control.EditField
        SensorLabel               matlab.ui.control.Label
        Node                      matlab.ui.control.DropDown
        GridLayout20              matlab.ui.container.GridLayout
        Spinner4                  matlab.ui.control.Spinner
        Spinner3                  matlab.ui.control.Spinner
        FimDatePickerLabel        matlab.ui.control.Label
        DatePicker2               matlab.ui.control.DatePicker
        GridLayout19              matlab.ui.container.GridLayout
        Spinner1                  matlab.ui.control.Spinner
        Spinner2                  matlab.ui.control.Spinner
        IncioDatePickerLabel      matlab.ui.control.Label
        DatePicker1               matlab.ui.control.DatePicker
        FreqStop                  matlab.ui.control.NumericEditField
        FrequnciafinalMHzLabel    matlab.ui.control.Label
        FrequnciainicialMHzLabel  matlab.ui.control.Label
        FreqStart                 matlab.ui.control.NumericEditField
        Tree                      matlab.ui.container.Tree
        Filter_Location           matlab.ui.container.TreeNode
        Filter_Node               matlab.ui.container.TreeNode
        Filter_Band               matlab.ui.container.TreeNode
        Filter_Observation        matlab.ui.container.TreeNode
        Filter_Description        matlab.ui.container.TreeNode
        Panel_9                   matlab.ui.container.Panel
        GridLayout13              matlab.ui.container.GridLayout
        Radius                    matlab.ui.control.NumericEditField
        RaioLabel                 matlab.ui.control.Label
        Longitude                 matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel   matlab.ui.control.Label
        Latitude                  matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel    matlab.ui.control.Label
        CheckBox                  matlab.ui.control.CheckBox
        City                      matlab.ui.control.DropDown
        MunicpioLabel             matlab.ui.control.Label
        UFLabel                   matlab.ui.control.Label
        UF                        matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        
        CallingApp
        
        repoData
        Filters
        
    end
    
    methods (Access = private)

        function Fcn_ObjRanges(app)

            if height(app.Table.Data)
                % UF & Node filter
                set(app.UF,   'Items', [''; unique(app.Table.Data.Site_State_Code)], 'Value', '')
                set(app.City, 'Items', {''}, 'Enable', 0);

                app.Node.Items = unique(app.Table.Data.Equipment_ID);
                
                % Band filter
                app.FreqStart.Value = min(app.Table.Data.Start_Frequency);
                app.FreqStop.Value  = max(app.Table.Data.Stop_Frequency);
    
                % Observation time filter
                firstTimeStamp = min(app.Table.Data.Initial_Time);
                lastTimeStamp  = max(app.Table.Data.Final_Time);
    
                app.DatePicker1.Limits = [datetime([0 1 1]), datetime([9999 12 31])];
                set(app.DatePicker1, 'Value', firstTimeStamp, 'Limits', [firstTimeStamp, lastTimeStamp])
                
                app.DatePicker2.Limits = [datetime([0 1 1]), datetime([9999 12 31])];
                set(app.DatePicker2, 'Value', lastTimeStamp, 'Limits', [firstTimeStamp, lastTimeStamp])
   
                app.FilesNumber.Text = num2str(numel(unique(app.Table.Data.Filename)));
            else
                app.FilesNumber.Text = '0';
            end
    
            app.Spinner1.Value = 0;
            app.Spinner2.Value = 0;
            app.Spinner3.Value = 23;
            app.Spinner4.Value = 59;

            Fcn_FilterPanel(app)
            
        end

        
        function Fcn_DatabaseFilter(app, Flag)
            
            global repositoryData

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on");

            tempTable = repositoryData(:, [11, 12, 7, 4, 5, 2, 3, 1, 13, 9, 10]);
            
            % Local da monitoração
            if ~isempty(app.Tree.Children(1).Icon)
                if ~app.CheckBox.Value
                    tempTable = tempTable(strcmp(tempTable.Site_State_Code,  app.UF.Value),   :);
                    tempTable = tempTable(strcmp(tempTable.Site_County_Name, app.City.Value), :);
                                        
                else
                    for ii = 1:height(tempTable)
                        tempTable.Distance(ii) = Misc_lldistkm([app.Latitude.Value,          app.Longitude.Value], ...
                                                               [tempTable.Site_Latitude(ii), tempTable.Site_Longitude(ii)]);                        
                    end
                    tempTable = tempTable(tempTable.Distance <= app.Radius.Value, :);
                end
            end
            
            % Sensor
            if ~isempty(app.Tree.Children(2).Icon)
                tempTable = tempTable(strcmpi(tempTable.Equipment_ID, app.Node.Value), :);
            end
            
            % Faixa de frequência
            if ~isempty(app.Tree.Children(3).Icon)
                tempTable = tempTable((tempTable.Start_Frequency >= app.FreqStart.Value) & ...
                                      (tempTable.Stop_Frequency  <= app.FreqStop.Value), :);
            end    
            
            % Período de observação
            if ~isempty(app.Tree.Children(4).Icon)
                Initial_Time = app.DatePicker1.Value + hours(app.Spinner1.Value)   + minutes(app.Spinner2.Value);
                Final_Time   = app.DatePicker2.Value   + hours(app.Spinner3.Value) + minutes(app.Spinner4.Value);

                tempTable = tempTable((tempTable.Initial_Time >= Initial_Time) & (tempTable.Final_Time <= Final_Time), :);
            end
            
            % Textual
            if ~isempty(app.Tree.Children(5).Icon)
                tempTable = tempTable(contains(tempTable.Description, app.Keyword.Value, 'IgnoreCase', true), :);
            end
            app.Table.Data = tempTable(:, 1:9);

            if strcmp(Flag, 'Add')
                switch app.Tree.SelectedNodes.NodeData
                    case 1
                        if ~app.CheckBox.Value
                            Info = sprintf('%s/%s', app.City.Value, app.UF.Value);
                        else
                            Info = sprintf('[%.6f %.6f] @ %.0f km', app.Latitude.Value, app.Longitude.Value, app.Radius.Value);
                        end
                    case 2; Info = app.Node.Value;
                    case 3; Info = sprintf('%.3f - %.3f MHz', app.FreqStart.Value, app.FreqStop.Value);
                    case 4; Info = sprintf('%s - %s', datestr(app.DatePicker1.Value + hours(app.Spinner1.Value) + minutes(app.Spinner2.Value), 'dd/mm/yyyy HH:MM:SS'), ...
                                                      datestr(app.DatePicker2.Value + hours(app.Spinner3.Value) + minutes(app.Spinner4.Value), 'dd/mm/yyyy HH:MM:SS'));
                    case 5; Info = app.Keyword.Value;
                end
                app.Filters(end+1) = struct('Type', app.Tree.SelectedNodes.Text, 'Data', app.Tree.SelectedNodes.NodeData, 'Info', Info);
            end
            SortRowsValueChanged(app)
            Fcn_ObjRanges(app)
            
        end


        function Fcn_FilterPanel(app)

            if isempty(app.Filters)
                app.GridLayout23.RowHeight = {36, 42,  0, '1x', 1, 24};

            else
                app.GridLayout23.RowHeight = {36, 42, 36, '1x', 1, 24};

                set(app.GridLayout25.Children, 'Visible', 0)
                set(app.DashboardFilter1, 'Visible', 1, 'FilterType', app.Filters(1).Type, 'FilterValue', app.Filters(1).Info)

                if numel(app.Filters) >= 2; set(app.DashboardFilter2, 'Visible', 1, 'FilterType', app.Filters(2).Type, 'FilterValue', app.Filters(2).Info)
                end

                if numel(app.Filters) >= 3; set(app.DashboardFilter3, 'Visible', 1, 'FilterType', app.Filters(3).Type, 'FilterValue', app.Filters(3).Info)
                end

                if numel(app.Filters) >= 4; set(app.DashboardFilter4, 'Visible', 1, 'FilterType', app.Filters(4).Type, 'FilterValue', app.Filters(4).Info)
                end

                if numel(app.Filters) == 5; set(app.DashboardFilter5, 'Visible', 1, 'FilterType', app.Filters(5).Type, 'FilterValue', app.Filters(5).Info)
                end
            end

        end        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            global repositoryData
            if isempty(repositoryData)
                RootFolder = 'C:\Users\eric\Documents\appAnalise';
                Misc_RepositoryBase(fullfile(RootFolder, 'DataBase', 'processsed_metadata.csv'));
            end
            app.Table.Data = repositoryData(:,[11, 12, 7, 4, 5, 2, 3, 1, 13]);

            app.Filters = struct('Type', [], 'Data', [], 'Info', []);
            app.Filters = app.Filters([]);

            app.UF.Items = [''; unique(app.Table.Data.Site_State_Code)];
            Fcn_ObjRanges(app)
            
            app.Tree.SelectedNodes = app.Tree.Children(1); 
            TreeSelectionChanged(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin6 = [];
            delete(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            selectedNodes = app.Tree.SelectedNodes;
            
            switch selectedNodes.NodeData
                case 1; app.GridLayout18.RowHeight = {77, 1, '1x', 1, 17, 22, 17, 22, 36, 88,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0, 1, 24};
                case 2; app.GridLayout18.RowHeight = {77, 1, '1x', 1,  0,  0,  0,  0,  0,  0, 17, 22,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0, 1, 24};
                case 3; app.GridLayout18.RowHeight = {77, 1, '1x', 1,  0,  0,  0,  0,  0,  0,  0,  0, 17, 22, 17, 22,  0,  0,  0,  0,  0,  0,  0,  0, 1, 24};
                case 4; app.GridLayout18.RowHeight = {77, 1, '1x', 1,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0, 17, 22, 22, 17, 22, 22,  0,  0, 1, 24};
                case 5; app.GridLayout18.RowHeight = {77, 1, '1x', 1,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0,  0, 17, 22, 1, 24};
            end

            if isempty(selectedNodes.Icon)
                app.Button1_Filter.Enable = 1;
            else
                app.Button1_Filter.Enable = 0;
            end
            
        end

        % Value changed function: UF
        function UFValueChanged(app, event)
            
            if ~isempty(app.UF.Value)
                set(app.City, 'Enable', 1, 'Items', unique(app.Table.Data.Site_County_Name(strcmp(app.Table.Data.Site_State_Code, app.UF.Value))))
            else
                set(app.City, 'Enable', 0, 'Items', {})
            end
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            
            if app.CheckBox.Value
                app.UFLabel.Enable       = 0;
                app.UF.Enable   = 0;
                app.MunicpioLabel.Enable = 0;
                app.City.Enable = 0;
                
                set(app.GridLayout13.Children, 'Enable', 1)
            else
                app.UFLabel.Enable       = 1;
                app.UF.Enable   = 1;
                app.MunicpioLabel.Enable = 1;
                app.City.Enable = 1; 

                set(app.GridLayout13.Children, 'Enable', 0)
            end
            
        end

        % Button pushed function: Button1_Filter
        function Button1_FilterPushed(app, event)
            
            app.Tree.SelectedNodes.Icon = 'Filter.png';
            app.Button1_Filter.Enable   = 0;

            Fcn_DatabaseFilter(app, 'Add')
            
        end

        % Callback function: SortRows, SortRows
        function SortRowsValueChanged(app, event)

            if strcmp(app.SortRows.ImageSource, 'ascend.png')
                type = 'ascend';
            else
                type = 'descend';
            end

            switch app.SortRows.Value
                case 'UF';                  app.Table.Data = sortrows(app.Table.Data, 1, type);
                case 'Município';           app.Table.Data = sortrows(app.Table.Data, 2, type);
                case 'Sensor';              app.Table.Data = sortrows(app.Table.Data, 3, type);
                case 'Freq. Inicial (MHz)'; app.Table.Data = sortrows(app.Table.Data, 4, type);
                case 'Freq. Final (MHz)';   app.Table.Data = sortrows(app.Table.Data, 5, type);
                case 'Início';              app.Table.Data = sortrows(app.Table.Data, 6, type);
                case 'Fim';                 app.Table.Data = sortrows(app.Table.Data, 7, type);
                case 'Descrição';           app.Table.Data = sortrows(app.Table.Data, 8, type);
                case 'Nome do arquivo';     app.Table.Data = sortrows(app.Table.Data, 9, type);
            end

        end

        % Callback function: DashboardFilter1, DashboardFilter2, 
        % DashboardFilter3, DashboardFilter4, DashboardFilter5
        function DashboardFilterImageClicked(app, event)
            
            for ii = 1:numel(app.Filters)
                if strcmp(app.Filters(ii).Type, event.Source.FilterType)
                    ind = ii;
                    break
                end
            end

            app.Tree.Children(app.Filters(ind).Data).Icon = '';
            app.Button1_Filter.Enable                     = 1;
            app.Filters(ind)                              = [];
            
            Fcn_DatabaseFilter(app, 'Delete')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Repositório de dados';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout22 = uigridlayout(app.UIFigure);
            app.GridLayout18 = uigridlayout(app.GridLayout22);
            app.UF = uidropdown(app.GridLayout18);
            app.UF.Items = {};
            app.UF.ValueChangedFcn = createCallbackFcn(app, @UFValueChanged, true);
            app.UF.Value = {};
            app.UFLabel = uilabel(app.GridLayout18);
            app.UFLabel.Text = 'UF';
            app.MunicpioLabel = uilabel(app.GridLayout18);
            app.MunicpioLabel.Text = 'Município';
            app.City = uidropdown(app.GridLayout18);
            app.City.Items = {};
            app.City.Enable = 'off';
            app.City.Value = {};
            app.CheckBox = uicheckbox(app.GridLayout18);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Raio em torno de coordenadas geográficas';
            app.Panel_9 = uipanel(app.GridLayout18);
            app.GridLayout13 = uigridlayout(app.Panel_9);
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LatitudeEditFieldLabel.Enable = 'off';
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.Latitude = uieditfield(app.GridLayout13, 'numeric');
            app.Latitude.ValueDisplayFormat = '%.6f';
            app.Latitude.Enable = 'off';
            app.Latitude.Value = -1;
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LongitudeEditFieldLabel.Enable = 'off';
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.Longitude = uieditfield(app.GridLayout13, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.6f';
            app.Longitude.Enable = 'off';
            app.Longitude.Value = -1;
            app.RaioLabel = uilabel(app.GridLayout13);
            app.RaioLabel.Enable = 'off';
            app.RaioLabel.Text = 'Raio:';
            app.Radius = uieditfield(app.GridLayout13, 'numeric');
            app.Radius.Limits = [1 500];
            app.Radius.RoundFractionalValues = 'on';
            app.Radius.ValueDisplayFormat = '%.0f';
            app.Radius.Enable = 'off';
            app.Radius.Value = 50;
            app.Tree = uitree(app.GridLayout18);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Filter_Location = uitreenode(app.Tree);
            app.Filter_Location.NodeData = 1;
            app.Filter_Location.Text = 'Local de monitoração';
            app.Filter_Node = uitreenode(app.Tree);
            app.Filter_Node.NodeData = 2;
            app.Filter_Node.Text = 'Sensor';
            app.Filter_Band = uitreenode(app.Tree);
            app.Filter_Band.NodeData = 3;
            app.Filter_Band.Text = 'Faixa de frequência';
            app.Filter_Observation = uitreenode(app.Tree);
            app.Filter_Observation.NodeData = 4;
            app.Filter_Observation.Text = 'Período de observação';
            app.Filter_Description = uitreenode(app.Tree);
            app.Filter_Description.NodeData = 5;
            app.Filter_Description.Text = 'Textual';
            app.FreqStart = uieditfield(app.GridLayout18, 'numeric');
            app.FreqStart.ValueDisplayFormat = '%.6f';
            app.FreqStart.Value = 76;
            app.FrequnciainicialMHzLabel = uilabel(app.GridLayout18);
            app.FrequnciainicialMHzLabel.Text = 'Frequência inicial (MHz):';
            app.FrequnciafinalMHzLabel = uilabel(app.GridLayout18);
            app.FrequnciafinalMHzLabel.Text = 'Frequência final (MHz):';
            app.FreqStop = uieditfield(app.GridLayout18, 'numeric');
            app.FreqStop.ValueDisplayFormat = '%.6f';
            app.FreqStop.Value = 108;
            app.DatePicker1 = uidatepicker(app.GridLayout18);
            app.IncioDatePickerLabel = uilabel(app.GridLayout18);
            app.IncioDatePickerLabel.Text = 'Início:';
            app.GridLayout19 = uigridlayout(app.GridLayout18);
            app.Spinner2 = uispinner(app.GridLayout19);
            app.Spinner2.Step = 10;
            app.Spinner2.Limits = [0 59];
            app.Spinner2.RoundFractionalValues = 'on';
            app.Spinner2.ValueDisplayFormat = '%.0f';
            app.Spinner1 = uispinner(app.GridLayout19);
            app.Spinner1.Limits = [0 23];
            app.Spinner1.RoundFractionalValues = 'on';
            app.Spinner1.ValueDisplayFormat = '%.0f';
            app.DatePicker2 = uidatepicker(app.GridLayout18);
            app.FimDatePickerLabel = uilabel(app.GridLayout18);
            app.FimDatePickerLabel.Text = 'Fim:';
            app.GridLayout20 = uigridlayout(app.GridLayout18);
            app.Spinner3 = uispinner(app.GridLayout20);
            app.Spinner3.Limits = [0 23];
            app.Spinner3.RoundFractionalValues = 'on';
            app.Spinner3.ValueDisplayFormat = '%.0f';
            app.Spinner4 = uispinner(app.GridLayout20);
            app.Spinner4.Limits = [0 59];
            app.Spinner4.RoundFractionalValues = 'on';
            app.Spinner4.ValueDisplayFormat = '%.0f';
            app.Node = uidropdown(app.GridLayout18);
            app.Node.Items = {};
            app.Node.Value = {};
            app.SensorLabel = uilabel(app.GridLayout18);
            app.SensorLabel.Text = 'Sensor:';
            app.Keyword = uieditfield(app.GridLayout18, 'text');
            app.PalavrachaveLabel = uilabel(app.GridLayout18);
            app.PalavrachaveLabel.Text = 'Palavra-chave:';
            app.Button1_Filter = uibutton(app.GridLayout18, 'push');
            app.Button1_Filter.ButtonPushedFcn = createCallbackFcn(app, @Button1_FilterPushed, true);
            app.Button1_Filter.Icon = 'Filter.png';
            app.Button1_Filter.Tooltip = {'Visualização padrão'};
            app.Button1_Filter.Text = '';
            app.Logo = uiimage(app.GridLayout18);
            app.Logo.ImageSource = 'Puzzle.png';
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.Button2_Download = uibutton(app.GridLayout23, 'push');
            app.Button2_Download.Icon = 'White_Save.png';
            app.Button2_Download.Enable = 'off';
            app.Button2_Download.Text = 'Download';
            app.Button3_Read = uibutton(app.GridLayout23, 'push');
            app.Button3_Read.Icon = 'White_Spectrum.png';
            app.Button3_Read.Enable = 'off';
            app.Button3_Read.Text = 'Leitura';
            app.GridLayout21 = uigridlayout(app.GridLayout23);
            app.Table = uitable(app.GridLayout21);
            app.Table.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.Table.RowName = {};
            app.Table.SelectionType = 'row';
            app.Table_Label1 = uilabel(app.GridLayout21);
            app.Table_Label1.Text = '  UF';
            app.Table_Label2 = uilabel(app.GridLayout21);
            app.Table_Label2.Text = '  Município';
            app.Table_Label3 = uilabel(app.GridLayout21);
            app.Table_Label3.Text = '  Sensor';
            app.Table_Label4 = uilabel(app.GridLayout21);
            app.Table_Label4.Text = {'  Freq. Inicial'; '  (MHz)'};
            app.Table_Label5 = uilabel(app.GridLayout21);
            app.Table_Label5.Text = {'  Freq. Final'; '  (MHz)'};
            app.Table_Label6 = uilabel(app.GridLayout21);
            app.Table_Label6.Text = '  Início';
            app.Table_Label7 = uilabel(app.GridLayout21);
            app.Table_Label7.Text = '  Fim';
            app.Table_Label8 = uilabel(app.GridLayout21);
            app.Table_Label8.Text = '  Descrição';
            app.Table_Label9 = uilabel(app.GridLayout21);
            app.Table_Label9.Text = '  Nome do arquivo';
            app.Title = uilabel(app.GridLayout23);
            app.Title.Text = 'Repositório de dados de monitoração do espectro de radiofrequências';
            app.Subtitle = uilabel(app.GridLayout23);
            app.Subtitle.Text = 'Consulta';
            app.Panel_11 = uipanel(app.GridLayout23);
            app.GridLayout24 = uigridlayout(app.Panel_11);
            app.FilesNumber = uilabel(app.GridLayout24);
            app.FilesNumber.Text = '617';
            app.FilesNumberLabel = uilabel(app.GridLayout24);
            app.FilesNumberLabel.Text = 'arquivos';
            app.GridLayout25 = uigridlayout(app.GridLayout23);
            app.DashboardFilter1 = DashboardFilter(app.GridLayout25);
            app.DashboardFilter1.ImageClickedFcn = createCallbackFcn(app, @DashboardFilterImageClicked, true);
            app.DashboardFilter2 = DashboardFilter(app.GridLayout25);
            app.DashboardFilter2.ImageClickedFcn = createCallbackFcn(app, @DashboardFilterImageClicked, true);
            app.DashboardFilter3 = DashboardFilter(app.GridLayout25);
            app.DashboardFilter3.ImageClickedFcn = createCallbackFcn(app, @DashboardFilterImageClicked, true);
            app.DashboardFilter4 = DashboardFilter(app.GridLayout25);
            app.DashboardFilter4.ImageClickedFcn = createCallbackFcn(app, @DashboardFilterImageClicked, true);
            app.DashboardFilter5 = DashboardFilter(app.GridLayout25);
            app.DashboardFilter5.ImageClickedFcn = createCallbackFcn(app, @DashboardFilterImageClicked, true);
            app.SortRows = SortRows(app.GridLayout23);
            app.SortRows.ImageClickedFcn = createCallbackFcn(app, @SortRowsValueChanged, true);
            app.SortRows.ValueChangedFcn = createCallbackFcn(app, @SortRowsValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRepository(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end