classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Grid1                 matlab.ui.container.GridLayout
        ButtonGroup           matlab.ui.container.ButtonGroup
        Separator4            matlab.ui.control.Image
        Separator3            matlab.ui.control.Image
        Separator2            matlab.ui.control.Image
        Separator1            matlab.ui.control.Image
        Button4_Label         matlab.ui.control.Label
        Button3_Label         matlab.ui.control.Label
        Button2_Label         matlab.ui.control.Label
        Button1_Label         matlab.ui.control.Label
        Button5_Info          matlab.ui.control.Button
        Button4_Graphic       matlab.ui.control.ToggleButton
        Button3_Data          matlab.ui.control.ToggleButton
        Button2_User          matlab.ui.control.ToggleButton
        Button1_Version       matlab.ui.control.ToggleButton
        TabGroup              matlab.ui.container.TabGroup
        Tab1_Version          matlab.ui.container.Tab
        Tab1_Grid             matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        version_info          matlab.ui.control.HTML
        Tab1_Tool1            matlab.ui.container.GridLayout
        version_anateldb      matlab.ui.control.Button
        version_redmine       matlab.ui.control.Button
        version_python        matlab.ui.control.Button
        Tab1_Grid1            matlab.ui.container.GridLayout
        Tab1_Grid1A           matlab.ui.container.GridLayout
        Tab1_refresh1         matlab.ui.control.Image
        Tab1_Title1           matlab.ui.control.Label
        Tab2_User             matlab.ui.container.Tab
        Tab2_Grid             matlab.ui.container.GridLayout
        Tab2_Tool3            matlab.ui.container.GridLayout
        img_Button            matlab.ui.control.Button
        Tab2_Panel3           matlab.ui.container.Panel
        Tab2_Grid3B           matlab.ui.container.GridLayout
        img_VisibilityLabel   matlab.ui.control.Label
        img_Visibility        matlab.ui.control.DropDown
        img_Resolution        matlab.ui.control.DropDown
        img_ResolutionLabel   matlab.ui.control.Label
        img_Format            matlab.ui.control.DropDown
        img_FormatLabel       matlab.ui.control.Label
        Tab2_Grid3            matlab.ui.container.GridLayout
        Tab2_Grid3A           matlab.ui.container.GridLayout
        Tab2_imgRefresh       matlab.ui.control.Image
        Tab2_Title3           matlab.ui.control.Label
        Tab2_Tool2            matlab.ui.container.GridLayout
        Color_Button          matlab.ui.control.Button
        Tab2_Panel2           matlab.ui.container.Panel
        Tab2_Grid2B           matlab.ui.container.GridLayout
        Color_WaterfallLabel  matlab.ui.control.Label
        Color_Waterfall       matlab.ui.control.DropDown
        GridLayout5           matlab.ui.container.GridLayout
        Color_ClearWrite      matlab.ui.control.Button
        Color_MaxHold         matlab.ui.control.Button
        Color_Median          matlab.ui.control.Button
        Color_MinHold         matlab.ui.control.Button
        CoresLabel            matlab.ui.control.Label
        Tab2_Grid2            matlab.ui.container.GridLayout
        Tab2_Grid2A           matlab.ui.container.GridLayout
        Color_imgRefresh      matlab.ui.control.Image
        Tab2_Title2           matlab.ui.control.Label
        Tab2_Tool1            matlab.ui.container.GridLayout
        Merge_Button          matlab.ui.control.Button
        Tab2_Panel1           matlab.ui.container.Panel
        Tab2_Grid1B           matlab.ui.container.GridLayout
        Merge_gpsGrid         matlab.ui.container.GridLayout
        Merge_gpsValue        matlab.ui.control.Spinner
        Merge_gpsLabel        matlab.ui.control.Label
        Merge_CheckBox7       matlab.ui.control.CheckBox
        Merge_CheckBox6       matlab.ui.control.CheckBox
        Merge_CheckBox5       matlab.ui.control.CheckBox
        Merge_Label2          matlab.ui.control.Label
        Merge_CheckBox4       matlab.ui.control.CheckBox
        Merge_CheckBox3       matlab.ui.control.CheckBox
        Merge_CheckBox2       matlab.ui.control.CheckBox
        Merge_CheckBox1       matlab.ui.control.CheckBox
        Merge_Label1          matlab.ui.control.Label
        Tab2_Grid1            matlab.ui.container.GridLayout
        Tab2_Grid1A           matlab.ui.container.GridLayout
        Merge_imgRefresh      matlab.ui.control.Image
        Tab2_Title1           matlab.ui.control.Label
        Tab3_Data             matlab.ui.container.Tab
        GridLayout5_2         matlab.ui.container.GridLayout
        Merge_Panel_2         matlab.ui.container.Panel
        Variables_Grid        matlab.ui.container.GridLayout
        Variables_Info2_3     matlab.ui.control.Label
        Variables_Info2_2     matlab.ui.control.Label
        Variables_Info2       matlab.ui.control.Label
        Variables_CheckBox4   matlab.ui.control.CheckBox
        Variables_CheckBox3   matlab.ui.control.CheckBox
        Variables_CheckBox2   matlab.ui.control.CheckBox
        Variables_CheckBox1   matlab.ui.control.CheckBox
        GridLayout13_2        matlab.ui.container.GridLayout
        Variables_Button      matlab.ui.control.Button
        GridLayout6_2         matlab.ui.container.GridLayout
        VARIVEISLabel         matlab.ui.control.Label
        GridLayout8_2         matlab.ui.container.GridLayout
        Tab4_Graphic          matlab.ui.container.Tab
        Graphic_Grid1         matlab.ui.container.GridLayout
        Graphic_mode          matlab.ui.control.DropDown
        Graphic_modeLabel     matlab.ui.control.Label
        Panel_2               matlab.ui.container.Panel
        Graphic_info          matlab.ui.control.HTML
        Graphic_tool          matlab.ui.container.GridLayout
        Graphic_Button        matlab.ui.control.Button
        Graphic_Title         matlab.ui.container.GridLayout
        Graphic_Grid2         matlab.ui.container.GridLayout
        Graphic_Label         matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
        newVersion
        
    end
    
    methods (Access = private)
        
        function Fcn1_UpdateVersion(app)
            app.CallingApp.General.ver = startup_Versions("Full", app.RootFolder);
        end


        function Fcn1_VersionInfo(app)
            cssStyle = '<style>p{font-family:helvetica;font-size:10px;color:#262626;margin: 10px 10px 10px 10px;}a{font-family:helvetica;font-size:10px;color:#808080;}</style>';
            app.version_info.HTMLSource  = sprintf(['%s<p><b>Sistema operacional:</b> <a>%s</a></p>' ...
                                                      '<p><b>Matlab:</b> <a>%s</a></p>'     ...
                                                      '<p><b>Python:</b> <a>%s</a></p>'     ...
                                                      '<p><b>appAnalise:</b> <a>%s</a></p>' ...
                                                      '<p><b>fiscaliza:</b> <a>%s</a></p>'  ...
                                                      '<p><b>anateldb:</b> <a>%s</a></p>'   ...
                                                      '<p>&nbsp;</p>'],                                  ...
                                                      cssStyle,                                          ...
                                                      app.CallingApp.General.ver.OS,                     ...
                                                      jsonencode(app.CallingApp.General.ver.Matlab),     ...
                                                      jsonencode(app.CallingApp.General.ver.Python),     ...
                                                      jsonencode(app.CallingApp.General.ver.appAnalise), ...
                                                      app.CallingApp.General.ver.fiscaliza,              ...
                                                      jsonencode(app.CallingApp.General.ver.anateldb));       
        end


        function Fcn2_Merge(app)
            set(findall(groot, 'Parent', app.Tab2_Grid1B, 'Type', 'uicheckbox'), 'Value', 0)
            
            if ismember(app.Merge_CheckBox1.Text, app.CallingApp.General.Merge.OptionalFieldsToRemove)
                app.Merge_CheckBox1.Value = 1;
                app.Merge_CheckBox5.Value = 1;
            end
            
            if ismember(app.Merge_CheckBox2.Text, app.CallingApp.General.Merge.OptionalFieldsToRemove)
                app.Merge_CheckBox2.Value = 1;
            end
            
            if ismember(app.Merge_CheckBox3.Text, app.CallingApp.General.Merge.OptionalFieldsToRemove)
                app.Merge_CheckBox3.Value = 1;
            end
            
            if ismember(app.Merge_CheckBox4.Text, app.CallingApp.General.Merge.OptionalFieldsToRemove)
                app.Merge_CheckBox4.Value = 1;
            end
            
            if app.CallingApp.General.Merge.DataTypeStatus    == "remove"
                app.Merge_CheckBox6.Value = 1;
            end
            
            if app.CallingApp.General.Merge.AntennaNameStatus == "remove"
                app.Merge_CheckBox7.Value = 1;
            end            
            
            app.Merge_gpsValue.Value = app.CallingApp.General.Merge.DistanceValue;            
            app.Merge_Button.Enable  = 0;
        end


        function Fcn2_Color(app)
            app.Color_MinHold.BackgroundColor    = app.CallingApp.General.Colors(1,:);
            app.Color_Median.BackgroundColor     = app.CallingApp.General.Colors(2,:);
            app.Color_MaxHold.BackgroundColor    = app.CallingApp.General.Colors(3,:);
            app.Color_ClearWrite.BackgroundColor = app.CallingApp.General.Colors(4,:);
            app.Color_Waterfall.Value            = app.CallingApp.General.Waterfall;

            app.Color_Button.Enable = 0;
        end


        function Fcn2_Image(app)
            app.img_Visibility.Value = app.CallingApp.General.Image.Visibility;
            app.img_Format.Value     = app.CallingApp.General.Image.Format;
            app.img_Resolution.Value = num2str(app.CallingApp.General.Image.Resolution);

            app.img_Button.Enable = 0;
        end
        
        
        function Fcn3_Graphics(app)
            graphRender = opengl('data');

            cssStyle = '<style>p{font-family:helvetica;font-size:10px;color:#262626;margin: 10px 10px 10px 10px;}a{font-family:helvetica;font-size:10px;color:#808080;}</style>';
            app.Graphic_info.HTMLSource  = sprintf('%s<p><b>OpenGL:</b> <a>%s</a></p><p>&nbsp;</p>', ...
                                                   cssStyle, jsonencode(app.CallingApp.General.ver.OpenGL));
            
            switch graphRender.HardwareSupportLevel
                case 'basic'; app.Graphic_mode.Value = 'Hardware Basic';
                case 'full';  app.Graphic_mode.Value = 'Hardware Full';
                case 'none';  app.Graphic_mode.Value = 'Software';
            end
            app.Graphic_Button.Enable = 0;            
        end


        function Fcn4_SaveFile(app)
            appVersion = rmfield(app.CallingApp.General, {'ver', 'Models', 'Report'});
            appVersion.userpath = '';

            writematrix(jsonencode(appVersion, "PrettyPrint", true), fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json'), "FileType", "text", "QuoteStrings", false, "WriteMode", "overwrite")
        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            if isempty(app.CallingApp.General.ver.fiscaliza)
                app.CallingApp.General.ver = startup_Versions("Full", app.RootFolder);
            end
            
            if isdeployed
                set(app.Variables_Grid.Children, 'Enable', 0)
            end

            Fcn1_VersionInfo(app)
            Fcn2_Merge(app)
            Fcn2_Color(app)
            Fcn2_Image(app)
            Fcn3_Graphics(app)
                        
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin5 = [];
            delete(app)
            
        end

        % Selection changed function: ButtonGroup
        function mainMenu_ButtonSelectionChanged(app, event)
            
            switch event.Source.SelectedObject
                case app.Button1_Version; idx = 1;
                case app.Button2_User;    idx = 2;
                case app.Button3_Data;    idx = 3;
                case app.Button4_Graphic; idx = 4;
            end

            app.TabGroup.SelectedTab = app.TabGroup.Children(idx);

        end

        % Button pushed function: Button5_Info
        function mainMenu_Button_Info(app, event)

            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winSettings>', '</winSettings>')));

            MessageBox(app, 'info', msg)

        end

        % Image clicked function: Tab1_refresh1
        function version_RefreshClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Verificando as versões dos módulos do appAnálise...');
        
            try
                presentVersion = struct('appAnalise', app.CallingApp.General.ver.appAnalise.Version, ...
                                        'fiscaliza',  app.CallingApp.General.ver.fiscaliza,  ...
                                        'anateldb',   app.CallingApp.General.ver.anateldb); 
                
                FileVersion    = webread(Misc_PublicLinks(app.RootFolder));
                app.newVersion = struct('appAnalise', FileVersion.appAnalise.Version, ...
                                        'fiscaliza',  FileVersion.fiscaliza.Version,  ...
                                        'anateldb',   FileVersion.anateldb);
                
                if isequal(presentVersion, app.newVersion)
                    MessageBox(app, 'info', sprintf('Módulos atualizados:\n• appAnalise, fiscaliza, anateldb'))
                    
                else
                    app.version_info.HTMLSource = sprintf(['%s<p>Operação: <b>CONSULTA VERSÕES</b></p>'  ...
                                                           '<p>• appAnalise: <a>%s</a></p>'              ...
                                                           '<p>• fiscaliza: <a>%s</a></p>'               ...
                                                           '<p>• anateldb: <a>%s</a></p><p>&nbsp;</p>'], ...
                                                           app.version_info.HTMLSource,                  ...
                                                           app.newVersion.appAnalise,                    ...
                                                           app.newVersion.fiscaliza,                     ...
                                                           jsonencode(app.newVersion.anateldb));
                                                                                               
                    str1 = {};
                    str2 = {};
                    if strcmp(presentVersion.appAnalise, app.newVersion.appAnalise); str1(end+1) = {'appAnalise'};
                    else;                                                            str2(end+1) = {'appAnalise'};
                    end                    
                    if strcmp(presentVersion.fiscaliza,  app.newVersion.fiscaliza);  str1(end+1) = {'fiscaliza'};
                    else;                                                            str2(end+1) = {'fiscaliza'}; app.version_redmine.Enable = 1;
                    end                    
                    if isequal(presentVersion.anateldb,  app.newVersion.anateldb);   str1(end+1) = {'anateldb'};
                    else;                                                            str2(end+1) = {'anateldb'};  app.version_anateldb.Enable = 1;
                    end
                    
                    if ~isempty(str1) && ~isempty(str2); msg = sprintf('Módulo(s) atualizado(s):\n• %s\n\nMódulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(str1, ', '), strjoin(str2, ', '));
                    else;                                msg = sprintf('Módulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(str2, ', '));
                    end

                    MessageBox(app, 'warning', msg)
                    
                end
                
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end

            delete(d)
            
        end

        % Button pushed function: version_python
        function version_pythonPushed(app, event)
            
            Python = pyenv;
            
            [file, pathfile] = uigetfile({'python*.exe', 'python*.exe'}, 'Selecione o arquivo executável do Python', Python.Executable);
            figure(app.UIFigure)
            
            if file
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento...');
                try                    
                    pyenv('Version', fullfile(pathfile, file));

                    Fcn1_UpdateVersion(app)
                    app.version_info.HTMLSource = sprintf(['%s<p>Operação: <b>EDIÇÃO AMBIENTE PYTHON</b></p>' ...
                                                           '<p>• Python: <a>%s</a></p>'                   ...
                                                           '<p>• fiscaliza: <a>%s</a></p><p>&nbsp;</p>'], ...
                                                           app.version_info.HTMLSource,                   ...
                                                           jsonencode(app.CallingApp.General.ver.Python), ...
                                                           app.CallingApp.General.ver.fiscaliza);

                    app.CallingApp.General.pythonPath = fullfile(pathfile, file);
                    Fcn4_SaveFile(app)
                catch ME
                    MessageBox(app, 'error', getReport(ME))
                end
                delete(d)
            end
            
        end

        % Button pushed function: version_redmine
        function version_redminePushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento...');            
            try
                Python   = pyenv;
                [~, msg] = system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), sprintf('install fiscaliza==%s', app.newVersion.fiscaliza)));

                Fcn1_UpdateVersion(app)
                app.version_info.HTMLSource = sprintf(['%s<p>Operação: <b>ATUALIZAÇÃO FISCALIZA</b></p>' ...
                                                       '<p><a>%s</a></p><p>&nbsp;</p>'], app.version_info.HTMLSource, msg);

                app.version_redmine.Enable  = 0;
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end
            delete(d)
            
        end

        % Button pushed function: version_anateldb
        function version_anateldbPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento...');
            try
                [~, anateldbLink] = Misc_PublicLinks(app.RootFolder);
                websave(fullfile(app.RootFolder, 'Temp', 'AnatelDB.parquet.gzip'), anateldbLink);

                ParquetInfoFile = parquetinfo(fullfile(app.RootFolder, 'Temp', 'AnatelDB.parquet.gzip'));
                if ~isequal(ParquetInfoFile.VariableTypes(1:9), ["string", "double", "single", "single", "string", "int16", "int32", "categorical", "single"])
                    error('Ao menos uma das colunas da nova versão do anateldb não está no formato esperado.')
                end

                if isfile(fullfile(app.RootFolder, 'DataBase', 'Anatel_old.mat'))
                    delete(fullfile(app.RootFolder, 'DataBase', 'Anatel_old.mat'))
                end

                while true
                    status = system(sprintf('rename "%s" "%s"', fullfile(app.RootFolder, 'DataBase', 'Anatel.mat'), 'Anatel_old.mat'));
                    if ~status
                        break
                    end
                    pause(.1)
                end

                clear global AnatelDB
                clear global AnatelDB_info
                drawnow nocallbacks
                
                anateldb_Read(app.RootFolder)

                global AnatelDB_info
                msg = sprintf('<b style="color:#262626;">anateldb:</b> %s', jsonencode(AnatelDB_info));

                Fcn1_UpdateVersion(app)
                app.version_info.HTMLSource = sprintf(['%s<p>Operação: <b>ATUALIZAÇÃO ANATELDB</b></p>' ...
                                                       '<p>• anateldb: <a>%s</a></p><p>&nbsp;</p>'],    ...
                                                       app.version_info.HTMLSource,                     ...
                                                       jsonencode(app.CallingApp.General.ver.anateldb));

                app.version_anateldb.Enable = 0;
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end
            delete(d)
            
        end

        % Value changed function: Merge_CheckBox1, Merge_CheckBox2, 
        % Merge_CheckBox3, Merge_CheckBox4, Merge_CheckBox6, 
        % Merge_CheckBox7, Merge_gpsValue
        function Merge_ValueChanged(app, event)
            
            if event.Source == app.Merge_CheckBox1
                app.Merge_CheckBox5.Value = app.Merge_CheckBox1.Value;
            end

            app.Merge_Button.Enable = 1;
            
        end

        % Image clicked function: Merge_imgRefresh
        function Merge_RefreshClicked(app, event)
            
            set(findall(groot, 'Parent', app.Tab2_Grid1B, 'Type', 'uicheckbox'), 'Value', 0)
            app.Merge_gpsValue.Value = 100;

            Merge_ButtonPushed(app)

        end

        % Button pushed function: Merge_Button
        function Merge_ButtonPushed(app, event)
            
            OptionalFieldsToRemove = {};
            if app.Merge_CheckBox1.Value
                OptionalFieldsToRemove = [OptionalFieldsToRemove; app.Merge_CheckBox1.Text];
            end
            
            if app.Merge_CheckBox2.Value
                OptionalFieldsToRemove = [OptionalFieldsToRemove; app.Merge_CheckBox2.Text];
            end
            
            if app.Merge_CheckBox3.Value
                OptionalFieldsToRemove = [OptionalFieldsToRemove; app.Merge_CheckBox3.Text];
            end
            
            if app.Merge_CheckBox4.Value
                OptionalFieldsToRemove = [OptionalFieldsToRemove; app.Merge_CheckBox4.Text];
            end
            MergeType.OptionalFieldsToRemove = OptionalFieldsToRemove;
            
            switch app.Merge_CheckBox5.Value
                case 0; MergeType.ThreadIDStatus = 'keep';
                case 1; MergeType.ThreadIDStatus = 'remove';
            end
            
            switch app.Merge_CheckBox6.Value
                case 0; MergeType.DataTypeStatus = 'keep';
                case 1; MergeType.DataTypeStatus = 'remove';
            end
            
            switch app.Merge_CheckBox7.Value
                case 0; MergeType.AntennaNameStatus = 'keep';
                case 1; MergeType.AntennaNameStatus = 'remove';
            end
            MergeType.DistanceValue = app.Merge_gpsValue.Value;
                        
            app.CallingApp.General.Merge = MergeType;
            
            Fcn2_Merge(app)
            Fcn4_SaveFile(app)
            
        end

        % Button pushed function: Color_ClearWrite, Color_MaxHold, 
        % Color_Median, Color_MinHold
        function Color_LineColorButtonPushed(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                switch event.Source
                    case app.Color_MinHold;    app.Color_MinHold.BackgroundColor    = selectedColor;
                    case app.Color_Median;     app.Color_Median.BackgroundColor     = selectedColor;
                    case app.Color_MaxHold;    app.Color_MaxHold.BackgroundColor    = selectedColor;
                    case app.Color_ClearWrite; app.Color_ClearWrite.BackgroundColor = selectedColor;
                end

                app.Color_Button.Enable = 1;
            end

        end

        % Value changed function: Color_Waterfall
        function Color_WaterfallValueChanged(app, event)
        
            app.Color_Button.Enable = 1;

        end

        % Image clicked function: Color_imgRefresh
        function Color_RefreshClicked(app, event)
            
            app.CallingApp.General.Colors    = [1.00,0.07,0.65; ...
                                                0.47,0.67,0.19; ...
                                                1.00,0.07,0.65; ...
                                                1.00,1.00,0.07];
            app.CallingApp.General.Waterfall = 'winter';

            Fcn2_Color(app)
            Fcn4_SaveFile(app)

        end

        % Button pushed function: Color_Button
        function Color_ButtonPushed(app, event)
            
            app.CallingApp.General.Colors    = [app.Color_MinHold.BackgroundColor; ...
                                                app.Color_Median.BackgroundColor;  ...
                                                app.Color_MaxHold.BackgroundColor; ...
                                                app.Color_ClearWrite.BackgroundColor];
            
            app.CallingApp.General.Waterfall = app.Color_Waterfall.Value;

            Fcn2_Color(app)
            Fcn4_SaveFile(app)

        end

        % Value changed function: img_Format, img_Resolution, 
        % img_Visibility
        function img_ValueChanged(app, event)

            app.img_Button.Enable = 1;
            
        end

        % Image clicked function: Tab2_imgRefresh
        function img_RefreshClicked(app, event)
            
            app.CallingApp.General.Image = struct('Visibility', 'on',   ...
                                                  'Format',     'jpeg', ...
                                                  'Resolution', 120);
            Fcn2_Image(app)
            Fcn4_SaveFile(app)

        end

        % Button pushed function: img_Button
        function img_ButtonPushed(app, event)
            
            app.CallingApp.General.Image = struct('Visibility', app.img_Visibility.Value, ...
                                                  'Format',     app.img_Format.Value,     ...
                                                  'Resolution', str2double(app.img_Resolution.Value));

            Fcn2_Image(app)
            Fcn4_SaveFile(app)

        end

        % Value changed function: Variables_CheckBox1, 
        % Variables_CheckBox2, Variables_CheckBox3, Variables_CheckBox4
        function Variables_CheckBoxValueChanged(app, event)
            
            if app.Variables_CheckBox1.Value || app.Variables_CheckBox2.Value || app.Variables_CheckBox3.Value || app.Variables_CheckBox4.Value
                app.Variables_Button.Enable = 1;
            else
                app.Variables_Button.Enable = 0;
            end
            
        end

        % Button pushed function: Variables_Button
        function Variables_ButtonPushed(app, event)
            
            if app.Variables_CheckBox1.Value
                assignin('base', 'metaData', app.CallingApp.metaData)
                openvar('metaData')
            end
            
            if app.Variables_CheckBox2.Value
                assignin('base', 'specData', app.CallingApp.specData)
                openvar('specData')
            end
            
            if app.Variables_CheckBox3.Value
                assignin('base', 'peaksTable', app.CallingApp.peaksTable)
                openvar('peaksTable')
            end
            
            if app.Variables_CheckBox4.Value
                assignin('base', 'exceptionList', app.CallingApp.exceptionList)
                openvar('exceptionList')
            end
            
        end

        % Value changed function: Graphic_mode
        function Graphic_TypeValueChanged(app, event)
            
            app.Graphic_Button.Enable = 1;
            
        end

        % Button pushed function: Graphic_Button
        function Graphic_ButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento...');

            switch app.Graphic_mode.Value
                case 'Software';       opengl software
                case 'Hardware Full';  opengl hardware
                case 'Hardware Basic'; opengl hardwarebasic
            end

            Fcn1_UpdateVersion(app)            
            Fcn3_Graphics(app)

            app.CallingApp.General.openGL = app.Graphic_mode.Value;
            Fcn4_SaveFile(app)

            delete(d)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.Grid1);
            app.Tab1_Version = uitab(app.TabGroup);
            app.Tab1_Version.Title = '1';
            app.Tab1_Grid = uigridlayout(app.Tab1_Version);
            app.Tab1_Grid1 = uigridlayout(app.Tab1_Grid);
            app.Tab1_Grid1.Tag = 'COLORLOCKED';
            app.Tab1_Title1 = uilabel(app.Tab1_Grid1);
            app.Tab1_Title1.Text = 'ASPECTOS GERAIS';
            app.Tab1_Grid1A = uigridlayout(app.Tab1_Grid1);
            app.Tab1_refresh1 = uiimage(app.Tab1_Grid1A);
            app.Tab1_refresh1.ImageClickedFcn = createCallbackFcn(app, @version_RefreshClicked, true);
            app.Tab1_refresh1.Tooltip = {''};
            app.Tab1_refresh1.ImageSource = 'LT_refresh.png';
            app.Tab1_Tool1 = uigridlayout(app.Tab1_Grid);
            app.version_python = uibutton(app.Tab1_Tool1, 'push');
            app.version_python.ButtonPushedFcn = createCallbackFcn(app, @version_pythonPushed, true);
            app.version_python.Icon = 'LT_Python.png';
            app.version_python.Tooltip = {''};
            app.version_python.Text = '';
            app.version_redmine = uibutton(app.Tab1_Tool1, 'push');
            app.version_redmine.ButtonPushedFcn = createCallbackFcn(app, @version_redminePushed, true);
            app.version_redmine.Icon = 'LT_redmine.png';
            app.version_redmine.Enable = 'off';
            app.version_redmine.Tooltip = {''};
            app.version_redmine.Text = '';
            app.version_anateldb = uibutton(app.Tab1_Tool1, 'push');
            app.version_anateldb.ButtonPushedFcn = createCallbackFcn(app, @version_anateldbPushed, true);
            app.version_anateldb.Icon = 'LT_mosaic.png';
            app.version_anateldb.Enable = 'off';
            app.version_anateldb.Tooltip = {''};
            app.version_anateldb.Text = '';
            app.Panel = uipanel(app.Tab1_Grid);
            app.version_info = uihtml(app.Panel);
            app.Tab2_User = uitab(app.TabGroup);
            app.Tab2_User.Title = '2';
            app.Tab2_Grid = uigridlayout(app.Tab2_User);
            app.Tab2_Grid1 = uigridlayout(app.Tab2_Grid);
            app.Tab2_Grid1.Tag = 'COLORLOCKED';
            app.Tab2_Title1 = uilabel(app.Tab2_Grid1);
            app.Tab2_Title1.Text = 'JUNÇÃO DE FLUXOS DE DADOS';
            app.Tab2_Grid1A = uigridlayout(app.Tab2_Grid1);
            app.Merge_imgRefresh = uiimage(app.Tab2_Grid1A);
            app.Merge_imgRefresh.ImageClickedFcn = createCallbackFcn(app, @Merge_RefreshClicked, true);
            app.Merge_imgRefresh.ImageSource = 'LT_refresh.png';
            app.Tab2_Panel1 = uipanel(app.Tab2_Grid);
            app.Tab2_Grid1B = uigridlayout(app.Tab2_Panel1);
            app.Merge_Label1 = uilabel(app.Tab2_Grid1B);
            app.Merge_Label1.Interpreter = 'html';
            app.Merge_Label1.Text = {'Campos da variável <b>app.metaData</b> que serão ignorados na junção de'; 'fluxos de dados:'};
            app.Merge_CheckBox1 = uicheckbox(app.Tab2_Grid1B);
            app.Merge_CheckBox1.ValueChangedFcn = createCallbackFcn(app, @Merge_ValueChanged, true);
            app.Merge_CheckBox1.Text = 'ThreadID';
            app.Merge_CheckBox2 = uicheckbox(app.Tab2_Grid1B);
            app.Merge_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @Merge_ValueChanged, true);
            app.Merge_CheckBox2.Text = 'FileFormat';
            app.Merge_CheckBox3 = uicheckbox(app.Tab2_Grid1B);
            app.Merge_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @Merge_ValueChanged, true);
            app.Merge_CheckBox3.Text = 'TaskName';
            app.Merge_CheckBox4 = uicheckbox(app.Tab2_Grid1B);
            app.Merge_CheckBox4.ValueChangedFcn = createCallbackFcn(app, @Merge_ValueChanged, true);
            app.Merge_CheckBox4.Text = 'Description';
            app.Merge_Label2 = uilabel(app.Tab2_Grid1B);
            app.Merge_Label2.Interpreter = 'html';
            app.Merge_Label2.Text = 'Campos da estrutura <b>MetaData</b>, da variável <b>app.metaData</b>, que serão ignorados na junção de fluxos de dados:';
            app.Merge_CheckBox5 = uicheckbox(app.Tab2_Grid1B);
            app.Merge_CheckBox5.Enable = 'off';
            app.Merge_CheckBox5.Text = 'ThreadID';
            app.Merge_CheckBox6 = uicheckbox(app.Tab2_Grid1B);
            app.Merge_CheckBox6.ValueChangedFcn = createCallbackFcn(app, @Merge_ValueChanged, true);
            app.Merge_CheckBox6.Text = 'DataType';
            app.Merge_CheckBox7 = uicheckbox(app.Tab2_Grid1B);
            app.Merge_CheckBox7.ValueChangedFcn = createCallbackFcn(app, @Merge_ValueChanged, true);
            app.Merge_CheckBox7.Text = 'AntennaName';
            app.Merge_gpsGrid = uigridlayout(app.Tab2_Grid1B);
            app.Merge_gpsLabel = uilabel(app.Merge_gpsGrid);
            app.Merge_gpsLabel.Interpreter = 'html';
            app.Merge_gpsLabel.Text = '<b>Distância mínima</b> registrada nos diferentes arquivos gerados por um mesmo sensor (metros):';
            app.Merge_gpsValue = uispinner(app.Merge_gpsGrid);
            app.Merge_gpsValue.Step = 50;
            app.Merge_gpsValue.Limits = [50 Inf];
            app.Merge_gpsValue.RoundFractionalValues = 'on';
            app.Merge_gpsValue.ValueDisplayFormat = '%.0f';
            app.Merge_gpsValue.ValueChangedFcn = createCallbackFcn(app, @Merge_ValueChanged, true);
            app.Merge_gpsValue.Value = 100;
            app.Tab2_Tool1 = uigridlayout(app.Tab2_Grid);
            app.Merge_Button = uibutton(app.Tab2_Tool1, 'push');
            app.Merge_Button.ButtonPushedFcn = createCallbackFcn(app, @Merge_ButtonPushed, true);
            app.Merge_Button.Icon = 'LT_edit.png';
            app.Merge_Button.Enable = 'off';
            app.Merge_Button.Text = '';
            app.Tab2_Grid2 = uigridlayout(app.Tab2_Grid);
            app.Tab2_Grid2.Tag = 'COLORLOCKED';
            app.Tab2_Title2 = uilabel(app.Tab2_Grid2);
            app.Tab2_Title2.Text = 'PLOT';
            app.Tab2_Grid2A = uigridlayout(app.Tab2_Grid2);
            app.Color_imgRefresh = uiimage(app.Tab2_Grid2A);
            app.Color_imgRefresh.ImageClickedFcn = createCallbackFcn(app, @Color_RefreshClicked, true);
            app.Color_imgRefresh.ImageSource = 'LT_refresh.png';
            app.Tab2_Panel2 = uipanel(app.Tab2_Grid);
            app.Tab2_Grid2B = uigridlayout(app.Tab2_Panel2);
            app.CoresLabel = uilabel(app.Tab2_Grid2B);
            app.CoresLabel.Text = 'Cores:';
            app.GridLayout5 = uigridlayout(app.Tab2_Grid2B);
            app.Color_MinHold = uibutton(app.GridLayout5, 'push');
            app.Color_MinHold.ButtonPushedFcn = createCallbackFcn(app, @Color_LineColorButtonPushed, true);
            app.Color_MinHold.Text = 'MinHold';
            app.Color_Median = uibutton(app.GridLayout5, 'push');
            app.Color_Median.ButtonPushedFcn = createCallbackFcn(app, @Color_LineColorButtonPushed, true);
            app.Color_Median.Text = 'Tendência central';
            app.Color_MaxHold = uibutton(app.GridLayout5, 'push');
            app.Color_MaxHold.ButtonPushedFcn = createCallbackFcn(app, @Color_LineColorButtonPushed, true);
            app.Color_MaxHold.Text = 'MaxHold';
            app.Color_ClearWrite = uibutton(app.GridLayout5, 'push');
            app.Color_ClearWrite.ButtonPushedFcn = createCallbackFcn(app, @Color_LineColorButtonPushed, true);
            app.Color_ClearWrite.Text = 'ClearWrite';
            app.Color_Waterfall = uidropdown(app.Tab2_Grid2B);
            app.Color_Waterfall.Items = {'winter', 'parula', 'turbo', 'gray', 'hot', 'jet', 'summer'};
            app.Color_Waterfall.ValueChangedFcn = createCallbackFcn(app, @Color_WaterfallValueChanged, true);
            app.Color_Waterfall.Value = 'jet';
            app.Color_WaterfallLabel = uilabel(app.Tab2_Grid2B);
            app.Color_WaterfallLabel.Text = 'Mapa de cor:';
            app.Tab2_Tool2 = uigridlayout(app.Tab2_Grid);
            app.Color_Button = uibutton(app.Tab2_Tool2, 'push');
            app.Color_Button.ButtonPushedFcn = createCallbackFcn(app, @Color_ButtonPushed, true);
            app.Color_Button.Icon = 'LT_edit.png';
            app.Color_Button.Enable = 'off';
            app.Color_Button.Text = '';
            app.Tab2_Grid3 = uigridlayout(app.Tab2_Grid);
            app.Tab2_Grid3.Tag = 'COLORLOCKED';
            app.Tab2_Title3 = uilabel(app.Tab2_Grid3);
            app.Tab2_Title3.Text = 'IMAGEM';
            app.Tab2_Grid3A = uigridlayout(app.Tab2_Grid3);
            app.Tab2_imgRefresh = uiimage(app.Tab2_Grid3A);
            app.Tab2_imgRefresh.ImageClickedFcn = createCallbackFcn(app, @img_RefreshClicked, true);
            app.Tab2_imgRefresh.ImageSource = 'LT_refresh.png';
            app.Tab2_Panel3 = uipanel(app.Tab2_Grid);
            app.Tab2_Grid3B = uigridlayout(app.Tab2_Panel3);
            app.img_FormatLabel = uilabel(app.Tab2_Grid3B);
            app.img_FormatLabel.Text = 'Formato:';
            app.img_Format = uidropdown(app.Tab2_Grid3B);
            app.img_Format.Items = {'jpeg', 'png'};
            app.img_Format.ValueChangedFcn = createCallbackFcn(app, @img_ValueChanged, true);
            app.img_Format.Value = 'jpeg';
            app.img_ResolutionLabel = uilabel(app.Tab2_Grid3B);
            app.img_ResolutionLabel.Text = 'Resolução (dpi):';
            app.img_Resolution = uidropdown(app.Tab2_Grid3B);
            app.img_Resolution.Items = {'100', '120', '150', '200'};
            app.img_Resolution.ValueChangedFcn = createCallbackFcn(app, @img_ValueChanged, true);
            app.img_Resolution.Value = '120';
            app.img_Visibility = uidropdown(app.Tab2_Grid3B);
            app.img_Visibility.Items = {'on', 'off'};
            app.img_Visibility.ValueChangedFcn = createCallbackFcn(app, @img_ValueChanged, true);
            app.img_Visibility.Value = 'on';
            app.img_VisibilityLabel = uilabel(app.Tab2_Grid3B);
            app.img_VisibilityLabel.Text = 'Visibilidade:';
            app.Tab2_Tool3 = uigridlayout(app.Tab2_Grid);
            app.img_Button = uibutton(app.Tab2_Tool3, 'push');
            app.img_Button.ButtonPushedFcn = createCallbackFcn(app, @img_ButtonPushed, true);
            app.img_Button.Icon = 'LT_edit.png';
            app.img_Button.Enable = 'off';
            app.img_Button.Text = '';
            app.Tab3_Data = uitab(app.TabGroup);
            app.Tab3_Data.Title = '3';
            app.GridLayout5_2 = uigridlayout(app.Tab3_Data);
            app.GridLayout6_2 = uigridlayout(app.GridLayout5_2);
            app.GridLayout6_2.Tag = 'COLORLOCKED';
            app.GridLayout8_2 = uigridlayout(app.GridLayout6_2);
            app.VARIVEISLabel = uilabel(app.GridLayout6_2);
            app.VARIVEISLabel.Text = 'VARIÁVEIS';
            app.GridLayout13_2 = uigridlayout(app.GridLayout5_2);
            app.Variables_Button = uibutton(app.GridLayout13_2, 'push');
            app.Variables_Button.ButtonPushedFcn = createCallbackFcn(app, @Variables_ButtonPushed, true);
            app.Variables_Button.Icon = 'LT_play.png';
            app.Variables_Button.Enable = 'off';
            app.Variables_Button.Text = '';
            app.Merge_Panel_2 = uipanel(app.GridLayout5_2);
            app.Variables_Grid = uigridlayout(app.Merge_Panel_2);
            app.Variables_CheckBox1 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox1.ValueChangedFcn = createCallbackFcn(app, @Variables_CheckBoxValueChanged, true);
            app.Variables_CheckBox1.Text = 'app.metaData';
            app.Variables_CheckBox2 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @Variables_CheckBoxValueChanged, true);
            app.Variables_CheckBox2.Text = 'app.specData';
            app.Variables_CheckBox3 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @Variables_CheckBoxValueChanged, true);
            app.Variables_CheckBox3.Text = 'app.peaksTable';
            app.Variables_CheckBox4 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox4.ValueChangedFcn = createCallbackFcn(app, @Variables_CheckBoxValueChanged, true);
            app.Variables_CheckBox4.Text = 'app.exceptionList';
            app.Variables_Info2 = uilabel(app.Variables_Grid);
            app.Variables_Info2.Interpreter = 'html';
            app.Variables_Info2.Text = 'A variável <b>app.specData</b> organiza metadados e matrizes numéricas de arquivos gerados em monitorações do espectro de radiofrequências. Trata-se de uma estrutura com os campos "Node", "MetaData", "Data", "gps" etc, cuja informação é orientada às faixas de frequências.';
            app.Variables_Info2_2 = uilabel(app.Variables_Grid);
            app.Variables_Info2_2.Interpreter = 'html';
            app.Variables_Info2_2.Text = 'A variável <b>app.metaData</b> organiza metadados de arquivos gerados em monitorações do espectro de radiofrequências. Trata-se de uma estrutura com os campos "File", "Type", "Data", "Samples" e "Memory", cuja informação é orientada aos arquivos.';
            app.Variables_Info2_3 = uilabel(app.Variables_Grid);
            app.Variables_Info2_3.Interpreter = 'html';
            app.Variables_Info2_3.Text = 'As variáveis <b>app.peaksTable</b> e <b>app.exceptionList</b> organizam informações dos processos de detecção e classificação de emissões.';
            app.Tab4_Graphic = uitab(app.TabGroup);
            app.Tab4_Graphic.Title = '4';
            app.Graphic_Grid1 = uigridlayout(app.Tab4_Graphic);
            app.Graphic_Title = uigridlayout(app.Graphic_Grid1);
            app.Graphic_Title.Tag = 'COLORLOCKED';
            app.Graphic_Label = uilabel(app.Graphic_Title);
            app.Graphic_Label.Text = 'UNIDADE GRÁFICA';
            app.Graphic_Grid2 = uigridlayout(app.Graphic_Title);
            app.Graphic_tool = uigridlayout(app.Graphic_Grid1);
            app.Graphic_Button = uibutton(app.Graphic_tool, 'push');
            app.Graphic_Button.ButtonPushedFcn = createCallbackFcn(app, @Graphic_ButtonPushed, true);
            app.Graphic_Button.Icon = 'LT_edit.png';
            app.Graphic_Button.Enable = 'off';
            app.Graphic_Button.Text = '';
            app.Panel_2 = uipanel(app.Graphic_Grid1);
            app.Graphic_info = uihtml(app.Panel_2);
            app.Graphic_modeLabel = uilabel(app.Graphic_Grid1);
            app.Graphic_modeLabel.Text = 'Tipo:';
            app.Graphic_mode = uidropdown(app.Graphic_Grid1);
            app.Graphic_mode.Items = {'Hardware Basic', 'Hardware Full', 'Software'};
            app.Graphic_mode.ValueChangedFcn = createCallbackFcn(app, @Graphic_TypeValueChanged, true);
            app.Graphic_mode.Value = 'Hardware Basic';
            app.ButtonGroup = uibuttongroup(app.Grid1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @mainMenu_ButtonSelectionChanged, true);
            app.ButtonGroup.Tag = 'COLORLOCKED';
            app.Button1_Version = uitogglebutton(app.ButtonGroup);
            app.Button1_Version.Icon = 'LT_home.png';
            app.Button1_Version.Text = '';
            app.Button1_Version.Value = true;
            app.Button2_User = uitogglebutton(app.ButtonGroup);
            app.Button2_User.Icon = 'LT_save.png';
            app.Button2_User.Text = '';
            app.Button3_Data = uitogglebutton(app.ButtonGroup);
            app.Button3_Data.Icon = 'LT_connect.png';
            app.Button3_Data.Text = '';
            app.Button4_Graphic = uitogglebutton(app.ButtonGroup);
            app.Button4_Graphic.Icon = 'LT_photo.png';
            app.Button4_Graphic.Text = '';
            app.Button5_Info = uibutton(app.ButtonGroup, 'push');
            app.Button5_Info.ButtonPushedFcn = createCallbackFcn(app, @mainMenu_Button_Info, true);
            app.Button5_Info.Icon = 'LT_info.png';
            app.Button5_Info.Text = '';
            app.Button1_Label = uilabel(app.ButtonGroup);
            app.Button1_Label.Text = 'Aspectos gerais';
            app.Button2_Label = uilabel(app.ButtonGroup);
            app.Button2_Label.Text = 'Personalização';
            app.Button3_Label = uilabel(app.ButtonGroup);
            app.Button3_Label.Text = 'Variáveis';
            app.Button4_Label = uilabel(app.ButtonGroup);
            app.Button4_Label.Text = 'Unidade gráfica';
            app.Separator1 = uiimage(app.ButtonGroup);
            app.Separator1.Enable = 'off';
            app.Separator1.ImageSource = 'LT_LineH.png';
            app.Separator2 = uiimage(app.ButtonGroup);
            app.Separator2.Enable = 'off';
            app.Separator2.ImageSource = 'LT_LineH.png';
            app.Separator3 = uiimage(app.ButtonGroup);
            app.Separator3.Enable = 'off';
            app.Separator3.ImageSource = 'LT_LineH.png';
            app.Separator4 = uiimage(app.ButtonGroup);
            app.Separator4.Enable = 'off';
            app.Separator4.ImageSource = 'LT_LineH.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end