classdef winSignalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Grid1                  matlab.ui.container.GridLayout
        GridLayout8            matlab.ui.container.GridLayout
        xLimFactorLabel        matlab.ui.control.Label
        xLimFactorValue        matlab.ui.control.Label
        xLimFactor_Down        matlab.ui.control.Image
        xLimFactor_Up          matlab.ui.control.Image
        Panel_5                matlab.ui.container.Panel
        GridLayout6            matlab.ui.container.GridLayout
        LOG                    matlab.ui.control.Label
        LOGLabel               matlab.ui.control.Label
        GridLayout5            matlab.ui.container.GridLayout
        Separator              matlab.ui.control.Image
        Button_edit            matlab.ui.control.Button
        Button_del             matlab.ui.control.Button
        Button_globalList      matlab.ui.control.Button
        Button_save            matlab.ui.control.Button
        Button_info            matlab.ui.control.Button
        Panel_4                matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        Grid4_2                matlab.ui.container.GridLayout
        StationID              matlab.ui.control.EditField
        StationIDLabel         matlab.ui.control.Label
        Info                   matlab.ui.control.TextArea
        InfoLabel              matlab.ui.control.Label
        RiskLevel_edited       matlab.ui.control.DropDown
        RiskLevel_editedLabel  matlab.ui.control.Label
        Compliance             matlab.ui.control.DropDown
        ComplianceLabel        matlab.ui.control.Label
        Type                   matlab.ui.control.DropDown
        TypeLabel              matlab.ui.control.Label
        RegInfoLabel_edited    matlab.ui.control.Label
        RegInfo_edited         matlab.ui.control.DropDown
        ServiceLabel_edited    matlab.ui.control.Label
        Service_edited         matlab.ui.control.NumericEditField
        Sublabel2              matlab.ui.control.Label
        Label2                 matlab.ui.control.Label
        Grid4                  matlab.ui.container.GridLayout
        Method                 matlab.ui.control.TextArea
        MethodLabel            matlab.ui.control.Label
        StationLabel_raw       matlab.ui.control.Label
        Station_raw            matlab.ui.control.NumericEditField
        ServiceLabel_raw       matlab.ui.control.Label
        Service_raw            matlab.ui.control.NumericEditField
        RegInfo_raw            matlab.ui.control.TextArea
        RegInfoLabel_raw       matlab.ui.control.Label
        Sublabel1              matlab.ui.control.Label
        Label1                 matlab.ui.control.Label
        DescriptionSubLabel    matlab.ui.control.Label
        Description            matlab.ui.control.TextArea
        DescriptionLabel       matlab.ui.control.Label
        Panel_3                matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        Station_dash           matlab.ui.control.Label
        StationLabel_dash      matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        Service_dash           matlab.ui.control.Label
        ServiceLabel_dash      matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Channel                matlab.ui.control.Label
        ChannelLabel           matlab.ui.control.Label
        Grid2                  matlab.ui.container.GridLayout
        Column2_2              matlab.ui.control.Label
        Column2                matlab.ui.control.Label
        Column9                matlab.ui.control.Label
        Column8                matlab.ui.control.Label
        Column4_2              matlab.ui.control.Label
        Column_1               matlab.ui.control.Label
        Column0                matlab.ui.control.Label
        Column4                matlab.ui.control.Label
        Column3                matlab.ui.control.Label
        Column5                matlab.ui.control.Label
        Column6                matlab.ui.control.Label
        Column7                matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        tableStyle  = uistyle("BackgroundColor", '#bfe5ff');
        row         = 1
        xLimFactor  = 2.5
        
    end
    
    methods (Access = private)
        
        function Fcn1_TableStyle(app)
            
            removeStyle(app.UITable)
            addStyle(app.UITable, uistyle('HorizontalAlignment', 'left'), 'column', 3:5)
                        
            if ~isempty(app.CallingApp.exceptionList)
                ind1 = [];
                for ii = 1:height(app.CallingApp.exceptionList)
                    ind2 = intersect(find(strcmp(app.CallingApp.peaksTable.Tag,  app.CallingApp.exceptionList.Tag(ii))), ...
                                     find(abs(app.CallingApp.peaksTable.Frequency - app.CallingApp.exceptionList.Frequency(ii)) <= 1e-5));

                    if ~isempty(ind2)
                        ind1 = [ind1; ind2];
                    end
                end
                
                if ~isempty(ind1)
                    addStyle(app.UITable, app.tableStyle, 'row', ind1)
                end
            end
            
        end
        
        
        function Fcn2_SelectedRowValues(app)
            
            % Panel 1
            app.Method.Value      = app.CallingApp.peaksTable.Detection{app.row};
            app.RegInfo_raw.Value = app.CallingApp.peaksTable.Regulatory{app.row};
            app.Service_raw.Value = double(app.CallingApp.peaksTable.Service(app.row));
            app.Station_raw.Value = double(app.CallingApp.peaksTable.Station(app.row));
            app.Description.Value = app.CallingApp.peaksTable.Description{app.row};

            % Panel 2
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(abs(app.CallingApp.exceptionList.Frequency - app.CallingApp.peaksTable.Frequency(app.row)) <= 1e-5));
            
            if isempty(idx)
                app.RegInfo_edited.Value = app.RegInfo_raw.Value;
                RegInfo_editedValueChanged(app)

                app.Service_edited.Value = app.Service_raw.Value;
                app.StationID.Value      = num2str(app.Station_raw.Value);
                
                app.Type.Value           = app.CallingApp.peaksTable.Type{app.row};
                app.Compliance.Value     = app.CallingApp.peaksTable.Irregular{app.row};
                ComplianceValueChanged(app)

                app.RiskLevel_edited.Value = app.CallingApp.peaksTable.RiskLevel{app.row};
                app.Info.Value             = '';

                app.Button_del.Enable       = 0;
                
            else
                idx = idx(1);
                
                app.RegInfo_edited.Value = app.CallingApp.exceptionList.Regulatory(idx);
                RegInfo_editedValueChanged(app)

                app.Service_edited.Value = double(app.CallingApp.exceptionList.Service(idx));
                app.StationID.Value      = num2str(app.CallingApp.exceptionList.Station(idx));

                app.Type.Value           = app.CallingApp.exceptionList.Type(idx);
                app.Compliance.Value     = app.CallingApp.exceptionList.Irregular(idx);
                ComplianceValueChanged(app)

                app.RiskLevel_edited.Value = app.CallingApp.exceptionList.RiskLevel(idx);
                app.Info.Value             = replace(app.CallingApp.exceptionList.Description(idx), '[EXC] ', '');

                app.Button_del.Enable       = 1;
            end

            % Panel 3 (Dashboard)
            app.Channel.Text = sprintf('%.3f MHz @ %.3f kHz', app.CallingApp.peaksTable.Frequency(app.row), ...
                                                              1000 * app.CallingApp.peaksTable.BW(app.row));

            app.Service_dash.Text = num2str(app.Service_edited.Value);
            app.Station_dash.Text = app.StationID.Value;

            Fcn3_Plot(app)
            Fcn5_Log(app, idx)
       
        end


        function Fcn3_Plot(app)

            idx = [];
            for ii = 1:numel(app.CallingApp.specData)
                Tag = sprintf('%s\nThreadID %d: %.3f - %.3f MHz', app.CallingApp.specData(ii).Node,                       ...
                                                                  app.CallingApp.specData(ii).ThreadID,                   ...
                                                                  app.CallingApp.specData(ii).MetaData.FreqStart ./ 1e+6, ...
                                                                  app.CallingApp.specData(ii).MetaData.FreqStop  ./ 1e+6);

                if strcmp(Tag, app.CallingApp.peaksTable.Tag{app.row})
                    idx = ii;
                    break
                end
            end

            cla(app.UIAxes)
            if ~isempty(idx)
                % Frequency limits
                [F0_axes, F1_axes] = Fcn4_xLim(app);

                F0_roi  = app.CallingApp.peaksTable.Frequency(app.row) - app.CallingApp.peaksTable.BW(app.row) / 2;
                BW_roi  = app.CallingApp.peaksTable.BW(app.row);

                % X/Y data and level limits
                rawData = app.CallingApp.specData(idx);
                xData   = linspace(rawData.MetaData.FreqStart/1e+6, rawData.MetaData.FreqStop/1e+6, rawData.MetaData.DataPoints);
                minY = min(rawData.statsData(:, 1));
                maxY = max(rawData.statsData(:, 4));

                color1   = [.93 .69 .13]; % MaxHold
                color2   = [.08 .49 .77]; % MinHold
                color3   = [.38 .56 .16]; % Median or mean
                roiColor = [0.40,0.73,0.88]; %[.00 .45 .74]; % Band roi region

                % Plot
                hold(app.UIAxes, 'on')
                area(app.UIAxes, xData, rawData.statsData(:, 1), 'EdgeColor', color2, 'FaceColor', color2, 'BaseValue', minY);
                plot(app.UIAxes, xData, rawData.statsData(:, 3), 'Color', color3);
                plot(app.UIAxes, xData, rawData.statsData(:, 4), 'Color', color1);
                hold(app.UIAxes, 'off')
    
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes], 'YLim', [minY, maxY])
                ylabel(app.UIAxes, sprintf('Nível (%s)', rawData.MetaData.metaString{1}))
    
                images.roi.Rectangle(app.UIAxes, Position = [F0_roi, -200, BW_roi, 400], ...
                                                 Color = roiColor, MarkerSize = 1, Deletable = 0, EdgeAlpha = 0, InteractionsAllowed = 'none');

                for ii = 1:numel(app.UIAxes.Children)
                    if ismember(app.UIAxes.Children(ii).Type, {'line', 'area', 'surface'}) 
                        Misc_DataTipSettings(app.UIAxes.Children(ii), rawData.MetaData.metaString{1})
                    end
                end

            else
                MessageBox(app, 'error', 'Não identificado o fluxo de dados relacionado à emissão...')
            end

        end


        function [F0_axes, F1_axes] = Fcn4_xLim(app)

            if app.CallingApp.peaksTable.BW(app.row) ~= 0
                F0_axes = app.CallingApp.peaksTable.Frequency(app.row) - app.xLimFactor *  app.CallingApp.peaksTable.BW(app.row);
                F1_axes = app.CallingApp.peaksTable.Frequency(app.row) + app.xLimFactor *  app.CallingApp.peaksTable.BW(app.row);
            else
                F0_axes = app.CallingApp.peaksTable.Frequency(app.row) - .5;
                F1_axes = app.CallingApp.peaksTable.Frequency(app.row) + .5;
            end

        end


        function Fcn5_Log(app, idx)

            if isempty(idx)
                logmsg = 'Não identificada alteração no registro.';

            else
                peakValues      = app.CallingApp.peaksTable(app.row,:);
                exceptionValues = app.CallingApp.exceptionList(idx,:);

                logmsg = {sprintf('<b style="color:#6199ba;">Emissão:</b> "%s" → "%s"',                   peakValues.Regulatory{1},  exceptionValues.Regulatory{1}); ...
                          sprintf('<b style="color:#6199ba;">Serviço:</b> "%.0f" → "%.0f"',               peakValues.Service,        exceptionValues.Service);       ...
                          sprintf('<b style="color:#6199ba;">Estação:</b> "%.0f" → "%.0f"',               peakValues.Station,        exceptionValues.Station);       ...
                          sprintf('<b style="color:#6199ba;">Tipo de emissão:</b> "%s" → "%s"',           peakValues.Type{1},        exceptionValues.Type{1});       ...
                          sprintf('<b style="color:#6199ba;">Indício de irregularidade:</b> "%s" → "%s"', peakValues.Irregular{1},   exceptionValues.Irregular{1});  ...
                          sprintf('<b style="color:#6199ba;">Potencial lesivo:</b> "%s" → "%s"',          peakValues.RiskLevel{1},   exceptionValues.RiskLevel{1});  ...
                          sprintf('<b style="color:#6199ba;">Descrição:</b> "%s" → "%s"',                 peakValues.Description{1}, exceptionValues.Description{1})};

                if strcmp(peakValues.Description{1}, exceptionValues.Description{1}); logmsg(7) = []; end
                if strcmp(peakValues.RiskLevel{1},   exceptionValues.RiskLevel{1});   logmsg(6) = []; end
                if strcmp(peakValues.Irregular{1},   exceptionValues.Irregular{1});   logmsg(5) = []; end
                if strcmp('Fundamental',             exceptionValues.Type{1});        logmsg(4) = []; end
                if        peakValues.Station   ==    exceptionValues.Station;         logmsg(3) = []; end
                if        peakValues.Service   ==    exceptionValues.Service;         logmsg(2) = []; end
                if strcmp(peakValues.Regulatory{1},  exceptionValues.Regulatory{1});  logmsg(1) = []; end

                logmsg = strjoin(logmsg, '; ');
            end

            app.LOG.Text = logmsg;

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnalise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnalise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnalise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.UITable.Data           = app.CallingApp.peaksTable(:, [1, 1, 5:12, 17:18]);

            app.UITable.Data.Tag       = extractBefore(app.UITable.Data.Tag, newline);
            app.UITable.Data.Tag_1     = extractBetween(app.UITable.Data.Tag_1, 'ThreadID', ':');
            app.UITable.Data.Frequency = app.UITable.Data.Frequency;
            app.UITable.Data.BW        = app.UITable.Data.BW * 1e+3;

            app.UIAxes.Interactions    = dataTipInteraction;
            enableDefaultInteractivity(app.UIAxes)

            Fcn1_TableStyle(app)
            pause(1)
            app.UITable.Selection = 1;

            Fcn2_SelectedRowValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin1 = [];
            delete(app)
            
        end

        % Cell selection callback: UITable
        function UITable_CellSelection(app, event)
            
            if app.row ~= event.Indices(1)
                app.row = event.Indices(1);
                Fcn2_SelectedRowValues(app)
            end
            
        end

        % Button pushed function: Button_globalList
        function Button_globalListPushed(app, event)
            
            global ExceptionGlobalList
            
            if isempty(ExceptionGlobalList)
                Misc_ExceptionGlobalList(app.RootFolder)
            end
            
            if isempty(ExceptionGlobalList)
                msg = 'Não identificada emissão na lista global de exceções contida no arquivo "ExceptionGlobalList.cfg".';

            else
                msg = [];
                for ii = 1:height(ExceptionGlobalList)
                    msg = sprintf('%s\n%s', msg, sprintf('<b>%.3f MHz</b> - "%s"', ExceptionGlobalList.Frequency(ii), ExceptionGlobalList.Description{ii}));
                end
            end

            MessageBox(app, 'info', msg)

        end

        % Button pushed function: Button_del
        function Button_delPushed(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(abs(app.CallingApp.exceptionList.Frequency - app.CallingApp.peaksTable.Frequency(app.row)) <= 1e-5));

            app.CallingApp.exceptionList(idx,:) = [];
            app.row = 1;

            Fcn1_TableStyle(app)
            app.UITable.Selection = 1;
            Fcn2_SelectedRowValues(app)

            scroll(app.UITable, 'top')

        end

        % Button pushed function: Button_edit
        function Button_edit_Clicked(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(abs(app.CallingApp.exceptionList.Frequency - app.CallingApp.peaksTable.Frequency(app.row)) <= 1e-5));

            if ~isempty(idx)
                idx = idx(1);
            end

            if ~strcmp(app.CallingApp.peaksTable.Regulatory{app.row}, app.RegInfo_edited.Value)   || ...
               app.CallingApp.peaksTable.Station(app.row) ~= str2double(app.StationID.Value) || ...
               ~strcmp(app.CallingApp.peaksTable.Type{app.row}, app.Type.Value)                   || ...
               ~strcmp(app.CallingApp.peaksTable.Irregular{app.row}, app.Compliance.Value)        || ...
               ~strcmp(app.CallingApp.peaksTable.RiskLevel{app.row}, app.RiskLevel_edited.Value)         || ...
               ~isempty(deblank(strjoin(app.Info.Value)))

                stationInfo = '';
                try
                    stationInfo = jsondecode(app.Info.Value{1});
                catch
                end
    
                if isstruct(stationInfo)
                    Service = str2double(stationInfo.Service);
                    if Service ~= -1; Regulatory = 'Licenciada';     Irregular = 'Não'; RiskLevel = '-';
                    else;             Regulatory = 'Não licenciada'; Irregular = 'Sim'; RiskLevel = 'Baixo';
                    end

                    exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},       ...
                                      app.CallingApp.peaksTable.Frequency(app.row), ...
                                      'Fundamental',                                ...
                                      Regulatory,                                   ...
                                      Service,                                      ...
                                      stationInfo.Station,                          ...
                                      stationInfo.Description,                      ...
                                      stationInfo.Distance,                         ...
                                      Irregular,                                    ...
                                      RiskLevel};
    
                else
                    switch app.RegInfo_edited.Value
                        case 'Licenciada'
                            if isempty(idx)
                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},         ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),   ...
                                                  app.Type.Value,                                 ...
                                                  app.RegInfo_edited.Value,                       ...
                                                  app.CallingApp.peaksTable.Service(app.row),     ...
                                                  app.CallingApp.peaksTable.Station(app.row),     ...
                                                  app.CallingApp.peaksTable.Description{app.row}, ...
                                                  app.CallingApp.peaksTable.Distance{app.row},    ...
                                                  app.Compliance.Value,                           ...
                                                  app.RiskLevel_edited.Value};
                            else
                                if str2double(app.StationID.Value) == app.CallingApp.peaksTable.Station(idx)
                                    Distance = app.CallingApp.peaksTable.Distance{app.row};
                                else
                                    Distance = app.CallingApp.exceptionList.Distance{idx};
                                end

                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},       ...
                                                  app.CallingApp.peaksTable.Frequency(app.row), ...
                                                  app.Type.Value,                               ...
                                                  app.RegInfo_edited.Value,                     ...
                                                  app.Service_edited.Value,                     ...
                                                  str2double(app.StationID.Value),              ...
                                                  app.Info.Value,                               ...
                                                  Distance,                                     ...
                                                  app.Compliance.Value,                         ...
                                                  app.RiskLevel_edited.Value};
                            end

                        otherwise
                            if isempty(deblank(strjoin(app.Info.Value)))
                                MessageBox(app, 'warning', 'Campo "Informações adicionais" não pode ficar vazio.')
                                return
                            end

                            exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                              app.CallingApp.peaksTable.Frequency(app.row),    ...
                                              app.Type.Value,                                  ...
                                              app.RegInfo_edited.Value,                        ...
                                              -1, -1, ['[EXC] ' strjoin(app.Info.Value)], '-', ...
                                              app.Compliance.Value,                            ...
                                              app.RiskLevel_edited.Value};
                    end
                end

                if isempty(idx); app.CallingApp.exceptionList(end+1,:) = exceptionValue;
                else;            app.CallingApp.exceptionList(idx,:)   = exceptionValue;
                end

                Fcn1_TableStyle(app)            
                Fcn2_SelectedRowValues(app)

            else
                Button_delPushed(app)
            end
            
            app.CallingApp.auxWin_SignalAnalysis
            
        end

        % Value changed function: StationID
        function StationIDValueChanged(app, event)
            
            try
                if (~contains(app.StationID.Value, '#') && isnan(str2double(app.StationID.Value))) || ...
                   ( contains(app.StationID.Value, '#') && (numel(app.StationID.Value) == 1     || ...
                                                                ~strcmp(app.StationID.Value(1), '#') || ...
                                                                isnan(str2double(app.StationID.Value(2:end)))))
                        error(['Esse campo deve ser preenchido com o número da estação ou a ID do registro no anateldb. ' ...
                               'O seu formato é numérico, com a ressalva de que quando inserido a ID do registro ' ...
                               'deve ser colocado o caractere "#" à frente do número. Por exemplo: 123456 ou #123456.'])
                end

                latNode  = app.CallingApp.peaksTable.Latitude(app.row);
                longNode = app.CallingApp.peaksTable.Longitude(app.row);

                StationInfo = anateldb_Query(app.StationID.Value, latNode, longNode, app.RootFolder);

                switch app.CallingApp.peaksTable.Regulatory{app.row}
                    case 'Licenciada'
                        msg1 = sprintf('<font style="font-size:11;"><b>• Indicação automática:</b>\n<font style="font-size:10;">Emissão Licenciada\nFrequência: %.3f MHz\nDescrição: %s\nDistância: %s km\n\n</font>', ...
                                        app.CallingApp.peaksTable.Frequency(app.row), app.CallingApp.peaksTable.Description{app.row}, app.CallingApp.peaksTable.Distance{app.row});
                    otherwise
                        msg1 = sprintf('<font style="font-size:11;"><b>• Indicação automática:</b>\n<font style="font-size:10;">Emissão %s\nFrequência: %.3f MHz\n\n</font>', ...
                                        app.CallingApp.peaksTable.Regulatory{app.row}, app.CallingApp.peaksTable.Frequency(app.row));
                end

                Service = StationInfo.Service;
                if Service ~= -1
                    msg2 = sprintf('<b>• Indicação manual:</b>\n<font style="font-size:10;">Emissão Licenciada\nFrequência(s): %s MHz\nDescrição: %s\nDistância: %.1f km\n\n</font><b>Confirma edição?</b></font>', ...
                                   StationInfo.Frequency, StationInfo.Description, StationInfo.Distance);
                else
                    msg2 = sprintf('<b>• Indicação manual:</b>\n<font style="font-size:10;">Emissão Não Licenciada\nFrequência(s): %s MHz\n\n</font><b>Confirma edição?</b></font>', ...
                                   StationInfo.Frequency);
                end
                
                selection = uiconfirm(app.UIFigure, [msg1, msg2], 'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Interpreter', 'html');
                
                if strcmp(selection, 'OK')
                    set(app.Info, 'Value', jsonencode(struct('ID', StationInfo.ID,                    ...
                                                             'Service', num2str(StationInfo.Service), ...
                                                             'Station', StationInfo.Station,          ...
                                                             'Description', StationInfo.Description,  ...
                                                             'Distance', sprintf('%.1f', StationInfo.Distance))), ...
                                  'Editable', 0)

                else
                    app.StationID.Value = num2str(app.CallingApp.peaksTable.Station(app.row));
                end

            catch ME
                app.StationID.Value = num2str(app.CallingApp.peaksTable.Station(app.row));
                
                MessageBox(app, 'error', getReport(ME))
            end
            
        end

        % Value changed function: RegInfo_edited
        function RegInfo_editedValueChanged(app, event)
            
            switch app.RegInfo_edited.Value
                case 'Licenciada'
                    set(app.StationID, 'Editable', 1)
                    set(app.Info,    'Editable', 0, 'Value', '')
                    
                    set(app.Type, 'Value', 'Fundamental', 'Enable', 0)
                    app.TypeLabel.Enable = 0;
    
                    set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')

                case {'Não licenciada', 'Não passível de licenciamento'}
                    set(app.Info,    'Editable', 1)
    
                    app.Type.Enable      = 1;
                    app.TypeLabel.Enable = 1;
    
                    switch app.RegInfo_edited.Value
                        case 'Não licenciada'
                            set(app.Compliance, 'Items', {'Sim'})
                            set(app.StationID, 'Editable', 1, 'Value', '-1')
                        case 'Não passível de licenciamento'
                            set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')
                            set(app.StationID, 'Editable', 0, 'Value', '-1')
                    end
            end
            ComplianceValueChanged(app)
            
        end

        % Image clicked function: xLimFactor_Down
        function xLimFactor_DownClicked(app, event)
            
            if app.xLimFactor > .5
                app.xLimFactor = app.xLimFactor - .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Image clicked function: xLimFactor_Up
        function xLimFactor_UpClicked(app, event)
            
            if app.xLimFactor < 10
                app.xLimFactor = app.xLimFactor + .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Value changed function: Compliance
        function ComplianceValueChanged(app, event)
            
            if strcmp(app.Compliance.Value, 'Não')
                set(app.RiskLevel_edited,      'Enable', 0, 'Items', {'-'})
                set(app.RiskLevel_editedLabel, 'Enable', 0)
            else
                set(app.RiskLevel_edited,      'Enable', 1, 'Items', {'Baixo', 'Médio', 'Alto'})
                set(app.RiskLevel_editedLabel, 'Enable', 1)
            end

        end

        % Button pushed function: Button_info
        function Button_infoPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winSignalAnalysis>', '</winSignalAnalysis>')));

            MessageBox(app, 'info', msg)

        end

        % Button pushed function: Button_save
        function Button_savePushed(app, event)
            
            CheckedRows = find([app.CallingApp.specData.reportFlag]);
            idx = Misc_FindOCCData(app.CallingApp.specData(CheckedRows));
            ProjectRows = sort([CheckedRows, idx]);

            [~, countTable] = ReportGenerator_Table_Summary(app.CallingApp.peaksTable, app.CallingApp.exceptionList);
            tableStr = app.CallingApp.ReportGenerator_Aux3(ProjectRows, countTable);

            filename = fullfile(app.CallingApp.menu_userPath.Value, sprintf('preReport_%s.json', datestr(now, 'yyyymmdd_THHMMSS')));
            try
                writematrix(tableStr, filename, "FileType", "text", "QuoteStrings", "none")
                MessageBox(app, 'info', sprintf('Informações relacionadas às emissões foram salvas no arquivo <b>%s</b>', filename))
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.Grid1);
            xlabel(app.UIAxes, 'Frequência (MHz)')
            ylabel(app.UIAxes, 'Nível')
            app.Grid2 = uigridlayout(app.Grid1);
            app.UITable = uitable(app.Grid2);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true false false false false false];
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.UITable.Multiselect = 'off';
            app.Column7 = uilabel(app.Grid2);
            app.Column7.Text = {'  Ocupação'; '  máxima (%)'};
            app.Column6 = uilabel(app.Grid2);
            app.Column6.Text = {'  Ocupação'; '  média (%)'};
            app.Column5 = uilabel(app.Grid2);
            app.Column5.Text = {'  Nível'; '  máximo'};
            app.Column3 = uilabel(app.Grid2);
            app.Column3.Text = {'  Largura de'; '  banda (kHz)'};
            app.Column4 = uilabel(app.Grid2);
            app.Column4.Text = {'  Nível'; '  mínimo'};
            app.Column0 = uilabel(app.Grid2);
            app.Column0.Text = {'   ThreadID'; '  '};
            app.Column_1 = uilabel(app.Grid2);
            app.Column_1.Text = {'  Sensor'; '  '};
            app.Column4_2 = uilabel(app.Grid2);
            app.Column4_2.Text = {'  Nível'; '  médio'};
            app.Column8 = uilabel(app.Grid2);
            app.Column8.Text = '  Identificação do provável emissor';
            app.Column9 = uilabel(app.Grid2);
            app.Column9.Text = {'  Distância'; '  (km)'};
            app.Column2 = uilabel(app.Grid2);
            app.Column2.Text = {'  Frequência'; '  (MHz)'};
            app.Column2_2 = uilabel(app.Grid2);
            app.Column2_2.Text = {'   Frequência'; '   truncada (MHz)'};
            app.Panel = uipanel(app.Grid1);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.GridLayout2.Tag = 'COLORLOCKED';
            app.ChannelLabel = uilabel(app.GridLayout2);
            app.ChannelLabel.Text = 'EMISSÃO';
            app.Channel = uilabel(app.GridLayout2);
            app.Channel.Text = '';
            app.Panel_2 = uipanel(app.Grid1);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.GridLayout3.Tag = 'COLORLOCKED';
            app.ServiceLabel_dash = uilabel(app.GridLayout3);
            app.ServiceLabel_dash.Text = 'SERVIÇO';
            app.Service_dash = uilabel(app.GridLayout3);
            app.Service_dash.Text = '';
            app.Panel_3 = uipanel(app.Grid1);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.GridLayout4.Tag = 'COLORLOCKED';
            app.StationLabel_dash = uilabel(app.GridLayout4);
            app.StationLabel_dash.Text = 'ESTAÇÃO';
            app.Station_dash = uilabel(app.GridLayout4);
            app.Station_dash.Text = '';
            app.Panel_4 = uipanel(app.Grid1);
            app.GridLayout7 = uigridlayout(app.Panel_4);
            app.Grid4 = uigridlayout(app.GridLayout7);
            app.DescriptionLabel = uilabel(app.Grid4);
            app.DescriptionLabel.Text = 'Descrição:';
            app.Description = uitextarea(app.Grid4);
            app.Description.Editable = 'off';
            app.DescriptionSubLabel = uilabel(app.Grid4);
            app.DescriptionSubLabel.Text = 'Serviço-Status, Classe, Entidade (Fistel, Estação), Município/UF';
            app.Label1 = uilabel(app.Grid4);
            app.Label1.Text = 'ASPECTOS GERAIS';
            app.Sublabel1 = uilabel(app.Grid4);
            app.Sublabel1.Text = 'Os dados extraídos da base legal da Agência são restritos aos sistemas Mosaico, STEL e SRD, não englobando informações das estações do Serviço 010.';
            app.RegInfoLabel_raw = uilabel(app.Grid4);
            app.RegInfoLabel_raw.Text = 'Emissão:';
            app.RegInfo_raw = uitextarea(app.Grid4);
            app.RegInfo_raw.Editable = 'off';
            app.Service_raw = uieditfield(app.Grid4, 'numeric');
            app.Service_raw.Limits = [-1 Inf];
            app.Service_raw.RoundFractionalValues = 'on';
            app.Service_raw.ValueDisplayFormat = '%.0f';
            app.Service_raw.Editable = 'off';
            app.Service_raw.Value = -1;
            app.ServiceLabel_raw = uilabel(app.Grid4);
            app.ServiceLabel_raw.Text = 'Serviço:';
            app.Station_raw = uieditfield(app.Grid4, 'numeric');
            app.Station_raw.Limits = [-1 Inf];
            app.Station_raw.RoundFractionalValues = 'on';
            app.Station_raw.ValueDisplayFormat = '%.0f';
            app.Station_raw.Editable = 'off';
            app.Station_raw.Value = -1;
            app.StationLabel_raw = uilabel(app.Grid4);
            app.StationLabel_raw.Text = 'Estação:';
            app.MethodLabel = uilabel(app.Grid4);
            app.MethodLabel.Text = 'Algoritmo detecção:';
            app.Method = uitextarea(app.Grid4);
            app.Method.Editable = 'off';
            app.Grid4_2 = uigridlayout(app.GridLayout7);
            app.Label2 = uilabel(app.Grid4_2);
            app.Label2.Text = 'ANÁLISE QUALITATIVA';
            app.Sublabel2 = uilabel(app.Grid4_2);
            app.Sublabel2.Text = 'Informações que possibilitam uma compreensão mais profunda acerca das condições de uso do espectro de radiofrequências.';
            app.Service_edited = uieditfield(app.Grid4_2, 'numeric');
            app.Service_edited.Limits = [-1 Inf];
            app.Service_edited.RoundFractionalValues = 'on';
            app.Service_edited.ValueDisplayFormat = '%.0f';
            app.Service_edited.Editable = 'off';
            app.Service_edited.Value = -1;
            app.ServiceLabel_edited = uilabel(app.Grid4_2);
            app.ServiceLabel_edited.Text = 'Serviço:';
            app.RegInfo_edited = uidropdown(app.Grid4_2);
            app.RegInfo_edited.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            app.RegInfo_edited.ValueChangedFcn = createCallbackFcn(app, @RegInfo_editedValueChanged, true);
            app.RegInfo_edited.Value = 'Licenciada';
            app.RegInfoLabel_edited = uilabel(app.Grid4_2);
            app.RegInfoLabel_edited.Text = 'Emissão:';
            app.TypeLabel = uilabel(app.Grid4_2);
            app.TypeLabel.Text = 'Tipo de emissão:';
            app.Type = uidropdown(app.Grid4_2);
            app.Type.Items = {'Fundamental', 'Harmônico de fundamental', 'Produto de intermodulação', 'Espúrio', 'Não identificado', 'Não se manifestou', 'Pendente de identificação'};
            app.Type.Value = 'Fundamental';
            app.ComplianceLabel = uilabel(app.Grid4_2);
            app.ComplianceLabel.Text = 'Indício de irregularidade?';
            app.Compliance = uidropdown(app.Grid4_2);
            app.Compliance.Items = {'Não', 'Sim'};
            app.Compliance.ValueChangedFcn = createCallbackFcn(app, @ComplianceValueChanged, true);
            app.Compliance.Value = 'Não';
            app.RiskLevel_editedLabel = uilabel(app.Grid4_2);
            app.RiskLevel_editedLabel.Text = 'Potencial lesivo:';
            app.RiskLevel_edited = uidropdown(app.Grid4_2);
            app.RiskLevel_edited.Items = {'-', 'Baixo', 'Médio', 'Alto'};
            app.RiskLevel_edited.Value = '-';
            app.InfoLabel = uilabel(app.Grid4_2);
            app.InfoLabel.Text = 'Descrição:';
            app.Info = uitextarea(app.Grid4_2);
            app.StationIDLabel = uilabel(app.Grid4_2);
            app.StationIDLabel.Text = 'Estação/ID:';
            app.StationID = uieditfield(app.Grid4_2, 'text');
            app.StationID.ValueChangedFcn = createCallbackFcn(app, @StationIDValueChanged, true);
            app.GridLayout5 = uigridlayout(app.Grid1);
            app.Button_info = uibutton(app.GridLayout5, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @Button_infoPushed, true);
            app.Button_info.Icon = 'LT_info.png';
            app.Button_info.Text = '';
            app.Button_save = uibutton(app.GridLayout5, 'push');
            app.Button_save.ButtonPushedFcn = createCallbackFcn(app, @Button_savePushed, true);
            app.Button_save.Icon = 'LT_save.png';
            app.Button_save.Tooltip = {'Exportar lista de emissões'};
            app.Button_save.Text = '';
            app.Button_globalList = uibutton(app.GridLayout5, 'push');
            app.Button_globalList.ButtonPushedFcn = createCallbackFcn(app, @Button_globalListPushed, true);
            app.Button_globalList.Icon = 'LT_list.png';
            app.Button_globalList.Tooltip = {'Lista global de exceções'};
            app.Button_globalList.Text = '';
            app.Button_del = uibutton(app.GridLayout5, 'push');
            app.Button_del.ButtonPushedFcn = createCallbackFcn(app, @Button_delPushed, true);
            app.Button_del.Icon = 'LT_redX.png';
            app.Button_del.Enable = 'off';
            app.Button_del.Tooltip = {'Excluir alterações'};
            app.Button_del.Text = '';
            app.Button_edit = uibutton(app.GridLayout5, 'push');
            app.Button_edit.ButtonPushedFcn = createCallbackFcn(app, @Button_edit_Clicked, true);
            app.Button_edit.Icon = 'DT_edit.png';
            app.Button_edit.Text = 'Editar emissão';
            app.Separator = uiimage(app.GridLayout5);
            app.Separator.Enable = 'off';
            app.Separator.ImageSource = 'LT_LineV.png';
            app.Panel_5 = uipanel(app.Grid1);
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.LOGLabel = uilabel(app.GridLayout6);
            app.LOGLabel.Text = 'LOG';
            app.LOG = uilabel(app.GridLayout6);
            app.LOG.Interpreter = 'html';
            app.LOG.Text = '';
            app.GridLayout8 = uigridlayout(app.Grid1);
            app.xLimFactor_Up = uiimage(app.GridLayout8);
            app.xLimFactor_Up.ImageClickedFcn = createCallbackFcn(app, @xLimFactor_UpClicked, true);
            app.xLimFactor_Up.ImageSource = 'LT_upArrow_Zoom.png';
            app.xLimFactor_Down = uiimage(app.GridLayout8);
            app.xLimFactor_Down.ImageClickedFcn = createCallbackFcn(app, @xLimFactor_DownClicked, true);
            app.xLimFactor_Down.ImageSource = 'LT_downArrow_Zoom.png';
            app.xLimFactorValue = uilabel(app.GridLayout8);
            app.xLimFactorValue.Text = '5';
            app.xLimFactorLabel = uilabel(app.GridLayout8);
            app.xLimFactorLabel.Text = 'Span/BW:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSignalAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end