classdef winTemplate_aux < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Grid1             matlab.ui.container.GridLayout
        Button            matlab.ui.control.Button
        Panel1            matlab.ui.container.Panel
        GridLayout        matlab.ui.container.GridLayout
        Tree1             matlab.ui.container.CheckBoxTree
        Tree1Label        matlab.ui.control.Label
        Tree2             matlab.ui.container.CheckBoxTree
        Tree2CheckBox     matlab.ui.control.CheckBox
        Author            matlab.ui.control.EditField
        AuthorLabel       matlab.ui.control.Label
        Description       matlab.ui.control.EditField
        DescriptionLabel  matlab.ui.control.Label
        Version           matlab.ui.control.EditField
        VersionLabel      matlab.ui.control.Label
        Name              matlab.ui.control.EditField
        NameLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        General

        K3 = {'Banda',                                                                                                                ...
              'N1_Licenciada', 'N1_NaoLicenciada', 'N1_NaoLicenciavel',                                                               ...
              'N2_Fundamental', 'N2_Harmonico', 'N2_Produto', 'N2_Espuria', 'N2_NaoIdentificada', 'N2_NaoManifestada', 'N2_Pendente', ...
              'N3_Licenciada', 'N3_NaoLicenciada', 'N3_NaoLicenciavel',                                                               ...
              'N4_Baixo', 'N4_Medio', 'N4_Alto',                                                                                      ...
              'N5_Radcom', 'N5_ClasseC', 'N5_ClasseB', 'N5_ClasseA', 'N5_ClasseE'}
        
    end
    
    methods (Access = private)

        function InitialValues(app)

            idx1 = app.General.idx;
            
            switch app.General.Operation
                case 'edit'
                    app.Name.Value    = app.CallingApp.Models.Name{idx1};
                    app.Version.Value = app.CallingApp.Models.Version{idx1};

                otherwise
                    kk = 1;
                    while true
                        newModel = sprintf('%s (%d)', app.CallingApp.Models.Name{idx1}, kk);    
                        if isempty(find(strcmp(app.CallingApp.Models.Name, newModel), 1))
                            break
                        end
                        kk = kk+1;
                    end
                    app.Name.Value    = newModel;
                    app.Version.Value = '1.00';
            end

            app.Description.Value = app.CallingApp.Models.Description{idx1};            
            app.Author.Value      = app.CallingApp.Models.Author{idx1};

            
            % TREE1 BUILDING
            for ii = 1:height(app.CallingApp.K2)
                uitreenode(app.Tree1, 'Text', app.CallingApp.K2(ii,2), 'NodeData', ii);
            end

            idx2 = cellfun(@(x) find(app.CallingApp.K2(:,1) == x, 1), app.CallingApp.Models.RelatedServices{idx1});
            app.Tree1.CheckedNodes = app.Tree1.Children(idx2);

            
            % TREE2 BUILDING
            for ii = 1:numel(app.K3)
                uitreenode(app.Tree2, 'Text', app.K3{ii}, 'NodeData', ii);
            end

            idx3 = app.CallingApp.Models.JournalTable{idx1};
            if ~isempty(idx3)
                app.Tree2CheckBox.Value = 1;
                set(app.Tree2, 'Enable', 1, 'CheckedNodes', app.Tree2.Children(idx3))
            end

        end
        

        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Operation, idx)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            app.General = struct('Operation', Operation, ...
                                 'idx', idx);

            switch Operation
                case 'edit'; set(app.Button, 'Text', 'Editar modelo', 'Icon', 'DT_edit.png');    app.Name.Editable = 'off';
                case 'new';  set(app.Button, 'Text', 'Criar modelo',  'Icon', 'DT_AddFiles.png')
                case 'open'; set(app.Button, 'Text', 'Abrir modelo',  'Icon', 'DT_OpenFile.png')
            end

            InitialValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            figure(app.CallingApp.UIFigure)
            delete(app)
            
        end

        % Value changed function: Name
        function NameValueChanged(app, event)
            
            app.Name.Value = strtrim(app.Name.Value);

            if isempty(app.Name.Value); app.Button.Enable = 0;
            else;                       app.Button.Enable = 1;
            end

        end

        % Value changed function: Version
        function VersionValueChanged(app, event)
            
            [startArray, stopArray] = regexp(app.Version.Value, '\d{1,2}[.]\d{2}');

            if isempty(startArray) | numel(startArray) > 1 | (stopArray-startArray+1) ~= numel(app.Version.Value)
                MessageBox(app, 'warning', 'Formato inválido! Exemplos válidos: "1.01" ou "2.00" ou "10.01".')
                app.Version.Value = event.PreviousValue;
            end

        end

        % Value changed function: Tree2CheckBox
        function CheckBoxValueChanged(app, event)
            
            if app.Tree2CheckBox.Value
                app.Tree2.Enable = 1;
            else
                app.Tree2.Enable = 0;
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if isempty(app.Tree1.CheckedNodes)
                MessageBox(app, 'warning', 'O modelo deve estar relacionado a algum serviço de telecomunicações.')
                return

            elseif app.Name.Editable & ismember(app.Name.Value, app.CallingApp.Models.Name)
                MessageBox(app, 'warning', 'O nome do novo modelo não pode coincidir com o nome de algum modelo já existente.')
                return
            end

            if isempty(app.Tree2.CheckedNodes); JournalTable = [];
            else;                               JournalTable = [app.Tree2.CheckedNodes.NodeData]';
            end

            Operation = app.General.Operation;
            idx = app.General.idx;

            try
                switch Operation
                    case 'edit'
                        app.CallingApp.Models(idx,[1,3:5,7:8]) = {app.Name.Value,        ...
                                                                  app.Description.Value, ...
                                                                  app.Author.Value,      ...
                                                                  app.Version.Value,     ...
                                                                  cellstr(app.CallingApp.K2([app.Tree1.CheckedNodes.NodeData]',1)), ...
                                                                  JournalTable};
    
                    otherwise
                        switch Operation
                            case 'new'
                                oldFile = fullfile(app.RootFolder, 'Template', app.CallingApp.Models.Template{idx});

                            case 'open'
                                [File, Folder] = uigetfile({'*.json', '*.json'}, 'Selecione um arquivo', 'MultiSelect', 'off');
                                figure(app.UIFigure)
                    
                                if ~File
                                    closeFcn(app)
                                    return
                                end
                                oldFile = fullfile(Folder, File);
                        end
                        newFile = sprintf('Template_%s.json', datestr(now, "yyyymmdd_THHMMSS"));
    
                        app.CallingApp.Models(end+1,:) = {app.Name.Value,        ...
                                                          newFile,               ...
                                                          app.Description.Value, ...
                                                          app.Author.Value,      ...
                                                          app.Version.Value,     ...
                                                          datestr(now, "dd/mm/yyyy"), ...
                                                          cellstr(app.CallingApp.K2([app.Tree1.CheckedNodes.NodeData]',1)), ...
                                                          JournalTable};
    
                        copyfile(oldFile, fullfile(app.RootFolder, 'Template', newFile), 'f')
                        fileattrib(fullfile(app.RootFolder, 'Template', newFile), '+w')
                end
                
                writematrix(jsonencode(app.CallingApp.Models, "PrettyPrint", true), fullfile(app.RootFolder, 'Template', 'html_General.cfg'), "FileType", "text", "QuoteStrings", "none", "WriteMode", "overwrite")
                
                app.CallingApp.Fcn_ModelsStartup(idx)
                closeFcn(app)

            catch ME
                MessageBox(app, 'error', getReport(ME))
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel1 = uipanel(app.Grid1);
            app.GridLayout = uigridlayout(app.Panel1);
            app.NameLabel = uilabel(app.GridLayout);
            app.NameLabel.Text = 'Nome:';
            app.Name = uieditfield(app.GridLayout, 'text');
            app.Name.ValueChangedFcn = createCallbackFcn(app, @NameValueChanged, true);
            app.VersionLabel = uilabel(app.GridLayout);
            app.VersionLabel.Text = 'Versão:';
            app.Version = uieditfield(app.GridLayout, 'text');
            app.Version.ValueChangedFcn = createCallbackFcn(app, @VersionValueChanged, true);
            app.DescriptionLabel = uilabel(app.GridLayout);
            app.DescriptionLabel.Text = 'Descrição:';
            app.Description = uieditfield(app.GridLayout, 'text');
            app.AuthorLabel = uilabel(app.GridLayout);
            app.AuthorLabel.Text = 'Autor:';
            app.Author = uieditfield(app.GridLayout, 'text');
            app.Tree2CheckBox = uicheckbox(app.GridLayout);
            app.Tree2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.Tree2CheckBox.Text = 'Incluir tabela no Fiscaliza';
            app.Tree2 = uitree(app.GridLayout, 'checkbox');
            app.Tree2.Enable = 'off';
            app.Tree1Label = uilabel(app.GridLayout);
            app.Tree1Label.Text = 'Serviços relacionados:';
            app.Tree1 = uitree(app.GridLayout, 'checkbox');
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_edit.png');
            app.Button.Text = 'Editar modelo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTemplate_aux(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end