classdef winTreeEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Grid1                   matlab.ui.container.GridLayout
        Grid2                   matlab.ui.container.GridLayout
        Column8                 matlab.ui.control.Label
        Column4_2               matlab.ui.control.Label
        Column_1                matlab.ui.control.Label
        Column0                 matlab.ui.control.Label
        Column1                 matlab.ui.control.Label
        Column2                 matlab.ui.control.Label
        Column4                 matlab.ui.control.Label
        Column3                 matlab.ui.control.Label
        Column5                 matlab.ui.control.Label
        Column6                 matlab.ui.control.Label
        Column7                 matlab.ui.control.Label
        Column9                 matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        Grid5                   matlab.ui.container.GridLayout
        Label3                  matlab.ui.control.Label
        Tree                    matlab.ui.container.Tree
        Grid3                   matlab.ui.container.GridLayout
        Grid4                   matlab.ui.container.GridLayout
        Label3_2                matlab.ui.control.Label
        addButton               matlab.ui.control.Button
        Info                    matlab.ui.control.TextArea
        InfoLabel               matlab.ui.control.Label
        Modulation              matlab.ui.control.DropDown
        ModulationLabel         matlab.ui.control.Label
        Type                    matlab.ui.control.DropDown
        TypeLabel               matlab.ui.control.Label
        DescriptionSubLabel     matlab.ui.control.Label
        TagLabel                matlab.ui.control.Label
        Tag                     matlab.ui.control.TextArea
        StationLabel            matlab.ui.control.Label
        Station                 matlab.ui.control.NumericEditField
        CF                      matlab.ui.control.NumericEditField
        FrequnciacanalMHzLabel  matlab.ui.control.Label
        regulatoryInfoLabel     matlab.ui.control.Label
        regulatoryInfo          matlab.ui.control.DropDown
        Description             matlab.ui.control.TextArea
        DescriptionLabel        matlab.ui.control.Label
        ContextMenu             matlab.ui.container.ContextMenu
        deleteButton            matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        CallingApp
        peakValues
        tableStyle = uistyle("BackgroundColor", '#bfe5ff');
        row
        
    end
    
    methods (Access = private)
        
        function Fcn1_TreeBuilding(app)
            
            delete(app.Tree.Children);
            Fcn1_ExceptionGlobalList(app)
            
            if ~isempty(app.CallingApp.exceptionList)
                app.CallingApp.exceptionList = sortrows(app.CallingApp.exceptionList, {'Tag', 'Frequency'});
                Category = unique(extractBefore(app.CallingApp.exceptionList.Tag, newline));
                for ii = 1:numel(Category)
                    ind  = find(strcmp(extractBefore(app.CallingApp.exceptionList.Tag, newline), Category(ii)));
                    Node = uitreenode(app.Tree, 'Text', Category{ii}, 'NodeData', ind, 'ContextMenu', app.ContextMenu);
                    
                    for jj = 1:numel(ind)
                        str = sprintf('%.3f MHz - [%s][%s][%s] "%s"',                       ...
                                      app.CallingApp.exceptionList.Frequency(ind(jj)),      ...
                                      app.CallingApp.exceptionList.regulatoryInfo{ind(jj)}, ...
                                      app.CallingApp.exceptionList.Type{ind(jj)},           ...
                                      app.CallingApp.exceptionList.Modulation{ind(jj)},     ...
                                      app.CallingApp.exceptionList.Description{ind(jj)});
                        
                        uitreenode(Node, 'Text', str, 'NodeData', ind(jj), 'ContextMenu', app.ContextMenu);
                    end
                    expand(Node);
                end
            end
            
        end
        
        
        function Fcn1_ExceptionGlobalList(app)
            
            global ExceptionGlobalList
            
            if isempty(ExceptionGlobalList)
                Misc_ExceptionGlobalList(app.CallingApp.RootFolder)
            end
            
            if ~isempty(ExceptionGlobalList)
                Global = uitreenode(app.Tree, 'Text', 'Global');
                for ii = 1:height(ExceptionGlobalList)
                    uitreenode(Global, 'Text', sprintf('%.3f MHz - "%s"', ExceptionGlobalList.Frequency(ii), ExceptionGlobalList.Description{ii}));
                end
            end

        end
        
        
        function Fcn2_TableStyle(app)
            
            removeStyle(app.UITable)
            addStyle(app.UITable, uistyle('HorizontalAlignment', 'left'), 'column', 5)
                        
            if ~isempty(app.CallingApp.exceptionList)
                ind1 = [];
                for ii = 1:height(app.CallingApp.exceptionList)
                    ind2 = intersect(find(strcmp(app.CallingApp.peaksTable.Tag,  app.CallingApp.exceptionList.Tag(ii))), ...
                                     find(app.CallingApp.peaksTable.Truncated == app.CallingApp.exceptionList.Frequency(ii)));
                    if ~isempty(ind2)
                        ind1 = [ind1; ind2];
                    end
                end
                
                if ~isempty(ind1)
                    addStyle(app.UITable, app.tableStyle, 'row', ind1)
                end
            end
            
        end
        
        
        function Fcn3_SelectedRowValues(app)
            
            app.Tag.Value         = app.CallingApp.peaksTable.Tag{app.row};
            app.CF.Value          = double(app.CallingApp.peaksTable.Truncated(app.row));
            app.Description.Value = app.UITable.Data.Description{app.row};
            
            if app.CallingApp.peaksTable.Station(app.row) == -1; app.Station.Value = -1;
            else;                                                app.Station.Value = double(app.CallingApp.peaksTable.Station(app.row));
            end
            
            ind = [];
            if ~isempty(app.CallingApp.exceptionList)
                ind = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                                find(app.CallingApp.exceptionList.Frequency == app.CallingApp.peaksTable.Truncated(app.row)));
            end
            
            if isempty(ind)
                str = app.CallingApp.peaksTable.regulatoryInfo{app.row};
                
                app.regulatoryInfo.Value = str;
                app.Type.Value           = 'Normal';
                app.Modulation.Value     = 'Analógico';
                app.Info.Value           = '';
                
                regulatoryInfoValueChanged(app)
                
            else
                app.Tree.SelectedNodes = [];
                
                app.regulatoryInfo.Value = app.CallingApp.exceptionList.regulatoryInfo(ind);
                app.Type.Value           = app.CallingApp.exceptionList.Type(ind);
                app.Modulation.Value     = app.CallingApp.exceptionList.Modulation(ind);
                
                regulatoryInfoValueChanged(app)
                app.Info.Value           = app.CallingApp.exceptionList.Description(ind);
                
                if strcmp(app.regulatoryInfo.Value, 'Licenciada') && ~isempty(app.Info.Value)
                    app.addButton.Enable = 0;
                else
                    app.addButton.Enable = 1;
                end
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp   = mainapp;
            app.UITable.Data = app.CallingApp.peaksTable(:,[1, 1, 4, 10, 13, 14:18, 22:23]);
            
            app.UITable.Data.Tag   = extractBefore(app.UITable.Data.Tag, newline);
            app.UITable.Data.Tag_1 = extractBetween(app.UITable.Data.Tag_1, 'ThreadID', ':');

            Fcn1_TreeBuilding(app)
            Fcn2_TableStyle(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin1 = [];
            delete(app)
            
        end

        % Cell selection callback: UITable
        function UITable_CellSelection(app, event)
            
            if numel(unique(event.Indices(:,1))) == 1
                set(app.Grid4.Children, 'Enable', 1)
                
                app.row = event.Indices(1);
                Fcn3_SelectedRowValues(app)
                
            else
                app.row = [];
                
                set(app.Grid4.Children, 'Enable', 0)
                app.addButton.Enable = 0;
            end
            
        end

        % Button pushed function: addButton
        function addButton_Clicked(app, event)
            
            if ~isempty(deblank(strjoin(app.Info.Value)))
                if ~strcmp(app.regulatoryInfo.Value, 'Licenciada')
                    exceptionValue = {sprintf('%s\n%s', app.Tag.Value{1}, app.Tag.Value{2}), ...
                                      app.CallingApp.peaksTable.Latitude(app.row),           ...
                                      app.CallingApp.peaksTable.Longitude(app.row),          ...
                                      round(single(app.CF.Value), 3),                        ...
                                      app.Type.Value,           ...
                                      app.Modulation.Value,     ...
                                      app.regulatoryInfo.Value, ...
                                      int16(-1), int32(-1),     ...
                                      strjoin(app.Info.Value), '-'};
                else
                    stationInfo = jsondecode(app.Info.Value{1});
                    
                    exceptionValue = {sprintf('%s\n%s', app.Tag.Value{1}, app.Tag.Value{2}), ...
                                      app.CallingApp.peaksTable.Latitude(app.row),           ...
                                      app.CallingApp.peaksTable.Longitude(app.row),          ...
                                      round(single(app.CF.Value), 3),                        ...
                                      app.Type.Value,           ...
                                      app.Modulation.Value,     ...
                                      app.regulatoryInfo.Value, ...
                                      int16(str2double(stationInfo.Service)), ...
                                      int32(str2double(stationInfo.Station)), ...
                                      stationInfo.Description, stationInfo.Distance};
                end
                
                ind = [];
                if ~isempty(app.CallingApp.exceptionList)
                    ind = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  exceptionValue{1})), ...
                                    find(app.CallingApp.exceptionList.Frequency == exceptionValue{4}));
                    
                end
                
                if isempty(ind); app.CallingApp.exceptionList(end+1,:) = exceptionValue;
                else;            app.CallingApp.exceptionList(ind,:)   = exceptionValue;
                end

                Fcn1_TreeBuilding(app)
                Fcn2_TableStyle(app)
                Fcn3_SelectedRowValues(app)
                
                if strcmp(app.regulatoryInfo.Value, 'Licenciada')
                    app.Station.Value = double(app.CallingApp.peaksTable.Station(app.row));
                end
                
                app.CallingApp.auxWin_TreeEdit
            else
                uialert(app.UIFigure, 'Campo "Informações adicionais" não pode ficar vazio.', 'appAnálise', 'Icon', 'warning')
            end
            
        end

        % Menu selected function: deleteButton
        function deleteButtonImageClicked(app, event)
            
            selectedNodes = app.Tree.SelectedNodes;
            
            if ~isempty(selectedNodes)
                ind = [];
                for ii = 1:numel(selectedNodes)
                    ind = [ind; selectedNodes(ii).NodeData];
                end
                ind = unique(ind);
                
                app.CallingApp.exceptionList(ind,:) = [];
                                
                Fcn1_TreeBuilding(app)
                Fcn2_TableStyle(app)
                
                app.CallingApp.auxWin_TreeEdit
            end
            
        end

        % Value changed function: Station
        function StationValueChanged(app, event)
            
            latNode  = app.CallingApp.peaksTable.Latitude(app.row);
            longNode = app.CallingApp.peaksTable.Longitude(app.row);
            
            try
                StationInfo = Misc_DataBaseQuery(app.Station.Value, latNode, longNode, app.CallingApp.RootFolder);
                
                msg = sprintf(['Indicação automática:\n• Frequência: %.3f MHz\n• Descrição: %s\n• Distância: %s\n\n'                      ...
                               'Indicação manual:    \n• Frequência(s): %s MHz\n• Descrição: %s\n• Distância: %.1f\n\n Confirma edição?'], ...
                               app.CallingApp.peaksTable.Truncated(app.row), app.CallingApp.peaksTable.Description{app.row},              ...
                               app.CallingApp.peaksTable.Distance{app.row}, StationInfo.Frequency, StationInfo.Description, StationInfo.Distance);
    
                selection = uiconfirm(app.UIFigure, msg, 'appAnálise', 'Options', {'OK', 'Cancelar'}, ...
                                                         'DefaultOption', 1, 'CancelOption', 2);
                
                if strcmp(selection, 'OK')
                    set(app.Info, 'Value', jsonencode(struct('Service', num2str(StationInfo.Service), ...
                                                             'Station', num2str(StationInfo.Station), ...
                                                             'Description', StationInfo.Description,  ...
                                                             'Distance', sprintf('%.1f', StationInfo.Distance))), ...
                                  'Editable', 0, 'BackgroundColor', [.80,.80,.80])
                    
                    app.addButton.Enable = 1;
                else
                    app.Station.Value = double(app.CallingApp.peaksTable.Station(app.row));
                end
            catch ME
                app.Station.Value = double(app.CallingApp.peaksTable.Station(app.row));
                
                uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
            end
            
        end

        % Value changed function: regulatoryInfo
        function regulatoryInfoValueChanged(app, event)
            
            if strcmp(app.regulatoryInfo.Value, 'Licenciada')
                set(app.Station, 'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Info,    'Editable', 0, 'BackgroundColor', [.80,.80,.80], 'Value', '')
            else
                set(app.Station, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Info,    'Editable', 1, 'BackgroundColor', [1,1,1])
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Lista de exceções';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Grid3 = uigridlayout(app.Grid1);
            app.Grid4 = uigridlayout(app.Grid3);
            app.DescriptionLabel = uilabel(app.Grid4);
            app.DescriptionLabel.Enable = 'off';
            app.DescriptionLabel.Text = 'Descrição:';
            app.Description = uitextarea(app.Grid4);
            app.Description.Editable = 'off';
            app.Description.Enable = 'off';
            app.regulatoryInfo = uidropdown(app.Grid4);
            app.regulatoryInfo.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            app.regulatoryInfo.ValueChangedFcn = createCallbackFcn(app, @regulatoryInfoValueChanged, true);
            app.regulatoryInfo.Enable = 'off';
            app.regulatoryInfo.Value = 'Licenciada';
            app.regulatoryInfoLabel = uilabel(app.Grid4);
            app.regulatoryInfoLabel.Enable = 'off';
            app.regulatoryInfoLabel.Text = 'Emissão:';
            app.FrequnciacanalMHzLabel = uilabel(app.Grid4);
            app.FrequnciacanalMHzLabel.Enable = 'off';
            app.FrequnciacanalMHzLabel.Text = 'Frequência canal (MHz):';
            app.CF = uieditfield(app.Grid4, 'numeric');
            app.CF.Limits = [0 Inf];
            app.CF.ValueDisplayFormat = '%.3f';
            app.CF.Editable = 'off';
            app.CF.Enable = 'off';
            app.Station = uieditfield(app.Grid4, 'numeric');
            app.Station.Limits = [-1 Inf];
            app.Station.RoundFractionalValues = 'on';
            app.Station.ValueDisplayFormat = '%.0f';
            app.Station.ValueChangedFcn = createCallbackFcn(app, @StationValueChanged, true);
            app.Station.Enable = 'off';
            app.Station.Value = -1;
            app.StationLabel = uilabel(app.Grid4);
            app.StationLabel.Enable = 'off';
            app.StationLabel.Text = 'Estação:';
            app.Tag = uitextarea(app.Grid4);
            app.Tag.Editable = 'off';
            app.Tag.Enable = 'off';
            app.TagLabel = uilabel(app.Grid4);
            app.TagLabel.Enable = 'off';
            app.TagLabel.Text = 'Identificador:';
            app.DescriptionSubLabel = uilabel(app.Grid4);
            app.DescriptionSubLabel.Enable = 'off';
            app.DescriptionSubLabel.Text = 'Serviço-Status, Classe, Entidade (Fistel, Estação), Município/UF';
            app.TypeLabel = uilabel(app.Grid4);
            app.TypeLabel.Enable = 'off';
            app.TypeLabel.Text = 'Tipo de emissão:';
            app.Type = uidropdown(app.Grid4);
            app.Type.Items = {'Normal', 'Espúrio', 'Não identificado', 'Não se manifestou'};
            app.Type.Enable = 'off';
            app.Type.Value = 'Normal';
            app.ModulationLabel = uilabel(app.Grid4);
            app.ModulationLabel.Enable = 'off';
            app.ModulationLabel.Text = 'Esquema de modulação:';
            app.Modulation = uidropdown(app.Grid4);
            app.Modulation.Items = {'Analógico', 'Digital', 'Não identificado'};
            app.Modulation.Enable = 'off';
            app.Modulation.Value = 'Analógico';
            app.InfoLabel = uilabel(app.Grid4);
            app.InfoLabel.Enable = 'off';
            app.InfoLabel.Text = 'Informações adicionais:';
            app.Info = uitextarea(app.Grid4);
            app.Info.Editable = 'off';
            app.addButton = uibutton(app.Grid4, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButton_Clicked, true);
            app.addButton.Icon = 'White_Edit.png';
            app.addButton.Enable = 'off';
            app.addButton.Text = 'Incluir/Editar';
            app.Label3_2 = uilabel(app.Grid4);
            app.Label3_2.Text = 'Emissão selecionada';
            app.Grid5 = uigridlayout(app.Grid1);
            app.Tree = uitree(app.Grid5);
            app.Tree.Multiselect = 'on';
            app.Label3 = uilabel(app.Grid5);
            app.Label3.Text = 'Lista de exceções';
            app.Grid2 = uigridlayout(app.Grid1);
            app.UITable = uitable(app.Grid2);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.Column9 = uilabel(app.Grid2);
            app.Column9.Text = {'  Distância'; '  (km)'};
            app.Column7 = uilabel(app.Grid2);
            app.Column7.Text = {'  Ocupação'; '  máxima'};
            app.Column6 = uilabel(app.Grid2);
            app.Column6.Text = {'  Ocupação'; '  média'};
            app.Column5 = uilabel(app.Grid2);
            app.Column5.Text = {'  Nível'; '  máximo'};
            app.Column3 = uilabel(app.Grid2);
            app.Column3.Text = {'  Frequência'; '  canal (MHz)'};
            app.Column4 = uilabel(app.Grid2);
            app.Column4.Text = {'  Nível'; '  mínimo'};
            app.Column2 = uilabel(app.Grid2);
            app.Column2.Text = {'  Frequência'; '  (MHz)'};
            app.Column1 = uilabel(app.Grid2);
            app.Column1.Text = {'  ID'; ''; ''};
            app.Column0 = uilabel(app.Grid2);
            app.Column0.Text = {'  ThreadID'; '  '};
            app.Column_1 = uilabel(app.Grid2);
            app.Column_1.Text = {'  Sensor'; '  '};
            app.Column4_2 = uilabel(app.Grid2);
            app.Column4_2.Text = {'  Nível'; '  médio'};
            app.Column8 = uilabel(app.Grid2);
            app.Column8.Text = {'  Identificação do provável emissor'; '  (Serviço/Status, Entidade, Município/UF)'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.deleteButton = uimenu(app.ContextMenu);
            app.deleteButton.MenuSelectedFcn = createCallbackFcn(app, @deleteButtonImageClicked, true);
            app.deleteButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTreeEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end